classdef CartiMorphToolbox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CartiMorphToolboxUIFigure       matlab.ui.Figure
        Panel_CMT                       matlab.ui.container.Panel
        CMT_version                     matlab.ui.control.Label
        TipsButton                      matlab.ui.control.StateButton
        AboutButton                     matlab.ui.control.StateButton
        Panel_ImageDataInformation      matlab.ui.container.Panel
        Panel_KneeSideInformation       matlab.ui.container.Panel
        Button_loadCSV                  matlab.ui.control.Button
        EditField_csvPath               matlab.ui.control.EditField
        EditFieldLabel_csvPath          matlab.ui.control.Label
        Panel_SegmentationLabels        matlab.ui.container.Panel
        EditField_background            matlab.ui.control.EditField
        EditField_TibialCartilagelateral  matlab.ui.control.EditField
        EditFieldLabel_background       matlab.ui.control.Label
        EditFieldLabel_TibialCartilagelateral  matlab.ui.control.Label
        EditFieldLabel_Tibia            matlab.ui.control.Label
        EditFieldLabel_FemoralCartilage  matlab.ui.control.Label
        EditField_TibialCartilagemedial  matlab.ui.control.EditField
        EditFieldLabel_TibialCartilagemedial  matlab.ui.control.Label
        EditField_Tibia                 matlab.ui.control.EditField
        EditField_FemoralCartilage      matlab.ui.control.EditField
        EditField_Femur                 matlab.ui.control.EditField
        EditFieldLabel_Femur            matlab.ui.control.Label
        Label2                          matlab.ui.control.Label
        Name2                           matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Name                            matlab.ui.control.Label
        Panel_RawImageFolders           matlab.ui.container.Panel
        Button_selectPath_imgInference  matlab.ui.control.Button
        EditFieldLabel_imgInference     matlab.ui.control.Label
        EditFieldLabel_labelTs          matlab.ui.control.Label
        EditFieldLabel_labelTr          matlab.ui.control.Label
        EditFieldLabel_imgTs            matlab.ui.control.Label
        EditField_imgInference          matlab.ui.control.EditField
        Button_selectPath_labelTs       matlab.ui.control.Button
        EditField_labelTs               matlab.ui.control.EditField
        Button_selectPath_labelTr       matlab.ui.control.Button
        EditField_labelTr               matlab.ui.control.EditField
        Button_selectPath_imgTs         matlab.ui.control.Button
        EditField_imgTs                 matlab.ui.control.EditField
        Button_selectPath_imgTr         matlab.ui.control.Button
        EditField_imgTr                 matlab.ui.control.EditField
        EditFieldLabel_imgTr            matlab.ui.control.Label
        Panel_ImageProcessingPipeline   matlab.ui.container.Panel
        Panel_Note                      matlab.ui.container.Panel
        setdifferentIDstotrainmodelsinparallelLabel  matlab.ui.control.Label
        SsuperiorLabel                  matlab.ui.control.Label
        IinferiorLabel                  matlab.ui.control.Label
        AanteriorLabel                  matlab.ui.control.Label
        PposteriorLabel                 matlab.ui.control.Label
        RrightLabel                     matlab.ui.control.Label
        LleftLabel                      matlab.ui.control.Label
        ImageorientationLabel           matlab.ui.control.Label
        checkatleastonceforeachprojectLabel  matlab.ui.control.Label
        checkatleastonceforeachmachineLabel  matlab.ui.control.Label
        PipelineLabel                   matlab.ui.control.Label
        Panel_vii                       matlab.ui.container.Panel
        Gauge_vii                       matlab.ui.control.Gauge
        Label_vii_progress              matlab.ui.control.Label
        Lamp_vii_regionalAnalysis       matlab.ui.control.Lamp
        Label_vii_regionalAnalysis      matlab.ui.control.Label
        CheckBox_vii_regionalAnalysis   matlab.ui.control.CheckBox
        Lamp_vii_parcellation           matlab.ui.control.Lamp
        Label_vii_parcellation          matlab.ui.control.Label
        CheckBox_vii_parcellation       matlab.ui.control.CheckBox
        Lamp_vii_CTM                    matlab.ui.control.Lamp
        Label_vii_CTM                   matlab.ui.control.Label
        CheckBox_vii_CTM                matlab.ui.control.CheckBox
        Lamp_vii                        matlab.ui.control.Lamp
        Label_vii                       matlab.ui.control.Label
        CheckBox_vii                    matlab.ui.control.CheckBox
        Panel_vi                        matlab.ui.container.Panel
        EditField_vi_CroppedImageSizeZ  matlab.ui.control.EditField
        EditField_vi_CroppedImageSizeY  matlab.ui.control.EditField
        EditField_vi_CroppedImageSizeX  matlab.ui.control.EditField
        EditFieldLabel_vi_CroppedImageSize  matlab.ui.control.Label
        Lamp_vi                         matlab.ui.control.Lamp
        EditField_vi_GPU                matlab.ui.control.EditField
        EditFieldLabel_vi_GPU           matlab.ui.control.Label
        CheckBox_vi                     matlab.ui.control.CheckBox
        Image_vi_Info                   matlab.ui.control.Image
        Label_vi                        matlab.ui.control.Label
        Panel_v                         matlab.ui.container.Panel
        Lamp_v                          matlab.ui.control.Lamp
        EditField_v_GPU                 matlab.ui.control.EditField
        EditFieldLabel_v_GPU            matlab.ui.control.Label
        Label_v                         matlab.ui.control.Label
        CheckBox_v                      matlab.ui.control.CheckBox
        Image_v_Info                    matlab.ui.control.Image
        Panel_iv2                       matlab.ui.container.Panel
        Label_LossFunction_iv2          matlab.ui.control.Label
        ButtonGroup_Loss_iv2            matlab.ui.container.ButtonGroup
        Button_MSE_LNCC_iv2             matlab.ui.control.ToggleButton
        Button_LNCC_iv2                 matlab.ui.control.ToggleButton
        Button_MSE_iv2                  matlab.ui.control.ToggleButton
        CheckBox_iv2_Freeze             matlab.ui.control.CheckBox
        Image_iv2_Info                  matlab.ui.control.Image
        Button_iv2_reset                matlab.ui.control.Button
        Lamp_iv2_loadModel              matlab.ui.control.Lamp
        Button_iv2_loadmodel            matlab.ui.control.Button
        Label_iv2_Model                 matlab.ui.control.Label
        EditField_iv2_epoch             matlab.ui.control.EditField
        EditFieldLabel_iv2_epoch        matlab.ui.control.Label
        Lamp_iv2_finetuning             matlab.ui.control.Lamp
        EditField_iv2_GPU               matlab.ui.control.EditField
        Label_iv2_finetuning            matlab.ui.control.Label
        CheckBox_iv2_finetuning         matlab.ui.control.CheckBox
        EditField_iv2_CroppedImageSizeZ  matlab.ui.control.EditField
        EditField_iv2_CroppedImageSizeY  matlab.ui.control.EditField
        EditField_iv2_CroppedImageSizeX  matlab.ui.control.EditField
        EditFieldLabel_iv2_CroppedImageSize  matlab.ui.control.Label
        Lamp_iv2_prepro                 matlab.ui.control.Lamp
        Label_iv2_prepro                matlab.ui.control.Label
        CheckBox_iv2_prepro             matlab.ui.control.CheckBox
        Lamp_iv2_finetune               matlab.ui.control.Lamp
        Label_iv2_finetune              matlab.ui.control.Label
        CheckBox_iv2_finetune           matlab.ui.control.CheckBox
        Lamp_iv2_deploy                 matlab.ui.control.Lamp
        Label_iv2_deploy                matlab.ui.control.Label
        CheckBox_iv2_deploy             matlab.ui.control.CheckBox
        Lamp_iv2                        matlab.ui.control.Lamp
        Label_iv2                       matlab.ui.control.Label
        CheckBox_iv2                    matlab.ui.control.CheckBox
        Panel_iv                        matlab.ui.container.Panel
        Label_LossFunction_iv           matlab.ui.control.Label
        ButtonGroup_Loss_iv             matlab.ui.container.ButtonGroup
        Button_MSE_LNCC_iv              matlab.ui.control.ToggleButton
        Button_LNCC_iv                  matlab.ui.control.ToggleButton
        Button_MSE_iv                   matlab.ui.control.ToggleButton
        Image_iv_networkInfo            matlab.ui.control.Image
        Label_NetworkWidth              matlab.ui.control.Label
        ButtonGroup_networkWidth        matlab.ui.container.ButtonGroup
        Button_x9                       matlab.ui.control.ToggleButton
        Button_x8                       matlab.ui.control.ToggleButton
        Button_x7                       matlab.ui.control.ToggleButton
        Button_x6                       matlab.ui.control.ToggleButton
        Button_x5                       matlab.ui.control.ToggleButton
        Button_x3                       matlab.ui.control.ToggleButton
        Button_x4                       matlab.ui.control.ToggleButton
        Button_x2                       matlab.ui.control.ToggleButton
        Button_x1                       matlab.ui.control.ToggleButton
        EditField_iv_epoch              matlab.ui.control.EditField
        EditFieldLabel_iv_epoch         matlab.ui.control.Label
        Image_iv_Info                   matlab.ui.control.Image
        Image_iv_Info_croppedImageSize  matlab.ui.control.Image
        EditFieldLabel_iv_CroppedImageSize  matlab.ui.control.Label
        EditField_iv_GPU                matlab.ui.control.EditField
        Lamp_iv_train                   matlab.ui.control.Lamp
        Label_iv_train                  matlab.ui.control.Label
        CheckBox_iv_train               matlab.ui.control.CheckBox
        EditField_iv_CroppedImageSizeZ  matlab.ui.control.EditField
        EditField_iv_CroppedImageSizeY  matlab.ui.control.EditField
        EditField_iv_CroppedImageSizeX  matlab.ui.control.EditField
        Lamp_iv_prepro                  matlab.ui.control.Lamp
        Label_iv_prepro                 matlab.ui.control.Label
        CheckBox_iv_prepro              matlab.ui.control.CheckBox
        Lamp_iv                         matlab.ui.control.Lamp
        Label_iv                        matlab.ui.control.Label
        CheckBox_iv                     matlab.ui.control.CheckBox
        Panel_iii2                      matlab.ui.container.Panel
        Image_iii2_Info                 matlab.ui.control.Image
        EditField_iii2_epoch            matlab.ui.control.EditField
        EditFieldLabel_iii2_epoch       matlab.ui.control.Label
        Button_iii2_reset               matlab.ui.control.Button
        Lamp_iii2_loadModel             matlab.ui.control.Lamp
        Button_iii2_loadmodel           matlab.ui.control.Button
        Label_iii2_Model                matlab.ui.control.Label
        Lamp_iii2_finetuning            matlab.ui.control.Lamp
        EditField_iii2_GPU              matlab.ui.control.EditField
        Label_iii2_finetuning           matlab.ui.control.Label
        CheckBox_iii2_finetuning        matlab.ui.control.CheckBox
        Lamp_iii2_prepro                matlab.ui.control.Lamp
        Label_iii2_prepro               matlab.ui.control.Label
        CheckBox_iii2_prepro            matlab.ui.control.CheckBox
        Lamp_iii2_finetune              matlab.ui.control.Lamp
        Label_iii2_finetune             matlab.ui.control.Label
        CheckBox_iii2_finetune          matlab.ui.control.CheckBox
        Lamp_iii2_deploy                matlab.ui.control.Lamp
        Label_iii2_deploy               matlab.ui.control.Label
        CheckBox_iii2_deploy            matlab.ui.control.CheckBox
        Lamp_iii2                       matlab.ui.control.Lamp
        Label_iii2                      matlab.ui.control.Label
        CheckBox_iii2                   matlab.ui.control.CheckBox
        Panel_iii                       matlab.ui.container.Panel
        Image_iii_epochInfo             matlab.ui.control.Image
        EditField_iii_epoch             matlab.ui.control.EditField
        EditFieldLabel_iii_epoch        matlab.ui.control.Label
        ButtonGroup_iii                 matlab.ui.container.ButtonGroup
        Button_iii_continue             matlab.ui.control.ToggleButton
        Button_iii_scratch              matlab.ui.control.ToggleButton
        EditField_iii_GPU               matlab.ui.control.EditField
        Lamp_iii_train                  matlab.ui.control.Lamp
        Label_iii_train                 matlab.ui.control.Label
        CheckBox_iii_train              matlab.ui.control.CheckBox
        Lamp_iii_prepro                 matlab.ui.control.Lamp
        Label_iii_prepro                matlab.ui.control.Label
        CheckBox_iii_prepro             matlab.ui.control.CheckBox
        Lamp_iii                        matlab.ui.control.Lamp
        Label_iii                       matlab.ui.control.Label
        CheckBox_iii                    matlab.ui.control.CheckBox
        Image_iii_Info                  matlab.ui.control.Image
        Panel_ii                        matlab.ui.container.Panel
        EditField_ii_imgSizeZ           matlab.ui.control.EditField
        EditField_ii_imgSizeY           matlab.ui.control.EditField
        EditField_ii_imgSizeX           matlab.ui.control.EditField
        Image_ii_Info_orientation       matlab.ui.control.Image
        Label_ii_orientation            matlab.ui.control.Label
        EditFieldLabel_ii_imgSizeZ      matlab.ui.control.Label
        EditFieldLabel_ii_imgSizeY      matlab.ui.control.Label
        EditFieldLabel_ii_imgSizeX      matlab.ui.control.Label
        Label_ii_ImageSize              matlab.ui.control.Label
        Lamp_ii                         matlab.ui.control.Lamp
        iiRawImageStandardizationLabel  matlab.ui.control.Label
        CheckBox_ii                     matlab.ui.control.CheckBox
        Panel_i                         matlab.ui.container.Panel
        Lamp_i                          matlab.ui.control.Lamp
        iComputingEnvironmentInitializationLabel  matlab.ui.control.Label
        CheckBox_i                      matlab.ui.control.CheckBox
        Image_i_Info                    matlab.ui.control.Image
        Panel_ComputingEnvironmentConfiguration  matlab.ui.container.Panel
        Button_selectpathConda          matlab.ui.control.Button
        EditFieldLabel_Conda            matlab.ui.control.Label
        EditField_pathConda             matlab.ui.control.EditField
        Button_selectpathCMT            matlab.ui.control.Button
        EditField_pathCMT               matlab.ui.control.EditField
        EditFieldLabel_pathCMT          matlab.ui.control.Label
        EditField_ProjectCodeName       matlab.ui.control.EditField
        EditFieldLabel_ProjectCodeName  matlab.ui.control.Label
        EditField_ProjectID             matlab.ui.control.EditField
        EditFieldLabel_ProjectID        matlab.ui.control.Label
        Panel_actions                   matlab.ui.container.Panel
        Button_evalRegModel             matlab.ui.control.StateButton
        Button_evalSegModel             matlab.ui.control.StateButton
        Image_Info_pause                matlab.ui.control.Image
        Button_exportRegModel           matlab.ui.control.StateButton
        Button_exportSegModel           matlab.ui.control.StateButton
        Button_Run                      matlab.ui.control.StateButton
        Button_Load                     matlab.ui.control.StateButton
        Button_Save                     matlab.ui.control.StateButton
        Button_Pause                    matlab.ui.control.Button
        Button_CMV                      matlab.ui.control.StateButton
        Image                           matlab.ui.control.Image
    end


    properties (Access = private)
        % folders
        dir_base char % base folder for the toolbox
        dir_conda_base char % Conda/Miniconda folder
        dir_rawData_imageTr char % training images folder
        dir_rawData_imageTs char % testing images folder
        dir_rawData_labelTr char % training labels folder
        dir_rawData_labelTs char % testing labels folder
        dir_rawData_imgInference char % inference images folder
        dir_source_py % folder for python source code
        dir_nnUNet_rawBase char
        dir_nnUNet_raw char
        dir_nnUNet_raw_imgTr char
        dir_nnUNet_raw_imgTs char
        dir_nnUNet_raw_labelTr char
        dir_nnUNet_raw_labelTs char
        dir_nnUNet_raw_imgInference char
        dir_nnUNet_preprocess char
        dir_nnUNet_model char
        dir_nnUNet_pretrained_model char
        dir_nnUNet_autoScript char
        dir_nnUNet_log char
        dir_nnUNet_predictionBase char
        dir_nnUNet_prediction_inferenceSet char
        dir_nnUNet_prediction_testSet char
        dir_vxm_data_base char
        dir_vxm_data_prepImgTr char
        dir_vxm_data_prepImgTs char
        dir_vxm_data_prepLabelTr char
        dir_vxm_data_prepLabelTs char
        dir_vxm_data_maskedImgInference char
        dir_vxm_data_Template char
        dir_vxm_model char
        dir_vxm_pretrained_model char
        dir_vxm_inference_warpedLabels_Tr char
        dir_vxm_inference_warpedTempSeg_InferenceSet char
        dir_vxm_inference_warpingField_InferenceSet char
        dir_vxm_inference_warpedTempSeg_Ts char
        dir_vxm_inference_warpingField_Ts char
        dir_vxm_autoScript char
        dir_vxm_log char
        dir_projectConfig char

        % paths
        path_bash_bin char % path of Bash binary file
        path_conda_src char % path of conda source file (conda.sh)
        path_CUDA_lib_nnUNet char % path of CUDA library for segmentation model training
        path_CUDA_lib_Vxm char % path of CUDA library for registration model training
        path_vxm_data_cropRange_nnUNet char % path of file with image cropping information (mask = nnUNet prediction)
        path_vxm_data_cropRange_Tr char % path of file with image cropping information for training images (mask = manual label)
        path_vxm_data_cropRange_Ts char % path of file with image cropping information for testing images (mask = manual label)

        % image processing pipeline options
        opt_CMT_init logical % initialize the toolbox
        opt_CondaEnvSetup logical % pipeline: computing environment configuration
        opt_imgStandardization logical % pipeline: raw image standardization
        opt_nnUNet_training logical % pipeline: segmentation network training
        opt_nnUNet_training_scratch logical % pipeline: train from scratch or continue training
        opt_nnUNet_training_begin logical % pipeline: start training
        opt_nnUNet_planning_preprocessing logical % pipeline: preprocessing for nnUNet training
        opt_nnUNet_inference logical % pipeline: segmentation network inference
        opt_vxm_training logical % pipeline: segmentation network training
        opt_vxm_training_scratch logical % pipeline: train from scratch or continue training
        opt_vxm_training_begin logical % pipeline: start training
        opt_vxm_preprocessing logical % pipeline: preprocessing for VoxelMorph training
        opt_vxm_inference logical % pipeline: registration network inference
        opt_vxm_freezeTemp logical =false % stop template learning
        opt_quantification logical % pipeline: cartilage morphological quantification
        opt_quant_CTM logical % pipeline: cartilage thickness mapping
        opt_quant_parcellation logical % pipeline: cartilage parcellation
        opt_quant_regionalAnalysis logical % pipeline: regional analysis

        % data configuration
        config_ROI_name cell =cell([1,6]) % cell of segmentation names
        config_ROI_label cell =cell([1,6]) % cell of segmentation labels

        % nnUNet configuration
        config_nnUNet_ID char % project ID
        config_nnUNet_task char % project code name
        config_nnUNet_taskIDName char % project ID & code name
        config_nnUNet_GPU char % GPU ID for segmentation network training
        config_nnUNet_models char % nnUNet type: "3dF"
        config_nnUNet_model_archi char % "3d_fullres"
        config_nnUNet_fold char % training fold: default to '0' (cross validation is not supported for now)
        config_nnUNet_imgSize double =NaN([1,3]) % image size of the standardized image
        config_nnUNet_epochs double % training epoch
        config_nnUNet_loadedPlans_identifier char % loaded plans identifier
        config_nnUNet_exportedPlans_identifier char % exported plans identifier

        % VoxelMorph configuration
        config_vxm_batchSize double % batch size
        config_vxm_GPU char % GPU ID for registration network training
        config_vxm_imgSize_out double =NaN([1,3]) % image size of cropped image
        config_vxm_epochs double % training epoch
        config_vxm_encoderArchi char % encoder architecture
        config_vxm_decoderArchi char % decoder architecture
        config_vxm_encoderArchi_base double
        config_vxm_decoderArchi_base double
        config_vxm_imgLoss char % image similarity loss: {'LNCC', 'MSE', 'MSE+LNCC'}

        % model inference configuration
        config_inference_GPU char % GPU ID for inference

        % parameters for morphological quantification
        config_CMT_ccPercentage double % ccLFC/ccMFC definition
        config_humanMaxThickness double
        config_numROI_FC double
        config_numROI_mTC double
        config_numROI_lTC double

        % others
        container_logFileID % log file ID
        container_jobs struct =struct('job_nnUNet_planning', []) % batch jobs container
        container_jobStatus struct =struct('job_nnUNet_train', []) % batch jobs status
        status_isPaused logical =false % pause/resume status
        counter_case double =0
        counter_cartilage double =0
        flag_SegModel_exported logical =false
        flag_RegModel_exported logical =false

        % apps
        aboutCMT % the matlab app showing CMT info
        CMViewer % CartiMorph-Viewer
    end


    properties (Access = public)
        % <<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<
        % DO NOT modify this section even the comments
        % (comments are used to recognized public variables)
        %
        % (related function: CMT_saveConfig_action)
        % <<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<

        % CMT Public Variable
        dir_result char
        % CMT Public Variable
        path_CMT_kneeSideInfo char
        % CMT Public Variable
        config_kneeSideInfo table

        % <<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<
    end


    methods (Static)
        function [dim_Right, dim_Anterior, dim_Superior] = CMT_nifti_getOrientation(affineT)
            % ==============================================================================
            % FUNCTION:
            %     Get NIfTI orientation. Find the anatomical orientation (R-L, A-P, S-L) of the image bases
            %
            % INPUT:
            %     - affineT: (4,4), affine transformation matrix
            %
            % OUTPUT:
            %     - dim_Right: dimension of image points to right
            %     - dim_Anterior: dimension of image points to anterior
            %     - dim_Superior: dimension of image points to superior
            %
            % This function only works for NIfTI image
            % Note, in NIfTI standard, real world bases correspond to {R, A, S} direction
            % Note, real world bases and image bases are orthorgonal bases
            % ------------------------------------------------------------------------------
            % Matlab Version: 2019b or later
            %
            % Last updated on: 06-Jul-2022
            %
            % Author:
            % Yongcheng YAO (yao_yongcheng@link.cuhk.edu.hk)
            % Department of Imaging and Interventional Radiology,
            % Chinese University of Hong Kong (CUHK)
            %
            % Copyright 2020 Yongcheng YAO
            % ------------------------------------------------------------------------------
            % ==============================================================================


            % ignore translation in affine transformation
            mat = affineT(1:3, 1:3);

            % "mat" is a linear transformation from "image space" to "real world space"
            % [note, in NIfTI header, bases are rows in the matrix]
            axis_i_real = mat(1, :);  % i axis in "image space" transformed to "real world space"
            axis_j_real = mat(2, :);  % j axis in "image space" transformed to "real world space"
            axis_k_real = mat(3, :);  % k axis in "image space" transformed to "real world space"

            % define the orientation for real world bases -- the NIfTI standard RAS+
            axis_R_real = [1,0,0];  % the [1,0,0] vector in "real space" point to Right
            axis_A_real = [0,1,0];  % the [0,1,0] vector in "real space" point to Anterior
            axis_S_real = [0,0,1];  % the [0,0,1] vector in "real space" point to Superior

            % angle between transformed image bases and real world bases
            angle_i_R = acosd(dot(axis_i_real, axis_R_real) / (norm(axis_R_real) * norm(axis_i_real)));
            angle_i_A = acosd(dot(axis_i_real, axis_A_real) / (norm(axis_A_real) * norm(axis_i_real)));
            angle_i_S = acosd(dot(axis_i_real, axis_S_real) / (norm(axis_S_real) * norm(axis_i_real)));

            angle_j_R = acosd(dot(axis_j_real, axis_R_real) / (norm(axis_R_real) * norm(axis_j_real)));
            angle_j_A = acosd(dot(axis_j_real, axis_A_real) / (norm(axis_A_real) * norm(axis_j_real)));
            angle_j_S = acosd(dot(axis_j_real, axis_S_real) / (norm(axis_S_real) * norm(axis_j_real)));

            angle_k_R = acosd(dot(axis_k_real, axis_R_real) / (norm(axis_R_real) * norm(axis_k_real)));
            angle_k_A = acosd(dot(axis_k_real, axis_A_real) / (norm(axis_A_real) * norm(axis_k_real)));
            angle_k_S = acosd(dot(axis_k_real, axis_S_real) / (norm(axis_S_real) * norm(axis_k_real)));

            % convert angles to range [0,90] degree considering flipping
            angles_i2RAS = [angle_i_R, angle_i_A, angle_i_S];
            flip_i = angles_i2RAS > 90;
            angles_i2RAS(flip_i) = 180 - angles_i2RAS(flip_i);

            angles_j2RAS = [angle_j_R, angle_j_A, angle_j_S];
            flip_j = angles_j2RAS > 90;
            angles_j2RAS(flip_j) = 180 - angles_j2RAS(flip_j);

            angles_k2RAS = [angle_k_R, angle_k_A, angle_k_S];
            flip_K = angles_k2RAS > 90;
            angles_k2RAS(flip_K) = 180 - angles_k2RAS(flip_K);

            % find the approximate direction of transformed image bases
            [~, direction_i] = min(angles_i2RAS);
            if flip_i(direction_i)
                direction_i = direction_i .* -1;
            end

            fake_angles_j2RAS = angles_j2RAS;
            fake_angles_j2RAS(abs(direction_i)) = 90;
            [~, direction_j] = min(fake_angles_j2RAS);
            if flip_j(direction_j)
                direction_j = direction_j .* -1;
            end

            fake_angles_k2RAS = angles_k2RAS;
            fake_angles_k2RAS(abs(direction_i)) = 90;
            fake_angles_k2RAS(abs(direction_j)) = 90;
            [~, direction_k] = min(fake_angles_k2RAS);
            if flip_K(direction_k)
                direction_k = direction_k .* -1;
            end

            % find the image dimension for Right, Anterior, and Superior directions
            dim_Right = find([direction_i, direction_j, direction_k]==1);
            if isempty(dim_Right)
                dim_Right = -1 * find([direction_i, direction_j, direction_k]==-1);
            end

            dim_Anterior = find([direction_i, direction_j, direction_k]==2);
            if isempty(dim_Anterior)
                dim_Anterior = -1 * find([direction_i, direction_j, direction_k]==-2);
            end

            dim_Superior = find([direction_i, direction_j, direction_k]==3);
            if isempty(dim_Superior)
                dim_Superior = -1 * find([direction_i, direction_j, direction_k]==-3);
            end

        end


        function img_out = CMT_imgPreprocess_resample(img_in, imgSize_out, method, datatype)

            n_dims = length(imgSize_out);
            imgSize_in = size(img_in);

            if n_dims==2
                % define grid
                xg = 0.5:imgSize_in(1)-0.5;
                yg = 0.5:imgSize_in(2)-0.5;
                % define gridded interpolator
                F = griddedInterpolant({xg, yg}, double(img_in));
                F.Method = method;
                % define query points
                xq = transpose(0.5:(imgSize_in(1)-1)/(imgSize_out(1)-1):imgSize_in(1)-0.5);
                yq = transpose(0.5:(imgSize_in(2)-1)/(imgSize_out(2)-1):imgSize_in(2)-0.5);
                img_out = (F({xq(1:end), yq(1:end)}));
                img_out = cast(img_out, datatype);
            elseif n_dims==3
                % define grid
                xg = 0.5:1:imgSize_in(1)-0.5;
                yg = 0.5:1:imgSize_in(2)-0.5;
                zg = 0.5:1:imgSize_in(3)-0.5;
                % define gridded interpolator
                F = griddedInterpolant({xg, yg, zg}, double(img_in));
                F.Method = method;
                % define query points
                xq = transpose(0.5:(imgSize_in(1)-1)/(imgSize_out(1)-1):imgSize_in(1)-0.5);
                yq = transpose(0.5:(imgSize_in(2)-1)/(imgSize_out(2)-1):imgSize_in(2)-0.5);
                zq = transpose(0.5:(imgSize_in(3)-1)/(imgSize_out(3)-1):imgSize_in(3)-0.5);
                img_out = F({xq(1:end), yq(1:end), zq(1:end)});
                img_out = cast(img_out, datatype);
            else
                error('wrong dimension of voxel size')
            end

        end


        function CMT_runBatchJob(path)
            cmd = strcat("bash ", path);
            system(cmd);
        end


        function img_out = cal_preprocessImg(img_in, n_vox_tbr)
            % ==============================================================================
            % FUNCTION:
            %       Image preprocessing: isolated voxels removal & holes filling
            %       (img_in must be a binary image)
            % ------------------------------------------------------------------------------
            % Matlab Version: 2019b or later
            %
            % Last updated on: 09-May-2022
            %
            % Author:
            % Yongcheng YAO (yao_yongcheng@link.cuhk.edu.hk)
            % Department of Imaging and Interventional Radiology,
            % Chinese University of Hong Kong (CUHK)
            %
            % Copyright 2020 Yongcheng YAO
            % ------------------------------------------------------------------------------
            % ==============================================================================

            % remove isolated voxels, defined as connected cluster of voxels with size less
            % than a threshold "n_vox_tbr"
            tmp = bwareaopen(img_in, n_vox_tbr, 26);  % conn=26

            img_out = uint8(imfill(tmp, 6, 'holes'));  % conn=6

        end


        function [subs_inter, subs_outer] = cal_initBoundarySplit_wBone(...
                mask_cartilage,...
                mask_bone)
            % ==============================================================================
            % FUNCTION:
            %     Coarse separation of boundary.
            %
            % INPUT:
            %     - mask_cartilage: [uint8] 3D cartilage mask
            %     - mask_bone:  [uint8] 3D bone mask
            %
            % OUTPUT:
            %     - subs_inter: [double] (nv, 3), the subscripts of voxels on the interface
            %     - subs_outer: [double] (nv, 3), the subscripts of voxels on the outer surface
            % ------------------------------------------------------------------------------
            % Matlab Version: 2019b or later
            %
            % Last updated on: 17-Jun-2022
            %
            % Author:
            % Yongcheng YAO (yao_yongcheng@link.cuhk.edu.hk)
            % Department of Imaging and Interventional Radiology,
            % Chinese University of Hong Kong (CUHK)
            %
            % Copyright 2020 Yongcheng YAO
            % ------------------------------------------------------------------------------
            % ==============================================================================

            % split boundary using sagittal slices (assuming RAS+ orientation)
            [subs_inter_sag, subs_outer_sag] = CartiMorphToolbox.cal_initBoundarySplit2D_wBone(mask_cartilage, mask_bone, 1);

            % split boundary using coronal slices (assuming RAS+ orientation)
            [subs_inter_cor, subs_outer_cor] = CartiMorphToolbox.cal_initBoundarySplit2D_wBone(mask_cartilage, mask_bone, 2);

            % combine results from sagittal and coronal slices
            subs_inter = unique([subs_inter_sag; subs_inter_cor], 'rows');
            subs_outer = unique([subs_outer_sag; subs_outer_cor], 'rows');
            subs_outer(ismember(subs_outer, subs_inter, 'rows'), :) = [];
        end


        function [subscripts_inter, subscripts_outer] = cal_initBoundarySplit2D_wBone(...
                mask_cartilage,...
                mask_bone,...
                idx_slicingDim)

            % get the image size of segmentation mask
            size_img = size(mask_cartilage);

            % grow the bone ROI so that there is no gap between bone and cartilage ROIs
            mask_bone_dilated = imdilate(mask_bone, strel('sphere', 1));
            mask_bone_dilated = mask_bone_dilated & ~mask_cartilage; % logical
            mask_bone_dilated = bwareaopen(mask_bone_dilated, round(sum(mask_bone_dilated(:))/10), 26); % logical
            mask_cartilage_bone = mask_cartilage | mask_bone_dilated; % logical
            mask_cartilage_bone = imfill(mask_cartilage_bone, 'holes'); % logical
            mask_bone_grown = uint8(mask_cartilage_bone) - uint8(mask_cartilage); % uint8
            mask_nonBone = 1 - mask_bone_grown;  % uint8

            % get the boundary of cartilage ROI
            boundary_cartilage = CartiMorphToolbox.cal_getBoundary2D(mask_cartilage, idx_slicingDim); % uint8

            % get the bone-cartilage interface
            boundary_nonBone =  CartiMorphToolbox.cal_getBoundary2D(mask_nonBone, idx_slicingDim); % uint8
            boundary_inter = uint8(boundary_cartilage & boundary_nonBone); % uint8

            % get the subscripts of voxels on the bone-cartilage interface
            idx_inter = find(boundary_inter);
            [sub1_inter, sub2_inter, sub3_inter] = ind2sub(size_img, idx_inter);
            subscripts_inter = cat(2, sub1_inter, sub2_inter, sub3_inter);

            % get the outer boundary
            boundary_outer = boundary_cartilage - boundary_inter;  % uint8

            % get the subscripts of voxels on the outer boundary
            idx_outer = find(boundary_outer);
            [sub1_outer, sub2_outer, sub3_outer] = ind2sub(size_img, idx_outer);
            subscripts_outer = cat(2, sub1_outer, sub2_outer, sub3_outer);
        end


        function  boundary = cal_getBoundary2D(img3d, idx_slicingDim)
            % ==============================================================================
            % FUNCTION:
            %     Returns the boundary of a 3D ROI.
            %
            % INPUT:
            %     - img3d: [uint8] 3D ROI - a binary 3D image
            %     - idx_slicingDim: [uint8] the dimension to be sliced
            %
            % OUTPUT:
            %     - boundary: [uint8] the boundary of 3D ROI - a binary 3D image
            %
            % ------------------------------------------------------------------------------
            % Matlab Version: 2019b or later
            %
            % Last updated on: 09-May-2022
            %
            % Author:
            % Yongcheng YAO (yao_yongcheng@link.cuhk.edu.hk)
            % Department of Imaging and Interventional Radiology,
            % Chinese University of Hong Kong (CUHK)
            %
            % Copyright 2020 Yongcheng YAO
            % ------------------------------------------------------------------------------
            % ==============================================================================

            %% Get indices of slices
            size_seg = size(img3d);
            idx_seg = find(img3d);
            [sub1_seg, sub2_seg, sub3_seg] = ind2sub(size_seg, idx_seg);
            switch idx_slicingDim
                case 1
                    idx_Slices = sort(unique(sub1_seg));
                case 2
                    idx_Slices = sort(unique(sub2_seg));
                case 3
                    idx_Slices = sort(unique(sub3_seg));
            end

            %% Get the boundary for each slice
            boundary = zeros(size_seg, 'uint8');
            for i=1:length(idx_Slices)
                % get the i-th slice
                i_idx = idx_Slices(i);
                i_slice =  CartiMorphToolbox.cal_slice3Dto2D(img3d, idx_slicingDim, i_idx);  % uint8
                % get boundary for the i-th slice (conn=4, pixels are connected if their edges touch)
                i_boundary = bwperim(i_slice, 4);
                % save the boundary in 3D array
                switch idx_slicingDim
                    case 1
                        boundary(i_idx, :, :) = i_boundary;
                    case 2
                        boundary(:, i_idx, :) = i_boundary;
                    case 3
                        boundary(:, :, i_idx) = i_boundary;
                end
            end
        end


        function img2d = cal_slice3Dto2D(img3d, dim, idx_dim)
            % ==============================================================================
            % FUNCTION:
            %     Extract one slice from a 3D array.
            %
            % INPUT:
            %     - img3d: [uint8] 3D image
            %     - dim: [uint8] the dimension to be sliced
            %     - idx_dim: [uint16] the index of the selected slice
            %
            % OUTPUT:
            %      - img2d: [uint8] 2D image
            %
            % ------------------------------------------------------------------------------
            % Matlab Version: 2019b or later
            %
            % Last updated on: 09-May-2022
            %
            % Author:
            % Yongcheng YAO (yao_yongcheng@link.cuhk.edu.hk)
            % Department of Imaging and Interventional Radiology,
            % Chinese University of Hong Kong (CUHK)
            %
            % Copyright 2020 Yongcheng YAO
            % ------------------------------------------------------------------------------
            % ==============================================================================

            % sanity check
            flag_inRange = (1<=idx_dim) & (idx_dim<=size(img3d, dim));
            assert(flag_inRange, append('Error: "idx_dim" should be in the range of [1,', num2str(size(img3d,dim)), ']'))

            %% Slice the 3d array and squeeze into 2d array
            if dim == 1
                img2d = squeeze(img3d(idx_dim, :, :));
            elseif dim == 2
                img2d = squeeze(img3d(:, idx_dim, :));
            elseif  dim == 3
                img2d = squeeze(img3d(:, :, idx_dim));
            end
        end


        function FV = cal_getBoundaryMesh(img3d)
            % ==============================================================================
            % FUNCTION:
            %     Surface reconstruction for a 3D ROI.
            %
            % INPUT:
            %     - img3d: [uint8] a binary 3D image
            %
            % OUTPUT:
            %     - FV: [structure]
            %         - FV.faces: size=[nf, 3], the faces on the boundary
            %         - FV.vertices: size=[nv, 3], the vertices on the boundary
            %
            % <<< Caution <<<
            % (tested on Matlab 2021b (release 3))
            % The build-in function "isosurface" returns (x,y) coordinates
            % Other build-in functions in matlab may return (row, column) coordinates
            % >>> Caution >>>
            %
            % ------------------------------------------------------------------------------
            % Matlab Version: 2019b or later
            %
            % Last updated on: 02-Jun-2022
            %
            % Author:
            % Yongcheng YAO (yao_yongcheng@link.cuhk.edu.hk)
            % Department of Imaging and Interventional Radiology,
            % Chinese University of Hong Kong (CUHK)
            %
            % Copyright 2020 Yongcheng YAO
            % ------------------------------------------------------------------------------
            % ==============================================================================

            % get triangular mesh for the boundary of the ROI
            FV = isosurface(~img3d, 0);

            % change the orientation: the "isosurface" function have swap the first two dimensions
            tmp = FV.vertices;
            if ~isempty(tmp)
                FV.vertices(:,2) = tmp(:,1);
                FV.vertices(:,1) = tmp(:,2);

                % vertices positions should represent the subscripts of voxels
                subs = round(FV.vertices);
                faces = FV.faces;

                % remove duplicated vertices
                [subs, faces] = CartiMorphToolbox.remove_duplicated_vertices(subs, faces); % MeshProcessingToolbox

                % remove unreferenced vertices
                [subs, faces] = CartiMorphToolbox.remove_unreferenced_vertices(subs, faces); % MeshProcessingToolbox

                % save vertices and faces
                FV.faces = faces;
                FV.vertices = subs;
            else
                FV.faces = [];
                FV.vertices = [];
            end

        end


        function faces_out = cal_extractFaces_AND(faces_source, vers_source, vers_in)
            % ==============================================================================
            % FUNCTION:
            %     Extract faces whose vertices are the target vertices.
            %
            % INPUT:
            %     - faces_source: (nf,3), faces of source mesh
            %     - vers_source: (nv,3), vertices of source mesh
            %     - vers_in: (nv_in, 3), target vertices
            %
            % OUTPUT:
            %     - faces_out: (nf_out, 3), output faces whose entries are row-ID of vers_source
            % ------------------------------------------------------------------------------
            % Matlab Version: 2019b or later
            %
            % Last updated on: 09-May-2022
            %
            % Author:
            % Yongcheng YAO (yao_yongcheng@link.cuhk.edu.hk)
            % Department of Imaging and Interventional Radiology,
            % Chinese University of Hong Kong (CUHK)
            %
            % Copyright 2020 Yongcheng YAO
            % ------------------------------------------------------------------------------
            % ==============================================================================

            verIDs_source = transpose(1:size(vers_source,1));
            verIDs = verIDs_source(ismember(vers_source, vers_in, 'rows'));
            faces_out = faces_source(sum(ismember(faces_source, verIDs), 2)==3,:);

        end


        function faces_out = cal_extractFaces_OR(faces_source, vers_source, vers_in)
            % ==============================================================================
            % FUNCTION:
            %     Extract faces with at least one vertices in the target vertices set.
            %
            % INPUT:
            %     - faces_source: (nf,3), faces of source mesh
            %     - vers_source: (nv,3), vertices of source mesh
            %     - vers_in: (nv_in, 3), target vertices
            %
            % OUTPUT:
            %     - faces_out: (nf_out, 3), output faces whose entries are row-ID of vers_source
            % ------------------------------------------------------------------------------
            % Matlab Version: 2019b or later
            %
            % Last updated on: 09-May-2022
            %
            % Author:
            % Yongcheng YAO (yao_yongcheng@link.cuhk.edu.hk)
            % Department of Imaging and Interventional Radiology,
            % Chinese University of Hong Kong (CUHK)
            %
            % Copyright 2020 Yongcheng YAO
            % ------------------------------------------------------------------------------
            % ==============================================================================

            verIDs_source = transpose(1:size(vers_source,1));
            verIDs = verIDs_source(ismember(vers_source, vers_in, 'rows'));
            faces_out = faces_source(sum(ismember(faces_source, verIDs), 2)>=1, :);

        end


        function faces_out = cal_surfaceClosing(...
                faces_in,...
                faces_source,...
                iteration_dilation,...
                iteration_erosion)
            % ==============================================================================
            % FUNCTION:
            %     Apply surface/mesh close operation.
            %
            % INPUT:
            %     - faces_source: (nf, 3), the faces of source mesh
            %     - faces_in: (nf_in, 3), the input faces
            %     - iteration_dilation: iteration time for surface dilation
            %     - iteration_erosion: iteration time for surface erosion
            %     (Note 1) faces_source and faces_in must base on the same vertices matrix
            %
            % OUTPUT:
            %     - faces_out: (nf_out, 3), the ouput faces
            % ------------------------------------------------------------------------------
            % Matlab Version: 2019b or later
            %
            % Last updated on: 09-May-2022
            %
            % Author:
            % Yongcheng YAO (yao_yongcheng@link.cuhk.edu.hk)
            % Department of Imaging and Interventional Radiology,
            % Chinese University of Hong Kong (CUHK)
            %
            % Copyright 2020 Yongcheng YAO
            % ------------------------------------------------------------------------------
            % ==============================================================================

            %% Surface dilation
            faces_dilated = CartiMorphToolbox.cal_surfaceDilation(...
                faces_in,...
                faces_source,...
                iteration_dilation);

            %% Surface erosion
            faces_out = CartiMorphToolbox.cal_surfaceErosion(...
                faces_dilated,...
                iteration_erosion);
        end


        function faces_out = cal_surfaceDilation(...
                faces_in,...
                faces_source,...
                iteration)
            % ==============================================================================
            % FUNCTION:
            %     Apply surface/mesh dilation to a mesh.
            %
            % INPUT:
            %     - faces_in: (nf_in, 3), the input faces
            %     - faces_source: (nf, 3), the faces of source mesh
            %     - iteration: iteration time for surface dilation
            %     (Note 1) faces_source and faces_in must base on the same vertices matrix
            %
            % OUTPUT:
            %     - faces_out: (nf_out, 3), the ouput faces
            % ------------------------------------------------------------------------------
            % Matlab Version: 2019b or later
            %
            % Last updated on: 09-May-2022
            %
            % Author:
            % Yongcheng YAO (yao_yongcheng@link.cuhk.edu.hk)
            % Department of Imaging and Interventional Radiology,
            % Chinese University of Hong Kong (CUHK)
            %
            % Copyright 2020 Yongcheng YAO
            % ------------------------------------------------------------------------------
            % ==============================================================================

            %% Define searching space
            % edges and faces in the seaching space
            % (the following two matrixs are corresponding in rows)
            % ----------------------------------------------------
            % edges
            edges_source = CartiMorphToolbox.cal_Faces2Edges(faces_source);
            % neighboring faces for each edge
            [neighFaces_source, ~] = CartiMorphToolbox.cal_neighFaces4Edges(faces_source);
            % ----------------------------------------------------

            %% Find the first layer of faces to be added
            % detect surface edge of the input mesh
            [marginEdges_in, ~] = CartiMorphToolbox.cal_detectSurfaceBoundary(faces_in);

            % find faces to be added to the edge of input mesh
            faces_tba_cell = neighFaces_source(ismember(edges_source, marginEdges_in, 'rows'), :);
            i_neighFacesID = unique(cell2mat(faces_tba_cell));
            faces_tba = faces_source(i_neighFacesID, :);

            % (!!!important!!!) ---
            faces_tba = CartiMorphToolbox.remove_triangles(faces_in, faces_tba, 'explicit'); % MeshProcessingToolbox
            % (!!!important!!!) ---

            %% Find more layers of faces to be added
            for i=1:iteration-1
                % detect surface edge of the input mesh
                [marginEdges_tba, ~] = CartiMorphToolbox.cal_detectSurfaceBoundary(faces_tba);

                % find faces to be added to the edge of input mesh
                faces_tba_cell = neighFaces_source(ismember(edges_source, marginEdges_tba, 'rows'), :);
                i_neighFacesID = unique(cell2mat(faces_tba_cell));
                faces_tba_next = faces_source(i_neighFacesID, :);
                faces_tba = cat(1, faces_tba, faces_tba_next);

                % (!!!important!!!) ---
                faces_tba = CartiMorphToolbox.remove_duplicated_triangles(faces_tba); % MeshProcessingToolbox
                faces_tba = CartiMorphToolbox.remove_triangles(faces_in, faces_tba, 'explicit'); % MeshProcessingToolbox
                % (!!!important!!!) ---
            end

            %% Add faces to the edge of input mesh
            faces_out = cat(1, faces_in, faces_tba);
        end


        function faces_out = cal_surfaceErosion(faces_in, iteration)
            % ==============================================================================
            % FUNCTION:
            %     Apply surface/mesh erosion to a mesh.
            %
            % INPUT:
            %     - faces_in: (nf_in, 3), the input faces
            %     - iteration: iteration time for surface dilation
            %
            % OUTPUT:
            %     - faces_out: (nf_out, 3), the ouput faces
            % ------------------------------------------------------------------------------
            % Matlab Version: 2019b or later
            %
            % Last updated on: 09-May-2022
            %
            % Author:
            % Yongcheng YAO (yao_yongcheng@link.cuhk.edu.hk)
            % Department of Imaging and Interventional Radiology,
            % Chinese University of Hong Kong (CUHK)
            %
            % Copyright 2020 Yongcheng YAO
            % ------------------------------------------------------------------------------
            % ==============================================================================


            %% Define searching space
            % edges and faces of the input faces
            % (the following two matrixs are corresponding in rows)
            % ----------------------------------------------------
            % edges
            edges_source = CartiMorphToolbox.cal_Faces2Edges(faces_in);
            % neighboring faces for each edge
            [neighFaces_source, n_neighFaces_source] = CartiMorphToolbox.cal_neighFaces4Edges(faces_in);
            % ----------------------------------------------------


            %% Find the first layer of faces to be deleted
            [~, faces_tbd] = CartiMorphToolbox.cal_detectSurfaceBoundary(faces_in);
            faces_tbd = CartiMorphToolbox.remove_duplicated_triangles(faces_tbd);


            %% Find other layers of faces to be deleted
            faces_tbd_next = faces_tbd;
            for i=1:iteration-1
                % detect surface edge of the mesh to be deleted
                [marginEdges_tbd, ~] = CartiMorphToolbox.cal_detectSurfaceBoundary(faces_tbd_next);

                % find faces to be deleted
                idx_candidateEdges = ismember(edges_source, marginEdges_tbd, 'rows') & n_neighFaces_source==2;
                neighFaces_candidates_cell = neighFaces_source(idx_candidateEdges, :);
                i_neighFacesID = unique(cell2mat(neighFaces_candidates_cell));
                faces_tbd_next = faces_in(i_neighFacesID, :);
                % (!!!important!!!) ---
                faces_tbd_next = CartiMorphToolbox.remove_duplicated_triangles(faces_tbd_next);
                faces_tbd_next = CartiMorphToolbox.remove_triangles(faces_tbd, faces_tbd_next, 'explicit');
                faces_tbd = cat(1, faces_tbd, faces_tbd_next);
                % (!!!important!!!) ---
            end


            %% Delete multiple layers of marginal faces
            faces_out = CartiMorphToolbox.remove_triangles(faces_tbd, faces_in, 'explicit'); % MeshProcessingToolbox

        end


        function faces_out = cal_surfaceDilation_restricted(...
                faces_in,...
                faces_source,...
                vers_source,...
                vers_border)
            % ==============================================================================
            % FUNCTION:
            %     Apply restricted surface/mesh dilation to a mesh.
            %
            % INPUT:
            %     - faces_in: (nf_in, 3), the input faces
            %     - faces_source: (nf, 3), the faces of source mesh
            %     - vers_source: (nv, 3), the vertices of source mesh
            %     - vers_border: (nv_border, 3), the vertices on border of dilation space
            %     (Note 1) faces_source and faces_in must base on the same vertices matrix
            %
            % OUTPUT:
            %     - faces_out: (nf_out, 3), the ouput faces
            % ------------------------------------------------------------------------------
            % Matlab Version: 2019b or later
            %
            % Last updated on: 09-May-2022
            %
            % Author:
            % Yongcheng YAO (yao_yongcheng@link.cuhk.edu.hk)
            % Department of Imaging and Interventional Radiology,
            % Chinese University of Hong Kong (CUHK)
            %
            % Copyright 2020 Yongcheng YAO
            % ------------------------------------------------------------------------------
            % ==============================================================================


            %% Define searching space
            % edges and faces in the seaching space
            % (the following two matrixs are corresponding in rows)
            % ----------------------------------------------------
            % edges
            edges_source = CartiMorphToolbox.cal_Faces2Edges(faces_source);
            % neighboring faces for each edge
            [neighFaces_source, ~] = CartiMorphToolbox.cal_neighFaces4Edges(faces_source);
            % ----------------------------------------------------


            %% Find the first layer of faces to be added
            % delete duplicated faces
            faces_in = CartiMorphToolbox.remove_duplicated_triangles(faces_in);
            % detect surface edge of the input mesh
            [marginEdges_in, ~] = CartiMorphToolbox.cal_detectSurfaceBoundary(faces_in);

            % find faces to be added to the edge of input mesh
            faces_tba_cell = neighFaces_source(ismember(edges_source, marginEdges_in, 'rows'), :);
            i_neighFacesID = unique(cell2mat(faces_tba_cell));
            faces_tba = faces_source(i_neighFacesID, :);

            % (!!!important!!!) ---
            faces_tba = CartiMorphToolbox.remove_triangles(faces_in, faces_tba, 'explicit'); % MeshProcessingToolbox
            % (!!!important!!!) ---

            % faces that reach the dilation border will not be added
            idx_border = ismember(vers_source, vers_border, 'rows');
            versID_source = transpose(1:size(vers_source,1));
            versID_border = versID_source(idx_border);
            idx_exclude = sum(ismember(faces_tba, versID_border), 2)>=1;
            faces_tba(idx_exclude, :) = [];


            %% Find more layers of faces to be added
            flag_continue = true;
            if ~isempty(faces_tba)
                while flag_continue
                    % detect surface edge of the input mesh
                    [marginEdges_tba, ~] = CartiMorphToolbox.cal_detectSurfaceBoundary(faces_tba);

                    % find faces to be added to the edge of input mesh
                    faces_tba_cell = neighFaces_source(ismember(edges_source, marginEdges_tba, 'rows'), :);
                    i_neighFacesID = unique(cell2mat(faces_tba_cell));
                    faces_tba_next = faces_source(i_neighFacesID, :);
                    % (!!!important!!!) ---
                    faces_tba_next = CartiMorphToolbox.remove_triangles(faces_in, faces_tba_next, 'explicit'); % MeshProcessingToolbox
                    faces_tba_next = CartiMorphToolbox.remove_triangles(faces_tba, faces_tba_next, 'explicit'); % MeshProcessingToolbox
                    idx_exclude = sum(ismember(faces_tba_next,versID_border), 2)>=1;
                    faces_tba_next(idx_exclude, :) = [];
                    % (!!!important!!!) ---
                    faces_tba = cat(1, faces_tba, faces_tba_next);

                    % stop surface dilation if no more faces can be added
                    if isempty(faces_tba_next)
                        flag_continue = false;
                    end
                end
            end


            %% Add faces to the edge of input mesh
            faces_out = cat(1, faces_in, faces_tba);

        end


        function faces_out = cal_deleteSharpEdgeTri(faces_in)
            % ==============================================================================
            % FUNCTION:
            %     Remove faces whose two or more edges are marginal edges of the mesh
            %
            % INPUT:
            %     - faces_in: (nf_in, 3), the input faces
            %
            % OUTPUT:
            %     - faces_out: (nf_out, 3), the ouput faces
            % ------------------------------------------------------------------------------
            % Matlab Version: 2019b or later
            %
            % Last updated on: 09-May-2022
            %
            % Author:
            % Yongcheng YAO (yao_yongcheng@link.cuhk.edu.hk)
            % Department of Imaging and Interventional Radiology,
            % Chinese University of Hong Kong (CUHK)
            %
            % Copyright 2020 Yongcheng YAO
            % ------------------------------------------------------------------------------
            % ==============================================================================

            % remove all "sharp triangles" on the edge
            flag_continue = true;
            faces_out_tmp = faces_in;
            while flag_continue
                % detect surface edge
                [marginEdges, ~] = CartiMorphToolbox.cal_detectSurfaceBoundary(faces_out_tmp);

                % find faces to be deleted
                idx_versOnMarginEdges = marginEdges(:);
                tmp = ismember(faces_out_tmp, idx_versOnMarginEdges);
                tmp = tmp(:,1) & tmp(:,2) & tmp(:,3);
                faces_tbd = faces_out_tmp(tmp, :);

                if ~isempty(faces_tbd)
                    % remove faces whose two or more edges are marginal edges of the mesh
                    faces_out_tmp = CartiMorphToolbox.remove_triangles(faces_tbd, faces_out_tmp, 'explicit'); % MeshProcessingToolbox
                else
                    flag_continue = false;
                end
            end

            faces_out = faces_out_tmp;

        end


        function edges = cal_Faces2Edges(faces)
            % ==============================================================================
            % FUNCTION:
            %     Get edges from triangular faces.
            %
            % INPUT:
            %     - faces: (nf, 3), faces of the mesh
            %
            % OUTPUT:
            %     - edges: (ne, 2), edges of the mesh
            % ------------------------------------------------------------------------------
            % Matlab Version: 2019b or later
            %
            % Last updated on: 09-May-2022
            %
            % Author:
            % Yongcheng YAO (yao_yongcheng@link.cuhk.edu.hk)
            % Department of Imaging and Interventional Radiology,
            % Chinese University of Hong Kong (CUHK)
            %
            % Copyright 2020 Yongcheng YAO
            % ------------------------------------------------------------------------------
            % ==============================================================================

            edges = sort([faces(:,[1 2]) ; faces(:,[2 3]) ; faces(:,[3 1])], 2);
            edges = unique(edges, 'rows');

        end


        function [neighFaces, n_neighFaces] = cal_neighFaces4Edges(faces)
            % ==============================================================================
            % FUNCTION:
            %     Get neighboring faces for each edge.
            %
            % INPUT:
            %     - faces: (nf, 3), faces of the mesh
            %
            % OUTPUT:
            %     - neighFaces: [cell], (n_edges, 1) neighboring faces for each edge
            %     - n_neighFaces: (n_edges, 1) the number of neighboring faces for each edge
            % ------------------------------------------------------------------------------
            % Matlab Version: 2019b or later
            %
            % Last updated on: 09-May-2022
            %
            % Author:
            % Yongcheng YAO (yao_yongcheng@link.cuhk.edu.hk)
            % Department of Imaging and Interventional Radiology,
            % Chinese University of Hong Kong (CUHK)
            %
            % Copyright 2020 Yongcheng YAO
            % ------------------------------------------------------------------------------
            % ==============================================================================

            % get edges from faces (contains duplicated edges)
            edges = sort(cat(1, faces(:,[1,2]), faces(:,[2,3]), faces(:,[3,1])), 2);

            % keep track of faceIDs
            numFaces = size(faces, 1);
            faceIDs = repmat((1:numFaces)', 3, 1);

            % remove duplicated edges
            [edges_clean, ~, idx_clean] = unique(edges, 'rows');
            numEdges = size(edges_clean, 1);

            % find neighboring faces for each edge
            neighFaces = cell(numEdges, 1);
            n_neighFaces = zeros(numEdges, 1);

            % [enable parallel computing if available]
            if ~license('test', 'Distrib_Computing_Toolbox')
                % (without parallel computing)
                for i = 1:numEdges
                    idx_neighFaces = idx_clean == i;
                    neighFaces{i, 1} = faceIDs(idx_neighFaces);
                    n_neighFaces(i, 1) = sum(idx_neighFaces);
                end
            elseif isempty(gcp("nocreate"))
                % (same code with parallel computing)
                parpool;
                parfor i = 1:numEdges
                    idx_neighFaces = idx_clean == i;
                    neighFaces{i, 1} = faceIDs(idx_neighFaces); %#ok<*PFBNS>
                    n_neighFaces(i, 1) = sum(idx_neighFaces);
                end
            else
                % (same code with parallel computing)
                parfor i = 1:numEdges
                    idx_neighFaces = idx_clean == i;
                    neighFaces{i, 1} = faceIDs(idx_neighFaces); %#ok<*PFBNS>
                    n_neighFaces(i, 1) = sum(idx_neighFaces);
                end
            end

        end


        function [marginEdges, marginFaces] = cal_detectSurfaceBoundary(faces_in)
            % ==============================================================================
            % FUNCTION:
            %
            % INPUT:
            %     - faces_in: (nf_in, 3), the input faces
            %
            % OUTPUT:
            %     - marginEdges: size=[ne, 2], the edge of the surface boundary
            %     - marginFaces: size=[nf, 3], the marginal faces
            % ------------------------------------------------------------------------------
            % Matlab Version: 2019b or later
            %
            % Last updated on: 09-May-2022
            %
            % Author:
            % Yongcheng YAO (yao_yongcheng@link.cuhk.edu.hk)
            % Department of Imaging and Interventional Radiology,
            % Chinese University of Hong Kong (CUHK)
            %
            % Copyright 2020 Yongcheng YAO
            % ------------------------------------------------------------------------------
            % ==============================================================================


            % (the following two matrixs are corresponding in rows)
            % ----------------------------------------------------
            % get edges from faces
            edges = CartiMorphToolbox.cal_Faces2Edges(faces_in);
            % find neighboring faces for each edge
            [neighFaces_cell, n_neighFaces] = CartiMorphToolbox.cal_neighFaces4Edges(faces_in);
            % ----------------------------------------------------

            % find edges with only one adjacent face
            idx_marginEdges = n_neighFaces==1;
            marginEdges = edges(idx_marginEdges, :);

            % find faces on the edge
            idx_marginFaces = cell2mat(neighFaces_cell(idx_marginEdges));
            marginFaces = faces_in(idx_marginFaces, :);

        end


        function mask = cal_removeSmallComponent4Bone_3D(mask)

            CC_Mask = bwconncomp(mask, 26);
            num_CC_Mask = length(CC_Mask.PixelIdxList);
            if num_CC_Mask>1
                CC_size = zeros([num_CC_Mask, 1]);
                for i=1:num_CC_Mask
                    CC_size(i, 1) = length(CC_Mask.PixelIdxList{i});
                end
                [~, idx_maxCC_Mask] = max(CC_size);
                tmp = CC_Mask.PixelIdxList;
                tmp(idx_maxCC_Mask) = [];
                CC_PixelIdxList_Mask_tbr = [];
                for i=1:length(tmp)
                    CC_PixelIdxList_Mask_tbr = [CC_PixelIdxList_Mask_tbr; tmp{i}];
                end
                mask(CC_PixelIdxList_Mask_tbr) = 0;
            end

        end


        function mask_3d = cal_convertVoxCoor2Mask_3D(coor_vers_in, size_img, size_voxel)

            % convert coordinates to volume
            subs_vertices = round(coor_vers_in ./ size_voxel);
            mask_3d = zeros(size_img);
            idx_vertices = sub2ind(size_img, subs_vertices(:,1), subs_vertices(:,2), subs_vertices(:,3));
            mask_3d(idx_vertices) = 1;

        end


        function mask_3d = cal_convertVoxIdx2Mask_3D(subs_vertices, size_img)

            % convert voxel indices to volume
            mask_3d = zeros(size_img);
            idx_vertices = sub2ind(size_img, subs_vertices(:,1), subs_vertices(:,2), subs_vertices(:,3));
            mask_3d(idx_vertices) = 1;

        end


        function [faces_filled, vers_filled, faces_filling, vers_filling] = cal_reconCartDefect_conn(...
                faces_inter, ...
                FV_bone)
            % ==============================================================================
            % FUNCTION:
            %     Full-thickness cartilage defect reconstruction (step 1).
            %
            % INPUT:
            %     - faces_inter: (nf_in, 3), faces of the bone-cartilage interface
            %     - FV_bone: the bone surface
            %
            % OUTPUT:
            %     - faces_filled: (nf_filled, 3), faces of the filled surface
            %     - vers_filled: (nv_filled, 3), vertices of the filled surface
            %     - faces_filling: (nf_filling, 3), faces of the filling surface
            %     - vers_filling: (nv_filling, 3), vertices of the filling surface
            % ------------------------------------------------------------------------------
            % Matlab Version: 2019b or later
            %
            % Last updated on: 09-May-2022
            %
            % Author:
            % Yongcheng YAO (yao_yongcheng@link.cuhk.edu.hk)
            % Department of Imaging and Interventional Radiology,
            % Chinese University of Hong Kong (CUHK)
            %
            % Copyright 2020 Yongcheng YAO
            % ------------------------------------------------------------------------------
            % ==============================================================================


            %% Find filling components
            % bone vertices and faces
            vers_bone = FV_bone.vertices;
            faces_bone = FV_bone.faces;
            % remove interface mesh from the bone mesh (MeshProcessingToolbox)
            faces_nonInter = CartiMorphToolbox.remove_triangles(faces_inter, faces_bone, 'explicit');
            % remove large components from the non-interface mesh (MeshProcessingToolbox)
            [~, components_nonInter] = CartiMorphToolbox.segment_connected_components(faces_nonInter, 'explicit');
            faces_filling = CartiMorphToolbox.cal_deleteLargeComponents(components_nonInter, 50);


            %% Add filling mesh to the interface mesh
            % add filling mesh
            faces_filled  = cat(1, faces_inter, faces_filling);
            % remove duplicated faces (MeshProcessingToolbox)
            faces_filled = CartiMorphToolbox.remove_duplicated_triangles(faces_filled);
            % remove unreferenced vertices (MeshProcessingToolbox)
            [vers_filled, faces_filled] = CartiMorphToolbox.remove_unreferenced_vertices(vers_bone, faces_filled);


            %% Update the mesh of filling components
            if ~isempty(faces_filling)
                % remove duplicated faces (MeshProcessingToolbox)
                faces_filling = CartiMorphToolbox.remove_duplicated_triangles(faces_filling);
                % remove unreferenced vertices (MeshProcessingToolbox)
                [vers_filling, faces_filling] = CartiMorphToolbox.remove_unreferenced_vertices(vers_bone, faces_filling);
            else
                vers_filling = [];
            end

        end


        function subs_filled = cal_reconCartDefect_curve_FC(subs_in, size_img)
            % ==============================================================================
            % FUNCTION:
            %     Full-thickness cartilage defect reconstruction for Femoral Cartilage (step 2).
            %
            % INPUT:
            %     - subs_in: (nc_in, 3), subscripts of voxels on the bone-cartilage interface
            %     - size_img: image size
            %
            % OUTPUT:
            %     - subs_filled: (nf_out, 3), subscripts of voxels on the filled bone-cartilage interface
            % ------------------------------------------------------------------------------
            % Matlab Version: 2019b or later
            %
            % Last updated on: 25-Jul-2022
            %
            % Author:
            % Yongcheng YAO (yao_yongcheng@link.cuhk.edu.hk)
            % Department of Imaging and Interventional Radiology,
            % Chinese University of Hong Kong (CUHK)
            %
            % Copyright 2020 Yongcheng YAO
            % ------------------------------------------------------------------------------
            % ==============================================================================


            %% Find sagittal slices need to be filled
            sagIdx = unique(subs_in(:, 1));
            num_sagSlices= size(sagIdx, 1);
            sagIdx_tbf = [];
            for i=1:num_sagSlices
                % get voxels indices in th i-th sagittal slices
                i_sagIdx = sagIdx(i);
                i_sagSlice = subs_in(subs_in(:,1)==i_sagIdx, :, :);
                % remove isolated voxels
                i_sagSlice_vol = CartiMorphToolbox.cal_convertVoxIdx2Mask_3D(i_sagSlice, size_img);
                i_sagSlice_vol = bwareaopen(i_sagSlice_vol, 10, 26);
                % find connected components
                i_cc = bwconncomp(i_sagSlice_vol);
                if i_cc.NumObjects > 1
                    sagIdx_tbf = cat(1, sagIdx_tbf, i_sagIdx);
                end
            end


            %% Setting
            maxOrder = 7;
            subs_filled = subs_in;
            num_sagSlices_tbf = size(sagIdx_tbf, 1);


            %% Gap filling via curve fitting
            % % Testing: plot-------------------------------------------------
            % count = 1;
            % figure
            % % Testing: plot-------------------------------------------------

            for j=1:num_sagSlices_tbf
                % get voxels indices in th i-th sagittal slices
                j_sagIdx = sagIdx_tbf(j);
                j_sagSlice = subs_in(subs_in(:,1)==j_sagIdx, :, :);

                if size(j_sagSlice,1)>=20
                    % [Coordinate Conversion & Normalization]
                    % -----------------------------------
                    % fit a circle to points & adjust the circle center
                    j_sagSlice_2D = j_sagSlice(:,[2,3]);
                    [j_centerX, ~, ~] = CartiMorphToolbox.cal_fitCircle(j_sagSlice_2D(:,1), j_sagSlice_2D(:,2));
                    j_center_2D = round([j_centerX, max(j_sagSlice_2D(:,2)+1)]);
                    % normalize the voxels indices via translation to the estimated center
                    j_sagSlice_2D_norm = j_sagSlice_2D - j_center_2D;
                    % convert from Cartesian coordinate to polar coordinate system
                    j_x = j_sagSlice_2D_norm(:,1);
                    j_y = j_sagSlice_2D_norm(:,2);
                    [j_theta, j_rho] = cart2pol(j_x, j_y);
                    j_theta_rho = [j_theta, j_rho];
                    % remove duplicated points in polar coordinate system
                    j_theta_rho = unique(j_theta_rho, 'rows');
                    j_theta = j_theta_rho(:, 1);
                    j_rho = j_theta_rho(:, 2);
                    % -----------------------------------


                    % [Curve fittings in polar coordinate]
                    % -----------------------------------
                    % supress warning: "curvefit:fit:equationBadlyConditioned"
                    warning('off', 'curvefit:fit:equationBadlyConditioned')
                    % find the best curve fitting model
                    j_bestModel = CartiMorphToolbox.cal_bestCurveFit_poly(j_theta, j_rho, maxOrder);
                    % estimate/evaluate the best model
                    j_grid_theta = transpose(min(j_theta):asin(1/max(j_rho))/10:max(j_theta));
                    j_grid_rho = j_bestModel(j_grid_theta);
                    [j_x_grid_norm, j_y_hat_grid_norm] = pol2cart(j_grid_theta, j_grid_rho);
                    % move the estrimated points back to the original center
                    j_x_grid = j_x_grid_norm + j_center_2D(1);
                    j_y_hat_grid = j_y_hat_grid_norm + j_center_2D(2);
                    j_xy_grid = [j_x_grid, j_y_hat_grid];
                    % -----------------------------------


                    % [Fill the gaps in sagittal slice]
                    % -----------------------------------
                    if min(j_xy_grid(:,1))>0 &&...
                            min(j_xy_grid(:,2))>0 &&...
                            max(j_xy_grid(:,1))<=size_img(2) &&...
                            max(j_xy_grid(:,2))<=size_img(3)
                        search_range = norm([1,1,1]);  % when vers is voxel indices
                        [j_distance, ~] = pdist2(j_sagSlice_2D, j_xy_grid, 'euclidean', 'Smallest', 1);
                        if sum(j_distance>search_range)>0
                            idx_xGrid_fill = j_distance > search_range;
                            j_xy_fill_smooth = j_xy_grid(idx_xGrid_fill, :);
                            j_xy_filling_2D = unique(round(j_xy_fill_smooth), 'rows');
                            j_sagSlice_filling = [repmat(j_sagIdx, [size(j_xy_filling_2D,1),1]), j_xy_filling_2D];
                            subs_filled = cat(1, subs_filled, j_sagSlice_filling);
                        end
                    end
                    % -----------------------------------
                end
            end

        end


        function subs_filled = cal_reconCartDefect_curve_TC(subs_in, size_img)
            % ==============================================================================
            % FUNCTION:
            %     Full-thickness cartilage defect reconstruction for Tibial Cartilage (step 2).
            %
            % INPUT:
            %     - subs_in: (nc_in, 3), subscripts of voxels on the bone-cartilage interface
            %     - size_img: image size
            %
            % OUTPUT:
            %     - subs_filled: (nf_out, 3), subscripts of voxels on the filled bone-cartilage interface
            % ------------------------------------------------------------------------------
            % Matlab Version: 2019b or later
            %
            % Last updated on: 25-Jul-2022
            %
            % Author:
            % Yongcheng YAO (yao_yongcheng@link.cuhk.edu.hk)
            % Department of Imaging and Interventional Radiology,
            % Chinese University of Hong Kong (CUHK)
            %
            % Copyright 2020 Yongcheng YAO
            % ------------------------------------------------------------------------------
            % ==============================================================================


            %% Find sagittal slices need to be filled
            sagIdx = unique(subs_in(:, 1));
            num_sagSlices= size(sagIdx, 1);
            sagIdx_tbf = [];
            for i=1:num_sagSlices
                % get voxels indices in th i-th sagittal slices
                i_sagIdx = sagIdx(i);
                i_sagSlice = subs_in(subs_in(:,1)==i_sagIdx, :, :);
                % remove isolated voxels
                i_sagSlice_vol = CartiMorphToolbox.cal_convertVoxIdx2Mask_3D(i_sagSlice, size_img);
                i_sagSlice_vol = bwareaopen(i_sagSlice_vol, 10, 26);
                % find connected components
                i_cc = bwconncomp(i_sagSlice_vol);
                if i_cc.NumObjects > 1
                    sagIdx_tbf = cat(1, sagIdx_tbf, i_sagIdx);
                end
            end


            %% Setting
            maxOrder = 5;
            subs_filled = subs_in;
            num_sagSlices_tbf = size(sagIdx_tbf, 1);


            %% Gap filling via curve fitting
            % % Testing: plot-------------------------------------------------
            % count = 1;
            % figure
            % % Testing: plot-------------------------------------------------

            for j=1:num_sagSlices_tbf
                % get voxels indices in th i-th sagittal slices
                j_sagIdx = sagIdx_tbf(j);
                j_sagSlice = subs_in(subs_in(:,1)==j_sagIdx, :, :);

                if size(j_sagSlice,1)>=20
                    % [Normalization]
                    % -----------------------------------
                    % find the center
                    j_sagSlice_2D = j_sagSlice(:,[2,3]);
                    j_center_2D = round([mean(j_sagSlice_2D(:,1)), min(j_sagSlice_2D(:,2))-1]);
                    % normalize the voxels indices via translation to the estimated center
                    j_sagSlice_2D_norm = j_sagSlice_2D - j_center_2D;
                    j_x = j_sagSlice_2D_norm(:,1);
                    j_y = j_sagSlice_2D_norm(:,2);
                    j_xy = [j_x, j_y];
                    % remove duplicated points
                    j_xy = unique(j_xy, 'rows');
                    j_x = j_xy(:, 1);
                    j_y = j_xy(:, 2);
                    % -----------------------------------

                    % [Curve fittings]
                    % -----------------------------------
                    % supress warning: "curvefit:fit:equationBadlyConditioned"
                    warning('off', 'curvefit:fit:equationBadlyConditioned')
                    % find the best curve fitting model
                    j_bestModel = CartiMorphToolbox.cal_bestCurveFit_poly(j_x, j_y, maxOrder);
                    % estimate/evaluate the best model
                    j_x_grid_norm = transpose(min(j_x):1/10:max(j_x));
                    j_y_hat_grid_norm = j_bestModel(j_x_grid_norm);
                    % move the estimated points back to the original center
                    j_x_grid = j_x_grid_norm + j_center_2D(1);
                    j_y_hat_grid = j_y_hat_grid_norm + j_center_2D(2);
                    j_xy_grid = [j_x_grid, j_y_hat_grid];
                    % -----------------------------------

                    % [Fill the gaps in sagittal slice]
                    % -----------------------------------
                    if min(j_xy_grid(:,1))>0 &&...
                            min(j_xy_grid(:,2))>0 &&...
                            max(j_xy_grid(:,1))<=size_img(2) &&...
                            max(j_xy_grid(:,2))<=size_img(3)
                        search_range = norm([1,1,1]);  % when vers is voxel indices
                        [j_distance, ~] = pdist2(j_sagSlice_2D, j_xy_grid, 'euclidean', 'Smallest', 1);
                        if sum(j_distance > search_range)>0
                            idx_xGrid_fill = j_distance > search_range;
                            j_xy_fill_smooth = j_xy_grid(idx_xGrid_fill, :);
                            j_xy_filling_2D = unique(round(j_xy_fill_smooth), 'rows');
                            j_sagSlice_filling = [repmat(j_sagIdx, [size(j_xy_filling_2D,1),1]), j_xy_filling_2D];
                            subs_filled = cat(1, subs_filled, j_sagSlice_filling);
                        end
                    end
                    % -----------------------------------
                end
            end
        end


        function [cx, cy, R] = cal_fitCircle(x, y)
            x = x(:);
            y = y(:);
            a = -1 * [x, y, ones(size(x))] \ (x.^2 + y.^2);
            cx = -0.5 * a(1);
            cy = -0.5 * a(2);
            R = sqrt((a(1)^2 + a(2)^2)/4 - a(3));
        end


        function bestModel = cal_bestCurveFit_poly(x, y, maxOrder)

            if maxOrder==9
                % fit 7 polynomial equations
                [curve_poly3, gof_poly3, ~] = fit(x, y, 'poly3');
                [curve_poly4, gof_poly4, ~] = fit(x, y, 'poly4');
                [curve_poly5, gof_poly5, ~] = fit(x, y, 'poly5');
                [curve_poly6, gof_poly6, ~] = fit(x, y, 'poly6');
                [curve_poly7, gof_poly7, ~] = fit(x, y, 'poly7');
                [curve_poly8, gof_poly8, ~] = fit(x, y, 'poly8');
                [curve_poly9, gof_poly9, ~] = fit(x, y, 'poly9');

                % find the best model
                RMSE = [gof_poly3.rmse, gof_poly4.rmse, gof_poly5.rmse, ...
                    gof_poly6.rmse, gof_poly7.rmse, gof_poly8.rmse, gof_poly9.rmse];
                curveFitModels = {curve_poly3, curve_poly4, curve_poly5,...
                    curve_poly6, curve_poly7, curve_poly8, curve_poly9};
                [~, idx_bestModel] = min(RMSE);
                bestModel = curveFitModels{idx_bestModel};
            end

            if maxOrder==8
                % fit 7 polynomial equations
                [curve_poly3, gof_poly3, ~] = fit(x, y, 'poly3');
                [curve_poly4, gof_poly4, ~] = fit(x, y, 'poly4');
                [curve_poly5, gof_poly5, ~] = fit(x, y, 'poly5');
                [curve_poly6, gof_poly6, ~] = fit(x, y, 'poly6');
                [curve_poly7, gof_poly7, ~] = fit(x, y, 'poly7');
                [curve_poly8, gof_poly8, ~] = fit(x, y, 'poly8');

                % find the best model
                RMSE = [gof_poly3.rmse, gof_poly4.rmse, gof_poly5.rmse, ...
                    gof_poly6.rmse, gof_poly7.rmse, gof_poly8.rmse];
                curveFitModels = {curve_poly3, curve_poly4, curve_poly5,...
                    curve_poly6, curve_poly7, curve_poly8};
                [~, idx_bestModel] = min(RMSE);
                bestModel = curveFitModels{idx_bestModel};
            end

            if maxOrder==7
                % fit 7 polynomial equations
                [curve_poly3, gof_poly3, ~] = fit(x, y, 'poly3');
                [curve_poly4, gof_poly4, ~] = fit(x, y, 'poly4');
                [curve_poly5, gof_poly5, ~] = fit(x, y, 'poly5');
                [curve_poly6, gof_poly6, ~] = fit(x, y, 'poly6');
                [curve_poly7, gof_poly7, ~] = fit(x, y, 'poly7');

                % find the best model
                RMSE = [gof_poly3.rmse, gof_poly4.rmse, gof_poly5.rmse, ...
                    gof_poly6.rmse, gof_poly7.rmse];
                curveFitModels = {curve_poly3, curve_poly4, curve_poly5,...
                    curve_poly6, curve_poly7};
                [~, idx_bestModel] = min(RMSE);
                bestModel = curveFitModels{idx_bestModel};
            end

            if maxOrder==6
                % fit 7 polynomial equations
                [curve_poly3, gof_poly3, ~] = fit(x, y, 'poly3');
                [curve_poly4, gof_poly4, ~] = fit(x, y, 'poly4');
                [curve_poly5, gof_poly5, ~] = fit(x, y, 'poly5');
                [curve_poly6, gof_poly6, ~] = fit(x, y, 'poly6');

                % find the best model
                RMSE = [gof_poly3.rmse, gof_poly4.rmse, gof_poly5.rmse, ...
                    gof_poly6.rmse];
                curveFitModels = {curve_poly3, curve_poly4, curve_poly5,...
                    curve_poly6};
                [~, idx_bestModel] = min(RMSE);
                bestModel = curveFitModels{idx_bestModel};
            end

            if maxOrder==5
                % fit 3 polynomial equations
                [curve_poly3, gof_poly3, ~] = fit(x, y, 'poly3');
                [curve_poly4, gof_poly4, ~] = fit(x, y, 'poly4');
                [curve_poly5, gof_poly5, ~] = fit(x, y, 'poly5');

                % find the best model
                RMSE = [gof_poly3.rmse, gof_poly4.rmse, gof_poly5.rmse];
                curveFitModels = {curve_poly3, curve_poly4, curve_poly5};
                [~, idx_bestModel] = min(RMSE);
                bestModel = curveFitModels{idx_bestModel};
            end

        end


        function faces_out = cal_deleteLargeComponents(components, threshold)
            % ==============================================================================
            % FUNCTION:
            %     Delete large mesh components.
            %
            % INPUT:
            %     - components: mesh component
            %     - threshold: size threshold above which the (large) components will be removed
            %
            % OUTPUT:
            %     - faces_out: (nf_out, 3), the ouput faces
            % ------------------------------------------------------------------------------
            % Matlab Version: 2019b or later
            %
            % Last updated on: 09-May-2022
            %
            % Author:
            % Yongcheng YAO (yao_yongcheng@link.cuhk.edu.hk)
            % Department of Imaging and Interventional Radiology,
            % Chinese University of Hong Kong (CUHK)
            %
            % Copyright 2020 Yongcheng YAO
            % ------------------------------------------------------------------------------
            % ==============================================================================

            size_components = cellfun(@(c) size(c,1), components);
            total_size = sum(size_components, 1);
            component_percentage = round(100 * size_components / total_size);
            faces_out = cell2mat(components(component_percentage<=threshold, 1));

        end


        function faces_out = cal_deleteSmallComponents(components, threshold)
            % ==============================================================================
            % FUNCTION:
            %     Delete small mesh components.
            %
            % INPUT:
            %     - components: mesh component
            %     - threshold: size threshold below which the (small) components will be removed
            %
            % OUTPUT:
            %     - faces_out: (nf_out, 3), the ouput faces
            % ------------------------------------------------------------------------------
            % Matlab Version: 2019b or later
            %
            % Last updated on: 09-May-2022
            %
            % Author:
            % Yongcheng YAO (yao_yongcheng@link.cuhk.edu.hk)
            % Department of Imaging and Interventional Radiology,
            % Chinese University of Hong Kong (CUHK)
            %
            % Copyright 2020 Yongcheng YAO
            % ------------------------------------------------------------------------------
            % ==============================================================================

            size_components = cellfun(@(c) size(c,1), components);
            total_size = sum(size_components, 1);
            component_percentage = round(100 * size_components / total_size);
            faces_out = cell2mat(components(component_percentage>=threshold, 1));

        end


        function SN = cal_estimateSN(vers_voxels, n_neigh)
            % ==============================================================================
            % FUNCTION:
            %     Surface normals estimation.
            %
            % INPUT:
            %     - coor_voxels: (nv, 3), voxel coordinates
            %     - n_neigh: number of neighbors to estimate surface normal for a voxel
            %
            % OUTPUT:
            %      - SN: (nv, 3), the estimated SNs
            % ------------------------------------------------------------------------------
            % Matlab Version: 2019b or later
            %
            % Last updated on: 11-May-2022
            %
            % Author:
            % Yongcheng YAO (yao_yongcheng@link.cuhk.edu.hk)
            % Department of Imaging and Interventional Radiology,
            % Chinese University of Hong Kong (CUHK)
            %
            % Copyright 2020 Yongcheng YAO
            % ------------------------------------------------------------------------------
            % ==============================================================================

            n_voxels = size(vers_voxels, 1); % number of vertices
            SN = zeros(n_voxels, 3); % unit surface normal

            % [enable parallel computing if available]
            if ~license('test', 'Distrib_Computing_Toolbox')
                % (without parallel computing)
                for i = 1:n_voxels
                    % get the coordinates of the i-th voxel
                    i_vox = vers_voxels(i, :);
                    % find the neighbors of the i-th voxel
                    [~, idx_neigh] = pdist2(vers_voxels, i_vox, 'euclidean', 'Smallest', n_neigh);
                    % get the coordinates of neighbors and the i-th voxel
                    i_pool = vers_voxels(idx_neigh, :);
                    % estimate the surface normal
                    i_pool_centered = i_pool - mean(i_pool, 1);
                    [~, ~, V] = svd(i_pool_centered, 0);
                    i_SN = V(:, end);
                    % get unit surface normal
                    SN(i, :) = i_SN ./ norm(i_SN);
                end
            elseif isempty(gcp("nocreate"))
                % (same code with parallel computing)
                parpool;
                parfor i = 1:n_voxels
                    % get the coordinates of the i-th voxel
                    i_vox = vers_voxels(i, :);
                    % find the neighbors of the i-th voxel
                    [~, idx_neigh] = pdist2(vers_voxels, i_vox, 'euclidean', 'Smallest', n_neigh);
                    % get the coordinates of neighbors and the i-th voxel
                    i_pool = vers_voxels(idx_neigh, :);
                    % estimate the surface normal
                    i_pool_centered = i_pool - mean(i_pool, 1);
                    [~, ~, V] = svd(i_pool_centered, 0);
                    i_SN = V(:, end);
                    % get unit surface normal
                    SN(i, :) = i_SN ./ norm(i_SN);
                end
            else
                % (same code with parallel computing)
                parfor i = 1:n_voxels
                    % get the coordinates of the i-th voxel
                    i_vox = vers_voxels(i, :);
                    % find the neighbors of the i-th voxel
                    [~, idx_neigh] = pdist2(vers_voxels, i_vox, 'euclidean', 'Smallest', n_neigh);
                    % get the coordinates of neighbors and the i-th voxel
                    i_pool = vers_voxels(idx_neigh, :);
                    % estimate the surface normal
                    i_pool_centered = i_pool - mean(i_pool, 1);
                    [~, ~, V] = svd(i_pool_centered, 0);
                    i_SN = V(:, end);
                    % get unit surface normal
                    SN(i, :) = i_SN ./ norm(i_SN);
                end
            end

        end


        function SN_out = cal_approximateSN(SN_in, vers_inter, n_neigh)
            % ==============================================================================
            % FUNCTION:
            %     SN approximation.
            %
            % INPUT:
            %     - SN_in: (nv, 3), input surface normals
            %     - vers_inter: (nv, 3), voxel coordinates
            %     - n_neigh: number of neighbors whose estimated surface normal are non-zero
            %
            % OUTPUT:
            %      - SN_out: (nv, 3), the SNs after SN approximation
            % ------------------------------------------------------------------------------
            % Matlab Version: 2019b or later
            %
            % Last updated on: 11-May-2022
            %
            % Author:
            % Yongcheng YAO (yao_yongcheng@link.cuhk.edu.hk)
            % Department of Imaging and Interventional Radiology,
            % Chinese University of Hong Kong (CUHK)
            %
            % Copyright 2020 Yongcheng YAO
            % ------------------------------------------------------------------------------
            % ==============================================================================

            % get all non-zero SNs
            SN_nonZero = SN_in(~ismember(SN_in, [0, 0, 0], 'row'), :);

            % get coordinates of voxels with non-zero estimated SN
            vers_nonZeroSN = vers_inter(~ismember(SN_in, [0, 0, 0], 'row'), :);

            SN_out = SN_in;
            idx_zeroSN = find(ismember(SN_in, [0, 0, 0], 'row'));
            SN_eval = SN_in(idx_zeroSN, :);

            % [enable parallel computing if available]
            if ~license('test', 'Distrib_Computing_Toolbox')
                % (without parallel computing)
                for i = 1:size(idx_zeroSN, 1)
                    % get the i-th voxel whose estimated SN is [0,0,0]
                    i_ver_zeroSN = vers_inter(idx_zeroSN(i), :);
                    % find neighbors whose estimated normal vectors are not [0,0,0], and get their SNs
                    [~, idx_neigh] = pdist2(vers_nonZeroSN, i_ver_zeroSN, 'euclidean', 'Smallest', n_neigh);
                    SN_nonZero_neigh = SN_nonZero(idx_neigh, :);
                    % use the mean SN of neighbors as the new estimated SN for this voxel
                    meanSN_neigh = mean(SN_nonZero_neigh, 1);
                    SN_eval(i, :) = meanSN_neigh ./ norm(meanSN_neigh);
                end
            elseif isempty(gcp("nocreate"))
                % (same code with parallel computing)
                parpool;
                parfor i = 1:size(idx_zeroSN, 1)
                    % get the i-th voxel whose estimated SN is [0,0,0]
                    i_ver_zeroSN = vers_inter(idx_zeroSN(i), :);
                    % find neighbors whose estimated normal vectors are not [0,0,0], and get their SNs
                    [~, idx_neigh] = pdist2(vers_nonZeroSN, i_ver_zeroSN, 'euclidean', 'Smallest', n_neigh);
                    SN_nonZero_neigh = SN_nonZero(idx_neigh, :);
                    % use the mean SN of neighbors as the new estimated SN for this voxel
                    meanSN_neigh = mean(SN_nonZero_neigh, 1);
                    SN_eval(i, :) = meanSN_neigh ./ norm(meanSN_neigh);
                end
            else
                % (same code with parallel computing)
                parfor i = 1:size(idx_zeroSN, 1)
                    % get the i-th voxel whose estimated SN is [0,0,0]
                    i_ver_zeroSN = vers_inter(idx_zeroSN(i), :);
                    % find neighbors whose estimated normal vectors are not [0,0,0], and get their SNs
                    [~, idx_neigh] = pdist2(vers_nonZeroSN, i_ver_zeroSN, 'euclidean', 'Smallest', n_neigh);
                    SN_nonZero_neigh = SN_nonZero(idx_neigh, :);
                    % use the mean SN of neighbors as the new estimated SN for this voxel
                    meanSN_neigh = mean(SN_nonZero_neigh, 1);
                    SN_eval(i, :) = meanSN_neigh ./ norm(meanSN_neigh);
                end
            end

            % replace zero SN with estimated SN
            SN_out(idx_zeroSN, :) = SN_eval;

        end

        function SN_out = cal_smoothSN(SN_in, vers_inter, n_neigh)
            % ==============================================================================
            % FUNCTION:
            %     Spatial smoothing of SNs.
            %
            % INPUT:
            %     - SN_in: (nv, 3), surface normal
            %     - vers_inter: (nv, 3), voxel coordinates
            %     - n_neigh: number of neighbors for spatial smoothing of SN
            %
            % OUTPUT:
            %      - SN_out: (nv, 3), smoothed SNs
            % ------------------------------------------------------------------------------
            % Matlab Version: 2019b or later
            %
            % Last updated on: 11-May-2022
            %
            % Author:
            % Yongcheng YAO (yao_yongcheng@link.cuhk.edu.hk)
            % Department of Imaging and Interventional Radiology,
            % Chinese University of Hong Kong (CUHK)
            %
            % Copyright 2020 Yongcheng YAO
            % ------------------------------------------------------------------------------
            % ==============================================================================

            % smoothed surface normal
            SN_out = zeros(size(SN_in));

            % [enable parallel computing if available]
            if ~license('test', 'Distrib_Computing_Toolbox')
                % (without parallel computing)
                for i = 1:size(vers_inter, 1)
                    % find the i-th voxel
                    i_coor = vers_inter(i, :);
                    % find neighbors of the i-th voxel and their SN
                    [~, idx_neigh] = pdist2(vers_inter, i_coor, 'euclidean', 'Smallest', n_neigh);
                    SN_neigh = SN_in(idx_neigh, :);
                    % calculate the mean SN as the smoothed SN for this voxel
                    mean_SN = mean(SN_neigh, 1);
                    SN_out(i, :) = mean_SN ./ norm(mean_SN);
                end
            elseif isempty(gcp("nocreate"))
                % (same code with parallel computing)
                parpool;
                parfor i = 1:size(vers_inter, 1)
                    % find the i-th voxel
                    i_coor = vers_inter(i, :);
                    % find neighbors of the i-th voxel and their SN
                    [~, idx_neigh] = pdist2(vers_inter, i_coor, 'euclidean', 'Smallest', n_neigh);
                    SN_neigh = SN_in(idx_neigh, :);
                    % calculate the mean SN as the smoothed SN for this voxel
                    mean_SN = mean(SN_neigh, 1);
                    SN_out(i, :) = mean_SN ./ norm(mean_SN);
                end
            else
                % (same code with parallel computing)
                parfor i = 1:size(vers_inter, 1)
                    % find the i-th voxel
                    i_coor = vers_inter(i, :);
                    % find neighbors of the i-th voxel and their SN
                    [~, idx_neigh] = pdist2(vers_inter, i_coor, 'euclidean', 'Smallest', n_neigh);
                    SN_neigh = SN_in(idx_neigh, :);
                    % calculate the mean SN as the smoothed SN for this voxel
                    mean_SN = mean(SN_neigh, 1);
                    SN_out(i, :) = mean_SN ./ norm(mean_SN);
                end
            end

        end


        function ThicknessMap = cal_thicknessMap_SN(vers_inter, SN, FV_outer, depth)
            % ==============================================================================
            % FUNCTION:
            %     SN-based thickness measurement.
            %
            % INPUT:
            %     - vers_inter: (nv, 3), voxel coordinates
            %     - SN: (nv, 3), surface normals
            %     - FV_outer: [structure]
            %          - FV_outer.vertices: the vertices on the outer boundary <<must be the coordinates of voxels>>
            %          - FV_outer.faces: the faces on the outer boundary
            %     - depth: the measuring depth
            %
            % OUTPUT:
            %      - ThicknessMap
            %
            % External functions/toolbox:
            %     - https://www.mathworks.com/matlabcentral/fileexchange/33073-triangle-ray-intersection
            % ------------------------------------------------------------------------------
            % Version:
            % Matlab Version: 2019b or later
            %
            % Last updated on: 11-May-2022
            %
            % Author:
            % Yongcheng YAO (yao_yongcheng@link.cuhk.edu.hk)
            % Department of Imaging and Interventional Radiology,
            % Chinese University of Hong Kong (CUHK)
            %
            % Copyright 2020 Yongcheng YAO
            % ------------------------------------------------------------------------------
            % ==============================================================================

            % find 3 points for each triangular face
            faces_outer = FV_outer.faces;
            vers_outer = FV_outer.vertices;
            p1 = vers_outer(faces_outer(:,1),:);
            p2 = vers_outer(faces_outer(:,2),:);
            p3 = vers_outer(faces_outer(:,3),:);

            % SN based thickness measurement
            ThicknessMap = cat(2, vers_inter, zeros(size(SN, 1), 1));

            % [enable parallel computing if available]
            if ~license('test', 'Distrib_Computing_Toolbox')
                % (without parallel computing)
                for i=1:size(SN,1)
                    i_SN = SN(i, :);
                    i_ver = vers_inter(i, :);
                    [idx_intersect, ~, ~, ~, xcoor] = CartiMorphToolbox.TriangleRayIntersection(i_ver, i_SN, p1, p2, p3, 'border', 'inclusive');
                    intersections = unique(xcoor(idx_intersect, :), 'rows');
                    if size(intersections,1)>0
                        if size(intersections,1)==1
                            distance = norm(i_ver - intersections);
                        else
                            % find the nearest intersection in the positive direction
                            [~, idx_dest] = pdist2(intersections, i_ver, 'euclidean', 'Smallest', 1);
                            destination = intersections(idx_dest, :);
                            % calculate the thickness as the distance between i-th voxel and destination
                            distance = norm(i_ver - destination);
                        end
                        if distance < depth
                            ThicknessMap(i, 4) = distance;
                        end
                    end
                end
            elseif isempty(gcp("nocreate"))
                % (same code with parallel computing)
                parpool;
                parfor i=1:size(SN,1)
                    i_SN = SN(i, :);
                    i_ver = vers_inter(i, :);
                    [idx_intersect, ~, ~, ~, xcoor] = CartiMorphToolbox.TriangleRayIntersection(i_ver, i_SN, p1, p2, p3, 'border', 'inclusive');
                    intersections = unique(xcoor(idx_intersect, :), 'rows');
                    if size(intersections,1)>0
                        if size(intersections,1)==1
                            distance = norm(i_ver - intersections);
                        else
                            % find the nearest intersection in the positive direction
                            [~, idx_dest] = pdist2(intersections, i_ver, 'euclidean', 'Smallest', 1);
                            destination = intersections(idx_dest, :);
                            % calculate the thickness as the distance between i-th voxel and destination
                            distance = norm(i_ver - destination);
                        end
                        if distance < depth
                            ThicknessMap(i, 4) = distance;
                        end
                    end
                end
            else
                % (same code with parallel computing)
                parfor i=1:size(SN,1)
                    i_SN = SN(i, :);
                    i_ver = vers_inter(i, :);
                    [idx_intersect, ~, ~, ~, xcoor] = CartiMorphToolbox.TriangleRayIntersection(i_ver, i_SN, p1, p2, p3, 'border', 'inclusive');
                    intersections = unique(xcoor(idx_intersect, :), 'rows');
                    if size(intersections,1)>0
                        if size(intersections,1)==1
                            distance = norm(i_ver - intersections);
                        else
                            % find the nearest intersection in the positive direction
                            [~, idx_dest] = pdist2(intersections, i_ver, 'euclidean', 'Smallest', 1);
                            destination = intersections(idx_dest, :);
                            % calculate the thickness as the distance between i-th voxel and destination
                            distance = norm(i_ver - destination);
                        end
                        if distance < depth
                            ThicknessMap(i, 4) = distance;
                        end
                    end
                end
            end

        end


        function thickness_out = cal_approximateThickness(thickness_in, vers_inter, n_neigh)
            % ==============================================================================
            % FUNCTION:
            %     Thickness approximation.
            %
            % INPUT:
            %     - thickness_in: (nv, 1), thickness values
            %     - vers_inter: (nv, 3), voxel coordinates on the bone-cartilage interface
            %     - n_neigh: number of neighbors whose estimated thicknesses are non-zero
            %
            % OUTPUT:
            %      - thickness_out: output thickness values
            % ------------------------------------------------------------------------------
            % Matlab Version: 2019b or later
            %
            % Last updated on: 11-May-2022
            %
            % Author:
            % Yongcheng YAO (yao_yongcheng@link.cuhk.edu.hk)
            % Department of Imaging and Interventional Radiology,
            % Chinese University of Hong Kong (CUHK)
            %
            % Copyright 2020 Yongcheng YAO
            % ------------------------------------------------------------------------------
            % ==============================================================================

            % get all non-zero thickness
            thickness_nonZero = thickness_in(thickness_in~=0, :);

            % get coordinates of voxels with non-zero estimated thickness
            vers_nonZeroThickness = vers_inter(thickness_in~=0, :);

            % thickness approximation
            thickness_out = thickness_in;
            idx_zeroThickness = find(thickness_in==0);
            thickness_eval = thickness_in(idx_zeroThickness, 1);
            vers_eval = vers_inter(idx_zeroThickness, :);

            % [enable parallel computing if available]
            if ~license('test', 'Distrib_Computing_Toolbox')
                % (without parallel computing)
                for i = 1:size(idx_zeroThickness, 1)
                    % get the i-th voxel whose estimated thickness is 0
                    i_ver = vers_eval(i, :);
                    % find neighbors whose estimated thickness are not 0, and get their values
                    [~, idx_neigh] = pdist2(vers_nonZeroThickness, i_ver, 'euclidean', 'Smallest', n_neigh);
                    thickness_nonZero_neigh = thickness_nonZero(idx_neigh, :);
                    % use the mean value of neighbors as the new estimated thickness for this voxel
                    thickness_eval(i, :) = mean(thickness_nonZero_neigh, 1);
                end
            elseif isempty(gcp("nocreate"))
                % (same code with parallel computing)
                parpool;
                parfor i = 1:size(idx_zeroThickness, 1)
                    % get the i-th voxel whose estimated thickness is 0
                    i_ver = vers_eval(i, :);
                    % find neighbors whose estimated thickness are not 0, and get their values
                    [~, idx_neigh] = pdist2(vers_nonZeroThickness, i_ver, 'euclidean', 'Smallest', n_neigh);
                    thickness_nonZero_neigh = thickness_nonZero(idx_neigh, :);
                    % use the mean value of neighbors as the new estimated thickness for this voxel
                    thickness_eval(i, :) = mean(thickness_nonZero_neigh, 1);
                end
            else
                % (same code with parallel computing)
                parfor i = 1:size(idx_zeroThickness, 1)
                    % get the i-th voxel whose estimated thickness is 0
                    i_ver = vers_eval(i, :);
                    % find neighbors whose estimated thickness are not 0, and get their values
                    [~, idx_neigh] = pdist2(vers_nonZeroThickness, i_ver, 'euclidean', 'Smallest', n_neigh);
                    thickness_nonZero_neigh = thickness_nonZero(idx_neigh, :);
                    % use the mean value of neighbors as the new estimated thickness for this voxel
                    thickness_eval(i, :) = mean(thickness_nonZero_neigh, 1);
                end
            end

            % replace zero thickness with estimated thickness
            thickness_out(idx_zeroThickness, 1) = thickness_eval;

        end


        function UncertaintyMap = cal_uncertainty(SN, vers, size_voxel, model)
            % ==============================================================================
            % FUNCTION:
            %     Calculate the uncertainty in thickness measurement using voxel size and the estimated surface normal.
            %
            % INPUT:
            %     - SN: [double], (nv, 3), estimated surface normals for voxels
            %     - vers: [double], (nv, 3), coordinates of voxels
            %     - size_voxel: [double], voxel size
            %     - model: [char] model type, must be {'cube', 'ellipsoid'}
            %
            % OUTPUT:
            %     - UncertaintyMap: [double], (n, 4)
            %           - column 1-3: the coordinate of voxels
            %           - column 4: the estimated uncertainty
            %
            % External function/toolbox:
            %     - Mesh Processing Toolbox
            % ------------------------------------------------------------------------------
            % Matlab Version: 2019b or later
            %
            % Last updated on: 11-May-2022
            %
            % Author:
            % Yongcheng YAO (yao_yongcheng@link.cuhk.edu.hk)
            % Department of Imaging and Interventional Radiology,
            % Chinese University of Hong Kong (CUHK)
            %
            % Copyright 2020 Yongcheng YAO
            % ------------------------------------------------------------------------------
            % ==============================================================================

            % model
            switch model
                case 'cube'
                    FV_model = CartiMorphToolbox.create_cube_mesh(size_voxel);  % Mesh Processing Toolbox
                case 'ellipsoid'
                    FV_model = CartiMorphToolbox.create_ellipsoid_mesh(size_voxel); % Mesh Processing Toolbox
            end

            % uncertainty estimation based on the selected model
            UncertaintyMap = CartiMorphToolbox.uncertaintyEstimation(FV_model, SN, vers);

        end


        function UncertaintyMap = uncertaintyEstimation(FV_model, SN, vers)

            UncertaintyMap = cat(2, vers, zeros(size(SN, 1), 1));
            vers_model = FV_model.vertices;
            faces_model = FV_model.faces;

            % [enable parallel computing if available]
            if ~license('test', 'Distrib_Computing_Toolbox')
                % (without parallel computing)
                for i=1:size(vers, 1)
                    % center the model mesh at the i-th voxel
                    i_ver = vers(i, :);
                    vers_model_trans = vers_model + i_ver;
                    faces_trans = faces_model;
                    % find intersection
                    p1 = vers_model_trans(faces_trans(:,1),:);
                    p2 = vers_model_trans(faces_trans(:,2),:);
                    p3 = vers_model_trans(faces_trans(:,3),:);
                    i_SN = SN(i, :);
                    [idx_intersect, ~, ~, ~, xcoor] = CartiMorphToolbox.TriangleRayIntersection(i_ver, i_SN, p1, p2, p3, 'border', 'inclusive');
                    intersections = unique(xcoor(idx_intersect, :), 'rows');
                    if size(intersections,1)>0
                        if size(intersections,1)==1
                            % calculate uncertainty
                            UncertaintyMap(i, 4) = norm(intersections - i_ver);
                        else
                            % find the nearest intersection in the positive direction
                            [UncertaintyMap(i, 4), ~] = pdist2(intersections, i_ver, 'euclidean', 'Smallest', 1);
                        end
                    end
                end
            elseif isempty(gcp("nocreate"))
                % (same code with parallel computing)
                parpool;
                parfor i=1:size(vers, 1)
                    % center the model mesh at the i-th voxel
                    i_ver = vers(i, :);
                    vers_model_trans = vers_model + i_ver;
                    faces_trans = faces_model;
                    % find intersection
                    p1 = vers_model_trans(faces_trans(:,1),:);
                    p2 = vers_model_trans(faces_trans(:,2),:);
                    p3 = vers_model_trans(faces_trans(:,3),:);
                    i_SN = SN(i, :);
                    [idx_intersect, ~, ~, ~, xcoor] = CartiMorphToolbox.TriangleRayIntersection(i_ver, i_SN, p1, p2, p3, 'border', 'inclusive');
                    intersections = unique(xcoor(idx_intersect, :), 'rows');
                    if size(intersections,1)>0
                        if size(intersections,1)==1
                            % calculate uncertainty
                            UncertaintyMap(i, 4) = norm(intersections - i_ver);
                        else
                            % find the nearest intersection in the positive direction
                            [UncertaintyMap(i, 4), ~] = pdist2(intersections, i_ver, 'euclidean', 'Smallest', 1);
                        end
                    end
                end
            else
                % (same code with parallel computing)
                parfor i=1:size(vers, 1)
                    % center the model mesh at the i-th voxel
                    i_ver = vers(i, :);
                    vers_model_trans = vers_model + i_ver;
                    faces_trans = faces_model;
                    % find intersection
                    p1 = vers_model_trans(faces_trans(:,1),:);
                    p2 = vers_model_trans(faces_trans(:,2),:);
                    p3 = vers_model_trans(faces_trans(:,3),:);
                    i_SN = SN(i, :);
                    [idx_intersect, ~, ~, ~, xcoor] = CartiMorphToolbox.TriangleRayIntersection(i_ver, i_SN, p1, p2, p3, 'border', 'inclusive');
                    intersections = unique(xcoor(idx_intersect, :), 'rows');
                    if size(intersections,1)>0
                        if size(intersections,1)==1
                            % calculate uncertainty
                            UncertaintyMap(i, 4) = norm(intersections - i_ver);
                        else
                            % find the nearest intersection in the positive direction
                            [UncertaintyMap(i, 4), ~] = pdist2(intersections, i_ver, 'euclidean', 'Smallest', 1);
                        end
                    end
                end
            end

        end


        function FV_cube = create_cube_mesh(size_voxel)

            % create unit cube (matGeom)
            [vers_cube, faces_cube] = CartiMorphToolbox.createCube();

            % convert to triangular mesh
            FV_cube.faces = cat(1, faces_cube(:, 1:3), faces_cube(:, [1,3,4]) );

            % scale and center the unit cube
            FV_cube.vertices = vers_cube .* size_voxel - (size_voxel/2);

        end


        function FV_ellipsoid = create_ellipsoid_mesh(size_voxel)

            % create ellipsoid mesh
            elli = [0,0,0,size_voxel,0,0,0];
            [vers_ellipsoid, faces_ellipsoid] = CartiMorphToolbox.ellipsoidMesh(elli); % Mesh Processing Toolbox

            % convert to triangular mesh (no mannual translation to the original needed)
            FV_ellipsoid.faces = cat(1, faces_ellipsoid(:, 1:3), faces_ellipsoid(:, [1,3,4]) );
            FV_ellipsoid.vertices = vers_ellipsoid;

        end


        function sMap = cal_smoothThicknessMap(Map, coor_inter, n_neigh)
            % ===============================================================
            % FUNCTION:
            %     Spatial smoothing of thickness map.
            %
            % INPUT:
            %     - Map: thickness map
            %     - coor_inter: (nv, 3), voxel coordinates
            %     - n_neigh: number of neighbors for spatial smoothing of thickness map
            %
            % OUTPUT:
            %      - sMap: smoothed thickness map
            %
            % ------------------------------------------------------------------------------------------
            % Version:
            % Matlab Version: 2019b or later
            %
            % Last updated on: 30-Sep-2021
            %
            % Author:
            % Yongcheng YAO (yao_yongcheng@link.cuhk.edu.hk)
            % Department of Imaging and Interventional Radiology,
            % Chinese University of Hong Kong (CUHK)
            %
            % Copyright 2020 Yongcheng YAO
            % ------------------------------------------------------------------------------------------
            % ===============================================================


            sMap = zeros(size(Map));

            % [enable parallel computing if available]
            if ~license('test', 'Distrib_Computing_Toolbox')
                % (without parallel computing)
                for i = 1:size(Map,1)
                    % find the i-th voxel
                    i_coor = coor_inter(i, :);
                    % find neighbors of the i-th voxel and their normal vectors
                    [~, idx_neigh] = pdist2(coor_inter, i_coor, 'euclidean', 'Smallest', n_neigh);
                    % spatial smoothing of ThicknessMap
                    thickness_neigh = Map(idx_neigh, 4);
                    % calculate the mean thickness of the neighbors as the new estimated thickness
                    mean_thickness_neigh = mean(thickness_neigh, 1);
                    sMap(i, :) = [i_coor, mean_thickness_neigh];
                end
            elseif isempty(gcp("nocreate"))
                % (same code with parallel computing)
                parpool;
                parfor i = 1:size(Map,1)
                    % find the i-th voxel
                    i_coor = coor_inter(i, :);
                    % find neighbors of the i-th voxel and their normal vectors
                    [~, idx_neigh] = pdist2(coor_inter, i_coor, 'euclidean', 'Smallest', n_neigh);
                    % spatial smoothing of ThicknessMap
                    thickness_neigh = Map(idx_neigh, 4);
                    % calculate the mean thickness of the neighbors as the new estimated thickness
                    mean_thickness_neigh = mean(thickness_neigh, 1);
                    sMap(i, :) = [i_coor, mean_thickness_neigh];
                end
            else
                % (same code with parallel computing)
                parfor i = 1:size(Map,1)
                    % find the i-th voxel
                    i_coor = coor_inter(i, :);
                    % find neighbors of the i-th voxel and their normal vectors
                    [~, idx_neigh] = pdist2(coor_inter, i_coor, 'euclidean', 'Smallest', n_neigh);
                    % spatial smoothing of ThicknessMap
                    thickness_neigh = Map(idx_neigh, 4);
                    % calculate the mean thickness of the neighbors as the new estimated thickness
                    mean_thickness_neigh = mean(thickness_neigh, 1);
                    sMap(i, :) = [i_coor, mean_thickness_neigh];
                end
            end

        end


        function plot_thicknessMap_wBone(...
                Mesh_C,...
                Mesh_B,...
                Mesh_iC,...
                thicknessMap,...
                fig_title,...
                file_path)
            % ==============================================================================
            % FUNCTION:
            %     Thickness map visualization.
            %
            % INPUT:
            %     - Mesh_C: [structure]
            %         - Mesh_C.faces: size:(nf, 3), the faces on the cartilage surface
            %         - Mesh_C.vertices: size: (nv, 3), the vertices on the cartilage surface
            %     - Mesh_B: [structure]
            %         - Mesh_B.faces: size:(nf, 3), the faces on the bone surface
            %         - Mesh_B.vertices: size: (nv, 3), the vertices on the bone surface
            %     - Mesh_iC: [structure]
            %         - Mesh_iC.faces: size:(nf, 3), the faces on the bone-cartilage interface
            %         - Mesh_iC.vertices: size: (nv, 3), the vertices on the bone-cartilage interface
            %     - thicknessMap: size=(nv, 4), thickness map
            %     - fig_title: [char] figure title
            %     - file_path: [char] the path of .fig file
            % ------------------------------------------------------------------------------
            % Matlab Version: 2019b or later
            %
            % Last updated on: 12-May-2022
            %
            % Author:
            % Yongcheng YAO (yao_yongcheng@link.cuhk.edu.hk)
            % Department of Imaging and Interventional Radiology,
            % Chinese University of Hong Kong (CUHK)
            %
            % Copyright 2020 Yongcheng YAO
            % ------------------------------------------------------------------------------
            % ==============================================================================

            %% Prepare
            faces_C = Mesh_C.faces;
            vers_C = Mesh_C.vertices;
            faces_B = Mesh_B.faces;
            vers_B = Mesh_B.vertices;
            faces_iC = Mesh_iC.faces;
            vers_iC = Mesh_iC.vertices;
            thickness = thicknessMap(:,4);
            cmax = 0.5 * ceil(max(thickness./0.5));


            %% [Figure 1] only thickness map
            f = figure('visible', 'off');
            % thickness map
            patch('Faces', faces_iC, 'Vertices', vers_iC, 'FaceVertexCData', thickness,...
                'FaceColor','interp','EdgeColor','none');
            % color bar
            colormap(jet);
            caxis([0, cmax])
            colorbar;
            % edit axis
            axis equal
            xlabel('L-R');
            ylabel('P-A');
            zlabel('I-S');
            % change view
            view(3);
            grid off;
            % add title
            title(fig_title, 'Interpreter','none');
            % save figure
            path_f = append(file_path, '_v01.fig');
            savefig(f, path_f, 'compact');
            delete(f)


            %% Figure 2: thickness map with the mesh edges of cartilage
            f = figure('visible', 'off');
            % thickness map
            patch('Faces', faces_iC, 'Vertices', vers_iC, 'FaceVertexCData', thickness,...
                'FaceColor','interp','EdgeColor','none');
            hold on
            % mesh edges of cartilage
            trisurf(faces_C, vers_C(:,1), vers_C(:,2), vers_C(:,3),...
                'FaceColor', 'none',...
                'EdgeColor', 'b', 'EdgeAlpha', 0.1)
            % color bar
            colormap(jet);
            caxis([0, cmax])
            colorbar;
            % edit axis
            axis equal
            xlabel('L-R');
            ylabel('P-A');
            zlabel('I-S');
            % change view
            view(3);
            grid off;
            % add title
            title(fig_title, 'Interpreter','none');
            hold off
            % save figure
            path_f = append(file_path, '_v02.fig');
            savefig(f, path_f, 'compact');
            delete(f)


            %% Figure 3: thickness map with the mesh faces of cartilage
            f = figure('visible', 'off');
            % thickness map
            patch('Faces', faces_iC, 'Vertices', vers_iC, 'FaceVertexCData', thickness,...
                'FaceColor','interp','EdgeColor','none');
            hold on
            % mesh faces of cartilage
            trisurf(faces_C, vers_C(:,1), vers_C(:,2), vers_C(:,3),...
                'FaceColor', 'b', 'FaceAlpha', 0.1,...
                'EdgeColor', 'none')
            % color bar
            colormap(jet);
            caxis([0, cmax])
            colorbar;
            % edit axis
            axis equal
            xlabel('L-R');
            ylabel('P-A');
            zlabel('I-S');
            % change view
            view(3);
            grid off;
            % add title
            title(fig_title, 'Interpreter','none');
            hold off
            % save figure
            path_f = append(file_path, '_v03.fig');
            savefig(f, path_f, 'compact');
            delete(f)


            %% Figure 4: thickness map with the mesh edges of bone
            % f = figure('visible', 'off');
            % % thickness map
            % patch('Faces', faces_iC, 'Vertices', vers_iC, 'FaceVertexCData', thickness,...
            %     'FaceColor','interp','EdgeColor','none');
            % hold on
            % % mesh edges of bone
            % trisurf(faces_B, vers_B(:,1), vers_B(:,2), vers_B(:,3),...
            %     'FaceColor', 'none',...
            %     'EdgeColor', 'b', 'EdgeAlpha', 0.1)
            % % color bar
            % colormap(jet);
            % caxis([0, cmax])
            % colorbar;
            % % edit axis
            % axis equal
            % xlabel('L-R');
            % ylabel('P-A');
            % zlabel('I-S');
            % % change view
            % view(3);
            % grid off;
            % % add title
            % title(fig_title, 'Interpreter','none');
            % hold off
            % % save figure
            % path_f = append(file_path, '_v04.fig');
            % savefig(f, path_f, 'compact');
            % delete(f)


            %% Figure 5: thickness map with the mesh faces of bone
            f = figure('visible', 'off');
            % thickness map
            patch('Faces', faces_iC, 'Vertices', vers_iC, 'FaceVertexCData', thickness,...
                'FaceColor','interp','EdgeColor','none');
            hold on
            % mesh faces of bone
            trisurf(faces_B, vers_B(:,1), vers_B(:,2), vers_B(:,3),...
                'FaceColor', 'b', 'FaceAlpha', 0.1,...
                'EdgeColor', 'none')
            % color bar
            colormap(jet);
            caxis([0, cmax])
            colorbar;
            % edit axis
            axis equal
            xlabel('L-R');
            ylabel('P-A');
            zlabel('I-S');
            % change view
            view(3);
            grid off;
            % add title
            title(fig_title, 'Interpreter','none');
            hold off
            % save figure
            path_f = append(file_path, '_v05.fig');
            savefig(f, path_f, 'compact');
            delete(f)


            %% Figure 6: thickness map with the mesh faces of bone and mesh edges of cartilage
            f = figure('visible', 'off');
            % thickness map
            patch('Faces', faces_iC, 'Vertices', vers_iC, 'FaceVertexCData', thickness,...
                'FaceColor','interp','EdgeColor','none');
            hold on
            % mesh faces of bone
            trisurf(faces_B, vers_B(:,1), vers_B(:,2), vers_B(:,3),...
                'FaceColor', 'b', 'FaceAlpha', 0.1,...
                'EdgeColor', 'none')
            % mesh edges of cartilage
            trisurf(faces_C, vers_C(:,1), vers_C(:,2), vers_C(:,3),...
                'FaceColor', 'none',...
                'EdgeColor', 'b', 'EdgeAlpha', 0.1)
            % color bar
            colormap(jet);
            caxis([0, cmax])
            colorbar;
            % edit axis
            axis equal
            xlabel('L-R');
            ylabel('P-A');
            zlabel('I-S');
            % change view
            view(3);
            grid off;
            % add title
            title(fig_title, 'Interpreter','none');
            hold off
            % save figure
            path_f = append(file_path, '_v06.fig');
            savefig(f, path_f, 'compact');
            delete(f)


            %% Figure 7: thickness map with the mesh faces of bone and cartilage
            % ------------------------------------------------------------------------------------------
            f = figure('visible', 'off');
            % thickness map
            patch('Faces', faces_iC, 'Vertices', vers_iC, 'FaceVertexCData', thickness,...
                'FaceColor','interp','EdgeColor','none');
            hold on
            % mesh faces of bone
            trisurf(faces_B, vers_B(:,1), vers_B(:,2), vers_B(:,3),...
                'FaceColor', 'b', 'FaceAlpha', 0.1,...
                'EdgeColor', 'none')
            % mesh faces of cartilage
            trisurf(faces_C, vers_C(:,1), vers_C(:,2), vers_C(:,3),...
                'FaceColor', 'b', 'FaceAlpha', 0.1,...
                'EdgeColor', 'none')
            % color bar
            colormap(jet);
            caxis([0, cmax])
            colorbar;
            % edit axis
            axis equal
            xlabel('L-R');
            ylabel('P-A');
            zlabel('I-S');
            % change view
            view(3);
            grid off;
            % add title
            title(fig_title, 'Interpreter','none');
            hold off
            % save figure
            path_f = append(file_path, '_v07.fig');
            savefig(f, path_f, 'compact');
            delete(f)


            %% Figure 8: thickness map with the mesh edges of bone and cartilage
            % f = figure('visible', 'off');
            % % thickness map
            % patch('Faces', faces_iC, 'Vertices', vers_iC, 'FaceVertexCData', thickness,...
            %     'FaceColor','interp','EdgeColor','none');
            % hold on
            % % mesh edges of bone
            % trisurf(faces_B, vers_B(:,1), vers_B(:,2), vers_B(:,3),...
            %     'FaceColor', 'none',...
            %     'EdgeColor', 'b', 'EdgeAlpha', 0.1)
            % % mesh edges of cartilage
            % trisurf(faces_C, vers_C(:,1), vers_C(:,2), vers_C(:,3),...
            %     'FaceColor', 'none',...
            %     'EdgeColor', 'b', 'EdgeAlpha', 0.1)
            % % color bar
            % colormap(jet);
            % caxis([0, cmax])
            % colorbar;
            % % edit axis
            % axis equal
            % xlabel('L-R');
            % ylabel('P-A');
            % zlabel('I-S');
            % % change view
            % view(3);
            % grid off;
            % % add title
            % title(fig_title, 'Interpreter','none');
            % hold off
            % % save figure
            % path_f = append(file_path, '_v08.fig');
            % savefig(f, path_f, 'compact');
            % delete(f)


            %% Figure 9: thickness map with the mesh edges of bone and mesh faces of cartilage
            % f = figure('visible', 'off');
            % % thickness map
            % patch('Faces', faces_iC, 'Vertices', vers_iC, 'FaceVertexCData', thickness,...
            %     'FaceColor','interp','EdgeColor','none');
            % hold on
            % % mesh edges of bone
            % trisurf(faces_B, vers_B(:,1), vers_B(:,2), vers_B(:,3),...
            %     'FaceColor', 'none',...
            %     'EdgeColor', 'b', 'EdgeAlpha', 0.1)
            % % mesh faces of cartilage
            % trisurf(faces_C, vers_C(:,1), vers_C(:,2), vers_C(:,3),...
            %     'FaceColor', 'b', 'FaceAlpha', 0.1,...
            %     'EdgeColor', 'none')
            % % color bar
            % colormap(jet);
            % caxis([0, cmax])
            % colorbar;
            % % edit axis
            % axis equal
            % xlabel('L-R');
            % ylabel('P-A');
            % zlabel('I-S');
            % % change view
            % view(3);
            % grid off;
            % % add title
            % title(fig_title, 'Interpreter','none');
            % hold off
            % % save figure
            % path_f = append(file_path, '_v09.fig');
            % savefig(f, path_f, 'compact');
            % delete(f)


        end


        function plot_integrated_thicknessMap_wBone(...
                Mesh_Femur,...
                Mesh_Tibia,...
                Mesh_FC,...
                Mesh_lTC,...
                Mesh_mTC,...
                Mesh_iC_FC,...
                Mesh_iC_lTC,...
                Mesh_iC_mTC,...
                sMap_FC,...
                sMap_lTC,...
                sMap_mTC,...
                fig_title,...
                save_path)
            % ==============================================================================
            % FUNCTION:
            %       Thickness maps visualization for all cartilages.
            %
            % INPUT:
            %       -
            %
            % OUTPUT:
            %       -
            % ------------------------------------------------------------------------------
            % Matlab Version: 2019b or later
            %
            % Last updated on: 15-May-2022
            %
            % Author:
            % Yongcheng YAO (yao_yongcheng@link.cuhk.edu.hk)
            % Department of Imaging and Interventional Radiology,
            % Chinese University of Hong Kong (CUHK)
            %
            % Copyright 2020 Yongcheng YAO
            % ------------------------------------------------------------------------------
            % ==============================================================================

            %% Figure v15: all thickness maps & mesh faces of bones and cartilages
            % ------------------------------------------------------------------------------------------
            f = figure('visible', 'off');

            % <<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<
            % [!] DO NOT change the order of the following lines, this will produce errors in CartiMorph Viewer
            % thickness map for FC
            if ~isempty(Mesh_iC_FC)
                patch('Faces', Mesh_iC_FC.faces,...
                    'Vertices', Mesh_iC_FC.vertices,...
                    'FaceVertexCData', sMap_FC(:,4),...
                    'FaceColor','interp','EdgeColor','none');
            end
            hold on
            % thickness map for lTC
            if ~isempty(Mesh_iC_lTC)
                patch('Faces', Mesh_iC_lTC.faces,...
                    'Vertices', Mesh_iC_lTC.vertices,...
                    'FaceVertexCData', sMap_lTC(:,4),...
                    'FaceColor','interp','EdgeColor','none');
            end
            % thickness map for mTC
            if ~isempty(Mesh_iC_mTC)
                patch('Faces', Mesh_iC_mTC.faces,...
                    'Vertices', Mesh_iC_mTC.vertices,...
                    'FaceVertexCData', sMap_mTC(:,4),...
                    'FaceColor','interp','EdgeColor','none');
            end
            % mesh faces for Femur
            if ~isempty(Mesh_Femur)
                trisurf(Mesh_Femur.faces,...
                    Mesh_Femur.vertices(:,1), Mesh_Femur.vertices(:,2), Mesh_Femur.vertices(:,3),...
                    'FaceColor', [0 0 1], 'FaceAlpha', 0.1,...
                    'EdgeColor', 'none');
            end
            % mesh faces for Tibia
            if ~isempty(Mesh_Tibia)
                trisurf(Mesh_Tibia.faces,...
                    Mesh_Tibia.vertices(:,1), Mesh_Tibia.vertices(:,2), Mesh_Tibia.vertices(:,3),...
                    'FaceColor', [0 0 1], 'FaceAlpha', 0.1,...
                    'EdgeColor', 'none');
            end
            % mesh faces for FC
            if ~isempty(Mesh_FC)
                trisurf(Mesh_FC.faces,...
                    Mesh_FC.vertices(:,1), Mesh_FC.vertices(:,2), Mesh_FC.vertices(:,3),...
                    'FaceColor', [0 0 1], 'FaceAlpha', 0.1,...
                    'EdgeColor', 'none');
            end
            % mesh faces for lTC
            if ~isempty(Mesh_lTC)
                trisurf(Mesh_lTC.faces,...
                    Mesh_lTC.vertices(:,1), Mesh_lTC.vertices(:,2), Mesh_lTC.vertices(:,3),...
                    'FaceColor', [0 0 1], 'FaceAlpha', 0.1,...
                    'EdgeColor', 'none');
            end
            % mesh faces for mTC
            if ~isempty(Mesh_mTC)
                trisurf(Mesh_mTC.faces,...
                    Mesh_mTC.vertices(:,1), Mesh_mTC.vertices(:,2), Mesh_mTC.vertices(:,3),...
                    'FaceColor', [0 0 1], 'FaceAlpha', 0.1,...
                    'EdgeColor', 'none');
            end
            % <<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<

            % color bar
            colormap(jet);
            thickness = [];
            % Check if each variable is empty before concatenating
            if ~isempty(sMap_FC)
                thickness = [thickness; sMap_FC(:,4)];
            end
            if ~isempty(sMap_lTC)
                thickness = [thickness; sMap_lTC(:,4)];
            end
            if ~isempty(sMap_mTC)
                thickness = [thickness; sMap_mTC(:,4)];
            end
            cmax = 0.5 * ceil(max(thickness./0.5));
            clim(gca, [0, cmax]);
            colorbar;
            % edit axis
            axis equal
            xlabel('L-R');
            ylabel('P-A');
            zlabel('I-S');
            % change to 3D view
            view(3);
            grid off
            hold off
            % add title
            title(fig_title, 'Interpreter','none');
            % save figure into .fig file
            savefig(f, append(save_path, '_v15.fig'), 'compact');
            delete(f)
        end


        function plot_CartSubregions_mesh(mesh_subreg_FC, mesh_subreg_TC, filePath)

            FV_ecLFC = mesh_subreg_FC.ecLFC;
            FV_ccLFC = mesh_subreg_FC.ccLFC;
            FV_icLFC = mesh_subreg_FC.icLFC;
            FV_aLFC = mesh_subreg_FC.aLFC;
            FV_pLFC = mesh_subreg_FC.pLFC;
            FV_ecMFC = mesh_subreg_FC.ecMFC;
            FV_ccMFC = mesh_subreg_FC.ccMFC;
            FV_icMFC = mesh_subreg_FC.icMFC;
            FV_aMFC = mesh_subreg_FC.aMFC;
            FV_pMFC = mesh_subreg_FC.pMFC;
            FV_cMTC = mesh_subreg_TC.cMTC;
            FV_aMTC = mesh_subreg_TC.aMTC;
            FV_pMTC = mesh_subreg_TC.pMTC;
            FV_iMTC = mesh_subreg_TC.iMTC;
            FV_eMTC = mesh_subreg_TC.eMTC;
            FV_cLTC = mesh_subreg_TC.cLTC;
            FV_aLTC = mesh_subreg_TC.aLTC;
            FV_pLTC = mesh_subreg_TC.pLTC;
            FV_iLTC = mesh_subreg_TC.iLTC;
            FV_eLTC = mesh_subreg_TC.eLTC;

            f = figure('visible', 'off');
            hold on

            % <<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<
            % [!] DO NOT change the order of the following lines, this will produce errors in CartiMorph Viewer
            % FC
            if ~isempty(FV_aMFC)
                trisurf(FV_aMFC.faces, FV_aMFC.vertices(:,1), FV_aMFC.vertices(:,2), FV_aMFC.vertices(:,3),...
                    'FaceColor', '#FAD7A0', 'EdgeAlpha', 1, 'EdgeColor', '#FAD7A0');
            end
            if ~isempty(FV_ecMFC)
                trisurf(FV_ecMFC.faces, FV_ecMFC.vertices(:,1), FV_ecMFC.vertices(:,2), FV_ecMFC.vertices(:,3),...
                    'FaceColor', '#F1948A', 'EdgeAlpha', 1, 'EdgeColor', '#F1948A');
            end
            if ~isempty(FV_ccMFC)
                trisurf(FV_ccMFC.faces, FV_ccMFC.vertices(:,1), FV_ccMFC.vertices(:,2), FV_ccMFC.vertices(:,3),...
                    'FaceColor', '#D7BDE2', 'EdgeAlpha', 1, 'EdgeColor', '#D7BDE2');
            end
            if ~isempty(FV_icMFC)
                trisurf(FV_icMFC.faces, FV_icMFC.vertices(:,1), FV_icMFC.vertices(:,2), FV_icMFC.vertices(:,3),...
                    'FaceColor', '#D6EAF8', 'EdgeAlpha', 1, 'EdgeColor', '#D6EAF8');
            end
            if ~isempty(FV_pMFC)
                trisurf(FV_pMFC.faces, FV_pMFC.vertices(:,1), FV_pMFC.vertices(:,2), FV_pMFC.vertices(:,3),...
                    'FaceColor', '#7DCEA0', 'EdgeAlpha', 1, 'EdgeColor', '#7DCEA0');
            end
            if ~isempty(FV_aLFC)
                trisurf(FV_aLFC.faces, FV_aLFC.vertices(:,1), FV_aLFC.vertices(:,2), FV_aLFC.vertices(:,3),...
                    'FaceColor', '#F39C12', 'EdgeAlpha', 1, 'EdgeColor', '#F39C12');
            end
            if ~isempty(FV_ecLFC)
                trisurf(FV_ecLFC.faces, FV_ecLFC.vertices(:,1), FV_ecLFC.vertices(:,2), FV_ecLFC.vertices(:,3),...
                    'FaceColor', '#E74C3C', 'EdgeAlpha', 1, 'EdgeColor', '#E74C3C');
            end
            if ~isempty(FV_ccLFC)
                trisurf(FV_ccLFC.faces, FV_ccLFC.vertices(:,1), FV_ccLFC.vertices(:,2), FV_ccLFC.vertices(:,3),...
                    'FaceColor', '#9B59B6', 'EdgeAlpha', 1, 'EdgeColor', '#9B59B6');
            end
            if ~isempty(FV_icLFC)
                trisurf(FV_icLFC.faces, FV_icLFC.vertices(:,1), FV_icLFC.vertices(:,2), FV_icLFC.vertices(:,3),...
                    'FaceColor', '#3498DB', 'EdgeAlpha', 1, 'EdgeColor', '#3498DB');
            end
            if ~isempty(FV_pLFC)
                trisurf(FV_pLFC.faces, FV_pLFC.vertices(:,1), FV_pLFC.vertices(:,2), FV_pLFC.vertices(:,3),...
                    'FaceColor', '#27AE60', 'EdgeAlpha', 1, 'EdgeColor', '#27AE60');
            end

            % mTC
            if ~isempty(FV_aMTC)
                trisurf(FV_aMTC.faces, FV_aMTC.vertices(:,1), FV_aMTC.vertices(:,2), FV_aMTC.vertices(:,3),...
                    'FaceColor', '#FFDA5E', 'EdgeAlpha', 1, 'EdgeColor', '#FFDA5E');
            end
            if ~isempty(FV_eMTC)
                trisurf(FV_eMTC.faces, FV_eMTC.vertices(:,1), FV_eMTC.vertices(:,2), FV_eMTC.vertices(:,3),...
                    'FaceColor', '#EA6975', 'EdgeAlpha', 1, 'EdgeColor', '#EA6975');
            end
            if ~isempty(FV_pMTC)
                trisurf(FV_pMTC.faces, FV_pMTC.vertices(:,1), FV_pMTC.vertices(:,2), FV_pMTC.vertices(:,3),...
                    'FaceColor', '#57AB89', 'EdgeAlpha', 1, 'EdgeColor', '#57AB89');
            end
            if ~isempty(FV_iMTC)
                trisurf(FV_iMTC.faces, FV_iMTC.vertices(:,1), FV_iMTC.vertices(:,2), FV_iMTC.vertices(:,3),...
                    'FaceColor', '#75AAD6', 'EdgeAlpha', 1, 'EdgeColor', '#75AAD6');
            end
            if ~isempty(FV_cMTC)
                trisurf(FV_cMTC.faces, FV_cMTC.vertices(:,1), FV_cMTC.vertices(:,2), FV_cMTC.vertices(:,3),...
                    'FaceColor', '#F6D3DE', 'EdgeAlpha', 1, 'EdgeColor', '#F6D3DE');
            end

            % lTC
            if ~isempty(FV_aLTC)
                trisurf(FV_aLTC.faces, FV_aLTC.vertices(:,1), FV_aLTC.vertices(:,2), FV_aLTC.vertices(:,3),...
                    'FaceColor', '#FFEB11', 'EdgeAlpha', 1, 'EdgeColor', '#FFEB11');
            end
            if ~isempty(FV_eLTC)
                trisurf(FV_eLTC.faces, FV_eLTC.vertices(:,1), FV_eLTC.vertices(:,2), FV_eLTC.vertices(:,3),...
                    'FaceColor', '#D71729', 'EdgeAlpha', 1, 'EdgeColor', '#D71729');
            end
            if ~isempty(FV_pLTC)
                trisurf(FV_pLTC.faces, FV_pLTC.vertices(:,1), FV_pLTC.vertices(:,2), FV_pLTC.vertices(:,3),...
                    'FaceColor', '#8BE071', 'EdgeAlpha', 1, 'EdgeColor', '#8BE071');
            end
            if ~isempty(FV_iLTC)
                trisurf(FV_iLTC.faces, FV_iLTC.vertices(:,1), FV_iLTC.vertices(:,2), FV_iLTC.vertices(:,3),...
                    'FaceColor', '#1076E2', 'EdgeAlpha', 1, 'EdgeColor', '#1076E2');
            end
            if ~isempty(FV_cLTC)
                trisurf(FV_cLTC.faces, FV_cLTC.vertices(:,1), FV_cLTC.vertices(:,2), FV_cLTC.vertices(:,3),...
                    'FaceColor', '#B08FC7', 'EdgeAlpha', 1, 'EdgeColor', '#B08FC7');
            end
            % <<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<

            % axis
            axis equal
            xlabel('L-R')
            ylabel('P-A')
            zlabel('I-S')
            view(3)
            grid off;
            hold off;
            % save figure
            savefig(f, append(filePath, '.fig'), 'compact');
            delete(f);

        end


        function [versSubReg_iC_FC, versSubReg_scB_FC, meshSubReg_iC_FC, meshSubReg_scB_FC] ...
                = cal_SurfaceParcellation_FC(...
                mesh_iC_FC, ...
                mesh_scB_FC, ...
                size_voxel, ...
                knee_side, ...
                cc_percentage)
            % ==============================================================================
            % FUNCTION:
            %     Cartilage parcellation of FC.
            %
            % INPUT:
            %     - mesh_iC_FC: interior cartilage surface for FC
            %     - mesh_scB_FC: subchondral bone surface for FC
            %     - size_voxel: voxel size
            %     - knee_side: knee side, must be one of {'left', 'Left', 'L', 'right', 'Right', 'R'}
            %     - cc_percentage: [double], the paramter for definition of ccLFC & ccMFC
            %
            % OUTPUT:
            %     - versSubReg_iC_FC: [structure], vertices on the interior cartilage surface of FC
            %     - versSubReg_scB_FC: [structure], vertices on the subchondral bone surface of FC
            %     - meshSubReg_iC_FC: [structure], the interior cartilage surface of FC
            %     - meshSubReg_scB_FC: [structure], the subchondral bone surface of FC
            % ------------------------------------------------------------------------------
            % Matlab Version: 2019b or later
            %
            % Last updated on: 16-May-2022
            %
            % Author:
            % Yongcheng YAO (yao_yongcheng@link.cuhk.edu.hk)
            % Department of Imaging and Interventional Radiology,
            % Chinese University of Hong Kong (CUHK)
            %
            % Copyright 2020 Yongcheng YAO
            % ------------------------------------------------------------------------------
            % ==============================================================================

            if isempty(mesh_iC_FC)
                ROInames_FC =  {'aMFC', 'ecMFC', 'ccMFC', 'icMFC',...
                    'pMFC', 'aLFC', 'ecLFC', 'ccLFC', 'icLFC', 'pLFC'};
                for i=1:length(ROInames_FC)
                    iROI = ROInames_FC{i};
                    versSubReg_iC_FC.(iROI) = [];
                    versSubReg_scB_FC.(iROI) = [];
                    meshSubReg_iC_FC.(iROI) = [];
                    meshSubReg_scB_FC.(iROI) = [];
                end
                return;
            else

                % interior cartilage surface of FC
                faces_iC_FC = mesh_iC_FC.faces;
                vers_iC_FC = mesh_iC_FC.vertices;
                subs_iC_FC = round(vers_iC_FC ./ size_voxel);

                % the subchondral bone surface of FC
                faces_scB_FC = mesh_scB_FC.faces;
                vers_scB_FC = mesh_scB_FC.vertices;
                subs_scB_FC = round(vers_scB_FC ./ size_voxel);


                %% Parcellate subchondral bone area of FC
                % ---------------------------------------------------------
                % Locate the intercondylar notch
                % ---------------------------------------------------------
                % (assuming the second dimension of image array is the P-A direction, i.e., y+ points to A)
                % sagittal slice indices
                subsLR_scB_FC = sort(unique(subs_scB_FC(:,1)), 'ascend');
                numSag = length(subsLR_scB_FC);
                % extract some (50%) center slices
                subsLR_scB_FC_center = subsLR_scB_FC(round(numSag * 0.25):round(numSag * 0.75));
                minSubAP_scB_FC_center = zeros(length(subsLR_scB_FC_center), 1);
                for i = 1:length(subsLR_scB_FC_center)
                    i_subLR_scB_FC_center = subsLR_scB_FC_center(i);
                    minSubAP_scB_FC_center(i,1) = min(subs_scB_FC(subs_scB_FC(:,1)==i_subLR_scB_FC_center, 2));
                end
                subNotchAP_scB_FC_center = max(minSubAP_scB_FC_center);
                candidate = sort(find(minSubAP_scB_FC_center==subNotchAP_scB_FC_center));
                idx = candidate(ceil(length(candidate)/2));
                subNotchLR_scB_FC = subsLR_scB_FC_center(idx);
                % ---------------------------------------------------------


                % ---------------------------------------------------------
                % Locate the central lateral FC (cLFC)
                % ---------------------------------------------------------
                % (1. assuming the first dimension of image array is the L-R direction, i.e., x+ points to R)
                % (2. assuming right knee)
                subs_scB_LFC = subs_scB_FC(subs_scB_FC(:,1)>=subNotchLR_scB_FC, :);
                minSubAP_scB_LFC = min(subs_scB_LFC(:,2));
                minSubAP_scB_cLFC = subNotchAP_scB_FC_center - round((subNotchAP_scB_FC_center - minSubAP_scB_LFC) * cc_percentage);
                idxAP_scB_cLFC = subs_scB_LFC(:,2)<subNotchAP_scB_FC_center & subs_scB_LFC(:,2)>minSubAP_scB_cLFC;
                subs_scB_cLFC = subs_scB_LFC(idxAP_scB_cLFC, :);

                % extract faces
                faces_scB_cLFC = CartiMorphToolbox.cal_extractFaces_OR(faces_scB_FC, subs_scB_FC, subs_scB_cLFC);

                % remove small components (MeshProcessingToolbox)
                [~, components_scB_cLFC] = CartiMorphToolbox.segment_connected_components(faces_scB_cLFC, 'explicit');
                faces_scB_cLFC = CartiMorphToolbox.cal_deleteSmallComponents(components_scB_cLFC, 50);
                [vers_scB_cLFC, ~] = CartiMorphToolbox.remove_unreferenced_vertices(vers_scB_FC, faces_scB_cLFC);
                subs_scB_cLFC = round(vers_scB_cLFC ./ size_voxel);

                subs_partial_scB_pLFC = [];
                subs_partial_scB_aLFC = [];
                faces_smallCC_scB_LFC = CartiMorphToolbox.cal_deleteLargeComponents(components_scB_cLFC, 50);
                if ~isempty(faces_smallCC_scB_LFC)
                    [~, components_smallCC_scB_LFC] = CartiMorphToolbox.segment_connected_components(faces_smallCC_scB_LFC, 'explicit');
                    for ii = 1:size(components_smallCC_scB_LFC,1)
                        faces_smallCC_scB_LFC = cell2mat(components_smallCC_scB_LFC(ii, 1));
                        [vers_smallCC_scB_LFC, ~] = CartiMorphToolbox.remove_unreferenced_vertices(vers_scB_FC, faces_smallCC_scB_LFC);
                        subs_centerSI_smallCC_scB_LFC = round(mean(vers_smallCC_scB_LFC(:,3)) ./ size_voxel(3));
                        subs_centerAP_smallCC_scB_LFC = round(mean(vers_smallCC_scB_LFC(:,2)) ./ size_voxel(2));
                        subs_centerSI_cLFC = round(mean(vers_scB_cLFC(:,3)) ./ size_voxel(3));
                        subsRange_centerSI_sLFC = round((max(vers_scB_cLFC(:,3))-min(vers_scB_cLFC(:,3))) ./ size_voxel(3));
                        if (subs_centerSI_smallCC_scB_LFC - subs_centerSI_cLFC) > subsRange_centerSI_sLFC
                            % the small connected component if part of pLFC
                            subs_partial_scB_pLFC = cat(1, subs_partial_scB_pLFC, round(vers_smallCC_scB_LFC ./ size_voxel));
                        elseif abs(subs_centerAP_smallCC_scB_LFC - subNotchAP_scB_FC_center) <= 2
                            % the small connected component if part of aLFC
                            subs_partial_scB_aLFC = cat(1, subs_partial_scB_aLFC, round(vers_smallCC_scB_LFC ./ size_voxel));
                        else
                            % the small connected component if part of cLFC
                            subs_scB_cLFC = cat(1, subs_scB_cLFC, round(vers_smallCC_scB_LFC ./ size_voxel));
                        end
                    end
                end
                % ---------------------------------------------------------


                % ---------------------------------------------------------
                % Locate the central medial FC (cMFC)
                % ---------------------------------------------------------
                % (1. assuming the first dimension of image array is the L-R direction, i.e., x+ points to R)
                % (2. assuming right knee)
                subs_scB_MFC = subs_scB_FC(subs_scB_FC(:,1)<subNotchLR_scB_FC, :);
                minSubAP_scB_MFC = min(subs_scB_MFC(:,2));
                minSubAP_scB_cMFC = subNotchAP_scB_FC_center - round((subNotchAP_scB_FC_center - minSubAP_scB_MFC) * cc_percentage);
                idxAP_scB_cMFC = subs_scB_MFC(:,2)<subNotchAP_scB_FC_center & subs_scB_MFC(:,2)>minSubAP_scB_cMFC;
                subs_scB_cMFC = subs_scB_MFC(idxAP_scB_cMFC, :);

                % extract faces
                faces_scB_cMFC = CartiMorphToolbox.cal_extractFaces_OR(faces_scB_FC, subs_scB_FC, subs_scB_cMFC);

                % remove small components from the non-interface mesh (MeshProcessingToolbox)
                [~, components_scB_cMFC] = CartiMorphToolbox.segment_connected_components(faces_scB_cMFC, 'explicit');
                faces_scB_cMFC = CartiMorphToolbox.cal_deleteSmallComponents(components_scB_cMFC, 50);
                [vers_scB_cMFC, ~] = CartiMorphToolbox.remove_unreferenced_vertices(vers_scB_FC, faces_scB_cMFC);
                subs_scB_cMFC = round(vers_scB_cMFC ./ size_voxel);

                subs_partial_scB_pMFC = [];
                subs_partial_scB_aMFC = [];
                faces_smallCC_scB_MFC = CartiMorphToolbox.cal_deleteLargeComponents(components_scB_cMFC, 50);
                if ~isempty(faces_smallCC_scB_MFC)
                    [~, components_smallCC_scB_MFC] = CartiMorphToolbox.segment_connected_components(faces_smallCC_scB_MFC, 'explicit');
                    for ii = 1:size(components_smallCC_scB_MFC,1)
                        faces_smallCC_scB_MFC = cell2mat(components_smallCC_scB_MFC(ii, 1));
                        [vers_smallCC_scB_MFC, ~] = CartiMorphToolbox.remove_unreferenced_vertices(vers_scB_FC, faces_smallCC_scB_MFC);
                        subs_centerSI_smallCC_scB_MFC = round(mean(vers_smallCC_scB_MFC(:,3)) ./ size_voxel(3));
                        subs_centerAP_smallCC_scB_MFC = round(mean(vers_smallCC_scB_MFC(:,2)) ./ size_voxel(2));
                        subs_centerSI_cMFC = round(mean(vers_scB_cMFC(:,3)) ./ size_voxel(3));
                        subsRange_centerSI_sMFC = round((max(vers_scB_cMFC(:,3))-min(vers_scB_cMFC(:,3))) ./ size_voxel(3));
                        if (subs_centerSI_smallCC_scB_MFC - subs_centerSI_cMFC) > subsRange_centerSI_sMFC
                            % the small connected component if part of pMFC
                            subs_partial_scB_pMFC = cat(1, subs_partial_scB_pMFC, round(vers_smallCC_scB_MFC ./ size_voxel));
                        elseif abs(subs_centerAP_smallCC_scB_MFC - subNotchAP_scB_FC_center) <= 2
                            % the small connected component if part of aMFC
                            subs_partial_scB_aMFC = cat(1, subs_partial_scB_aMFC, round(vers_smallCC_scB_MFC ./ size_voxel));
                        else
                            % the small connected component if part of cMFC
                            subs_scB_cMFC = cat(1, subs_scB_cMFC, round(vers_smallCC_scB_MFC ./ size_voxel));
                        end
                    end
                end
                % ---------------------------------------------------------


                % ---------------------------------------------------------
                % Locate aLFC, pLFC
                % (1. assuming the first dimension of image array is the L-R direction, i.e., x+ points to R)
                % (2. assuming right knee)
                % ---------------------------------------------------------
                % aLFC
                maxSubAP_scB_LFC = max(subs_scB_LFC(:,2));
                idx_scB_aLFC = subs_scB_LFC(:,2)<=maxSubAP_scB_LFC & subs_scB_LFC(:,2)>=subNotchAP_scB_FC_center;
                subs_scB_aLFC = subs_scB_LFC(idx_scB_aLFC, :);
                if exist("subs_partial_scB_aLFC", 'var')
                    subs_scB_aLFC = cat(1, subs_scB_aLFC, subs_partial_scB_aLFC);
                end
                vers_scB_aLFC = subs_scB_aLFC .* size_voxel;

                % pLFC
                idx_scB_pLFC = subs_scB_LFC(:,2)<=minSubAP_scB_cLFC & subs_scB_LFC(:,2)>=minSubAP_scB_LFC;
                subs_scB_pLFC = subs_scB_LFC(idx_scB_pLFC, :);
                if exist("subs_partial_scB_pLFC", 'var')
                    subs_scB_pLFC = cat(1, subs_scB_pLFC, subs_partial_scB_pLFC);
                end
                vers_scB_pLFC = subs_scB_pLFC .* size_voxel;
                % ---------------------------------------------------------


                % ---------------------------------------------------------
                % Locate aMFC, pMFC
                % (1. assuming the first dimension of image array is the L-R direction, i.e., x+ points to R)
                % (2. assuming right knee)
                % ---------------------------------------------------------
                % aMFC
                maxSubAP_scB_MFC = max(subs_scB_MFC(:,2));
                idx_scB_aMFC = subs_scB_MFC(:,2)<=maxSubAP_scB_MFC & subs_scB_MFC(:,2)>=subNotchAP_scB_FC_center;
                subs_scB_aMFC = subs_scB_MFC(idx_scB_aMFC, :);
                if exist("subs_partial_scB_aMFC", 'var')
                    subs_scB_aMFC = cat(1, subs_scB_aMFC, subs_partial_scB_aMFC);
                end
                vers_scB_aMFC = subs_scB_aMFC .* size_voxel;

                % pMFC
                idx_scB_pMFC = subs_scB_MFC(:,2)<=minSubAP_scB_cMFC & subs_scB_MFC(:,2)>=minSubAP_scB_MFC;
                subs_scB_pMFC = subs_scB_MFC(idx_scB_pMFC, :);
                if exist("subs_partial_scB_pMFC", 'var')
                    subs_scB_pMFC = cat(1, subs_scB_pMFC, subs_partial_scB_pMFC);
                end
                vers_scB_pMFC = subs_scB_pMFC .* size_voxel;
                % ---------------------------------------------------------


                % ---------------------------------------------------------
                % Locate the ecLFC, ccLFC, and icLFC
                % (1. assuming the first dimension of image array is the L-R direction, i.e., x+ points to R)
                % (2. assuming right knee)
                % ecLFC: exterior part of cLFC
                % ccLFC: central part of cLFC
                % icLFC: interior part of cLFC
                % ---------------------------------------------------------
                subsAP_scB_cLFC = unique(subs_scB_cLFC(:,2));
                subs_scB_ecLFC = [];
                subs_scB_ccLFC = [];
                subs_scB_icLFC = [];
                for i = 1:length(subsAP_scB_cLFC)
                    i_subAP_scB_cLFC = subsAP_scB_cLFC(i);
                    i_subs_scB_cLFC = subs_scB_cLFC(subs_scB_cLFC(:,2)==i_subAP_scB_cLFC, :);
                    i_maxSubLR_scB_cLFC = max(i_subs_scB_cLFC(:,1));
                    i_minSubLR_scB_cLFC = min(i_subs_scB_cLFC(:,1));
                    i_cutSubLR_scB_cLFC_f1 = i_minSubLR_scB_cLFC + (i_maxSubLR_scB_cLFC - i_minSubLR_scB_cLFC) * 1/3;
                    i_cutSubLR_scB_cLFC_f2 = i_minSubLR_scB_cLFC + (i_maxSubLR_scB_cLFC - i_minSubLR_scB_cLFC) * 2/3;
                    i_idx_scB_ecLFC = i_subs_scB_cLFC(:,1)<=i_maxSubLR_scB_cLFC & i_subs_scB_cLFC(:,1)>i_cutSubLR_scB_cLFC_f2;
                    i_idx_scB_ccLFC = i_subs_scB_cLFC(:,1)<=i_cutSubLR_scB_cLFC_f2 & i_subs_scB_cLFC(:,1)>=i_cutSubLR_scB_cLFC_f1;
                    i_idx_scB_icLFC = i_subs_scB_cLFC(:,1)<i_cutSubLR_scB_cLFC_f1 & i_subs_scB_cLFC(:,1)>=i_minSubLR_scB_cLFC;
                    i_subs_scB_ecLFC = i_subs_scB_cLFC(i_idx_scB_ecLFC, :);
                    i_subs_scB_ccLFC = i_subs_scB_cLFC(i_idx_scB_ccLFC, :);
                    i_subs_scB_icLFC = i_subs_scB_cLFC(i_idx_scB_icLFC, :);
                    subs_scB_ecLFC = cat(1, subs_scB_ecLFC, i_subs_scB_ecLFC);
                    subs_scB_ccLFC = cat(1, subs_scB_ccLFC, i_subs_scB_ccLFC);
                    subs_scB_icLFC = cat(1, subs_scB_icLFC, i_subs_scB_icLFC);
                end
                vers_scB_ecLFC = subs_scB_ecLFC .* size_voxel;
                vers_scB_ccLFC = subs_scB_ccLFC .* size_voxel;
                vers_scB_icLFC = subs_scB_icLFC .* size_voxel;
                % ---------------------------------------------------------


                % ---------------------------------------------------------
                % Locate the ecMFC, ccMFC, and icMFC
                % (1. assuming the first dimension of image array is the L-R direction, i.e., x+ points to R)
                % (2. assuming right knee)
                % ecMFC: exterior part of cMFC
                % ccMFC: central part of cMFC
                % icMFC: interior part of cMFC
                % ---------------------------------------------------------
                subsAP_scB_cMFC = unique(subs_scB_cMFC(:,2));
                subs_scB_ecMFC = [];
                subs_scB_ccMFC = [];
                subs_scB_icMFC = [];
                for i = 1:length(subsAP_scB_cMFC)
                    i_subAP_scB_cMFC = subsAP_scB_cMFC(i);
                    i_subs_scB_cMFC = subs_scB_cMFC(subs_scB_cMFC(:,2)==i_subAP_scB_cMFC, :);
                    i_maxSubLR_scB_cMFC = max(i_subs_scB_cMFC(:,1));
                    i_minSubLR_scB_cMFC = min(i_subs_scB_cMFC(:,1));
                    i_cutSubLR_scB_cMFC_f1 = i_minSubLR_scB_cMFC + (i_maxSubLR_scB_cMFC - i_minSubLR_scB_cMFC) * 1/3;
                    i_cutSubLR_scB_cMFC_f2 = i_minSubLR_scB_cMFC + (i_maxSubLR_scB_cMFC - i_minSubLR_scB_cMFC) * 2/3;
                    i_idx_scB_icMFC = i_subs_scB_cMFC(:,1)<=i_maxSubLR_scB_cMFC & i_subs_scB_cMFC(:,1)>i_cutSubLR_scB_cMFC_f2;
                    i_idx_scB_ccMFC = i_subs_scB_cMFC(:,1)<=i_cutSubLR_scB_cMFC_f2 & i_subs_scB_cMFC(:,1)>=i_cutSubLR_scB_cMFC_f1;
                    i_idx_scB_ecMFC = i_subs_scB_cMFC(:,1)<i_cutSubLR_scB_cMFC_f1 & i_subs_scB_cMFC(:,1)>=i_minSubLR_scB_cMFC;
                    i_subs_scB_icMFC = i_subs_scB_cMFC(i_idx_scB_icMFC, :);
                    i_subs_scB_ccMFC = i_subs_scB_cMFC(i_idx_scB_ccMFC, :);
                    i_subs_scB_ecMFC = i_subs_scB_cMFC(i_idx_scB_ecMFC, :);
                    subs_scB_icMFC = cat(1, subs_scB_icMFC, i_subs_scB_icMFC);
                    subs_scB_ccMFC = cat(1, subs_scB_ccMFC, i_subs_scB_ccMFC);
                    subs_scB_ecMFC = cat(1, subs_scB_ecMFC, i_subs_scB_ecMFC);
                end
                vers_scB_ecMFC = subs_scB_ecMFC .* size_voxel;
                vers_scB_ccMFC = subs_scB_ccMFC .* size_voxel;
                vers_scB_icMFC = subs_scB_icMFC .* size_voxel;
                % ---------------------------------------------------------


                %% Parcellate FC
                vers_iC_aLFC = vers_iC_FC(ismember(subs_iC_FC, subs_scB_aLFC, 'rows'), :);
                vers_iC_pLFC = vers_iC_FC(ismember(subs_iC_FC, subs_scB_pLFC, 'rows'), :);
                vers_iC_ecLFC = vers_iC_FC(ismember(subs_iC_FC, subs_scB_ecLFC, 'rows'), :);
                vers_iC_ccLFC = vers_iC_FC(ismember(subs_iC_FC, subs_scB_ccLFC, 'rows'), :);
                vers_iC_icLFC = vers_iC_FC(ismember(subs_iC_FC, subs_scB_icLFC, 'rows'), :);
                vers_iC_aMFC = vers_iC_FC(ismember(subs_iC_FC, subs_scB_aMFC, 'rows'), :);
                vers_iC_pMFC = vers_iC_FC(ismember(subs_iC_FC, subs_scB_pMFC, 'rows'), :);
                vers_iC_ecMFC = vers_iC_FC(ismember(subs_iC_FC, subs_scB_ecMFC, 'rows'), :);
                vers_iC_ccMFC = vers_iC_FC(ismember(subs_iC_FC, subs_scB_ccMFC, 'rows'), :);
                vers_iC_icMFC = vers_iC_FC(ismember(subs_iC_FC, subs_scB_icMFC, 'rows'), :);
                subs_iC_aLFC = round(vers_iC_aLFC ./ size_voxel);
                subs_iC_pLFC = round(vers_iC_pLFC ./ size_voxel);
                subs_iC_ecLFC = round(vers_iC_ecLFC ./ size_voxel);
                subs_iC_ccLFC = round(vers_iC_ccLFC ./ size_voxel);
                subs_iC_icLFC = round(vers_iC_icLFC ./ size_voxel);
                subs_iC_aMFC = round(vers_iC_aMFC ./ size_voxel);
                subs_iC_pMFC = round(vers_iC_pMFC ./ size_voxel);
                subs_iC_ecMFC = round(vers_iC_ecMFC ./ size_voxel);
                subs_iC_ccMFC = round(vers_iC_ccMFC ./ size_voxel);
                subs_iC_icMFC = round(vers_iC_icMFC ./ size_voxel);


                %% Extract faces of each subregion
                % FC with recovered full-thickness cartilage loss
                faces_scB_aLFC = CartiMorphToolbox.cal_extractFaces_OR(faces_scB_FC, subs_scB_FC, subs_scB_aLFC);
                [Mesh_scB_aLFC.vertices, Mesh_scB_aLFC.faces] = CartiMorphToolbox.remove_unreferenced_vertices(vers_scB_FC, faces_scB_aLFC);
                faces_scB_pLFC = CartiMorphToolbox.cal_extractFaces_OR(faces_scB_FC, subs_scB_FC, subs_scB_pLFC);
                [Mesh_scB_pLFC.vertices, Mesh_scB_pLFC.faces] = CartiMorphToolbox.remove_unreferenced_vertices(vers_scB_FC, faces_scB_pLFC);
                faces_scB_ecLFC = CartiMorphToolbox.cal_extractFaces_OR(faces_scB_FC, subs_scB_FC, subs_scB_ecLFC);
                [Mesh_scB_ecLFC.vertices, Mesh_scB_ecLFC.faces] = CartiMorphToolbox.remove_unreferenced_vertices(vers_scB_FC, faces_scB_ecLFC);
                faces_scB_ccLFC = CartiMorphToolbox.cal_extractFaces_OR(faces_scB_FC, subs_scB_FC, subs_scB_ccLFC);
                [Mesh_scB_ccLFC.vertices, Mesh_scB_ccLFC.faces] = CartiMorphToolbox.remove_unreferenced_vertices(vers_scB_FC, faces_scB_ccLFC);
                faces_scB_icLFC = CartiMorphToolbox.cal_extractFaces_OR(faces_scB_FC, subs_scB_FC, subs_scB_icLFC);
                [Mesh_scB_icLFC.vertices, Mesh_scB_icLFC.faces] = CartiMorphToolbox.remove_unreferenced_vertices(vers_scB_FC, faces_scB_icLFC);
                faces_scB_aMFC = CartiMorphToolbox.cal_extractFaces_OR(faces_scB_FC, subs_scB_FC, subs_scB_aMFC);
                [Mesh_scB_aMFC.vertices, Mesh_scB_aMFC.faces] = CartiMorphToolbox.remove_unreferenced_vertices(vers_scB_FC, faces_scB_aMFC);
                faces_scB_pMFC = CartiMorphToolbox.cal_extractFaces_OR(faces_scB_FC, subs_scB_FC, subs_scB_pMFC);
                [Mesh_scB_pMFC.vertices, Mesh_scB_pMFC.faces] = CartiMorphToolbox.remove_unreferenced_vertices(vers_scB_FC, faces_scB_pMFC);
                faces_scB_ecMFC = CartiMorphToolbox.cal_extractFaces_OR(faces_scB_FC, subs_scB_FC, subs_scB_ecMFC);
                [Mesh_scB_ecMFC.vertices, Mesh_scB_ecMFC.faces] = CartiMorphToolbox.remove_unreferenced_vertices(vers_scB_FC, faces_scB_ecMFC);
                faces_scB_ccMFC = CartiMorphToolbox.cal_extractFaces_OR(faces_scB_FC, subs_scB_FC, subs_scB_ccMFC);
                [Mesh_scB_ccMFC.vertices, Mesh_scB_ccMFC.faces] = CartiMorphToolbox.remove_unreferenced_vertices(vers_scB_FC, faces_scB_ccMFC);
                faces_scB_icMFC = CartiMorphToolbox.cal_extractFaces_OR(faces_scB_FC, subs_scB_FC, subs_scB_icMFC);
                [Mesh_scB_icMFC.vertices, Mesh_scB_icMFC.faces] = CartiMorphToolbox.remove_unreferenced_vertices(vers_scB_FC, faces_scB_icMFC);

                % original FC
                if ~isempty(subs_iC_aLFC)
                    faces_iC_aLFC = CartiMorphToolbox.cal_extractFaces_OR(faces_iC_FC, subs_iC_FC, subs_iC_aLFC);
                    [Mesh_iC_aLFC.vertices, Mesh_iC_aLFC.faces] = CartiMorphToolbox.remove_unreferenced_vertices(vers_iC_FC, faces_iC_aLFC);
                else
                    Mesh_iC_aLFC = [];
                end

                if ~isempty(subs_iC_pLFC)
                    faces_iC_pLFC = CartiMorphToolbox.cal_extractFaces_OR(faces_iC_FC, subs_iC_FC, subs_iC_pLFC);
                    [Mesh_iC_pLFC.vertices, Mesh_iC_pLFC.faces] = CartiMorphToolbox.remove_unreferenced_vertices(vers_iC_FC, faces_iC_pLFC);
                else
                    Mesh_iC_pLFC = [];
                end

                if ~isempty(subs_iC_ecLFC)
                    faces_iC_ecLFC = CartiMorphToolbox.cal_extractFaces_OR(faces_iC_FC, subs_iC_FC, subs_iC_ecLFC);
                    [Mesh_iC_ecLFC.vertices, Mesh_iC_ecLFC.faces] = CartiMorphToolbox.remove_unreferenced_vertices(vers_iC_FC, faces_iC_ecLFC);
                else
                    Mesh_iC_ecLFC = [];
                end

                if ~isempty(subs_iC_ccLFC)
                    faces_iC_ccLFC = CartiMorphToolbox.cal_extractFaces_OR(faces_iC_FC, subs_iC_FC, subs_iC_ccLFC);
                    [Mesh_iC_ccLFC.vertices, Mesh_iC_ccLFC.faces] = CartiMorphToolbox.remove_unreferenced_vertices(vers_iC_FC, faces_iC_ccLFC);
                else
                    Mesh_iC_ccLFC = [];
                end

                if ~isempty(subs_iC_icLFC)
                    faces_iC_icLFC = CartiMorphToolbox.cal_extractFaces_OR(faces_iC_FC, subs_iC_FC, subs_iC_icLFC);
                    [Mesh_iC_icLFC.vertices, Mesh_iC_icLFC.faces] = CartiMorphToolbox.remove_unreferenced_vertices(vers_iC_FC, faces_iC_icLFC);
                else
                    Mesh_iC_icLFC = [];
                end

                if ~isempty(subs_iC_aMFC)
                    faces_iC_aMFC = CartiMorphToolbox.cal_extractFaces_OR(faces_iC_FC, subs_iC_FC, subs_iC_aMFC);
                    [Mesh_iC_aMFC.vertices, Mesh_iC_aMFC.faces] = CartiMorphToolbox.remove_unreferenced_vertices(vers_iC_FC, faces_iC_aMFC);
                else
                    Mesh_iC_aMFC = [];
                end

                if ~isempty(subs_iC_pMFC)
                    faces_iC_pMFC = CartiMorphToolbox.cal_extractFaces_OR(faces_iC_FC, subs_iC_FC, subs_iC_pMFC);
                    [Mesh_iC_pMFC.vertices, Mesh_iC_pMFC.faces] = CartiMorphToolbox.remove_unreferenced_vertices(vers_iC_FC, faces_iC_pMFC);
                else
                    Mesh_iC_pMFC = [];
                end

                if ~isempty(subs_iC_ecMFC)
                    faces_iC_ecMFC = CartiMorphToolbox.cal_extractFaces_OR(faces_iC_FC, subs_iC_FC, subs_iC_ecMFC);
                    [Mesh_iC_ecMFC.vertices, Mesh_iC_ecMFC.faces] = CartiMorphToolbox.remove_unreferenced_vertices(vers_iC_FC, faces_iC_ecMFC);
                else
                    Mesh_iC_ecMFC = [];
                end

                if ~isempty(subs_iC_ccMFC)
                    faces_iC_ccMFC = CartiMorphToolbox.cal_extractFaces_OR(faces_iC_FC, subs_iC_FC, subs_iC_ccMFC);
                    [Mesh_iC_ccMFC.vertices, Mesh_iC_ccMFC.faces] = CartiMorphToolbox.remove_unreferenced_vertices(vers_iC_FC, faces_iC_ccMFC);
                else
                    Mesh_iC_ccMFC = [];
                end

                if ~isempty(subs_iC_icMFC)
                    faces_iC_icMFC = CartiMorphToolbox.cal_extractFaces_OR(faces_iC_FC, subs_iC_FC, subs_iC_icMFC);
                    [Mesh_iC_icMFC.vertices, Mesh_iC_icMFC.faces] = CartiMorphToolbox.remove_unreferenced_vertices(vers_iC_FC, faces_iC_icMFC);
                else
                    Mesh_iC_icMFC = [];
                end


                %% Combine results
                switch knee_side
                    case {'right', 'Right', 'R'}
                        %% Vertices
                        % FC with recovered full-thickness cartilage loss
                        versSubReg_scB_FC.aLFC = vers_scB_aLFC;
                        versSubReg_scB_FC.pLFC = vers_scB_pLFC;
                        versSubReg_scB_FC.ecLFC = vers_scB_ecLFC;
                        versSubReg_scB_FC.ccLFC = vers_scB_ccLFC;
                        versSubReg_scB_FC.icLFC = vers_scB_icLFC;
                        versSubReg_scB_FC.aMFC = vers_scB_aMFC;
                        versSubReg_scB_FC.pMFC = vers_scB_pMFC;
                        versSubReg_scB_FC.ecMFC = vers_scB_ecMFC;
                        versSubReg_scB_FC.ccMFC = vers_scB_ccMFC;
                        versSubReg_scB_FC.icMFC = vers_scB_icMFC;
                        % original FC
                        versSubReg_iC_FC.aLFC = vers_iC_aLFC;
                        versSubReg_iC_FC.pLFC = vers_iC_pLFC;
                        versSubReg_iC_FC.ecLFC = vers_iC_ecLFC;
                        versSubReg_iC_FC.ccLFC = vers_iC_ccLFC;
                        versSubReg_iC_FC.icLFC = vers_iC_icLFC;
                        versSubReg_iC_FC.aMFC = vers_iC_aMFC;
                        versSubReg_iC_FC.pMFC = vers_iC_pMFC;
                        versSubReg_iC_FC.ecMFC = vers_iC_ecMFC;
                        versSubReg_iC_FC.ccMFC = vers_iC_ccMFC;
                        versSubReg_iC_FC.icMFC = vers_iC_icMFC;

                        %% Mesh
                        % FC with recovered full-thickness cartilage loss
                        meshSubReg_scB_FC.aLFC = Mesh_scB_aLFC;
                        meshSubReg_scB_FC.pLFC = Mesh_scB_pLFC;
                        meshSubReg_scB_FC.ecLFC = Mesh_scB_ecLFC;
                        meshSubReg_scB_FC.ccLFC = Mesh_scB_ccLFC;
                        meshSubReg_scB_FC.icLFC = Mesh_scB_icLFC;
                        meshSubReg_scB_FC.aMFC = Mesh_scB_aMFC;
                        meshSubReg_scB_FC.pMFC = Mesh_scB_pMFC;
                        meshSubReg_scB_FC.ecMFC = Mesh_scB_ecMFC;
                        meshSubReg_scB_FC.ccMFC = Mesh_scB_ccMFC;
                        meshSubReg_scB_FC.icMFC = Mesh_scB_icMFC;
                        % original FC
                        meshSubReg_iC_FC.aLFC = Mesh_iC_aLFC;
                        meshSubReg_iC_FC.pLFC = Mesh_iC_pLFC;
                        meshSubReg_iC_FC.ecLFC = Mesh_iC_ecLFC;
                        meshSubReg_iC_FC.ccLFC = Mesh_iC_ccLFC;
                        meshSubReg_iC_FC.icLFC = Mesh_iC_icLFC;
                        meshSubReg_iC_FC.aMFC = Mesh_iC_aMFC;
                        meshSubReg_iC_FC.pMFC = Mesh_iC_pMFC;
                        meshSubReg_iC_FC.ecMFC = Mesh_iC_ecMFC;
                        meshSubReg_iC_FC.ccMFC = Mesh_iC_ccMFC;
                        meshSubReg_iC_FC.icMFC = Mesh_iC_icMFC;

                    case {'left', 'Left', 'L'}
                        %% Vertices
                        % FC with recovered full-thickness cartilage loss
                        versSubReg_scB_FC.aLFC = vers_scB_aMFC;
                        versSubReg_scB_FC.pLFC = vers_scB_pMFC;
                        versSubReg_scB_FC.ecLFC = vers_scB_ecMFC;
                        versSubReg_scB_FC.ccLFC = vers_scB_ccMFC;
                        versSubReg_scB_FC.icLFC = vers_scB_icMFC;
                        versSubReg_scB_FC.aMFC = vers_scB_aLFC;
                        versSubReg_scB_FC.pMFC = vers_scB_pLFC;
                        versSubReg_scB_FC.ecMFC = vers_scB_ecLFC;
                        versSubReg_scB_FC.ccMFC = vers_scB_ccLFC;
                        versSubReg_scB_FC.icMFC = vers_scB_icLFC;
                        % original FC
                        versSubReg_iC_FC.aLFC = vers_iC_aMFC;
                        versSubReg_iC_FC.pLFC = vers_iC_pMFC;
                        versSubReg_iC_FC.ecLFC = vers_iC_ecMFC;
                        versSubReg_iC_FC.ccLFC = vers_iC_ccMFC;
                        versSubReg_iC_FC.icLFC = vers_iC_icMFC;
                        versSubReg_iC_FC.aMFC = vers_iC_aLFC;
                        versSubReg_iC_FC.pMFC = vers_iC_pLFC;
                        versSubReg_iC_FC.ecMFC = vers_iC_ecLFC;
                        versSubReg_iC_FC.ccMFC = vers_iC_ccLFC;
                        versSubReg_iC_FC.icMFC = vers_iC_icLFC;

                        %% Mesh
                        % FC with recovered full-thickness cartilage loss
                        meshSubReg_scB_FC.aLFC = Mesh_scB_aMFC;
                        meshSubReg_scB_FC.pLFC = Mesh_scB_pMFC;
                        meshSubReg_scB_FC.ecLFC = Mesh_scB_ecMFC;
                        meshSubReg_scB_FC.ccLFC = Mesh_scB_ccMFC;
                        meshSubReg_scB_FC.icLFC = Mesh_scB_icMFC;
                        meshSubReg_scB_FC.aMFC = Mesh_scB_aLFC;
                        meshSubReg_scB_FC.pMFC = Mesh_scB_pLFC;
                        meshSubReg_scB_FC.ecMFC = Mesh_scB_ecLFC;
                        meshSubReg_scB_FC.ccMFC = Mesh_scB_ccLFC;
                        meshSubReg_scB_FC.icMFC = Mesh_scB_icLFC;
                        % original FC
                        meshSubReg_iC_FC.aLFC = Mesh_iC_aMFC;
                        meshSubReg_iC_FC.pLFC = Mesh_iC_pMFC;
                        meshSubReg_iC_FC.ecLFC = Mesh_iC_ecMFC;
                        meshSubReg_iC_FC.ccLFC = Mesh_iC_ccMFC;
                        meshSubReg_iC_FC.icLFC = Mesh_iC_icMFC;
                        meshSubReg_iC_FC.aMFC = Mesh_iC_aLFC;
                        meshSubReg_iC_FC.pMFC = Mesh_iC_pLFC;
                        meshSubReg_iC_FC.ecMFC = Mesh_iC_ecLFC;
                        meshSubReg_iC_FC.ccMFC = Mesh_iC_ccLFC;
                        meshSubReg_iC_FC.icMFC = Mesh_iC_icLFC;
                end

            end
        end

        function [versSubReg_iC_TC, versSubReg_scB_TC, meshSubReg_iC_TC, meshSubReg_scB_TC] = cal_SurfaceParcellation_TC( ...
                mesh_iC_LTC, ...
                mesh_iC_MTC, ...
                mesh_scB_LTC, ...
                mesh_scB_MTC, ...
                size_voxel, ...
                knee_side)
            % ==============================================================================
            % FUNCTION:
            %     Cartilage parcellation of FC.
            %
            % INPUT:
            %     - mesh_iC_LTC: interior cartilage surface of LTC
            %     - mesh_iC_MTC: interior cartilage surface of MTC
            %     - mesh_scB_LTC: subchondral bone surface of LTC
            %     - mesh_scB_MTC: subchondral bone surface of MTC
            %     - size_voxel: voxel size
            %     - knee_side: knee side, must be one of {'left', 'Left', 'L', 'right', 'Right', 'R'}
            %
            % OUTPUT:
            %     - versSubReg_iC_TC: [structure], vertices on the interior cartilage surface of TC
            %     - versSubReg_scB_TC: [structure], vertices on the subchondral bone surface of TC
            %     - meshSubReg_iC_TC: [structure], the interior cartilage surface of TC
            %     - meshSubReg_scB_TC: [structure], the subchondral bone surface of TC
            % ------------------------------------------------------------------------------
            % Matlab Version: 2019b or later
            %
            % Last updated on: 17-Sep-2022
            %
            % Author:
            % Yongcheng YAO (yao_yongcheng@link.cuhk.edu.hk)
            % Department of Imaging and Interventional Radiology,
            % Chinese University of Hong Kong (CUHK)
            %
            % Copyright 2020 Yongcheng YAO
            % ------------------------------------------------------------------------------
            % ==============================================================================


            if isempty(mesh_iC_LTC) || isempty(mesh_iC_MTC)
                ROInames_TC = {'aMTC', 'eMTC', 'pMTC', 'iMTC', 'cMTC',...
                    'aLTC', 'eLTC', 'pLTC', 'iLTC', 'cLTC'};
                for i=1:length(ROInames_TC)
                    iROI = ROInames_TC{i};
                    versSubReg_iC_TC.(iROI) = [];
                    versSubReg_scB_TC.(iROI) = [];
                    meshSubReg_iC_TC.(iROI) = [];
                    meshSubReg_scB_TC.(iROI) = [];
                end
                return;

            else

                %% Preparing
                % interior cartilage surface for MTC
                faces_iC_MTC = mesh_iC_MTC.faces;
                vers_iC_MTC = mesh_iC_MTC.vertices;
                subs_iC_MTC = round(vers_iC_MTC ./ size_voxel);

                % subchondral bone surface for MTC
                faces_scB_MTC = mesh_scB_MTC.faces;
                vers_scB_MTC = mesh_scB_MTC.vertices;
                subs_scB_MTC = round(vers_scB_MTC ./ size_voxel);

                % interior cartilage surface for LTC
                faces_iC_LTC = mesh_iC_LTC.faces;
                vers_iC_LTC = mesh_iC_LTC.vertices;
                subs_iC_LTC = round(vers_iC_LTC ./ size_voxel);

                % subchondral bone surface for LTC
                faces_scB_LTC = mesh_scB_LTC.faces;
                vers_scB_LTC = mesh_scB_LTC.vertices;
                subs_scB_LTC = round(vers_scB_LTC ./ size_voxel);


                %% Parcellate subchondral bone area of TC -- "filled TC"
                % ---------------------------------------------------------
                % Locate cMTC
                % ---------------------------------------------------------
                % translation
                center_scB_MTC = mean(vers_scB_MTC, 1);
                vers_scB_MTC_c = vers_scB_MTC - center_scB_MTC;
                % SVD
                [~, S, V] = svd(vers_scB_MTC_c, 0);
                diagS = diag(S);

                % .........................................................
                % find the nearest axis to the each singular vector (assuming RAS+ orientation)
                sigV1 = V(:,1);
                sigV2 = V(:,2);
                sigV3 = V(:,3);
                axis_R = [1,0,0];
                axis_A = [0,1,0];
                axis_S = [0,0,1];
                % angle between singular vectors and axes
                angle_sigV1_R = acosd(dot(sigV1, axis_R) / (norm(axis_R) * norm(sigV1)));
                angle_sigV1_A = acosd(dot(sigV1, axis_A) / (norm(axis_A) * norm(sigV1)));
                angle_sigV1_S = acosd(dot(sigV1, axis_S) / (norm(axis_S) * norm(sigV1)));
                angle_sigV2_R = acosd(dot(sigV2, axis_R) / (norm(axis_R) * norm(sigV2)));
                angle_sigV2_A = acosd(dot(sigV2, axis_A) / (norm(axis_A) * norm(sigV2)));
                angle_sigV2_S = acosd(dot(sigV2, axis_S) / (norm(axis_S) * norm(sigV2)));
                angle_sigV3_R = acosd(dot(sigV3, axis_R) / (norm(axis_R) * norm(sigV3)));
                angle_sigV3_A = acosd(dot(sigV3, axis_A) / (norm(axis_A) * norm(sigV3)));
                angle_sigV3_S = acosd(dot(sigV3, axis_S) / (norm(axis_S) * norm(sigV3)));
                % convert angles to the range [0,90]
                angles_sigV12RAS = [angle_sigV1_R, angle_sigV1_A, angle_sigV1_S];
                flip_sigV1 = angles_sigV12RAS > 90;
                angles_sigV12RAS(flip_sigV1) = 180 - angles_sigV12RAS(flip_sigV1);
                angles_sigV22RAS = [angle_sigV2_R, angle_sigV2_A, angle_sigV2_S];
                flip_sigV2 = angles_sigV22RAS > 90;
                angles_sigV22RAS(flip_sigV2) = 180 - angles_sigV22RAS(flip_sigV2);
                angles_sigV32RAS = [angle_sigV3_R, angle_sigV3_A, angle_sigV3_S];
                flip_sigV3 = angles_sigV32RAS > 90;
                angles_sigV32RAS(flip_sigV3) = 180 - angles_sigV32RAS(flip_sigV3);
                % find the nearest axis for each singular vector
                [~, direction_sigV1] = min(angles_sigV12RAS);
                fake_angles_sigV22RAS = angles_sigV22RAS;
                fake_angles_sigV22RAS(direction_sigV1) = 90;
                [~, direction_sigV2] = min(fake_angles_sigV22RAS);
                fake_angles_sigV32RAS = angles_sigV32RAS;
                fake_angles_sigV32RAS(direction_sigV1) = 90;
                fake_angles_sigV32RAS(direction_sigV2) = 90;
                [~, direction_sigV3] = min(fake_angles_sigV32RAS);
                absDirection_sigV123 = [direction_sigV1, direction_sigV2, direction_sigV3];
                % .........................................................

                % transformation
                transMat_scB_MTC(:, direction_sigV1) = sigV1;
                transMat_scB_MTC(:, direction_sigV2) = sigV2;
                transMat_scB_MTC(:, direction_sigV3) = sigV3;
                transMat_scB_MTC(:,diag(transMat_scB_MTC)<0) = transMat_scB_MTC(:,diag(transMat_scB_MTC)<0) * -1;
                vers_scB_MTC_ct = transpose(transMat_scB_MTC \ vers_scB_MTC_c');

                % initial parcellation of cMTL
                tmpA = 5;
                tmpB = tmpA * sqrt(diagS(absDirection_sigV123==2) / diagS(absDirection_sigV123==1));
                tmpX = vers_scB_MTC_ct(:,1);
                tmpY = vers_scB_MTC_ct(:,2);
                idx_scB_cMTC = (tmpX.^2 ./ tmpA^2 + tmpY.^2 ./ tmpB^2 - 1)<0;
                vers_scB_cMTC_ct = vers_scB_MTC_ct(idx_scB_cMTC, :);
                vers_scB_cMTC_c = transpose(transMat_scB_MTC * vers_scB_cMTC_ct');
                vers_scB_cMTC = vers_scB_cMTC_c + center_scB_MTC;

                % adjust the cMTL untill it covers 20% area of the MTL
                area_scB_MTC = sum(CartiMorphToolbox.quant_triMeshArea(vers_scB_MTC, faces_scB_MTC));
                faces_scB_cMTC = CartiMorphToolbox.cal_extractFaces_OR(faces_scB_MTC, vers_scB_MTC, vers_scB_cMTC);
                area_scB_cMTC = sum(CartiMorphToolbox.quant_triMeshArea(vers_scB_MTC, faces_scB_cMTC));
                recorder_osillation = 0;
                counter_Act = 0;
                while abs(area_scB_cMTC/area_scB_MTC - 0.2)>0.005 && recorder_osillation<1000
                    if area_scB_cMTC/area_scB_MTC > 0.2
                        counter_Act = counter_Act + 1;
                        % new cMTL
                        tmpA = tmpA - 0.5;
                        tmpB = tmpA * sqrt(diagS(absDirection_sigV123==2) / diagS(absDirection_sigV123==1));
                        tmpX = vers_scB_MTC_ct(:,1);
                        tmpY = vers_scB_MTC_ct(:,2);
                        idx_scB_cMTC = (tmpX.^2 ./ tmpA^2 + tmpY.^2 ./ tmpB^2 - 1)<0;
                        vers_scB_cMTC_ct = vers_scB_MTC_ct(idx_scB_cMTC, :);
                        vers_scB_cMTC_c = transpose(transMat_scB_MTC * vers_scB_cMTC_ct');
                        vers_scB_cMTC = vers_scB_cMTC_c + center_scB_MTC;
                        % new cMTL area
                        faces_scB_cMTC = CartiMorphToolbox.cal_extractFaces_OR(faces_scB_MTC, vers_scB_MTC, vers_scB_cMTC);
                        area_scB_cMTC = sum(CartiMorphToolbox.quant_triMeshArea(vers_scB_MTC, faces_scB_cMTC));
                        % update osillation status
                        recorder_currAct = 1;
                        if counter_Act==1
                            recorder_lastAct=recorder_currAct;
                        end
                        if recorder_currAct~=recorder_lastAct
                            recorder_osillation = recorder_osillation + 1;
                        end
                    else
                        counter_Act = counter_Act + 1;
                        % new cMTL
                        tmpA = tmpA + 0.5;
                        tmpB = tmpA * sqrt(diagS(absDirection_sigV123==2) / diagS(absDirection_sigV123==1));
                        tmpX = vers_scB_MTC_ct(:,1);
                        tmpY = vers_scB_MTC_ct(:,2);
                        idx_scB_cMTC = (tmpX.^2 ./ tmpA^2 + tmpY.^2 ./ tmpB^2 - 1)<0;
                        vers_scB_cMTC_ct = vers_scB_MTC_ct(idx_scB_cMTC, :);
                        vers_scB_cMTC_c = transpose(transMat_scB_MTC * vers_scB_cMTC_ct');
                        vers_scB_cMTC = vers_scB_cMTC_c + center_scB_MTC;
                        % new cMTL area
                        faces_scB_cMTC = CartiMorphToolbox.cal_extractFaces_OR(faces_scB_MTC, vers_scB_MTC, vers_scB_cMTC);
                        area_scB_cMTC = sum(CartiMorphToolbox.quant_triMeshArea(vers_scB_MTC, faces_scB_cMTC));
                        % update osillation status
                        recorder_currAct = -1;
                        if counter_Act==1
                            recorder_lastAct=recorder_currAct;
                        end
                        if recorder_currAct~=recorder_lastAct
                            recorder_osillation = recorder_osillation + 1;
                        end
                    end
                end
                subs_scB_cMTC = round(vers_scB_cMTC ./ size_voxel);
                % ---------------------------------------------------------


                % ---------------------------------------------------------
                % Locate cLTC
                % ---------------------------------------------------------
                % translation
                center_scB_LTC = mean(vers_scB_LTC, 1);
                vers_scB_LTC_c = vers_scB_LTC - center_scB_LTC;
                % SVD
                [~, S, V] = svd(vers_scB_LTC_c, 0);
                diagS = diag(S);

                % .........................................................
                % find the nearest axis to the each singular vector (assuming RAS+ orientation)
                sigV1 = V(:,1);
                sigV2 = V(:,2);
                sigV3 = V(:,3);
                axis_R = [1,0,0];
                axis_A = [0,1,0];
                axis_S = [0,0,1];
                % angle between singular vectors and axes
                angle_sigV1_R = acosd(dot(sigV1, axis_R) / (norm(axis_R) * norm(sigV1)));
                angle_sigV1_A = acosd(dot(sigV1, axis_A) / (norm(axis_A) * norm(sigV1)));
                angle_sigV1_S = acosd(dot(sigV1, axis_S) / (norm(axis_S) * norm(sigV1)));
                angle_sigV2_R = acosd(dot(sigV2, axis_R) / (norm(axis_R) * norm(sigV2)));
                angle_sigV2_A = acosd(dot(sigV2, axis_A) / (norm(axis_A) * norm(sigV2)));
                angle_sigV2_S = acosd(dot(sigV2, axis_S) / (norm(axis_S) * norm(sigV2)));
                angle_sigV3_R = acosd(dot(sigV3, axis_R) / (norm(axis_R) * norm(sigV3)));
                angle_sigV3_A = acosd(dot(sigV3, axis_A) / (norm(axis_A) * norm(sigV3)));
                angle_sigV3_S = acosd(dot(sigV3, axis_S) / (norm(axis_S) * norm(sigV3)));
                % convert angles to the range [0,90]
                angles_sigV12RAS = [angle_sigV1_R, angle_sigV1_A, angle_sigV1_S];
                flip_sigV1 = angles_sigV12RAS > 90;
                angles_sigV12RAS(flip_sigV1) = 180 - angles_sigV12RAS(flip_sigV1);
                angles_sigV22RAS = [angle_sigV2_R, angle_sigV2_A, angle_sigV2_S];
                flip_sigV2 = angles_sigV22RAS > 90;
                angles_sigV22RAS(flip_sigV2) = 180 - angles_sigV22RAS(flip_sigV2);
                angles_sigV32RAS = [angle_sigV3_R, angle_sigV3_A, angle_sigV3_S];
                flip_sigV3 = angles_sigV32RAS > 90;
                angles_sigV32RAS(flip_sigV3) = 180 - angles_sigV32RAS(flip_sigV3);
                % find the nearest axis for each singular vector
                [~, direction_sigV1] = min(angles_sigV12RAS);
                fake_angles_sigV22RAS = angles_sigV22RAS;
                fake_angles_sigV22RAS(direction_sigV1) = 90;
                [~, direction_sigV2] = min(fake_angles_sigV22RAS);
                fake_angles_sigV32RAS = angles_sigV32RAS;
                fake_angles_sigV32RAS(direction_sigV1) = 90;
                fake_angles_sigV32RAS(direction_sigV2) = 90;
                [~, direction_sigV3] = min(fake_angles_sigV32RAS);
                absDirection_sigV123 = [direction_sigV1, direction_sigV2, direction_sigV3];
                % .........................................................

                % transformation
                transMat_scB_LTC(:, direction_sigV1) = sigV1;
                transMat_scB_LTC(:, direction_sigV2) = sigV2;
                transMat_scB_LTC(:, direction_sigV3) = sigV3;
                transMat_scB_LTC(:,diag(transMat_scB_LTC)<0) = transMat_scB_LTC(:,diag(transMat_scB_LTC)<0) * -1;
                vers_scB_LTC_ct = transpose(transMat_scB_LTC \ vers_scB_LTC_c');

                % initial parcellation of cMTL
                tmpA = 5;
                tmpB = tmpA * sqrt(diagS(absDirection_sigV123==2) / diagS(absDirection_sigV123==1));
                tmpX = vers_scB_LTC_ct(:,1);
                tmpY = vers_scB_LTC_ct(:,2);
                idx_scB_cLTC = (tmpX.^2 ./ tmpA^2 + tmpY.^2 ./ tmpB^2 - 1)<0;
                vers_scB_cLTC_ct = vers_scB_LTC_ct(idx_scB_cLTC, :);
                vers_scB_cLTC_c = transpose(transMat_scB_LTC * vers_scB_cLTC_ct');
                vers_scB_cLTC = vers_scB_cLTC_c + center_scB_LTC;

                % adjust the cMTL untill it covers 20% area of the MTL
                area_scB_LTC = sum(CartiMorphToolbox.quant_triMeshArea(vers_scB_LTC, faces_scB_LTC));
                faces_scB_cLTC = CartiMorphToolbox.cal_extractFaces_OR(faces_scB_LTC, vers_scB_LTC, vers_scB_cLTC);
                area_scB_cLTC = sum(CartiMorphToolbox.quant_triMeshArea(vers_scB_LTC, faces_scB_cLTC));
                recorder_osillation = 0;
                counter_Act = 0;
                while abs(area_scB_cLTC/area_scB_LTC - 0.2)>0.005 && recorder_osillation<1000
                    if area_scB_cLTC/area_scB_LTC > 0.2
                        counter_Act = counter_Act + 1;
                        % new cMTL
                        tmpA = tmpA - 0.5;
                        tmpB = tmpA * sqrt(diagS(absDirection_sigV123==2) / diagS(absDirection_sigV123==1));
                        tmpX = vers_scB_LTC_ct(:,1);
                        tmpY = vers_scB_LTC_ct(:,2);
                        idx_scB_cLTC = (tmpX.^2 ./ tmpA^2 + tmpY.^2 ./ tmpB^2 - 1)<0;
                        vers_scB_cLTC_ct = vers_scB_LTC_ct(idx_scB_cLTC, :);
                        vers_scB_cLTC_c = transpose(transMat_scB_LTC * vers_scB_cLTC_ct');
                        vers_scB_cLTC = vers_scB_cLTC_c + center_scB_LTC;
                        % new cMTL area
                        faces_scB_cLTC = CartiMorphToolbox.cal_extractFaces_OR(faces_scB_LTC, vers_scB_LTC, vers_scB_cLTC);
                        area_scB_cLTC = sum(CartiMorphToolbox.quant_triMeshArea(vers_scB_LTC, faces_scB_cLTC));
                        % update osillation status
                        recorder_currAct = 1;
                        if counter_Act==1
                            recorder_lastAct=recorder_currAct;
                        end
                        if recorder_currAct~=recorder_lastAct
                            recorder_osillation = recorder_osillation + 1;
                        end
                    else
                        counter_Act = counter_Act + 1;
                        % new cMTL
                        tmpA = tmpA + 0.5;
                        tmpB = tmpA * sqrt(diagS(absDirection_sigV123==2) / diagS(absDirection_sigV123==1));
                        tmpX = vers_scB_LTC_ct(:,1);
                        tmpY = vers_scB_LTC_ct(:,2);
                        idx_scB_cLTC = (tmpX.^2 ./ tmpA^2 + tmpY.^2 ./ tmpB^2 - 1)<0;
                        vers_scB_cLTC_ct = vers_scB_LTC_ct(idx_scB_cLTC, :);
                        vers_scB_cLTC_c = transpose(transMat_scB_LTC * vers_scB_cLTC_ct');
                        vers_scB_cLTC = vers_scB_cLTC_c + center_scB_LTC;
                        % new cMTL area
                        faces_scB_cLTC = CartiMorphToolbox.cal_extractFaces_OR(faces_scB_LTC, vers_scB_LTC, vers_scB_cLTC);
                        area_scB_cLTC = sum(CartiMorphToolbox.quant_triMeshArea(vers_scB_LTC, faces_scB_cLTC));
                        % update osillation status
                        recorder_currAct = -1;
                        if counter_Act==1
                            recorder_lastAct=recorder_currAct;
                        end
                        if recorder_currAct~=recorder_lastAct
                            recorder_osillation = recorder_osillation + 1;
                        end
                    end
                end
                subs_scB_cLTC = round(vers_scB_cLTC ./ size_voxel);
                % ---------------------------------------------------------


                % ---------------------------------------------------------
                % Locate aMTC, pMTC, iMTC, eMTC
                % ---------------------------------------------------------
                % centered and translated non-cMTC
                vers_scB_noncMTC_ct = vers_scB_MTC_ct(~idx_scB_cMTC, :);
                % lTC centered to the centroid of mTC and translated to eigen-vectors of mTC
                center_scB_LTC_c2MTC = center_scB_LTC - center_scB_MTC;
                center_scB_LTC_c2MTC_t = transpose(transMat_scB_MTC \ center_scB_LTC_c2MTC');
                % define cutting line 1 in x-y plane
                a1 = 45;
                transMat_scB_MTC_U1 = [cosd(a1), -sind(a1), 0; sind(a1), cosd(a1), 0; 0, 0, 1];
                vect1_scB_MTC2LTC_t = transMat_scB_MTC_U1 * center_scB_LTC_c2MTC_t';
                k1_scB_MTC = vect1_scB_MTC2LTC_t(2) / vect1_scB_MTC2LTC_t(1);
                % define cutting line 2 in x-y plane
                a2 = -45;
                transMat_scB_MTC_U2 = [cosd(a2), -sind(a2), 0; sind(a2), cosd(a2), 0; 0, 0, 1];
                vect2_scB_MTC2LTC_t = transMat_scB_MTC_U2 * center_scB_LTC_c2MTC_t';
                k2_scB_MTC = vect2_scB_MTC2LTC_t(2) / vect2_scB_MTC2LTC_t(1);
                % cut the non-mTC region into 4 clusters (ROIs)
                idx_scB_C1_MTC = vers_scB_noncMTC_ct(:,2) > k1_scB_MTC*vers_scB_noncMTC_ct(:,1) & ...
                    vers_scB_noncMTC_ct(:,2) > k2_scB_MTC*vers_scB_noncMTC_ct(:,1);
                idx_scB_C2_MTC = vers_scB_noncMTC_ct(:,2) < k1_scB_MTC*vers_scB_noncMTC_ct(:,1) & ...
                    vers_scB_noncMTC_ct(:,2) < k2_scB_MTC*vers_scB_noncMTC_ct(:,1);
                idx_scB_C3_MTC = vers_scB_noncMTC_ct(:,2) <= k1_scB_MTC*vers_scB_noncMTC_ct(:,1) & ...
                    vers_scB_noncMTC_ct(:,2) >= k2_scB_MTC*vers_scB_noncMTC_ct(:,1);
                idx_scB_C4_MTC = vers_scB_noncMTC_ct(:,2) >= k1_scB_MTC*vers_scB_noncMTC_ct(:,1) & ...
                    vers_scB_noncMTC_ct(:,2) <= k2_scB_MTC*vers_scB_noncMTC_ct(:,1);
                vers_scB_C1_MTC_ct = vers_scB_noncMTC_ct(idx_scB_C1_MTC, :);
                vers_scB_C2_MTC_ct = vers_scB_noncMTC_ct(idx_scB_C2_MTC, :);
                vers_scB_C3_MTC_ct = vers_scB_noncMTC_ct(idx_scB_C3_MTC, :);
                vers_scB_C4_MTC_ct = vers_scB_noncMTC_ct(idx_scB_C4_MTC, :);
                vers_scB_C1_MTC = transpose(transMat_scB_MTC * vers_scB_C1_MTC_ct') + center_scB_MTC;
                vers_scB_C2_MTC = transpose(transMat_scB_MTC * vers_scB_C2_MTC_ct') + center_scB_MTC;
                vers_scB_C3_MTC = transpose(transMat_scB_MTC * vers_scB_C3_MTC_ct') + center_scB_MTC;
                vers_scB_C4_MTC = transpose(transMat_scB_MTC * vers_scB_C4_MTC_ct') + center_scB_MTC;
                vers_scB_Clusters_MTC = {vers_scB_C1_MTC, vers_scB_C2_MTC, vers_scB_C3_MTC, vers_scB_C4_MTC};
                % assign label to each cluster (assuming RAS+ orientation)
                centerCluster_MTC(1, :) = mean(vers_scB_C1_MTC, 1);
                centerCluster_MTC(2, :) = mean(vers_scB_C2_MTC, 1);
                centerCluster_MTC(3, :) = mean(vers_scB_C3_MTC, 1);
                centerCluster_MTC(4, :) = mean(vers_scB_C4_MTC, 1);
                [~, idx_aMTC] = max(centerCluster_MTC(:, 2));
                [~, idx_pMTC] = min(centerCluster_MTC(:, 2));
                [~, idx_iMTC] = max(centerCluster_MTC(:, 1));
                [~, idx_eMTC] = min(centerCluster_MTC(:, 1));
                vers_scB_aMTC = vers_scB_Clusters_MTC{idx_aMTC};
                vers_scB_pMTC = vers_scB_Clusters_MTC{idx_pMTC};
                vers_scB_iMTC = vers_scB_Clusters_MTC{idx_iMTC};
                vers_scB_eMTC = vers_scB_Clusters_MTC{idx_eMTC};
                subs_scB_aMTC = round(vers_scB_aMTC ./ size_voxel);
                subs_scB_pMTC = round(vers_scB_pMTC ./ size_voxel);
                subs_scB_iMTC = round(vers_scB_iMTC ./ size_voxel);
                subs_scB_eMTC = round(vers_scB_eMTC ./ size_voxel);
                % ---------------------------------------------------------


                % ---------------------------------------------------------
                % Locate aLTC, pLTC, iLTC, eLTC
                % ---------------------------------------------------------
                % centered and translated non-cLTC
                vers_scB_noncLTC_ct = vers_scB_LTC_ct(~idx_scB_cLTC, :);
                % mTC centered to the centroid of lTC and translated to eigen-vectors of lTC
                center_scB_MTC_c2LTC = center_scB_MTC - center_scB_LTC;
                center_scB_MTC_c2LTC_t = transpose(transMat_scB_LTC \ center_scB_MTC_c2LTC');
                % define cutting line 1 in x-y plane
                a1 = 45;
                transMat_scB_LTC_U1 = [cosd(a1), -sind(a1), 0; sind(a1), cosd(a1), 0; 0, 0, 1];
                vect1_scB_LTC2MTC_t = transMat_scB_LTC_U1 * center_scB_MTC_c2LTC_t';
                k1_scB_LTC = vect1_scB_LTC2MTC_t(2) / vect1_scB_LTC2MTC_t(1);
                % define cutting line 2 in x-y plane
                a2 = -45;
                transMat_scB_LTC_U2 = [cosd(a2), -sind(a2), 0; sind(a2), cosd(a2), 0; 0, 0, 1];
                vect2_scB_LTC2MTC_t = transMat_scB_LTC_U2 * center_scB_MTC_c2LTC_t';
                k2_scB_LTC = vect2_scB_LTC2MTC_t(2) / vect2_scB_LTC2MTC_t(1);
                % cut the non-lTC region into 4 clusters (ROIs)
                idx_scB_C1_LTC = vers_scB_noncLTC_ct(:,2) > k1_scB_LTC*vers_scB_noncLTC_ct(:,1) & ...
                    vers_scB_noncLTC_ct(:,2) > k2_scB_LTC*vers_scB_noncLTC_ct(:,1);
                idx_scB_C2_LTC = vers_scB_noncLTC_ct(:,2) < k1_scB_LTC*vers_scB_noncLTC_ct(:,1) & ...
                    vers_scB_noncLTC_ct(:,2) < k2_scB_LTC*vers_scB_noncLTC_ct(:,1);
                idx_scB_C3_LTC = vers_scB_noncLTC_ct(:,2) <= k1_scB_LTC*vers_scB_noncLTC_ct(:,1) & ...
                    vers_scB_noncLTC_ct(:,2) >= k2_scB_LTC*vers_scB_noncLTC_ct(:,1);
                idx_scB_C4_LTC = vers_scB_noncLTC_ct(:,2) >= k1_scB_LTC*vers_scB_noncLTC_ct(:,1) & ...
                    vers_scB_noncLTC_ct(:,2) <= k2_scB_LTC*vers_scB_noncLTC_ct(:,1);
                vers_scB_C1_LTC_ct = vers_scB_noncLTC_ct(idx_scB_C1_LTC, :);
                vers_scB_C2_LTC_ct = vers_scB_noncLTC_ct(idx_scB_C2_LTC, :);
                vers_scB_C3_LTC_ct = vers_scB_noncLTC_ct(idx_scB_C3_LTC, :);
                vers_scB_C4_LTC_ct = vers_scB_noncLTC_ct(idx_scB_C4_LTC, :);
                vers_scB_C1_LTC = transpose(transMat_scB_LTC * vers_scB_C1_LTC_ct') + center_scB_LTC;
                vers_scB_C2_LTC = transpose(transMat_scB_LTC * vers_scB_C2_LTC_ct') + center_scB_LTC;
                vers_scB_C3_LTC = transpose(transMat_scB_LTC * vers_scB_C3_LTC_ct') + center_scB_LTC;
                vers_scB_C4_LTC = transpose(transMat_scB_LTC * vers_scB_C4_LTC_ct') + center_scB_LTC;
                vers_scB_Clusters_LTC = {vers_scB_C1_LTC, vers_scB_C2_LTC, vers_scB_C3_LTC, vers_scB_C4_LTC};
                % assign label to each cluster (assuming RAS+ orientation)
                centerCluster_LTC(1, :) = mean(vers_scB_C1_LTC, 1);
                centerCluster_LTC(2, :) = mean(vers_scB_C2_LTC, 1);
                centerCluster_LTC(3, :) = mean(vers_scB_C3_LTC, 1);
                centerCluster_LTC(4, :) = mean(vers_scB_C4_LTC, 1);
                [~, idx_aLTC] = max(centerCluster_LTC(:, 2));
                [~, idx_pLTC] = min(centerCluster_LTC(:, 2));
                [~, idx_eLTC] = max(centerCluster_LTC(:, 1));
                [~, idx_iLTC] = min(centerCluster_LTC(:, 1));
                vers_scB_aLTC = vers_scB_Clusters_LTC{idx_aLTC};
                vers_scB_pLTC = vers_scB_Clusters_LTC{idx_pLTC};
                vers_scB_iLTC = vers_scB_Clusters_LTC{idx_iLTC};
                vers_scB_eLTC = vers_scB_Clusters_LTC{idx_eLTC};
                subs_scB_aLTC = round(vers_scB_aLTC ./ size_voxel);
                subs_scB_pLTC = round(vers_scB_pLTC ./ size_voxel);
                subs_scB_iLTC = round(vers_scB_iLTC ./ size_voxel);
                subs_scB_eLTC = round(vers_scB_eLTC ./ size_voxel);
                % ---------------------------------------------------------


                %% Parcellate TC
                vers_iC_cMTC = vers_iC_MTC(ismember(subs_iC_MTC, subs_scB_cMTC, 'rows'), :);
                vers_iC_aMTC = vers_iC_MTC(ismember(subs_iC_MTC, subs_scB_aMTC, 'rows'), :);
                vers_iC_pMTC = vers_iC_MTC(ismember(subs_iC_MTC, subs_scB_pMTC, 'rows'), :);
                vers_iC_iMTC = vers_iC_MTC(ismember(subs_iC_MTC, subs_scB_iMTC, 'rows'), :);
                vers_iC_eMTC = vers_iC_MTC(ismember(subs_iC_MTC, subs_scB_eMTC, 'rows'), :);
                vers_iC_cLTC = vers_iC_LTC(ismember(subs_iC_LTC, subs_scB_cLTC, 'rows'), :);
                vers_iC_aLTC = vers_iC_LTC(ismember(subs_iC_LTC, subs_scB_aLTC, 'rows'), :);
                vers_iC_pLTC = vers_iC_LTC(ismember(subs_iC_LTC, subs_scB_pLTC, 'rows'), :);
                vers_iC_iLTC = vers_iC_LTC(ismember(subs_iC_LTC, subs_scB_iLTC, 'rows'), :);
                vers_iC_eLTC = vers_iC_LTC(ismember(subs_iC_LTC, subs_scB_eLTC, 'rows'), :);
                subs_iC_cMTC = subs_iC_MTC(ismember(subs_iC_MTC, subs_scB_cMTC, 'rows'), :);
                subs_iC_aMTC = subs_iC_MTC(ismember(subs_iC_MTC, subs_scB_aMTC, 'rows'), :);
                subs_iC_pMTC = subs_iC_MTC(ismember(subs_iC_MTC, subs_scB_pMTC, 'rows'), :);
                subs_iC_iMTC = subs_iC_MTC(ismember(subs_iC_MTC, subs_scB_iMTC, 'rows'), :);
                subs_iC_eMTC = subs_iC_MTC(ismember(subs_iC_MTC, subs_scB_eMTC, 'rows'), :);
                subs_iC_cLTC = subs_iC_LTC(ismember(subs_iC_LTC, subs_scB_cLTC, 'rows'), :);
                subs_iC_aLTC = subs_iC_LTC(ismember(subs_iC_LTC, subs_scB_aLTC, 'rows'), :);
                subs_iC_pLTC = subs_iC_LTC(ismember(subs_iC_LTC, subs_scB_pLTC, 'rows'), :);
                subs_iC_iLTC = subs_iC_LTC(ismember(subs_iC_LTC, subs_scB_iLTC, 'rows'), :);
                subs_iC_eLTC = subs_iC_LTC(ismember(subs_iC_LTC, subs_scB_eLTC, 'rows'), :);


                %% Extract faces of each subregion
                % TC with recovered full-thickness cartilage loss
                faces_scB_cMTC = CartiMorphToolbox.cal_extractFaces_OR(faces_scB_MTC, subs_scB_MTC, subs_scB_cMTC);
                [Mesh_scB_cMTC.vertices, Mesh_scB_cMTC.faces] = CartiMorphToolbox.remove_unreferenced_vertices(vers_scB_MTC, faces_scB_cMTC);
                faces_scB_aMTC = CartiMorphToolbox.cal_extractFaces_OR(faces_scB_MTC, subs_scB_MTC, subs_scB_aMTC);
                [Mesh_scB_aMTC.vertices, Mesh_scB_aMTC.faces] = CartiMorphToolbox.remove_unreferenced_vertices(vers_scB_MTC, faces_scB_aMTC);
                faces_scB_pMTC = CartiMorphToolbox.cal_extractFaces_OR(faces_scB_MTC, subs_scB_MTC, subs_scB_pMTC);
                [Mesh_scB_pMTC.vertices, Mesh_scB_pMTC.faces] = CartiMorphToolbox.remove_unreferenced_vertices(vers_scB_MTC, faces_scB_pMTC);
                faces_scB_iMTC = CartiMorphToolbox.cal_extractFaces_OR(faces_scB_MTC, subs_scB_MTC, subs_scB_iMTC);
                [Mesh_scB_iMTC.vertices, Mesh_scB_iMTC.faces] = CartiMorphToolbox.remove_unreferenced_vertices(vers_scB_MTC, faces_scB_iMTC);
                faces_scB_eMTC = CartiMorphToolbox.cal_extractFaces_OR(faces_scB_MTC, subs_scB_MTC, subs_scB_eMTC);
                [Mesh_scB_eMTC.vertices, Mesh_scB_eMTC.faces] = CartiMorphToolbox.remove_unreferenced_vertices(vers_scB_MTC, faces_scB_eMTC);
                faces_scB_cLTC = CartiMorphToolbox.cal_extractFaces_OR(faces_scB_LTC, subs_scB_LTC, subs_scB_cLTC);
                [Mesh_scB_cLTC.vertices, Mesh_scB_cLTC.faces] = CartiMorphToolbox.remove_unreferenced_vertices(vers_scB_LTC, faces_scB_cLTC);
                faces_scB_aLTC = CartiMorphToolbox.cal_extractFaces_OR(faces_scB_LTC, subs_scB_LTC, subs_scB_aLTC);
                [Mesh_scB_aLTC.vertices, Mesh_scB_aLTC.faces] = CartiMorphToolbox.remove_unreferenced_vertices(vers_scB_LTC, faces_scB_aLTC);
                faces_scB_pLTC = CartiMorphToolbox.cal_extractFaces_OR(faces_scB_LTC, subs_scB_LTC, subs_scB_pLTC);
                [Mesh_scB_pLTC.vertices, Mesh_scB_pLTC.faces] = CartiMorphToolbox.remove_unreferenced_vertices(vers_scB_LTC, faces_scB_pLTC);
                faces_scB_iLTC = CartiMorphToolbox.cal_extractFaces_OR(faces_scB_LTC, subs_scB_LTC, subs_scB_iLTC);
                [Mesh_scB_iLTC.vertices, Mesh_scB_iLTC.faces] = CartiMorphToolbox.remove_unreferenced_vertices(vers_scB_LTC, faces_scB_iLTC);
                faces_scB_eLTC = CartiMorphToolbox.cal_extractFaces_OR(faces_scB_LTC, subs_scB_LTC, subs_scB_eLTC);
                [Mesh_scB_eLTC.vertices, Mesh_scB_eLTC.faces] = CartiMorphToolbox.remove_unreferenced_vertices(vers_scB_LTC, faces_scB_eLTC);

                % original FC
                if ~isempty(subs_iC_cMTC)
                    faces_iC_cMTC = CartiMorphToolbox.cal_extractFaces_OR(faces_iC_MTC, subs_iC_MTC, subs_iC_cMTC);
                    [Mesh_iC_cMTC.vertices, Mesh_iC_cMTC.faces] = CartiMorphToolbox.remove_unreferenced_vertices(vers_iC_MTC, faces_iC_cMTC);
                else
                    Mesh_iC_cMTC = [];
                end

                if ~isempty(subs_iC_aMTC)
                    faces_iC_aMTC = CartiMorphToolbox.cal_extractFaces_OR(faces_iC_MTC, subs_iC_MTC, subs_iC_aMTC);
                    [Mesh_iC_aMTC.vertices, Mesh_iC_aMTC.faces] = CartiMorphToolbox.remove_unreferenced_vertices(vers_iC_MTC, faces_iC_aMTC);
                else
                    Mesh_iC_aMTC = [];
                end

                if ~isempty(subs_iC_pMTC)
                    faces_iC_pMTC = CartiMorphToolbox.cal_extractFaces_OR(faces_iC_MTC, subs_iC_MTC, subs_iC_pMTC);
                    [Mesh_iC_pMTC.vertices, Mesh_iC_pMTC.faces] = CartiMorphToolbox.remove_unreferenced_vertices(vers_iC_MTC, faces_iC_pMTC);
                else
                    Mesh_iC_pMTC = [];
                end

                if ~isempty(subs_iC_iMTC)
                    faces_iC_iMTC = CartiMorphToolbox.cal_extractFaces_OR(faces_iC_MTC, subs_iC_MTC, subs_iC_iMTC);
                    [Mesh_iC_iMTC.vertices, Mesh_iC_iMTC.faces] = CartiMorphToolbox.remove_unreferenced_vertices(vers_iC_MTC, faces_iC_iMTC);
                else
                    Mesh_iC_iMTC = [];
                end

                if ~isempty(subs_iC_eMTC)
                    faces_iC_eMTC = CartiMorphToolbox.cal_extractFaces_OR(faces_iC_MTC, subs_iC_MTC, subs_iC_eMTC);
                    [Mesh_iC_eMTC.vertices, Mesh_iC_eMTC.faces] = CartiMorphToolbox.remove_unreferenced_vertices(vers_iC_MTC, faces_iC_eMTC);
                else
                    Mesh_iC_eMTC = [];
                end

                if ~isempty(subs_iC_cLTC)
                    faces_iC_cLTC = CartiMorphToolbox.cal_extractFaces_OR(faces_iC_LTC, subs_iC_LTC, subs_iC_cLTC);
                    [Mesh_iC_cLTC.vertices, Mesh_iC_cLTC.faces] = CartiMorphToolbox.remove_unreferenced_vertices(vers_iC_LTC, faces_iC_cLTC);
                else
                    Mesh_iC_cLTC = [];
                end

                if ~isempty(subs_iC_aLTC)
                    faces_iC_aLTC = CartiMorphToolbox.cal_extractFaces_OR(faces_iC_LTC, subs_iC_LTC, subs_iC_aLTC);
                    [Mesh_iC_aLTC.vertices, Mesh_iC_aLTC.faces] = CartiMorphToolbox.remove_unreferenced_vertices(vers_iC_LTC, faces_iC_aLTC);
                else
                    Mesh_iC_aLTC = [];
                end

                if ~isempty(subs_iC_pLTC)
                    faces_iC_pLTC = CartiMorphToolbox.cal_extractFaces_OR(faces_iC_LTC, subs_iC_LTC, subs_iC_pLTC);
                    [Mesh_iC_pLTC.vertices, Mesh_iC_pLTC.faces] = CartiMorphToolbox.remove_unreferenced_vertices(vers_iC_LTC, faces_iC_pLTC);
                else
                    Mesh_iC_pLTC = [];
                end

                if ~isempty(subs_iC_iLTC)
                    faces_iC_iLTC = CartiMorphToolbox.cal_extractFaces_OR(faces_iC_LTC, subs_iC_LTC, subs_iC_iLTC);
                    [Mesh_iC_iLTC.vertices, Mesh_iC_iLTC.faces] = CartiMorphToolbox.remove_unreferenced_vertices(vers_iC_LTC, faces_iC_iLTC);
                else
                    Mesh_iC_iLTC = [];
                end

                if ~isempty(subs_iC_eLTC)
                    faces_iC_eLTC = CartiMorphToolbox.cal_extractFaces_OR(faces_iC_LTC, subs_iC_LTC, subs_iC_eLTC);
                    [Mesh_iC_eLTC.vertices, Mesh_iC_eLTC.faces] = CartiMorphToolbox.remove_unreferenced_vertices(vers_iC_LTC, faces_iC_eLTC);
                else
                    Mesh_iC_eLTC = [];
                end


                %% Combine results
                % Vertices
                % TC with recovered full-thickness cartilage loss
                versSubReg_scB_TC.cMTC = vers_scB_cMTC;
                versSubReg_scB_TC.aMTC = vers_scB_aMTC;
                versSubReg_scB_TC.pMTC = vers_scB_pMTC;
                versSubReg_scB_TC.cLTC = vers_scB_cLTC;
                versSubReg_scB_TC.aLTC = vers_scB_aLTC;
                versSubReg_scB_TC.pLTC = vers_scB_pLTC;
                % original TC
                versSubReg_iC_TC.cMTC = vers_iC_cMTC;
                versSubReg_iC_TC.aMTC = vers_iC_aMTC;
                versSubReg_iC_TC.pMTC = vers_iC_pMTC;
                versSubReg_iC_TC.cLTC = vers_iC_cLTC;
                versSubReg_iC_TC.aLTC = vers_iC_aLTC;
                versSubReg_iC_TC.pLTC = vers_iC_pLTC;

                % Mesh
                % TC with recovered full-thickness cartilage loss
                meshSubReg_scB_TC.cMTC = Mesh_scB_cMTC;
                meshSubReg_scB_TC.aMTC = Mesh_scB_aMTC;
                meshSubReg_scB_TC.pMTC = Mesh_scB_pMTC;
                meshSubReg_scB_TC.cLTC = Mesh_scB_cLTC;
                meshSubReg_scB_TC.aLTC = Mesh_scB_aLTC;
                meshSubReg_scB_TC.pLTC = Mesh_scB_pLTC;
                % original TC
                meshSubReg_iC_TC.cMTC = Mesh_iC_cMTC;
                meshSubReg_iC_TC.aMTC = Mesh_iC_aMTC;
                meshSubReg_iC_TC.pMTC = Mesh_iC_pMTC;
                meshSubReg_iC_TC.cLTC = Mesh_iC_cLTC;
                meshSubReg_iC_TC.aLTC = Mesh_iC_aLTC;
                meshSubReg_iC_TC.pLTC = Mesh_iC_pLTC;

                switch knee_side
                    case {'right', 'Right', 'R'}
                        % Vertices
                        % TC with recovered full-thickness cartilage loss
                        versSubReg_scB_TC.iMTC = vers_scB_iMTC;
                        versSubReg_scB_TC.eMTC = vers_scB_eMTC;
                        versSubReg_scB_TC.iLTC = vers_scB_iLTC;
                        versSubReg_scB_TC.eLTC = vers_scB_eLTC;
                        % original TC
                        versSubReg_iC_TC.iMTC = vers_iC_iMTC;
                        versSubReg_iC_TC.eMTC = vers_iC_eMTC;
                        versSubReg_iC_TC.iLTC = vers_iC_iLTC;
                        versSubReg_iC_TC.eLTC = vers_iC_eLTC;

                        % Mesh
                        % TC with recovered full-thickness cartilage loss
                        meshSubReg_scB_TC.iMTC = Mesh_scB_iMTC;
                        meshSubReg_scB_TC.eMTC = Mesh_scB_eMTC;
                        meshSubReg_scB_TC.iLTC = Mesh_scB_iLTC;
                        meshSubReg_scB_TC.eLTC = Mesh_scB_eLTC;
                        % original TC
                        meshSubReg_iC_TC.iMTC = Mesh_iC_iMTC;
                        meshSubReg_iC_TC.eMTC = Mesh_iC_eMTC;
                        meshSubReg_iC_TC.iLTC = Mesh_iC_iLTC;
                        meshSubReg_iC_TC.eLTC = Mesh_iC_eLTC;

                    case {'left', 'Left', 'L'} % swapping the interior and exterior compartments
                        % Vertices
                        % TC with recovered full-thickness cartilage loss
                        versSubReg_scB_TC.iMTC = vers_scB_eMTC;
                        versSubReg_scB_TC.eMTC = vers_scB_iMTC;
                        versSubReg_scB_TC.iLTC = vers_scB_eLTC;
                        versSubReg_scB_TC.eLTC = vers_scB_iLTC;
                        % original TC
                        versSubReg_iC_TC.iMTC = vers_iC_eMTC;
                        versSubReg_iC_TC.eMTC = vers_iC_iMTC;
                        versSubReg_iC_TC.iLTC = vers_iC_eLTC;
                        versSubReg_iC_TC.eLTC = vers_iC_iLTC;

                        % Mesh
                        % TC with recovered full-thickness cartilage loss
                        meshSubReg_scB_TC.iMTC = Mesh_scB_eMTC;
                        meshSubReg_scB_TC.eMTC = Mesh_scB_iMTC;
                        meshSubReg_scB_TC.iLTC = Mesh_scB_eLTC;
                        meshSubReg_scB_TC.eLTC = Mesh_scB_iLTC;
                        % original TC
                        meshSubReg_iC_TC.iMTC = Mesh_iC_eMTC;
                        meshSubReg_iC_TC.eMTC = Mesh_iC_iMTC;
                        meshSubReg_iC_TC.iLTC = Mesh_iC_eLTC;
                        meshSubReg_iC_TC.eLTC = Mesh_iC_iLTC;
                end

            end

        end


        function facesArea = quant_triMeshArea(vertices, faces)
            % ==============================================================================
            % FUNCTION:
            %     Calculate area of triangular mesh.
            %
            % INPUT:
            %     -
            %
            % OUTPUT:
            %     -
            % ------------------------------------------------------------------------------
            % Matlab Version: 2019b or later
            %
            % Last updated on: 18-May-2022
            %
            % Author:
            % Yongcheng YAO (yao_yongcheng@link.cuhk.edu.hk)
            % Department of Imaging and Interventional Radiology,
            % Chinese University of Hong Kong (CUHK)
            %
            % Copyright 2020 Yongcheng YAO
            % ------------------------------------------------------------------------------
            % ==============================================================================

            vector1 = vertices(faces(:, 2), :) - vertices(faces(:, 1), :);
            vector2 = vertices(faces(:, 3), :) - vertices(faces(:, 1), :);
            crossV1V2 = cross(vector1, vector2, 2);
            facesArea = 0.5 * vecnorm(crossV1V2, 2, 2);
        end


        function MorphQuant = wrapper_MorphQuant( ...
                Map_FC, ...
                Map_mTC, ...
                Map_lTC, ...
                Mesh_iC_FC, ...
                Mesh_iC_mTC, ...
                Mesh_iC_lTC, ...
                vers_subreg_FC, ...
                vers_subreg_FC_f, ...
                mesh_subreg_FC, ...
                mesh_subreg_FC_f, ...
                vers_subreg_TC, ...
                vers_subreg_TC_f, ...
                mesh_subreg_TC, ...
                mesh_subreg_TC_f,...
                Mask_FC,...
                Mask_mTC,...
                Mask_lTC,...
                voxSize,...
                dir_case,...
                fileName_woExt)
            % ==============================================================================
            % FUNCTION:
            %     Cartilage thickness quantification for each subregion.
            %
            % INPUT:
            %     -
            %
            % OUTPUT:
            %     -
            % ------------------------------------------------------------------------------
            % Matlab Version: 2019b or later
            %
            % Last updated on: 15-May-2022
            %
            % Author:
            % Yongcheng YAO (yao_yongcheng@link.cuhk.edu.hk)
            % Department of Imaging and Interventional Radiology,
            % Chinese University of Hong Kong (CUHK)
            %
            % Copyright 2020 Yongcheng YAO
            % ------------------------------------------------------------------------------
            % ==============================================================================

            % ROI names from label 1 to 20
            ROInames = {'aMFC', 'ecMFC', 'ccMFC', 'icMFC', 'pMFC', 'aLFC', 'ecLFC', 'ccLFC', 'icLFC', 'pLFC',...
                'aMTC', 'eMTC', 'pMTC', 'iMTC', 'cMTC',...
                'aLTC', 'eLTC', 'pLTC', 'iLTC', 'cLTC'};
            ROInames_FC = {'aMFC', 'ecMFC', 'ccMFC', 'icMFC', 'pMFC', 'aLFC', 'ecLFC', 'ccLFC', 'icLFC', 'pLFC'};
            ROInames_mTC = {'aMTC', 'eMTC', 'pMTC', 'iMTC', 'cMTC'};
            ROInames_lTC = {'aLTC', 'eLTC', 'pLTC', 'iLTC', 'cLTC'};


            % ----------------------------------------------
            % [FCL, surface area, and mean thickness]
            % ----------------------------------------------
            % regional quantification (FC)
            for i = 1:length(ROInames_FC)
                i_ROIname = ROInames_FC{i};
                MorphQuant.(i_ROIname) = CartiMorphToolbox.wrapper_regionalMorphQuant( ...
                    Map_FC, ...
                    Mesh_iC_FC, ...
                    vers_subreg_FC.(i_ROIname), ...
                    mesh_subreg_FC.(i_ROIname), ...
                    vers_subreg_FC_f.(i_ROIname), ...
                    mesh_subreg_FC_f.(i_ROIname));
            end

            % regional quantification (mTC)
            for i = 1:length(ROInames_mTC)
                i_ROIname = ROInames_mTC{i};
                MorphQuant.(i_ROIname) = CartiMorphToolbox.wrapper_regionalMorphQuant( ...
                    Map_mTC, ...
                    Mesh_iC_mTC, ...
                    vers_subreg_TC.(i_ROIname), ...
                    mesh_subreg_TC.(i_ROIname), ...
                    vers_subreg_TC_f.(i_ROIname), ...
                    mesh_subreg_TC_f.(i_ROIname));
            end


            % regional quantification (lTC)
            for i = 1:length(ROInames_lTC)
                i_ROIname = ROInames_lTC{i};
                MorphQuant.(i_ROIname) = CartiMorphToolbox.wrapper_regionalMorphQuant( ...
                    Map_lTC, ...
                    Mesh_iC_lTC, ...
                    vers_subreg_TC.(i_ROIname), ...
                    mesh_subreg_TC.(i_ROIname), ...
                    vers_subreg_TC_f.(i_ROIname), ...
                    mesh_subreg_TC_f.(i_ROIname));
            end
            % ----------------------------------------------


            % ----------------------------------------------
            % [volume]
            % ----------------------------------------------
            % Initialize an empty cell array to store the struct2cell outputs
            struct2cell_outputs = {};
            % Initialize an empty cell array to store the fieldnames
            fieldnames_outputs = {};
            % Check if vers_subreg_FC is not empty
            if ~isempty(vers_subreg_FC)
                struct2cell_outputs = [struct2cell_outputs; struct2cell(vers_subreg_FC)];
                fieldnames_outputs = [fieldnames_outputs; fieldnames(vers_subreg_FC)];
            end
            % Check if vers_subreg_TC is not empty
            if ~isempty(vers_subreg_TC)
                struct2cell_outputs = [struct2cell_outputs; struct2cell(vers_subreg_TC)];
                fieldnames_outputs = [fieldnames_outputs; fieldnames(vers_subreg_TC)];
            end
            % Combine the struct2cell outputs and fieldnames using cell2struct
            vers_subregions = cell2struct(struct2cell_outputs, fieldnames_outputs);


            % convert surface parcellation to volume parcellation for the interior cartilage surface
            mask_subregions_iC = zeros(size(Mask_FC), 'uint8');
            for i = 1:length(ROInames)
                if isfield(vers_subregions, ROInames{i})
                    i_vers = vers_subregions.(ROInames{i});
                    if ~isempty(i_vers)
                        i_subs = round(i_vers ./ voxSize);
                        i_idx = sub2ind(size(Mask_FC), i_subs(:,1), i_subs(:,2), i_subs(:,3));
                        mask_subregions_iC(i_idx) = i;
                    end
                end
            end

            % create volume parcellation (3D mask)
            mask_subregions_FC = CartiMorphToolbox.cal_mapSeg2Mask(Mask_FC, mask_subregions_iC .* Mask_FC);
            mask_subregions_mTC = CartiMorphToolbox.cal_mapSeg2Mask(Mask_mTC, mask_subregions_iC .* Mask_mTC);
            mask_subregions_lTC = CartiMorphToolbox.cal_mapSeg2Mask(Mask_lTC, mask_subregions_iC .* Mask_lTC);
            mask_subregions_FTC = mask_subregions_FC + mask_subregions_mTC + mask_subregions_lTC;

            % save volume parcellation (3D mask)
            cartilageSubregions = mask_subregions_FTC;
            file_out = fullfile(dir_case, "CartilageSubregion", strcat(fileName_woExt, '_subregions.mat'));
            save(file_out, 'cartilageSubregions');

            % save voxel size
            voxelSize = voxSize;
            file_out = fullfile(dir_case, "CartilageSubregion", strcat(fileName_woExt, '_voxelSize.mat'));
            save(file_out, 'voxelSize');

            % ----------------------------------
            % save volume parcellation (3D mesh)
            % ----------------------------------
            ROILabels = 1:20;
            for i=1:length(ROILabels)
                % ROIs
                i_ROIName = ROInames{i};
                i_ROILabel = ROILabels(i);
                % get boundary mesh
                i_mask_subregion = mask_subregions_FTC==i_ROILabel;
                i_FV = CartiMorphToolbox.cal_getBoundaryMesh(i_mask_subregion);
                if ~isempty(i_FV.vertices)
                    % convert subscripts to coordinates
                    i_FV.vertices = i_FV.vertices .* voxSize;
                    % smooth the mesh of the cartilage surface (toolbox: matGeom)
                    [i_sFV.vertices, i_sFV.faces] = CartiMorphToolbox.smoothMesh(i_FV.vertices, i_FV.faces, 2);
                else
                    i_sFV.vertices = [];
                    i_sFV.faces = [];
                end
                % save mesh
                sMesh_subregions.(i_ROIName) = i_sFV;
                Mesh_subregions.(i_ROIName) = i_FV;
            end
            % plot the unsmoothed subregions
            filePath_fig = fullfile(dir_case, "CartilageSubregion_Figure", strcat(fileName_woExt, '_3DSubregions_FTC.fig'));
            filePath_emptySurfaceIdx = fullfile(dir_case, "CartilageSubregion_Figure", strcat(fileName_woExt, '_emptySurfaceIdx.mat'));
            CartiMorphToolbox.plot_mesh_subregions3D(Mesh_subregions, filePath_fig, filePath_emptySurfaceIdx);
            % plot the smoothed subregions
            filePath_fig = fullfile(dir_case, "CartilageSubregion_Figure", strcat(fileName_woExt, '_3DSubregions_FTC_smoothed.fig'));
            filePath_emptySurfaceIdx = fullfile(dir_case, "CartilageSubregion_Figure", strcat(fileName_woExt, '_emptySurfaceIdx_smoothed.mat'));
            CartiMorphToolbox.plot_mesh_subregions3D(sMesh_subregions, filePath_fig, filePath_emptySurfaceIdx);
            % ----------------------------------

            % calculate volume
            voxelVolume = norm(voxSize);
            for i = 1:length(ROInames)
                MorphQuant.(ROInames{i}).Volume = voxelVolume * sum(mask_subregions_FTC==i, 'all');
            end
            % ----------------------------------------------
        end


        function plot_mesh_subregions3D(Mesh, filePath_fig, filePath_emptySurfaceIdx)
            % <<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<
            % (the variable is used in CMV only)
            % record the empty surface for CMV
            emptySurfaceIdx = zeros([20,1], 'uint8');
            % <<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<

            f = figure(Visible="off");
            hold on;
            % FC
            if ~isempty(Mesh.aMFC.faces)
                trisurf(Mesh.aMFC.faces, Mesh.aMFC.vertices(:,1), Mesh.aMFC.vertices(:,2), Mesh.aMFC.vertices(:,3),...
                    'FaceColor', '#FAD7A0', 'EdgeAlpha', 0, 'EdgeColor', '#FAD7A0', 'FaceAlpha', 0.5);
            else
                emptySurfaceIdx(1,1) = 1;
            end
            if ~isempty(Mesh.ecMFC.faces)
                trisurf(Mesh.ecMFC.faces, Mesh.ecMFC.vertices(:,1), Mesh.ecMFC.vertices(:,2), Mesh.ecMFC.vertices(:,3),...
                    'FaceColor', '#F1948A', 'EdgeAlpha', 0, 'EdgeColor', '#F1948A', 'FaceAlpha', 0.5);
            else
                emptySurfaceIdx(2,1) = 1;
            end
            if ~isempty(Mesh.ccMFC.faces)
                trisurf(Mesh.ccMFC.faces, Mesh.ccMFC.vertices(:,1), Mesh.ccMFC.vertices(:,2), Mesh.ccMFC.vertices(:,3),...
                    'FaceColor', '#D7BDE2', 'EdgeAlpha', 0, 'EdgeColor', '#D7BDE2', 'FaceAlpha', 0.5);
            else
                emptySurfaceIdx(3,1) = 1;
            end
            if ~isempty(Mesh.icMFC.faces)
                trisurf(Mesh.icMFC.faces, Mesh.icMFC.vertices(:,1), Mesh.icMFC.vertices(:,2), Mesh.icMFC.vertices(:,3),...
                    'FaceColor', '#D6EAF8', 'EdgeAlpha', 0, 'EdgeColor', '#D6EAF8', 'FaceAlpha', 0.5);
            else
                emptySurfaceIdx(4,1) = 1;
            end
            if ~isempty(Mesh.pMFC.faces)
                trisurf(Mesh.pMFC.faces, Mesh.pMFC.vertices(:,1), Mesh.pMFC.vertices(:,2), Mesh.pMFC.vertices(:,3),...
                    'FaceColor', '#7DCEA0', 'EdgeAlpha', 0, 'EdgeColor', '#7DCEA0', 'FaceAlpha', 0.5);
            else
                emptySurfaceIdx(5,1) = 1;
            end
            if ~isempty(Mesh.aLFC.faces)
                trisurf(Mesh.aLFC.faces, Mesh.aLFC.vertices(:,1), Mesh.aLFC.vertices(:,2), Mesh.aLFC.vertices(:,3),...
                    'FaceColor', '#F39C12', 'EdgeAlpha', 0, 'EdgeColor', '#F39C12', 'FaceAlpha', 0.5);
            else
                emptySurfaceIdx(6,1) = 1;
            end
            if ~isempty(Mesh.ecLFC.faces)
                trisurf(Mesh.ecLFC.faces, Mesh.ecLFC.vertices(:,1), Mesh.ecLFC.vertices(:,2), Mesh.ecLFC.vertices(:,3),...
                    'FaceColor', '#E74C3C', 'EdgeAlpha', 0, 'EdgeColor', '#E74C3C', 'FaceAlpha', 0.5);
            else
                emptySurfaceIdx(7,1) = 1;
            end
            if ~isempty(Mesh.ccLFC.faces)
                trisurf(Mesh.ccLFC.faces, Mesh.ccLFC.vertices(:,1), Mesh.ccLFC.vertices(:,2), Mesh.ccLFC.vertices(:,3),...
                    'FaceColor', '#9B59B6', 'EdgeAlpha', 0, 'EdgeColor', '#9B59B6', 'FaceAlpha', 0.5);
            else
                emptySurfaceIdx(8,1) = 1;
            end
            if ~isempty(Mesh.icLFC.faces)
                trisurf(Mesh.icLFC.faces, Mesh.icLFC.vertices(:,1), Mesh.icLFC.vertices(:,2), Mesh.icLFC.vertices(:,3),...
                    'FaceColor', '#3498DB', 'EdgeAlpha', 0, 'EdgeColor', '#3498DB', 'FaceAlpha', 0.5);
            else
                emptySurfaceIdx(9,1) = 1;
            end
            if ~isempty(Mesh.pLFC.faces)
                trisurf(Mesh.pLFC.faces, Mesh.pLFC.vertices(:,1), Mesh.pLFC.vertices(:,2), Mesh.pLFC.vertices(:,3),...
                    'FaceColor', '#27AE60', 'EdgeAlpha', 0, 'EdgeColor', '#27AE60', 'FaceAlpha', 0.5);
            else
                emptySurfaceIdx(10,1) = 1;
            end
            % mTC
            if ~isempty(Mesh.aMTC.faces)
                trisurf(Mesh.aMTC.faces, Mesh.aMTC.vertices(:,1), Mesh.aMTC.vertices(:,2), Mesh.aMTC.vertices(:,3),...
                    'FaceColor', '#FFDA5E', 'EdgeAlpha', 0, 'EdgeColor', '#FFDA5E', 'FaceAlpha', 0.5);
            else
                emptySurfaceIdx(11,1) = 1;
            end
            if ~isempty(Mesh.eMTC.faces)
                trisurf(Mesh.eMTC.faces, Mesh.eMTC.vertices(:,1), Mesh.eMTC.vertices(:,2), Mesh.eMTC.vertices(:,3),...
                    'FaceColor', '#EA6975', 'EdgeAlpha', 0, 'EdgeColor', '#EA6975', 'FaceAlpha', 0.5);
            else
                emptySurfaceIdx(12,1) = 1;
            end
            if ~isempty(Mesh.pMTC.faces)
                trisurf(Mesh.pMTC.faces, Mesh.pMTC.vertices(:,1), Mesh.pMTC.vertices(:,2), Mesh.pMTC.vertices(:,3),...
                    'FaceColor', '#57AB89', 'EdgeAlpha', 0, 'EdgeColor', '#57AB89', 'FaceAlpha', 0.5);
            else
                emptySurfaceIdx(13,1) = 1;
            end
            if ~isempty(Mesh.iMTC.faces)
                trisurf(Mesh.iMTC.faces, Mesh.iMTC.vertices(:,1), Mesh.iMTC.vertices(:,2), Mesh.iMTC.vertices(:,3),...
                    'FaceColor', '#75AAD6', 'EdgeAlpha', 0, 'EdgeColor', '#75AAD6', 'FaceAlpha', 0.5);
            else
                emptySurfaceIdx(14,1) = 1;
            end
            if ~isempty(Mesh.cMTC.faces)
                trisurf(Mesh.cMTC.faces, Mesh.cMTC.vertices(:,1), Mesh.cMTC.vertices(:,2), Mesh.cMTC.vertices(:,3),...
                    'FaceColor', '#F6D3DE', 'EdgeAlpha', 0, 'EdgeColor', '#F6D3DE', 'FaceAlpha', 0.5);
            else
                emptySurfaceIdx(15,1) = 1;
            end
            % lTC
            if ~isempty(Mesh.aLTC.faces)
                trisurf(Mesh.aLTC.faces, Mesh.aLTC.vertices(:,1), Mesh.aLTC.vertices(:,2), Mesh.aLTC.vertices(:,3),...
                    'FaceColor', '#FFEB11', 'EdgeAlpha', 0, 'EdgeColor', '#FFEB11', 'FaceAlpha', 0.5);
            else
                emptySurfaceIdx(16,1) = 1;
            end
            if ~isempty(Mesh.eLTC.faces)
                trisurf(Mesh.eLTC.faces, Mesh.eLTC.vertices(:,1), Mesh.eLTC.vertices(:,2), Mesh.eLTC.vertices(:,3),...
                    'FaceColor', '#D71729', 'EdgeAlpha', 0, 'EdgeColor', '#D71729', 'FaceAlpha', 0.5);
            else
                emptySurfaceIdx(17,1) = 1;
            end
            if ~isempty(Mesh.pLTC.faces)
                trisurf(Mesh.pLTC.faces, Mesh.pLTC.vertices(:,1), Mesh.pLTC.vertices(:,2), Mesh.pLTC.vertices(:,3),...
                    'FaceColor', '#8BE071', 'EdgeAlpha', 0, 'EdgeColor', '#8BE071', 'FaceAlpha', 0.5);
            else
                emptySurfaceIdx(18,1) = 1;
            end
            if ~isempty(Mesh.iLTC.faces)
                trisurf(Mesh.iLTC.faces, Mesh.iLTC.vertices(:,1), Mesh.iLTC.vertices(:,2), Mesh.iLTC.vertices(:,3),...
                    'FaceColor', '#1076E2', 'EdgeAlpha', 0, 'EdgeColor', '#1076E2', 'FaceAlpha', 0.5);
            else
                emptySurfaceIdx(19,1) = 1;
            end
            if ~isempty(Mesh.cLTC.faces)
                trisurf(Mesh.cLTC.faces, Mesh.cLTC.vertices(:,1), Mesh.cLTC.vertices(:,2), Mesh.cLTC.vertices(:,3),...
                    'FaceColor', '#B08FC7', 'EdgeAlpha', 0, 'EdgeColor', '#B08FC7', 'FaceAlpha', 0.5);
            else
                emptySurfaceIdx(20,1) = 1;
            end

            % save figure to file
            savefig(f, filePath_fig, 'compact');
            delete(f);

            % save the enmpy surface indicator
            save(filePath_emptySurfaceIdx, 'emptySurfaceIdx');
        end


        function regionalMorphQuant = wrapper_regionalMorphQuant( ...
                Map, ...
                Mesh, ...
                vers_iC, ...
                mesh_iC, ...
                vers_scB, ...
                mesh_scB)
            % ==============================================================================
            % FUNCTION:
            %     Cartilage thickness quantification for a subregion.
            %
            % INPUT:
            %     -
            %
            % OUTPUT:
            %     -
            % ------------------------------------------------------------------------------
            % Matlab Version: 2019b or later
            %
            % Last updated on: 15-May-2022
            %
            % Author:
            % Yongcheng YAO (yao_yongcheng@link.cuhk.edu.hk)
            % Department of Imaging and Interventional Radiology,
            % Chinese University of Hong Kong (CUHK)
            %
            % Copyright 2020 Yongcheng YAO
            % ------------------------------------------------------------------------------
            % ==============================================================================


            % cAB: area of subchondral bone covered by cartilage
            if ~isempty(mesh_iC)
                area_iC = sum(CartiMorphToolbox.quant_triMeshArea(mesh_iC.vertices, mesh_iC.faces));
            else
                area_iC = 0;
            end
            regionalMorphQuant.cAB = area_iC;

            % tAB: total area of subchondral bone
            if ~isempty(mesh_scB)
                area_scB = sum(CartiMorphToolbox.quant_triMeshArea(mesh_scB.vertices, mesh_scB.faces));
            else
                area_scB = 0;
            end
            regionalMorphQuant.tAB = area_scB;

            % dABp: % area of subchondral bone denuded of cartilage
            if area_scB~=0
                tmp_dABp = (area_scB - area_iC) / area_scB;
                if tmp_dABp<0
                    regionalMorphQuant.dABp = 0;
                else
                    regionalMorphQuant.dABp = tmp_dABp;
                end
            else
                regionalMorphQuant.dABp = nan;
            end

            % ThCcAB_aMe: mean cartilage thickness over cAB
            if ~isempty(vers_iC)
                regionalMorphQuant.ThCcAB_aMe = CartiMorphToolbox.quant_ThCcAB_aMe(Map, Mesh, vers_iC);
            else
                regionalMorphQuant.ThCcAB_aMe = 0;
            end

            % ThCtAB_aMe: mean cartilage thickness over tAB
            % ThCtAB_aMav: mean of the 1% of highest thickness values over tAB
            % ThCtAB_aMiv: mean of the 1% of lowest thickness values over tAB
            % ThCtAB_aSD: standard deviation (SD) of cartilage thickness over tAB
            % ThCtAB_aCV: coefficient of variation (CV) of cartilage thickness over tAB
            if ~isempty(vers_iC)
                [regionalMorphQuant.ThCtAB_aMav,...
                    regionalMorphQuant.ThCtAB_aMe,...
                    regionalMorphQuant.ThCtAB_aMiv,...
                    regionalMorphQuant.ThCtAB_aSD,...
                    regionalMorphQuant.ThCtAB_aCV]...
                    = CartiMorphToolbox.quant_ThCtAB(Map, Mesh, vers_iC, vers_scB);
            else
                regionalMorphQuant.ThCtAB_aMav = 0;
                regionalMorphQuant.ThCtAB_aMe = 0;
                regionalMorphQuant.ThCtAB_aMiv = 0;
                regionalMorphQuant.ThCtAB_aSD = 0;
                regionalMorphQuant.ThCtAB_aCV = 0;
            end

        end


        function ThCcAB_aMe = quant_ThCcAB_aMe(Map, Mesh, vers_iC)
            % ==============================================================================
            % FUNCTION:
            %     Cartilage thickness quantification: ThCcAB_aMe
            %
            % INPUT:
            %     - Map: (nv, 4), thickness map of the whole cartilage
            %     - Mesh: bone-cartilage interface mesh
            %     - vers_iC: vertices of bone-cartilage interface subregion
            %
            % OUTPUT:
            %     - ThCcAB_aMe: mean cartilage thickness over cAB
            % ------------------------------------------------------------------------------
            % Matlab Version: 2019b or later
            %
            % Last updated on: 15-May-2022
            %
            % Author:
            % Yongcheng YAO (yao_yongcheng@link.cuhk.edu.hk)
            % Department of Imaging and Interventional Radiology,
            % Chinese University of Hong Kong (CUHK)
            %
            % Copyright 2020 Yongcheng YAO
            % ------------------------------------------------------------------------------
            % ==============================================================================

            % find vertices of cartilage
            idx_vers_subregion = ismember(Mesh.vertices, vers_iC, "rows");

            % mean cartilage thickness over cAB
            ThCcAB_aMe = mean(Map(idx_vers_subregion, 4));

        end


        function [aMav, aMe, aMiv, aSD, aCV] = quant_ThCtAB(Map, Mesh, vers_iC, vers_scB)
            % ==============================================================================
            % FUNCTION:
            %     Cartilage thickness quantification.
            %
            % INPUT:
            %     - Map: (nv, 4), thickness map of the whole cartilage
            %     - Mesh: bone-cartilage interface mesh
            %     - vers_iC: vertices of bone-cartilage interface subregion
            %     - vers_scB: vertices of subchondral bone subregion
            %
            % OUTPUT:
            %     - aMav: mean of the 1% of highest thickness values over a given area
            %     - aMe: mean cartilage thickness over tAB
            %     - aMiv: mean of the 1% of lowest thickness values over a given area
            %     - aSD: standard deviation (SD) of cartilage thickness over tAB
            %     - aCV: coefficient of variation (CV) of cartilage thickness over tAB
            % ------------------------------------------------------------------------------
            % Matlab Version: 2019b or later
            %
            % Last updated on: 15-May-2022
            %
            % Author:
            % Yongcheng YAO (yao_yongcheng@link.cuhk.edu.hk)
            % Department of Imaging and Interventional Radiology,
            % Chinese University of Hong Kong (CUHK)
            %
            % Copyright 2020 Yongcheng YAO
            % ------------------------------------------------------------------------------
            % ==============================================================================

            % find vertices of cartilage
            idx_vers_subregion = ismember(Mesh.vertices, vers_iC, "rows");

            % number of vertices of subchondral bone denuded of cartilage
            num_zeros = sum(~ismember(vers_scB, vers_iC, "rows"));

            % new thickness values pool
            thickness_zeroPadded = cat(1, Map(idx_vers_subregion, 4), zeros(num_zeros, 1));


            %% Quantification
            % mean cartilage thickness over tAB
            aMe = mean(thickness_zeroPadded);

            % aSD: standard deviation (SD) of cartilage thickness over tAB
            aSD = std(thickness_zeroPadded);

            % aCV: coefficient of variation (CV) of cartilage thickness over tAB
            aCV = aSD / aMe;

            % mean of the 1% of lowest thickness values over a given area
            thickness_zeroPadded_ascend = sort(thickness_zeroPadded, 'ascend');
            num_avg = round(size(thickness_zeroPadded_ascend,1) * 0.1);
            aMiv = mean(thickness_zeroPadded_ascend(1:num_avg));

            % mean of the 1% of highest thickness values over a given area
            thickness_zeroPadded_descend = sort(thickness_zeroPadded, 'descend');
            num_avg = round(size(thickness_zeroPadded_descend,1) * 0.1);
            aMav = mean(thickness_zeroPadded_descend(1:num_avg));

        end


        function volume = quant_Volume(Mask, vers_iC, voxSize)
            % ==============================================================================
            % FUNCTION:
            %     Cartilage thickness quantification.
            %
            % INPUT:
            %     - Mask: 3D mask of cartilage (the cartilage should be one of the FC, mTC and lTC)
            %     - vers_iC: vertices on the interior cartilage surface --> this is surface parcellation
            %     - voxSize: voxel size
            %
            % OUTPUT:
            %     - volume: volume of subregion
            % ------------------------------------------------------------------------------
            % Matlab Version: 2019b or later
            %
            % Last updated on: 11-Jun-2023
            %
            % Author:
            % Yongcheng YAO (yao_yongcheng@link.cuhk.edu.hk)
            % Department of Imaging and Interventional Radiology,
            % Chinese University of Hong Kong (CUHK)
            %
            % Copyright 2020 Yongcheng YAO
            % ------------------------------------------------------------------------------
            % ==============================================================================

            % get mask of subregion (convert surface parcellation to volume parcellation)
            mask_subRegion = zeros(size(Mask), 'uint8'); % --> this is volume parcellation
            mask_iC = zeros(size(Mask), 'uint8'); % --> this is mask of interior cartilage surface
            if ~isempty(vers_iC)
                subs = round(vers_iC ./ voxSize);
                idx = sub2ind(size(Mask), subs(:,1), subs(:,2), subs(:,3));
                mask_iC(idx) = 1;
            end


            % calculate the volume of subregion
            voxelVolume = voxSize(1) * voxSize(2) * voxSize(3);
            volume = voxelVolume * sum(mask_subRegion, 'all');
        end


        function seg_out = cal_mapSeg2Mask(mask_dest, seg_src)
            % ==============================================================================
            % FUNCTION:
            %     Map segmentation (i.e., label) to mask (i.e. binary image).
            %
            % INPUT:
            %     -
            %
            % OUTPUT:
            %     -
            %
            % ------------------------------------------------------------------------------
            % Matlab Version: 2019b or later
            %
            % Last updated on: 27-Jun-2022
            %
            % Author:
            % Yongcheng YAO (yao_yongcheng@link.cuhk.edu.hk)
            % Department of Imaging and Interventional Radiology,
            % Chinese University of Hong Kong (CUHK)
            %
            % Copyright 2020 Yongcheng YAO
            % ------------------------------------------------------------------------------
            % ==============================================================================

            if sum(mask_dest, 'all')==0 || sum(seg_src, 'all')==0
                seg_out = zeros(size(mask_dest), class(mask_dest));
            else
                size_img = size(mask_dest);
                idx_dest = find(mask_dest);
                [sub1_dest, sub2_dest, sub3_dest] = ind2sub(size_img, idx_dest);
                subs_dest = [sub1_dest, sub2_dest, sub3_dest];
                [sub1_src, sub2_src, sub3_src] = ind2sub(size_img, find(seg_src));
                subs_src = [sub1_src, sub2_src, sub3_src];
                [~, tmpIdx] = pdist2(subs_src, subs_dest, 'euclidean', 'Smallest', 1);
                subs_nearVox_src = subs_src(tmpIdx, :);
                idx_nearVox_src = sub2ind(size_img, subs_nearVox_src(:,1),...
                    subs_nearVox_src(:,2), subs_nearVox_src(:,3));
                seg_out = zeros(size_img, 'uint8');
                seg_out(idx_dest) = seg_src(idx_nearVox_src);
            end

        end


        function table_MorphQuant = cal_MorphQuant2table(MorphQuant, ROInames)

            array_MorphQuant = zeros([4, length(ROInames)], "double");
            for i=1:length(ROInames)
                % FCL
                array_MorphQuant(1, i) = MorphQuant.(ROInames{i}).dABp;
                % mean thickness
                array_MorphQuant(2, i) = MorphQuant.(ROInames{i}).ThCtAB_aMe;
                % surface area
                array_MorphQuant(3, i) = MorphQuant.(ROInames{i}).cAB;
                % volume
                array_MorphQuant(4, i) = MorphQuant.(ROInames{i}).Volume;
            end

            RowNames = {'FCL', 'Mean Thickness', 'Surface Area', 'Volume'};
            table_MorphQuant = array2table(array_MorphQuant, "VariableNames",ROInames, "RowNames",RowNames);

        end


        % (adapted from the MeshProcessingToolbox)
        function [V_out, T_out] = remove_duplicated_vertices(V_in, T_in)
            %% remove_duplicated_vertices : function to remove duplicated
            % vertices from the point set.
            %
            % Author & support : nicolas.douillet (at) free.fr, 2020.
            %
            %
            % Input arguments
            %
            %          [ |    |    |  ]
            % - V_in = [X_in Y_in Z_in], real matrix double, the input point set, size(V_in) = [nb_input_vertices,3].
            %          [ |    |    |  ]
            %
            %          [  |     |     |  ]
            % - T_in = [i1_in i2_in i3_in], positive integer matrix double, the input triangulation, size(T_in) = [nb_input_triangles,3].
            %          [  |     |     |  ]
            %
            %
            % Output arguments
            %
            %           [  |     |     |  ]
            % - V_out = [X_out Y_out Z_out], real matrix double, the output point set, size(V_out) = [nb_output_vertices,3],
            %           [  |     |     |  ]
            %
            %           where nb_output_vertices = nb_input_vertices - nb_duplicata.
            %
            %           [  |      |      |   ]
            % - T_out = [i1_out i2_out i3_out], positive integer matrix double, the output triangulation, size(T_out) = [nb_output_triangles,3].
            %           [  |      |      |   ]

            [V_out,~,n] = unique(V_in,'rows');
            T_out = n(T_in);
        end


        % (adapted from the MeshProcessingToolbox)
        function [T_out] = remove_triangles(T_set, T_in, mode)
            %% remove_triangles : function to remove triangles from the triangle set.
            %
            % Author & support : nicolas.douillet (at) free.fr, 2020.
            %
            %
            % Input arguments
            %
            %           [|  |  | ]
            % - T_set = [i1 i2 i3], positive integer matrix double, the triangle set, size(T_set) = [nb_triangles,3],
            %           [|  |  | ]
            %
            %           or integer vector of indices, size(T_set) = [1,nb_triangles]. NB : in the case size(T_set) = [1,3]
            %           the difference between the two possible options is only made thanks to the input argument mode value.
            %
            %          [  |     |     |  ]
            % - T_in = [i1_in i2_in i3_in], positive integer matrix double, the input triangulation, size(T_in) = [nb_input_triangles,3].
            %          [  |     |     |  ]
            %
            % - mode : character string in the set {'indices','explicit','INDICES','EXPLICIT'}. Case insensitive.
            %
            %
            % Output argument
            %
            %           [  |      |      |   ]
            % - T_out = [i1_out i2_out i3_out], positive integer matrix double, the output triangulation, size(T_out) = [nb_output_triangles,3],
            %           [  |      |      |   ]
            %
            %           with nb_output_triangles = nb_input_triangles - nb_removed_triangles.

            if nargin < 3
                mode = 'indices'; % default behaviour
            end

            T_out = T_in;

            if strcmpi(mode,'indices') && ismember(1,size(T_set))
                T_out(T_set,:) = [];
            elseif strcmpi(mode,'explicit') && size(T_set,2) == 3
                T_out = setdiff(T_in,T_set,'rows','stable');
            else
                error('mode value must be either set to ''indices'' with T_set a one row/column vector or to ''explicit'' with size(T_set,2) = 3.');
            end
        end


        % (adapted from the MeshProcessingToolbox)
        function [T_out] = remove_duplicated_triangles(T_in)
            %% remove_duplicated_triangles : function to remove duplicated
            % triangles present in the triangulation T_in.
            %
            % Author & support : nicolas.douillet (at) free.fr, 2020.
            %
            %
            % Input argument
            %
            %          [  |     |     |  ]
            % - T_in = [i1_in i2_in i3_in], positive integer matrix double, the input triangulation, size(T_in) = [nb_input_triangles,3].
            %          [  |     |     |  ]
            %
            %
            % Output argument
            %
            %           [  |      |      |   ]
            % - T_out = [i1_out i2_out i3_out], positive integer matrix double, the output triangulation, size(T_out) = [nb_output_triangles,3],
            %           [  |      |      |   ]
            %
            %           where nb_output_triangles = nb_input_triangles - nb_duplicata.

            T_sort = sort(T_in,2);
            [~,idx,~] = unique(T_sort,'rows','stable'); % sort a copy only to keep face orientation
            T_out = T_in(idx,:);
        end


        % (adapted from the MeshProcessingToolbox)
        function [V_out, T_out] = remove_unreferenced_vertices(V_in, T_in)
            %% remove_unreferenced_vertices : function to remove vertices
            % which are not referenced in the triangulation.
            %
            % Author & support : nicolas.douillet (at) free.fr, 2020.
            %
            %
            % Input arguments
            %
            %          [ |    |    |  ]
            % - V_in = [X_in Y_in Z_in], real matrix double, the input point set, size(V_in) = [nb_input_vertices,3].
            %          [ |    |    |  ]
            %
            %          [  |     |     |  ]
            % - T_in = [i1_in i2_in i3_in], positive integer matrix double, the input triangulation, size(T_in) = [nb_input_triangles,3].
            %          [  |     |     |  ]
            %
            %
            % Output arguments
            %
            %           [  |     |     |  ]
            % - V_out = [X_out Y_out Z_out], real matrix double, the output point set, size(V_out) = [nb_output_vertices,3].
            %           [  |     |     |  ]
            %
            %           where nb_output_vertices = nb_input_vertices - nb_unreferenced_vertices.
            %
            %           [  |      |      |   ]
            % - T_out = [i1_out i2_out i3_out], positive integer matrix double, the output triangulation, size(T_out) = [nb_output_triangles,3],
            %           [  |      |      |   ]
            %
            %           where nb_output_triangles = nb_input_triangles.

            unref_vtx_idx = setdiff(1:size(V_in,1),unique(T_in(:)));
            [V_out,T_out] = CartiMorphToolbox.remove_vertices(unref_vtx_idx,V_in,T_in,'indices');

        end


        % (adapted from the MeshProcessingToolbox)
        function [V_out, T_out] = remove_vertices(V_set, V_in, T_in, mode)
            %% remove_vertices : function to remove vertices from the vertex set.
            %
            % Author & support : nicolas.douillet (at) free.fr, 2020.
            %
            %
            % Input arguments
            %
            %           [| | |]
            % - V_set = [X Y Z], real matrix double, the vertex set to remove, size(V_set) = [nb_new_vertices,3],
            %           [| | |]
            %                    or positive integer row vector double, the index list of the vertices to remove.
            %
            %          [ |    |    |  ]
            % - V_in = [X_in Y_in Z_in], real matrix double, the input point set, size(V_in) = [nb_input_vertices,3].
            %          [ |    |    |  ]
            %
            %          [  |     |     |  ]
            % - T_in = [i1_in i2_in i3_in], positive integer matrix double, the input triangulation, size(T_in) = [nb_input_triangles,3].
            %          |  |     |     |  ]
            %
            % - mode : character string in the set {'indices','explicit','INDICES','EXPLICIT'}. Explicit mode corresponds to the
            %          case where V_set is made of vertices [X Y Z] coordinates. Case insensitive.
            %
            %
            % Output arguments
            %
            %           [  |     |     |  ]
            % - V_out = [X_out Y_out Z_out], real matrix double, the output point set, size(V_out) = [nb_output_vertices,3],
            %           [  |     |     |  ]
            %
            %           with nb_output_vertices = nb_input_vertices + nb_new_vertices.
            %
            %           [  |      |      |   ]
            % - T_out = [i1_out i2_out i3_out], positive integer matrix double, the output triangulation, size(T_out) = [nb_output_triangles,3].
            %           [  |      |      |   ]

            if nargin  < 4
                mode = 'indices';
            else
                assert((strcmpi(mode,'indices') && ismember(1,size(V_set))) || (strcmpi(mode,'explicit') && size(V_set,2) == 3),...
                    'mode value must be either set to ''indices'' with V_set a one row/column indices vector or to ''explicit'' with size(V_set,2) = 3.');
            end

            V_out = V_in;
            T_out = T_in;

            if strcmpi(mode,'indices') && ismember(1,size(V_set)) || nargin < 4
                vtx_idx_2_suppr = V_set;
            elseif strcmpi(mode,'explicit') && size(V_set,2) == 3
                vtx_idx_2_suppr = ismember(V_in,V_set,'rows');
            end

            % I Suppress vertices & triangles
            V_out(vtx_idx_2_suppr,:) = [];
            tgl_idx_2_suppr = CartiMorphToolbox.find_triangles_from_vertex_list(T_in,vtx_idx_2_suppr);
            T_out(tgl_idx_2_suppr,:) = [];

            % II Reindex triangulation
            keep_idx = setdiff(1:size(V_in,1),vtx_idx_2_suppr); % what V_out indices are relatively to V_in ones
            M = containers.Map(keep_idx,1:size(V_out,1));
            T_suppr_idx = isKey(M,num2cell(T_out));
            T_out(T_suppr_idx) = cell2mat(values(M,num2cell(T_out(T_suppr_idx))));
        end


        % (adapted from the MeshProcessingToolbox)
        function [tgl_idx_list] = find_triangles_from_vertex_list(T, vtx_idx)
            %% find_triangles_from_vertex_list : function to compute the index
            % list of triangles containing the vertex indices of vtx_idx.
            %
            % Author & support : nicolas.douillet (at) free.fr, 2020.
            %
            %
            % Input arguments
            %
            %       [|  |  | ]
            % - T = [i1 i2 i3], positive integer matrix double, the triangulation, size(T) = [nb_triangles,3].
            %       [|  |  | ]
            %
            % - vtx_idx : positive integer row vector double, the vertex indices list.
            %
            %
            % Output argument
            %
            % - tgl_idx_list : positive integer row vector double, the triangle indices list.

            tgl_idx_list = find(sum(reshape(ismember(T(:),vtx_idx),[size(T,1),size(T,2)]),2));
        end


        % (adapted from the MeshProcessingToolbox)
        function [cc_nb, components] = segment_connected_components(T, mode)
            %% segment_connected_components : function to compute the number cc_nb of connected
            % components and consequently segment the triangulation into cc_nb triangulation cells.
            %
            % Author & support : nicolas.douillet (at) free.fr, 2020.
            %
            %
            % Input arguments
            %
            %       [|  |  | ]
            % - T = [i1 i2 i3], positive integer matrix double, the triangulation, size(T) = [nb_input_triangles,3].
            %       [|  |  | ]
            %
            % - mode : character string in the set {'explicit','indices','EXPLICIT','INDICES'}, the format of each cell
            %          content of output cell array components. In case mode is set to 'explicit', triangles are refered
            %          as triplet lists of positive integer scalar double, whereas in case it is set to 'indices', they are
            %          just identified with their indices (positive integer scalar double). Case insensitive.
            %
            %
            % Output arguments
            %
            % - cc_nb : positive integer scalar double, the number of connected components.
            %
            % - components : cell array of triangle sets (positive integer matrices double), the component array.

            if nargin < 2
                mode = 'explicit'; % default
            end

            components = {};
            component = [];
            cc_nb = 0; % hypothesis : T non empty

            ngb_T = CartiMorphToolbox.find_neighbor_triangle_indices(T); % 'all'
            stored_tgl_idx = 1; % Default seed triangle is #1
            t = 1; % nb processed / stored triangles
            curr_tgl_idx = stored_tgl_idx(t);


            if strcmpi(mode,'explicit')

                component = cat(1,component,T(curr_tgl_idx,:));

                while numel(stored_tgl_idx) < size(T,1) % some unstored triangles remain

                    % One same component
                    while t < numel(stored_tgl_idx) + 1 % ~isempty(idx_ngb_tgl_2_store)
                        curr_tgl_idx = stored_tgl_idx(t);
                        ngb_tgl_idx = cell2mat(ngb_T(curr_tgl_idx,1))';
                        idx_ngb_tgl_2_store = setdiff(ngb_tgl_idx,stored_tgl_idx);
                        component = cat(1,component,T(idx_ngb_tgl_2_store,:));
                        stored_tgl_idx = cat(2,stored_tgl_idx,idx_ngb_tgl_2_store);
                        t = t + 1;
                    end

                    components = cat(1,components,{component});

                    % New component
                    component = [];
                    cc_nb = cc_nb + 1;

                    % New triangle seed : the first which is not in the list
                    unstored_tgl_idx = setdiff(1:size(T,1),stored_tgl_idx);

                    if ~isempty(unstored_tgl_idx) % some unstored triangles remain
                        curr_tgl_idx = unstored_tgl_idx(1,1); % default seed triangle is 1st of the list
                        component = cat(1,component,T(curr_tgl_idx,:));
                        stored_tgl_idx = cat(2,stored_tgl_idx,curr_tgl_idx);
                    end

                    if numel(stored_tgl_idx) == size(T,1) % store last triangle
                        if ~isempty(component)
                            components = cat(1,components,{component});
                            cc_nb = cc_nb + 1;
                        end
                    end
                end

            elseif strcmpi(mode,'indices')

                component = cat(2,component,curr_tgl_idx);

                while numel(stored_tgl_idx) < size(T,1) % some unstored triangles remain

                    % One same component
                    while t < numel(stored_tgl_idx) + 1 % ~isempty(idx_ngb_tgl_2_store)
                        curr_tgl_idx = stored_tgl_idx(t);
                        ngb_tgl_idx = cell2mat(ngb_T(curr_tgl_idx,1))';
                        idx_ngb_tgl_2_store = setdiff(ngb_tgl_idx,stored_tgl_idx);
                        component = cat(2,component,idx_ngb_tgl_2_store);
                        stored_tgl_idx = cat(2,stored_tgl_idx,idx_ngb_tgl_2_store);
                        t = t + 1;
                    end

                    components = cat(1,components,{component});

                    % New component
                    component = [];
                    cc_nb = cc_nb + 1;

                    % New triangle seed : the first which is not in the list
                    unstored_tgl_idx = setdiff(1:size(T,1),stored_tgl_idx);

                    if ~isempty(unstored_tgl_idx) % some unstored triangles remain
                        curr_tgl_idx = unstored_tgl_idx(1,1); % default seed triangle is 1st of the list
                        component = cat(2,component,curr_tgl_idx);
                        stored_tgl_idx = cat(2,stored_tgl_idx,curr_tgl_idx);
                    end

                    if numel(stored_tgl_idx) == size(T,1) % store last triangle
                        if ~isempty(component)
                            components = cat(1,components,{component});
                        end
                    end
                end
            end
        end


        % (adapted from the MeshProcessingToolbox)
        function [ngb_T] = find_neighbor_triangle_indices(T, T_subset, mode)
            %% find_neighbor_triangle_indices : function to compute the list of triangles
            % which are neighbors (share one edge) to the triangles in T_subset.
            %
            % Author & support : nicolas.douillet (at) free.fr, 2020.
            %
            %
            % Input arguments
            %
            %       [ |  |  |]
            % - T = [i1 i2 i3], positive integer matrix double, the triangulation, size(T) = [nb_triangles,3].
            %       [ |  |  |]
            %
            % - T_subset : either positive integer row vector double* (the indices of the triangles; default behaviour) or
            %              positive integer matrix double giving the explicit indices triplet list. In this latter case,
            %              mode must be set to 'explicit'.
            %
            % - mode : character string in the set : {'explicit',indices,'EXPLICIT','INDICES'}. Explicit mode
            %          corresponds to the case where T_subset is made of triangle vertices [i1 i2 i3] indices.
            %          Case insensitive.
            %
            %
            % Output argument
            %
            % - ngb_T : positive integer matrix double, the neighbor triangle indices, size(ngb_T) = [nbg_nb,3],
            %           with nbg_nb the number of neighbors.

            if nargin > 1
                if nargin > 2
                    if strcmpi(mode,'indices')
                        subset_idx = T_subset;
                    elseif strcmpi(mode,'explicit')
                        subset_idx = find(ismember(T,T_subset,'rows'));
                    end
                else
                    % mode = 'indices'; % default behaviour
                    subset_idx = T_subset;
                end
                T_subset = T(subset_idx,:);
            else
                T_subset = T;
            end

            ngb_T = cellfun(@(t) find(sum(bitor(bitor(T==T_subset(t,1),T==T_subset(t,2)),T==T_subset(t,3)),2)==2),...
                num2cell((1:size(T_subset,1))'),'un',0);
        end


        % (adapted from matGeom)
        function varargout = smoothMesh(varargin)
            %SMOOTHMESH Smooth mesh by replacing each vertex by the average of its neighbors.
            %
            %   V2 = smoothMesh(V, F)
            %   [V2, F2] = smoothMesh(V, F)
            %   Performs smoothing of the values given in V, by using adjacency
            %   information given in F.
            %   V is a numeric array representing either vertex coordinate, or value
            %   field associated to each vertex. F is an array of faces, given either
            %   as a NF-by-3 or NF-by-4 numeric array, or as a cell array.
            %   Artifact adjacencies are added if faces have more than 4 vertices.
            %
            %   ... = smoothMesh(V, F, NITER)
            %   Repeat the smoothing procedure NITER times. This is equivalent to
            %   calling the smoothMesh function NITER times.
            %
            %
            %   Example
            %     [v f] = torusMesh([50 50 50 30 10 30 45]);
            %     v = v + randn(size(v));
            %     [v2 f] = smoothMesh(v, f, 3);
            %     figure; drawMesh(v2, f);
            %     l = light; lighting gouraud
            %
            %   See also
            %     meshes3d, meshAdjacencyMatrix, triangulateFaces, drawMesh
            %

            % ------
            % Author: David Legland
            % e-mail: david.legland@inra.fr
            % Created: 2013-04-29,    using Matlab 7.9.0.529 (R2009b)
            % Copyright 2013 INRA - Cepia Software Platform.

            var1 = varargin{1};
            if isstruct(var1)
                vertices = var1.vertices;
                faces = var1.faces;
                varargin(1) = [];
            else
                vertices = varargin{1};
                faces = varargin{2};
                varargin(1:2) = [];
            end

            % determine number of iterations
            nIter = 1;
            if ~isempty(varargin)
                nIter = varargin{1};
            end

            % compute adjacency matrix,
            % result is a Nv-by-Nv matrix with zeros on the diagonal
            adj = CartiMorphToolbox.meshAdjacencyMatrix(faces);

            % ensure the size of the matrix is Nv-by-Nv
            % (this can not be the case if some vertices are not referenced)
            nv = size(vertices, 1);
            if size(adj, 1) < nv
                adj(nv, nv) = 0;
            end

            % Add "self adjacencies"
            adj = adj + speye(nv);

            % weight each vertex by the number of its neighbors
            w = spdiags(full(sum(adj, 2).^(-1)), 0, nv, nv);
            adj = w * adj;

            % do averaging to smooth the field
            v2 = vertices;
            for k = 1:nIter
                v2 = adj * v2;
            end

            varargout = CartiMorphToolbox.formatMeshOutput(nargout, v2, faces);
        end


        % (adapted from matGeom)
        function adj = meshAdjacencyMatrix(faces, varargin)
            %MESHADJACENCYMATRIX Compute adjacency matrix of a mesh from set of faces.
            %
            %   ADJMAT = meshAdjacencyMatrix(FACES)
            %   Returns a sparse NV-by-NV matrix (NV being the largest vertex index)
            %   containing vertex adjacency of the mesh represented by FACES.
            %   FACES is either a NF-by-3, a NF-by-4 index array, or a Nf-by-1 cell
            %   array.
            %
            %   Example
            %     [v f] = createCube;
            %     adj = meshAdjacencyMatrix(f);
            %
            %   See also
            %     meshes3d, triangulateFaces, smoothMesh

            % ------
            % Author: David Legland
            % e-mail: david.legland@inra.fr
            % Created: 2013-04-30,    using Matlab 7.9.0.529 (R2009b)
            % Copyright 2013 INRA - Cepia Software Platform.

            % Ensures faces is a N-by-3 or N-by-4 array
            if iscell(faces) || (isnumeric(faces) && size(faces, 2) > 4)
                faces = CartiMorphToolbox.triangulateFaces(faces);
            end

            % forces faces to be floating point array, for sparse function
            if ~isfloat(faces)
                faces = double(faces);
            end
            nv = max(faces(:));

            % populate a sparse matrix
            if size(faces, 2) == 3
                adj = sparse(...
                    [faces(:,1); faces(:,1); faces(:,2); faces(:,2); faces(:,3); faces(:,3)], ...
                    [faces(:,3); faces(:,2); faces(:,1); faces(:,3); faces(:,2); faces(:,1)], ...
                    1.0, nv, nv);
            elseif size(faces, 2) == 4
                adj = sparse(...
                    [faces(:,1); faces(:,1); faces(:,2); faces(:,2); faces(:,3); faces(:,3); faces(:,4); faces(:,4)], ...
                    [faces(:,4); faces(:,2); faces(:,1); faces(:,3); faces(:,2); faces(:,4); faces(:,3); faces(:,1)], ...
                    1.0, nv, nv);
            end

            % remove double adjacencies
            adj = min(adj, 1);

        end


        % (adapted from matGeom)
        function [tri, inds] = triangulateFaces(faces)
            %TRIANGULATEFACES Convert face array to an array of triangular faces.
            %
            %   TRI = triangulateFaces(FACES)
            %   Returns a 3-columns array of indices, based on the data stored in the
            %   argument FACES:
            %   - if FACES is a N-by-3 array, returns the same array
            %   - if FACES is a N-by-4 array, returns an array with 2*N rows and 3
            %       columns, splitting each square into 2 triangles (uses first and
            %       third vertex of each square as diagonal).
            %   - if FACES is a cell array, split each face into a set of triangles,
            %       and returns the union of all triangles. Faces are assumed to be
            %       convex.
            %
            %   [TRI INDS] = triangulateFaces(FACES)
            %   Also returns original face index of each new triangular face. INDS has
            %   the same number of rows as TRI, and has values between 1 and the
            %   number of rows of the original FACES array.
            %
            %
            %   Example
            %     % create a basic shape
            %     [n e f] = createCubeOctahedron;
            %     % draw with plain faces
            %     figure;
            %     drawMesh(n, f);
            %     % draw as a triangulation
            %     tri = triangulateFaces(f);
            %     figure;
            %     patch('vertices', n, 'faces', tri, 'facecolor', 'r');
            %
            %   See also
            %     meshes3d, drawMesh, mergeCoplanarFaces
            %

            % ------
            % Author: David Legland
            % e-mail: david.legland@inra.fr
            % Created: 2008-09-08,    using Matlab 7.4.0.287 (R2007a)
            % Copyright 2008 INRA - BIA PV Nantes - MIAJ Jouy-en-Josas.

            %% Tri mesh case: return original set of faces

            if isnumeric(faces) && size(faces, 2) == 3
                tri = faces;
                if nargout > 1
                    inds = (1:size(faces, 1))';
                end
                return;
            end


            %% Square faces: split each square into 2 triangles

            if isnumeric(faces) && size(faces, 2) == 4
                nf = size(faces, 1);
                tri = zeros(nf * 2, 3);
                tri(1:2:end, :) = faces(:, [1 2 3]);
                tri(2:2:end, :) = faces(:, [1 3 4]);

                if nargout > 1
                    inds = kron(1:size(faces, 1), ones(1,2))';
                end

                return;
            end


            %% Pentagonal faces (for dodecahedron...): split into 3 triangles

            if isnumeric(faces) && size(faces, 2) == 5
                nf = size(faces, 1);
                tri = zeros(nf * 3, 3);
                tri(1:3:end, :) = faces(:, [1 2 3]);
                tri(2:3:end, :) = faces(:, [1 3 4]);
                tri(3:3:end, :) = faces(:, [1 4 5]);

                if nargout > 1
                    inds = kron(1:size(faces, 1), ones(1,2))';
                end

                return;
            end


            %% Faces as cell array

            % number of faces
            nf  = length(faces);

            % compute total number of triangles
            ni = zeros(nf, 1);
            for i = 1:nf
                % as many triangles as the number of vertices minus 1
                ni(i) = length(faces{i}) - 2;
            end
            nt = sum(ni);

            % allocate memory for triangle array
            tri = zeros(nt, 3);
            inds = zeros(nt, 1);

            % convert faces to triangles
            t = 1;
            for i = 1:nf
                face = faces{i};
                nv = length(face);
                v0 = face(1);
                for j = 3:nv
                    tri(t, :) = [v0 face(j-1) face(j)];
                    inds(t) = i;
                    t = t + 1;
                end
            end

        end

        % (adapted from matGeom)
        function res = formatMeshOutput(nbArgs, vertices, edges, faces)
            %FORMATMESHOUTPUT Format mesh output depending on nargout.
            %
            %   OUTPUT = formatMeshOutput(NARGOUT, VERTICES, EDGES, FACES)
            %   Utilitary function to convert mesh data .
            %   If NARGOUT is 0 or 1, return a matlab structure with fields vertices,
            %   edges and faces.
            %   If NARGOUT is 2, return a cell array with data VERTICES and FACES.
            %   If NARGOUT is 3, return a cell array with data VERTICES, EDGES and
            %   FACES.
            %
            %   OUTPUT = formatMeshOutput(NARGOUT, VERTICES, FACES)
            %   Same as before, but do not intialize EDGES in output. NARGOUT can not
            %   be equal to 3.
            %
            %   Example
            %     % Typical calling sequence (for a very basic mesh of only one face)
            %     v = [0 0; 0 1;1 0;1 1];
            %     e = [1 2;1 3;2 4;3 4];
            %     f = [1 2 3 4];
            %
            %     varargout = formatMeshOutput(nargout, v, e, f);
            %
            %   See also
            %   meshes3d, parseMeshData
            %
            % ------
            % Author: David Legland
            % e-mail: david.legland@grignon.inra.fr
            % Created: 2010-12-06,    using Matlab 7.9.0.529 (R2009b)
            % Copyright 2010 INRA - Cepia Software Platform.

            if nargin < 4
                faces = edges;
                edges = [];
            end

            switch nbArgs
                case {0, 1}
                    % output is a data structure with fields vertices, edges and faces
                    mesh.vertices = vertices;
                    if ~isempty(edges)
                        mesh.edges = edges;
                    end
                    mesh.faces = faces;
                    res = {mesh};

                case 2
                    % keep only vertices and faces
                    res = cell(nbArgs, 1);
                    res{1} = vertices;
                    res{2} = faces;

                case 3
                    % return vertices, edges and faces as 3 separate outputs
                    res = cell(nbArgs, 1);
                    res{1} = vertices;
                    res{2} = edges;
                    res{3} = faces;

                otherwise
                    error('Can not manage more than 3 outputs');
            end

        end


        % (adapted from matGeom)
        function varargout = createCube()
            %CREATECUBE Create a 3D mesh representing the unit cube.
            %
            %   [V, E, F] = createCube
            %   Create a unit cube, as a polyhedra representation.
            %   c has the form [V E F], where V is a 8-by-3 array with vertices
            %   coordinates, E is a 12-by-2 array containing indices of neighbour
            %   vertices, and F is a 6-by-4 array containing vertices array of each
            %   face.
            %
            %   [V, F] = createCube;
            %   Returns only the vertices and the face vertex indices.
            %
            %   MESH = createCube;
            %   Returns the data as a mesh structure, with fields 'vertices', 'edges'
            %   and 'faces'.
            %
            %   Example
            %   [n, e, f] = createCube;
            %   drawMesh(n, f);
            %
            %   See also
            %   meshes3d, drawMesh
            %   createOctahedron, createTetrahedron, createDodecahedron
            %   createIcosahedron, createCubeOctahedron
            %

            % ---------
            % author : David Legland
            % e-mail: david.legland@inra.fr
            % INRA - TPV URPOI - BIA IMASTE
            % created the 10/02/2005.
            %   HISTORY
            %   04/01/2007: remove unused variables

            x0 = 0; dx= 1;
            y0 = 0; dy= 1;
            z0 = 0; dz= 1;

            nodes = [...
                x0 y0 z0; ...
                x0+dx y0 z0; ...
                x0 y0+dy z0; ...
                x0+dx y0+dy z0; ...
                x0 y0 z0+dz; ...
                x0+dx y0 z0+dz; ...
                x0 y0+dy z0+dz; ...
                x0+dx y0+dy z0+dz];

            edges = [1 2;1 3;1 5;2 4;2 6;3 4;3 7;4 8;5 6;5 7;6 8;7 8];

            % faces are oriented such that normals point outwards
            faces = [1 3 4 2;5 6 8 7;2 4 8 6;1 5 7 3;1 2 6 5;3 7 8 4];

            % format output
            varargout = CartiMorphToolbox.formatMeshOutput(nargout, nodes, edges, faces);
        end


        % (adapted from matGeom)
        function varargout = ellipsoidMesh(elli, varargin)
            %ELLIPSOIDMESH Convert a 3D ellipsoid to face-vertex mesh representation.
            %
            %   [V, F] = ellipsoidMesh(ELLI)
            %   ELLI is given by:
            %   [XC YC ZC  A B C  PHI THETA PSI],
            %   where (XC, YC, ZC) is the ellipsoid center, A, B and C are the half
            %   lengths of the ellipsoid main axes, and PHI THETA PSI are Euler angles
            %   representing ellipsoid orientation, in degrees.
            %
            %
            %   See also
            %   meshes3d, drawEllipsoid, sphereMesh, equivalentEllipsoid
            %

            % ------
            % Author: David Legland
            % e-mail: david.legland@grignon.inra.fr
            % Created: 2011-03-12,    using Matlab 7.9.0.529 (R2009b)
            % Copyright 2011 INRA - Cepia Software Platform.


            %% Default values

            % number of meridians
            nPhi    = 32;

            % number of parallels
            nTheta  = 16;


            %% Extract input arguments

            % Parse the input (try to extract center coordinates and radius)
            if nargin == 0
                % no input: assumes ellipsoid with default shape
                elli = [0 0 0 5 4 3 0 0 0];
            end

            % default set of options for drawing meshes
            options = {'FaceColor', 'g', 'linestyle', 'none'};

            while length(varargin) > 1
                switch lower(varargin{1})
                    case 'nphi'
                        nPhi = varargin{2};

                    case 'ntheta'
                        nTheta = varargin{2};

                    otherwise
                        % assumes this is drawing option
                        options = [options varargin(1:2)]; %#ok<AGROW>
                end

                varargin(1:2) = [];
            end


            %% Parse numerical inputs

            % Extract ellipsoid parameters
            xc  = elli(:,1);
            yc  = elli(:,2);
            zc  = elli(:,3);
            a   = elli(:,4);
            b   = elli(:,5);
            c   = elli(:,6);
            k   = pi / 180;
            ellPhi   = elli(:,7) * k;
            ellTheta = elli(:,8) * k;
            ellPsi   = elli(:,9) * k;


            %% Coordinates computation

            % convert unit basis to ellipsoid basis
            sca     = CartiMorphToolbox.createScaling3d(a, b, c);
            rotZ    = CartiMorphToolbox.createRotationOz(ellPhi);
            rotY    = CartiMorphToolbox.createRotationOy(ellTheta);
            rotX    = CartiMorphToolbox.createRotationOx(ellPsi);
            tra     = CartiMorphToolbox.createTranslation3d([xc yc zc]);

            % concatenate transforms
            trans   = tra * rotZ * rotY * rotX * sca;


            %% parametrisation of ellipsoid

            % spherical coordinates
            theta   = linspace(0, pi, nTheta+1);
            phi     = linspace(0, 2*pi, nPhi+1);

            % convert to cartesian coordinates
            sintheta = sin(theta);
            x = cos(phi') * sintheta;
            y = sin(phi') * sintheta;
            z = ones(length(phi),1) * cos(theta);

            % transform mesh vertices
            [x, y, z] = CartiMorphToolbox.transformPoint3d(x, y, z, trans);

            % convert to FV mesh
            [vertices, faces] = CartiMorphToolbox.surfToMesh(x, y, z, 'xPeriodic', false, 'yPeriodic', true);

            % format output
            varargout = CartiMorphToolbox.formatMeshOutput(nargout, vertices, faces);
        end


        % (adapted from matGeom)
        function trans = createScaling3d(varargin)
            %CREATESCALING3D Create the 4x4 matrix of a 3D scaling.
            %
            %   TRANS = createScaling3d(S);
            %   returns the scaling transform corresponding to a scaling factor S in
            %   each direction. S can be a scalar, or a 1-by-3 vector containing the
            %   scaling factor in each direction.
            %
            %   TRANS = createScaling3d(SX, SY, SZ);
            %   returns the scaling transform corresponding to a different scaling
            %   factor in each direction.
            %
            %   The returned matrix has the form :
            %   [SX  0  0  0]
            %   [ 0 SY  0  0]
            %   [ 0  0 SZ  0]
            %   [ 0  0  0  0]
            %
            %   See also:
            %   transforms3d, transformPoint3d, transformVector3d, createTranslation3d,
            %   createRotationOx, createRotationOy, createRotationOz

            %
            %   ---------
            %   author : David Legland
            %   INRA - TPV URPOI - BIA IMASTE
            %   created the 20/04/2006.
            %

            %   HISTORY
            %   25/11/2008 rename from scale3d to scaling3d
            %   30/04/2009 rename to createScaling3d


            %% default arguments
            sx = 1;
            sy = 1;
            sz = 1;
            center = [0 0 0];

            %% process input parameters
            if nargin == 1
                % only one argument -> scaling factor
                [sx, sy, sz]= CartiMorphToolbox.parseScalingFactors(varargin{1});

            elseif nargin == 2
                % 2 arguments, giving center and uniform scaling
                center = varargin{1};
                [sx, sy, sz]= CartiMorphToolbox.parseScalingFactors(varargin{2});

            elseif nargin == 3
                % 3 arguments, giving scaling in each direction
                sx = varargin{1};
                sy = varargin{2};
                sz = varargin{3};

            elseif nargin == 4
                % 4 arguments, giving center and scaling in each direction
                center = varargin{1};
                sx = varargin{2};
                sy = varargin{3};
                sz = varargin{4};
            end

            %% create the scaling matrix
            trans = [...
                sx 0 0 center(1)*(1-sx);...
                0 sy 0 center(2)*(1-sy);...
                0 0 sz center(3)*(1-sz);...
                0 0 0 1];
        end


        % (adapted from matGeom)
        function [sx, sy, sz] = parseScalingFactors(var)

            if length(var)==1
                % same scaling factor in each direction
                sx = var;
                sy = var;
                sz = var;
            elseif length(var)==3
                % scaling is a vector, giving different scaling in each direction
                sx = var(1);
                sy = var(2);
                sz = var(3);
            else
                error('wrong size for first parameter of "createScaling3d"');
            end
        end


        % (adapted from matGeom)
        function trans = createRotationOx(varargin)
            %CREATEROTATIONOX Create the 4x4 matrix of a 3D rotation around x-axis.
            %
            %   TRANS = createRotationOx(THETA);
            %   Returns the transform matrix corresponding to a rotation by the angle
            %   THETA (in radians) around the Ox axis. A rotation by an angle of PI/2
            %   would transform the vector [0 1 0] into the vector [0 0 1].
            %
            %   The returned matrix has the form:
            %   [1      0            0      0]
            %   [0  cos(THETA) -sin(THETA)  0]
            %   [0  sin(THETA)  cos(THETA)  0]
            %   [0      0            0      1]
            %
            %   TRANS = createRotationOx(ORIGIN, THETA);
            %   TRANS = createRotationOx(X0, Y0, Z0, THETA);
            %   Also specifies origin of rotation. The result is similar as performing
            %   translation(-X0, -Y0, -Z0), rotation, and translation(X0, Y0, Z0).
            %
            %   See also:
            %   transforms3d, transformPoint3d, createRotationOy, createRotationOz
            %

            %   ---------
            %   author : David Legland
            %   INRA - TPV URPOI - BIA IMASTE
            %   created the 18/02/2005.
            %

            %   HISTORY
            %   24/11/2008 changed convention for angle
            %   22/04/2009 rename as createRotationOx


            % default values
            dx = 0;
            dy = 0;
            dz = 0;
            theta = 0;

            % get input values
            if length(varargin) == 1
                % only one argument -> rotation angle
                theta = varargin{1};

            elseif length(varargin) == 2
                % origin point (as array) and angle
                var = varargin{1};
                dx = var(1);
                dy = var(2);
                dz = var(3);
                theta = varargin{2};

            elseif length(varargin) == 4
                % origin (x and y) and angle
                dx = varargin{1};
                dy = varargin{2};
                dz = varargin{3};
                theta = varargin{4};
            end

            % compute coefs
            cot = cos(theta);
            sit = sin(theta);

            % create transformation
            trans = [...
                1 0 0 0;...
                0 cot -sit 0;...
                0 sit cot 0;...
                0 0 0 1];

            % add the translation part
            t = [1 0 0 dx;0 1 0 dy;0 0 1 dz;0 0 0 1];
            trans = t * trans / t;
        end


        % (adapted from matGeom)
        function trans = createRotationOy(varargin)
            %CREATEROTATIONOY Create the 4x4 matrix of a 3D rotation around y-axis.
            %
            %   TRANS = createRotationOy(THETA);
            %   Returns the transform matrix corresponding to a rotation by the angle
            %   THETA (in radians) around the Oy axis. A rotation by an angle of PI/2
            %   would transform the vector [0 0 1] into the vector [1 0 0].
            %
            %   The returned matrix has the form:
            %   [ cos(THETA)  0  sin(THETA)  0 ]
            %   [    0        1       0      0 ]
            %   [-sin(THETA)  0  cos(THETA)  0 ]
            %   [    0        0       0      1 ]
            %
            %   TRANS = createRotationOy(ORIGIN, THETA);
            %   TRANS = createRotationOy(X0, Y0, Z0, THETA);
            %   Also specifies origin of rotation. The result is similar as performing
            %   translation(-X0, -Y0, -Z0), rotation, and translation(X0, Y0, Z0).
            %
            %
            %   See also:
            %   transforms3d, transformPoint3d, createRotationOx, createRotationOz
            %

            %   ---------
            %   author : David Legland
            %   INRA - TPV URPOI - BIA IMASTE
            %   created the 18/02/2005.
            %

            %   HISTORY
            %   2008/11/24 changed convention for angle
            %   22/04/2009 rename as createcreateRotationOy


            % default values
            dx = 0;
            dy = 0;
            dz = 0;
            theta = 0;

            % get input values
            if length(varargin) == 1
                % only one argument -> rotation angle
                theta = varargin{1};

            elseif length(varargin) == 2
                % origin point (as array) and angle
                var = varargin{1};
                dx = var(1);
                dy = var(2);
                dz = var(3);
                theta = varargin{2};

            elseif length(varargin) == 3
                % origin (x and y) and angle
                dx = varargin{1};
                dy = varargin{2};
                dz = 0;
                theta = varargin{3};

            elseif length(varargin) == 4
                % origin (x and y) and angle
                dx = varargin{1};
                dy = varargin{2};
                dz = varargin{3};
                theta = varargin{4};
            end

            % compute coefs
            cot = cos(theta);
            sit = sin(theta);

            % create transformation
            trans = [...
                cot  0  sit  0;...
                0    1    0  0;...
                -sit 0  cot  0;...
                0    0    0  1];

            % add the translation part
            t = [1 0 0 dx;0 1 0 dy;0 0 1 dz;0 0 0 1];
            trans = t * trans / t;
        end


        % (adapted from matGeom)
        function trans = createRotationOz(varargin)
            %CREATEROTATIONOZ Create the 4x4 matrix of a 3D rotation around z-axis.
            %
            %   TRANS = createRotationOz(THETA);
            %   Returns the transform matrix corresponding to a rotation by the angle
            %   THETA (in radians) around the Oz axis. A rotation by an angle of PI/2
            %   would transform the vector [1 0 0] into the vector [0 1 0].
            %
            %   The returned matrix has the form:
            %   [cos(THETA) -sin(THETA)  0  0]
            %   [sin(THETA)  cos(THETA)  0  0]
            %   [    0           0       1  0]
            %   [    0           0       0  1]
            %
            %   TRANS = createRotationOz(ORIGIN, THETA);
            %   TRANS = createRotationOz(X0, Y0, Z0, THETA);
            %   Also specifies origin of rotation. The result is similar as performing
            %   translation(-X0, -Y0, -Z0), rotation, and translation(X0, Y0, Z0).
            %
            %
            %   See also:
            %   transforms3d, transformPoint3d, createRotationOx, createRotationOy
            %

            %   ---------
            %   author : David Legland
            %   INRA - TPV URPOI - BIA IMASTE
            %   created the 06/04/2004.
            %

            %   HISTORY
            %   2008/11/24 changed convention for angle
            %   22/04/2009 rename as createcreateRotationOz


            % default values
            dx = 0;
            dy = 0;
            dz = 0;
            theta = 0;

            % get input values
            if length(varargin) == 1
                % only one argument -> rotation angle
                theta = varargin{1};

            elseif length(varargin) == 2
                % origin point (as array) and angle
                var = varargin{1};
                dx = var(1);
                dy = var(2);
                dz = var(3);
                theta = varargin{2};

            elseif length(varargin) == 4
                % origin (x and y) and angle
                dx = varargin{1};
                dy = varargin{2};
                dz = varargin{3};
                theta = varargin{4};
            end

            % compute coefs
            cot = cos(theta);
            sit = sin(theta);

            % create transformation
            trans = [...
                cot -sit 0 0;...
                sit  cot 0 0;...
                0 0 1 0;...
                0 0 0 1];

            % add the translation part
            t = [1 0 0 dx;0 1 0 dy;0 0 1 dz;0 0 0 1];
            trans = t * trans / t;
        end


        % (adapted from matGeom)
        function trans = createTranslation3d(varargin)
            %CREATETRANSLATION3D Create the 4x4 matrix of a 3D translation.
            %
            %   usage:
            %   TRANS = createTranslation3d(DX, DY, DZ);
            %   return the translation corresponding to DX and DY.
            %   The returned matrix has the form :
            %   [1 0 0 DX]
            %   [0 1 0 DY]
            %   [0 0 1 DZ]
            %   [0 0 0  1]
            %
            %   TRANS = createTranslation3d(VECT);
            %   return the translation corresponding to the given vector [x y z].
            %
            %
            %   See also:
            %   transforms3d, transformPoint3d, transformVector3d,
            %   createRotationOx, createRotationOy, createRotationOz, createScaling3d
            %
            %   ---------
            %
            %   author : David Legland
            %   INRA - TPV URPOI - BIA IMASTE
            %   created the 06/04/2004.
            %

            %   HISTORY
            %   22/04/2009 rename as createTranslation3d


            if isempty(varargin)
                % assert translation with null vector
                dx = 0;
                dy = 0;
                dz = 0;
            elseif length(varargin)==1
                % translation vector given in a single argument
                var = varargin{1};
                dx = var(1);
                dy = var(2);
                dz = var(3);
            else
                % translation vector given in 3 arguments
                dx = varargin{1};
                dy = varargin{2};
                dz = varargin{3};
            end

            % create the translation matrix
            trans = [1 0 0 dx ; 0 1 0 dy ; 0 0 1 dz; 0 0 0 1];
        end


        % (adapted from matGeom)
        function varargout = transformPoint3d(varargin)
            %TRANSFORMPOINT3D Transform a point with a 3D affine transform.
            %
            %   PT2 = transformPoint3d(PT1, TRANS);
            %   PT2 = transformPoint3d(X1, Y1, Z1, TRANS);
            %   where PT1 has the form [xp yp zp], and TRANS is a 3-by-3, 3-by-4, or
            %   4-by-4 matrix, returns the point transformed according to the affine
            %   transform specified by TRANS.
            %
            %   The function accepts transforms given using the following formats:
            %   [a b c]   ,   [a b c j] , or [a b c j]
            %   [d e f]       [d e f k]      [d e f k]
            %   [g h i]       [g h i l]      [g h i l]
            %                                [0 0 0 1]
            %
            %   PT2 = transformPoint3d(PT1, TRANS)
            %   also work when PT1 is a [Nx3xMxPxETC] array of double. In this case,
            %   PT2 has the same size as PT1.
            %
            %   PT2 = transformPoint3d(X1, Y1, Z1, TRANS);
            %   also work when X1, Y1 and Z1 are 3 arrays with the same size. In this
            %   case, PT2 will be a 1-by-3 cell containing {X Y Z} outputs of size(X1).
            %
            %   [X2, Y2, Z2] = transformPoint3d(...);
            %   returns the result in 3 different arrays the same size as the input.
            %   This form can be useful when used with functions like meshgrid or warp.
            %
            %   MESH2 = transformPoint3d(MESH, TRANS)
            %   transforms the field 'vertices' of the struct MESH and returns the same
            %   struct with the transformed vertices.
            %
            %   See also:
            %     points3d, transforms3d, translation3d, meshgrid
            %

            %   ---------
            %   author : David Legland
            %   INRA - TPV URPOI - BIA IMASTE
            %   created the 10/02/2005.
            %

            %   23/03/2006 add support for non vector point data
            %   26/10/2006 better support for large data handling: iterate on points
            %       in the case of a memory lack.
            %   20/04/2007 add link to rotationXX functions
            %   29/09/2010 fix bug in catch case
            %   12/03/2011 slightly reduce memory usage

            % parse input arguments
            meshStructSwitch = false;
            if length(varargin) == 2
                if isstruct(varargin{1}) && isfield(varargin{1}, 'vertices')
                    % If first argument is a struct with the field 'vertices' the
                    % output will be the same struct, but with the transformed vertices
                    meshStructSwitch = true;
                    meshStruct = varargin{1};
                    varargin{1}=varargin{1}.vertices;
                end
                % Point coordinates are given in a single N-by-3-by-M-by-etc argument.
                % Preallocate x, y, and z to size N-by-1-by-M-by-etc, then fill them in
                dim = size(varargin{1});
                dim(2) = 1;
                [x, y, z] = deal(zeros(dim, class(varargin{1})));
                x(:) = varargin{1}(:,1,:);
                y(:) = varargin{1}(:,2,:);
                z(:) = varargin{1}(:,3,:);
                trans  = varargin{2};

            elseif length(varargin) == 4
                % Point coordinates are given in 3 different arrays
                x = varargin{1};
                y = varargin{2};
                z = varargin{3};
                dim = size(x);
                trans = varargin{4};
            end

            % eventually add null translation
            if size(trans, 2) == 3
                trans = [trans zeros(size(trans, 1), 1)];
            end

            % eventually add normalization
            if size(trans, 1) == 3
                trans = [trans ; 0 0 0 1];
            end

            % convert coordinates
            NP  = numel(x);
            try
                % vectorial processing, if there is enough memory
                %res = (trans*[x(:) y(:) z(:) ones(NP, 1)]')';
                %res = [x(:) y(:) z(:) ones(NP, 1)]*trans';
                res = [x(:) y(:) z(:) ones(NP,1,class(x))] * trans';

                % Back-fill x,y,z with new result (saves calling costly reshape())
                x(:) = res(:,1);
                y(:) = res(:,2);
                z(:) = res(:,3);
            catch ME
                disp(ME.message)
                % process each point one by one, writing in existing array
                for i = 1:NP
                    res = [x(i) y(i) z(i) 1] * trans';
                    x(i) = res(1);
                    y(i) = res(2);
                    z(i) = res(3);
                end
            end

            % process output arguments
            if nargout <= 1
                % results are stored in a unique array
                if length(dim) > 2 && dim(2) > 1
                    warning('geom3d:shapeMismatch',...
                        'Shape mismatch: Non-vector xyz input should have multiple x,y,z output arguments. Cell {x,y,z} returned instead.')
                    varargout{1} = {x,y,z};
                else
                    if meshStructSwitch
                        meshStruct.vertices = [x y z];
                        varargout{1}=meshStruct;
                    else
                        varargout{1} = [x y z];
                    end
                end

            elseif nargout == 3
                varargout{1} = x;
                varargout{2} = y;
                varargout{3} = z;
            end
        end


        % (adapted from matGeom)
        function varargout = surfToMesh(x, y, varargin)
            % Convert surface grids into face-vertex mesh.
            %
            %   [V, F] = surfToMesh(X, Y)
            %   [V, F] = surfToMesh(X, Y, Z)
            %   Converts the surface grid given by two or three coordinate arrays into
            %   a face-vertex quad mesh.
            %
            %   Example
            %     % transform a surface into a mesh
            %     [X, Y] = meshgrid(-2:.2:2, -2:.2:2);
            %     Z = X .* exp(-X.^2 - Y.^2);
            %     [V, F] = surfToMesh(X, Y, Z);
            %     figure;
            %     drawMesh(V, F); view(3);
            %
            %     % Transform surface of a cylinder as a mesh
            %     [x, y, z] = cylinder(5*ones(1, 10));
            %     [v, f] = surfToMesh(x, y, z, 'xPeriodic', true);
            %     figure;
            %     drawMesh(v, f);
            %     view(3); axis equal;
            %
            %   See also
            %     meshes3d, meshgrid, drawMesh, torusMesh, sphereMesh

            % ------
            % Author: David Legland
            % e-mail: david.legland@inra.fr
            % Created: 2012-10-25,    using Matlab 7.9.0.529 (R2009b)
            % Copyright 2012 INRA - Cepia Software Platform.


            %% Parse inputs

            % check if z-value is present
            if ~isempty(varargin) && isnumeric(varargin{1})
                z = varargin{1};
                varargin(1) = [];
            end

            % default periodicities
            xPeriodic = false;
            yPeriodic = false;

            % parse input options
            while length(varargin) > 1
                paramName = lower(varargin{1});
                switch paramName
                    case 'xperiodic'
                        xPeriodic = varargin{2};
                    case 'yperiodic'
                        yPeriodic = varargin{2};
                    otherwise
                        error(['Unknown parameter name: ' paramName]);
                end

                varargin(1:2) = [];
            end


            %% Compute vertex indices

            % size along each direction (arrays are (y,x)-indexed)
            n1 = size(x, 1);
            n2 = size(x, 2);

            % in case of periodicity, the last vertex of the grid is drop (it is
            % assumed to be the same as the first one)
            if xPeriodic
                n2 = n2 - 1;
            end
            if yPeriodic
                n1 = n1 - 1;
            end

            % new size of vertex grid
            dim = [n1 n2];
            nv = n1 * n2;


            %% Create vertex array

            % eventually remove boundary vertices
            x = x(1:n1, 1:n2);
            y = y(1:n1, 1:n2);

            % create vertex array
            if ~exist('z', 'var')
                vertices = [x(:) y(:)];
            else
                z = z(1:n1, 1:n2);
                vertices = [x(:) y(:) z(:)];
            end


            %% Create face array

            % vertex indices in grid
            inds = reshape(1:nv, dim);
            if xPeriodic
                inds = inds(:, [1:end 1]);
            end
            if yPeriodic
                inds = inds([1:end 1], :);
            end

            % vertex indices for each face
            v1 = inds(1:end-1, 1:end-1);
            v2 = inds(1:end-1, 2:end);
            v3 = inds(2:end, 2:end);
            v4 = inds(2:end, 1:end-1);

            % concatenate indices
            faces = [v1(:) v2(:) v3(:) v4(:)];


            %% format output
            varargout = CartiMorphToolbox.formatMeshOutput(nargout, vertices, faces);

        end


        % (adapted from: https://www.mathworks.com/matlabcentral/fileexchange/33073-triangle-ray-intersection)
        function [intersect, t, u, v, xcoor] = TriangleRayIntersection (...
                orig, dir, vert0, vert1, vert2, varargin)
            %TRIANGLERAYINTERSECTION Ray/triangle intersection.
            %    INTERSECT = TriangleRayIntersection(ORIG, DIR, VERT1, VERT2, VERT3)
            %      calculates ray/triangle intersections using the algorithm proposed
            %      BY Möller and Trumbore (1997), implemented as highly vectorized
            %      MATLAB code. The ray starts at ORIG and points toward DIR. The
            %      triangle is defined by vertix points: VERT1, VERT2, VERT3. All input
            %      arrays are in Nx3 or 1x3 format, where N is number of triangles or
            %      rays.
            %
            %   [INTERSECT, T, U, V, XCOOR] = TriangleRayIntersection(...)
            %     Returns:
            %     * Intersect - boolean array of length N informing which line and
            %                 triangle pair intersect
            %     * t   - distance from the ray origin to the intersection point in
            %             units of |dir|. Provided only for line/triangle pair that
            %             intersect unless 'fullReturn' parameter is true.
            %     * u,v - barycentric coordinates of the intersection point
            %     * xcoor - carthesian coordinates of the intersection point
            %
            %   TriangleRayIntersection(...,'param','value','param','value'...) allows
            %    additional param/value pairs to be used. Allowed parameters:
            %    * planeType - 'one sided' or 'two sided' (default) - how to treat
            %        triangles. In 'one sided' version only intersections in single
            %        direction are counted and intersections with back facing
            %           tringles are ignored
            %    * lineType - 'ray' (default), 'line' or 'segment' - how to treat rays:
            %        - 'line' means infinite (on both sides) line;
            %        - 'ray' means infinite (on one side) ray comming out of origin;
            %        - 'segment' means line segment bounded on both sides
            %    * border - controls border handling:
            %        - 'normal'(default) border - triangle is exactly as defined.
            %           Intersections with border points can be easily lost due to
            %           rounding errors.
            %        - 'inclusive' border - triangle is marginally larger.
            %           Intersections with border points are always captured but can
            %           lead to double counting when working with surfaces.
            %        - 'exclusive' border - triangle is marginally smaller.
            %           Intersections with border points are not captured and can
            %           lead to under-counting when working with surfaces.
            %    * epsilon - (default = 1e-5) controls border size
            %    * fullReturn - (default = false) controls returned variables t, u, v,
            %        and xcoor. By default in order to save time, not all t, u & v are
            %        calculated, only t, u & v for intersections can be expected.
            %        fullReturn set to true will force the calculation of them all.
            %
            % ALGORITHM:
            %  Function solves
            %        |t|
            %    M * |u| = (o-v0)
            %        |v|
            %  for [t; u; v] where M = [-d, v1-v0, v2-v0]. u,v are barycentric coordinates
            %  and t - the distance from the ray origin in |d| units
            %  ray/triangle intersect if u>=0, v>=0 and u+v<=1
            %
            % NOTE:
            %  The algorithm is able to solve several types of problems:
            %  * many faces / single ray  intersection
            %  * one  face  / many   rays intersection
            %  * one  face  / one    ray  intersection
            %  * many faces / many   rays intersection
            %  In order to allow that to happen all imput arrays are expected in Nx3
            %  format, where N is number of vertices or rays. In most cases number of
            %  vertices is different than number of rays, so one of the imputs will
            %  have to be cloned to have the right size. Use "repmat(A,size(B,1),1)".
            %
            % Based on:
            %  *"Fast, minimum storage ray-triangle intersection". Tomas Möller and
            %    Ben Trumbore. Journal of Graphics Tools, 2(1):21--28, 1997.
            %    http://www.graphics.cornell.edu/pubs/1997/MT97.pdf
            %  * http://fileadmin.cs.lth.se/cs/Personal/Tomas_Akenine-Moller/raytri/
            %  * http://fileadmin.cs.lth.se/cs/Personal/Tomas_Akenine-Moller/raytri/raytri.c
            %
            % Author:
            %    Jarek Tuszynski (jaroslaw.w.tuszynski@leidos.com)
            %
            % License: BSD license (http://en.wikipedia.org/wiki/BSD_licenses)

            %% Transpose inputs if needed
            if (size(orig ,1)==3 && size(orig ,2)~=3), orig =orig' ; end
            if (size(dir  ,1)==3 && size(dir  ,2)~=3), dir  =dir'  ; end
            if (size(vert0,1)==3 && size(vert0,2)~=3), vert0=vert0'; end
            if (size(vert1,1)==3 && size(vert1,2)~=3), vert1=vert1'; end
            if (size(vert2,1)==3 && size(vert2,2)~=3), vert2=vert2'; end

            %% In case of single points clone them to the same size as the rest
            N = max([size(orig,1), size(dir,1), size(vert0,1), size(vert1,1), size(vert2,1)]);
            if (size(orig ,1)==1 && N>1 && size(orig ,2)==3), orig  = repmat(orig , N, 1); end
            if (size(dir  ,1)==1 && N>1 && size(dir  ,2)==3), dir   = repmat(dir  , N, 1); end
            if (size(vert0,1)==1 && N>1 && size(vert0,2)==3), vert0 = repmat(vert0, N, 1); end
            if (size(vert1,1)==1 && N>1 && size(vert1,2)==3), vert1 = repmat(vert1, N, 1); end
            if (size(vert2,1)==1 && N>1 && size(vert2,2)==3), vert2 = repmat(vert2, N, 1); end

            %% Check if all the sizes match
            SameSize = (any(size(orig)==size(vert0)) && ...
                any(size(orig)==size(vert1)) && ...
                any(size(orig)==size(vert2)) && ...
                any(size(orig)==size(dir  )) );
            assert(SameSize && size(orig,2)==3, ...
                'All input vectors have to be in Nx3 format.');

            %% Read user preferences
            eps        = 1e-5;
            planeType  = 'two sided';
            lineType   = 'ray';
            border     = 'normal';
            fullReturn = false;
            nVarargs   = length(varargin);
            k = 1;
            if nVarargs>0 && isstruct(varargin{1})
                % This section is provided for backward compability only
                options = varargin{1};
                if (isfield(options, 'eps'     )), eps      = options.eps;      end
                if (isfield(options, 'triangle')), planeType= options.triangle; end
                if (isfield(options, 'ray'     )), lineType = options.ray;      end
                if (isfield(options, 'border'  )), border   = options.border;   end
            else
                while (k<=nVarargs)
                    assert(ischar(varargin{k}), 'Incorrect input parameters')
                    switch lower(varargin{k})
                        case 'eps'
                            eps = abs(varargin{k+1});
                            k = k+1;
                        case 'planetype'
                            planeType = lower(strtrim(varargin{k+1}));
                            k = k+1;
                        case 'border'
                            border = lower(strtrim(varargin{k+1}));
                            k = k+1;
                        case 'linetype'
                            lineType = lower(strtrim(varargin{k+1}));
                            k = k+1;
                        case 'fullreturn'
                            fullReturn = (double(varargin{k+1})~=0);
                            k = k+1;
                    end
                    k = k+1;
                end
            end

            %% Set up border parameter
            switch border
                case 'normal'
                    zero=0.0;
                case 'inclusive'
                    zero=eps;
                case 'exclusive'
                    zero=-eps;
                otherwise
                    error('Border parameter must be either "normal", "inclusive" or "exclusive"')
            end

            %% initialize default output
            intersect = false(size(orig,1),1); % by default there are no intersections
            t = inf+zeros(size(orig,1),1); u=t; v=t;
            xcoor = nan+zeros(size(orig));

            %% Find faces parallel to the ray
            edge1 = vert1-vert0;          % find vectors for two edges sharing vert0
            edge2 = vert2-vert0;
            tvec  = orig -vert0;          % vector from vert0 to ray origin
            pvec  = cross(dir, edge2,2);  % begin calculating determinant - also used to calculate U parameter
            det   = sum(edge1.*pvec,2);   % determinant of the matrix M = dot(edge1,pvec)
            switch planeType
                case 'two sided'            % treats triangles as two sided
                    angleOK = (abs(det)>eps); % if determinant is near zero then ray lies in the plane of the triangle
                case 'one sided'            % treats triangles as one sided
                    angleOK = (det>eps);
                otherwise
                    error('Triangle parameter must be either "one sided" or "two sided"');
            end
            if all(~angleOK), return; end % if all parallel than no intersections

            %% Different behavior depending on one or two sided triangles
            det(~angleOK) = nan;              % change to avoid division by zero
            u    = sum(tvec.*pvec,2)./det;    % 1st barycentric coordinate
            if fullReturn
                % calculate all variables for all line/triangle pairs
                qvec = cross(tvec, edge1,2);    % prepare to test V parameter
                v    = sum(dir  .*qvec,2)./det; % 2nd barycentric coordinate
                t    = sum(edge2.*qvec,2)./det; % 'position on the line' coordinate
                % test if line/plane intersection is within the triangle
                ok   = (angleOK & u>=-zero & v>=-zero & u+v<=1.0+zero);
            else
                % limit some calculations only to line/triangle pairs where it makes
                % a difference. It is tempting to try to push this concept of
                % limiting the number of calculations to only the necessary to "u"
                % and "t" but that produces slower code
                v = nan+zeros(size(u)); t=v;
                ok = (angleOK & u>=-zero & u<=1.0+zero); % mask
                % if all line/plane intersections are outside the triangle than no intersections
                if ~any(ok), intersect = ok; return; end
                qvec = cross(tvec(ok,:), edge1(ok,:),2); % prepare to test V parameter
                v(ok,:) = sum(dir(ok,:).*qvec,2) ./ det(ok,:); % 2nd barycentric coordinate
                if (~strcmpi(lineType,'line')) % 'position on the line' coordinate
                    t(ok,:) = sum(edge2(ok,:).*qvec,2)./det(ok,:);
                end
                % test if line/plane intersection is within the triangle
                ok = (ok & v>=-zero & u+v<=1.0+zero);
            end

            %% Test where along the line the line/plane intersection occurs
            switch lineType
                case 'line'      % infinite line
                    intersect = ok;
                case 'ray'       % ray is bound on one side
                    intersect = (ok & t>=-zero); % intersection on the correct side of the origin
                case 'segment'   % segment is bound on two sides
                    intersect = (ok & t>=-zero & t<=1.0+zero); % intersection between origin and destination
                otherwise
                    error('lineType parameter must be either "line", "ray" or "segment"');
            end

            %% calculate intersection coordinates if requested
            if (nargout>4)
                ok = intersect | fullReturn;
                xcoor(ok,:) = vert0(ok,:) ...
                    + edge1(ok,:).*repmat(u(ok,1),1,3) ...
                    + edge2(ok,:).*repmat(v(ok,1),1,3);
            end

        end


    end


    methods (Access = private)


        function CMT_checkProjID4nnUNet(app)
            % check whether there are projects with the same specified ID in 3 subfolders
            dir_tbc_nnUNet_raw_data = fullfile(app.dir_base, 'Models_training', 'nnUNet',...
                'nnUNet_raw_data_base', 'nnUNet_raw_data');
            dir_tbc_nnUNet_cropped_data = fullfile(app.dir_base, 'Models_training', 'nnUNet',...
                'nnUNet_raw_data_base', 'nnUNet_cropped_data');
            dir_tbc_nnUNet_preprocessed = fullfile(app.dir_base, 'Models_training', 'nnUNet',...
                'nnUNet_preprocessed');

            % the specified ID to be checked: app.config_nnUNet_ID
            sumnum = 0;
            leading_string = ['Task', app.config_nnUNet_ID];

            % check folder 1
            if isfolder(dir_tbc_nnUNet_raw_data)
                % preparing
                subfolders = dir(dir_tbc_nnUNet_raw_data);
                num_MatchingSubfolders = 0;
                % Iterate over each subfolder
                for i = 1:length(subfolders)
                    % Check if the current item is a folder
                    if subfolders(i).isdir
                        % Get the name of the subfolder
                        subfolderName = subfolders(i).name;
                        % Check if the subfolder name starts with the leading string
                        if strncmp(subfolderName, leading_string, length(leading_string))
                            num_MatchingSubfolders = num_MatchingSubfolders + 1;
                        end
                    end
                end
                sumnum = sumnum + num_MatchingSubfolders;
            end

            % check folder 2
            if isfolder(dir_tbc_nnUNet_cropped_data)
                % preparing
                subfolders = dir(dir_tbc_nnUNet_cropped_data);
                num_MatchingSubfolders = 0;
                % Iterate over each subfolder
                for i = 1:length(subfolders)
                    % Check if the current item is a folder
                    if subfolders(i).isdir
                        % Get the name of the subfolder
                        subfolderName = subfolders(i).name;
                        % Check if the subfolder name starts with the leading string
                        if strncmp(subfolderName, leading_string, length(leading_string))
                            num_MatchingSubfolders = num_MatchingSubfolders + 1;
                        end
                    end
                end
                sumnum = sumnum + num_MatchingSubfolders;
            end

            % check folder 3
            if isfolder(dir_tbc_nnUNet_preprocessed)
                % preparing
                subfolders = dir(dir_tbc_nnUNet_preprocessed);
                num_MatchingSubfolders = 0;
                % Iterate over each subfolder
                for i = 1:length(subfolders)
                    % Check if the current item is a folder
                    if subfolders(i).isdir
                        % Get the name of the subfolder
                        subfolderName = subfolders(i).name;
                        % Check if the subfolder name starts with the leading string
                        if strncmp(subfolderName, leading_string, length(leading_string))
                            num_MatchingSubfolders = num_MatchingSubfolders + 1;
                        end
                    end
                end
                sumnum = sumnum + num_MatchingSubfolders;
            end

            % summary
            if sumnum>0
                quest = sprintf(['Existing project files with the same ID are detected under these folder: \n\n',...
                    dir_tbc_nnUNet_raw_data, '\n',...
                    dir_tbc_nnUNet_cropped_data, '\n',...
                    dir_tbc_nnUNet_preprocessed, '\n\n',...
                    'You can either change the base folder (CartiMorph Toolbox Folder) or the project ID. \n\n',...
                    'Another solution is deleting these folders manually. \n\n',...
                    'What would you like to do next?']);
                dlgtitle = 'Existing projects with the same ID detected';
                answer = questdlg(quest, dlgtitle, 'change base folder', 'change project ID', 'change project ID');
                switch answer
                    case 'change base folder'
                        app.EditField_pathCMT.Value = '';
                        app.dir_base = '';
                    case 'change project ID'
                        app.EditField_ProjectID.Value = '';
                        app.config_nnUNet_ID = '';
                end
            else
                % once there is no conflic, toolbox should be re-initialize
                app.opt_CMT_init = true;
            end
        end


        function CMT_Run_action(app)

            % -----------------------------------------------------
            % [disable the buttons at the beginning of the pipeline]
            % -----------------------------------------------------
            CMT_disableButtons(app);
            % enable the pause/resume button
            app.Button_Pause.Enable = "on";
            app.Image_Info_pause.Enable = "on";
            % modify the run button
            app.Button_Run.Text = "Running";
            app.Button_Run.BackgroundColor = [143, 187, 118]./255; % #8FBB76 (green);
            % -----------------------------------------------------


            % -----------------------------------------------------
            % [toolbox initialization]
            % -----------------------------------------------------
            CMT_init(app);
            % -----------------------------------------------------


            % -------------------------------------------------------
            % (i) Computing Environment Initialization
            % -------------------------------------------------------
            CMT_CondaEnvSetup(app);
            % freeze i
            app.CheckBox_i.Enable = "off";
            % -------------------------------------------------------


            % -------------------------------------------------------
            % (ii) Raw Image Standardization
            % -------------------------------------------------------
            CMT_imgStandardization(app);
            % -------------------------------------------------------


            % -----------------------------------------------------
            % (iii/iii-2) Segmentation Model Training/Fine-tune
            % part 1: training
            % -----------------------------------------------------
            CMT_nnUNet_train(app);
            % -----------------------------------------------------


            % -----------------------------------------------------
            % (iv/iv-2) Registration Model Training/Fine-tune
            % -----------------------------------------------------
            % part 1: training
            CMT_vxm_train(app);
            % part 2: model evaluation
            CMT_vxm_eval(app);
            CMT_vxm_train_finished(app);
            % -----------------------------------------------------


            % -----------------------------------------------------
            % (iii/iii-2) Segmentation Model Training/Fine-tune
            % part 2: model evaluation on testing set
            % -----------------------------------------------------
            % wait for the nnUNet training
            if app.opt_nnUNet_training_begin && ~strcmp(app.container_jobStatus.job_nnUNet_train, "finished")
                CMT_wait4nnUNet_training(app);
            end
            % model evaluation
            CMT_nnUNet_eval(app);
            CMT_nnUNet_train_finished(app);
            % -----------------------------------------------------


            % -----------------------------------------------------
            % (v) Segmentation Model Inference
            % -----------------------------------------------------
            CMT_nnUNet_inference(app);
            % -----------------------------------------------------


            % -----------------------------------------------------
            % (vi) Registration Model Inference
            % -----------------------------------------------------
            CMT_vxm_inference(app);
            % -----------------------------------------------------


            % -----------------------------------------------------
            % (vii) Morphological Quantification
            % -----------------------------------------------------
            CMT_morphQuant(app);
            % -----------------------------------------------------


            % -----------------------------------------
            % [reset the buttons]
            % -----------------------------------------
            % general settings
            CMT_enableButtons(app);

            % (settings specific to the end of pipeline)
            % load button
            app.Button_Load.Enable = "on";
            app.Button_Load.Value = false;
            % run button
            app.Button_Run.Enable = "on";
            app.Button_Run.BackgroundColor = [0.96,0.96,0.96];
            app.Button_Run.Text = "Run";
            app.Button_Run.Value = false;
            % pause and resume buttons
            app.Button_Pause.Enable = "off";
            app.Button_Pause.Text = "Pause      ";
            app.Image_Info_pause.Enable = "off";
            % -----------------------------------------
        end


        function CMT_closeAllWindows(app)
            delete(app.aboutCMT);
            delete(app.CMViewer);
            delete(app);
        end


        function CMT_init(app)
            if app.opt_CMT_init
                % Conda source path
                app.path_conda_src = fullfile(app.dir_conda_base, "etc/profile.d/conda.sh");

                % CUDA library path
                CUDA_lib1 = fullfile(app.dir_conda_base, "envs/CartiMorphToolbox-Vxm/lib/python3.10/site-packages/nvidia/cublas/lib");
                CUDA_lib2 = fullfile(app.dir_conda_base, "envs/CartiMorphToolbox-Vxm/lib/python3.10/site-packages/nvidia/cudnn/lib");
                app.path_CUDA_lib_Vxm = strcat(CUDA_lib1, ":", CUDA_lib2);
                CUDA_lib3 = fullfile(app.dir_conda_base, "envs/CartiMorphToolbox-nnUNet/lib/python3.10/site-packages/nvidia/cublas/lib");
                CUDA_lib4 = fullfile(app.dir_conda_base, "envs/CartiMorphToolbox-nnUNet/lib/python3.10/site-packages/nvidia/cudnn/lib");
                CUDA_lib5 = fullfile(app.dir_conda_base, "envs/CartiMorphToolbox-nnUNet/lib/python3.10/site-packages/nvidia/nvjitlink/lib");
                app.path_CUDA_lib_nnUNet = strcat(CUDA_lib3, ":", CUDA_lib4, ":", CUDA_lib5);

                % source code folders
                app.dir_source_py = fullfile(app.dir_base, 'Source_Python');

                % model folders
                dir_model_base = fullfile(app.dir_base, 'Models_training');

                % nnUNet folders
                dir_model_seg = fullfile(dir_model_base, 'nnUNet');
                app.dir_nnUNet_rawBase = fullfile(dir_model_seg, 'nnUNet_raw_data_base');
                app.dir_nnUNet_raw = fullfile(app.dir_nnUNet_rawBase, 'nnUNet_raw_data', app.config_nnUNet_taskIDName);
                app.dir_nnUNet_raw_imgTr = fullfile(app.dir_nnUNet_raw, 'imagesTr');
                app.dir_nnUNet_raw_imgTs = fullfile(app.dir_nnUNet_raw, 'imagesTs');
                app.dir_nnUNet_raw_labelTr = fullfile(app.dir_nnUNet_raw, 'labelsTr');
                app.dir_nnUNet_raw_labelTs = fullfile(app.dir_nnUNet_raw, 'labelsTs');
                app.dir_nnUNet_raw_imgInference = fullfile(app.dir_nnUNet_raw, "imgInference");
                app.dir_nnUNet_preprocess = fullfile(dir_model_seg, 'nnUNet_preprocessed');
                app.dir_nnUNet_model = fullfile(dir_model_seg, 'nnUNet_trained_models', app.config_nnUNet_taskIDName);
                app.dir_nnUNet_autoScript = fullfile(dir_model_seg, 'nnUNet_autoScripts', app.config_nnUNet_taskIDName);
                app.dir_nnUNet_log = fullfile(dir_model_seg, 'nnUNet_log', app.config_nnUNet_taskIDName);
                app.dir_nnUNet_predictionBase = fullfile(dir_model_seg, 'nnUNet_prediction', app.config_nnUNet_taskIDName);
                app.dir_nnUNet_prediction_inferenceSet = fullfile(app.dir_nnUNet_predictionBase, 'inference', app.config_nnUNet_models);
                app.dir_nnUNet_prediction_testSet = fullfile(app.dir_nnUNet_predictionBase, 'test', app.config_nnUNet_models);
                if  ~isfolder(app.dir_nnUNet_raw_imgTr)
                    mkdir(app.dir_nnUNet_raw_imgTr);
                end
                if  ~isfolder(app.dir_nnUNet_raw_imgTs)
                    mkdir(app.dir_nnUNet_raw_imgTs);
                end
                if  ~isfolder(app.dir_nnUNet_raw_labelTr)
                    mkdir(app.dir_nnUNet_raw_labelTr);
                end
                if  ~isfolder(app.dir_nnUNet_raw_labelTs)
                    mkdir(app.dir_nnUNet_raw_labelTs);
                end
                if  ~isfolder(app.dir_nnUNet_raw_imgInference)
                    mkdir(app.dir_nnUNet_raw_imgInference);
                end
                if ~isfolder(app.dir_nnUNet_preprocess)
                    mkdir(app.dir_nnUNet_preprocess);
                end
                if ~isfolder(app.dir_nnUNet_model)
                    mkdir(app.dir_nnUNet_model);
                end
                if ~isfolder(app.dir_nnUNet_autoScript)
                    mkdir(app.dir_nnUNet_autoScript);
                end
                if ~isfolder(app.dir_nnUNet_log)
                    mkdir(app.dir_nnUNet_log);
                end
                if ~isfolder(app.dir_nnUNet_prediction_inferenceSet)
                    mkdir(app.dir_nnUNet_prediction_inferenceSet);
                end
                if ~isfolder(app.dir_nnUNet_prediction_testSet)
                    mkdir(app.dir_nnUNet_prediction_testSet);
                end

                % VoxelMorph folders and files
                dir_vxm_base = fullfile(dir_model_base, "VoxelMorph");
                app.dir_vxm_data_base = fullfile(dir_vxm_base, "vxm_data", app.config_nnUNet_taskIDName);
                app.dir_vxm_data_prepImgTr = fullfile(app.dir_vxm_data_base, "imagesTr_mdcn");
                app.dir_vxm_data_prepLabelTr = fullfile(app.dir_vxm_data_base, "labelsTr_mdcn");
                app.dir_vxm_data_prepImgTs = fullfile(app.dir_vxm_data_base, "imagesTs_mdcn");
                app.dir_vxm_data_prepLabelTs = fullfile(app.dir_vxm_data_base, "labelsTs_mdcn");
                app.dir_vxm_data_maskedImgInference = fullfile(app.dir_vxm_data_base, "imagesInference_mdcn_nnUNet"); % masked by nnUNet prediction
                app.dir_vxm_data_Template = fullfile(app.dir_vxm_data_base, "template");
                app.dir_vxm_model = fullfile(dir_vxm_base, "vxm_models", app.config_nnUNet_taskIDName);
                dir_vxm_inference_base = fullfile(dir_vxm_base, "vxm_inference", app.config_nnUNet_taskIDName);
                app.dir_vxm_inference_warpedLabels_Tr = fullfile(dir_vxm_inference_base, "img2temp_warpedLabels_Tr");
                app.dir_vxm_inference_warpedTempSeg_InferenceSet = fullfile(dir_vxm_inference_base, "temp2img_warpedTempSeg_InferenceSet");
                app.dir_vxm_inference_warpingField_InferenceSet = fullfile(dir_vxm_inference_base, "temp2img_warpingField_InferenceSet");
                app.dir_vxm_inference_warpedTempSeg_Ts = fullfile(dir_vxm_inference_base, "temp2img_warpedTempSeg_Ts");
                app.dir_vxm_inference_warpingField_Ts = fullfile(dir_vxm_inference_base, "temp2img_warpingField_Ts");
                app.dir_vxm_autoScript = fullfile(dir_vxm_base, "vxm_autoScripts", app.config_nnUNet_taskIDName);
                app.dir_vxm_log = fullfile(dir_vxm_base, "vxm_log", app.config_nnUNet_taskIDName);
                app.path_vxm_data_cropRange_nnUNet = fullfile(app.dir_vxm_data_base, "cropRange_nnUNetSeg.mat");
                app.path_vxm_data_cropRange_Tr = fullfile(app.dir_vxm_data_base, "cropRange_Label_Tr.mat");
                app.path_vxm_data_cropRange_Ts = fullfile(app.dir_vxm_data_base, "cropRange_Label_Ts.mat");
                if ~isfolder(app.dir_vxm_data_prepImgTr)
                    mkdir(app.dir_vxm_data_prepImgTr);
                end
                if ~isfolder(app.dir_vxm_data_prepLabelTr)
                    mkdir(app.dir_vxm_data_prepLabelTr);
                end
                if ~isfolder(app.dir_vxm_data_prepImgTs)
                    mkdir(app.dir_vxm_data_prepImgTs);
                end
                if ~isfolder(app.dir_vxm_data_prepLabelTs)
                    mkdir(app.dir_vxm_data_prepLabelTs);
                end
                if ~isfolder(app.dir_vxm_data_maskedImgInference)
                    mkdir(app.dir_vxm_data_maskedImgInference);
                end
                if ~isfolder(app.dir_vxm_data_Template)
                    mkdir(app.dir_vxm_data_Template);
                end
                if ~isfolder(app.dir_vxm_model)
                    mkdir(app.dir_vxm_model);
                end
                if ~isfolder(dir_vxm_inference_base)
                    mkdir(dir_vxm_inference_base);
                end
                if ~isfolder(app.dir_vxm_inference_warpedLabels_Tr)
                    mkdir(app.dir_vxm_inference_warpedLabels_Tr);
                end
                if ~isfolder(app.dir_vxm_inference_warpedTempSeg_InferenceSet)
                    mkdir(app.dir_vxm_inference_warpedTempSeg_InferenceSet);
                end
                if ~isfolder(app.dir_vxm_inference_warpingField_InferenceSet)
                    mkdir(app.dir_vxm_inference_warpingField_InferenceSet);
                end
                if ~isfolder(app.dir_vxm_inference_warpedTempSeg_Ts)
                    mkdir(app.dir_vxm_inference_warpedTempSeg_Ts);
                end
                if ~isfolder(app.dir_vxm_inference_warpingField_Ts)
                    mkdir(app.dir_vxm_inference_warpingField_Ts);
                end
                if ~isfolder(app.dir_vxm_autoScript)
                    mkdir(app.dir_vxm_autoScript);
                end
                if ~isfolder(app.dir_vxm_log)
                    mkdir(app.dir_vxm_log);
                end

                % folder of project configuration files
                app.dir_projectConfig = fullfile(app.dir_base, "ProjectConfiguration", app.config_nnUNet_taskIDName);
                if ~isfolder(app.dir_projectConfig)
                    mkdir(app.dir_projectConfig)
                end

                % bash setup for Matlab
                setenv('MATLAB_SHELL', app.path_bash_bin);

                % results folder
                app.dir_result = fullfile(app.dir_base, "Results", app.config_nnUNet_taskIDName);
                if ~isfolder(app.dir_result)
                    mkdir(app.dir_result);
                end

                % log the cartilage morphological quantification
                logFileName = strcat('log_', regexprep(char(datetime), {' ', ':'}, '_'));
                app.container_logFileID = fopen(fullfile(app.dir_result, logFileName), 'w+');

                % freeze fields in section I
                app.EditField_ProjectID.Editable = "off";
                app.EditField_ProjectCodeName.Editable = "off";
                app.EditField_pathCMT.Editable = "off";
                app.Button_selectpathCMT.Enable = "off";
                app.EditField_pathConda.Editable = "off";
                app.Button_selectpathConda.Enable = "off";

                % disable this step
                app.opt_CMT_init = false;
            end
        end


        function status = CMT_checkFields(app)
            % initialize status
            status = true;
            % check eidtable fields
            if isempty(app.EditField_ProjectID.Value)
                uialert(app.CartiMorphToolboxUIFigure, 'Missing field in (I): "Project ID (3 digits)".', 'Error', 'CloseFcn','uiresume(gcbf)');
                uiwait(gcbf);
                app.Button_Run.Value = false;
                status = false;
                return;
            end
            if isempty(app.EditField_ProjectCodeName.Value)
                uialert(app.CartiMorphToolboxUIFigure, 'Missing field in (I): "Project Code Name".', 'Error', 'CloseFcn','uiresume(gcbf)');
                uiwait(gcbf);
                app.Button_Run.Value = false;
                status = false;
                return;
            end
            if isempty(app.EditField_pathCMT.Value)
                uialert(app.CartiMorphToolboxUIFigure, 'Missing field in (I): "CartiMorph Toolbox Folder".', 'Error', 'CloseFcn','uiresume(gcbf)');
                uiwait(gcbf);
                app.Button_Run.Value = false;
                status = false;
                return;
            end
            if isempty(app.EditField_pathConda.Value)
                uialert(app.CartiMorphToolboxUIFigure, 'Missing field in (I): "Conda Folder".', 'Error', 'CloseFcn','uiresume(gcbf)');
                uiwait(gcbf);
                app.Button_Run.Value = false;
                status = false;
                return;
            end
            if app.EditField_imgTr.Enable && isempty(app.EditField_imgTr.Value)
                uialert(app.CartiMorphToolboxUIFigure, 'Missing field in (III): "Training Images".', 'Error', 'CloseFcn','uiresume(gcbf)');
                uiwait(gcbf);
                app.Button_Run.Value = false;
                status = false;
                return;
            end
            if app.EditField_imgTs.Enable && isempty(app.EditField_imgTs.Value)
                uialert(app.CartiMorphToolboxUIFigure, 'Missing field in (III): "Testing Images".', 'Error', 'CloseFcn','uiresume(gcbf)');
                uiwait(gcbf);
                app.Button_Run.Value = false;
                status = false;
                return;
            end
            if app.EditField_labelTr.Enable && isempty(app.EditField_labelTr.Value)
                uialert(app.CartiMorphToolboxUIFigure, 'Missing field in (III): "Training Labels".', 'Error', 'CloseFcn','uiresume(gcbf)');
                uiwait(gcbf);
                app.Button_Run.Value = false;
                status = false;
                return;
            end
            if app.EditField_labelTs.Enable && isempty(app.EditField_labelTs.Value)
                uialert(app.CartiMorphToolboxUIFigure, 'Missing field in (III): "Testing Labels".', 'Error', 'CloseFcn','uiresume(gcbf)');
                uiwait(gcbf);
                app.Button_Run.Value = false;
                status = false;
                return;
            end
            if app.EditField_imgInference.Enable && isempty(app.EditField_imgInference.Value)
                uialert(app.CartiMorphToolboxUIFigure, 'Missing field in (III): "Inference Images".', 'Error', 'CloseFcn','uiresume(gcbf)');
                uiwait(gcbf);
                app.Button_Run.Value = false;
                status = false;
                return;
            end
            if app.EditField_Femur.Enable && sum(cellfun(@isempty, app.config_ROI_label))~=0
                uialert(app.CartiMorphToolboxUIFigure, 'Missing field(s) in (III): "Segmentation Labels".', 'Error', 'CloseFcn','uiresume(gcbf)');
                uiwait(gcbf);
                app.Button_Run.Value = false;
                status = false;
                return;
            end
            if app.EditField_csvPath.Enable && isempty(app.EditField_csvPath.Value)
                uialert(app.CartiMorphToolboxUIFigure, 'Missing field in (III): "Knee Side Information".', 'Error', 'CloseFcn','uiresume(gcbf)');
                uiwait(gcbf);
                app.Button_Run.Value = false;
                status = false;
                return;
            end
            if app.EditField_ii_imgSizeX.Enable && any(isnan(app.config_nnUNet_imgSize))
                uialert(app.CartiMorphToolboxUIFigure, 'Missing field(s) in (II-ii): "Resampled Image Size".', 'Error', 'CloseFcn','uiresume(gcbf)');
                uiwait(gcbf);
                app.Button_Run.Value = false;
                status = false;
                return;
            end
            if app.CheckBox_iii2.Value && ~isequal(app.Lamp_iii2_loadModel.Color, [0.3804, 0.7333, 0.2745])
                uialert(app.CartiMorphToolboxUIFigure, 'You should load the segmentation model in (II-iii-2) first.',...
                    'Error', 'CloseFcn','uiresume(gcbf)');
                uiwait(gcbf);
                app.Button_Run.Value = false;
                status = false;
                return;
            end
            if app.CheckBox_iv_prepro.Value && any(isnan(app.config_vxm_imgSize_out))
                uialert(app.CartiMorphToolboxUIFigure, 'Missing field(s) in (II-iv): "Cropped Image Size".', 'Error', 'CloseFcn','uiresume(gcbf)');
                uiwait(gcbf);
                app.Button_Run.Value = false;
                status = false;
                return;
            end
            if app.CheckBox_iv2.Value && ~isequal(app.Lamp_iv2_loadModel.Color, [0.3804, 0.7333, 0.2745])
                uialert(app.CartiMorphToolboxUIFigure, 'You should load the registration model in (II-iv-2) first.',...
                    'Error', 'CloseFcn','uiresume(gcbf)');
                uiwait(gcbf);
                app.Button_Run.Value = false;
                status = false;
                return;
            end
            % check training epoch setting for VoxelMorph
            if ~app.opt_vxm_training_scratch
                tmp = dir(fullfile(app.dir_vxm_model, "*.h5"));
                vxm_list_models_cell = {tmp.name}';
                vxm_modelEpoch = zeros(length(vxm_list_models_cell),1);
                for i=1:length(vxm_list_models_cell)
                    vxm_modelEpoch(i) = str2double(vxm_list_models_cell{i}(end-8:end-3));
                end
                [~, vxm_idx_lastModel] = max(vxm_modelEpoch);
                config_vxm_lastModelEpoch = vxm_modelEpoch(vxm_idx_lastModel);
                if config_vxm_lastModelEpoch >= str2double(app.config_vxm_epochs)
                    uialert(app.CartiMorphToolboxUIFigure,...
                        'A model with an epoch number larger than the specified training epoch has been detected.',...
                        'Error', 'CloseFcn','uiresume(gcbf)');
                    uiwait(gcbf);
                    app.Button_Run.Value = false;
                    status = false;
                    return;
                end
            end

        end


        function path_projectConfig = CMT_setConfigFile(app)
            % user-defined path
            defaultFile_projectConfig = strcat('config_', regexprep(char(datetime), {' ', ':'}, '_'));
            prompt = {'\fontsize{14} Please set the name of the configuration file to be saved.'};
            opts.Interpreter = 'tex';
            answer = inputdlg(prompt, "Configuration file name", 1, ...
                {defaultFile_projectConfig}, opts);

            % set path
            if ~isempty(answer)
                fileName_projectConfig = answer{1};
                % (when the user include .mat in file name)
                if length(fileName_projectConfig)>4 && strcmp(fileName_projectConfig(end-3:end), '.mat')
                    fileName_projectConfig = fileName_projectConfig(1:end-4);
                end

                if isempty(app.dir_projectConfig)
                    app.dir_projectConfig = fullfile(app.dir_base, "ProjectConfiguration", app.config_nnUNet_taskIDName);
                    if ~isfolder(app.dir_projectConfig)
                        mkdir(app.dir_projectConfig);
                    end
                end
                path_projectConfig = fullfile(app.dir_projectConfig, strcat(fileName_projectConfig, ".mat"));

                % (when existing file is detected)
                if isfile(path_projectConfig)
                    quest = 'The specified project configuration file already exists. What would you like to do next?';
                    dlgtitle = 'Project configuration file exists';
                    answer = questdlg(quest, dlgtitle, 'overwrite existing file', 'reset file name',...
                        'cancel', 'reset file name');
                    switch answer
                        case 'overwrite existing file'
                            return;
                        case 'reset file name'
                            path_projectConfig = CMT_setConfigFile(app);
                            return;
                        case 'cancel'
                            path_projectConfig = '';
                            return;
                    end
                end
            else
                path_projectConfig = '';
                return;
            end

        end


        function CMT_saveConfig(app)
            if ~isempty(app.config_nnUNet_ID) && ~isempty(app.config_nnUNet_task) && ~isempty(app.dir_base)
                % set configuration file path
                path_projectConfig = CMT_setConfigFile(app);
                if isempty(path_projectConfig)
                    return;
                end

                % save configurations
                CMT_saveConfig_action(app, path_projectConfig, true);

                % show message
                message = sprintf('The configuration and status of the current project have been saved to: %s \n', path_projectConfig);
                uialert(app.CartiMorphToolboxUIFigure, message, 'Save project configuration and status',...
                    'icon', 'success', 'CloseFcn','uiresume(gcbf)');
                uiwait(gcbf);
            else
                uialert(app.CartiMorphToolboxUIFigure, "Please set the 'Project ID', 'Project Code Name', and 'CartiMorph Toolbox Folder' first.",...
                    'Missing configuration', 'icon', 'error');
            end
        end


        function CMT_autosaveConfig(app, configFileName)
            % predefined path for autosaved configuration
            if isempty(app.dir_projectConfig)
                app.dir_projectConfig = fullfile(app.dir_base, "ProjectConfiguration", app.config_nnUNet_taskIDName);
                if ~isfolder(app.dir_projectConfig)
                    mkdir(app.dir_projectConfig);
                end
            end
            path_projectConfig = fullfile(app.dir_projectConfig, configFileName);

            % save configurations
            CMT_saveConfig_action(app, path_projectConfig, false);
        end


        function CMT_saveConfig_action(app, path_projectConfig, verbose)
            % display waitbar
            if verbose
                f = waitbar(0, 'Collecting configurations...');
            end

            % collect public properties that correspond to app components
            publicProps = properties(app);
            publicProps_values = cell(1, length(publicProps));
            publicProps_visibilities = cell(1, length(publicProps));
            publicProps_color = cell(1, length(publicProps));
            publicProps_BGcolor = cell(1, length(publicProps));
            publicProps_enable = cell(1, length(publicProps));
            publicProps_enditable = cell(1, length(publicProps));
            publicProps_text = cell(1, length(publicProps));
            for i = 1:length(publicProps)
                propName = publicProps{i};
                property = app.(propName);
                if isprop(property, 'Value')
                    publicProps_values{i} = app.(propName).Value;
                end
                if isprop(property, 'Visible')
                    publicProps_visibilities{i} = app.(propName).Visible;
                end
                if isprop(property, 'Color')
                    publicProps_color{i} = app.(propName).Color;
                end
                if isprop(property, 'BackgroundColor')
                    publicProps_BGcolor{i} = app.(propName).BackgroundColor;
                end
                if isprop(property, 'Enable')
                    publicProps_enable{i} = app.(propName).Enable;
                end
                if isprop(property, 'Editable')
                    publicProps_enditable{i} = app.(propName).Editable;
                end
                if isprop(property, 'Text')
                    publicProps_text{i} = app.(propName).Text;
                end
            end

            % display waitbar
            if verbose
                waitbar(1/4, f, 'Collecting configurations...');
            end

            % collect public properties/variables
            allProps = ?CartiMorphToolbox;
            allProps_list = allProps.PropertyList;
            idx_publicProps = zeros([10000, 1]);
            k_count = 0;
            for k=1:length(allProps_list)
                if strcmp(allProps_list(k).GetAccess, 'public')
                    if strcmp(allProps_list(k).Description, "CMT Public Variable")
                        k_count = k_count + 1;
                        idx_publicProps(k_count, 1) = k;
                    end
                end
            end
            idx_publicProps(idx_publicProps==0) = [];
            num_publicProps = length(idx_publicProps);
            publicVarNames = cell([num_publicProps, 1]);
            publicVars = cell([num_publicProps, 1]);
            for k = 1:num_publicProps
                propName = allProps_list(idx_publicProps(k)).Name;
                publicVarNames{k} = propName;
                publicVars{k} = app.(propName);
            end

            % display waitbar
            if verbose
                waitbar(2/4, f, 'Collecting configurations...');
            end

            % collect private properties/variables
            allProps = ?CartiMorphToolbox;
            allProps_list = allProps.PropertyList;
            idx_privateProps = zeros([10000, 1]);
            j_count = 0;
            for j=1:length(allProps_list)
                if strcmp(allProps_list(j).GetAccess, 'private')
                    j_count = j_count + 1;
                    idx_privateProps(j_count, 1) = j;
                end
            end
            idx_privateProps(idx_privateProps==0) = [];
            num_privateProps = length(idx_privateProps);
            privatePropNames = cell([num_privateProps, 1]);
            privateProps = cell([num_privateProps, 1]);
            for k = 1:num_privateProps
                propName = allProps_list(idx_privateProps(k)).Name;
                privatePropNames{k} = propName;
                privateProps{k} = app.(propName);
            end

            % display waitbar
            if verbose
                waitbar(3/4, f, 'Saving configurations...');
            end

            % [important] remove app.container_jobs since the job object can not be loaded from mat file
            idx_tbr = find(strcmp(privatePropNames, "container_jobs"));
            privatePropNames(idx_tbr) = [];
            privateProps(idx_tbr) = [];

            % [important] remove other Apps: CMV and aboutCMT
            idx_CMV = strcmp(privatePropNames, "CMViewer");
            idx_aboutCMT = strcmp(privatePropNames, "aboutCMT");
            privatePropNames(idx_CMV) = [];
            privatePropNames(idx_aboutCMT) = [];
            privateProps(idx_CMV) = [];
            privateProps(idx_aboutCMT) = [];

            % save the project configuration & status
            save(path_projectConfig, 'publicVarNames', 'publicVars', 'publicProps', 'publicProps_values',...
                'publicProps_visibilities', 'publicProps_color', 'publicProps_BGcolor', 'publicProps_enable',...
                'publicProps_enditable', 'publicProps_text', 'privatePropNames', 'privateProps');

            % display waitbar
            if verbose
                waitbar(4/4, f, 'Done...');
                delete(f);
            end

        end


        function CMT_loadConfig(app)
            % select path
            [file, path] = uigetfile('*.mat','Select a project configuration file.');
            if path~=0
                % get current version
                version_info = app.CMT_version.Text;

                % display waitbar
                f = waitbar(0, 'Loading configurations..');

                % load properties
                load(fullfile(path, file), 'publicVarNames', 'publicVars', 'publicProps', 'publicProps_values',...
                    'publicProps_visibilities', 'publicProps_color', 'publicProps_BGcolor', 'publicProps_enable',...
                    'publicProps_enditable', 'publicProps_text', 'privatePropNames', 'privateProps');

                % display waitbar
                waitbar(1/5, f, 'Loading configurations..');

                % recover public properties
                for i = 1:length(publicProps)
                    propName = publicProps{i};
                    property = app.(propName);
                    if isprop(property, 'Value')
                        app.(propName).Value = publicProps_values{i};
                    end
                    if isprop(property, 'Visible')
                        app.(propName).Visible = publicProps_visibilities{i};
                    end
                    if isprop(property, 'Color')
                        app.(propName).Color = publicProps_color{i};
                    end
                    if isprop(property, 'BackgroundColor')
                        app.(propName).BackgroundColor = publicProps_BGcolor{i};
                    end
                    if isprop(property, 'Enable')
                        app.(propName).Enable = publicProps_enable{i};
                    end
                    if isprop(property, 'Editable')
                        app.(propName).Editable = publicProps_enditable{i};
                    end
                    if isprop(property, 'Text')
                        app.(propName).Text = publicProps_text{i};
                    end
                end

                % display waitbar
                waitbar(2/5, f, 'Setting toolbox properties..');

                % recover public properties/variables
                for j=1:length(publicVarNames)
                    app.(publicVarNames{j}) = publicVars{j};
                end

                % display waitbar
                waitbar(3/5, f, 'Setting toolbox properties..');

                % recover private properties/variables
                for j=1:length(privatePropNames)
                    app.(privatePropNames{j}) = privateProps{j};
                end

                % display waitbar
                waitbar(4/5, f, 'Setting toolbox properties..');

                % reset the save button
                app.Button_Save.Value = false;
                app.Button_Save.Enable = "on";

                % reset version info
                app.CMT_version.Text = version_info;

                % display waitbar
                waitbar(5/5, f, 'Done..');
                delete(f);
            end
        end


        function CMT_exportSegModel_auto(app)
            % set model folder and identifier
            model_identifier = "autosaved";
            model_folder = strcat('nnUNet-', model_identifier);
            % export segmentation model
            CMT_exportSegModel_action(app, model_folder, model_identifier, false);
        end


        function CMT_exportSegModel_action(app, model_folder, model_identifier, verbose)
            % freeze the main ui
            CMT_disableButtons(app);

            if verbose
                % display waitbar
                f = waitbar(0, 'Exporting the segmentation model...');
            end

            % folder containing the exported model
            dir_preTrainedModel = fullfile(app.dir_base, "Models_saved", "segmentation", model_folder);
            mkdir(dir_preTrainedModel);

            % plans_identifier and the original model folder
            if app.CheckBox_iii.Value
                plans_identifier = "nnUNetPlansv2.1";
            else
                plans_identifier = strcat("nnUNetPlans_pretrained_", app.config_nnUNet_loadedPlans_identifier);
            end
            dir_model = fullfile(app.dir_nnUNet_model, "nnUNet", app.config_nnUNet_model_archi, app.config_nnUNet_taskIDName,...
                strcat("nnUNetTrainerV2__", plans_identifier));
            dir_model_full = fullfile(dir_model, "all");

            % [segModelConfig.mat]
            segModelConfig.config_nnUNet_imgSize = app.config_nnUNet_imgSize;
            app.config_nnUNet_exportedPlans_identifier = model_identifier;
            segModelConfig.config_nnUNet_loadedPlans_identifier = model_identifier;
            segModelConfig.config_ROI_label = app.config_ROI_label;
            segModelConfig.config_ROI_name = app.config_ROI_name;
            segModelConfig.config_nnUNet_model_archi = app.config_nnUNet_model_archi;
            path_config = fullfile(dir_preTrainedModel, 'segModelConfig.mat');
            save(path_config, 'segModelConfig');

            if verbose
                % display waitbar
                waitbar(1/5, f, 'Saving model config files: segModelConfig.mat...');
            end

            % [plans.pkl]
            path_src_plans = fullfile(dir_model, "plans.pkl");
            path_tar_plans = fullfile(dir_preTrainedModel, "plans.pkl");
            copyfile(path_src_plans, path_tar_plans);

            if verbose
                % display waitbar
                waitbar(2/5, f, 'Saving model config files: plans.pkl...');
            end

            % [postprocessing.json]
            path_src_pp = fullfile(dir_model_full, 'postprocessing.json');
            path_tar_pp = fullfile(dir_preTrainedModel, 'postprocessing.json');
            copyfile(path_src_pp, path_tar_pp);

            if verbose
                % display waitbar
                waitbar(3/5, f, 'Saving model config files: postprocessing.json...');
            end

            % [segModel.model]
            path_src_model = fullfile(dir_model_full, 'model_final_checkpoint.model');
            path_tar_model = fullfile(dir_preTrainedModel, 'segModel.model');
            copyfile(path_src_model, path_tar_model);

            if verbose
                % display waitbar
                waitbar(4/5, f, 'Saving model: segModel.model...');
            end

            % [segModel.model.pkl]
            path_src_model_pkl = fullfile(dir_model_full, 'model_final_checkpoint.model.pkl');
            path_tar_model_pkl = fullfile(dir_preTrainedModel, 'segModel.model.pkl');
            copyfile(path_src_model_pkl, path_tar_model_pkl);

            if verbose
                % display waitbar
                waitbar(4/5, f, 'Saving model: segModel.model.pkl...');
            end

            % set the flag
            app.flag_SegModel_exported = true;

            if verbose
                % display waitbar
                waitbar(5/5, f, 'Done...');
                delete(f);
            end

            if verbose
                % show message
                message = sprintf('The segmentation model has been saved to: %s \n', dir_preTrainedModel);
                uialert(app.CartiMorphToolboxUIFigure, message, 'Export segmentation model',...
                    'icon', 'success', 'CloseFcn','uiresume(gcbf)');
                uiwait(gcbf);
            end

            % recover the main ui
            CMT_enableButtons(app);
        end


        function CMT_exportSegModel(app)
            [model_folder, model_identifier] = CMT_setSegModelFolder(app);
            if isempty(model_folder)
                return;
            else
                % export segmentation model
                CMT_exportSegModel_action(app, model_folder, model_identifier, true);
            end
        end


        function [model_folder, model_identifier] = CMT_setSegModelFolder(app)
            % base foder of pretrained models (CMT's default setting)
            dir_pretrained_SegModel = fullfile(app.dir_base, "Models_saved", "segmentation");
            if ~isfolder(dir_pretrained_SegModel)
                mkdir(dir_pretrained_SegModel);
            end

            % user-defined path
            prompt = {'\fontsize{14} Kindly specify a model identifier of your choice.'};
            opts.Interpreter = 'tex';
            model_identifier = inputdlg(prompt, "Segmentation model identifier", 1, {''}, opts);
            model_folder = strcat('nnUNet-', model_identifier);
            if isempty(model_identifier)
                model_folder = '';
                return;
            elseif isfolder(fullfile(dir_pretrained_SegModel, model_folder))
                quest = 'The specified folder already exists. What would you like to do next?';
                dlgtitle = 'Duplicated model identifier detected';
                answer = questdlg(quest, dlgtitle, 'overwrite existing model', 'reset model identifier',...
                    'cancel', 'reset model identifier');
                switch answer
                    case 'overwrite existing model'
                        return;
                    case 'reset model identifier'
                        model_folder = CMT_setSegModelFolder(app);
                        return;
                    case 'cancel'
                        model_folder = '';
                        return;
                end
            end
        end


        function CMT_exportRegModel_auto(app)
            % set model folder and identifier
            model_folder = 'VoxelMorph-autosaved';
            % export the registration model
            CMT_exportRegModel_action(app, model_folder, false);
        end


        function CMT_exportRegModel_action(app, model_folder, verbose)
            % freeze the main ui
            CMT_disableButtons(app);

            if verbose
                % display waitbar
                f = waitbar(0, 'Exporting the registration model...');
            end

            % folder containing the exported model
            dir_preTrainedModel = fullfile(app.dir_base, "Models_saved", "registration", model_folder);
            if ~isfolder(dir_preTrainedModel)
                mkdir(dir_preTrainedModel);
            end

            % [regModelConfig.mat]
            % output image size
            regModelConfig.config_vxm_imgSize_out = app.config_vxm_imgSize_out;
            % vxm model architecture
            regModelConfig.config_vxm_encoderArchi_base = app.config_vxm_encoderArchi_base;
            regModelConfig.config_vxm_decoderArchi_base = app.config_vxm_decoderArchi_base;
            regModelConfig.config_vxm_encoderArchi = app.config_vxm_encoderArchi;
            regModelConfig.config_vxm_decoderArchi = app.config_vxm_decoderArchi;

            path_config = fullfile(dir_preTrainedModel, "regModelConfig.mat");
            save(path_config, "regModelConfig");

            if verbose
                % display waitbar
                waitbar(1/4, f, 'Saving model config files: regModelConfig.mat...');
            end

            % [regModel.h5]
            path_src_model = fullfile(app.dir_vxm_model, "bestModel.h5");
            path_tar_model = fullfile(dir_preTrainedModel, "regModel.h5");
            copyfile(path_src_model, path_tar_model);

            if verbose
                % display waitbar
                waitbar(2/4, f, 'Saving model: regModel.h5...');
            end

            % [template.nii.gz]
            path_src_temp = fullfile(app.dir_vxm_data_Template, "template.nii.gz");
            path_tar_temp = fullfile(dir_preTrainedModel, "template.nii.gz");
            copyfile(path_src_temp, path_tar_temp);

            if verbose
                % display waitbar
                waitbar(3/4, f, 'Saving template image: template.nii.gz...');
            end

            % [templateSeg.nii.gz]
            path_src_tempSeg = fullfile(app.dir_vxm_data_Template, "templateSeg.nii.gz");
            path_tar_tempSeg = fullfile(dir_preTrainedModel, "templateSeg.nii.gz");
            copyfile(path_src_tempSeg, path_tar_tempSeg);

            if verbose
                % display waitbar
                waitbar(3/4, f, 'Saving template segmentation mask: templateSeg.nii.gz...');
            end

            % set the flag
            app.flag_RegModel_exported = true;

            if verbose
                % display waitbar
                waitbar(4/4, f, 'Done...');
                delete(f);
            end

            if verbose
                % show message
                message = sprintf('The registration model has been saved to: %s \n', dir_preTrainedModel);
                uialert(app.CartiMorphToolboxUIFigure, message, 'Export registration model',...
                    'icon', 'success', 'CloseFcn','uiresume(gcbf)');
                uiwait(gcbf);
            end

            % recover the main ui
            CMT_enableButtons(app);
        end


        function CMT_exportRegModel(app)
            [model_folder, ~] = CMT_setRegModelFolder(app);
            if isempty(model_folder)
                return;
            else
                % export the registration model
                CMT_exportRegModel_action(app, model_folder, true);
            end
        end


        function [model_folder, model_identifier] = CMT_setRegModelFolder(app)
            % base foder of pretrained models (CMT's default setting)
            dir_pretrained_RegModel = fullfile(app.dir_base, "Models_saved", "registration");
            if ~isfolder(dir_pretrained_RegModel)
                mkdir(dir_pretrained_RegModel);
            end

            % user-defined path
            if ~isempty(app.config_nnUNet_exportedPlans_identifier)
                defaultValue = app.config_nnUNet_exportedPlans_identifier;
            else
                defaultValue = '';
            end
            prompt = {'\fontsize{14} Kindly specify a model identifier of your choice.'};
            opts.Interpreter = 'tex';
            model_identifier = inputdlg(prompt, "Registration model identifier", 1, {defaultValue}, opts);
            model_folder = strcat('VoxelMorph-', model_identifier);
            if isempty(model_identifier)
                model_folder = '';
                return;
            elseif isfolder(fullfile(dir_pretrained_RegModel, model_folder))
                quest = 'The specified folder already exists. What would you like to do next?';
                dlgtitle = 'Duplicated model identifier detected';
                answer = questdlg(quest, dlgtitle, 'overwrite existing model', 'reset model identifier',...
                    'cancel', 'reset model identifier');
                switch answer
                    case 'overwrite existing model'
                        return;
                    case 'reset model identifier'
                        model_folder = CMT_setRegModelFolder(app);
                        return;
                    case 'cancel'
                        model_folder = '';
                        return;
                end
            end
        end


        function [tab_DSC, tab_DSC_mean] = CMT_evalSegModel(app)
            % display waitbar
            f= waitbar(0, 'Evaluating the segmentation model...');

            % list of cases
            tmp = [dir(fullfile(app.dir_nnUNet_prediction_testSet, "*.nii.gz"));...
                dir(fullfile(app.dir_nnUNet_prediction_testSet, "*.nii"))];
            list_caseNames = {tmp.name}';
            num_cases = length(list_caseNames);

            % number of ROIs (except background)
            GT_labels = zeros([1, length(app.config_ROI_label)]);
            for ii=1:length(app.config_ROI_label)
                GT_labels(1, ii) = str2double(app.config_ROI_label{ii});
            end
            GT_labels(GT_labels==0) = [];
            num_ROIs = length(GT_labels);

            % calculate DSC for each case
            metric_DSC = zeros([num_cases, num_ROIs], "double");
            for i=1:num_cases
                % display waitbar
                waitbar(i/num_cases, f, 'Evaluating the segmentation model...');
                % read nifti file
                i_path_prediction = fullfile(app.dir_nnUNet_prediction_testSet, list_caseNames{i});
                i_prediction = niftiread(i_path_prediction);
                i_path_GT = fullfile(app.dir_nnUNet_raw_labelTs, list_caseNames{i});
                i_GT = niftiread(i_path_GT);
                % calculate DSC for each ROI
                for j=1:num_ROIs
                    ij_mask_prediction = i_prediction==GT_labels(j);
                    ij_mask_GT = i_GT==GT_labels(j);
                    if sum(ij_mask_GT, 'all')>0
                        metric_DSC(i, j) = dice(ij_mask_GT, ij_mask_prediction);
                    else
                        metric_DSC(i, j) = nan;
                    end
                end
            end

            % [orginize results into table]
            % DSC
            tab_casesName = cell2table(list_caseNames, "VariableNames", "Case Name");
            tab_DSC = array2table(metric_DSC);
            tab_DSC.Properties.VariableNames = cellstr(string(GT_labels));
            tab_DSC = [tab_casesName, tab_DSC];
            % mean DSC
            metric_DSC_mean = mean(metric_DSC, 1);
            tab_DSC_mean = array2table(metric_DSC_mean);
            tab_DSC_mean.Properties.VariableNames = cellstr(string(GT_labels));

            % display waitbar
            waitbar(1, f, 'Done...');
            delete(f);
        end


        function [tab_DSC, tab_DSC_mean] = CMT_evalRegModel(app)
            % display waitbar
            f= waitbar(0, 'Evaluating the registration model...');

            % list of cases
            tmp = [dir(fullfile(app.dir_vxm_data_prepImgTs, "*.nii.gz"));...
                dir(fullfile(app.dir_vxm_data_prepImgTs, "*.nii"))];
            list_caseNames = {tmp.name}';
            num_cases = length(list_caseNames);

            % number of ROIs (except background)
            GT_labels = zeros([1, length(app.config_ROI_label)]);
            for ii=1:length(app.config_ROI_label)
                GT_labels(1, ii) = str2double(app.config_ROI_label{ii});
            end
            GT_labels(GT_labels==0) = [];
            num_ROIs = length(GT_labels);

            % calculate DSC for each case
            metric_DSC = zeros([num_cases, num_ROIs], "double");
            for i=1:num_cases
                % display waitbar
                waitbar(i/num_cases, f, 'Evaluating the segmentation model...');

                % read nifti file
                i_path_prediction = fullfile(app.dir_vxm_inference_warpedTempSeg_Ts, list_caseNames{i});
                i_prediction = niftiread(i_path_prediction);
                i_path_GT = fullfile(app.dir_vxm_data_prepLabelTs, list_caseNames{i});
                i_GT = niftiread(i_path_GT);

                % calculate DSC for each ROI
                for j=1:num_ROIs
                    ij_mask_prediction = i_prediction==GT_labels(j);
                    ij_mask_GT = i_GT==GT_labels(j);
                    if sum(ij_mask_GT, 'all')>0
                        metric_DSC(i, j) = dice(ij_mask_GT, ij_mask_prediction);
                    else
                        metric_DSC(i, j) = nan;
                    end
                end
            end

            % [orginize results into table]
            % DSC
            tab_casesName = cell2table(list_caseNames, "VariableNames", "Case Name");
            tab_DSC = array2table(metric_DSC);
            tab_DSC.Properties.VariableNames = cellstr(string(GT_labels));
            tab_DSC = [tab_casesName, tab_DSC];
            % mean DSC
            metric_DSC_mean = mean(metric_DSC, 1);
            tab_DSC_mean = array2table(metric_DSC_mean);
            tab_DSC_mean.Properties.VariableNames = cellstr(string(GT_labels));

            % display waitbar
            waitbar(1, f, 'Done...');
            delete(f);
        end

        function CMT_CondaEnvSetup(app)
            if app.opt_CondaEnvSetup
                % env1: CartiMorphToolbox-nnUNet
                CMT_envSetup_CMTnnUNet(app);

                % env2: CartiMorphToolbox-vxm
                CMT_envSetup_CMTvxm(app);

                % light the lamp
                app.Lamp_i.Color = [0.3804, 0.7333, 0.2745];
                % freeze i
                app.CheckBox_i.Enable = "off";
                % disable this step
                app.opt_CondaEnvSetup = false;

                % autosave configuration
                CMT_autosaveConfig(app, 'config_latest');
                CMT_autosaveConfig(app, 'config_i');
            end
        end


        function CMT_envSetup_CMTnnUNet(app)
            if app.opt_CondaEnvSetup
                % create conda virtual environment: CartiMorphToolbox-nnUNet
                path_log_CondaEnvSetup = fullfile(app.dir_nnUNet_log, 'envSetup_CMTnnUNet.log');
                path_script = CMT_genEnvSetupScript_CMTnnUNet(app);
                cmd = strcat("chmod u+x ", path_script);
                system(cmd);
                cmd = strcat("source ", app.path_conda_src, "; bash ", path_script, " > ", path_log_CondaEnvSetup, " 2>&1");
                system(cmd);
            end
        end


        function path_script = CMT_genEnvSetupScript_CMTnnUNet(app)
            % TODO: remove old one
            % % CMT v1.0.4 ---------
            % % write "envSetup_CMTnnUNet.sh"
            % path_script = fullfile(app.dir_nnUNet_autoScript, 'envSetup_CMTnnUNet.sh');
            % fileID = fopen(path_script, 'w');
            % fprintf(fileID, '#!/usr/bin/env bash \n');
            % fprintf(fileID, '\n');
            % fprintf(fileID, '# remove old Conda environment: CartiMorphToolbox-nnUNet \n');
            % fprintf(fileID, 'conda remove --name CartiMorphToolbox-nnUNet --all -y \n');
            % fprintf(fileID, '\n');
            % fprintf(fileID, '# create Conda environment \n');
            % fprintf(fileID, 'conda create -n CartiMorphToolbox-nnUNet -y \n');
            % fprintf(fileID, '\n');
            % fprintf(fileID, '# activate Conda environment \n');
            % fprintf(fileID, 'eval "$(conda shell.bash hook)" \n'); % CMT v1.0.4 use anothers, check github
            % fprintf(fileID, 'conda activate CartiMorphToolbox-nnUNet \n');
            % fprintf(fileID, '\n');
            % fprintf(fileID, '# Python \n');
            % fprintf(fileID, 'conda install python=3.10 -y \n');
            % fprintf(fileID, 'python -m pip install update \n');
            % fprintf(fileID, '\n');
            % fprintf(fileID, '# PyTorch \n');
            % fprintf(fileID, 'conda install pytorch torchvision torchaudio pytorch-cuda=12.1 -c pytorch -c nvidia -y \n');
            % fprintf(fileID, '\n');
            % fprintf(fileID, '# CartiMorph-nnUNet \n');
            % fprintf(fileID, 'python -m pip install CartiMorph-nnUNet \n');
            % fprintf(fileID, 'python -m pip install batchgenerators \n');
            % fprintf(fileID, 'python -m pip install mkl \n');
            % fprintf(fileID, '\n');
            % fclose(fileID);
            % % CMT v1.0.4 ---------

            % CMT v1.0.5 ---------
            % write "envSetup_CMTnnUNet.sh"
            path_script = fullfile(app.dir_nnUNet_autoScript, 'envSetup_CMTnnUNet.sh');
            fileID = fopen(path_script, 'w');
            fprintf(fileID, '#!/usr/bin/env bash \n');
            fprintf(fileID, '\n');
            fprintf(fileID, '# remove old Conda environment: CartiMorphToolbox-nnUNet \n');
            fprintf(fileID, 'conda remove --name CartiMorphToolbox-nnUNet --all -y \n');
            fprintf(fileID, '\n');
            fprintf(fileID, '# create Conda environment \n');
            fprintf(fileID, 'conda create -n CartiMorphToolbox-nnUNet -y \n');
            fprintf(fileID, '\n');
            fprintf(fileID, '# activate Conda environment \n');
            fprintf(fileID, 'eval "$(conda shell.bash hook)" \n');
            fprintf(fileID, 'conda activate CartiMorphToolbox-nnUNet \n');
            fprintf(fileID, '\n');
            fprintf(fileID, '# Python \n');
            fprintf(fileID, 'conda install python=3.10 -y \n');
            fprintf(fileID, 'python -m pip install update \n');
            fprintf(fileID, '\n');
            fprintf(fileID, '# Nvidia libraries \n'); % tested in v1.0.5
            fprintf(fileID, 'conda install -c conda-forge cudatoolkit -y \n'); % maybe useless
            fprintf(fileID, 'python -m pip install nvidia-cudnn-cu11 \n'); %
            fprintf(fileID, 'mkdir -p $CONDA_PREFIX/etc/conda/activate.d \n');
            fprintf(fileID, 'echo "CUDNN_PATH=$(dirname $(python -c "import nvidia.cudnn;print(nvidia.cudnn.__file__)"))" >> $CONDA_PREFIX/etc/conda/activate.d/env_vars.sh \n');
            fprintf(fileID, 'echo "export LD_LIBRARY_PATH=$CONDA_PREFIX/lib/:$CUDNN_PATH/lib:$LD_LIBRARY_PATH" >> $CONDA_PREFIX/etc/conda/activate.d/env_vars.sh \n');
            fprintf(fileID, 'source $CONDA_PREFIX/etc/conda/activate.d/env_vars.sh \n');
            fprintf(fileID, '\n');
            fprintf(fileID, '# PyTorch \n');
            fprintf(fileID, 'python -m pip install torch torchvision torchaudio --index-url https://download.pytorch.org/whl/cu121 \n'); % tested in v1.0.5
            fprintf(fileID, '\n');
            fprintf(fileID, '# CartiMorph-nnUNet \n');
            fprintf(fileID, 'python -m pip install CartiMorph-nnUNet \n');
            fprintf(fileID, 'python -m pip install batchgenerators \n');
            fprintf(fileID, 'python -m pip install mkl \n');
            fprintf(fileID, '\n');
            fclose(fileID);
        end


        function CMT_envSetup_CMTvxm(app)
            if app.opt_CondaEnvSetup
                % create conda virtual environment: CartiMorphToolbox-vxm
                path_log_CondaEnvSetup = fullfile(app.dir_vxm_log, 'envSetup_CMTvxm.log');
                path_script = CMT_genEnvSetupScript_CMTvxm(app);
                cmd = strcat("chmod u+x ", path_script);
                system(cmd);
                cmd = strcat("source ", app.path_conda_src, "; bash ", path_script, " > ", path_log_CondaEnvSetup, " 2>&1");
                system(cmd);
            end
        end


        function path_script = CMT_genEnvSetupScript_CMTvxm(app)
            % write "envSetup_CMTvxm.sh"
            path_script = fullfile(app.dir_vxm_autoScript, 'envSetup_CMTvxm.sh');
            fileID = fopen(path_script, 'w');
            fprintf(fileID, '#!/usr/bin/env bash \n');
            fprintf(fileID, '\n');
            fprintf(fileID, '# remove old Conda environment: CartiMorphToolbox-Vxm \n');
            fprintf(fileID, 'conda remove --name CartiMorphToolbox-Vxm --all -y \n');
            fprintf(fileID, '\n');
            fprintf(fileID, '# create Conda environment \n');
            fprintf(fileID, 'conda create -n CartiMorphToolbox-Vxm -y \n');
            fprintf(fileID, '\n');
            fprintf(fileID, '# activate Conda environment \n');
            fprintf(fileID, 'eval "$(conda shell.bash hook)" \n');
            fprintf(fileID, 'conda activate CartiMorphToolbox-Vxm \n');
            fprintf(fileID, '\n');
            fprintf(fileID, '# Python \n');
            fprintf(fileID, 'conda install python=3.10 -y \n');
            fprintf(fileID, 'python -m pip install update \n');
            fprintf(fileID, '\n');
            fprintf(fileID, '# Nvidia libraries \n');
            fprintf(fileID, 'conda install -c conda-forge cudatoolkit=11.8.0 -y \n');
            fprintf(fileID, 'python -m pip install nvidia-cudnn-cu11==8.6.0.163 \n');
            fprintf(fileID, 'mkdir -p $CONDA_PREFIX/etc/conda/activate.d \n');
            fprintf(fileID, 'echo "CUDNN_PATH=$(dirname $(python -c "import nvidia.cudnn;print(nvidia.cudnn.__file__)"))" >> $CONDA_PREFIX/etc/conda/activate.d/env_vars.sh \n');
            fprintf(fileID, 'echo "export LD_LIBRARY_PATH=$CONDA_PREFIX/lib/:$CUDNN_PATH/lib:$LD_LIBRARY_PATH" >> $CONDA_PREFIX/etc/conda/activate.d/env_vars.sh \n');
            fprintf(fileID, 'source $CONDA_PREFIX/etc/conda/activate.d/env_vars.sh \n');
            fprintf(fileID, '\n');
            fprintf(fileID, '# TensorFlow \n');
            fprintf(fileID, 'python -m pip install tensorflow==2.12 \n');
            fprintf(fileID, '\n');
            fprintf(fileID, '# CartiMorph-vxm \n');
            % fprintf(fileID, 'python -m pip install protobuf==3.20.0 \n'); % used in v1.0.4
            fprintf(fileID, 'python -m pip install protobuf==3.20.0  \n');
            fprintf(fileID, 'python -m pip install chardet==5.1.0 \n');
            fprintf(fileID, 'python -m pip install simpleitk==2.1.1.2 \n');
            fprintf(fileID, 'python -m pip install keras==2.9.0 \n');
            fprintf(fileID, 'python -m pip install neurite==0.2 \n');
            fprintf(fileID, 'python -m pip install packaging==21.3 \n');
            fprintf(fileID, 'python -m pip install nibabel==3.2.2 \n');
            fprintf(fileID, 'python -m pip install numpy==1.23.2 \n');
            fprintf(fileID, 'python -m pip install CartiMorph-vxm \n');
            fprintf(fileID, '\n');
            fclose(fileID);
        end


        function CMT_imgStd_action(app, dir_in, dir_out, imgSize_out, flag_img)
            % ==============================================================================
            % FUNCTION:
            %     1. Convert all NIfTI files to RAS+ orientation in a folder.
            %     2. Resample images
            %     3. Normliaze image intensities into [0,1]
            %
            % INPUT:
            %     - dir_in: [char], input folder
            %     - dir_out: [char], output folder
            %     - imgSize_out: output image size
            %     - flag_img: [logical], is the input data images?
            %
            % OUTPUT:
            %     no output variable
            % ------------------------------------------------------------------------------
            % Matlab Version: 2019b or later
            %
            % Last updated on: 25-May-2022
            %
            % Author:
            % Yongcheng YAO (yao_yongcheng@link.cuhk.edu.hk)
            % Department of Imaging and Interventional Radiology,
            % Chinese University of Hong Kong (CUHK)
            %
            % Copyright 2020 Yongcheng YAO
            % ------------------------------------------------------------------------------
            % ==============================================================================


            % make folder
            if ~isfolder(dir_out)
                mkdir(dir_out)
            end

            % subject list
            dict_sub = [dir(fullfile(dir_in, "*.nii.gz")); dir(fullfile(dir_in, "*.nii"))];
            n_sub = size(dict_sub, 1);

            for i=1:n_sub
                % nifti file name
                i_nifti = dict_sub(i).name;
                [~, tmp_name, tmp_ext] = fileparts(i_nifti);
                if strcmp(tmp_ext, '.gz')
                    [~, i_fileName, ~] = fileparts(tmp_name);
                    i_fileName = char(i_fileName);
                else
                    i_fileName = char(tmp_name);
                end
                if flag_img
                    if strcmp(i_fileName(end-4:end), "_0000")
                        i_fileName_out = i_fileName;
                    else
                        i_fileName_out = strcat(i_fileName, "_0000");
                    end
                else
                    i_fileName_out = i_fileName;
                end
                % nifti info
                i_nifti_info = niftiinfo(fullfile(dir_in, i_nifti));
                % data array
                i_nifti_data = double(niftiread(i_nifti_info));
                % affine transformation matrix
                affineMat = i_nifti_info.Transform.T;

                % get image orientation
                [dim_Right, dim_Anterior, dim_Superior] = CartiMorphToolbox.CMT_nifti_getOrientation(affineMat);

                % convert data array to RAS+
                i_nifti_data_RASplus = permute(i_nifti_data, abs([dim_Right, dim_Anterior, dim_Superior]));
                if dim_Right<0
                    i_nifti_data_RASplus = flip(i_nifti_data_RASplus, 1);
                end
                if dim_Anterior<0
                    i_nifti_data_RASplus = flip(i_nifti_data_RASplus, 2);
                end
                if dim_Superior<0
                    i_nifti_data_RASplus = flip(i_nifti_data_RASplus, 3);
                end

                % update the affine transformation matrix
                i_affineMat_RASplus = cat(1, affineMat(abs(dim_Right),:), ...
                    affineMat(abs(dim_Anterior),:), ...
                    affineMat(abs(dim_Superior),:), ...
                    affineMat(4,:));
                if dim_Right<0
                    i_affineMat_RASplus(1,:) = -1 .* i_affineMat_RASplus(1,:);
                end
                if dim_Anterior<0
                    i_affineMat_RASplus(2,:) = -1 .* i_affineMat_RASplus(2,:);
                end
                if dim_Superior<0
                    i_affineMat_RASplus(3,:) = -1 .* i_affineMat_RASplus(3,:);
                end

                % update image size
                i_imgSize = i_nifti_info.ImageSize;
                i_imgSize_RASplus = [i_imgSize(abs(dim_Right)), i_imgSize(abs(dim_Anterior)), i_imgSize(abs(dim_Superior))];

                % update voxel size
                i_voxSize = i_nifti_info.PixelDimensions;
                i_voxSize_RASplus = [i_voxSize(abs(dim_Right)), i_voxSize(abs(dim_Anterior)), i_voxSize(abs(dim_Superior))];

                % resample
                i_scale = i_imgSize_RASplus ./ imgSize_out;
                i_voxSize_out = i_voxSize_RASplus .* i_scale;
                if flag_img
                    i_nifti_data_out = CartiMorphToolbox.CMT_imgPreprocess_resample(i_nifti_data_RASplus, imgSize_out,...
                        'linear', 'double');
                else
                    i_nifti_data_out = CartiMorphToolbox.CMT_imgPreprocess_resample(i_nifti_data_RASplus, imgSize_out,...
                        'nearest', 'double');
                end

                % update the affine matrix
                tmp_affineMat0 = [i_scale(1),0,0,0;
                    0,i_scale(2),0,0;
                    0,0,i_scale(3),0;
                    0,0,0,1];
                i_affineMat_out = transpose(transpose(i_affineMat_RASplus) * tmp_affineMat0);
                i_affineObj_out = i_nifti_info.Transform;
                i_affineObj_out.T = i_affineMat_out;

                % normalize image intensity
                if flag_img
                    intensity_min = min(i_nifti_data_out(:));
                    intensity_max = max(i_nifti_data_out(:));
                    i_nifti_data_out = (i_nifti_data_out - intensity_min)/(intensity_max - intensity_min);
                end

                % update nifti info
                i_nifti_info_out = i_nifti_info;
                i_nifti_info_out.Transform = i_affineObj_out; % affine transformation matrix (important)
                i_nifti_info_out.Filename = fullfile(dir_out, i_fileName_out); % filename (important)
                i_nifti_info_out.ImageSize = imgSize_out; % image size (important)
                i_nifti_info_out.PixelDimensions = i_voxSize_out; % voxel size (important)
                i_nifti_info_out.Datatype = class(i_nifti_data_out);
                i_nifti_info_out.MultiplicativeScaling = 1;
                i_nifti_info_out.FileModDate = regexprep(char(datetime), {' ', ':'}, '_');
                i_nifti_info_out.FileSize = [];

                % save image
                niftiwrite(i_nifti_data_out, i_nifti_info_out.Filename, i_nifti_info_out, 'Compressed',true);

                % internal checkpoint for the pause/resume and stop function
                CMT_checkPauseStopStatus(app);
            end
        end


        function joint_info = CMT_estKneeJointSize(app, dir_seg, config_ROI_label, config_ROI_name)
            % -----------------------------------------------
            % Only used for template learning in CartiMorph-Toolbox
            %
            % We force the model to learn a right knee template
            % by flipping all left knee MRIs to right knee MRIs
            % -----------------------------------------------

            % get list of segmentatoin masks
            tmp = [dir(fullfile(dir_seg, "*.nii.gz"));dir(fullfile(dir_seg, "*.nii"))];
            filename = {tmp.name}';
            numCase = size(filename, 1);

            % get the size and center of whole knee joint
            size_joint = zeros(numCase, 3);
            center_joint = zeros(numCase, 3);

            for i=1:numCase
                % read NIfTI
                i_Name = filename{i,:};
                i_segPath = fullfile(dir_seg, i_Name);
                i_segVol = niftiread(i_segPath);

                % ----------------------------------------
                % [Force to learn a right knee template]
                % (flip the 3D image volume in the L-R direction for each left knee MRI)
                % ----------------------------------------
                % get knee side info
                CMT_kneeSideInfo = app.config_kneeSideInfo;
                idx_file = strcmp(CMT_kneeSideInfo{:,1}, i_Name);
                i_kneeSide = char(CMT_kneeSideInfo{idx_file, 2});
                % flip left to right (assuming RAS+ orientation)
                if sum(strcmp(i_kneeSide, {'left', 'Left', 'L'}))==1
                    i_segVol = flip(i_segVol, 1);
                end
                % ----------------------------------------

                % get the segmentation mask for the whole knee joint
                label_FC = str2double(config_ROI_label{strcmpi(config_ROI_name, 'femoral cartilage')});
                label_mTC = str2double(config_ROI_label{strcmpi(config_ROI_name, 'medial tibial cartilage')});
                label_lTC = str2double(config_ROI_label{strcmpi(config_ROI_name, 'lateral tibial cartilage')});
                mask_joint = uint8(i_segVol==label_FC | i_segVol==label_mTC | i_segVol==label_lTC);

                % remove cluster less than 10 voxels
                tmp = bwareaopen(mask_joint, 10, 26);  % conn=26
                mask_joint = uint8(imfill(tmp, 6, 'holes'));  % conn=6

                % find knee joint
                imgSize = size(mask_joint);
                [x_range, y_range, z_range] = ind2sub(imgSize, find(mask_joint)) ;
                dLR = [min(x_range), max(x_range)];  % L-R distance
                dPA = [min(y_range), max(y_range)];  % P-A distance
                dIS = [min(z_range), max(z_range)];  % I-S distance
                x_center = round((dLR(1) + dLR(2)) / 2);
                y_center = round((dPA(1) + dPA(2)) / 2);
                z_center = round((dIS(1) + dIS(2)) / 2);
                center_joint(i, :) = [x_center, y_center, z_center];
                size_joint(i, :) = [dLR(2)-dLR(1), dPA(2)-dPA(1), dIS(2)-dIS(1)] + 1;

                % internal checkpoint for the pause/resume and stop function
                CMT_checkPauseStopStatus(app);
            end

            % gather knee joint info
            joint_info.center = center_joint;
            joint_info.size = size_joint;
            joint_info.filename = filename;
        end


        function CMT_regNIfTI_rigid(~, movingFile, fixedFile, outputFile)
            % -----------------------------------------------
            % Register (rigid-body reg.) a moving NIfTI image to a fixed NIfTI image and update the affine matrix.
            %
            % Inputs:
            %   movingFile  - Path to the moving NIfTI image
            %   fixedFile   - Path to the fixed (reference) NIfTI image
            %   outputFile  - Path to save the registered NIfTI image
            %
            % Example:
            %   CMT_regNIfTI('movingImage.nii', 'fixedImage.nii', 'registeredImage.nii
            % -----------------------------------------------

            % Load NIfTI volumes
            movingVolume = niftiread(movingFile);
            fixedVolume = niftiread(fixedFile);

            % Get spatial referencing objects
            Rmoving = imref3d(size(movingVolume));
            Rfixed = imref3d(size(fixedVolume));

            % Set up optimizer and metric for registration
            [optimizer, metric] = imregconfig('multimodal');

            % Compute the transformation matrix (affine)
            tform = imregtform(movingVolume, Rmoving, fixedVolume, Rfixed, 'rigid', optimizer, metric);

            % Apply the transformation to the moving image
            movingRegistered = imwarp(movingVolume, tform, 'OutputView', Rfixed);

            % Copy the header info of the moving NIfTI file
            outputInfo = niftiinfo(movingFile);

            %Update the NIfTI header with the new transformation matrix
            outputInfo.Transform.T = tform.T * outputInfo.Transform.T;  % Adjust affine matrix

            % Save the registered image with the updated affine matrix
            niftiwrite(movingRegistered, outputFile, outputInfo, 'Compressed', true);
        end


        function cropRangeInfo = CMT_imgPreprocess_mdcn(app, dir_seg_in, dir_img_in, dir_seg_out, dir_img_out,...
                imgSize_out, kneeJointInfo, padding)
            % -----------------------------------------------
            % Only used for template learning in CartiMorph-Toolbox
            %
            % We force the model to learn a right knee template
            % by flipping all left knee MRIs to right knee MRIs
            %
            % m: mask
            % d: downsample
            % c: crop
            % n: normalize
            % -----------------------------------------------

            % get list of images
            tmp = [dir(fullfile(dir_seg_in, "*.nii.gz"));dir(fullfile(dir_seg_in, "*.nii"))];
            filenames_ext_cell = {tmp.name}';
            numCase = length(filenames_ext_cell);

            % record the cropping range
            cropRange = zeros(numCase, 6);

            % log image info
            fileNames = cell([numCase, 1]);
            imageSize_original = zeros([numCase, 3]);
            imageSize_downsampled = zeros([numCase, 3]);
            downFactor = zeros([numCase, 3]);

            % image preprocessing
            for i=1:numCase
                i_fileName_wExt = filenames_ext_cell{i};
                [~, tmp_fileName, tmp_ext] = fileparts(i_fileName_wExt);
                if strcmp(tmp_ext, '.gz')
                    [~, i_fileName_woExt, ~] = fileparts(tmp_fileName);
                    i_fileExt = ".nii.gz";
                elseif strcmp(tmp_ext, '.nii')
                    i_fileName_woExt = tmp_fileName;
                    i_fileExt = ".nii";
                else
                    error("File Error: wrong file extension. We only support .nii and .nii.gz");
                end

                % read NIfTI files
                i_fileName = strcat(i_fileName_woExt, i_fileExt);
                fileNames{i} = i_fileName;
                i_segPath = fullfile(dir_seg_in, i_fileName);
                i_imgPath = fullfile(dir_img_in, strcat(i_fileName_woExt, "_0000", i_fileExt));
                i_segInfo = niftiinfo(i_segPath);
                i_imgInfo = niftiinfo(i_imgPath);
                i_segVol = niftiread(i_segInfo);
                i_imgVol = double(niftiread(i_imgInfo));

                % ----------------------------------------
                % [Force to learn a right knee template]
                % (flip the 3D image volume in the L-R direction for each left knee MRI)
                % ----------------------------------------
                % get knee side info
                CMT_kneeSideInfo = app.config_kneeSideInfo;
                idx_file = strcmp(CMT_kneeSideInfo{:,1}, i_fileName_wExt);
                i_kneeSide = char(CMT_kneeSideInfo{idx_file, 2});
                % flip left to right (assuming RAS+ orientation)
                if sum(strcmp(i_kneeSide, {'left', 'Left', 'L'}))==1
                    i_segVol = flip(i_segVol, 1);
                    i_imgVol = flip(i_imgVol, 1);
                end
                % ----------------------------------------

                % get the original voxel size
                i_voxSize = i_imgInfo.PixelDimensions;

                % get the original knee joint center and size
                i_idx = strcmp(kneeJointInfo.filename, i_fileName);
                i_joint_center = kneeJointInfo.center(i_idx, :);
                i_joint_size = kneeJointInfo.size(i_idx, :);


                % ===============================================================
                % [1] mask image
                % ===============================================================
                i_mask = double(i_segVol>0);
                tmp = bwareaopen(i_mask, 10, 26);  % conn=26
                i_mask = double(imfill(tmp, 6, 'holes'));  % conn=6
                i_imgVol_m = i_imgVol .* i_mask;
                i_imgSize = size(i_imgVol_m);
                % log the original image size
                imageSize_original(i, :) = i_imgSize;
                % ===============================================================


                % ===============================================================
                % [2] determine the downsampling factor
                %     - i_downFactor>1: downsample
                %     - i_downFactor<1: upsample
                % ===============================================================
                % adjust padding automatically
                tmp_dROI_size = i_joint_size + 2 * padding;
                while any(tmp_dROI_size>i_imgSize)
                    padding = padding - 1;
                    tmp_dROI_size = i_joint_size + 2 * padding;
                end
                i_dROI_size = tmp_dROI_size;
                i_tmpDownFactor = i_dROI_size ./ imgSize_out;
                i_imgSize_d = round(i_imgSize ./ i_tmpDownFactor);
                i_downFactor = i_imgSize ./ i_imgSize_d;
                i_voxSize_out = i_voxSize .* i_downFactor;
                % log the downsampling factor
                downFactor(i, :) = i_downFactor;
                % ===============================================================


                % ===============================================================
                % [3] downsample image and segmantation mask
                % ===============================================================
                i_imgVol_md = CartiMorphToolbox.CMT_imgPreprocess_resample(i_imgVol_m, i_imgSize_d, 'linear', 'double');
                i_segVol_md = CartiMorphToolbox.CMT_imgPreprocess_resample(i_segVol, i_imgSize_d, 'nearest', 'uint8');
                i_joint_center_d = round(i_joint_center ./ i_downFactor);
                % log the downsampled image size
                imageSize_downsampled(i, :) = i_imgSize_d;
                % prepare for updating the affine matrix
                tmp_affineMat0 = [i_downFactor(1),0,0,0;
                    0,i_downFactor(2),0,0;
                    0,0,i_downFactor(3),0;
                    0,0,0,1];
                % ===============================================================


                % ===============================================================
                % [4]crop image and segmentation mask
                % ===============================================================
                % estiamte the cropping box
                x_boxLen = imgSize_out(1);
                y_boxLen = imgSize_out(2);
                z_boxLen = imgSize_out(3);
                x_cropRange = (i_joint_center_d(1)-floor(x_boxLen/2)):(i_joint_center_d(1)+ceil(x_boxLen/2)-1);
                y_cropRange = (i_joint_center_d(2)-floor(y_boxLen/2)):(i_joint_center_d(2)+ceil(y_boxLen/2)-1);
                z_cropRange = (i_joint_center_d(3)-floor(z_boxLen/2)):(i_joint_center_d(3)+ceil(z_boxLen/2)-1);

                % shift the cropping box if necessary
                if min(x_cropRange) <= 0
                    x_cropRange = x_cropRange + abs(min(x_cropRange)) + 1;
                end
                if min(y_cropRange) <= 0
                    y_cropRange = y_cropRange + abs(min(y_cropRange)) + 1;
                end
                if min(z_cropRange) <= 0
                    z_cropRange = z_cropRange + abs(min(z_cropRange)) + 1;
                end
                if max(x_cropRange) > i_imgSize_d(1)
                    x_cropRange = x_cropRange - (max(x_cropRange) - i_imgSize_d(1));
                end
                if max(y_cropRange) > i_imgSize_d(2)
                    y_cropRange = y_cropRange - (max(y_cropRange) - i_imgSize_d(2));
                end
                if max(z_cropRange) > i_imgSize_d(3)
                    z_cropRange = z_cropRange - (max(z_cropRange) - i_imgSize_d(3));
                end

                % crop MR image and segmentation mask
                i_imgVol_mdc = i_imgVol_md(x_cropRange, y_cropRange, z_cropRange);
                i_segVol_mdc = i_segVol_md(x_cropRange, y_cropRange, z_cropRange);

                % update affine transformation matrix (important)
                i_affine_obj_out = i_imgInfo.Transform;
                i_affineMat = i_imgInfo.Transform.T;
                tmp_affineMat1 = [1,0,0, x_cropRange(1)-1;
                    0,1,0,y_cropRange(1)-1;
                    0,0,1,z_cropRange(1)-1;
                    0,0,0,1];
                i_affineMat_out = transpose(transpose(i_affineMat) * (tmp_affineMat0 * tmp_affineMat1));
                i_affine_obj_out.T = i_affineMat_out;
                % ===============================================================


                % ===============================================================
                % [4] normalized image intensity
                % ===============================================================
                i_imgIntensity_min = min(i_imgVol_mdc(:));
                i_imgIntensity_max = max(i_imgVol_mdc(:));
                i_imgVol_mdcn = (i_imgVol_mdc - i_imgIntensity_min) ./ (i_imgIntensity_max - i_imgIntensity_min);
                % ===============================================================


                % ===============================================================
                % save files
                % ===============================================================
                % change NIfTI header info for the cropped image
                i_imgInfo_out = i_imgInfo;
                % change filename (important)
                i_imgInfo_out.Filename = fullfile(dir_img_out, i_fileName_woExt);
                % change image size (important)
                i_imgInfo_out.ImageSize = imgSize_out;
                % change voxel size (important)
                i_imgInfo_out.PixelDimensions = i_voxSize_out;
                % change affine transformation matrix (important)
                i_imgInfo_out.Transform = i_affine_obj_out;
                % others
                i_imgInfo_out.MultiplicativeScaling = 1;
                i_imgInfo_out.FileModDate = regexprep(char(datetime), {' ', ':'}, '_');
                i_imgInfo_out.FileSize = [];
                i_imgInfo_out.Datatype = class(i_imgVol_mdcn);
                % save cropped MR image
                niftiwrite(i_imgVol_mdcn, i_imgInfo_out.Filename, i_imgInfo_out, 'Compressed', true);

                % save cropped segmentation mask
                if ~isempty(dir_seg_out)
                    % change NIfTI header info for the cropped label
                    i_segInfo_out = i_imgInfo;
                    % change filename (important)
                    i_segInfo_out.Filename = fullfile(dir_seg_out, i_fileName_woExt);
                    % change image size (important)
                    i_segInfo_out.ImageSize = imgSize_out;
                    % change voxel size (important)
                    i_segInfo_out.PixelDimensions = i_voxSize_out;
                    % change affine transformation matrix (important)
                    i_segInfo_out.Transform = i_affine_obj_out;
                    % others
                    i_segInfo_out.MultiplicativeScaling = 1;
                    i_segInfo_out.FileModDate = regexprep(char(datetime), {' ', ':'}, '_');
                    i_segInfo_out.FileSize = [];
                    i_segInfo_out.Datatype = class(i_segVol_mdc);
                    % save to .nii
                    niftiwrite(i_segVol_mdc, i_segInfo_out.Filename, i_segInfo_out, 'Compressed', true);
                end
                % ===============================================================

                % log the cropping range
                cropRange(i, :) = [min(x_cropRange), max(x_cropRange), ...
                    min(y_cropRange), max(y_cropRange), ...
                    min(z_cropRange), max(z_cropRange)];

                % internal checkpoint for the pause/resume and stop function
                CMT_checkPauseStopStatus(app);
            end

            % gather all info
            cropRangeInfo.filename = fileNames;
            cropRangeInfo.cropRange = cropRange;
            cropRangeInfo.imageSize_original = imageSize_original;
            cropRangeInfo.imageSize_downsampled = imageSize_downsampled;
            cropRangeInfo.downFactor = downFactor;
        end


        function  CMT_wrapper_KneeCartilageThickness_wBone(...
                app, ...
                mask_cartilage,...
                mask_bone,...
                mask_warpSeg,...
                size_voxel,...
                method,...
                name_cartilage,...
                subID,...
                dir_subject,...
                log_fileID,...
                opt)
            % ==============================================================================
            % FUNCTION:
            %       Calculate knee cartilage thickness from segmentation masks.
            %
            % INPUT:
            %       - mask_cartilage: [uint8] 3D cartilage mask
            %       - mask_bone: [uint8] 3D bone mask
            %       - mask_warpSeg: [uint8] the warped segmentation mask for the knee template
            %       - size_voxel: [double] the spatial resolution of a voxel
            %       - method: [char] method for thickness measurement
            %       - name_cartilage: [char] name of cartilage
            %       - subID: [double] subject ID
            %       - dir_subject: [char] subject directory
            %       - log_fileID: [double] log file ID
            %       - opt.depth: [double] the measuring depth
            %       - opt.n_ROI: [double] #ROI for adaptive neighborhood size selection regardless of image resolution
            %       - opt.size_voxel_resample: (1,3),[double] voxel size of resampled image
            % ------------------------------------------------------------------------------
            % Matlab Version: 2019b or later
            %
            % Last updated on: 17-Jun-2022
            %
            % Author:
            % Yongcheng YAO (yao_yongcheng@link.cuhk.edu.hk)
            % Department of Imaging and Interventional Radiology,
            % Chinese University of Hong Kong (CUHK)
            %
            % Copyright 2020 Yongcheng YAO
            % ------------------------------------------------------------------------------
            % ==============================================================================


            %% Preprocessing of segmentation masks
            if ~isempty(log_fileID)
                tic;
                fprintf(log_fileID, '[1] Preprocessing of segmentation masks...');
            end

            % image preprocessing: isolated voxels removal and filling holes within the mask
            % set threshold for isolated voxels removal: regions with area less than 1 mm3
            threshold_isoVox = ceil(1 / (size_voxel(1)*size_voxel(2)*size_voxel(3)));
            mask_cartilage = CartiMorphToolbox.cal_preprocessImg(mask_cartilage, threshold_isoVox);
            mask_bone = CartiMorphToolbox.cal_preprocessImg(mask_bone, threshold_isoVox);

            % internal checkpoint for the pause/resume and stop function
            CMT_checkPauseStopStatus(app);

            if ~isempty(log_fileID)
                time = toc;
                fprintf(log_fileID, append(' finished. (Elapsed time is ', num2str(time), ' seconds). \n'));
            end


            %% Split the cartilage boundary
            if ~isempty(log_fileID)
                fprintf(log_fileID, '[2] Splitting cartilage boundary with bone mask... \n');
            end

            % --------------------------------------------------------------
            % iC: interior surface of cartilage / bone-cartilage interface
            % eC: exterior surface of cartilage
            % C: cartilage surface
            % scB: subchondral bone surface, the bone surface covered by cartilage
            % FCL: reconstructed full-thickness cartilage loss surface
            % B: bone surface
            % --------------------------------------------------------------
            [sMesh_iC, sMesh_eC, sMesh_C, sMesh_scB, sMesh_FCL, sMesh_B, ...
                Mesh_iC, Mesh_eC, Mesh_C, Mesh_scB, Mesh_FCL, Mesh_B] = cal_splitBoundary3D_wBone(...
                app, ...
                mask_cartilage,...
                mask_bone,...
                mask_warpSeg,...
                size_voxel,...
                name_cartilage,...
                log_fileID);

            % internal checkpoint for the pause/resume and stop function
            CMT_checkPauseStopStatus(app);


            %% Calculate the Cartilage Thickness

            switch method
                case 'SN'
                    if ~isempty(log_fileID)
                        fprintf(log_fileID, '[3] Calculating cartilage thickness using SN-based method... \n');
                    end

                    if ~isempty(Mesh_iC)

                        %% Sanity check
                        if ~isfield(opt, 'depth')
                            error('missing name-value pair: depth')
                        else
                            depth = opt.depth;
                        end

                        %% Hyper-parameters definition
                        % ===============================================
                        temp_n_neigh = ceil(size(Mesh_iC.vertices, 1) / opt.n_ROI);
                        if temp_n_neigh > 9
                            n_neigh = temp_n_neigh;
                        else
                            n_neigh = 9;
                        end
                        % number of neighbors to estimate surface normal for a voxel
                        hyperParameters.neigh_priSN = n_neigh;
                        % number of neighbors to flip surface normal for a voxel
                        hyperParameters.neigh_flipSN = n_neigh*10;
                        % number of neighbors whose estimated surface normal are non-zero
                        hyperParameters.neigh_approSN = ceil(n_neigh/2);
                        % number of neighbors for spatial smoothing of SN
                        hyperParameters.neigh_smoothSN = n_neigh;
                        % number of neighbors whose estimated thickness are non-zero
                        hyperParameters.neigh_approThickness = ceil(n_neigh/2);
                        % number of neighbors for spatial smoothing of thickness map
                        hyperParameters.neigh_smoothMap = n_neigh;
                        % ===============================================

                        %% Calculate thickness using SN method
                        [ThicknessMap, sThicknessMap] = wrapper_thickness_SN(...
                            app, ...
                            sMesh_iC,...
                            sMesh_eC,...
                            name_cartilage,...
                            size_voxel,...
                            depth,...
                            hyperParameters,...
                            dir_subject,...
                            subID,...
                            log_fileID);

                        % internal checkpoint for the pause/resume and stop function
                        CMT_checkPauseStopStatus(app);

                    else
                        ThicknessMap = [];
                        sThicknessMap = [];
                        if ~isempty(log_fileID)
                            fprintf(log_fileID, '[skipped due to empty mesh]. \n');
                        end
                    end
            end


            %% Saving reconstructed surfaces
            if ~isempty(log_fileID)
                fprintf(log_fileID, '[4] Saving reconstructed surfaces ... ');
            end

            if ~isempty(Mesh_iC)

                % create folder for reconstructed surface
                dir_surface = fullfile(dir_subject, 'Surface');
                if ~isfolder(dir_surface)
                    mkdir(dir_surface);
                end

                % bone surface
                fileName = append(subID, '_Mesh_B_', name_cartilage, '.mat');
                filePath = fullfile(dir_surface, fileName);
                save(string(filePath), '-struct', 'Mesh_B');
                fileName = append(subID, '_sMesh_B_', name_cartilage, '.mat');
                filePath = fullfile(dir_surface, fileName);
                save(string(filePath), '-struct', 'sMesh_B');

                % subchondral bone surface -- bone surface covered by cartilage
                fileName = append(subID, '_Mesh_scB_', name_cartilage, '.mat');
                filePath = fullfile(dir_surface, fileName);
                save(string(filePath), '-struct', 'Mesh_scB');
                fileName = append(subID, '_sMesh_scB_', name_cartilage, '.mat');
                filePath = fullfile(dir_surface, fileName);
                save(string(filePath), '-struct', 'sMesh_scB');

                % full-thickness cartilage loss surface
                fileName = append(subID, '_Mesh_FCL_', name_cartilage, '.mat');
                filePath = fullfile(dir_surface, fileName);
                save(string(filePath), '-struct', 'Mesh_FCL');
                fileName = append(subID, '_sMesh_FCL_', name_cartilage, '.mat');
                filePath = fullfile(dir_surface, fileName);
                save(string(filePath), '-struct', 'sMesh_FCL');

                % cartilage surface
                fileName = append(subID, '_Mesh_C_', name_cartilage, '.mat');
                filePath = fullfile(dir_surface, fileName);
                save(string(filePath), '-struct', 'Mesh_C');
                fileName = append(subID, '_sMesh_C_', name_cartilage, '.mat');
                filePath = fullfile(dir_surface, fileName);
                save(string(filePath), '-struct', 'sMesh_C');

                % interior cartilage surface / bone-cartilage interface
                fileName = append(subID, '_Mesh_iC_', name_cartilage, '.mat');
                filePath = fullfile(dir_surface, fileName);
                save(string(filePath), '-struct', 'Mesh_iC');
                fileName = append(subID, '_sMesh_iC_', name_cartilage, '.mat');
                filePath = fullfile(dir_surface, fileName);
                save(string(filePath), '-struct', 'sMesh_iC');

                % exterior cartilage surface
                fileName = append(subID, '_Mesh_eC_', name_cartilage, '.mat');
                filePath = fullfile(dir_surface, fileName);
                save(string(filePath), '-struct', 'Mesh_eC');
                fileName = append(subID, '_sMesh_eC_', name_cartilage, '.mat');
                filePath = fullfile(dir_surface, fileName);
                save(string(filePath), '-struct', 'sMesh_eC');

                % internal checkpoint for the pause/resume and stop function
                CMT_checkPauseStopStatus(app);

                if ~isempty(log_fileID)
                    fprintf(log_fileID, 'finished. \n');
                end

            else
                if ~isempty(log_fileID)
                    fprintf(log_fileID, '[skipped due to empty mesh]. \n');
                end
            end


            %% Saving thickness maps
            if ~isempty(log_fileID)
                fprintf(log_fileID, '[5] Saving thickness maps ... ');
            end

            if ~isempty(ThicknessMap)

                % create folder for thickness map
                dir_thicknessMap = fullfile(dir_subject, 'ThicknessMap');
                if ~isfolder(dir_thicknessMap)
                    mkdir(dir_thicknessMap);
                end

                % save thicknessMap
                fileName = append(subID, '_Map_', name_cartilage, '.mat');
                filePath = fullfile(dir_thicknessMap, fileName);
                save(string(filePath), 'ThicknessMap');
                fileName = append(subID, '_sMap_', name_cartilage, '.mat');
                filePath = fullfile(dir_thicknessMap, fileName);
                save(string(filePath), 'sThicknessMap');

                % internal checkpoint for the pause/resume and stop function
                CMT_checkPauseStopStatus(app);

                if ~isempty(log_fileID)
                    fprintf(log_fileID, 'finished. \n');
                end

            else
                if ~isempty(log_fileID)
                    fprintf(log_fileID, '[skipped due to empty mesh]. \n');
                end
            end


            %% Visualizing reconstructed surfaces
            if ~isempty(log_fileID)
                fprintf(log_fileID, '[6] Visualizing reconstructed surfaces ... ');
            end

            if ~isempty(sMesh_iC)
                % create folder for surface visualization
                dir_surfaceFig = fullfile(dir_subject, 'Surface_Figure');
                if ~isfolder(dir_surfaceFig)
                    mkdir(dir_surfaceFig);
                end

                % ---------------------------------------------------
                % [fig1] interior and exterior cartilage surface
                % ---------------------------------------------------
                fig_title = append('Cartilage Surfaces (', subID, ')');
                f = figure('visible', 'off');

                % <<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<
                % [!] DO NOT change the order of the following lines, this will produce errors in CartiMorph Viewer
                trisurf(sMesh_iC.faces, sMesh_iC.vertices(:,1), sMesh_iC.vertices(:,2), sMesh_iC.vertices(:,3), ...
                    'FaceColor', '#FF5251');
                hold on;
                trisurf(sMesh_eC.faces, sMesh_eC.vertices(:,1), sMesh_eC.vertices(:,2), sMesh_eC.vertices(:,3), ...
                    'FaceColor', '#006CFF');
                % <<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<

                axis equal;
                xlabel('L-R');
                ylabel('P-A');
                zlabel('I-S');
                view(3);
                title(fig_title, 'Interpreter','none');
                grid off;
                hold off;
                fileName = append(subID, '_sMesh_iCeC_', name_cartilage);
                filePath = fullfile(dir_surfaceFig, fileName);
                savefig(f, append(filePath, '.fig'), 'compact');
                delete(f);
                % ---------------------------------------------------

                % ---------------------------------------------------
                % [fig2] interior cartilage surface and full-thickness cartilage loss surface
                % ---------------------------------------------------
                fig_title = append('Cartilage Surfaces & Reconstructed FCL (', subID, ')');
                f = figure('visible', 'off');

                % <<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<
                % [!] DO NOT change the order of the following lines, this will produce errors in CartiMorph Viewer
                trisurf(sMesh_iC.faces, sMesh_iC.vertices(:,1), sMesh_iC.vertices(:,2), sMesh_iC.vertices(:,3), ...
                    'FaceColor', '#FF5251');
                hold on;
                trisurf(sMesh_FCL.faces, sMesh_FCL.vertices(:,1), sMesh_FCL.vertices(:,2), sMesh_FCL.vertices(:,3), ...
                    'FaceColor', '#9F9F9F');
                % <<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<

                axis equal;
                xlabel('L-R');
                ylabel('P-A');
                zlabel('I-S');
                view(3);
                title(fig_title, 'Interpreter','none');
                grid off;
                hold off;
                fileName = append(subID, '_sMesh_iCFCL_', name_cartilage);
                filePath = fullfile(dir_surfaceFig, fileName);
                savefig(f, append(filePath, '.fig'), 'compact');
                delete(f);
                % ---------------------------------------------------

                % internal checkpoint for the pause/resume and stop function
                CMT_checkPauseStopStatus(app);

                if ~isempty(log_fileID)
                    fprintf(log_fileID, 'finished. \n');
                end

            else
                if ~isempty(log_fileID)
                    fprintf(log_fileID, '[skipped visualisation due to empty mesh]. \n');
                end
            end


            %% Visualizing thickness map
            if ~isempty(log_fileID)
                fprintf(log_fileID, '[7] Visualizing thickness map ... ');
            end

            if ~isempty(sThicknessMap)

                % create folder for surface visualization
                dir_thicknessMapFig = fullfile(dir_subject, 'ThicknessMap_Figure', name_cartilage);
                if ~isfolder(dir_thicknessMapFig)
                    mkdir(dir_thicknessMapFig)
                end

                % sThicknessMap
                fileName = append(subID, '_sMap_', name_cartilage);
                filePath = fullfile(dir_thicknessMapFig, fileName);
                fig_title = append('Thickness Map (', subID, ')');
                CartiMorphToolbox.plot_thicknessMap_wBone(...
                    sMesh_C,...
                    sMesh_B,...
                    sMesh_iC,...
                    sThicknessMap,...
                    fig_title,...
                    filePath);

                % internal checkpoint for the pause/resume and stop function
                CMT_checkPauseStopStatus(app);

                if ~isempty(log_fileID)
                    fprintf(log_fileID, 'finished. \n');
                end

            else
                if ~isempty(log_fileID)
                    fprintf(log_fileID, '[skipped visualisation due to empty mesh]. \n');
                end
            end

        end


        function [sFV_inter_rebased, sFV_outer, sFV_Cart_woInter, sFV_subchondral, sFV_filling, sFV_bone_wInter, ...
                FV_inter_rebased, FV_outer, FV_Cart_woInter, FV_subchondral, FV_filling, FV_bone_wInter]...
                = cal_splitBoundary3D_wBone(...
                app, ...
                mask_cartilage,...
                mask_bone,...
                mask_warpSeg,...
                size_voxel,...
                name_cartilage,...
                log_fileID)
            % ==============================================================================
            % FUNCTION:
            %     1. Split the cartilage boundary into two parts:
            %         (1) the bone-cartilage interface
            %         (2) the outer boundary
            %     2. Triangulation for two boundaries
            %
            % INPUT:
            %     - mask_cartilage: [uint8] 3D cartilage mask
            %     - mask_bone:  [uint8] 3D bone mask
            %     - mask_warpSeg: [uint8] the warped segmentation mask for the knee template
            %     - size_voxel: [double] the spatial resolution of a voxel
            %     - name_cartilage: [char] name of cartilage
            %     - log_fileID: log file ID
            %
            % OUTPUT:
            %        (the following structure variables have same fields "faces" and "vertices")
            %     - sFV_inter_rebased: [structure]
            %     - sFV_outer: [structure]
            %     - sFV_Cart_woInter: [structure]
            %     - sFV_subchondral: [structure]
            %     - sFV_filling: [structure]
            %     - sFV_bone_wInter: [structure]
            %     - FV_inter_rebased: [structure]
            %     - FV_outer: [structure]
            %     - FV_Cart_woInter: [structure]
            %     - FV_subchondral: [structure]
            %     - FV_filling: [structure]
            %     - FV_bone_wInter: [structure]
            % ------------------------------------------------------------------------------
            % Matlab Version: 2019b or later
            %
            % Last updated on: 19-Jun-2022
            %
            % Author:
            % Yongcheng YAO (yao_yongcheng@link.cuhk.edu.hk)
            % Department of Imaging and Interventional Radiology,
            % Chinese University of Hong Kong (CUHK)
            %
            % Copyright 2020 Yongcheng YAO
            % ------------------------------------------------------------------------------
            % ==============================================================================


            %% Initial separation of cartilage boundary
            %------------------------------------------------------------------------------------------
            if ~isempty(log_fileID)
                tic;
                fprintf(log_fileID, '    [2.1] Initial separation of cartilage boundary... ');
            end

            % coarse surface segmentation
            [subs_inter_coarse, subs_outer_coarse] = CartiMorphToolbox.cal_initBoundarySplit_wBone(...
                mask_cartilage,...
                mask_bone);

            % convert subscripts to coordinates
            vers_inter_coarse = subs_inter_coarse .* size_voxel;
            vers_outer_coarse  = subs_outer_coarse .* size_voxel;

            % internal checkpoint for the pause/resume and stop function
            CMT_checkPauseStopStatus(app);

            if ~isempty(log_fileID)
                time = toc;
                fprintf(log_fileID, append(' finished. (Elapsed time is ', num2str(time), ' seconds). \n'));
            end
            %------------------------------------------------------------------------------------------


            %% Cartilage surface reconstruction
            %------------------------------------------------------------------------------------------
            if ~isempty(log_fileID)
                fprintf(log_fileID, '    [2.2] Cartilage surface reconstruction... ');
            end

            % get boundary mesh
            FV_cartilage = CartiMorphToolbox.cal_getBoundaryMesh(mask_cartilage);

            % Error handling: when FV_cartilage is empty
            if isempty(FV_cartilage.faces)
                sFV_inter_rebased = [];
                sFV_outer = [];
                sFV_Cart_woInter = [];
                sFV_subchondral = [];
                sFV_filling = [];
                sFV_bone_wInter = [];
                FV_inter_rebased = [];
                FV_outer = [];
                FV_Cart_woInter = [];
                FV_subchondral = [];
                FV_filling = [];
                FV_bone_wInter =[];
                return;
            end


            % convert subscripts to coordinates
            subs_cartilage = FV_cartilage.vertices;
            vers_cartilage = subs_cartilage .* size_voxel;
            FV_cartilage.vertices = vers_cartilage;

            % smooth the mesh of the cartilage surface (toolbox: matGeom)
            faces_cartilage = FV_cartilage.faces;
            [sFV_cartilage.vertices, sFV_cartilage.faces] = CartiMorphToolbox.smoothMesh(vers_cartilage, faces_cartilage, 2);

            % internal checkpoint for the pause/resume and stop function
            CMT_checkPauseStopStatus(app);

            if ~isempty(log_fileID)
                time = toc;
                fprintf(log_fileID, append(' finished. (Elapsed time is ', num2str(time), ' seconds). \n'));
            end
            %------------------------------------------------------------------------------------------


            %%  Bone-cartilage interface reconstruction
            %------------------------------------------------------------------------------------------
            if ~isempty(log_fileID)
                tic;
                fprintf(log_fileID, '    [2.3] Bone-cartilage interface reconstruction... ');
            end

            % coarse interface mesh
            faces_inter_coarse = CartiMorphToolbox.cal_extractFaces_AND(faces_cartilage, vers_cartilage, vers_inter_coarse);

            % surface close on the coarse interface mesh
            iteration_dilation = 4;
            iteration_erosion = 4;
            faces_inter = CartiMorphToolbox.cal_surfaceClosing(...
                faces_inter_coarse,...
                faces_cartilage,...
                iteration_dilation,...
                iteration_erosion);

            % internal checkpoint for the pause/resume and stop function
            CMT_checkPauseStopStatus(app);

            % (!!!important!!!) ---
            % (the removal of duplicated faces must be after the surface reconstruction)
            faces_inter = CartiMorphToolbox.remove_duplicated_triangles(faces_inter);  % MeshProcessingToolbox
            % (!!!important!!!) ---

            % remove sharp-edge faces
            for iter = 1:5
                faces_inter = CartiMorphToolbox.cal_deleteSharpEdgeTri(faces_inter);
            end

            % add faces whose two edges are the edges of the interface mesh
            faces_inter = CartiMorphToolbox.cal_extractFaces_AND(faces_cartilage, vers_cartilage, vers_cartilage(unique(faces_inter(:)), :));

            % update vertices of interface mesh (MeshProcessingToolbox)
            [vers_inter, ~] = CartiMorphToolbox.remove_unreferenced_vertices(vers_cartilage, faces_inter);

            % internal checkpoint for the pause/resume and stop function
            CMT_checkPauseStopStatus(app);

            if ~isempty(log_fileID)
                time = toc;
                fprintf(log_fileID, append(' finished. (Elapsed time is ', num2str(time), ' seconds). \n'));
            end
            %------------------------------------------------------------------------------------------


            %%  Outer cartilage surface reconstruction
            %------------------------------------------------------------------------------------------
            if ~isempty(log_fileID)
                tic;
                fprintf(log_fileID, '    [2.4] Outer cartilage surface reconstruction... ');
            end

            % coarse outer cartilage surface
            faces_outer_coarse = CartiMorphToolbox.cal_extractFaces_AND(faces_cartilage, vers_cartilage, vers_outer_coarse);

            % restricted surface dilation
            faces_outer = CartiMorphToolbox.cal_surfaceDilation_restricted(...
                faces_outer_coarse,...
                faces_cartilage,...
                vers_cartilage,...
                vers_inter);

            % internal checkpoint for the pause/resume and stop function
            CMT_checkPauseStopStatus(app);

            % add more faces to the outer cartilage surface
            faces_outer = CartiMorphToolbox.cal_extractFaces_OR(faces_cartilage, vers_cartilage, vers_cartilage(unique(faces_outer(:)), :));

            % (!!!important!!!) ---
            % (the removal of duplicated faces must be after the surface reconstruction)
            faces_outer = CartiMorphToolbox.remove_duplicated_triangles(faces_outer);  % MeshProcessingToolbox
            % (!!!important!!!) ---

            % update vertices of outer cartilage surface (MeshProcessingToolbox)
            [vers_outer, faces_outer] = CartiMorphToolbox.remove_unreferenced_vertices(vers_cartilage, faces_outer);

            % the smoothed and unsmoothed mesh
            idx_outerVers = ismember(vers_cartilage, vers_outer, 'rows');
            sFV_outer.faces = faces_outer;
            sFV_outer.vertices = sFV_cartilage.vertices(idx_outerVers, :);
            FV_outer.faces = faces_outer;
            FV_outer.vertices = vers_outer;

            % internal checkpoint for the pause/resume and stop function
            CMT_checkPauseStopStatus(app);

            if ~isempty(log_fileID)
                time = toc;
                fprintf(log_fileID, append(' finished. (Elapsed time is ', num2str(time), ' seconds). \n'));
            end
            %------------------------------------------------------------------------------------------


            %% Bone surface reconstruction
            %------------------------------------------------------------------------------------------
            if ~isempty(log_fileID)
                fprintf(log_fileID, '    [2.5] Bone surface reconstruction... ');
            end

            % image size
            size_mask = size(mask_cartilage);

            % remove small connected components (connectivity=26, i.e., 3D connectivity)
            mask_bone = CartiMorphToolbox.cal_removeSmallComponent4Bone_3D(mask_bone);

            % 3d Gaussian smoothing for bone mask
            mask_bone = imgaussfilt3(mask_bone, 2, "FilterDomain", "spatial");

            % grow the bone ROI so that there is no gap between bone and cartilage ROIs
            mask_cartilage_bone = mask_cartilage | mask_bone;  % logical
            mask_cartilage_bone = imfill(mask_cartilage_bone, 'holes');  % logical
            mask_bone_grown = uint8(mask_cartilage_bone) - uint8(mask_cartilage);  % uint8

            % add the bone-cartilage interface to the grown bone mask
            mask_inter = CartiMorphToolbox.cal_convertVoxCoor2Mask_3D(vers_inter, size_mask, size_voxel);
            mask_bone_inter = mask_bone_grown | mask_inter;

            % bone surface reconstruction
            FV_bone_wInter = CartiMorphToolbox.cal_getBoundaryMesh(mask_bone_inter);

            % convert subscripts to coordinates
            subs_bone_wInter = FV_bone_wInter.vertices;
            vers_bone_wInter = subs_bone_wInter .* size_voxel;
            FV_bone_wInter.vertices = vers_bone_wInter;

            % remove duplicated faces (MeshProcessingToolbox)
            faces_bone_wInter = CartiMorphToolbox.remove_duplicated_triangles(FV_bone_wInter.faces);
            FV_bone_wInter.faces = faces_bone_wInter;

            % smooth the mesh of the bone surface (toolbox: matGeom)
            [sFV_bone_wInter.vertices, sFV_bone_wInter.faces] = CartiMorphToolbox.smoothMesh(vers_bone_wInter, faces_bone_wInter, 2);

            % internal checkpoint for the pause/resume and stop function
            CMT_checkPauseStopStatus(app);

            if ~isempty(log_fileID)
                time = toc;
                fprintf(log_fileID, append(' finished. (Elapsed time is ', num2str(time), ' seconds). \n'));
            end
            %% -----------------------------------------------------------------------------


            %%  Subchondral bone surface reconstruction
            %------------------------------------------------------------------------------------------
            if ~isempty(log_fileID)
                tic;
                fprintf(log_fileID, '    [2.6] Subchondral bone surface reconstruction... ');
            end

            % (1) Initial estimation of subchondral bone
            % smoothing
            mask_warpSeg_s = imgaussfilt3(mask_warpSeg, 1);
            mask_warpSeg_s = mask_warpSeg_s>0.5;
            % [important] check mask sizes
            if ~isequal(size(mask_warpSeg_s), size_mask)
                error("incompatible sizes: (1) warped template segmentation mask, (2) image segmentation mask")
            end
            % map the warped segmentation mask from knee template onto the bone surface
            [sub1_warpSeg, sub2_warpSeg, sub3_warpSeg] = ind2sub(size_mask, find(mask_warpSeg_s));
            subs_warpSeg = [sub1_warpSeg, sub2_warpSeg, sub3_warpSeg];
            [~, idx_warpSeg] = pdist2(subs_bone_wInter, subs_warpSeg, 'euclidean', 'Smallest', 1);
            subs_warpSeg_map2B = subs_bone_wInter(unique(idx_warpSeg), :);
            % voxel indices of the initial estimation of subchondral bone
            subs_scB_init = unique(cat(1, subs_warpSeg_map2B, subs_cartilage), 'rows');
            % convert voxel indices to 3D mask
            mask_scB_init = CartiMorphToolbox.cal_convertVoxIdx2Mask_3D(subs_scB_init, size_mask);
            % image close operation
            mask_scB_init = imclose(mask_scB_init, strel("cuboid", [3,3,3]));
            % remove isolated clusters
            mask_scB_init = bwareaopen(mask_scB_init, round(sum(mask_scB_init(:))/10), 26);  % conn=26
            % convert 3D mask back to voxel indices
            [sub1_scB_init, sub2_scB_init, sub3_scB_init] = ind2sub(size_mask, find(mask_scB_init));
            subs_scB_init = [sub1_scB_init, sub2_scB_init, sub3_scB_init];
            % remove voxels that are not on the bone surface
            idx_onBoneSurface = ismember(subs_scB_init, subs_bone_wInter, 'rows');
            subs_scB_init = subs_scB_init(idx_onBoneSurface, :);
            % voxel coordinates of the initial estimation of subchondral bone
            vers_scB_init = subs_scB_init .* size_voxel;

            % internal checkpoint for the pause/resume and stop function
            CMT_checkPauseStopStatus(app);

            % (2) Fill holes that do not reach the boundary
            faces_scB_init = CartiMorphToolbox.cal_extractFaces_OR(faces_bone_wInter, vers_bone_wInter, vers_scB_init);
            [~, vers_scB_filled_1, ~, ~] = CartiMorphToolbox.cal_reconCartDefect_conn(faces_scB_init, FV_bone_wInter);

            % internal checkpoint for the pause/resume and stop function
            CMT_checkPauseStopStatus(app);

            % (3) Fill holes that reach the boundary: initial estimation
            subs_scB_filled_1 = round(vers_scB_filled_1 ./ size_voxel);
            switch name_cartilage
                case 'FemoralCartilage'
                    tmp_subs_scB_filled = CartiMorphToolbox.cal_reconCartDefect_curve_FC(subs_scB_filled_1, size_mask);
                case 'mTibialCartilage'
                    tmp_1_1 = CartiMorphToolbox.cal_reconCartDefect_curve_TC(subs_scB_filled_1, size_mask);
                    tmp_1_1_swapD1D2 = [tmp_1_1(:,2), tmp_1_1(:,1), tmp_1_1(:,3)];
                    size_mask_swapD1D2 = [size_mask(2), size_mask(1), size_mask(3)];
                    tmp_1_2_swapD1D2 = CartiMorphToolbox.cal_reconCartDefect_curve_TC(tmp_1_1_swapD1D2, size_mask_swapD1D2);
                    tmp_subs_scB_filled = [tmp_1_2_swapD1D2(:,2), tmp_1_2_swapD1D2(:,1), tmp_1_2_swapD1D2(:,3)];
                case 'lTibialCartilage'
                    tmp_1_1 = CartiMorphToolbox.cal_reconCartDefect_curve_TC(subs_scB_filled_1, size_mask);
                    tmp_1_1_swapD1D2 = [tmp_1_1(:,2), tmp_1_1(:,1), tmp_1_1(:,3)];
                    size_mask_swapD1D2 = [size_mask(2), size_mask(1), size_mask(3)];
                    tmp_1_2_swapD1D2 = CartiMorphToolbox.cal_reconCartDefect_curve_TC(tmp_1_1_swapD1D2, size_mask_swapD1D2);
                    tmp_subs_scB_filled = [tmp_1_2_swapD1D2(:,2), tmp_1_2_swapD1D2(:,1), tmp_1_2_swapD1D2(:,3)];
            end
            [~, idx_scB_fill2] = pdist2(subs_bone_wInter, tmp_subs_scB_filled, 'euclidean', 'Smallest', 1);
            subs_scB_filled_2 = subs_bone_wInter(unique(idx_scB_fill2), :);
            vers_scB_filled_2 = subs_scB_filled_2 .* size_voxel;
            faces_scB_filled_2 = CartiMorphToolbox.cal_extractFaces_OR(faces_bone_wInter, vers_bone_wInter, vers_scB_filled_2);

            % internal checkpoint for the pause/resume and stop function
            CMT_checkPauseStopStatus(app);

            % (4) Fill holes that reach the boundary: fine tunning step
            % estimate the number of surface dilation and erosion
            switch name_cartilage
                case 'FemoralCartilage'
                    extentLimit = 4;
                case 'mTibialCartilage'
                    extentLimit = 3;
                case 'lTibialCartilage'
                    extentLimit = 3;
            end
            iteration_dilation = round(extentLimit / size_voxel(1));
            iteration_erosion = iteration_dilation + 4;
            faces_subchondral = CartiMorphToolbox.cal_surfaceClosing(...
                faces_scB_filled_2,...
                faces_bone_wInter,...
                iteration_dilation,...
                iteration_erosion);

            % internal checkpoint for the pause/resume and stop function
            CMT_checkPauseStopStatus(app);

            % (5) Remove sharp-edge faces
            faces_subchondral = CartiMorphToolbox.cal_deleteSharpEdgeTri(faces_subchondral);

            % internal checkpoint for the pause/resume and stop function
            CMT_checkPauseStopStatus(app);

            % (6) Add faces whose two edges are the edges of the reconstructed subchondral bone surface
            faces_subchondral = CartiMorphToolbox.cal_extractFaces_AND(faces_bone_wInter, vers_bone_wInter,...
                vers_bone_wInter(unique(faces_subchondral(:)), :));

            % internal checkpoint for the pause/resume and stop function
            CMT_checkPauseStopStatus(app);

            % (!!!important!!!) ---
            % (7) Delete small components for the reconstructed subchondral bone surface
            % (!!!important!!!) ---
            [~, components_subchondral] = CartiMorphToolbox.segment_connected_components(faces_subchondral, 'explicit');
            faces_subchondral = CartiMorphToolbox.cal_deleteSmallComponents(components_subchondral, 50);

            % internal checkpoint for the pause/resume and stop function
            CMT_checkPauseStopStatus(app);

            % (8) Detect filling mesh
            % remove interface from the subchondral bone mesh to get the filling components
            % (MeshProcessingToolbox)
            faces_inter_rebased = CartiMorphToolbox.cal_extractFaces_AND(faces_bone_wInter, vers_bone_wInter, vers_inter);
            faces_filling = CartiMorphToolbox.remove_triangles(faces_inter_rebased, faces_subchondral, 'explicit');
            faces_filling = CartiMorphToolbox.remove_duplicated_triangles(faces_filling);
            if ~isempty(faces_filling)
                [vers_filling, faces_filling] = CartiMorphToolbox.remove_unreferenced_vertices(vers_bone_wInter, faces_filling);
                idx_filling = ismember(vers_bone_wInter, vers_filling, 'rows');
                sFV_filling.vertices = sFV_bone_wInter.vertices(idx_filling, :);
                sFV_filling.faces = faces_filling;
                FV_filling.faces = faces_filling;
                FV_filling.vertices = vers_filling;
            else
                FV_filling = [];
                sFV_filling = [];
            end

            % internal checkpoint for the pause/resume and stop function
            CMT_checkPauseStopStatus(app);

            % (9) faces and vertices of the subchondral bone surface
            % update vertices and faces
            [vers_subchondral, faces_subchondral] = CartiMorphToolbox.remove_unreferenced_vertices(vers_bone_wInter, faces_subchondral);
            faces_subchondral = CartiMorphToolbox.remove_duplicated_triangles(faces_subchondral);
            % the smoothed and unsmoothed mesh
            idx_subchondral = ismember(vers_bone_wInter, vers_subchondral, 'rows');
            vers_subchondral_smoothed = sFV_bone_wInter.vertices(idx_subchondral, :);
            sFV_subchondral.faces = faces_subchondral;
            sFV_subchondral.vertices = vers_subchondral_smoothed;
            FV_subchondral.faces = faces_subchondral;
            FV_subchondral.vertices = vers_subchondral;

            % internal checkpoint for the pause/resume and stop function
            CMT_checkPauseStopStatus(app);

            if ~isempty(log_fileID)
                time = toc;
                fprintf(log_fileID, append(' finished. (Elapsed time is ', num2str(time), ' seconds). \n'));
            end
            %------------------------------------------------------------------------------------------


            %%  Align the interface mesh to the subchondral bone mesh
            %------------------------------------------------------------------------------------------
            if ~isempty(log_fileID)
                tic;
                fprintf(log_fileID, '    [2.7] Align the interface mesh to the subchondral bone mesh... ');
            end

            % remove unreferenced vertices (MeshProcessingToolbox)
            [vers_inter_rebased, faces_inter_rebased] = CartiMorphToolbox.remove_unreferenced_vertices(vers_bone_wInter, faces_inter_rebased);
            faces_inter_rebased = CartiMorphToolbox.remove_duplicated_triangles(faces_inter_rebased);

            % the faces and vertices of the rebased bone-cartilage interface
            idx_inter_rebased = ismember(vers_bone_wInter, vers_inter_rebased, 'rows');
            vers_inter_rebased_smoothed = sFV_bone_wInter.vertices(idx_inter_rebased, :);
            sFV_inter_rebased.faces = faces_inter_rebased;
            sFV_inter_rebased.vertices = vers_inter_rebased_smoothed;
            FV_inter_rebased.faces = faces_inter_rebased;
            FV_inter_rebased.vertices = vers_inter_rebased;

            % internal checkpoint for the pause/resume and stop function
            CMT_checkPauseStopStatus(app);

            if ~isempty(log_fileID)
                time = toc;
                fprintf(log_fileID, append(' finished. (Elapsed time is ', num2str(time), ' seconds). \n'));
            end
            %------------------------------------------------------------------------------------------


            %%  Cartilage surface reconstruction for better visualisation
            %------------------------------------------------------------------------------------------
            if ~isempty(log_fileID)
                tic;
                fprintf(log_fileID, '    [2.8] Cartilage surface reconstruction for better visualisation... ');
            end

            % remove bone-cartilage interface from the cartilage mask
            mask_Cart_woInter = uint8(mask_cartilage) - uint8(mask_inter);
            % get boundary mesh
            FV_Cart_woInter = CartiMorphToolbox.cal_getBoundaryMesh(mask_Cart_woInter);
            % remove duplicated faces
            FV_Cart_woInter.faces = CartiMorphToolbox.remove_duplicated_triangles(FV_Cart_woInter.faces);
            % convert subscripts to coordinates
            FV_Cart_woInter.vertices = FV_Cart_woInter.vertices .* size_voxel;
            % smooth the mesh for the cartilage surface (matGeom)
            [sFV_Cart_woInter.vertices, sFV_Cart_woInter.faces] = CartiMorphToolbox.smoothMesh(FV_Cart_woInter.vertices, FV_Cart_woInter.faces, 2);

            % internal checkpoint for the pause/resume and stop function
            CMT_checkPauseStopStatus(app);

            if ~isempty(log_fileID)
                time = toc;
                fprintf(log_fileID, append(' finished. (Elapsed time is ', num2str(time), ' seconds). \n'));
            end
            %------------------------------------------------------------------------------------------

        end


        function CMT_imgStandardization(app)
            if app.opt_imgStandardization
                % training images
                if strcmp(app.EditField_imgTr.Editable, "on")
                    CMT_imgStd_action(app, app.dir_rawData_imageTr, app.dir_nnUNet_raw_imgTr, app.config_nnUNet_imgSize, true);
                    app.EditField_imgTr.Editable = "off";
                    app.Button_selectPath_imgTr.Enable = "off";
                end

                % testing images
                if strcmp(app.EditField_imgTs.Editable, "on")
                    CMT_imgStd_action(app, app.dir_rawData_imageTs, app.dir_nnUNet_raw_imgTs, app.config_nnUNet_imgSize, true);
                    app.EditField_imgTs.Editable = "off";
                    app.Button_selectPath_imgTs.Enable = "off";
                end

                % training labels
                if strcmp(app.EditField_labelTr.Editable, "on")
                    CMT_imgStd_action(app, app.dir_rawData_labelTr, app.dir_nnUNet_raw_labelTr, app.config_nnUNet_imgSize, false);
                    app.EditField_labelTr.Editable = "off";
                    app.Button_selectPath_labelTr.Enable = "off";
                end

                % testing labels
                if strcmp(app.EditField_labelTs.Editable, "on")
                    CMT_imgStd_action(app, app.dir_rawData_labelTs, app.dir_nnUNet_raw_labelTs, app.config_nnUNet_imgSize, false);
                    app.EditField_labelTs.Editable = "off";
                    app.Button_selectPath_labelTs.Enable = "off";
                end

                % inference images
                if strcmp(app.EditField_imgInference.Editable, "on")
                    CMT_imgStd_action(app, app.dir_rawData_imgInference,...
                        app.dir_nnUNet_raw_imgInference, app.config_nnUNet_imgSize, true);
                    app.EditField_imgInference.Editable = "off";
                    app.Button_selectPath_imgInference.Enable = "off";
                end

                % light the lamp
                app.Lamp_ii.Color = [0.3804, 0.7333, 0.2745];
                % freeze ii
                app.CheckBox_ii.Enable = "off";
                app.EditField_ii_imgSizeX.Editable = "off";
                app.EditField_ii_imgSizeY.Editable = "off";
                app.EditField_ii_imgSizeZ.Editable = "off";

                % disable this step
                app.opt_imgStandardization = false;

                % autosave configuration
                CMT_autosaveConfig(app, 'config_latest');
                CMT_autosaveConfig(app, 'config_ii');

                % internal checkpoint for the pause/resume and stop function
                CMT_checkPauseStopStatus(app);
            end
        end


        function CMT_nnUNet_train(app)
            if app.opt_nnUNet_training
                % ------------------------------------------------------------
                % [1] Experiment Planning and Preprocessing (nnUNet)
                % ------------------------------------------------------------
                if app.opt_nnUNet_planning_preprocessing
                    % [nnUNet configuration: generate "dataset.json"]
                    % write "generate_dataset_json.py"
                    path_nnUNet_py_genDatasetJson = fullfile(app.dir_nnUNet_autoScript, 'generate_dataset_json.py');
                    fileID = fopen(path_nnUNet_py_genDatasetJson, 'w');
                    fprintf(fileID, "from batchgenerators.utilities.file_and_folder_operations import * \n");
                    fprintf(fileID, "from CartiMorph_nnUNet.dataset_conversion.utils import generate_dataset_json \n");
                    fprintf(fileID, "from CartiMorph_nnUNet.paths import nnUNet_raw_data, preprocessing_output_dir \n");
                    fprintf(fileID, " \n");
                    fprintf(fileID, "task_name='%s' \n", app.config_nnUNet_taskIDName);
                    fprintf(fileID, "target_base = join(nnUNet_raw_data, task_name) \n");
                    fprintf(fileID, "target_imagesTr = join(target_base, 'imagesTr') \n");
                    fprintf(fileID, "target_imagesTs = join(target_base, 'imagesTs') \n");
                    fprintf(fileID, "target_labelsTr = join(target_base, 'labelsTr') \n" );
                    fprintf(fileID, "target_labelsTs = join(target_base, 'labelsTs') \n");
                    fprintf(fileID, " \n" );
                    fprintf(fileID, "generate_dataset_json(join(target_base, 'dataset.json'), target_imagesTr, target_imagesTs, ('D'), \n");
                    tmp = '    labels={';
                    for i=1:length(app.config_ROI_label)
                        if i~=length(app.config_ROI_label)
                            tmp = strcat(tmp, app.config_ROI_label{i}, ": '", app.config_ROI_name{i}, "', ");
                        else
                            tmp = strcat(tmp, app.config_ROI_label{i}, ": '", app.config_ROI_name{i}, "'");
                        end
                    end
                    tmp = strcat(tmp, "},");
                    fprintf(fileID, "%s \n", tmp );
                    fprintf(fileID, "    dataset_name=task_name, license='hands off!') \n" );
                    fclose(fileID);

                    % write "generate_dataset_json.sh"
                    path_nnUNet_bash_genDatasetJson = fullfile(app.dir_nnUNet_autoScript, 'generate_dataset_json.sh');
                    path_nnUNet_log_genDatasetJson = fullfile(app.dir_nnUNet_log, 'generate_dataset_json.log');
                    fileID = fopen(path_nnUNet_bash_genDatasetJson, 'w');
                    fprintf(fileID, '# activate Conda environment \n');
                    fprintf(fileID, 'eval "$(conda shell.bash hook)" \n');
                    fprintf(fileID, 'conda activate CartiMorphToolbox-nnUNet \n');
                    fprintf(fileID, '\n');
                    fprintf(fileID, 'export LD_LIBRARY_PATH="%s:$LD_LIBRARY_PATH" \n', app.path_CUDA_lib_nnUNet);
                    fprintf(fileID, " \n");
                    fprintf(fileID, "export nnUNet_raw_data_base='%s' \n", app.dir_nnUNet_rawBase);
                    fprintf(fileID, "export nnUNet_preprocessed='%s' \n", app.dir_nnUNet_preprocess);
                    fprintf(fileID, "export RESULTS_FOLDER='%s' \n", app.dir_nnUNet_model);
                    fprintf(fileID, " \n");
                    fprintf(fileID, "export log_file='%s' \n", path_nnUNet_log_genDatasetJson);
                    fprintf(fileID, " \n");
                    fprintf(fileID, 'Dir_current="$( cd "$( dirname "${BASH_SOURCE[0]}" )" && pwd )" \n');
                    fprintf(fileID, 'python $Dir_current/generate_dataset_json.py > "$log_file" 2>&1');
                    fclose(fileID);

                    % run "generate_dataset_json.sh"
                    cmd = strcat("chmod u+x ", path_nnUNet_py_genDatasetJson);
                    system(cmd);
                    cmd = strcat("chmod u+x ", path_nnUNet_bash_genDatasetJson);
                    system(cmd);
                    cmd = strcat("bash ", path_nnUNet_bash_genDatasetJson);
                    system(cmd);

                    % internal checkpoint for the pause/resume and stop function
                    CMT_checkPauseStopStatus(app);

                    % write "planning_preprocessing.sh"
                    path_nnUNet_bash_planning = fullfile(app.dir_nnUNet_autoScript, "planning_preprocessing.sh");
                    path_nnUNet_log_planning = fullfile(app.dir_nnUNet_log, 'planning_preprocessing.log');
                    fileID = fopen(path_nnUNet_bash_planning, 'w');
                    fprintf(fileID, '# activate Conda environment \n');
                    fprintf(fileID, 'eval "$(conda shell.bash hook)" \n');
                    fprintf(fileID, 'conda activate CartiMorphToolbox-nnUNet \n');
                    fprintf(fileID, '\n');
                    fprintf(fileID, 'export LD_LIBRARY_PATH="%s:$LD_LIBRARY_PATH" \n', app.path_CUDA_lib_nnUNet);
                    fprintf(fileID, " \n");
                    fprintf(fileID, "export nnUNet_raw_data_base='%s' \n", app.dir_nnUNet_rawBase);
                    fprintf(fileID, "export nnUNet_preprocessed='%s' \n", app.dir_nnUNet_preprocess);
                    fprintf(fileID, "export RESULTS_FOLDER='%s' \n", app.dir_nnUNet_model);
                    fprintf(fileID, " \n");
                    fprintf(fileID, "export taskID='%s' \n", app.config_nnUNet_ID);
                    fprintf(fileID, " \n");
                    fprintf(fileID, "export log_file='%s' \n", path_nnUNet_log_planning);
                    fprintf(fileID, " \n");
                    if app.CheckBox_iii.Value
                        fprintf(fileID, 'CartiMorph_nnUNet_plan_and_preprocess -t "$taskID" --verify_dataset_integrity > "$log_file" 2>&1 \n');
                    else
                        loadedPlans = fullfile(app.dir_nnUNet_pretrained_model, "plans.pkl");
                        loadedPlans_identifier = app.config_nnUNet_loadedPlans_identifier;
                        fprintf(fileID, "export Plans='%s' \n", loadedPlans);
                        fprintf(fileID, "export Plans_identifier='%s' \n", loadedPlans_identifier);
                        fprintf(fileID, " \n");
                        fprintf(fileID, 'CartiMorph_nnUNet_plan_and_preprocess -t "$taskID" --verify_dataset_integrity -pl3d ExperimentPlanner3D_v21_Pretrained --overwrite_plans "$Plans" --overwrite_plans_identifier "$Plans_identifier" > "$log_file" 2>&1 \n');
                    end
                    fprintf(fileID, " \n");

                    % temporary bug-fix (https://github.com/MIC-DKFZ/nnUNet/issues/291)
                    tmp_foler0 = fullfile(app.dir_nnUNet_preprocess, app.config_nnUNet_taskIDName,'nnUNetData_plans_v2.1_stage0');
                    tmp_foler1 = fullfile(app.dir_nnUNet_preprocess, app.config_nnUNet_taskIDName,'nnUNetData_plans_v2.1_stage1');
                    if isfolder(tmp_foler0)
                        fprintf(fileID, 'find %s -name "*.npy" -type f -delete \n', tmp_foler0);
                    end
                    if isfolder(tmp_foler1)
                        fprintf(fileID, 'find %s -name "*.npy" -type f -delete \n', tmp_foler1);
                    end
                    fclose(fileID);

                    % run "planning_preprocessing.sh"
                    cmd = strcat("chmod u+x ", path_nnUNet_bash_planning);
                    system(cmd);
                    job_nnUNet_planning = batch(@CartiMorphToolbox.CMT_runBatchJob, 0, {path_nnUNet_bash_planning});
                    app.container_jobs.job_nnUNet_planning = job_nnUNet_planning;
                    app.container_jobStatus.job_nnUNet_planning = "running";

                    % wait until the model planning finishs
                    while ~strcmp(app.container_jobs.job_nnUNet_planning.State, "finished")
                        pause(60)
                        % internal checkpoint for the pause/resume and stop function
                        CMT_checkPauseStopStatus(app);
                    end
                    app.container_jobStatus.job_nnUNet_planning = "finished";

                    % disable this step
                    app.opt_nnUNet_planning_preprocessing = false;

                    if app.CheckBox_iii2.Value
                        % light the lamp
                        app.Lamp_iii2_prepro.Color = [0.3804, 0.7333, 0.2745];
                        % disable the reset button
                        app.Button_iii2_reset.Enable = "off";
                        app.Button_iii2_reset.Visible = "off";
                    else
                        % light the lamp
                        app.Lamp_iii_prepro.Color = [0.3804, 0.7333, 0.2745];
                        % freeze
                        app.CheckBox_iii.Enable = "off";
                        app.CheckBox_iii_prepro.Enable = "off";
                    end

                    % autosave configuration
                    CMT_autosaveConfig(app, 'config_latest');

                    % internal checkpoint for the pause/resume and stop function
                    CMT_checkPauseStopStatus(app);
                end
                % ------------------------------------------------------------


                % ------------------------------------------------------------
                % [2] Training (nnUNet)
                % ------------------------------------------------------------
                if app.opt_nnUNet_training_begin
                    % write "training_xxxx.sh"
                    path_nnUNet_bash_training = fullfile(app.dir_nnUNet_autoScript, "training_3dF.sh");
                    path_nnUNet_log_training = fullfile(app.dir_nnUNet_log, 'training_3dF.log');

                    fileID = fopen(path_nnUNet_bash_training, 'w');
                    fprintf(fileID, '# activate Conda environment \n');
                    % fprintf(fileID, "source %s \n", app.path_conda_src);
                    fprintf(fileID, 'eval "$(conda shell.bash hook)" \n');
                    fprintf(fileID, 'conda activate CartiMorphToolbox-nnUNet \n');
                    fprintf(fileID, '\n');
                    fprintf(fileID, 'export LD_LIBRARY_PATH="%s:$LD_LIBRARY_PATH" \n', app.path_CUDA_lib_nnUNet);
                    fprintf(fileID, " \n");
                    fprintf(fileID, "export MKL_NUM_THREADS=6 \n"); % TODO: check necessity
                    fprintf(fileID, "\n");
                    fprintf(fileID, "export gpuIDs='%s' \n", app.config_nnUNet_GPU);
                    fprintf(fileID, "export nnUNet_taskName='%s' \n", app.config_nnUNet_taskIDName);
                    fprintf(fileID, "export nnUNet_taskID='%s' \n", app.config_nnUNet_ID);
                    fprintf(fileID, "export nnUNet_trainer='nnUNetTrainerV2' \n");
                    fprintf(fileID, "export epoch=%s \n", num2str(app.config_nnUNet_epochs));
                    fprintf(fileID, "export nnUNet_architecture='%s' \n", app.config_nnUNet_model_archi);
                    fprintf(fileID, "export nnUNet_fold='%s' \n", app.config_nnUNet_fold);
                    fprintf(fileID, " \n");
                    fprintf(fileID, "export nnUNet_raw_data_base='%s' \n", app.dir_nnUNet_rawBase);
                    fprintf(fileID, "export nnUNet_preprocessed='%s' \n", app.dir_nnUNet_preprocess);
                    fprintf(fileID, "export RESULTS_FOLDER='%s' \n", app.dir_nnUNet_model);
                    fprintf(fileID, " \n");
                    fprintf(fileID, "export log_file='%s' \n", path_nnUNet_log_training);
                    fprintf(fileID, " \n");
                    if app.opt_nnUNet_training_scratch  % (iii: train from scratch)
                        fprintf(fileID, 'CUDA_VISIBLE_DEVICES=$gpuIDs CartiMorph_nnUNet_train $nnUNet_architecture $nnUNet_trainer $nnUNet_taskID $nnUNet_fold --epoch $epoch > "$log_file" 2>&1 \n');
                    elseif app.CheckBox_iii.Value  % (iii: continue training)
                        fprintf(fileID, 'CUDA_VISIBLE_DEVICES=$gpuIDs CartiMorph_nnUNet_train $nnUNet_architecture $nnUNet_trainer $nnUNet_taskID $nnUNet_fold --epoch $epoch -c > "$log_file" 2>&1 \n');
                    else % (iii-2: finetuning)
                        nnUNetPlans_pretrained_IDENTIFIER = strcat("nnUNetPlans_pretrained_", app.config_nnUNet_loadedPlans_identifier);
                        path_pretrainedModelWeights = fullfile(app.dir_nnUNet_pretrained_model, "segModel.model");
                        fprintf(fileID, 'CUDA_VISIBLE_DEVICES=$gpuIDs CartiMorph_nnUNet_train $nnUNet_architecture $nnUNet_trainer $nnUNet_taskID $nnUNet_fold --epoch $epoch -p %s --pretrained_weights %s > "$log_file" 2>&1 \n',...
                            nnUNetPlans_pretrained_IDENTIFIER, path_pretrainedModelWeights);
                    end
                    fclose(fileID);

                    % run "training_xxxx.sh" on a matlab worker
                    cmd = strcat("chmod u+x ", path_nnUNet_bash_training);
                    system(cmd);
                    job_nnUNet_train = batch(@CartiMorphToolbox.CMT_runBatchJob, 0, {path_nnUNet_bash_training});
                    app.container_jobs.job_nnUNet_train = job_nnUNet_train;
                    app.container_jobStatus.job_nnUNet_train = "running";

                    % internal checkpoint for the pause/resume and stop function
                    CMT_checkPauseStopStatus(app);
                end
                % ------------------------------------------------------------
            end
        end


        function CMT_nnUNet_eval(app)
            % ----------------------------------------------
            % Inference on testing set for model evaluation
            % ----------------------------------------------
            if isfield(app.container_jobStatus, 'job_nnUNet_train') && strcmp(app.container_jobStatus.job_nnUNet_train, 'finished')
                % write "inference_3dF_testSet.sh"
                path_nnUNet_bash_inference_testSet = fullfile(app.dir_nnUNet_autoScript, "inference_3dF_testSet.sh");
                path_nnUNet_log_inference_testSet = fullfile(app.dir_nnUNet_log, 'inference_3dF_testSet.log');

                fileID = fopen(path_nnUNet_bash_inference_testSet, 'w');
                fprintf(fileID, '# activate Conda environment \n');
                % fprintf(fileID, "source %s \n", app.path_conda_src);
                fprintf(fileID, 'eval "$(conda shell.bash hook)" \n');
                fprintf(fileID, 'conda activate CartiMorphToolbox-nnUNet \n');
                fprintf(fileID, '\n');
                fprintf(fileID, 'export LD_LIBRARY_PATH="%s:$LD_LIBRARY_PATH" \n', app.path_CUDA_lib_nnUNet);
                fprintf(fileID, " \n");
                fprintf(fileID, 'export MKL_NUM_THREADS=6 \n'); % TODO: check necessity
                fprintf(fileID, " \n");
                fprintf(fileID, "export gpuIDs='%s' \n", app.config_nnUNet_GPU);
                fprintf(fileID, "export nnUNet_prediction_in='%s' \n", app.dir_nnUNet_raw_imgTs);
                fprintf(fileID, "export nnUNet_prediction_out='%s' \n", app.dir_nnUNet_prediction_testSet);
                fprintf(fileID, "export nnUNet_fold='%s' \n", app.config_nnUNet_fold);
                fprintf(fileID, "export nnUNet_archi='%s' \n", app.config_nnUNet_model_archi);
                fprintf(fileID, "export nnUNet_taskName='%s' \n", app.config_nnUNet_taskIDName);
                if app.CheckBox_iii.Value
                    plans_identifier = "nnUNetPlansv2.1";
                else
                    plans_identifier = strcat("nnUNetPlans_pretrained_", app.config_nnUNet_loadedPlans_identifier);
                end
                fprintf(fileID, "export nnUNet_plans_identifier='%s' \n", plans_identifier);
                fprintf(fileID, " \n");
                fprintf(fileID, "export nnUNet_raw_data_base='%s' \n", app.dir_nnUNet_rawBase);
                fprintf(fileID, "export nnUNet_preprocessed='%s' \n", app.dir_nnUNet_preprocess);
                fprintf(fileID, "export RESULTS_FOLDER='%s' \n", app.dir_nnUNet_model);
                fprintf(fileID, " \n");
                fprintf(fileID, 'CUDA_VISIBLE_DEVICES=$gpuIDs CartiMorph_nnUNet_predict -i "$nnUNet_prediction_in" -o "$nnUNet_prediction_out" -f "$nnUNet_fold" -tr nnUNetTrainerV2 -m "$nnUNet_archi" -p "$nnUNet_plans_identifier" -t "$nnUNet_taskName" > %s 2>&1 \n',...
                    path_nnUNet_log_inference_testSet);
                fclose(fileID);

                % run "inference_3dF_inferenceSet.sh"
                cmd = strcat("chmod u+x ", path_nnUNet_bash_inference_testSet);
                system(cmd);

                % model inference on testing set
                % don't use: batch(@CartiMorphToolbox.CMT_runBatchJob, 0, {path_nnUNet_bash_inference_testSet})
                cmd = strcat("bash ", path_nnUNet_bash_inference_testSet);
                system(cmd); % toolbox will continue after the inference process is finished
            end
            % ----------------------------------------------
        end

        function CMT_vxm_eval(app)
            % ------------------------------------------------------------
            % Model inference on testing set
            % ------------------------------------------------------------
            % CartiMorph-vxm scripts folder
            dir_CMvxm_scripts = fullfile(app.dir_conda_base, "envs/CartiMorphToolbox-Vxm/lib/python3.10/site-packages/CartiMorph_vxm/tf/scripts");

            % -------------------------------
            % Network prediction on testing set -- for model evaluation
            % -------------------------------
            if isfield(app.container_jobStatus, 'job_vxm_train') && strcmp(app.container_jobStatus.job_vxm_train, 'finished')
                path_vxm_bash_inference_warpTempSeg_testSet = fullfile(app.dir_vxm_autoScript, "inference_warpTempSeg_testSet.sh");
                path_vxm_log_inference_warpTempSeg_testSet = fullfile(app.dir_vxm_log, "inference_warpTempSeg_testSet.log");

                % write "inference_warpTempSeg.sh"
                fileID = fopen(path_vxm_bash_inference_warpTempSeg_testSet, 'w');
                fprintf(fileID, '# activate Conda environment \n');
                % fprintf(fileID, "source %s \n", app.path_conda_src);
                fprintf(fileID, 'eval "$(conda shell.bash hook)" \n');
                fprintf(fileID, 'conda activate CartiMorphToolbox-Vxm \n');
                fprintf(fileID, '\n');
                fprintf(fileID, 'export LD_LIBRARY_PATH="%s:$LD_LIBRARY_PATH" \n', app.path_CUDA_lib_Vxm);
                fprintf(fileID, " \n");
                fprintf(fileID, "export dir_scripts='%s' \n", dir_CMvxm_scripts);
                fprintf(fileID, " \n");
                fprintf(fileID, "export dir_targetImg='%s' \n", app.dir_vxm_data_prepImgTs);
                fprintf(fileID, "export file_TempSeg='%s' \n", fullfile(app.dir_vxm_data_Template, "templateSeg.nii.gz"));
                fprintf(fileID, "export dir_warpedTempSeg='%s' \n", app.dir_vxm_inference_warpedTempSeg_Ts);
                fprintf(fileID, "export dir_warpingField='%s' \n", app.dir_vxm_inference_warpingField_Ts);
                fprintf(fileID, "export file_model='%s' \n", fullfile(app.dir_vxm_model, 'bestModel.h5'));
                fprintf(fileID, "export gpuIDs='%s' \n", app.config_vxm_GPU);
                fprintf(fileID, " \n");
                cmd = sprintf(['"$dir_scripts"/inference_temp2img_warpTempSeg.py ',...
                    '--dir_targetImg "$dir_targetImg" ',...
                    '--file_TempSeg "$file_TempSeg" ',...
                    '--dir_warpedTempSeg "$dir_warpedTempSeg" ',...
                    '--dir_warpingField "$dir_warpingField" ',...
                    '--file_model "$file_model" ',...
                    '--gpuIDs "$gpuIDs" ',...
                    '> %s 2>&1 \n'],...
                    path_vxm_log_inference_warpTempSeg_testSet);
                fprintf(fileID, cmd);
                fclose(fileID);

                % add execution priority
                path_CMvxm_script = fullfile(dir_CMvxm_scripts, 'inference_temp2img_warpTempSeg.py');
                cmd = strcat("chmod u+x ", path_CMvxm_script);
                system(cmd);

                % run "inference_warpTempSeg.sh"
                cmd = strcat("chmod u+x ", path_vxm_bash_inference_warpTempSeg_testSet);
                system(cmd);

                % model evaluation
                % don't use: batch(@CartiMorphToolbox.CMT_runBatchJob, 0, {path_vxm_bash_inference_warpTempSeg_testSet})
                cmd = strcat("bash ", path_vxm_bash_inference_warpTempSeg_testSet);
                system(cmd);

                % internal checkpoint for the pause/resume and stop function
                CMT_checkPauseStopStatus(app);
            end
            % -------------------------------
            % ------------------------------------------------------------
        end


        function CMT_vxm_train(app)
            if app.opt_vxm_training
                % CartiMorph-vxm scripts folder
                dir_CMvxm_scripts = fullfile(app.dir_conda_base, "envs/CartiMorphToolbox-Vxm/lib/python3.10/site-packages/CartiMorph_vxm/tf/scripts");

                % ------------------------------------------------------------
                % [1] Experiment Planning and Preprocessing (VoxelMorph)
                % ------------------------------------------------------------
                if app.opt_vxm_preprocessing
                    % -------------------------------
                    % [training set]
                    % -------------------------------
                    % estimate knee joint info
                    path_vxm_data_jointInfo_Tr = fullfile(app.dir_vxm_data_base, "kneeJointInfo_Label_Tr.mat");
                    kneeJointInfo_Label_Tr = CMT_estKneeJointSize(app, app.dir_nnUNet_raw_labelTr, app.config_ROI_label, app.config_ROI_name);
                    save(path_vxm_data_jointInfo_Tr, '-struct', "kneeJointInfo_Label_Tr");
                    % image preprocessing: masking, downsampling, cropping, image intensity normalization
                    cropRange_Label_Tr = CMT_imgPreprocess_mdcn(app, app.dir_nnUNet_raw_labelTr, app.dir_nnUNet_raw_imgTr,...
                        app.dir_vxm_data_prepLabelTr, app.dir_vxm_data_prepImgTr,...
                        app.config_vxm_imgSize_out, kneeJointInfo_Label_Tr, 4);
                    % save cropping range (in the downsampled image)
                    save(app.path_vxm_data_cropRange_Tr, '-struct', "cropRange_Label_Tr");
                    % -------------------------------

                    % -------------------------------
                    % [testing set]
                    % -------------------------------
                    % estimate knee joint info
                    path_vxm_data_jointInfo_Ts = fullfile(app.dir_vxm_data_base, "kneeJointInfo_Label_Ts.mat");
                    kneeJointInfo_Label_Ts = CMT_estKneeJointSize(app, app.dir_nnUNet_raw_labelTs, app.config_ROI_label, app.config_ROI_name);
                    save(path_vxm_data_jointInfo_Ts, '-struct', "kneeJointInfo_Label_Ts");
                    % image preprocessing: masking, downsampling, cropping, image intensity normalization
                    cropRange_Label_Ts = CMT_imgPreprocess_mdcn(app, app.dir_nnUNet_raw_labelTs, app.dir_nnUNet_raw_imgTs,...
                        app.dir_vxm_data_prepLabelTs, app.dir_vxm_data_prepImgTs,...
                        app.config_vxm_imgSize_out, kneeJointInfo_Label_Ts, 4);
                    % save cropping range (in the downsampled image)
                    save(app.path_vxm_data_cropRange_Ts, '-struct', "cropRange_Label_Ts");
                    % -------------------------------

                    % disable this step
                    app.opt_vxm_preprocessing = false;

                    if app.CheckBox_iv2.Value
                        % light the lamp
                        app.Lamp_iv2_prepro.Color = [0.3804, 0.7333, 0.2745];
                        % freeze
                        app.CheckBox_iv2_prepro.Enable = "off";
                    else
                        % light the lamp
                        app.Lamp_iv_prepro.Color = [0.3804, 0.7333, 0.2745];
                        % freeze
                        app.CheckBox_iv.Enable = "off";
                        app.CheckBox_iv_prepro.Enable = "off";
                        app.EditField_iv_CroppedImageSizeX.Editable = "off";
                        app.EditField_iv_CroppedImageSizeY.Editable = "off";
                        app.EditField_iv_CroppedImageSizeZ.Editable = "off";
                    end

                    % autosave configuration
                    CMT_autosaveConfig(app, 'config_latest');

                    % internal checkpoint for the pause/resume and stop function
                    CMT_checkPauseStopStatus(app);
                end
                % ------------------------------------------------------------


                % ------------------------------------------------------------
                % [2] Training (VoxelMorph)
                % ------------------------------------------------------------
                % two-stage training strategy
                %   - stage 1: update template
                %   - stage 2: freeze the learned template and train the model
                % ------------------------------------------------------------
                if app.opt_vxm_training_begin
                    % generate training images list
                    tmp = [dir(fullfile(app.dir_vxm_data_prepImgTr, "*.nii.gz"));...
                        dir(fullfile(app.dir_vxm_data_prepImgTr, "*.nii"))];
                    list_vxm_data_imgTr = {tmp.name}';
                    path_vxm_imgTrList = fullfile(app.dir_vxm_data_base, "list_imagesTr.txt");
                    writecell(list_vxm_data_imgTr, path_vxm_imgTrList);

                    % log file and Bash script
                    if app.opt_vxm_training_scratch
                        path_vxm_bash_training = fullfile(app.dir_vxm_autoScript, "training_scratch.sh");
                        path_vxm_log_training_s1 = fullfile(app.dir_vxm_log, "training_scratch_s1.log");
                        path_vxm_log_training_s2 = fullfile(app.dir_vxm_log, "training_scratch_s2.log");
                    else
                        path_vxm_bash_training = fullfile(app.dir_vxm_autoScript, "training_transfer_deploy.sh");
                        path_vxm_log_training_s1 = fullfile(app.dir_vxm_log, "training_transfer_deploy_s1.log");
                        path_vxm_log_training_s2 = fullfile(app.dir_vxm_log, "training_transfer_deploy_s2.log");
                    end

                    % use the voxel size of the first image as that of the template image
                    tmp_img1_info = niftiinfo(fullfile(app.dir_vxm_data_prepImgTr, list_vxm_data_imgTr{1}));
                    tmp_img1_voxSize = tmp_img1_info.PixelDimensions;

                    % --------------------------------------
                    % write "training_xxxx.sh"
                    % --------------------------------------
                    fileID = fopen(path_vxm_bash_training, 'w');
                    fprintf(fileID, '# activate Conda environment \n');
                    % fprintf(fileID, "source %s \n", app.path_conda_src);
                    fprintf(fileID, 'eval "$(conda shell.bash hook)" \n');
                    fprintf(fileID, 'conda activate CartiMorphToolbox-Vxm \n');
                    fprintf(fileID, '\n');
                    fprintf(fileID, 'export LD_LIBRARY_PATH="%s:$LD_LIBRARY_PATH" \n', app.path_CUDA_lib_Vxm);
                    fprintf(fileID, "\n");
                    fprintf(fileID, "# [Folders] \n");
                    fprintf(fileID, "export dir_scripts='%s' \n", dir_CMvxm_scripts);
                    fprintf(fileID, "export dir_model='%s' \n", app.dir_vxm_model);
                    fprintf(fileID, "\n");
                    fprintf(fileID, "# [Data] \n");
                    fprintf(fileID, "export img_list='%s' \n", path_vxm_imgTrList);
                    fprintf(fileID, "export img_prefix='%s' \n", strcat(app.dir_vxm_data_prepImgTr, '/'));
                    fprintf(fileID, "export img_suffix='' \n");
                    fprintf(fileID, "\n");

                    % [training]
                    fprintf(fileID, "# [Training] \n");
                    switch app.config_vxm_imgLoss
                        case "MSE"
                            fprintf(fileID, "export imgLoss_stage1='mse' \n");
                            fprintf(fileID, "export imgLoss_stage2='mse' \n");
                        case "LNCC"
                            fprintf(fileID, "export imgLoss_stage1='ncc' \n");
                            fprintf(fileID, "export imgLoss_stage2='ncc' \n");
                        case "MSE+LNCC"
                            fprintf(fileID, "export imgLoss_stage1='mse' \n");
                            fprintf(fileID, "export imgLoss_stage2='ncc' \n");
                    end
                    fprintf(fileID, "export weight_meanLoss=1 \n");
                    fprintf(fileID, "export weight_gradLoss=0.5 \n");
                    fprintf(fileID, "export gpuIDs=%s \n", app.config_vxm_GPU);
                    fprintf(fileID, "export batch_size=%s \n", num2str(app.config_vxm_batchSize));

                    % set: training epoch, model saving step
                    config_epochs_stage1 = floor(app.config_vxm_epochs*0.5);
                    % (stage 1)
                    if config_epochs_stage1<=10
                        model_saving_step_s1 = 1;
                    elseif config_epochs_stage1<=50
                        model_saving_step_s1 = 10;
                    elseif config_epochs_stage1>50
                        model_saving_step_s1 = 50;
                    end
                    % (stage 2)
                    epochs_count_stage2 = app.config_vxm_epochs - config_epochs_stage1;
                    if epochs_count_stage2<=10
                        model_saving_step_s2 = 1;
                    elseif epochs_count_stage2<=50
                        model_saving_step_s2 = 10;
                    elseif epochs_count_stage2>50
                        model_saving_step_s2 = 50;
                    end
                    fprintf(fileID, "export epochs_stage1=%s \n", num2str(config_epochs_stage1));
                    fprintf(fileID, "export epochs_stage2=%s \n", num2str(app.config_vxm_epochs));
                    fprintf(fileID, "export model_saving_step_s1=%s \n", num2str(model_saving_step_s1));
                    fprintf(fileID, "export model_saving_step_s2=%s \n", num2str(model_saving_step_s2));

                    % connect 2 stages of training
                    fprintf(fileID, "export lastModel_stage1='%s' \n", fullfile(app.dir_vxm_model,...
                        strcat(num2str(config_epochs_stage1, '%06d'), '.h5')));
                    fprintf(fileID, "export lastTemplate_stage1='%s' \n", fullfile(app.dir_vxm_model,...
                        strcat('template_epoch', num2str(config_epochs_stage1, '%06d'), '.nii.gz')));

                    % [Transfer learning or deployment]
                    if ~app.opt_vxm_training_scratch
                        fprintf(fileID, "export initModelWeights='%s' \n", fullfile(app.dir_vxm_model, "init_model.h5"));
                        fprintf(fileID, "export initTemplate='%s' \n", fullfile(app.dir_vxm_model, "init_template.nii.gz"));
                    end

                    % step per epoch
                    fprintf(fileID, "export steps_per_epoch=%s \n", num2str(round(length(list_vxm_data_imgTr)/app.config_vxm_batchSize)));
                    fprintf(fileID, "\n");

                    % set: log files for 2 stages
                    fprintf(fileID, "# [Logging] \n");
                    fprintf(fileID, "export log_file_stage1='%s' \n", path_vxm_log_training_s1);
                    fprintf(fileID, "export log_file_stage2='%s' \n", path_vxm_log_training_s2);
                    fprintf(fileID, "\n");
                    fprintf(fileID, "\n");

                    % call train_tempLearnModel.py
                    if app.opt_vxm_training_scratch
                        % [*] training from scratch: stage 1: with learnable template and network
                        fprintf(fileID, "# training from scratch: stage 1: with learnable template and network \n");
                        cmd = sprintf(['CUDA_VISIBLE_DEVICES=$gpuIDs ',...
                            '$dir_scripts/train_tempLearnModel.py ',...
                            '--enc %s ',...
                            '--dec %s ',...
                            '--mean-loss-weight "$weight_meanLoss" ',...
                            '--grad-loss-weight "$weight_gradLoss" ',...
                            '--image-loss "$imgLoss_stage1" ',...
                            '--imgVoxelSize %s ',...
                            '--img-list "$img_list" ',...
                            '--img-prefix "$img_prefix" ',...
                            '--img-suffix "$img_suffix" ',...
                            '--model-dir "$dir_model" ',...
                            '--batch-size "$batch_size" ',...
                            '--epochs "$epochs_stage1" ',...
                            '--steps-per-epoch "$steps_per_epoch" ',...
                            '--model_saving_step "$model_saving_step_s1" ',...
                            '> $log_file_stage1 2>&1'],...
                            app.config_vxm_encoderArchi,...
                            app.config_vxm_decoderArchi,...
                            num2str(tmp_img1_voxSize));
                        fprintf(fileID, cmd);
                        fprintf(fileID, "\n");
                        % [*] training from scratch: stage 2: with frozen template and learnable network
                        fprintf(fileID, "# training from scratch: stage 2: with frozen template and learnable network \n");
                        cmd = sprintf(['CUDA_VISIBLE_DEVICES=$gpuIDs ',...
                            '$dir_scripts/train_tempLearnModel.py --freezeTemp ',...
                            '--enc %s ',...
                            '--dec %s ',...
                            '--mean-loss-weight "$weight_meanLoss" ',...
                            '--grad-loss-weight "$weight_gradLoss" ',...
                            '--image-loss "$imgLoss_stage2" ',...
                            '--imgVoxelSize %s ',...
                            '--img-list "$img_list" ',...
                            '--img-prefix "$img_prefix" ',...
                            '--img-suffix "$img_suffix" ',...
                            '--init-template "$lastTemplate_stage1" ',...
                            '--model-dir "$dir_model" ',...
                            '--batch-size "$batch_size" ',...
                            '--epochs "$epochs_stage2" ',...
                            '--steps-per-epoch "$steps_per_epoch" ',...
                            '--model_saving_step "$model_saving_step_s2" ',...
                            '--load-weights "$lastModel_stage1" ',...
                            '--initial-epoch "$epochs_stage1" ',...
                            '>> $log_file_stage2 2>&1'],...
                            app.config_vxm_encoderArchi,...
                            app.config_vxm_decoderArchi,...
                            num2str(tmp_img1_voxSize));
                        fprintf(fileID, cmd);
                        fprintf(fileID, "\n");
                    else
                        if app.opt_vxm_freezeTemp
                            % [*] transfer learning: stage 1: with frozen template and learnable network
                            fprintf(fileID, "# transfer learning: stage 1: with frozen template and learnable network \n");
                            cmd = sprintf(['CUDA_VISIBLE_DEVICES=$gpuIDs ',...
                                '$dir_scripts/train_tempLearnModel.py --freezeTemp ',...
                                '--init-template "$initTemplate" ',...
                                '--load-weights "$initModelWeights" ',...
                                '--initial-epoch 0 ',...
                                '--enc %s ',...
                                '--dec %s ',...
                                '--mean-loss-weight "$weight_meanLoss" ',...
                                '--grad-loss-weight "$weight_gradLoss" ',...
                                '--image-loss "$imgLoss_stage1" ',...
                                '--imgVoxelSize %s ',...
                                '--img-list "$img_list" ',...
                                '--img-prefix "$img_prefix" ',...
                                '--img-suffix "$img_suffix" ',...
                                '--model-dir "$dir_model" ',...
                                '--batch-size "$batch_size" ',...
                                '--epochs "$epochs_stage1" ',...
                                '--steps-per-epoch "$steps_per_epoch" ',...
                                '--model_saving_step "$model_saving_step_s1" ',...
                                '> $log_file_stage1 2>&1'],...
                                app.config_vxm_encoderArchi,...
                                app.config_vxm_decoderArchi,...
                                num2str(tmp_img1_voxSize));
                            fprintf(fileID, cmd);
                            fprintf(fileID, "\n");
                            % [*] transfer learning: stage 2: with frozen template and learnable network
                            fprintf(fileID, "# transfer learning: stage 2: with frozen template and learnable network \n");
                            cmd = sprintf(['CUDA_VISIBLE_DEVICES=$gpuIDs ',...
                                '$dir_scripts/train_tempLearnModel.py --freezeTemp ',...
                                '--init-template "$lastTemplate_stage1" ',...
                                '--load-weights "$lastModel_stage1" ',...
                                '--initial-epoch "$epochs_stage1" ',...
                                '--enc %s ',...
                                '--dec %s ',...
                                '--mean-loss-weight "$weight_meanLoss" ',...
                                '--grad-loss-weight "$weight_gradLoss" ',...
                                '--image-loss "$imgLoss_stage2" ',...
                                '--imgVoxelSize %s ',...
                                '--img-list "$img_list" ',...
                                '--img-prefix "$img_prefix" ',...
                                '--img-suffix "$img_suffix" ',...
                                '--model-dir "$dir_model" ',...
                                '--batch-size "$batch_size" ',...
                                '--epochs "$epochs_stage2" ',...
                                '--steps-per-epoch "$steps_per_epoch" ',...
                                '--model_saving_step "$model_saving_step_s2" ',...
                                '>> $log_file_stage2 2>&1'],...
                                app.config_vxm_encoderArchi,...
                                app.config_vxm_decoderArchi,...
                                num2str(tmp_img1_voxSize));
                            fprintf(fileID, cmd);
                            fprintf(fileID, "\n");
                        else
                            % [*] transfer learning: stage 1: with learnable template and network
                            fprintf(fileID, "# transfer learning: stage 1: with learnable template and network \n");
                            cmd = sprintf(['CUDA_VISIBLE_DEVICES=$gpuIDs ',...
                                '$dir_scripts/train_tempLearnModel.py ',...
                                '--init-template "$initTemplate" ',...
                                '--load-weights "$initModelWeights" ',...
                                '--initial-epoch 0 ',...
                                '--enc %s ',...
                                '--dec %s ',...
                                '--mean-loss-weight "$weight_meanLoss" ',...
                                '--grad-loss-weight "$weight_gradLoss" ',...
                                '--image-loss "$imgLoss_stage1" ',...
                                '--imgVoxelSize %s ',...
                                '--img-list "$img_list" ',...
                                '--img-prefix "$img_prefix" ',...
                                '--img-suffix "$img_suffix" ',...
                                '--model-dir "$dir_model" ',...
                                '--batch-size "$batch_size" ',...
                                '--epochs "$epochs_stage1" ',...
                                '--steps-per-epoch "$steps_per_epoch" ',...
                                '--model_saving_step "$model_saving_step_s1" ',...
                                '> $log_file_stage1 2>&1'],...
                                app.config_vxm_encoderArchi,...
                                app.config_vxm_decoderArchi,...
                                num2str(tmp_img1_voxSize));
                            fprintf(fileID, cmd);
                            fprintf(fileID, "\n");
                            % [*] transfer learning: stage 2: with frozen template and learnable network
                            fprintf(fileID, "# transfer learning: stage 2: with frozen template and learnable network \n");
                            cmd = sprintf(['CUDA_VISIBLE_DEVICES=$gpuIDs ',...
                                '$dir_scripts/train_tempLearnModel.py --freezeTemp ',...
                                '--init-template "$lastTemplate_stage1" ',...
                                '--load-weights "$lastModel_stage1" ',...
                                '--initial-epoch "$epochs_stage1" ',...
                                '--enc %s ',...
                                '--dec %s ',...
                                '--mean-loss-weight "$weight_meanLoss" ',...
                                '--grad-loss-weight "$weight_gradLoss" ',...
                                '--image-loss "$imgLoss_stage2" ',...
                                '--imgVoxelSize %s ',...
                                '--img-list "$img_list" ',...
                                '--img-prefix "$img_prefix" ',...
                                '--img-suffix "$img_suffix" ',...
                                '--model-dir "$dir_model" ',...
                                '--batch-size "$batch_size" ',...
                                '--epochs "$epochs_stage2" ',...
                                '--steps-per-epoch "$steps_per_epoch" ',...
                                '--model_saving_step "$model_saving_step_s2" ',...
                                '>> $log_file_stage2 2>&1'],...
                                app.config_vxm_encoderArchi,...
                                app.config_vxm_decoderArchi,...
                                num2str(tmp_img1_voxSize));
                            fprintf(fileID, cmd);
                            fprintf(fileID, "\n");
                        end
                    end
                    fclose(fileID);
                    % --------------------------------------

                    % wait for the segmentation model training when two models use the same GPU for training
                    if app.opt_nnUNet_training_begin && strcmp(app.config_vxm_GPU, app.config_nnUNet_GPU)
                        while ~strcmp(app.container_jobs.job_nnUNet_train.State, "finished")
                            pause(60);
                            % internal checkpoint for the pause/resume and stop function
                            CMT_checkPauseStopStatus(app);
                        end
                        app.container_jobStatus.job_nnUNet_train = "finished";
                    end

                    % add execution priority
                    path_CMvxm_script = fullfile(dir_CMvxm_scripts, 'train_tempLearnModel.py');
                    cmd = strcat("chmod u+x ", path_CMvxm_script);
                    system(cmd);

                    % run "training_xxxx.sh" on a matlab worker
                    cmd = strcat("chmod u+x ", path_vxm_bash_training);
                    system(cmd);
                    job_vxm_train = batch(@CartiMorphToolbox.CMT_runBatchJob, 0, {path_vxm_bash_training});
                    app.container_jobs.job_vxm_train = job_vxm_train;
                    app.container_jobStatus.job_vxm_train = "running";

                    % internal checkpoint for the pause/resume and stop function
                    CMT_checkPauseStopStatus(app);
                end
                % ------------------------------------------------------------


                % wait until the model training finishs
                while ~strcmp(app.container_jobs.job_vxm_train.State, "finished")
                    pause(60)
                    % internal checkpoint for the pause/resume and stop function
                    CMT_checkPauseStopStatus(app);
                end
                app.container_jobStatus.job_vxm_train = "finished";


                % rename the final model file
                tmp_finalModel = fullfile(app.dir_vxm_model, strcat(num2str(app.config_vxm_epochs, '%06d'), '.h5'));
                path_vxm_finalModel = fullfile(app.dir_vxm_model, "bestModel.h5");
                copyfile(tmp_finalModel, path_vxm_finalModel);


                % ------------------------------------------------------------
                % [3] Construct Segmentation Mask for the Learned Template (VoxelMorph)
                % ------------------------------------------------------------
                if ~app.opt_vxm_freezeTemp
                    % move template image to data folder
                    tmp_template = fullfile(app.dir_vxm_model, strcat('template_epoch', num2str(app.config_vxm_epochs, '%06d'), '.nii.gz'));
                    path_vxm_template = fullfile(app.dir_vxm_data_Template, "template.nii.gz");
                    copyfile(tmp_template, path_vxm_template);

                    % write "inference_getTempSeg.sh"
                    path_vxm_bash_inference_getTempSeg = fullfile(app.dir_vxm_autoScript, 'inference_getTempSeg.sh');
                    fileID = fopen(path_vxm_bash_inference_getTempSeg, 'w');
                    fprintf(fileID, '# activate Conda environment \n');
                    % fprintf(fileID, "source %s \n", app.path_conda_src);
                    fprintf(fileID, 'eval "$(conda shell.bash hook)" \n');
                    fprintf(fileID, 'conda activate CartiMorphToolbox-Vxm \n');
                    fprintf(fileID, '\n');
                    fprintf(fileID, 'export LD_LIBRARY_PATH="%s:$LD_LIBRARY_PATH" \n', app.path_CUDA_lib_Vxm);
                    fprintf(fileID, "\n");
                    fprintf(fileID, 'export file_targetTemp="%s" \n', path_vxm_template);
                    fprintf(fileID, 'export dir_sourceImgs="%s" \n', app.dir_vxm_data_prepImgTr);
                    fprintf(fileID, 'export dir_sourceLabels="%s" \n', app.dir_vxm_data_prepLabelTr);
                    fprintf(fileID, 'export dir_warpedLabels="%s" \n', app.dir_vxm_inference_warpedLabels_Tr);
                    fprintf(fileID, "export file_model='%s' \n", path_vxm_finalModel);
                    fprintf(fileID, "export dir_scripts='%s' \n", dir_CMvxm_scripts);
                    fprintf(fileID, "export gpuIDs=%s \n", app.config_vxm_GPU);
                    fprintf(fileID, '\n');
                    fprintf(fileID, '# ====== registration ========================= \n');
                    fprintf(fileID, "for img in $dir_sourceImgs/*.nii.gz \n");
                    fprintf(fileID, 'do \n');
                    fprintf(fileID, '    export file_sourceImg="$dir_sourceImgs/${img##*/}" \n');
                    fprintf(fileID, '    export file_sourceLabel="$dir_sourceLabels/${img##*/}" \n');
                    fprintf(fileID, '    export file_warpedLabel="$dir_warpedLabels/${img##*/}" \n');
                    fprintf(fileID, '    "$dir_scripts"/inference_img2temp_warpLabel.py --file_targetTemp "$file_targetTemp" --file_sourceImg "$file_sourceImg" --file_sourceLabel "$file_sourceLabel" --file_warpedLabel "$file_warpedLabel" --file_model "$file_model" --gpuIDs "$gpuIDs" \n');
                    fprintf(fileID, 'done \n');
                    fprintf(fileID, '# ====== registration ========================= \n');
                    fclose(fileID);

                    % add execution priority
                    path_CMvxm_script = fullfile(dir_CMvxm_scripts, 'inference_img2temp_warpLabel.py');
                    cmd = strcat("chmod u+x ", path_CMvxm_script);
                    system(cmd);

                    % run "inference_getTempSeg.sh"
                    cmd = strcat("chmod u+x ", path_vxm_bash_inference_getTempSeg);
                    system(cmd);
                    job_vxm_inference_getTempSeg = batch(@CartiMorphToolbox.CMT_runBatchJob, 0, {path_vxm_bash_inference_getTempSeg});
                    app.container_jobs.job_vxm_inference_getTempSeg = job_vxm_inference_getTempSeg;
                    app.container_jobStatus.job_vxm_inference_getTempSeg = "running";

                    % wait until the model inference finishs
                    while ~strcmp(app.container_jobs.job_vxm_inference_getTempSeg.State, "finished")
                        pause(60)
                        % internal checkpoint for the pause/resume and stop function
                        CMT_checkPauseStopStatus(app);
                    end
                    app.container_jobStatus.job_vxm_inference_getTempSeg = "finished";

                    % construct probability map
                    tmp = [dir(fullfile(app.dir_vxm_inference_warpedLabels_Tr, "*.nii.gz"));...
                        dir(fullfile(app.dir_vxm_inference_warpedLabels_Tr, "*.nii"))];
                    vxm_list_warpedLabel = {tmp.name}';
                    vxm_tempProbMap = zeros(cat(2, app.config_vxm_imgSize_out, length(app.config_ROI_label)), "double");
                    for j=1:length(vxm_list_warpedLabel)
                        j_warpedLabel = niftiread(fullfile(app.dir_vxm_inference_warpedLabels_Tr, vxm_list_warpedLabel{j}));
                        for k=1:length(app.config_ROI_label)
                            vxm_tempProbMap(:,:,:,k) = vxm_tempProbMap(:,:,:,k) + double(j_warpedLabel==k-1);
                        end
                        % internal checkpoint for the pause/resume and stop function
                        CMT_checkPauseStopStatus(app);
                    end
                    vxm_tempProbMap = vxm_tempProbMap ./ length(vxm_list_warpedLabel);

                    % save the segmentation mask for the template
                    [~, vxm_tempSeg] = max(vxm_tempProbMap, [], 4);
                    vxm_tempSeg = uint8(vxm_tempSeg-1);
                    vxm_tempInfo = niftiinfo(path_vxm_template);
                    vxm_tempSegInfo = vxm_tempInfo;
                    vxm_tempSegInfo.Description = char("segmentation mask for the learned template");
                    vxm_tempSegInfo.Datatype = class(vxm_tempSeg);
                    vxm_tempSegInfo.Filemoddate = regexprep(char(datetime), {' ', ':'}, '_');
                    vxm_tempSegInfo.Filename = fullfile(app.dir_vxm_data_Template, "templateSeg");
                    vxm_tempSegInfo.Filesize = [];
                    niftiwrite(vxm_tempSeg, vxm_tempSegInfo.Filename, vxm_tempSegInfo, "Compressed",true);

                    % internal checkpoint for the pause/resume and stop function
                    CMT_checkPauseStopStatus(app);
                end
                % ------------------------------------------------------------
            end
        end


        function [ThicknessMap, sThicknessMap] = wrapper_thickness_SN(...
                app, ...
                FV_inter,...
                FV_outer,...
                name_cartilage,...
                size_voxel,...
                depth,...
                HP,...
                dir_Sub,...
                subID,...
                log_fileID)
            % ==============================================================================
            % FUNCTION:
            %     Calculate the thickness using Surface Normal approach.
            %
            % INPUT:
            %     - FV_inter: [structure]
            %          - FV_inter.vertices: the vertices on the interface <<must be the coordinates of voxels>>
            %          - FV_inter.faces: the faces on the interface
            %     - FV_outer: [structure]
            %          - FV_outer.vertices: the vertices on the outer boundary <<must be the coordinates of voxels>>
            %          - FV_outer.faces: the faces on the outer boundary
            %     - name_cartilage: [char] name of cartilage
            %     - size_voxel: [double] the spatial resolution of a voxel <<must be in milimeter>>
            %     - depth: [double] the measuring depth -- human maximum cartilage thickness
            %     - HP: [structure] some hyper-parameters
            %          - HP.neigh_priSN: number of neighbors to estimate surface normal for a voxel
            %          - HP.neigh_flipSN: number of neighbors to flip surface normal for a voxel
            %          - HP.neigh_approSN: number of neighbors whose estimated surface normals are non-zero
            %          - HP.neigh_smoothSN: number of neighbors for spatial smoothing of SN
            %          - HP.neigh_smoothMap: number of neighbors for spatial smoothing of thickness map
            %          - HP.neigh_approThickness: number of neighbors whose estimated thicknesses are non-zero
            %     - dir_Sub: [char] full file path of results folder
            %     - subID: [double] subject ID
            %     - log_fileID: [double] log file ID
            %
            % OUTPUT:
            %      - ThicknessMap: (nv, 4), thickness map
            %      - sThicknessMap: (nv, 4), smoothed thickness map
            % ------------------------------------------------------------------------------
            % Matlab Version: 2019b or later
            %
            % Last updated on: 11-May-2022
            %
            % Author:
            % Yongcheng YAO (yao_yongcheng@link.cuhk.edu.hk)
            % Department of Imaging and Interventional Radiology,
            % Chinese University of Hong Kong (CUHK)
            %
            % Copyright 2020 Yongcheng YAO
            % ------------------------------------------------------------------------------
            % ==============================================================================

            %% Surface normal estimation for voxels in the bone-cartilage interface
            % ------------------------------------------------------------------------------------------
            if ~isempty(log_fileID)
                tic;
                fprintf(log_fileID,append( '    [3.1] Estimating surface normals: #neighbors=', num2str(HP.neigh_priSN), '... '));
            end

            % initial SN estimation
            vers_inter = FV_inter.vertices;
            SN_initial = CartiMorphToolbox.cal_estimateSN(vers_inter, HP.neigh_priSN);

            % internal checkpoint for the pause/resume and stop function
            CMT_checkPauseStopStatus(app);

            if ~isempty(log_fileID)
                time = toc;
                fprintf(log_fileID, append(' finished. (Elapsed time is ', num2str(time), ' seconds). \n'));
            end

            % ------------------------------------------------------------------------------------------
            % Plot voxels and surface normals on it
            % ------------------------------------------------------------------------------------------
            % plot_faces_out = FV_outer.faces;
            % plot_vers_out = FV_outer.vertices;
            % plot_faces_in = FV_inter.faces;
            % plot_vers_in = FV_inter.vertices;
            % plot_SN  = SN_initial .* min(size_voxel);
            %
            % figure
            % trisurf(plot_faces_in, plot_vers_in(:,1), plot_vers_in(:,2), plot_vers_in(:,3),...
            %     'FaceColor', 'r', 'FaceAlpha', 1, 'EdgeAlpha',0.5);
            % hold on
            % grid off
            % axis equal
            % axis off
            % trisurf(plot_faces_out, plot_vers_out(:,1), plot_vers_out(:,2), plot_vers_out(:,3),...
            %     'FaceColor', 'b', 'FaceAlpha', 0.1, 'EdgeAlpha',0);
            % quiver3(plot_vers_in(:,1), plot_vers_in(:,2), plot_vers_in(:,3),...
            %     plot_SN(:,1), plot_SN(:,2), plot_SN(:,3), 1, 'Color', 'b')
            % title('Surface Normal (primary)', 'Interpreter','none');
            % hold off
            % ------------------------------------------------------------------------------------------
            %% -----------------------------------------------------------------------------


            %% Surface normals orientation correction, ensuring the each SN is pointing to the outer boundary
            % ------------------------------------------------------------------------------------------
            if ~isempty(log_fileID)
                tic;
                fprintf(log_fileID, append('    [3.2] SN orientation correction: #neighbors=', num2str(HP.neigh_flipSN), '... '));
            end

            % orientation correction for SN
            vers_outer = FV_outer.vertices;
            SN_reoriented = CMT_cal_reorientSN(...
                app, ...
                SN_initial,...
                vers_inter,...
                vers_outer,...
                size_voxel,...
                HP.neigh_flipSN);

            % internal checkpoint for the pause/resume and stop function
            CMT_checkPauseStopStatus(app);

            if ~isempty(log_fileID)
                time = toc;
                fprintf(log_fileID, append(' finished. (Elapsed time is ', num2str(time), ' seconds). \n'));
            end

            % ------------------------------------------------------------------------------------------
            % Plot voxels and surface normals on it
            % ------------------------------------------------------------------------------------------
            % plot_faces_out = FV_outer.faces;
            % plot_vers_out = FV_outer.vertices;
            % plot_faces_in = FV_inter.faces;
            % plot_vers_in = FV_inter.vertices;
            % plot_SN  = SN_reoriented .* min(size_voxel);
            %
            % figure
            % trisurf(plot_faces_in, plot_vers_in(:,1), plot_vers_in(:,2), plot_vers_in(:,3),...
            %     'FaceColor', 'r', 'FaceAlpha', 1, 'EdgeAlpha',0.5);
            % hold on
            % grid off
            % axis equal
            % axis off
            % trisurf(plot_faces_out, plot_vers_out(:,1), plot_vers_out(:,2), plot_vers_out(:,3),...
            %     'FaceColor', 'b', 'FaceAlpha', 0.1, 'EdgeAlpha',0);
            % quiver3(plot_vers_in(:,1), plot_vers_in(:,2), plot_vers_in(:,3),...
            %     plot_SN(:,1), plot_SN(:,2), plot_SN(:,3), 1, 'Color', '#2ECC71')
            % title('Surface Normal (flipped)', 'Interpreter','none')
            % hold off
            % ------------------------------------------------------------------------------------------
            %% -----------------------------------------------------------------------------


            %% Surface normal approximation: use the mean SN of neighbors if the estimated SN is [0, 0, 0]
            % ------------------------------------------------------------------------------------------
            if ~isempty(log_fileID)
                tic;
                fprintf(log_fileID, append('    [3.3] SN approximation: #neighbors=', num2str(HP.neigh_approSN), '... '));
            end

            % SN approximation
            SN_appro = CartiMorphToolbox.cal_approximateSN(...
                SN_reoriented,...
                vers_inter,...
                HP.neigh_approSN);

            % internal checkpoint for the pause/resume and stop function
            CMT_checkPauseStopStatus(app);

            if ~isempty(log_fileID)
                time = toc;
                fprintf(log_fileID, append(' finished. (Elapsed time is ', num2str(time), ' seconds). \n'));
            end

            % ------------------------------------------------------------------------------------------
            % Plot voxels and surface normals on it
            % ------------------------------------------------------------------------------------------
            % plot_faces_out = FV_outer.faces;
            % plot_vers_out = FV_outer.vertices;
            % plot_faces_in = FV_inter.faces;
            % plot_vers_in = FV_inter.vertices;
            % plot_SN  = SN_appro .* min(size_voxel);
            %
            % figure
            % trisurf(plot_faces_in, plot_vers_in(:,1), plot_vers_in(:,2), plot_vers_in(:,3),...
            %     'FaceColor', 'r', 'FaceAlpha', 1, 'EdgeAlpha',0.5);
            % hold on
            % grid off
            % axis equal
            % axis off
            % trisurf(plot_faces_out, plot_vers_out(:,1), plot_vers_out(:,2), plot_vers_out(:,3),...
            %     'FaceColor', 'b', 'FaceAlpha', 0.1, 'EdgeAlpha',0);
            % quiver3(plot_vers_in(:,1), plot_vers_in(:,2), plot_vers_in(:,3),...
            %     plot_SN(:,1), plot_SN(:,2), plot_SN(:,3), 1, 'Color', '#2ECC71')
            % title('Surface Normal (approximated)', 'Interpreter','none')
            % hold off
            %% -----------------------------------------------------------------------------


            %% Apply spatial smoothing on the estimated SN
            % ------------------------------------------------------------------------------------------
            if ~isempty(log_fileID)
                tic;
                fprintf(log_fileID, append('    [3.4] SN smoothing: #neighbors=', num2str(HP.neigh_smoothSN), '... '));
            end

            % calculate the smoothed SN
            SN_smoothed = CartiMorphToolbox.cal_smoothSN(...
                SN_appro,...
                vers_inter,...
                HP.neigh_smoothSN);

            % internal checkpoint for the pause/resume and stop function
            CMT_checkPauseStopStatus(app);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Optional (begin) %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            dir_SN = fullfile(dir_Sub, 'SurfaceNormal');
            if ~isfolder(dir_SN)
                mkdir(dir_SN);
            end
            dir_SN_figure = fullfile(dir_Sub, 'SurfaceNormal_Figure');
            if ~isfolder(dir_SN_figure)
                mkdir(dir_SN_figure);
            end

            % visuzalize the smoothed surface normals
            plot_faces_out = FV_outer.faces;
            plot_vers_out = FV_outer.vertices;
            plot_faces_in = FV_inter.faces;
            plot_vers_in = FV_inter.vertices;
            plot_SN  = SN_smoothed .* min(size_voxel);
            f1 = figure('visible', 'off');

            % <<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<
            % [!] DO NOT change the order of the following figure components, this will produce errors in CartiMorph Viewer
            trisurf(plot_faces_in, plot_vers_in(:,1), plot_vers_in(:,2), plot_vers_in(:,3),...
                'FaceColor', '#FF5251', 'FaceAlpha', 1, 'EdgeAlpha',0.5);
            hold on
            grid off
            axis equal
            axis off
            trisurf(plot_faces_out, plot_vers_out(:,1), plot_vers_out(:,2), plot_vers_out(:,3),...
                'FaceColor', 'b', 'FaceAlpha', 0.1, 'EdgeAlpha',0);
            quiver3(plot_vers_in(:,1), plot_vers_in(:,2), plot_vers_in(:,3),...
                plot_SN(:,1), plot_SN(:,2), plot_SN(:,3), 1, 'Color', '#2ECC71')
            % <<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<

            title('Surface Normal', 'Interpreter','none')
            hold off
            file_Name = append(subID, '_SN_', name_cartilage, '.fig');
            filePath = fullfile(dir_SN_figure, file_Name);
            savefig(f1, filePath, 'compact');
            delete(f1);

            % save the smoothed surface normals
            fileName = append(subID, '_SN_', name_cartilage, '.mat');
            filePath = fullfile(dir_SN, fileName);
            save(string(filePath), 'SN_smoothed');
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Optional (end) %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            % internal checkpoint for the pause/resume and stop function
            CMT_checkPauseStopStatus(app);

            if ~isempty(log_fileID)
                time = toc;
                fprintf(log_fileID, append(' finished. (Elapsed time is ', num2str(time), ' seconds). \n'));
            end

            % ------------------------------------------------------------------------------------------
            % Plot voxels and smoothed surface normals on it
            % ------------------------------------------------------------------------------------------
            % plot_faces_out = FV_outer.faces;
            % plot_vers_out = FV_outer.vertices;
            % plot_faces_in = FV_inter.faces;
            % plot_vers_in = FV_inter.vertices;
            % plot_SN  = SN_smoothed .* min(size_voxel);
            %
            % figure
            % trisurf(plot_faces_in, plot_vers_in(:,1), plot_vers_in(:,2), plot_vers_in(:,3),...
            %     'FaceColor', 'r', 'FaceAlpha', 1, 'EdgeAlpha',0.5);
            % hold on
            % grid off
            % axis equal
            % axis off
            % trisurf(plot_faces_out, plot_vers_out(:,1), plot_vers_out(:,2), plot_vers_out(:,3),...
            %     'FaceColor', 'b', 'FaceAlpha', 0.1, 'EdgeAlpha',0);
            % quiver3(plot_vers_in(:,1), plot_vers_in(:,2), plot_vers_in(:,3),...
            %     plot_SN(:,1), plot_SN(:,2), plot_SN(:,3), 1, 'Color', '#2ECC71')
            % title('Surface Normal (smoothed)', 'Interpreter','none')
            % hold off
            % ------------------------------------------------------------------------------------------
            %% -----------------------------------------------------------------------------


            %% Calculate the Thickness using Surface Normal approach (primary)
            % ------------------------------------------------------------------------------------------
            if ~isempty(log_fileID)
                tic;
                fprintf(log_fileID, '    [3.5] Cartilage thickness estimation... ');
            end

            % initial thickness map
            ThicknessMap_initial = CartiMorphToolbox.cal_thicknessMap_SN(...
                vers_inter,...
                SN_smoothed,...
                FV_outer,...
                depth);

            % internal checkpoint for the pause/resume and stop function
            CMT_checkPauseStopStatus(app);

            % remove estimated thickness values larger than the pre-defined maximum thickness
            idx_zeroThickness = ThicknessMap_initial(:, 4) > depth;
            if sum(idx_zeroThickness)>0
                ThicknessMap_initial(idx_zeroThickness, 4) = 0;
            end

            % thickness values approximation: use the mean thickness of neighbors if the
            % estimated value is 0
            ThicknessMap_initial(:, 4) = CartiMorphToolbox.cal_approximateThickness( ...
                ThicknessMap_initial(:, 4), ...
                vers_inter, ...
                HP.neigh_approThickness);

            % internal checkpoint for the pause/resume and stop function
            CMT_checkPauseStopStatus(app);

            if ~isempty(log_fileID)
                time = toc;
                fprintf(log_fileID, append(' finished. (Elapsed time is ', num2str(time), ' seconds). \n'));
            end
            %% -----------------------------------------------------------------------------


            %% Add uncertainty to the estimated thickness map
            % ------------------------------------------------------------------------------------------
            if ~isempty(log_fileID)
                tic;
                fprintf(log_fileID, '    [3.6] Uncertainty estimation & generating the thickness maps... ');
            end

            % estimate unvertainty at each location
            UncertaintyMap = CartiMorphToolbox.cal_uncertainty(SN_smoothed, vers_inter, size_voxel, 'ellipsoid');

            % ThicknessMap: add twofold uncertainty to all locations
            ThicknessMap = ThicknessMap_initial;
            ThicknessMap(:, 4) = ThicknessMap(:, 4) + (2 * UncertaintyMap(:, 4));

            % internal checkpoint for the pause/resume and stop function
            CMT_checkPauseStopStatus(app);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Optional (begin) %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % % save UncertaintyMap
            % f2 = figure('visible', 'on');
            % scatter3(UncertaintyMap(:,1), UncertaintyMap(:,2), UncertaintyMap(:,3), 10, UncertaintyMap(:,4), 'filled');
            % colormap(jet);
            % colorbar;
            % cmax = ceil(max(UncertaintyMap(:,4)));
            % caxis([0, cmax]);
            % axis equal;
            % xlabel('x');
            % ylabel('y');
            % zlabel('z');
            % view(3);
            % grid off;
            % title('Uncertainty Map', 'Interpreter','none');
            %
            % file_Name = append(subID, '_UncertaintyMap_', name_cartilage, '.fig');
            % filePath = fullfile(dir_Sub, file_Name);
            % savefig(f2, filePath, 'compact');
            % delete(f2);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Optional (begin) %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            if ~isempty(log_fileID)
                time = toc;
                fprintf(log_fileID, append(' finished. (Elapsed time is ', num2str(time), ' seconds). \n'));
            end
            %% -----------------------------------------------------------------------------


            %% Apply spatial smoothing on the thickness map
            % ------------------------------------------------------------------------------------------
            if ~isempty(log_fileID)
                tic;
                fprintf(log_fileID, append('    [3.7] Spatial smoothing for thickness map: #neighbors=', num2str(HP.neigh_smoothMap), '...'));
            end

            sThicknessMap = CartiMorphToolbox.cal_smoothThicknessMap(...
                ThicknessMap,...
                vers_inter,...
                HP.neigh_smoothMap);

            % internal checkpoint for the pause/resume and stop function
            CMT_checkPauseStopStatus(app);

            if ~isempty(log_fileID)
                time = toc;
                fprintf(log_fileID, append(' finished. (Elapsed time is ', num2str(time), ' seconds). \n'));
            end
            %% -----------------------------------------------------------------------------

        end


        function CMT_nnUNet_train_finished(app)
            % disable training step
            app.opt_nnUNet_training = false;
            app.opt_nnUNet_training_begin = false;

            % finish segmentation model training
            if app.CheckBox_iii2.Value
                % light the lamp
                app.Lamp_iii2.Color = [0.3804, 0.7333, 0.2745];
                app.Lamp_iii2_finetune.Color = [0.3804, 0.7333, 0.2745];
                app.Lamp_iii2_finetuning.Color = [0.3804, 0.7333, 0.2745];
                % freeze
                app.CheckBox_iii2_finetuning.Enable = "off";
                app.EditField_iii2_GPU.Editable = "off";
                app.EditField_iii2_epoch.Editable = "off";
            else
                % light the lamp
                app.Lamp_iii.Color = [0.3804, 0.7333, 0.2745];
                app.Lamp_iii_train.Color = [0.3804, 0.7333, 0.2745];
                % freeze
                app.CheckBox_iii_train.Enable = "off";
                app.EditField_iii_GPU.Editable = "off";
                app.Button_iii_scratch.Enable = "off";
                app.Button_iii_continue.Enable = "off";
                app.EditField_iii_epoch.Editable = "off";
            end

            % autosave configuration
            CMT_autosaveConfig(app, 'config_latest');
            CMT_autosaveConfig(app, 'config_iii');

            % internal checkpoint for the pause/resume and stop function
            CMT_checkPauseStopStatus(app);
        end


        function CMT_vxm_train_finished(app)
            app.opt_vxm_training = false;
            app.opt_vxm_training_begin = false;

            if app.CheckBox_iv2.Value
                % light the lamp
                app.Lamp_iv2.Color = [0.3804, 0.7333, 0.2745];
                app.Lamp_iv2_finetune.Color = [0.3804, 0.7333, 0.2745];
                app.Lamp_iv2_finetuning.Color = [0.3804, 0.7333, 0.2745];
                % freeze
                app.CheckBox_iv2_finetuning.Enable = "off";
                app.EditField_iv2_GPU.Editable = "off";
                app.EditField_iv2_epoch.Editable = "off";
                app.Button_MSE_iv2.Enable = "off";
                app.Button_LNCC_iv2.Enable = "off";
                app.Button_MSE_LNCC_iv2.Enable = "off";
                % disable the reset button
                app.Button_iv2_reset.Enable = "off";
                app.Button_iv2_reset.Visible = "off";
            else
                % light the lamp
                app.Lamp_iv.Color = [0.3804, 0.7333, 0.2745];
                app.Lamp_iv_train.Color = [0.3804, 0.7333, 0.2745];
                % freeze
                app.CheckBox_iv_train.Enable = "off";
                app.EditField_iv_GPU.Editable = "off";
                app.EditField_iv_epoch.Editable = "off";
                app.Button_MSE_iv2.Enable = "off";
                app.Button_LNCC_iv2.Enable = "off";
                app.Button_MSE_LNCC_iv2.Enable = "off";
            end

            % autosave configuration
            CMT_autosaveConfig(app, 'config_latest');

            % internal checkpoint for the pause/resume and stop function
            CMT_checkPauseStopStatus(app);

        end


        function CMT_wait4nnUNet_training(app)
            % wait until the model training finishs
            while ~strcmp(app.container_jobs.job_nnUNet_train.State, "finished")
                pause(60)
                % internal checkpoint for the pause/resume and stop function
                CMT_checkPauseStopStatus(app);
            end
            % [important] set status for nnUNet training
            app.container_jobStatus.job_nnUNet_train = "finished";
        end


        function CMT_nnUNet_inference(app)
            if app.opt_nnUNet_inference
                % ----------------------------------------------
                % [1] inference set for model deployment
                % ----------------------------------------------
                % write "inference_3dF_inferenceSet.sh"
                path_nnUNet_bash_inference_inferenceSet = fullfile(app.dir_nnUNet_autoScript, "inference_3dF_inferenceSet.sh");
                path_nnUNet_log_inference_inferenceSet = fullfile(app.dir_nnUNet_log, 'inference_3dF_inferenceSet.log');

                fileID = fopen(path_nnUNet_bash_inference_inferenceSet, 'w');
                fprintf(fileID, '# activate Conda environment \n');
                % fprintf(fileID, "source %s \n", app.path_conda_src);
                fprintf(fileID, 'eval "$(conda shell.bash hook)" \n');
                fprintf(fileID, 'conda activate CartiMorphToolbox-nnUNet \n');
                fprintf(fileID, '\n');
                fprintf(fileID, 'export LD_LIBRARY_PATH="%s:$LD_LIBRARY_PATH" \n', app.path_CUDA_lib_nnUNet);
                fprintf(fileID, " \n");
                fprintf(fileID, 'export MKL_NUM_THREADS=6 \n');
                fprintf(fileID, " \n");
                fprintf(fileID, "export gpuIDs='%s' \n", app.config_inference_GPU);
                fprintf(fileID, "export nnUNet_prediction_in='%s' \n", app.dir_nnUNet_raw_imgInference);
                fprintf(fileID, "export nnUNet_prediction_out='%s' \n", app.dir_nnUNet_prediction_inferenceSet);
                fprintf(fileID, "export nnUNet_fold='%s' \n", app.config_nnUNet_fold);
                fprintf(fileID, "export nnUNet_archi='%s' \n", app.config_nnUNet_model_archi);
                fprintf(fileID, "export nnUNet_taskName='%s' \n", app.config_nnUNet_taskIDName);
                if app.CheckBox_iii.Value
                    plans_identifier = "nnUNetPlansv2.1";
                else
                    plans_identifier = strcat("nnUNetPlans_pretrained_", app.config_nnUNet_loadedPlans_identifier);
                end
                fprintf(fileID, "export nnUNet_plans_identifier='%s' \n", plans_identifier);
                fprintf(fileID, " \n");
                fprintf(fileID, "export nnUNet_raw_data_base='%s' \n", app.dir_nnUNet_rawBase);
                fprintf(fileID, "export nnUNet_preprocessed='%s' \n", app.dir_nnUNet_preprocess);
                fprintf(fileID, "export RESULTS_FOLDER='%s' \n", app.dir_nnUNet_model);
                fprintf(fileID, " \n");
                fprintf(fileID, "export log_file='%s' \n", path_nnUNet_log_inference_inferenceSet);
                fprintf(fileID, " \n");
                fprintf(fileID, 'CUDA_VISIBLE_DEVICES=$gpuIDs CartiMorph_nnUNet_predict -i "$nnUNet_prediction_in" -o "$nnUNet_prediction_out" -f "$nnUNet_fold" -tr nnUNetTrainerV2 -m "$nnUNet_archi" -p "$nnUNet_plans_identifier" -t "$nnUNet_taskName" > "$log_file" 2>&1 \n');
                fclose(fileID);

                % run "inference_3dF_inferenceSet.sh"
                cmd = strcat("chmod u+x ", path_nnUNet_bash_inference_inferenceSet);
                system(cmd);
                cmd = strcat("bash ", path_nnUNet_bash_inference_inferenceSet);
                system(cmd);

                % internal checkpoint for the pause/resume and stop function
                CMT_checkPauseStopStatus(app);
                % ----------------------------------------------

                % ----------------------------------------------
                % [finish model inference]
                % ----------------------------------------------
                % disable this step
                app.opt_nnUNet_inference = false;
                % light the lamp
                app.Lamp_v.Color = [0.3804, 0.7333, 0.2745];
                % freeze v
                app.CheckBox_v.Enable = "off";
                app.EditField_v_GPU.Editable = "off";
                % disable the reset button
                app.Button_iii2_reset.Enable = "off";
                app.Button_iii2_reset.Visible = "off";

                % autosave configuration
                CMT_autosaveConfig(app, 'config_latest');
                CMT_autosaveConfig(app, 'config_v');

                % internal checkpoint for the pause/resume and stop function
                CMT_checkPauseStopStatus(app);
                % ----------------------------------------------
            end
        end


        function CMT_deploySegModel(app)
            % [fill in the raw image information that match the segmentation model]
            % get info
            tmp = load(fullfile(app.dir_nnUNet_pretrained_model, "segModelConfig.mat"));
            app.config_ROI_name = tmp.segModelConfig.config_ROI_name;
            app.config_ROI_label = tmp.segModelConfig.config_ROI_label;
            app.config_nnUNet_imgSize = tmp.segModelConfig.config_nnUNet_imgSize;
            app.config_nnUNet_loadedPlans_identifier = tmp.segModelConfig.config_nnUNet_loadedPlans_identifier;
            app.config_nnUNet_model_archi = tmp.segModelConfig.config_nnUNet_model_archi;
            % segmentation labels
            app.EditField_Femur.Value = app.config_ROI_label{strcmp(app.config_ROI_name, 'femur')};
            app.EditField_FemoralCartilage.Value = app.config_ROI_label{strcmp(app.config_ROI_name, 'femoral cartilage')};
            app.EditField_Tibia.Value = app.config_ROI_label{strcmp(app.config_ROI_name, 'tibia')};
            app.EditField_TibialCartilagemedial.Value = app.config_ROI_label{strcmp(app.config_ROI_name, 'medial tibial cartilage')};
            app.EditField_TibialCartilagelateral.Value = app.config_ROI_label{strcmp(app.config_ROI_name, 'lateral tibial cartilage')};
            app.EditField_background.Value = app.config_ROI_label{strcmp(app.config_ROI_name, 'background')};
            % image size
            app.EditField_ii_imgSizeX.Value = num2str(app.config_nnUNet_imgSize(1,1));
            app.EditField_ii_imgSizeY.Value = num2str(app.config_nnUNet_imgSize(1,2));
            app.EditField_ii_imgSizeZ.Value = num2str(app.config_nnUNet_imgSize(1,3));

            % copy model files
            plans_identifier = strcat("nnUNetPlans_pretrained_", app.config_nnUNet_loadedPlans_identifier);
            dir_model = fullfile(app.dir_nnUNet_model, "nnUNet", app.config_nnUNet_model_archi, app.config_nnUNet_taskIDName,...
                strcat("nnUNetTrainerV2__", plans_identifier), "all");
            dir_model2 = fullfile(app.dir_nnUNet_model, "nnUNet", app.config_nnUNet_model_archi, app.config_nnUNet_taskIDName,...
                strcat("nnUNetTrainerV2__", plans_identifier));
            if ~isfolder(dir_model)
                mkdir(dir_model);
            end
            % source path
            path_src_model = fullfile(app.dir_nnUNet_pretrained_model, "segModel.model");
            path_src_model_pkl = fullfile(app.dir_nnUNet_pretrained_model, "segModel.model.pkl");
            path_src_pp = fullfile(app.dir_nnUNet_pretrained_model, "postprocessing.json");
            path_src_plans = fullfile(app.dir_nnUNet_pretrained_model, "plans.pkl");
            % target path
            path_tar_model = fullfile(dir_model, "model_final_checkpoint.model");
            path_tar_model_pkl = fullfile(dir_model, "model_final_checkpoint.model.pkl");
            path_tar_pp = fullfile(dir_model, "postprocessing.json");
            path_tar_pp2 = fullfile(dir_model2, "postprocessing.json");
            path_tar_plans = fullfile(dir_model2, "plans.pkl");
            % copy files
            copyfile(path_src_model, path_tar_model);
            copyfile(path_src_model_pkl, path_tar_model_pkl);
            copyfile(path_src_pp, path_tar_pp);
            copyfile(path_src_pp, path_tar_pp2);
            copyfile(path_src_plans, path_tar_plans);

            % freeze the fields
            app.EditField_Femur.Enable = "off";
            app.EditField_FemoralCartilage.Enable = "off";
            app.EditField_Tibia.Enable = "off";
            app.EditField_TibialCartilagemedial.Enable = "off";
            app.EditField_TibialCartilagelateral.Enable = "off";
            app.EditField_background.Enable = "off";

            if app.CheckBox_iii2_deploy.Value
                % [when fine-tuning is off]
                % light the lamp
                app.Lamp_iii2_deploy.Color = [0.3804, 0.7333, 0.2745];
                app.Lamp_iii2.Color = [0.3804, 0.7333, 0.2745];
                % iii2
                app.CheckBox_iii2.Enable = "off";
                % iii2-deploy
                app.CheckBox_iii2_deploy.Enable = "off";
                % iii2-finetune
                app.CheckBox_iii2_finetune.Enable = "off";
                app.Label_iii2_finetune.Enable = "off";
                % iii2-prepro
                app.CheckBox_iii2_prepro.Enable = "off";
                app.Label_iii2_prepro.Enable = "off";
                % iii2-finetuning
                app.CheckBox_iii2_finetuning.Enable = "off";
                app.Label_iii2_finetuning.Enable = "off";
                % iii2-GPU
                app.EditField_iii2_GPU.Enable = "off";
            else
                % [when fine-tuning is on]
                % iii2
                app.CheckBox_iii2.Enable = "off";
                % iii2-deploy
                app.CheckBox_iii2_deploy.Enable = "off";
                app.Label_iii2_deploy.Enable = "off";
                % iii2-finetune
                app.CheckBox_iii2_finetune.Enable = "off";
                % iii2-prepro
                app.CheckBox_iii2_prepro.Enable = "off";
            end
        end


        function CMT_deployRegModel(app)
            % set output image size
            tmp = load(fullfile(app.dir_vxm_pretrained_model, "regModelConfig"));
            app.config_vxm_imgSize_out = tmp.regModelConfig.config_vxm_imgSize_out;

            % set and freeze vi-croppedImageSize
            if app.CheckBox_iv2_finetune.Value
                app.EditField_iv2_CroppedImageSizeX.Value = num2str(app.config_vxm_imgSize_out(1,1));
                app.EditField_iv2_CroppedImageSizeY.Value = num2str(app.config_vxm_imgSize_out(1,2));
                app.EditField_iv2_CroppedImageSizeZ.Value = num2str(app.config_vxm_imgSize_out(1,3));
            end
            app.EditField_vi_CroppedImageSizeX.Value = num2str(app.config_vxm_imgSize_out(1,1));
            app.EditField_vi_CroppedImageSizeY.Value = num2str(app.config_vxm_imgSize_out(1,2));
            app.EditField_vi_CroppedImageSizeZ.Value = num2str(app.config_vxm_imgSize_out(1,3));

            % set app.config_vxm_encoderArchi and app.config_vxm_decoderArchi [str]
            app.config_vxm_encoderArchi = tmp.regModelConfig.config_vxm_encoderArchi;
            app.config_vxm_decoderArchi = tmp.regModelConfig.config_vxm_decoderArchi;
            % set app.config_vxm_encoderArchi_base and app.config_vxm_decoderArchi_base [doulbe]
            app.config_vxm_encoderArchi_base = tmp.regModelConfig.config_vxm_encoderArchi_base;
            app.config_vxm_decoderArchi_base = tmp.regModelConfig.config_vxm_decoderArchi_base;
            tmp1 = str2num(app.config_vxm_encoderArchi);
            tmp1 = tmp1(1);
            tmp2 = app.config_vxm_encoderArchi_base(1);
            if tmp1/tmp2==1
                app.ButtonGroup_networkWidth.SelectedObject = app.Button_x1;
            elseif tmp1/tmp2==2
                app.ButtonGroup_networkWidth.SelectedObject = app.Button_x2;
            elseif tmp1/tmp2==3
                app.ButtonGroup_networkWidth.SelectedObject = app.Button_x4;
            end

            % move the pretrained model to destination folder
            if app.CheckBox_iv2_deploy.Value
                path_source_model = fullfile(app.dir_vxm_pretrained_model, "regModel.h5");
                path_source_template = fullfile(app.dir_vxm_pretrained_model, 'template.nii.gz');
                path_source_templateSeg = fullfile(app.dir_vxm_pretrained_model, 'templateSeg.nii.gz');
                path_target_model = fullfile(app.dir_vxm_model, "bestModel.h5");
                path_target_template = fullfile(app.dir_vxm_data_Template, "template.nii.gz");
                path_target_templateSeg = fullfile(app.dir_vxm_data_Template, "templateSeg.nii.gz");
                copyfile(path_source_model, path_target_model);
                copyfile(path_source_template, path_target_template);
                copyfile(path_source_templateSeg, path_target_templateSeg);
            elseif app.opt_vxm_freezeTemp
                path_source_model = fullfile(app.dir_vxm_pretrained_model, "regModel.h5");
                path_source_template = fullfile(app.dir_vxm_pretrained_model, 'template.nii.gz');
                path_source_templateSeg = fullfile(app.dir_vxm_pretrained_model, 'templateSeg.nii.gz');
                if length(dir(app.dir_vxm_model))>2
                    rmdir(app.dir_vxm_model, "s"); % remove folder and subfolders/files
                    mkdir(app.dir_vxm_model);
                end
                path_target_model4training = fullfile(app.dir_vxm_model, "init_model.h5");
                path_target_template4training = fullfile(app.dir_vxm_model, "init_template.nii.gz");
                path_target_template_final = fullfile(app.dir_vxm_data_Template, "template.nii.gz");
                path_target_templateSeg_final = fullfile(app.dir_vxm_data_Template, "templateSeg.nii.gz");
                copyfile(path_source_model, path_target_model4training);
                copyfile(path_source_template, path_target_template4training);
                copyfile(path_source_template, path_target_template_final);
                copyfile(path_source_templateSeg, path_target_templateSeg_final);
            else
                path_source_model = fullfile(app.dir_vxm_pretrained_model, "regModel.h5");
                path_source_template = fullfile(app.dir_vxm_pretrained_model, 'template.nii.gz');
                if length(dir(app.dir_vxm_model))>2
                    rmdir(app.dir_vxm_model, "s"); % remove folder and subfolders/files
                    mkdir(app.dir_vxm_model);
                end
                path_target_model = fullfile(app.dir_vxm_model, "init_model.h5");
                path_target_template = fullfile(app.dir_vxm_model, "init_template.nii.gz");
                copyfile(path_source_model, path_target_model);
                copyfile(path_source_template, path_target_template);
            end

            if app.CheckBox_iv2_deploy.Value
                % [when fine-tuning is off]
                % light the lamp
                app.Lamp_iv2_deploy.Color = [0.3804, 0.7333, 0.2745];
                app.Lamp_iv2.Color = [0.3804, 0.7333, 0.2745];
                % iv2
                app.CheckBox_iv2.Enable = "off";
                % iv2-deploy
                app.CheckBox_iv2_deploy.Enable = "off";
                % iv2-finetune
                app.CheckBox_iv2_finetune.Enable = "off";
                app.Label_iv2_finetune.Enable = "off";
                % iv2-prepro
                app.CheckBox_iv2_prepro.Enable = "off";
                app.Label_iv2_prepro.Enable = "off";
                app.EditFieldLabel_iv2_CroppedImageSize.Enable = "off";
                % iv2-finetuning
                app.CheckBox_iv2_finetuning.Enable = "off";
                app.Label_iv2_finetuning.Enable = "off";
                % iv2-GPU
                app.EditField_iv2_GPU.Enable = "off";
                % iv2-epoch
                app.EditField_iv2_epoch.Value = "";
                app.EditField_iv2_epoch.Enable = "off";
                app.EditFieldLabel_iv2_epoch.Enable = "off";
                % iv2_LossFunction
                app.Label_LossFunction_iv2.Enable = "off";
                app.Button_MSE_iv2.Enable = "off";
                app.Button_LNCC_iv2.Enable = "off";
                app.Button_MSE_LNCC_iv2.Enable = "off";
            else
                % [when fine-tuning is on]
                % iv2
                app.CheckBox_iv2.Enable = "off";
                % iv2-deploy
                app.CheckBox_iv2_deploy.Enable = "off";
                app.Label_iv2_deploy.Enable = "off";
                % iv2-finetune
                app.CheckBox_iv2_finetune.Enable = "off";
                % iv2-prepro
                app.CheckBox_iv2_prepro.Enable = "off";
            end
        end


        function CMT_vxm_inference(app)
            if app.opt_vxm_inference
                % CartiMorph-vxm scripts folder
                dir_CMvxm_scripts = fullfile(app.dir_conda_base, "envs/CartiMorphToolbox-Vxm/lib/python3.10/site-packages/CartiMorph_vxm/tf/scripts");

                % -------------------------------------------------------------------------
                % Preprocessing (inference set)
                % -------------------------------------------------------------------------
                % estimate knee joint info using the nnUNet segmentation masks
                path_vxm_data_jointInfo_nnUNet = fullfile(app.dir_vxm_data_base, "kneeJointInfo_nnUNetSeg.mat");
                kneeJointInfo_nnUNetSeg = CMT_estKneeJointSize(app, app.dir_nnUNet_prediction_inferenceSet, app.config_ROI_label, app.config_ROI_name);
                save(path_vxm_data_jointInfo_nnUNet, '-struct', "kneeJointInfo_nnUNetSeg");

                % image preprocessing: masking, downsampling, cropping, image intensity normalization
                cropRange_nnUNetSeg = CMT_imgPreprocess_mdcn(app, app.dir_nnUNet_prediction_inferenceSet, app.dir_nnUNet_raw_imgInference,...
                    [], app.dir_vxm_data_maskedImgInference,...
                    app.config_vxm_imgSize_out, kneeJointInfo_nnUNetSeg, 4);
                % save cropping range (in the downsampled image)
                save(app.path_vxm_data_cropRange_nnUNet, '-struct', "cropRange_nnUNetSeg");

                % internal checkpoint for the pause/resume and stop function
                CMT_checkPauseStopStatus(app);
                % -------------------------------------------------------------------------


                % % -------------------------------------------------------------------------
                % %% Network prediction on testing set -- for model evaluation
                % % -------------------------------------------------------------------------
                % if isfield(app.container_jobStatus, 'job_vxm_train') && strcmp(app.container_jobStatus.job_vxm_train, 'finished')
                %     path_vxm_bash_inference_warpTempSeg_testSet = fullfile(app.dir_vxm_autoScript, "inference_warpTempSeg_testSet.sh");
                %     path_vxm_log_inference_warpTempSeg_testSet = fullfile(app.dir_vxm_log, "inference_warpTempSeg_testSet.log");
                %
                %     % write "inference_warpTempSeg.sh"
                %     fileID = fopen(path_vxm_bash_inference_warpTempSeg_testSet, 'w');
                %     fprintf(fileID, '# activate Conda environment \n');
                %     % fprintf(fileID, "source %s \n", app.path_conda_src);
                %     fprintf(fileID, 'eval "$(conda shell.bash hook)" \n');
                %     fprintf(fileID, 'conda activate CartiMorphToolbox-Vxm \n');
                %     fprintf(fileID, '\n');
                %     fprintf(fileID, 'export LD_LIBRARY_PATH="%s:$LD_LIBRARY_PATH" \n', app.path_CUDA_lib_Vxm);
                %     fprintf(fileID, " \n");
                %     fprintf(fileID, "export dir_scripts='%s' \n", dir_CMvxm_scripts);
                %     fprintf(fileID, " \n");
                %     fprintf(fileID, "export dir_targetImg='%s' \n", app.dir_vxm_data_prepImgTs);
                %     fprintf(fileID, "export file_TempSeg='%s' \n", fullfile(app.dir_vxm_data_Template, "templateSeg.nii.gz"));
                %     fprintf(fileID, "export dir_warpedTempSeg='%s' \n", app.dir_vxm_inference_warpedTempSeg_Ts);
                %     fprintf(fileID, "export dir_warpingField='%s' \n", app.dir_vxm_inference_warpingField_Ts);
                %     fprintf(fileID, "export file_model='%s' \n", fullfile(app.dir_vxm_model, 'bestModel.h5'));
                %     fprintf(fileID, "export gpuIDs='%s' \n", app.config_inference_GPU);
                %     fprintf(fileID, " \n");
                %     cmd = sprintf(['"$dir_scripts"/inference_temp2img_warpTempSeg.py ',...
                %         '--dir_targetImg "$dir_targetImg" ',...
                %         '--file_TempSeg "$file_TempSeg" ',...
                %         '--dir_warpedTempSeg "$dir_warpedTempSeg" ',...
                %         '--dir_warpingField "$dir_warpingField" ',...
                %         '--file_model "$file_model" ',...
                %         '--gpuIDs "$gpuIDs" ',...
                %         '> %s 2>&1 \n'],...
                %         path_vxm_log_inference_warpTempSeg_testSet);
                %     fprintf(fileID, cmd);
                %     fclose(fileID);
                %
                %     % add execution priority
                %     path_CMvxm_script = fullfile(dir_CMvxm_scripts, 'inference_temp2img_warpTempSeg.py');
                %     cmd = strcat("chmod u+x ", path_CMvxm_script);
                %     system(cmd);
                %
                %     % run "inference_warpTempSeg.sh"
                %     cmd = strcat("chmod u+x ", path_vxm_bash_inference_warpTempSeg_testSet);
                %     system(cmd);
                %     cmd = strcat("bash ", path_vxm_bash_inference_warpTempSeg_testSet);
                %     system(cmd);
                %
                %     % internal checkpoint for the pause/resume and stop function
                %     CMT_checkPauseStopStatus(app);
                % end
                % % -------------------------------------------------------------------------

                % -------------------------------------------------------------------------
                % Network prediction on inference set -- as part of the pipeline at the inference stage
                % -------------------------------------------------------------------------
                path_vxm_bash_inference_warpTempSeg_inferenceSet = fullfile(app.dir_vxm_autoScript, "inference_warpTempSeg_inferenceSet.sh");
                path_vxm_log_inference_warpTempSeg_inferenceSet = fullfile(app.dir_vxm_log, "inference_warpTempSeg_inferenceSet.log");

                % write "inference_warpTempSeg.sh"
                fileID = fopen(path_vxm_bash_inference_warpTempSeg_inferenceSet, 'w');
                fprintf(fileID, '# activate Conda environment \n');
                % fprintf(fileID, "source %s \n", app.path_conda_src);
                fprintf(fileID, 'eval "$(conda shell.bash hook)" \n');
                fprintf(fileID, 'conda activate CartiMorphToolbox-Vxm \n');
                fprintf(fileID, '\n');
                fprintf(fileID, 'export LD_LIBRARY_PATH="%s:$LD_LIBRARY_PATH" \n', app.path_CUDA_lib_Vxm);
                fprintf(fileID, " \n");
                fprintf(fileID, "export dir_scripts='%s' \n", dir_CMvxm_scripts);
                fprintf(fileID, " \n");
                fprintf(fileID, "export dir_targetImg='%s' \n", app.dir_vxm_data_maskedImgInference);
                fprintf(fileID, "export file_TempSeg='%s' \n", fullfile(app.dir_vxm_data_Template, "templateSeg.nii.gz"));
                fprintf(fileID, "export dir_warpedTempSeg='%s' \n", app.dir_vxm_inference_warpedTempSeg_InferenceSet);
                fprintf(fileID, "export dir_warpingField='%s' \n", app.dir_vxm_inference_warpingField_InferenceSet);
                fprintf(fileID, "export file_model='%s' \n", fullfile(app.dir_vxm_model, 'bestModel.h5'));
                fprintf(fileID, "export gpuIDs='%s' \n", app.config_inference_GPU);
                fprintf(fileID, " \n");
                cmd = sprintf(['"$dir_scripts"/inference_temp2img_warpTempSeg.py ',...
                    '--dir_targetImg "$dir_targetImg" ',...
                    '--file_TempSeg "$file_TempSeg" ',...
                    '--dir_warpedTempSeg "$dir_warpedTempSeg" ',...
                    '--dir_warpingField "$dir_warpingField" ',...
                    '--file_model "$file_model" ',...
                    '--gpuIDs "$gpuIDs" ',...
                    '> %s 2>&1 \n'],...
                    path_vxm_log_inference_warpTempSeg_inferenceSet);
                fprintf(fileID, cmd);
                fclose(fileID);

                % add execution priority
                path_CMvxm_script = fullfile(dir_CMvxm_scripts, 'inference_temp2img_warpTempSeg.py');
                cmd = strcat("chmod u+x ", path_CMvxm_script);
                system(cmd);

                % run "inference_warpTempSeg.sh"
                cmd = strcat("chmod u+x ", path_vxm_bash_inference_warpTempSeg_inferenceSet);
                system(cmd);
                cmd = strcat("bash ", path_vxm_bash_inference_warpTempSeg_inferenceSet);
                system(cmd);

                % internal checkpoint for the pause/resume and stop function
                CMT_checkPauseStopStatus(app);
                % -------------------------------------------------------------------------

                % -------------------------------------------------------------------------
                % Finish model inference
                % -------------------------------------------------------------------------
                % disable this step
                app.opt_vxm_inference = false;
                % light the lamp
                app.Lamp_vi.Color = [0.3804, 0.7333, 0.2745];
                % freeze vi
                app.CheckBox_vi.Enable = "off";
                app.EditField_vi_GPU.Editable = "off";
                % disable the reset button
                app.Button_iv2_reset.Enable = "off";
                app.Button_iv2_reset.Visible = "off";

                % autosave configuration
                CMT_autosaveConfig(app, 'config_latest');
                CMT_autosaveConfig(app, 'config_vi');

                % internal checkpoint for the pause/resume and stop function
                CMT_checkPauseStopStatus(app);
                % -------------------------------------------------------------------------
            end
        end

        function CMT_morphQuant(app)
            if app.opt_quantification
                % --------------------------------------------------------------------
                % [initialization]
                % --------------------------------------------------------------------
                % list of cases
                tmp = [dir(fullfile(app.dir_vxm_inference_warpedTempSeg_InferenceSet, "*.nii.gz"));...
                    dir(fullfile(app.dir_vxm_inference_warpedTempSeg_InferenceSet, "*.nii"))];
                list_case = {tmp.name}';
                CMT_num_case = length(list_case);

                % knee side info
                CMT_kneeSideInfo = app.config_kneeSideInfo;

                % freeze field
                app.EditField_csvPath.Editable = "off";
                app.Button_loadCSV.Enable = "off";

                % the labels for the cartilage whose thickness need to be quantified
                CMT_cartilage_labels.FemoralCartilage = str2double(app.config_ROI_label{strcmp(app.config_ROI_name, 'femoral cartilage')});
                CMT_cartilage_labels.mTibialCartilage = str2double(app.config_ROI_label{strcmp(app.config_ROI_name, 'medial tibial cartilage')});
                CMT_cartilage_labels.lTibialCartilage = str2double(app.config_ROI_label{strcmp(app.config_ROI_name, 'lateral tibial cartilage')});
                CMT_cartilage_names = fieldnames(CMT_cartilage_labels);

                % load related files
                cropRangeInfo = load(app.path_vxm_data_cropRange_nnUNet);

                % used to calculate the parameter: neigh = #voxels / n_ROI (for SN-based method only)
                CMT_numROI.FemoralCartilage = app.config_numROI_FC; % built-in default value
                CMT_numROI.mTibialCartilage = app.config_numROI_mTC; % built-in default value
                CMT_numROI.lTibialCartilage = app.config_numROI_lTC; % built-in default value

                % write log file
                if ~isempty(app.container_logFileID)
                    fprintf(app.container_logFileID, '===============================================\n');
                    fprintf(app.container_logFileID, 'Project: %s \n', app.config_nnUNet_taskIDName);
                    for l=1:length(app.config_ROI_name)
                        fprintf(app.container_logFileID, '     - label %s: %s \n', app.config_ROI_label{l}, app.config_ROI_name{l});
                    end
                    fprintf(app.container_logFileID, 'Number of cases: %s \n', num2str(CMT_num_case));
                    fprintf(app.container_logFileID, '===============================================\n');
                    fprintf(app.container_logFileID, '\n');
                    fprintf(app.container_logFileID, '\n');
                    fprintf(app.container_logFileID, '\n');
                end

                % internal checkpoint for the pause/resume and stop function
                CMT_checkPauseStopStatus(app);
                % --------------------------------------------------------------------

                % initialize progress indicator
                if CMT_num_case>1
                    app.Gauge_vii.Visible = "on";
                    app.Label_vii_progress.Visible = "on";
                    progress = app.counter_case / CMT_num_case * 100;
                    app.Gauge_vii.Value = progress;
                end

                resume_case = app.counter_case + 1;
                for i=resume_case:CMT_num_case
                    % --------------------------------------------------------------------
                    %% [1] Cartilage Thickness Mapping
                    % --------------------------------------------------------------------
                    if app.opt_quant_CTM
                        % file name
                        i_fileName_wExt = list_case{i};
                        [~, tmp_fileName, tmp_ext] = fileparts(i_fileName_wExt);
                        if strcmp(tmp_ext, ".gz")
                            [~, i_fileName_woExt, ~] = fileparts(tmp_fileName);
                            i_ext = ".nii.gz";
                        else
                            i_fileName_woExt = tmp_fileName;
                            i_ext = ".nii";
                        end
                        % create results folder for each case
                        i_dir_case = fullfile(app.dir_result, i_fileName_woExt);
                        if  ~isfolder(i_dir_case)
                            mkdir(i_dir_case);
                        end
                        % get knee side
                        tmp_idx = strcmp(CMT_kneeSideInfo{:,1}, i_fileName_wExt);
                        i_kneeSide = char(CMT_kneeSideInfo{tmp_idx, 2});
                        % save knee side info to individual result folder
                        kneeSideInfo = i_kneeSide;
                        save(fullfile(app.dir_result, i_fileName_woExt, 'kneeSideInfo.mat'), 'kneeSideInfo');
                        % write log file
                        if ~isempty(app.container_logFileID)
                            fprintf(app.container_logFileID, '%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%\n');
                            fprintf(app.container_logFileID, 'Date-Time: %s \n', char(datetime));
                            fprintf(app.container_logFileID, 'Case: %s \n', i_fileName_woExt);
                            fprintf(app.container_logFileID, '%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%\n');
                            fprintf(app.container_logFileID, '\n');
                        end

                        % <<<<<< for CartiMorph Viewer <<<<<<
                        % save the standardized MR image array into .mat file
                        i_img = niftiread(fullfile(app.dir_nnUNet_raw_imgInference, strcat(i_fileName_woExt, '_0000', i_ext)));
                        i_dir_img = fullfile(app.dir_result, i_fileName_woExt, 'Image');
                        if ~isfolder(i_dir_img)
                            mkdir(i_dir_img);
                        end
                        save(fullfile(i_dir_img, strcat(i_fileName_woExt, '_image.mat')), 'i_img');
                        % <<<<<< for CartiMorph Viewer <<<<<<


                        % [1.1] Recover the warped tissue segmentation for the knee template to the original image size
                        % get cropping range
                        i_idx = strcmp(vertcat(cropRangeInfo.filename{:}), i_fileName_wExt);
                        i_cropRange = cropRangeInfo.cropRange(i_idx, :);
                        i_cropRangeX = i_cropRange(1):i_cropRange(2);
                        i_cropRangeY = i_cropRange(3):i_cropRange(4);
                        i_cropRangeZ = i_cropRange(5):i_cropRange(6);
                        % load the warped template segmentation
                        i_warpedTempSeg_info = niftiinfo(fullfile(app.dir_vxm_inference_warpedTempSeg_InferenceSet, i_fileName_wExt));
                        i_warpedTempSeg_data = niftiread(i_warpedTempSeg_info);
                        % recover the downsampled image
                        i_warpTempSeg_lowres = zeros(cropRangeInfo.imageSize_downsampled(i_idx, :));
                        i_warpTempSeg_lowres(i_cropRangeX, i_cropRangeY, i_cropRangeZ) = i_warpedTempSeg_data;
                        % recover the full-resolution image
                        i_warpTempSeg_fullres = CartiMorphToolbox.CMT_imgPreprocess_resample(i_warpTempSeg_lowres, cropRangeInfo.imageSize_original(i_idx, :),...
                            'nearest', 'uint8');

                        % -------------------------------------------------------------
                        % [flip the 3D volume in L-R direction for each left knee MRI]
                        % In the image preprocessing step prior to template learning model training,
                        %   all left knee MRIs are flipped in the L-R direction
                        %
                        % check these functions:
                        %   - CMT_estKneeJointSize
                        %   - CMT_imgPreprocess_mdcn
                        % -------------------------------------------------------------
                        % flip left to right (assuming RAS+ orientation)
                        if sum(strcmp(i_kneeSide, {'left', 'Left', 'L'}))==1
                            i_warpTempSeg_fullres = flip(i_warpTempSeg_fullres, 1);
                        end
                        % -------------------------------------------------------------


                        % [1.2] Calculate the thickness map and mean thickness for all cartilages
                        % load nnUNet segmentation
                        i_nnUNetSeg_info = niftiinfo(fullfile(app.dir_nnUNet_prediction_inferenceSet, i_fileName_wExt));
                        i_nnUNetSeg = niftiread(i_nnUNetSeg_info);

                        % I (Yongcheng Yao) have confirmed: i_voxSize_fullres==i_voxSize
                        i_voxSize = i_nnUNetSeg_info.PixelDimensions;

                        resume_cartilage = app.counter_cartilage + 1;
                        for j=resume_cartilage:length(CMT_cartilage_names)
                            % get the segmentation of cartilage
                            j_cartilageName = CMT_cartilage_names{j};
                            j_label = CMT_cartilage_labels.(j_cartilageName);
                            j_cartilageMask = zeros(size(i_nnUNetSeg), 'uint8');
                            j_cartilageMask(i_nnUNetSeg==j_label) = 1;
                            % get the warped segmentation mask for the knee template
                            j_warpTempSegMask = zeros(size(i_warpTempSeg_fullres), 'uint8');
                            j_warpTempSegMask(i_warpTempSeg_fullres==j_label) = 1;
                            % n_ROI for the auto-tunning of a variety of neighborhood settings
                            j_numROI = CMT_numROI.(j_cartilageName);
                            % get the segmentation of bone
                            j_boneMask = zeros(size(i_nnUNetSeg), 'uint8');
                            switch j_cartilageName
                                case 'FemoralCartilage'
                                    j_boneMask(i_nnUNetSeg==str2double(app.config_ROI_label{strcmp(app.config_ROI_name, "femur")})) = 1;
                                case 'mTibialCartilage'
                                    j_boneMask(i_nnUNetSeg==str2double(app.config_ROI_label{strcmp(app.config_ROI_name, "tibia")})) = 1;
                                case 'lTibialCartilage'
                                    j_boneMask(i_nnUNetSeg==str2double(app.config_ROI_label{strcmp(app.config_ROI_name, "tibia")})) = 1;
                            end
                            if ~isempty(app.container_logFileID)
                                fprintf(app.container_logFileID, '* Thickness mapping for <<< %s >>> \n', j_cartilageName);
                                fprintf(app.container_logFileID, '------------------------------------------------------------------ \n');
                                fprintf(app.container_logFileID, '');
                            end
                            % calculate cartilage thickness, save results into .mat files, and save thickness map as .fig files
                            opt.depth = app.config_humanMaxThickness;
                            opt.n_ROI = j_numROI;
                            CMT_wrapper_KneeCartilageThickness_wBone( ...
                                app, ...
                                j_cartilageMask,...
                                j_boneMask,...
                                j_warpTempSegMask,...
                                i_voxSize,...
                                'SN',...
                                j_cartilageName,...
                                i_fileName_woExt,...
                                i_dir_case,...
                                app.container_logFileID,...
                                opt);
                            if ~isempty(app.container_logFileID)
                                fprintf(app.container_logFileID, '------------------------------------------------------------------ \n');
                                fprintf(app.container_logFileID, '\n');
                            end

                            % update cartilage counter (important)
                            app.counter_cartilage = app.counter_cartilage + 1;

                            % autosave configuration
                            CMT_autosaveConfig(app, 'config_latest');

                            % internal checkpoint for the pause/resume and stop function
                            CMT_checkPauseStopStatus(app);
                        end

                        % reset cartilage counter (important)
                        app.counter_cartilage = 0;


                        % [1.3] Create integrated thickness maps, e.g. show all cartilages in the same figure
                        if ~isempty(app.container_logFileID)
                            fprintf(app.container_logFileID, '* Visualizing the thickness maps of all cartilage ... ');
                        end
                        % load smoothed FC thickness map
                        fileName_sMap_FC = append(i_fileName_woExt, '_sMap_FemoralCartilage.mat');
                        if isfile(fullfile(i_dir_case, 'ThicknessMap', fileName_sMap_FC))
                            i_sMap_FC = load(fullfile(i_dir_case, 'ThicknessMap', fileName_sMap_FC));
                            i_sMap_FC = i_sMap_FC.sThicknessMap;
                        else
                            i_sMap_FC = [];
                        end
                        % load smoothed mTC thickness map
                        fileName_sMap_mTC = append(i_fileName_woExt, '_sMap_mTibialCartilage.mat');
                        if isfile(fullfile(i_dir_case, 'ThicknessMap', fileName_sMap_mTC))
                            i_sMap_mTC = load(fullfile(i_dir_case, 'ThicknessMap', fileName_sMap_mTC));
                            i_sMap_mTC = i_sMap_mTC.sThicknessMap;
                        else
                            i_sMap_mTC = [];
                        end
                        % load smoothed lTC thickness map
                        fileName_sMap_lTC = append(i_fileName_woExt, '_sMap_lTibialCartilage.mat');
                        if isfile(fullfile(i_dir_case, 'ThicknessMap', fileName_sMap_lTC))
                            i_sMap_lTC = load(fullfile(i_dir_case, 'ThicknessMap', fileName_sMap_lTC));
                            i_sMap_lTC = i_sMap_lTC.sThicknessMap;
                        else
                            i_sMap_lTC = [];
                        end
                        % load Femur surface
                        fileName_Femur = append(i_fileName_woExt, '_sMesh_B_FemoralCartilage.mat');
                        if isfile(fullfile(i_dir_case, 'Surface', fileName_Femur))
                            i_sMesh_Femur = load(fullfile(i_dir_case, 'Surface', fileName_Femur));
                        else
                            i_sMesh_Femur = [];
                        end
                        % load Tibia surface
                        fileName_Tibia = append(i_fileName_woExt, '_sMesh_B_lTibialCartilage.mat');
                        if isfile(fullfile(i_dir_case, 'Surface', fileName_Tibia))
                            i_sMesh_Tibia = load(fullfile(i_dir_case, 'Surface', fileName_Tibia));
                        else
                            i_sMesh_Tibia = [];
                        end
                        % load FC surface
                        fileName_FC = append(i_fileName_woExt, '_sMesh_C_FemoralCartilage.mat');
                        if isfile(fullfile(i_dir_case, 'Surface', fileName_FC))
                            i_sMesh_FC = load(fullfile(i_dir_case, 'Surface', fileName_FC));
                        else
                            i_sMesh_FC = [];
                        end
                        % load lTC surface
                        fileName_lTC = append(i_fileName_woExt, '_sMesh_C_lTibialCartilage.mat');
                        if isfile(fullfile(i_dir_case, 'Surface', fileName_lTC))
                            i_sMesh_lTC = load(fullfile(i_dir_case, 'Surface', fileName_lTC));
                        else
                            i_sMesh_lTC = [];
                        end
                        % load mTC surface
                        fileName_mTC = append(i_fileName_woExt, '_sMesh_C_mTibialCartilage.mat');
                        if isfile(fullfile(i_dir_case, 'Surface', fileName_mTC))
                            i_sMesh_mTC = load(fullfile(i_dir_case, 'Surface', fileName_mTC));
                        else
                            i_sMesh_mTC = [];
                        end
                        % load surface meshes of FC
                        fileName_iC_FC = append(i_fileName_woExt, '_sMesh_iC_FemoralCartilage.mat');
                        if isfile(fullfile(i_dir_case, 'Surface', fileName_iC_FC))
                            i_sMesh_iC_FC = load(fullfile(i_dir_case, 'Surface', fileName_iC_FC));
                        else
                            i_sMesh_iC_FC = [];
                        end
                        % load surface meshes of mTC
                        fileName_iC_mTC = append(i_fileName_woExt, '_sMesh_iC_mTibialCartilage.mat');
                        if isfile(fullfile(i_dir_case, 'Surface', fileName_iC_mTC))
                            i_sMesh_iC_mTC = load(fullfile(i_dir_case, 'Surface', fileName_iC_mTC));
                        else
                            i_sMesh_iC_mTC = [];
                        end
                        % load surface meshes of lTC
                        fileName_iC_lTC = append(i_fileName_woExt, '_sMesh_iC_lTibialCartilage.mat');
                        if isfile(fullfile(i_dir_case, 'Surface', fileName_iC_lTC))
                            i_sMesh_iC_lTC = load(fullfile(i_dir_case, 'Surface', fileName_iC_lTC));
                        else
                            i_sMesh_iC_lTC = [];
                        end
                        % create folder for surface visualization
                        dir_thicknessMapFig = fullfile(i_dir_case, 'ThicknessMap_Figure', 'AllCartilages');
                        if ~isfolder(dir_thicknessMapFig)
                            mkdir(dir_thicknessMapFig)
                        end
                        % smoothed thickness map visualization
                        fileName = append(i_fileName_woExt, '_sMap_allCartilages');
                        filePath = fullfile(dir_thicknessMapFig, fileName);
                        fig_title = append('Thickness Map (', i_fileName_woExt, ')');
                        CartiMorphToolbox.plot_integrated_thicknessMap_wBone(...
                            i_sMesh_Femur,...
                            i_sMesh_Tibia,...
                            i_sMesh_FC,...
                            i_sMesh_lTC,...
                            i_sMesh_mTC,...
                            i_sMesh_iC_FC,...
                            i_sMesh_iC_lTC,...
                            i_sMesh_iC_mTC,...
                            i_sMap_FC,...
                            i_sMap_lTC,...
                            i_sMap_mTC,...
                            fig_title,...
                            filePath);
                        if ~isempty(app.container_logFileID)
                            fprintf(app.container_logFileID, 'finished. \n');
                            fprintf(app.container_logFileID, '\n');
                        end

                        % internal checkpoint for the pause/resume and stop function
                        CMT_checkPauseStopStatus(app);
                    end
                    % --------------------------------------------------------------------


                    % --------------------------------------------------------------------
                    %% [2] Cartilage Parcellation
                    % --------------------------------------------------------------------
                    if app.opt_quant_parcellation
                        if ~isempty(app.container_logFileID)
                            fprintf(app.container_logFileID, '* Cartilage parcellation ... ');
                        end
                        % load surface meshes of FC
                        i_fileName_scB_FC = append(i_fileName_woExt, '_Mesh_scB_FemoralCartilage.mat');
                        if isfile(fullfile(i_dir_case, 'Surface', i_fileName_scB_FC))
                            i_Mesh_scB_FC = load(fullfile(i_dir_case, 'Surface', i_fileName_scB_FC));
                        else
                            i_Mesh_scB_FC = [];
                        end
                        i_fileName_iC_FC = append(i_fileName_woExt, '_Mesh_iC_FemoralCartilage.mat');
                        if isfile(fullfile(i_dir_case, 'Surface', i_fileName_iC_FC))
                            i_Mesh_iC_FC = load(fullfile(i_dir_case, 'Surface', i_fileName_iC_FC));
                        else
                            i_Mesh_iC_FC = [];
                        end
                        % load surface meshes of mTC
                        i_fileName_scB_mTC = append(i_fileName_woExt, '_Mesh_scB_mTibialCartilage.mat');
                        if isfile(fullfile(i_dir_case, 'Surface', i_fileName_scB_mTC))
                            i_Mesh_scB_mTC = load(fullfile(i_dir_case, 'Surface', i_fileName_scB_mTC));
                        else
                            i_Mesh_scB_mTC = [];
                        end
                        i_fileName_iC_mTC = append(i_fileName_woExt, '_Mesh_iC_mTibialCartilage.mat');
                        if isfile(fullfile(i_dir_case, 'Surface', i_fileName_iC_mTC))
                            i_Mesh_iC_mTC = load(fullfile(i_dir_case, 'Surface', i_fileName_iC_mTC));
                        else
                            i_Mesh_iC_mTC = [];
                        end
                        % load surface meshes of lTC
                        i_fileName_scB_lTC = append(i_fileName_woExt, '_Mesh_scB_lTibialCartilage.mat');
                        if isfile(fullfile(i_dir_case, 'Surface', i_fileName_scB_lTC))
                            i_Mesh_scB_lTC = load(fullfile(i_dir_case, 'Surface', i_fileName_scB_lTC));
                        else
                            i_Mesh_scB_lTC = [];
                        end
                        i_fileName_iC_lTC = append(i_fileName_woExt, '_Mesh_iC_lTibialCartilage.mat');
                        if isfile(fullfile(i_dir_case, 'Surface', i_fileName_iC_lTC))
                            i_Mesh_iC_lTC = load(fullfile(i_dir_case, 'Surface', i_fileName_iC_lTC));
                        else
                            i_Mesh_iC_lTC= [];
                        end

                        % internal checkpoint for the pause/resume and stop function
                        CMT_checkPauseStopStatus(app);

                        % get subregions vertices of FC
                        [i_vers_subreg_FC, i_vers_subreg_FC_f, i_mesh_subreg_FC, i_mesh_subreg_FC_f] = CartiMorphToolbox.cal_SurfaceParcellation_FC( ...
                            i_Mesh_iC_FC, ...
                            i_Mesh_scB_FC, ...
                            i_voxSize, ...
                            i_kneeSide, ...
                            app.config_CMT_ccPercentage);

                        % internal checkpoint for the pause/resume and stop function
                        CMT_checkPauseStopStatus(app);

                        % get subregions vertices of TC
                        [i_vers_subreg_TC, i_vers_subreg_TC_f, i_mesh_subreg_TC, i_mesh_subreg_TC_f] = ...
                            CartiMorphToolbox.cal_SurfaceParcellation_TC( ...
                            i_Mesh_iC_lTC, ...
                            i_Mesh_iC_mTC, ...
                            i_Mesh_scB_lTC, ...
                            i_Mesh_scB_mTC, ...
                            i_voxSize, ...
                            i_kneeSide);

                        % internal checkpoint for the pause/resume and stop function
                        CMT_checkPauseStopStatus(app);

                        % save subregions
                        dir_CartSubregions = fullfile(i_dir_case, 'CartilageSubregion');
                        if  ~isfolder(dir_CartSubregions)
                            mkdir(dir_CartSubregions);
                        end
                        if ~isempty(i_vers_subreg_FC)
                            fileName = append(i_fileName_woExt, '_vers_subreg_iC_FC');
                            filePath = fullfile(dir_CartSubregions, fileName);
                            save(string(filePath), '-struct', 'i_vers_subreg_FC');
                        end
                        if ~isempty(i_vers_subreg_TC)
                            fileName = append(i_fileName_woExt, '_vers_subreg_iC_TC');
                            filePath = fullfile(dir_CartSubregions, fileName);
                            save(string(filePath), '-struct', 'i_vers_subreg_TC');
                        end
                        if ~isempty(i_mesh_subreg_FC)
                            fileName = append(i_fileName_woExt, '_mesh_subreg_iC_FC');
                            filePath = fullfile(dir_CartSubregions, fileName);
                            save(string(filePath), '-struct', 'i_mesh_subreg_FC');
                        end
                        if ~isempty(i_mesh_subreg_TC)
                            fileName = append(i_fileName_woExt, '_mesh_subreg_iC_TC');
                            filePath = fullfile(dir_CartSubregions, fileName);
                            save(string(filePath), '-struct', 'i_mesh_subreg_TC');
                        end
                        % visualize subregions of FC and TC
                        dir_CartSubreg_Fig = fullfile(i_dir_case, 'CartilageSubregion_Figure');
                        if  ~isfolder(dir_CartSubreg_Fig)
                            mkdir(dir_CartSubreg_Fig);
                        end
                        fileName = append(i_fileName_woExt, '_mesh_subreg_iC_FTC');
                        filePath = fullfile(dir_CartSubreg_Fig, fileName);
                        CartiMorphToolbox.plot_CartSubregions_mesh(i_mesh_subreg_FC, i_mesh_subreg_TC, filePath)
                        % visualize subregions of subchondral bone covered by FC and TC
                        fileName = append(i_fileName_woExt, '_mesh_subreg_scB_FTC');
                        filePath = fullfile(dir_CartSubreg_Fig, fileName);
                        CartiMorphToolbox.plot_CartSubregions_mesh(i_mesh_subreg_FC_f, i_mesh_subreg_TC_f, filePath)
                        if ~isempty(app.container_logFileID)
                            fprintf(app.container_logFileID, 'finished \n');
                            fprintf(app.container_logFileID, '\n');
                        end

                        % internal checkpoint for the pause/resume and stop function
                        CMT_checkPauseStopStatus(app);
                    end
                    % --------------------------------------------------------------------


                    % --------------------------------------------------------------------
                    %% [3] Regional Analysis
                    % --------------------------------------------------------------------
                    if app.opt_quant_regionalAnalysis
                        % [3.1] Cartilage thickness quantification for each subregion
                        if ~isempty(app.container_logFileID)
                            fprintf(app.container_logFileID, '* Cartilage thickness quantification for each subregion ... ');
                        end

                        % load FC thickness map
                        fileName_Map_FC = append(i_fileName_woExt, '_Map_FemoralCartilage.mat');
                        if isfile(fullfile(i_dir_case, 'ThicknessMap', fileName_Map_FC))
                            i_Map_FC = load(fullfile(i_dir_case, 'ThicknessMap', fileName_Map_FC));
                            i_Map_FC = i_Map_FC.ThicknessMap;
                        else
                            i_Map_FC = [];
                        end
                        % load mTC thickness map
                        fileName_Map_mTC = append(i_fileName_woExt, '_Map_mTibialCartilage.mat');
                        if isfile(fullfile(i_dir_case, 'ThicknessMap', fileName_Map_mTC))
                            i_Map_mTC = load(fullfile(i_dir_case, 'ThicknessMap', fileName_Map_mTC));
                            i_Map_mTC = i_Map_mTC.ThicknessMap;
                        else
                            i_Map_mTC = [];
                        end
                        % load lTC thickness map
                        fileName_Map_lTC = append(i_fileName_woExt, '_Map_lTibialCartilage.mat');
                        if isfile(fullfile(i_dir_case, 'ThicknessMap', fileName_Map_lTC))
                            i_Map_lTC = load(fullfile(i_dir_case, 'ThicknessMap', fileName_Map_lTC));
                            i_Map_lTC = i_Map_lTC.ThicknessMap;
                        else
                            i_Map_lTC = [];
                        end

                        % load surface meshes of FC
                        fileName_iC_FC = append(i_fileName_woExt, '_Mesh_iC_FemoralCartilage.mat');
                        if isfile(fullfile(i_dir_case, 'Surface', fileName_iC_FC))
                            i_Mesh_iC_FC = load(fullfile(i_dir_case, 'Surface', fileName_iC_FC));
                        else
                            i_Mesh_iC_FC = [];
                        end
                        % load surface meshes of mTC
                        fileName_iC_mTC = append(i_fileName_woExt, '_Mesh_iC_mTibialCartilage.mat');
                        if isfile(fullfile(i_dir_case, 'Surface', fileName_iC_mTC))
                            i_Mesh_iC_mTC = load(fullfile(i_dir_case, 'Surface', fileName_iC_mTC));
                        else
                            i_Mesh_iC_mTC = [];
                        end
                        % load surface meshes of lTC
                        fileName_iC_lTC = append(i_fileName_woExt, '_Mesh_iC_lTibialCartilage.mat');
                        if isfile(fullfile(i_dir_case, 'Surface', fileName_iC_lTC))
                            i_Mesh_iC_lTC = load(fullfile(i_dir_case, 'Surface', fileName_iC_lTC));
                        else
                            i_Mesh_iC_lTC = [];
                        end

                        % get cartilage segmentation masks
                        i_Mask_FC = zeros(size(i_nnUNetSeg), "uint8");
                        i_Mask_mTC = zeros(size(i_nnUNetSeg), "uint8");
                        i_Mask_lTC = zeros(size(i_nnUNetSeg), "uint8");
                        i_Mask_FC(i_nnUNetSeg==CMT_cartilage_labels.FemoralCartilage) = 1;
                        i_Mask_mTC(i_nnUNetSeg==CMT_cartilage_labels.mTibialCartilage) = 1;
                        i_Mask_lTC(i_nnUNetSeg==CMT_cartilage_labels.lTibialCartilage) = 1;

                        % internal checkpoint for the pause/resume and stop function
                        CMT_checkPauseStopStatus(app);

                        % morphological quantification
                        MorphQuant = CartiMorphToolbox.wrapper_MorphQuant( ...
                            i_Map_FC, ...
                            i_Map_mTC, ...
                            i_Map_lTC, ...
                            i_Mesh_iC_FC, ...
                            i_Mesh_iC_mTC, ...
                            i_Mesh_iC_lTC, ...
                            i_vers_subreg_FC, ...
                            i_vers_subreg_FC_f, ...
                            i_mesh_subreg_FC, ...
                            i_mesh_subreg_FC_f, ...
                            i_vers_subreg_TC, ...
                            i_vers_subreg_TC_f, ...
                            i_mesh_subreg_TC, ...
                            i_mesh_subreg_TC_f,...
                            i_Mask_FC,...
                            i_Mask_mTC,...
                            i_Mask_lTC,...
                            i_voxSize,...
                            i_dir_case,...
                            i_fileName_woExt);

                        % save morphological quantification result to .mat
                        dir_MorphQuant = fullfile(i_dir_case, 'MorphologicalQuantification');
                        if  ~isfolder(dir_MorphQuant)
                            mkdir(dir_MorphQuant);
                        end
                        fileName = append(i_fileName_woExt, '_MorphQuant.mat');
                        filePath = fullfile(dir_MorphQuant, fileName);
                        save(string(filePath), '-struct', 'MorphQuant');

                        % [save morphological quantification result to .csv]
                        % ROI names from label 1 to 20
                        % <<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<
                        % <do not change the order which is in line with the order in CartiMorph Viewer>
                        ROInames = {'aMFC', 'ecMFC', 'ccMFC', 'icMFC', 'pMFC', 'aLFC', 'ecLFC', 'ccLFC', 'icLFC', 'pLFC',...
                            'aMTC', 'eMTC', 'pMTC', 'iMTC', 'cMTC',...
                            'aLTC', 'eLTC', 'pLTC', 'iLTC', 'cLTC'};
                        % <<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<

                        table_MorphQuant = CartiMorphToolbox.cal_MorphQuant2table(MorphQuant, ROInames);
                        fileName = append(i_fileName_woExt, '_MorphQuant.csv');
                        filePath = fullfile(dir_MorphQuant, fileName);
                        writetable(table_MorphQuant, filePath, "Delimiter", ",", "WriteRowNames",true, "WriteVariableNames",true);

                        if ~isempty(app.container_logFileID)
                            fprintf(app.container_logFileID, 'finished \n');
                            fprintf(app.container_logFileID, '\n');
                            fprintf(app.container_logFileID, '\n');
                            fprintf(app.container_logFileID, '\n');
                        end

                        % internal checkpoint for the pause/resume and stop function
                        CMT_checkPauseStopStatus(app);
                    end
                    % --------------------------------------------------------------------

                    % update counter (important for autoresuming)
                    app.counter_case = app.counter_case + 1;

                    if CMT_num_case>1
                        % update the progress of (vii)
                        progress = app.counter_case / CMT_num_case * 100;
                        app.Gauge_vii.Value = progress;
                    end

                    % autosave configuration
                    CMT_autosaveConfig(app, 'config_latest');

                    % internal checkpoint for the pause/resume and stop function
                    CMT_checkPauseStopStatus(app);
                end


                % light the lamps, freeze fields and disable some modules
                app.Lamp_vii.Color = [0.3804, 0.7333, 0.2745];
                app.CheckBox_vii.Enable = "off";
                app.opt_quantification = false;
                app.Lamp_vii_CTM.Color = [0.3804, 0.7333, 0.2745];
                app.CheckBox_vii_CTM.Enable = "off";
                app.opt_quant_CTM = false;
                if app.CheckBox_vii_parcellation.Value
                    app.Lamp_vii_parcellation.Color = [0.3804, 0.7333, 0.2745];
                    app.CheckBox_vii_parcellation.Enable = "off";
                    app.opt_quant_parcellation = false;
                end
                if app.CheckBox_vii_regionalAnalysis.Value
                    app.Lamp_vii_regionalAnalysis.Color = [0.3804, 0.7333, 0.2745];
                    app.CheckBox_vii_regionalAnalysis.Enable = "off";
                    app.opt_quant_regionalAnalysis = false;
                end

                % autosave configuration
                CMT_autosaveConfig(app, 'config_latest');
                CMT_autosaveConfig(app, 'config_vii');

                % internal checkpoint for the pause/resume and stop function
                CMT_checkPauseStopStatus(app);
            end
        end


        function CMT_enableButtons(app)
            % save
            app.Button_Save.Enable = "on";
            app.Button_Save.Value = false;

            % enable the export buttons
            if isfield(app.container_jobStatus, 'job_nnUNet_train')
                if strcmp(app.container_jobStatus.job_nnUNet_train, 'finished')
                    app.Button_exportSegModel.Enable = "on";
                    app.Button_exportSegModel.Value = false;
                end
            end
            if isfield(app.container_jobStatus, 'job_vxm_train')
                if strcmp(app.container_jobStatus.job_vxm_train, 'finished')
                    app.Button_exportRegModel.Enable = "on";
                    app.Button_exportRegModel.Value = false;
                end
            end

            % enable models evaluation buttons when the segmentation model inference is finished
            if isequal(app.Lamp_iii_train.Color, [0.3804, 0.7333, 0.2745]) || isequal(app.Lamp_iii2_finetune.Color, [0.3804, 0.7333, 0.2745])
                app.Button_evalSegModel.Enable = "on";
                app.Button_evalSegModel.Value = false;
            end

            % enable models evaluation buttons when the registration model inference is finished
            if isequal(app.Lamp_iv_train.Color, [0.3804, 0.7333, 0.2745]) || isequal(app.Lamp_iv2_finetune.Color, [0.3804, 0.7333, 0.2745])
                app.Button_evalRegModel.Enable = "on";
                app.Button_evalRegModel.Value = false;
            end

            % viewer
            if isequal(app.Lamp_vii.Color, [0.3804, 0.7333, 0.2745])
                app.Button_CMV.Enable = "on";
                app.Button_CMV.Value = false;
            end

            % enable panels
            app.Panel_ComputingEnvironmentConfiguration.Enable = "on";
            app.Panel_ImageProcessingPipeline.Enable = "on";
            app.Panel_ImageDataInformation.Enable = "on";

            % "About" button
            app.AboutButton.Enable = "on";
            % "Tips" button
            app.TipsButton.Enable = "on";
        end


        function CMT_disableButtons(app)
            % disable the save and load buttons
            app.Button_Save.Enable = "off";
            app.Button_Load.Enable = "off";
            % disable the run button
            app.Button_Run.Enable = "off";
            % disable the model export buttons
            app.Button_exportSegModel.Enable = "off";
            app.Button_exportRegModel.Enable = "off";
            % disable model evaluation buttons
            app.Button_evalSegModel.Enable = "off";
            app.Button_evalRegModel.Enable = "off";
            % disable panels
            app.Panel_ComputingEnvironmentConfiguration.Enable = "off";
            app.Panel_ImageProcessingPipeline.Enable = "off";
            app.Panel_ImageDataInformation.Enable = "off";
            % "About" button
            app.AboutButton.Enable = "off";
            % "Tips" button
            app.TipsButton.Enable = "off";
        end


        function CMT_checkPauseStopStatus(app)
            % <<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<
            % execute pending jobs (button-pressed-callback in our case)
            % ----------------------------------------------------------------
            drawnow;
            % <<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<

            if app.status_isPaused
                % the Pause button is actually with text "Pause      " where the space is for better visualization
                % so we change the "Pause      " to "Resume      "
                app.Button_Pause.Text = "Resume      ";

                % enable buttons after pressing the "Pause" button while running the pipeline
                CMT_enableButtons(app);

                % waiting
                waitfor(app.Button_Pause,'Text', "Pause      ");
            end
        end


        function SN_out = CMT_cal_reorientSN(...
                app, ...
                SN_in,...
                vers_inter,...
                vers_outer,...
                size_voxel,...
                n_neigh)
            % ==============================================================================
            % FUNCTION:
            %     Reorient surface normals so that they point from inner to outer surface.
            %
            % INPUT:
            %     - SN_in: (nv, 3), input unit surface normals
            %     - vers_inter: (nv, 3), vertices on the bone-cartilage interface
            %     - vers_outer: (nv, 3), vertices on the outer cartilage surface
            %     - size_voxel: voxel size
            %     - n_neigh: number of neighbors
            %
            % OUTPUT:
            %      - SN_out: (nv, 3), the orientation-corrected SNs
            % ------------------------------------------------------------------------------
            % Matlab Version: 2019b or later
            %
            % Last updated on: 11-May-2022
            %
            % Author:
            % Yongcheng YAO (yao_yongcheng@link.cuhk.edu.hk)
            % Department of Imaging and Interventional Radiology,
            % Chinese University of Hong Kong (CUHK)
            %
            % Copyright 2020 Yongcheng YAO
            % ------------------------------------------------------------------------------
            % ==============================================================================


            %% Step 1: flip SN if it is getting far away from the outer boundary
            % ------------------------------------------------------------------------------------------
            % remove duplicated vertices from the outer boundary
            vers_outer = vers_outer(~ismember(vers_outer, vers_inter, 'rows'), :);

            % scaling
            SN_scaled = SN_in .* min(size_voxel);

            % orientation correction
            SN_f1 = zeros(size(SN_in));

            % [enable parallel computing if available]
            if ~license('test', 'Distrib_Computing_Toolbox')
                % (without parallel computing)
                for i = 1:size(SN_in, 1)
                    i_SN = SN_scaled(i, :);
                    i_ver = vers_inter(i, :);
                    i_endPoint_SN = i_ver + i_SN;
                    i_endPoint_fSN = i_ver - i_SN;
                    [i_distance_endPoint_SN, ~] = pdist2(vers_outer, i_endPoint_SN, 'euclidean', 'Smallest', 1);
                    [i_distance_endPoint_fSN, ~] = pdist2(vers_outer, i_endPoint_fSN, 'euclidean', 'Smallest', 1);
                    if i_distance_endPoint_SN > i_distance_endPoint_fSN
                        SN_f1(i, :) = -i_SN;
                    else
                        SN_f1(i, :) = i_SN;
                    end
                end
            elseif isempty(gcp("nocreate"))
                % (same code with parallel computing)
                parpool;
                parfor i = 1:size(SN_in, 1)
                    i_SN = SN_scaled(i, :);
                    i_ver = vers_inter(i, :);
                    i_endPoint_SN = i_ver + i_SN;
                    i_endPoint_fSN = i_ver - i_SN;

                    [i_distance_endPoint_SN, ~] = pdist2(vers_outer, i_endPoint_SN, 'euclidean', 'Smallest', 1);
                    [i_distance_endPoint_fSN, ~] = pdist2(vers_outer, i_endPoint_fSN, 'euclidean', 'Smallest', 1);

                    if i_distance_endPoint_SN > i_distance_endPoint_fSN
                        SN_f1(i, :) = -i_SN;
                    else
                        SN_f1(i, :) = i_SN;
                    end
                end
            else
                % (same code with parallel computing)
                parfor i = 1:size(SN_in, 1)
                    i_SN = SN_scaled(i, :);
                    i_ver = vers_inter(i, :);
                    i_endPoint_SN = i_ver + i_SN;
                    i_endPoint_fSN = i_ver - i_SN;

                    [i_distance_endPoint_SN, ~] = pdist2(vers_outer, i_endPoint_SN, 'euclidean', 'Smallest', 1);
                    [i_distance_endPoint_fSN, ~] = pdist2(vers_outer, i_endPoint_fSN, 'euclidean', 'Smallest', 1);

                    if i_distance_endPoint_SN > i_distance_endPoint_fSN
                        SN_f1(i, :) = -i_SN;
                    else
                        SN_f1(i, :) = i_SN;
                    end
                end
            end

            % internal checkpoint for the pause/resume and stop function
            CMT_checkPauseStopStatus(app);
            % ------------------------------------------------------------------------------------------


            %% Step 2: majority voting - flip the SN if the majority of its neighbors are in opposite direction
            % ------------------------------------------------------------------------------------------
            % get all non-zero SNs
            SN_nonZero = SN_f1(~ismember(SN_f1, [0, 0, 0], 'row'), :);

            % get coordinates of voxels with non-zero estimated SN
            coor_nonZeroSN = vers_inter(~ismember(SN_f1, [0, 0, 0], 'row'), :);

            % orientation correction
            SN_f2 = zeros(size(SN_f1));
            for j = 1:size(SN_f1,1)
                j_SN = SN_f1(j,:);
                j_ver = vers_inter(j, :);

                if ~isequal(j_SN, [0,0,0])
                    % find neighbors of the i-the vertex
                    [~, idx_neigh] = pdist2(coor_nonZeroSN, j_ver, 'euclidean', 'Smallest', n_neigh);
                    j_SN_f1_neigh = SN_nonZero(idx_neigh, :);

                    % majority voting: flip the SN if the majority of its neighbors are in opposite direction
                    inner_product = dot(repmat(j_SN, n_neigh, 1), j_SN_f1_neigh, 2);
                    num_sameOrientation = sum(inner_product>0) - 1; % exclude the i-th SN itself
                    if num_sameOrientation > floor((n_neigh-1)/2)
                        SN_f2(j,:) = j_SN;
                    else
                        SN_f2(j,:) = -j_SN;
                    end
                end
            end

            % internal checkpoint for the pause/resume and stop function
            CMT_checkPauseStopStatus(app);
            % ------------------------------------------------------------------------------------------

            SN_out = SN_f2;

        end


        function CMT_iv_suggestImgSize(app)
            % size(feature maps in bottleneck) = 1/vxm_downScale * size(input image)
            vxm_downScale = 2^length(str2num(app.config_vxm_encoderArchi)); %#ok<*ST2NM>
            remainders = rem(app.config_vxm_imgSize_out, vxm_downScale);
            % [x]
            if remainders(1)~=0
                if app.config_vxm_imgSize_out(1,1)-remainders(1)~=0
                    if remainders(1)>=vxm_downScale/2
                        suggestX = app.config_vxm_imgSize_out(1,1)-remainders(1)+vxm_downScale;
                    else
                        suggestX = app.config_vxm_imgSize_out(1,1)-remainders(1);
                    end
                else
                    suggestX = vxm_downScale;
                end
            else
                suggestX = app.config_vxm_imgSize_out(1,1);
            end
            suggestX_s = num2str(suggestX);

            % [y]
            if remainders(2)~=0
                if app.config_vxm_imgSize_out(1,2)-remainders(2)~=0
                    if remainders(2)>=vxm_downScale/2
                        suggestY = app.config_vxm_imgSize_out(1,2)-remainders(2)+vxm_downScale;
                    else
                        suggestY = app.config_vxm_imgSize_out(1,2)-remainders(2);
                    end
                else
                    suggestY = vxm_downScale;
                end
            else
                suggestY = app.config_vxm_imgSize_out(1,2);
            end
            suggestY_s = num2str(suggestY);

            % [z]
            if remainders(3)~=0
                if app.config_vxm_imgSize_out(1,3)-remainders(3)~=0
                    if remainders(3)>=vxm_downScale/2
                        suggestZ = app.config_vxm_imgSize_out(1,3)-remainders(3)+vxm_downScale;
                    else
                        suggestZ = app.config_vxm_imgSize_out(1,3)-remainders(3);
                    end
                else
                    suggestZ = vxm_downScale;
                end
            else
                suggestZ = app.config_vxm_imgSize_out(1,3);
            end
            suggestZ_s = num2str(suggestZ);

            message = sprintf('The cropped image size is unresonable. It should be divied by %s. \n Suggestion: \n [%s, %s, %s]',...
                num2str(vxm_downScale), suggestX_s, suggestY_s, suggestZ_s);
            uialert(app.CartiMorphToolboxUIFigure, message, 'Error');

            % reset cropped image size
            app.config_vxm_imgSize_out = [suggestX, suggestY, suggestZ];
            app.EditField_iv_CroppedImageSizeX.Value = suggestX_s;
            app.EditField_iv_CroppedImageSizeY.Value = suggestY_s;
            app.EditField_iv_CroppedImageSizeZ.Value = suggestZ_s;
            app.EditField_vi_CroppedImageSizeX.Value = suggestX_s;
            app.EditField_vi_CroppedImageSizeY.Value = suggestY_s;
            app.EditField_vi_CroppedImageSizeZ.Value = suggestZ_s;
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Set default values

            % pipeline options
            app.opt_CMT_init = true;
            app.opt_CondaEnvSetup = app.CheckBox_i.Value;
            app.opt_imgStandardization = app.CheckBox_ii.Value;
            app.opt_nnUNet_training = app.CheckBox_iii.Value;
            app.opt_nnUNet_planning_preprocessing = app.CheckBox_iii_prepro.Value;
            app.opt_nnUNet_training_begin = app.CheckBox_iii_train.Value;
            selectedButton_iii = app.ButtonGroup_iii.SelectedObject;
            if strcmp(selectedButton_iii.Text, 'Train from scratch')
                app.opt_nnUNet_training_scratch = true;
            else
                app.opt_nnUNet_training_scratch = false;
            end
            app.opt_vxm_training = app.CheckBox_iv.Value;
            app.opt_vxm_preprocessing = app.CheckBox_iv_prepro.Value;
            app.opt_vxm_training_begin = app.CheckBox_iv_train.Value;
            if app.CheckBox_iv.Value
                app.opt_vxm_training_scratch = true;
            else
                app.opt_vxm_training_scratch = false;
            end
            app.opt_nnUNet_inference = app.CheckBox_v.Value;
            app.opt_vxm_inference = app.CheckBox_vi.Value;
            app.opt_quantification = app.CheckBox_vii.Value;
            app.opt_quant_CTM = app.CheckBox_vii_CTM.Value;
            app.opt_quant_parcellation = app.CheckBox_vii_parcellation.Value;
            app.opt_quant_regionalAnalysis = app.CheckBox_vii_regionalAnalysis.Value;

            % [nnUNet configuration]
            % nnUNet fold
            app.config_nnUNet_fold = 'all'; % built-in default value
            % network architecture
            app.config_nnUNet_models = '3dF'; % built-in default value
            app.config_nnUNet_model_archi = "3d_fullres"; % built-in default value
            % GPU
            app.config_nnUNet_GPU = app.EditField_iii_GPU.Value;
            % training epoch
            app.config_nnUNet_epochs = str2double(app.EditField_iii_epoch.Value);

            % [VoxelMorph configuration]
            % batch size
            app.config_vxm_batchSize = 1; % built-in default value
            % image similarity loss
            app.ButtonGroup_Loss_iv.SelectedObject = app.Button_MSE_LNCC_iv;
            app.config_vxm_imgLoss = "MSE+LNCC";
            % GPU
            app.config_vxm_GPU = app.EditField_iv_GPU.Value;
            % training epoch
            app.config_vxm_epochs = str2double(app.EditField_iv_epoch.Value);
            % network architecture
            selectedButton = app.ButtonGroup_networkWidth.SelectedObject;
            app.config_vxm_encoderArchi_base = [16 32 32 32];
            app.config_vxm_decoderArchi_base = [32 32 32 32 32 16 16];
            if strcmp(selectedButton.Text, 'x1')
                app.config_vxm_encoderArchi = num2str(app.config_vxm_encoderArchi_base);
                app.config_vxm_decoderArchi = num2str(app.config_vxm_decoderArchi_base);
            elseif strcmp(selectedButton.Text, 'x2')
                app.config_vxm_encoderArchi = num2str(app.config_vxm_encoderArchi_base .* 2);
                app.config_vxm_decoderArchi = num2str(app.config_vxm_decoderArchi_base .* 2);
            elseif strcmp(selectedButton.Text, 'x3')
                app.config_vxm_encoderArchi = num2str(app.config_vxm_encoderArchi_base .* 3);
                app.config_vxm_decoderArchi = num2str(app.config_vxm_decoderArchi_base .* 3);
            end

            % [other configuration]
            % inference GPU
            app.config_inference_GPU = app.EditField_v_GPU.Value;
            % maximum knee articular cartilage thickness
            app.config_humanMaxThickness = 7; % built-in default value
            % ccLFC/ccMFC definition
            app.config_CMT_ccPercentage = 0.6; % built-in default value
            % used to calculate the parameter: neigh = #voxels / n_ROI (for SN-based method only)
            app.config_numROI_FC = 1000; % built-in default value
            app.config_numROI_mTC = 200; % built-in default value
            app.config_numROI_lTC = 200; % built-in default value

            % paths
            app.path_bash_bin = '/bin/bash'; % built-in default value
        end

        % Button pushed function: Button_selectpathCMT
        function Button_selectpathCMTPushed(app, event)
            % freeze the button
            app.Button_selectpathCMT.Enable = "off";

            % select path
            message = ['Select the base folder of the CartiMorph Toolbox. ',...
                'All results will be saved under this folder. '];
            path = uigetdir([], message);
            if path==0
                app.EditField_pathCMT.Value = '';
                app.dir_base = '';
            elseif isempty(app.EditField_ProjectID.Value)
                app.EditField_pathCMT.Value = path;
                app.dir_base = path;
            else
                app.EditField_pathCMT.Value = path;
                app.dir_base = path;
                % check whether there are projects with the same specified ID in 3 subfolders
                CMT_checkProjID4nnUNet(app);
            end

            % reset the button
            app.Button_selectpathCMT.Enable = "on";
        end

        % Button pushed function: Button_selectpathConda
        function Button_selectpathCondaPushed(app, event)
            % freeze the button
            app.Button_selectpathConda.Enable = "off";
            % select path
            path = uigetdir([],'Select the folder of Conda/Miniconda.');
            if path~=0
                app.EditField_pathConda.Value = path;
                app.dir_conda_base = path;
            else
                app.EditField_pathConda.Value = '';
                app.dir_conda_base = '';
            end
            % reset the button
            app.Button_selectpathConda.Enable = "on";
        end

        % Value changed function: EditField_Femur
        function EditField_FemurValueChanged(app, event)
            value = app.EditField_Femur.Value;
            if isnan(str2double(value)) || ~isreal(str2double(value)) % if user enter a non-number string
                uialert(app.CartiMorphToolboxUIFigure, 'You need to enter a number.', 'Error');
                app.EditField_Femur.Value = '';
                app.config_ROI_name{2} = 'femur';
                app.config_ROI_label{2} = '';
            else
                app.config_ROI_name{2} = 'femur';
                app.config_ROI_label{2} = value;
            end
        end

        % Value changed function: EditField_FemoralCartilage
        function EditField_FemoralCartilageValueChanged(app, event)
            value = app.EditField_FemoralCartilage.Value;
            if isnan(str2double(value)) || ~isreal(value) % if user enter a non-number string
                uialert(app.CartiMorphToolboxUIFigure, 'You need to enter a number.', 'Error');
                app.EditField_FemoralCartilage.Value = '';
                app.config_ROI_name{3} = 'femoral cartilage';
                app.config_ROI_label{3} = '';
            else
                app.config_ROI_name{3} = 'femoral cartilage';
                app.config_ROI_label{3} = value;
            end
        end

        % Value changed function: EditField_Tibia
        function EditField_TibiaValueChanged(app, event)
            value = app.EditField_Tibia.Value;
            if isnan(str2double(value)) || ~isreal(value) % if user enter a non-number string
                uialert(app.CartiMorphToolboxUIFigure, 'You need to enter a number.', 'Error');
                app.EditField_Tibia.Value = '';
                app.config_ROI_name{4} = 'tibia';
                app.config_ROI_label{4} = '';
            else
                app.config_ROI_name{4} = 'tibia';
                app.config_ROI_label{4} = value;
            end
        end

        % Value changed function: EditField_TibialCartilagemedial
        function EditField_TibialCartilagemedialValueChanged(app, event)
            value = app.EditField_TibialCartilagemedial.Value;
            if isnan(str2double(value)) || ~isreal(value) % if user enter a non-number string
                uialert(app.CartiMorphToolboxUIFigure, 'You need to enter a number.', 'Error');
                app.EditField_TibialCartilagemedial.Value = '';
                app.config_ROI_name{5} = 'medial tibial cartilage';
                app.config_ROI_label{5} = '';
            else
                app.config_ROI_name{5} = 'medial tibial cartilage';
                app.config_ROI_label{5} = value;
            end
        end

        % Value changed function: EditField_TibialCartilagelateral
        function EditField_TibialCartilagelateralValueChanged(app, event)
            value = app.EditField_TibialCartilagelateral.Value;
            if isnan(str2double(value)) || ~isreal(value) % if user enter a non-number string
                uialert(app.CartiMorphToolboxUIFigure, 'You need to enter a number.', 'Error');
                app.EditField_TibialCartilagelateral.Value = '';
                app.config_ROI_name{6} = 'lateral tibial cartilage';
                app.config_ROI_label{6} = '';
            else
                app.config_ROI_name{6} = 'lateral tibial cartilage';
                app.config_ROI_label{6} = value;
            end
        end

        % Value changed function: EditField_background
        function EditField_backgroundValueChanged(app, event)
            value = app.EditField_background.Value;
            if isnan(str2double(value)) || ~isreal(value) % if user enter a non-number string
                uialert(app.CartiMorphToolboxUIFigure, 'You need to enter a number.', 'Error');
                app.EditField_background.Value = '';
                app.config_ROI_name{1} = 'background';
                app.config_ROI_label{1} = '';
            else
                app.config_ROI_name{1} = 'background';
                app.config_ROI_label{1} = value;
            end
        end

        % Button pushed function: Button_loadCSV
        function Button_loadCSVPushed(app, event)
            % set path
            [file, path] = uigetfile('*.csv','Select a .csv file with the reqiured knee side information for each subject');
            if path~=0
                full_path = fullfile(path, file);
                app.EditField_csvPath.Value = full_path;
                app.path_CMT_kneeSideInfo = full_path;
                app.config_kneeSideInfo = readtable(app.path_CMT_kneeSideInfo, "ReadRowNames",false, "ReadVariableNames",false, "Delimiter",",");
            else
                app.EditField_csvPath.Value = '';
                app.path_CMT_kneeSideInfo = '';
            end
        end

        % Value changed function: EditField_ProjectID
        function EditField_ProjectIDValueChanged(app, event)
            value = app.EditField_ProjectID.Value;
            if isnan(str2double(value)) || ~isreal(str2double(value)) || length(value)>3
                uialert(app.CartiMorphToolboxUIFigure, 'You need to enter a 3-digit number', 'Error');
                app.EditField_ProjectID.Value = '';
                app.config_nnUNet_ID = '';
            elseif isempty(app.EditField_pathCMT.Value)
                app.config_nnUNet_ID = num2str(str2double(value),'%03.f');
                if ~isempty(app.config_nnUNet_task)
                    app.config_nnUNet_taskIDName = strcat('Task',  app.config_nnUNet_ID, '_', app.config_nnUNet_task);
                end
            else
                app.config_nnUNet_ID = num2str(str2double(value),'%03.f');
                % check whether there are projects with the same specified ID in 3 subfolders
                CMT_checkProjID4nnUNet(app);
            end

        end

        % Value changed function: EditField_ProjectCodeName
        function EditField_ProjectCodeNameValueChanged(app, event)
            app.config_nnUNet_task = app.EditField_ProjectCodeName.Value;
            if ~isempty(app.config_nnUNet_ID)
                app.config_nnUNet_taskIDName = strcat('Task',  app.config_nnUNet_ID, '_', app.config_nnUNet_task);
            end
            % toolbox should be re-initialize
            app.opt_CMT_init = true;
        end

        % Value changed function: EditField_pathCMT
        function EditField_pathCMTValueChanged(app, event)
            value = app.EditField_pathCMT.Value;
            if ~isfolder(value)
                uialert(app.CartiMorphToolboxUIFigure, 'Folder does not exist', 'Error');
                app.EditField_pathCMT.Value = '';
                app.dir_base = '';
            elseif isempty(app.EditField_ProjectID.Value)
                app.dir_base = value;
            else
                app.dir_base = value;
                % check whether there are projects with the same specified ID in 3 subfolders
                CMT_checkProjID4nnUNet(app);
            end
        end

        % Value changed function: EditField_pathConda
        function EditField_pathCondaValueChanged(app, event)
            value = app.EditField_pathConda.Value;
            if ~isfolder(value)
                uialert(app.CartiMorphToolboxUIFigure, 'Folder does not exist', 'Error');
                app.EditField_pathConda.Value = '';
                app.dir_conda_base = '';
            else
                app.dir_conda_base = value;
            end
        end

        % Value changed function: EditField_imgTr
        function EditField_imgTrValueChanged(app, event)
            value = app.EditField_imgTr.Value;
            if isempty(value)
                app.dir_rawData_imageTr = '';
            elseif ~isfolder(value)
                uialert(app.CartiMorphToolboxUIFigure, 'Folder does not exist', 'Error');
                app.EditField_imgTr.Value = '';
                app.dir_rawData_imageTr = '';
            else
                app.dir_rawData_imageTr = value;
            end
        end

        % Button pushed function: Button_selectPath_imgTr
        function Button_selectPath_imgTrPushed(app, event)
            % freeze the button
            app.Button_selectPath_imgTr.Enable = "off";
            % select path
            path = uigetdir([],'Select the folder of training images.');
            if path~=0
                app.EditField_imgTr.Value = path;
                app.dir_rawData_imageTr = path;
            else
                app.EditField_imgTr.Value = '';
                app.dir_rawData_imageTr = '';
            end
            % reset the button
            app.Button_selectPath_imgTr.Enable = "on";
        end

        % Value changed function: EditField_imgTs
        function EditField_imgTsValueChanged(app, event)
            value = app.EditField_imgTs.Value;
            if isempty(value)
                app.dir_rawData_imageTs = '';
            elseif ~isfolder(value)
                uialert(app.CartiMorphToolboxUIFigure, 'Folder does not exist', 'Error');
                app.EditField_imgTs.Value = '';
                app.dir_rawData_imageTs = '';
            else
                app.dir_rawData_imageTs = value;
            end
        end

        % Button pushed function: Button_selectPath_imgTs
        function Button_selectPath_imgTsPushed(app, event)
            % freeze the button
            app.Button_selectPath_imgTs.Enable = "off";
            % select path
            path = uigetdir([],'Select the folder of testing images.');
            if path~=0
                app.EditField_imgTs.Value = path;
                app.dir_rawData_imageTs = path;
            else
                app.EditField_imgTs.Value = '';
                app.dir_rawData_imageTs = '';
            end
            % reset the button
            app.Button_selectPath_imgTs.Enable = "on";
        end

        % Value changed function: EditField_labelTr
        function EditField_labelTrValueChanged(app, event)
            value = app.EditField_labelTr.Value;
            if isempty(value)
                app.dir_rawData_labelTr = '';
            elseif ~isfolder(value)
                uialert(app.CartiMorphToolboxUIFigure, 'Folder does not exist', 'Error');
                app.EditField_labelTr.Value = '';
                app.dir_rawData_labelTr = '';
            else
                app.dir_rawData_labelTr = value;
            end
        end

        % Button pushed function: Button_selectPath_labelTr
        function Button_selectPath_labelTrPushed(app, event)
            % freeze the button
            app.Button_selectPath_labelTr.Enable = "off";
            % select path
            path = uigetdir([],'Select the folder of training labels.');
            if path~=0
                app.EditField_labelTr.Value = path;
                app.dir_rawData_labelTr = path;
            else
                app.EditField_labelTr.Value = '';
                app.dir_rawData_labelTr = '';
            end
            % reset the button
            app.Button_selectPath_labelTr.Enable = "on";
        end

        % Value changed function: EditField_labelTs
        function EditField_labelTsValueChanged(app, event)
            value = app.EditField_labelTs.Value;
            if isempty(value)
                app.dir_rawData_labelTs = '';
            elseif ~isfolder(value)
                uialert(app.CartiMorphToolboxUIFigure, 'Folder does not exist', 'Error');
                app.EditField_labelTs.Value = '';
                app.dir_rawData_labelTs = '';
            else
                app.dir_rawData_labelTs = value;
            end
        end

        % Button pushed function: Button_selectPath_labelTs
        function Button_selectPath_labelTsPushed(app, event)
            % freeze the button
            app.Button_selectPath_labelTs.Enable = "off";
            % select path
            path = uigetdir([],'Select the folder of testing labels.');
            if path~=0
                app.EditField_labelTs.Value = path;
                app.dir_rawData_labelTs = path;
            else
                app.EditField_labelTs.Value = '';
                app.dir_rawData_labelTs = '';
            end
            % reset the button
            app.Button_selectPath_labelTs.Enable = "on";
        end

        % Value changed function: EditField_imgInference
        function EditField_imgInferenceValueChanged(app, event)
            value = app.EditField_imgInference.Value;
            if isempty(value)
                app.dir_rawData_imgInference = '';
            elseif ~isfolder(value)
                uialert(app.CartiMorphToolboxUIFigure, 'Folder does not exist', 'Error');
                app.EditField_imgInference.Value = '';
                app.dir_rawData_imgInference = '';
            else
                app.dir_rawData_imgInference = value;
            end
        end

        % Button pushed function: Button_selectPath_imgInference
        function Button_selectPath_imgInferencePushed(app, event)
            % freeze the button
            app.Button_selectPath_imgInference.Enable = "off";
            % select
            path = uigetdir([],'Select the folder of inference images.');
            if path~=0
                app.EditField_imgInference.Value = path;
                app.dir_rawData_imgInference = path;
            else
                app.EditField_imgInference.Value = '';
                app.dir_rawData_imgInference = '';
            end
            % reset the button
            app.Button_selectPath_imgInference.Enable = "on";
        end

        % Value changed function: EditField_csvPath
        function EditField_csvPathValueChanged(app, event)
            full_path = app.EditField_csvPath.Value;
            if ~isfile(full_path)
                uialert(app.CartiMorphToolboxUIFigure, 'File does not exist.', 'Error');
                app.EditField_csvPath.Value = '';
                app.path_CMT_kneeSideInfo = '';
            else
                app.path_CMT_kneeSideInfo = full_path;
                app.config_kneeSideInfo = readtable(app.path_CMT_kneeSideInfo, "ReadRowNames",false, "ReadVariableNames",false, "Delimiter",",");
            end
        end

        % Value changed function: CheckBox_iii
        function CheckBox_iiiValueChanged(app, event)
            value = app.CheckBox_iii.Value;
            if ~value
                % iii
                app.Image_iii_Info.Visible = "off";
                app.Lamp_iii.Visible = "off";
                app.CheckBox_iii.Enable = "off";
                app.Label_iii.Enable = "off";
                % iii-prepro
                app.Lamp_iii_prepro.Visible = "off";
                app.CheckBox_iii_prepro.Value = false;
                app.CheckBox_iii_prepro.Enable = "off";
                app.Label_iii_prepro.Enable = "off";
                % iii-train
                app.Lamp_iii_train.Visible = "off";
                app.CheckBox_iii_train.Value = false;
                app.CheckBox_iii_train.Enable = "off";
                app.Label_iii_train.Enable = "off";
                % iii-GPU
                app.EditField_iii_GPU.Value = '';
                app.EditField_iii_GPU.Enable = "off";
                % iii-trainingType
                app.opt_nnUNet_training_scratch = false;
                app.ButtonGroup_iii.Visible = "off";
                % iii-epoch
                app.EditFieldLabel_iii_epoch.Enable = "off";
                app.EditField_iii_epoch.Enable = "off";
                app.EditField_iii_epoch.Value = '';
                app.Image_iii_epochInfo.Visible = "off";
                % iii2
                app.Lamp_iii2.Visible = "on";
                app.CheckBox_iii2.Enable = "on";
                app.CheckBox_iii2.Value = true;
                app.Label_iii2.Enable = "on";
                app.Image_iii2_Info.Visible = "on";
                % iii2-deploy
                app.Lamp_iii2_deploy.Visible = "on";
                app.opt_nnUNet_training = false;
                app.CheckBox_iii2_deploy.Enable = "on";
                app.CheckBox_iii2_deploy.Value = true;
                app.Label_iii2_deploy.Enable = "on";
                % iii2-finetune
                app.Lamp_iii2_finetune.Visible = "off";
                app.CheckBox_iii2_finetune.Enable = "on";
                app.CheckBox_iii2_finetune.Value = false;
                app.Label_iii2_finetune.Enable = "on";
                % iii2-prepro
                app.Lamp_iii2_prepro.Visible = "off";
                app.opt_nnUNet_planning_preprocessing = false;
                app.CheckBox_iii2_prepro.Enable = "on";
                app.CheckBox_iii2_prepro.Value = false;
                app.Label_iii2_prepro.Enable = "on";
                % iii2-finetuning
                app.Lamp_iii2_finetuning.Visible = "off";
                app.opt_nnUNet_training_begin = false; % this line is important
                app.CheckBox_iii2_finetuning.Enable = "on";
                app.CheckBox_iii2_finetuning.Value = false;
                app.Label_iii2_finetuning.Enable = "on";
                % iii2-GPU
                app.EditField_iii2_GPU.Enable = "on";
                app.EditField_iii2_GPU.Value = '';
                % iii2-epoch
                app.EditFieldLabel_iii2_epoch.Enable = "off";
                app.EditField_iii2_epoch.Enable = "off";
                app.EditField_iii2_epoch.Value = '';
                % iii2-loadModel
                app.Label_iii2_Model.Enable = "on";
                app.Button_iii2_loadmodel.Enable = "on";
                app.Lamp_iii2_loadModel.Visible = "on";
                if app.CheckBox_iv2_deploy.Value
                    % folders for training images and labels
                    app.EditField_imgTr.Enable = "off";
                    app.EditFieldLabel_imgTr.Enable = "off";
                    app.Button_selectPath_imgTr.Enable = "off";
                    app.EditField_imgTs.Enable = "off";
                    app.EditFieldLabel_imgTs.Enable = "off";
                    app.Button_selectPath_imgTs.Enable = "off";
                    app.EditField_labelTr.Enable = "off";
                    app.EditFieldLabel_labelTr.Enable = "off";
                    app.Button_selectPath_labelTr.Enable = "off";
                    app.EditField_labelTs.Enable = "off";
                    app.EditFieldLabel_labelTs.Enable = "off";
                    app.Button_selectPath_labelTs.Enable = "off";
                    % v
                    app.Lamp_v.Visible = "on";
                    app.opt_nnUNet_inference = true;
                    app.CheckBox_v.Value = true;
                    app.EditField_v_GPU.Visible = "on";
                    % folder for inference images
                    app.EditFieldLabel_imgInference.Enable = "on";
                    app.EditField_imgInference.Enable = "on";
                    app.Button_selectPath_imgInference.Enable = "on";
                end
                % freeze "Segmentation Label"
                app.EditField_Femur.Enable = "off";
                app.EditField_FemoralCartilage.Enable = "off";
                app.EditField_Tibia.Enable = "off";
                app.EditField_TibialCartilagemedial.Enable = "off";
                app.EditField_TibialCartilagelateral.Enable = "off";
                app.EditField_background.Enable = "off";
                % freeze "Image Size"
                app.EditField_ii_imgSizeX.Value = '';
                app.EditField_ii_imgSizeY.Value = '';
                app.EditField_ii_imgSizeZ.Value = '';
                app.EditField_ii_imgSizeX.Enable = "off";
                app.EditField_ii_imgSizeY.Enable = "off";
                app.EditField_ii_imgSizeZ.Enable = "off";
            end
        end

        % Value changed function: CheckBox_iv
        function CheckBox_ivValueChanged(app, event)
            value = app.CheckBox_iv.Value;
            if ~value
                % iv
                app.Image_iv_Info.Visible = "off";
                app.Lamp_iv.Visible = "off";
                app.CheckBox_iv.Enable = "off";
                app.Label_iv.Enable = "off";
                app.opt_vxm_training_scratch = false;
                % iv-prepro
                app.Lamp_iv_prepro.Visible = "off";
                app.CheckBox_iv_prepro.Value = false;
                app.CheckBox_iv_prepro.Enable = "off";
                app.Label_iv_prepro.Enable = "off";
                % iv-croppedImageSize
                app.Image_iv_Info_croppedImageSize.Visible = "off";
                app.EditField_iv_CroppedImageSizeX.Enable = "off";
                app.EditField_iv_CroppedImageSizeY.Enable = "off";
                app.EditField_iv_CroppedImageSizeZ.Enable = "off";
                app.EditFieldLabel_iv_CroppedImageSize.Enable = "off";
                app.EditField_iv_CroppedImageSizeX.Value = '';
                app.EditField_iv_CroppedImageSizeY.Value = '';
                app.EditField_iv_CroppedImageSizeZ.Value = '';
                % iv-train
                app.Lamp_iv_train.Visible = "off";
                app.CheckBox_iv_train.Value = false;
                app.CheckBox_iv_train.Enable = "off";
                app.Label_iv_train.Enable = "off";
                % iv-GPU
                app.EditField_iv_GPU.Value = '';
                app.EditField_iv_GPU.Enable = "off";
                % iv-epoch
                app.EditFieldLabel_iv_epoch.Enable = "off";
                app.EditField_iv_epoch.Enable = "off";
                app.EditField_iv_epoch.Value = '';
                % iv-networkWidth
                app.Label_NetworkWidth.Enable = "off";
                app.Button_x1.Enable = "off";
                app.Button_x2.Enable = "off";
                app.Button_x4.Enable = "off";
                app.Image_iv_networkInfo.Visible = "off";
                % iv-LossFunction
                app.Label_LossFunction_iv.Enable = "off";
                app.Button_MSE_iv.Enable = "off";
                app.Button_LNCC_iv.Enable = "off";
                app.Button_MSE_LNCC_iv.Enable = "off";
                % iv2
                app.Lamp_iv2.Visible = "on";
                app.CheckBox_iv2.Enable = "on";
                app.CheckBox_iv2.Value = true;
                app.Label_iv2.Enable = "on";
                app.Image_iv2_Info.Visible = "on";
                % iv2-deploy
                app.Lamp_iv2_deploy.Visible = "on";
                app.opt_vxm_training = false;
                app.CheckBox_iv2_deploy.Enable ="on";
                app.CheckBox_iv2_deploy.Value = true;
                app.Label_iv2_deploy.Enable = "on";
                % iv2-finetune
                app.Lamp_iv2_finetune.Visible = "off";
                app.CheckBox_iv2_finetune.Enable = "on";
                app.CheckBox_iv2_finetune.Value = false;
                app.Label_iv2_finetune.Enable = "on";
                % iv2-prepro
                app.Lamp_iv2_prepro.Visible = "off";
                app.opt_vxm_preprocessing = false;
                app.CheckBox_iv2_prepro.Enable = "on";
                app.CheckBox_iv2_prepro.Value = false;
                app.Label_iv2_prepro.Enable = "on";
                % iv2-croppedImageSize
                app.EditFieldLabel_iv2_CroppedImageSize.Enable = "off";
                app.EditField_iv2_CroppedImageSizeX.Enable = "off";
                app.EditField_iv2_CroppedImageSizeY.Enable = "off";
                app.EditField_iv2_CroppedImageSizeZ.Enable = "off";
                % iv2-finetuning
                app.Lamp_iv2_finetuning.Visible = "off";
                app.opt_vxm_training_begin = false;
                app.CheckBox_iv2_finetuning.Enable = "on";
                app.CheckBox_iv2_finetuning.Value = false;
                app.Label_iv2_finetuning.Enable = "on";
                % iv2-GPU
                app.EditField_iv2_GPU.Enable = "on";
                app.EditField_iv2_GPU.Value = '';
                % iv2-epoch
                app.EditFieldLabel_iv2_epoch.Enable = "off";
                app.EditField_iv2_epoch.Value = '';
                % iv2-loadModel
                app.Label_iv2_Model.Enable = "on";
                app.Button_iv2_loadmodel.Enable = "on";
                app.Lamp_iv2_loadModel.Visible = "on";
                if app.CheckBox_iii2_deploy.Value
                    % folders for training images and labels
                    app.EditField_imgTr.Enable = "off";
                    app.EditFieldLabel_imgTr.Enable = "off";
                    app.Button_selectPath_imgTr.Enable = "off";
                    app.EditField_imgTs.Enable = "off";
                    app.EditFieldLabel_imgTs.Enable = "off";
                    app.Button_selectPath_imgTs.Enable = "off";
                    app.EditField_labelTr.Enable = "off";
                    app.EditFieldLabel_labelTr.Enable = "off";
                    app.Button_selectPath_labelTr.Enable = "off";
                    app.EditField_labelTs.Enable = "off";
                    app.EditFieldLabel_labelTs.Enable = "off";
                    app.Button_selectPath_labelTs.Enable = "off";
                    % v
                    app.Lamp_v.Visible = "on";
                    app.opt_nnUNet_inference = true;
                    app.CheckBox_v.Value = true;
                    app.EditField_v_GPU.Visible = "on";
                    % folder for inference images
                    app.EditFieldLabel_imgInference.Enable = "on";
                    app.EditField_imgInference.Enable = "on";
                    app.Button_selectPath_imgInference.Enable = "on";
                end
            end
        end

        % Value changed function: CheckBox_iii_prepro
        function CheckBox_iii_preproValueChanged(app, event)
            value = app.CheckBox_iii_prepro.Value;
            if ~value
                % iii-prepro
                app.Lamp_iii_prepro.Visible = "off";
                app.CheckBox_iii_prepro.Enable = "off";
                app.Label_iii_prepro.Enable = "off";
                % iii-train
                app.Lamp_iii_train.Visible = "off";
                app.opt_nnUNet_training = false;
                app.CheckBox_iii_train.Value = false;
                app.CheckBox_iii_train.Enable = "off";
                app.Label_iii_train.Enable = "off";
                % iii-GPU
                app.EditField_iii_GPU.Value = '';
                app.EditField_iii_GPU.Enable = "off";
                % iii-trainingType
                app.opt_nnUNet_training_scratch = false;
                app.ButtonGroup_iii.Visible = "off";
                % iii-epoch
                app.EditFieldLabel_iii_epoch.Enable = "off";
                app.EditField_iii_epoch.Enable = "off";
                app.EditField_iii_epoch.Value = '';
                app.Image_iii_epochInfo.Visible = "off";
                % iii
                app.Image_iii_Info.Visible = "off";
                app.Lamp_iii.Visible = "off";
                app.CheckBox_iii.Value = false;
                app.CheckBox_iii.Enable = "off";
                app.Label_iii.Enable = "off";
                % iii2
                app.Lamp_iii2.Visible = "on";
                app.CheckBox_iii2.Enable = "on";
                app.CheckBox_iii2.Value = true;
                app.Label_iii2.Enable = "on";
                app.Image_iii2_Info.Visible = "on";
                % iii2-deploy
                app.Lamp_iii2_deploy.Visible = "on";
                app.opt_nnUNet_training = false;
                app.CheckBox_iii2_deploy.Enable = "on";
                app.CheckBox_iii2_deploy.Value = true;
                app.Label_iii2_deploy.Enable = "on";
                % iii2-finetune
                app.Lamp_iii2_finetune.Visible = "off";
                app.CheckBox_iii2_finetune.Enable = "on";
                app.CheckBox_iii2_finetune.Value = false;
                app.Label_iii2_finetune.Enable = "on";
                % iii2-prepro
                app.Lamp_iii2_prepro.Visible = "off";
                app.opt_nnUNet_planning_preprocessing = false;
                app.CheckBox_iii2_prepro.Enable = "on";
                app.CheckBox_iii2_prepro.Value = false;
                app.Label_iii2_prepro.Enable = "on";
                % iii2-finetuning
                app.Lamp_iii2_finetuning.Visible = "off";
                app.opt_nnUNet_training_begin = false; % this line is important
                app.CheckBox_iii2_finetuning.Enable = "on";
                app.CheckBox_iii2_finetuning.Value = false;
                app.Label_iii2_finetuning.Enable = "on";
                % iii2-GPU
                app.EditField_iii2_GPU.Enable = "on";
                app.EditField_iii2_GPU.Value = '';
                % iii2-epoch
                app.EditFieldLabel_iii2_epoch.Enable = "off";
                app.EditField_iii2_epoch.Enable = "off";
                app.EditField_iii2_epoch.Value = '';
                % iii2-loadModel
                app.Label_iii2_Model.Enable = "on";
                app.Button_iii2_loadmodel.Enable = "on";
                app.Lamp_iii2_loadModel.Visible = "on";
                if app.CheckBox_iv2_deploy.Value
                    % folders for training images and labels
                    app.EditField_imgTr.Enable = "off";
                    app.EditFieldLabel_imgTr.Enable = "off";
                    app.Button_selectPath_imgTr.Enable = "off";
                    app.EditField_imgTs.Enable = "off";
                    app.EditFieldLabel_imgTs.Enable = "off";
                    app.Button_selectPath_imgTs.Enable = "off";
                    app.EditField_labelTr.Enable = "off";
                    app.EditFieldLabel_labelTr.Enable = "off";
                    app.Button_selectPath_labelTr.Enable = "off";
                    app.EditField_labelTs.Enable = "off";
                    app.EditFieldLabel_labelTs.Enable = "off";
                    app.Button_selectPath_labelTs.Enable = "off";
                    % v
                    app.Lamp_v.Visible = "on";
                    app.opt_nnUNet_inference = true;
                    app.CheckBox_v.Value = true;
                    app.EditField_v_GPU.Visible = "on";
                    % folder for inference images
                    app.EditFieldLabel_imgInference.Enable = "on";
                    app.EditField_imgInference.Enable = "on";
                    app.Button_selectPath_imgInference.Enable = "on";
                end
                % freeze "Segmentation Label"
                app.EditField_Femur.Enable = "off";
                app.EditField_FemoralCartilage.Enable = "off";
                app.EditField_Tibia.Enable = "off";
                app.EditField_TibialCartilagemedial.Enable = "off";
                app.EditField_TibialCartilagelateral.Enable = "off";
                app.EditField_background.Enable = "off";
                % freeze "Image Size"
                app.EditField_ii_imgSizeX.Value = '';
                app.EditField_ii_imgSizeY.Value = '';
                app.EditField_ii_imgSizeZ.Value = '';
                app.config_nnUNet_imgSize = NaN(1,3);
                app.EditField_ii_imgSizeX.Enable = "off";
                app.EditField_ii_imgSizeY.Enable = "off";
                app.EditField_ii_imgSizeZ.Enable = "off";
            end
        end

        % Value changed function: CheckBox_iii_train
        function CheckBox_iii_trainValueChanged(app, event)
            value = app.CheckBox_iii_train.Value;
            if value
                % iii_train
                app.Lamp_iii_train.Visible = "on";
                app.opt_nnUNet_training_begin = true;
                app.EditField_iii_GPU.Value = app.config_nnUNet_GPU;
                app.ButtonGroup_iii.Visible = "on";
                % iii-epoch
                app.EditFieldLabel_iii_epoch.Enable = "on";
                app.EditField_iii_epoch.Enable = "on";
                app.EditField_iii_epoch.Value = num2str(app.config_nnUNet_epochs);
                app.Image_iii_epochInfo.Visible = "on";
            else
                % iii_train
                app.Lamp_iii_train.Visible = "off";
                app.opt_nnUNet_training_begin = false;
                app.EditField_iii_GPU.Value = '';
                app.ButtonGroup_iii.Visible = "off";
                % iii-epoch
                app.EditFieldLabel_iii_epoch.Enable = "off";
                app.EditField_iii_epoch.Enable = "off";
                app.EditField_iii_epoch.Value = "";
                app.Image_iii_epochInfo.Visible = "off";
                % v
                if app.CheckBox_v.Value
                    % disable v
                    app.CheckBox_v.Value = false;
                    % v
                    app.Lamp_v.Visible = "off";
                    app.opt_nnUNet_inference = false;
                    app.Image_v_Info.Visible = "off";
                    app.EditFieldLabel_v_GPU.Visible = "off";
                    app.EditField_v_GPU.Visible = "off";
                    % vi
                    app.Lamp_vi.Visible = "off";
                    app.opt_vxm_inference = false;
                    app.CheckBox_vi.Value = false;
                    app.Image_vi_Info.Visible = "off";
                    app.EditFieldLabel_vi_GPU.Visible = "off";
                    app.EditField_vi_GPU.Visible = "off";
                    app.EditField_vi_CroppedImageSizeX.Value = '';
                    app.EditField_vi_CroppedImageSizeY.Value = '';
                    app.EditField_vi_CroppedImageSizeZ.Value = '';
                    % inference images folder
                    app.EditField_imgInference.Value = "";
                    app.EditField_imgInference.Enable = "off";
                    app.EditFieldLabel_imgInference.Enable = "off";
                    app.Button_selectPath_imgInference.Enable = "off";
                    % vii
                    app.Lamp_vii.Visible = "off";
                    app.opt_quantification = false;
                    app.CheckBox_vii.Value = false;
                    app.EditFieldLabel_vi_CroppedImageSize.Enable = "off";
                    % vii-x
                    app.Lamp_vii_CTM.Visible = "off";
                    app.opt_quant_CTM = false;
                    app.CheckBox_vii_CTM.Value = false;
                    app.Lamp_vii_parcellation.Visible = "off";
                    app.opt_quant_parcellation = false;
                    app.CheckBox_vii_parcellation.Value = false;
                    app.Lamp_vii_regionalAnalysis.Visible = "off";
                    app.opt_quant_regionalAnalysis = false;
                    app.CheckBox_vii_regionalAnalysis.Value = false;
                end
            end
        end

        % Value changed function: EditField_iii_GPU
        function EditField_iii_GPUValueChanged(app, event)
            if ~isempty(app.EditField_iii_GPU.Value)
                value = str2double(app.EditField_iii_GPU.Value);
                if isnan(value) || ~isreal(value) % if user enter a string
                    uialert(app.CartiMorphToolboxUIFigure, 'You need to enter a number.', 'Error');
                    % iii_train
                    app.EditField_iii_GPU.Value = '';
                    app.Lamp_iii_train.Visible = "off";
                    app.opt_nnUNet_training_begin = false;
                    app.CheckBox_iii_train.Value = false;
                    app.ButtonGroup_iii.Visible = "off";
                    % iii-epoch
                    app.EditFieldLabel_iii_epoch.Enable = "off";
                    app.EditField_iii_epoch.Enable = "off";
                    app.EditField_iii_epoch.Value = "";
                    app.Image_iii_epochInfo.Visible = "off";
                elseif isinf(value) || floor(value)~=value || value<0 % if user enter a floating number or negative integer
                    uialert(app.CartiMorphToolboxUIFigure, 'You need to enter a positive integer.', 'Error');
                    % iii_train
                    app.EditField_iii_GPU.Value = '';
                    app.Lamp_iii_train.Visible = "off";
                    app.opt_nnUNet_training_begin = false;
                    app.CheckBox_iii_train.Value = false;
                    app.ButtonGroup_iii.Visible = "off";
                    % iii-epoch
                    app.EditFieldLabel_iii_epoch.Enable = "off";
                    app.EditField_iii_epoch.Enable = "off";
                    app.EditField_iii_epoch.Value = "";
                    app.Image_iii_epochInfo.Visible = "off";
                else
                    % iii_train
                    app.config_nnUNet_GPU = app.EditField_iii_GPU.Value;
                    app.Lamp_iii_train.Visible = "on";
                    app.opt_nnUNet_training_begin = true;
                    app.CheckBox_iii_train.Value = true;
                    app.ButtonGroup_iii.Visible = "on";
                    % iii-epoch
                    app.EditFieldLabel_iii_epoch.Enable = "on";
                    app.EditField_iii_epoch.Enable = "on";
                    app.EditField_iii_epoch.Value = num2str(app.config_nnUNet_epochs);
                    app.Image_iii_epochInfo.Visible = "on";
                end
            else
                % iii_train
                app.Lamp_iii_train.Visible = "off";
                app.opt_nnUNet_training_begin = false;
                app.CheckBox_iii_train.Value = false;
                app.ButtonGroup_iii.Visible = "off";
                % iii-epoch
                app.EditFieldLabel_iii_epoch.Enable = "off";
                app.EditField_iii_epoch.Enable = "off";
                app.EditField_iii_epoch.Value = "";
                app.Image_iii_epochInfo.Visible = "off";
                % v
                if app.CheckBox_v.Value
                    % disable v
                    app.CheckBox_v.Value = false;
                    % v
                    app.Lamp_v.Visible = "off";
                    app.opt_nnUNet_inference = false;
                    app.Image_v_Info.Visible = "off";
                    app.EditFieldLabel_v_GPU.Visible = "off";
                    app.EditField_v_GPU.Visible = "off";
                    % vi
                    app.Lamp_vi.Visible = "off";
                    app.opt_vxm_inference = false;
                    app.CheckBox_vi.Value = false;
                    app.Image_vi_Info.Visible = "off";
                    app.EditFieldLabel_vi_GPU.Visible = "off";
                    app.EditField_vi_GPU.Visible = "off";
                    app.EditField_vi_CroppedImageSizeX.Value = '';
                    app.EditField_vi_CroppedImageSizeY.Value = '';
                    app.EditField_vi_CroppedImageSizeZ.Value = '';
                    % inference images folder
                    app.EditField_imgInference.Value = "";
                    app.EditField_imgInference.Enable = "off";
                    app.EditFieldLabel_imgInference.Enable = "off";
                    app.Button_selectPath_imgInference.Enable = "off";
                    % vii
                    app.Lamp_vii.Visible = "off";
                    app.opt_quantification = false;
                    app.CheckBox_vii.Value = false;
                    app.EditFieldLabel_vi_CroppedImageSize.Enable = "off";
                    % vii-x
                    app.Lamp_vii_CTM.Visible = "off";
                    app.opt_quant_CTM = false;
                    app.CheckBox_vii_CTM.Value = false;
                    app.Lamp_vii_parcellation.Visible = "off";
                    app.opt_quant_parcellation = false;
                    app.CheckBox_vii_parcellation.Value = false;
                    app.Lamp_vii_regionalAnalysis.Visible = "off";
                    app.opt_quant_regionalAnalysis = false;
                    app.CheckBox_vii_regionalAnalysis.Value = false;
                end
            end
        end

        % Value changed function: CheckBox_iv_prepro
        function CheckBox_iv_preproValueChanged(app, event)
            value = app.CheckBox_iv_prepro.Value;
            if ~value
                % iv-prepro
                app.Lamp_iv_prepro.Visible = "off";
                app.opt_vxm_preprocessing = false;
                app.CheckBox_iv_prepro.Enable = "off";
                app.Label_iv_prepro.Enable = "off";
                % iv-croppedImageSize
                app.Image_iv_Info_croppedImageSize.Visible = "off";
                app.EditField_iv_CroppedImageSizeX.Enable = "off";
                app.EditField_iv_CroppedImageSizeY.Enable = "off";
                app.EditField_iv_CroppedImageSizeZ.Enable = "off";
                app.EditFieldLabel_iv_CroppedImageSize.Enable = "off";
                app.EditField_iv_CroppedImageSizeX.Value = '';
                app.EditField_iv_CroppedImageSizeY.Value = '';
                app.EditField_iv_CroppedImageSizeZ.Value = '';
                % iv-train
                app.Lamp_iv_train.Visible = "off";
                app.CheckBox_iv_train.Value = false;
                app.CheckBox_iv_train.Enable = "off";
                app.Label_iv_train.Enable = "off";
                % iv-GPU
                app.EditField_iv_GPU.Value = '';
                app.EditField_iv_GPU.Enable = "off";
                % iv-epoch
                app.EditFieldLabel_iv_epoch.Enable = "off";
                app.EditField_iv_epoch.Enable = "off";
                app.EditField_iv_epoch.Value = '';
                % iv-networkWidth
                app.Label_NetworkWidth.Enable = "off";
                app.Button_x1.Enable = "off";
                app.Button_x2.Enable = "off";
                app.Button_x4.Enable = "off";
                app.Image_iv_networkInfo.Visible = "off";
                % iv-LossFunction
                app.Label_LossFunction_iv.Enable = "off";
                app.Button_MSE_iv.Enable = "off";
                app.Button_LNCC_iv.Enable = "off";
                app.Button_MSE_LNCC_iv.Enable = "off";
                % iv
                app.Image_iv_Info.Visible = "off";
                app.Lamp_iv.Visible = "off";
                app.CheckBox_iv.Value = false;
                app.CheckBox_iv.Enable = "off";
                app.Label_iv.Enable = "off";
                app.opt_vxm_training_scratch = false;
                % iv2
                app.Lamp_iv2.Visible = "on";
                app.CheckBox_iv2.Enable = "on";
                app.CheckBox_iv2.Value = true;
                app.Label_iv2.Enable = "on";
                app.Image_iv2_Info.Visible = "on";
                % iv2-deploy
                app.Lamp_iv2_deploy.Visible = "on";
                app.opt_vxm_training = false;
                app.CheckBox_iv2_deploy.Enable ="on";
                app.CheckBox_iv2_deploy.Value = true;
                app.Label_iv2_deploy.Enable = "on";
                % iv2-finetune
                app.Lamp_iv2_finetune.Visible = "off";
                app.CheckBox_iv2_finetune.Enable = "on";
                app.CheckBox_iv2_finetune.Value = false;
                app.Label_iv2_finetune.Enable = "on";
                % iv2-prepro
                app.Lamp_iv2_prepro.Visible = "off";
                app.opt_vxm_preprocessing = false;
                app.CheckBox_iv2_prepro.Enable = "on";
                app.CheckBox_iv2_prepro.Value = false;
                app.Label_iv2_prepro.Enable = "on";
                % iv2-croppedImageSize
                app.EditFieldLabel_iv2_CroppedImageSize.Enable = "off";
                app.EditField_iv2_CroppedImageSizeX.Enable = "off";
                app.EditField_iv2_CroppedImageSizeY.Enable = "off";
                app.EditField_iv2_CroppedImageSizeZ.Enable = "off";
                % iv2-finetuning
                app.Lamp_iv2_finetuning.Visible = "off";
                app.opt_vxm_training_begin = false;
                app.CheckBox_iv2_finetuning.Enable = "on";
                app.CheckBox_iv2_finetuning.Value = false;
                app.Label_iv2_finetuning.Enable = "on";
                % iv2-GPU
                app.EditField_iv2_GPU.Enable = "on";
                app.EditField_iv2_GPU.Value = '';
                % iv2-epoch
                app.EditFieldLabel_iv2_epoch.Enable = "off";
                app.EditField_iv2_epoch.Value = '';
                % iv2-loadModel
                app.Label_iv2_Model.Enable = "on";
                app.Button_iv2_loadmodel.Enable = "on";
                app.Lamp_iv2_loadModel.Visible = "on";
                if app.CheckBox_iii2_deploy.Value
                    % folders for training images and labels
                    app.EditField_imgTr.Enable = "off";
                    app.EditFieldLabel_imgTr.Enable = "off";
                    app.Button_selectPath_imgTr.Enable = "off";
                    app.EditField_imgTs.Enable = "off";
                    app.EditFieldLabel_imgTs.Enable = "off";
                    app.Button_selectPath_imgTs.Enable = "off";
                    app.EditField_labelTr.Enable = "off";
                    app.EditFieldLabel_labelTr.Enable = "off";
                    app.Button_selectPath_labelTr.Enable = "off";
                    app.EditField_labelTs.Enable = "off";
                    app.EditFieldLabel_labelTs.Enable = "off";
                    app.Button_selectPath_labelTs.Enable = "off";
                    % v
                    app.Lamp_v.Visible = "on";
                    app.opt_nnUNet_inference = true;
                    app.CheckBox_v.Value = true;
                    app.EditField_v_GPU.Visible = "on";
                    % folder for inference images
                    app.EditFieldLabel_imgInference.Enable = "on";
                    app.EditField_imgInference.Enable = "on";
                    app.Button_selectPath_imgInference.Enable = "on";
                end
            end
        end

        % Value changed function: CheckBox_iv_train
        function CheckBox_iv_trainValueChanged(app, event)
            value = app.CheckBox_iv_train.Value;
            if value
                % iv_train
                app.Lamp_iv_train.Visible = "on";
                app.opt_vxm_training_begin = true;
                app.EditField_iv_GPU.Value = app.config_vxm_GPU;
                % iv-epoch
                app.EditFieldLabel_iv_epoch.Enable = "on";
                app.EditField_iv_epoch.Enable = "on";
                app.EditField_iv_epoch.Value = num2str(app.config_vxm_epochs);
                % iv-networkWidth
                app.Label_NetworkWidth.Enable = "on";
                app.Button_x1.Enable = "on";
                app.Button_x2.Enable = "on";
                app.Button_x4.Enable = "on";
                app.Image_iv_networkInfo.Visible = "on";
                % iv-LossFunction
                app.Label_LossFunction_iv.Enable = "on";
                app.Button_MSE_iv.Enable = "on";
                app.Button_LNCC_iv.Enable = "on";
                app.Button_MSE_LNCC_iv.Enable = "on";
            else
                % iv_train
                app.Lamp_iv_train.Visible = "off";
                app.opt_vxm_training_begin = false;
                app.EditField_iv_GPU.Value = '';
                % iv-epoch
                app.EditFieldLabel_iv_epoch.Enable = "off";
                app.EditField_iv_epoch.Enable = "off";
                app.EditField_iv_epoch.Value = '';
                % iv-networkWidth
                app.Label_NetworkWidth.Enable = "off";
                app.Button_x1.Enable = "off";
                app.Button_x2.Enable = "off";
                app.Button_x4.Enable = "off";
                app.Image_iv_networkInfo.Visible = "off";
                % iv-LossFunction
                app.Label_LossFunction_iv.Enable = "off";
                app.Button_MSE_iv.Enable = "off";
                app.Button_LNCC_iv.Enable = "off";
                app.Button_MSE_LNCC_iv.Enable = "off";
                % vi
                if app.CheckBox_vi.Value
                    % vi
                    app.CheckBox_vi.Value = false;
                    app.Lamp_vi.Visible = "off";
                    app.opt_vxm_inference = false;
                    app.Image_vi_Info.Visible = "off";
                    app.EditFieldLabel_vi_GPU.Visible = "off";
                    app.EditField_vi_GPU.Visible = "off";
                    % vi-croppedImageSize
                    app.EditFieldLabel_vi_CroppedImageSize.Enable = "off";
                    app.EditField_vi_CroppedImageSizeX.Value = '';
                    app.EditField_vi_CroppedImageSizeY.Value = '';
                    app.EditField_vi_CroppedImageSizeZ.Value = '';
                    % vii
                    app.Lamp_vii.Visible = "off";
                    app.opt_quantification = false;
                    app.CheckBox_vii.Value = false;
                    % vii-x
                    app.Lamp_vii_CTM.Visible = "off";
                    app.opt_quant_CTM = false;
                    app.CheckBox_vii_CTM.Value = false;
                    app.Lamp_vii_parcellation.Visible = "off";
                    app.opt_quant_parcellation = false;
                    app.CheckBox_vii_parcellation.Value = false;
                    app.Lamp_vii_regionalAnalysis.Visible = "off";
                    app.opt_quant_regionalAnalysis = false;
                    app.CheckBox_vii_regionalAnalysis.Value = false;
                end
            end
        end

        % Value changed function: EditField_iv_GPU
        function EditField_iv_GPUValueChanged(app, event)
            if ~isempty(app.EditField_iv_GPU.Value)
                value = str2double(app.EditField_iv_GPU.Value);
                if isnan(value) || ~isreal(value) % if user enter a string
                    uialert(app.CartiMorphToolboxUIFigure, 'You need to enter a number.', 'Error');
                    % iv_train
                    app.EditField_iv_GPU.Value = '';
                    app.Lamp_iv_train.Visible = "off";
                    app.opt_vxm_training_begin = false;
                    app.CheckBox_iv_train.Value = false;
                    % iv-epoch
                    app.EditFieldLabel_iv_epoch.Enable = "off";
                    app.EditField_iv_epoch.Enable = "off";
                    app.EditField_iv_epoch.Value = '';
                    % iv-networkWidth
                    app.Label_NetworkWidth.Enable = "off";
                    app.Button_x1.Enable = "off";
                    app.Button_x2.Enable = "off";
                    app.Button_x4.Enable = "off";
                    app.Image_iv_networkInfo.Visible = "off";
                    % iv-LossFunction
                    app.Label_LossFunction_iv.Enable = "off";
                    app.Button_MSE_iv.Enable = "off";
                    app.Button_LNCC_iv.Enable = "off";
                    app.Button_MSE_LNCC_iv.Enable = "off";
                elseif isinf(value) || floor(value)~=value || value<0 % if user enter a floating number or negative integer
                    uialert(app.CartiMorphToolboxUIFigure, 'You need to enter a positive integer.', 'Error');
                    % iv_train
                    app.EditField_iv_GPU.Value = '';
                    app.Lamp_iv_train.Visible = "off";
                    app.opt_vxm_training_begin = false;
                    app.CheckBox_iv_train.Value = false;
                    % iv-epoch
                    app.EditFieldLabel_iv_epoch.Enable = "off";
                    app.EditField_iv_epoch.Enable = "off";
                    app.EditField_iv_epoch.Value = '';
                    % iv-networkWidth
                    app.Label_NetworkWidth.Enable = "off";
                    app.Button_x1.Enable = "off";
                    app.Button_x2.Enable = "off";
                    app.Button_x4.Enable = "off";
                    app.Image_iv_networkInfo.Visible = "off";
                    % iv-LossFunction
                    app.Label_LossFunction_iv.Enable = "off";
                    app.Button_MSE_iv.Enable = "off";
                    app.Button_LNCC_iv.Enable = "off";
                    app.Button_MSE_LNCC_iv.Enable = "off";
                else
                    % iv_train
                    app.config_vxm_GPU = app.EditField_iv_GPU.Value;
                    app.Lamp_iv_train.Visible = "on";
                    app.opt_vxm_training_begin = true;
                    app.CheckBox_iv_train.Value = true;
                    % iv-epoch
                    app.EditFieldLabel_iv_epoch.Enable = "on";
                    app.EditField_iv_epoch.Enable = "on";
                    app.EditField_iv_epoch.Value = num2str(app.config_vxm_epochs);
                    % iv-networkWidth
                    app.Label_NetworkWidth.Enable = "on";
                    app.Button_x1.Enable = "on";
                    app.Button_x2.Enable = "on";
                    app.Button_x4.Enable = "on";
                    app.Image_iv_networkInfo.Visible = "on";
                    % iv-LossFunction
                    app.Label_LossFunction_iv.Enable = "on";
                    app.Button_MSE_iv.Enable = "on";
                    app.Button_LNCC_iv.Enable = "on";
                    app.Button_MSE_LNCC_iv.Enable = "on";
                end
            else
                % iv_train
                app.Lamp_iv_train.Visible = "off";
                app.opt_vxm_training_begin = false;
                app.CheckBox_iv_train.Value = false;
                % iv-epoch
                app.EditFieldLabel_iv_epoch.Enable = "off";
                app.EditField_iv_epoch.Enable = "off";
                app.EditField_iv_epoch.Value = '';
                % iv-networkWidth
                app.Label_NetworkWidth.Enable = "off";
                app.Button_x1.Enable = "off";
                app.Button_x2.Enable = "off";
                app.Button_x4.Enable = "off";
                app.Image_iv_networkInfo.Visible = "off";
                % iv-LossFunction
                app.Label_LossFunction_iv.Enable = "off";
                app.Button_MSE_iv.Enable = "off";
                app.Button_LNCC_iv.Enable = "off";
                app.Button_MSE_LNCC_iv.Enable = "off";
                % vi
                if app.CheckBox_vi.Value
                    % vi
                    app.CheckBox_vi.Value = false;
                    app.Lamp_vi.Visible = "off";
                    app.opt_vxm_inference = false;
                    app.Image_vi_Info.Visible = "off";
                    app.EditFieldLabel_vi_GPU.Visible = "off";
                    app.EditField_vi_GPU.Visible = "off";
                    % vi-croppedImageSize
                    app.EditFieldLabel_vi_CroppedImageSize.Enable = "off";
                    app.EditField_vi_CroppedImageSizeX.Value = '';
                    app.EditField_vi_CroppedImageSizeY.Value = '';
                    app.EditField_vi_CroppedImageSizeZ.Value = '';
                    % vii
                    app.Lamp_vii.Visible = "off";
                    app.opt_quantification = false;
                    app.CheckBox_vii.Value = false;
                    % vii-x
                    app.Lamp_vii_CTM.Visible = "off";
                    app.opt_quant_CTM = false;
                    app.CheckBox_vii_CTM.Value = false;
                    app.Lamp_vii_parcellation.Visible = "off";
                    app.opt_quant_parcellation = false;
                    app.CheckBox_vii_parcellation.Value = false;
                    app.Lamp_vii_regionalAnalysis.Visible = "off";
                    app.opt_quant_regionalAnalysis = false;
                    app.CheckBox_vii_regionalAnalysis.Value = false;
                end
            end
        end

        % Value changed function: CheckBox_i
        function CheckBox_iValueChanged(app, event)
            value = app.CheckBox_i.Value;
            if value
                app.opt_CondaEnvSetup = true;
                app.Lamp_i.Visible = "on";
            else
                app.opt_CondaEnvSetup = false;
                app.Lamp_i.Visible = "off";
            end
        end

        % Value changed function: CheckBox_ii
        function CheckBox_iiValueChanged(app, event)
            value = app.CheckBox_ii.Value;
            if value
                app.opt_imgStandardization = true;
                app.Lamp_ii.Visible = "on";
                % ii-imageSize
                app.Label_ii_ImageSize.Enable = "on";
                app.EditFieldLabel_ii_imgSizeX.Enable = "on";
                app.EditFieldLabel_ii_imgSizeY.Enable = "on";
                app.EditFieldLabel_ii_imgSizeZ.Enable = "on";
                if ~app.CheckBox_iii2.Value
                    app.EditField_ii_imgSizeX.Enable = "on";
                    app.EditField_ii_imgSizeY.Enable = "on";
                    app.EditField_ii_imgSizeZ.Enable = "on";
                end
                if ~isnan(app.config_nnUNet_imgSize(1,1))
                    app.EditField_ii_imgSizeX.Value = num2str(app.config_nnUNet_imgSize(1,1));
                end
                if ~isnan(app.config_nnUNet_imgSize(1,2))
                    app.EditField_ii_imgSizeY.Value = num2str(app.config_nnUNet_imgSize(1,2));
                end
                if ~isnan(app.config_nnUNet_imgSize(1,3))
                    app.EditField_ii_imgSizeZ.Value = num2str(app.config_nnUNet_imgSize(1,3));
                end
                app.Label_ii_orientation.Enable = "on";
                app.Image_ii_Info_orientation.Visible = "on";
            else
                app.opt_imgStandardization = false;
                app.Lamp_ii.Visible = "off";
                % ii-imageSize
                app.Label_ii_ImageSize.Enable = "off";
                app.EditFieldLabel_ii_imgSizeX.Enable = "off";
                app.EditFieldLabel_ii_imgSizeY.Enable = "off";
                app.EditFieldLabel_ii_imgSizeZ.Enable = "off";
                app.EditField_ii_imgSizeX.Enable = "off";
                app.EditField_ii_imgSizeY.Enable = "off";
                app.EditField_ii_imgSizeZ.Enable = "off";
                app.EditField_ii_imgSizeX.Value = '';
                app.EditField_ii_imgSizeY.Value = '';
                app.EditField_ii_imgSizeZ.Value = '';
                app.Label_ii_orientation.Enable = "off";
                app.Image_ii_Info_orientation.Visible = "off";
            end
        end

        % Selection changed function: ButtonGroup_iii
        function ButtonGroup_iiiSelectionChanged(app, event)
            selectedButton = app.ButtonGroup_iii.SelectedObject;
            switch selectedButton
                case app.Button_iii_scratch.Text
                    app.opt_nnUNet_training_scratch = true;
                case app.Button_iii_continue.Text
                    app.opt_nnUNet_training_scratch = false;
            end
        end

        % Value changed function: CheckBox_v
        function CheckBox_vValueChanged(app, event)
            value = app.CheckBox_v.Value;
            if value
                % v
                app.Lamp_v.Visible = "on";
                app.opt_nnUNet_inference = true;
                app.Image_v_Info.Visible = "on";
                app.EditFieldLabel_v_GPU.Visible = "on";
                app.EditField_v_GPU.Visible = "on";
                if ~strcmp(app.config_inference_GPU, '-1')
                    app.EditField_v_GPU.Value = app.config_inference_GPU;
                else
                    app.EditField_v_GPU.Value = '';
                end
                % inference images folder
                if ~isempty(app.dir_rawData_imgInference)
                    app.EditField_imgInference.Value = app.dir_rawData_imgInference;
                end
                app.EditField_imgInference.Enable = "on";
                app.EditFieldLabel_imgInference.Enable = "on";
                app.Button_selectPath_imgInference.Enable = "on";
            elseif app.CheckBox_iii2_deploy.Value && app.CheckBox_iv2_deploy.Value
                message = sprintf(['You chose to deploy both the segmentation and registration models. \n',...
                    'There is no model training process selected. \n',...
                    'You should at least check this checkbox.']);
                uialert(app.CartiMorphToolboxUIFigure, message, 'Error');
                app.Lamp_v.Visible = "on";
                app.opt_nnUNet_inference = true;
                app.CheckBox_v.Value = true;
                app.EditField_v_GPU.Visible = "on";
            else
                % v
                app.Lamp_v.Visible = "off";
                app.opt_nnUNet_inference = false;
                app.Image_v_Info.Visible = "off";
                app.EditFieldLabel_v_GPU.Visible = "off";
                app.EditField_v_GPU.Visible = "off";
                % vi
                app.Lamp_vi.Visible = "off";
                app.opt_vxm_inference = false;
                app.CheckBox_vi.Value = false;
                app.Image_vi_Info.Visible = "off";
                app.EditFieldLabel_vi_GPU.Visible = "off";
                app.EditField_vi_GPU.Visible = "off";
                app.EditField_vi_CroppedImageSizeX.Value = '';
                app.EditField_vi_CroppedImageSizeY.Value = '';
                app.EditField_vi_CroppedImageSizeZ.Value = '';
                % inference images folder
                app.EditField_imgInference.Value = "";
                app.EditField_imgInference.Enable = "off";
                app.EditFieldLabel_imgInference.Enable = "off";
                app.Button_selectPath_imgInference.Enable = "off";
                % vii
                app.Lamp_vii.Visible = "off";
                app.opt_quantification = false;
                app.CheckBox_vii.Value = false;
                app.EditFieldLabel_vi_CroppedImageSize.Enable = "off";
                % vii-x
                app.Lamp_vii_CTM.Visible = "off";
                app.opt_quant_CTM = false;
                app.CheckBox_vii_CTM.Value = false;
                app.Lamp_vii_parcellation.Visible = "off";
                app.opt_quant_parcellation = false;
                app.CheckBox_vii_parcellation.Value = false;
                app.Lamp_vii_regionalAnalysis.Visible = "off";
                app.opt_quant_regionalAnalysis = false;
                app.CheckBox_vii_regionalAnalysis.Value = false;
            end
        end

        % Value changed function: CheckBox_vi
        function CheckBox_viValueChanged(app, event)
            value = app.CheckBox_vi.Value;
            if value
                % vi
                app.Lamp_vi.Visible = "on";
                app.opt_vxm_inference = true;
                app.Image_vi_Info.Visible = "on";
                app.EditFieldLabel_vi_GPU.Visible = "on";
                app.EditField_vi_GPU.Visible = "on";
                if ~strcmp(app.config_inference_GPU, '-1')
                    app.EditField_vi_GPU.Value = app.config_inference_GPU;
                else
                    app.EditField_vi_GPU.Value = '';
                end
                % vi-croppedImageSize
                app.EditFieldLabel_vi_CroppedImageSize.Enable = "on";
                if ~isnan(app.config_vxm_imgSize_out(1,1))
                    app.EditField_vi_CroppedImageSizeX.Value = num2str(app.config_vxm_imgSize_out(1,1));
                end
                if ~isnan(app.config_vxm_imgSize_out(1,2))
                    app.EditField_vi_CroppedImageSizeY.Value = num2str(app.config_vxm_imgSize_out(1,2));
                end
                if ~isnan(app.config_vxm_imgSize_out(1,3))
                    app.EditField_vi_CroppedImageSizeZ.Value = num2str(app.config_vxm_imgSize_out(1,3));
                end
                % v
                if app.CheckBox_v.Enable
                    app.Lamp_v.Visible = "on";
                    app.opt_nnUNet_inference = true;
                    app.CheckBox_v.Value = true;
                    app.Image_v_Info.Visible = "on";
                    app.EditFieldLabel_v_GPU.Visible = "on";
                    app.EditField_v_GPU.Visible = "on";
                    if ~strcmp(app.config_inference_GPU, '-1')
                        app.EditField_v_GPU.Value = app.config_inference_GPU;
                    else
                        app.EditField_v_GPU.Value = '';
                    end
                end
                % inference images folder
                if app.CheckBox_v.Enable
                    if ~isempty(app.dir_rawData_imgInference)
                        app.EditField_imgInference.Value = app.dir_rawData_imgInference;
                    end
                    app.EditField_imgInference.Enable = "on";
                    app.EditFieldLabel_imgInference.Enable = "on";
                    app.Button_selectPath_imgInference.Enable = "on";
                end
            else
                % vi
                app.Lamp_vi.Visible = "off";
                app.opt_vxm_inference = false;
                app.Image_vi_Info.Visible = "off";
                app.EditFieldLabel_vi_GPU.Visible = "off";
                app.EditField_vi_GPU.Visible = "off";
                % vi-croppedImageSize
                app.EditFieldLabel_vi_CroppedImageSize.Enable = "off";
                app.EditField_vi_CroppedImageSizeX.Value = '';
                app.EditField_vi_CroppedImageSizeY.Value = '';
                app.EditField_vi_CroppedImageSizeZ.Value = '';
                % vii
                app.Lamp_vii.Visible = "off";
                app.opt_quantification = false;
                app.CheckBox_vii.Value = false;
                % vii-x
                app.Lamp_vii_CTM.Visible = "off";
                app.opt_quant_CTM = false;
                app.CheckBox_vii_CTM.Value = false;
                app.Lamp_vii_parcellation.Visible = "off";
                app.opt_quant_parcellation = false;
                app.CheckBox_vii_parcellation.Value = false;
                app.Lamp_vii_regionalAnalysis.Visible = "off";
                app.opt_quant_regionalAnalysis = false;
                app.CheckBox_vii_regionalAnalysis.Value = false;
            end
        end

        % Value changed function: CheckBox_vii
        function CheckBox_viiValueChanged(app, event)
            value = app.CheckBox_vii.Value;
            if value
                % vii
                app.Lamp_vii.Visible = "on";
                app.opt_quantification = true;
                app.CheckBox_vii.Value = true;
                % vi
                if app.CheckBox_vi.Enable
                    app.Lamp_vi.Visible = "on";
                    app.opt_vxm_inference = true;
                    app.CheckBox_vi.Value = true;
                    app.Image_vi_Info.Visible = "on";
                    app.EditField_vi_GPU.Visible = "on";
                    app.EditFieldLabel_vi_GPU.Visible = "on";
                    app.EditFieldLabel_vi_CroppedImageSize.Enable = "on";
                    if ~isnan(app.config_vxm_imgSize_out(1,1))
                        app.EditField_vi_CroppedImageSizeX.Value = num2str(app.config_vxm_imgSize_out(1,1));
                    end
                    if ~isnan(app.config_vxm_imgSize_out(1,2))
                        app.EditField_vi_CroppedImageSizeY.Value = num2str(app.config_vxm_imgSize_out(1,2));
                    end
                    if ~isnan(app.config_vxm_imgSize_out(1,3))
                        app.EditField_vi_CroppedImageSizeZ.Value = num2str(app.config_vxm_imgSize_out(1,3));
                    end
                end
                % v
                if app.CheckBox_v.Enable
                    app.Lamp_v.Visible = "on";
                    app.opt_nnUNet_inference = true;
                    app.CheckBox_v.Value = true;
                    app.Image_v_Info.Visible = "on";
                    app.EditField_v_GPU.Visible = "on";
                    app.EditFieldLabel_v_GPU.Visible = "on";
                end
                % inference images folder
                if app.CheckBox_v.Enable
                    if ~isempty(app.dir_rawData_imgInference)
                        app.EditField_imgInference.Value = app.dir_rawData_imgInference;
                    end
                    app.EditField_imgInference.Enable = "on";
                    app.EditFieldLabel_imgInference.Enable = "on";
                    app.Button_selectPath_imgInference.Enable = "on";
                end
                % vii-x
                app.Lamp_vii_CTM.Visible = "on";
                app.opt_quant_CTM = true;
                app.CheckBox_vii_CTM.Value = true;
                app.Lamp_vii_parcellation.Visible = "on";
                app.opt_quant_parcellation = true;
                app.CheckBox_vii_parcellation.Value = true;
                app.Lamp_vii_regionalAnalysis.Visible = "on";
                app.opt_quant_regionalAnalysis = true;
                app.CheckBox_vii_regionalAnalysis.Value = true;
            else
                % vii
                app.Lamp_vii.Visible = "off";
                app.opt_quantification = false;
                app.CheckBox_vii.Value = false;
                % vii-x
                app.Lamp_vii_CTM.Visible = "off";
                app.opt_quant_CTM = false;
                app.CheckBox_vii_CTM.Value = false;
                app.Lamp_vii_parcellation.Visible = "off";
                app.opt_quant_parcellation = false;
                app.CheckBox_vii_parcellation.Value = false;
                app.Lamp_vii_regionalAnalysis.Visible = "off";
                app.opt_quant_regionalAnalysis = false;
                app.CheckBox_vii_regionalAnalysis.Value = false;
            end
        end

        % Value changed function: CheckBox_vii_CTM
        function CheckBox_vii_CTMValueChanged(app, event)
            value = app.CheckBox_vii_CTM.Value;
            if value
                % vii_CTM
                app.Lamp_vii_CTM.Visible = "on";
                app.opt_quant_CTM = true;
                % vii
                app.Lamp_vii.Visible = "on";
                app.opt_quantification = true;
                app.CheckBox_vii.Value = true;
                % vi
                if app.CheckBox_vi.Enable
                    app.Lamp_vi.Visible = "on";
                    app.opt_vxm_inference = true;
                    app.CheckBox_vi.Value = true;
                    app.Image_vi_Info.Visible = "on";
                    app.EditField_vi_GPU.Visible = "on";
                    app.EditFieldLabel_vi_GPU.Visible = "on";
                    app.EditFieldLabel_vi_CroppedImageSize.Enable = "on";
                    if ~isnan(app.config_vxm_imgSize_out(1,1))
                        app.EditField_vi_CroppedImageSizeX.Value = num2str(app.config_vxm_imgSize_out(1,1));
                    end
                    if ~isnan(app.config_vxm_imgSize_out(1,2))
                        app.EditField_vi_CroppedImageSizeY.Value = num2str(app.config_vxm_imgSize_out(1,2));
                    end
                    if ~isnan(app.config_vxm_imgSize_out(1,3))
                        app.EditField_vi_CroppedImageSizeZ.Value = num2str(app.config_vxm_imgSize_out(1,3));
                    end
                end
                % v
                if app.CheckBox_v.Enable
                    app.Lamp_v.Visible = "on";
                    app.opt_nnUNet_inference = true;
                    app.CheckBox_v.Value = true;
                    app.Image_v_Info.Visible = "on";
                    app.EditField_v_GPU.Visible = "on";
                    app.EditFieldLabel_v_GPU.Visible = "on";
                end
                % inference images folder
                if app.CheckBox_v.Enable
                    if ~isempty(app.dir_rawData_imgInference)
                        app.EditField_imgInference.Value = app.dir_rawData_imgInference;
                    end
                    app.EditField_imgInference.Enable = "on";
                    app.EditFieldLabel_imgInference.Enable = "on";
                    app.Button_selectPath_imgInference.Enable = "on";
                end
            else
                % vii
                app.Lamp_vii.Visible = "off";
                app.opt_quantification = false;
                app.CheckBox_vii.Value = false;
                % vii-x
                app.Lamp_vii_CTM.Visible = "off";
                app.opt_quant_CTM = false;
                app.Lamp_vii_parcellation.Visible = "off";
                app.opt_quant_parcellation = false;
                app.CheckBox_vii_parcellation.Value = false;
                app.Lamp_vii_regionalAnalysis.Visible = "off";
                app.opt_quant_regionalAnalysis = false;
                app.CheckBox_vii_regionalAnalysis.Value = false;
            end
        end

        % Value changed function: CheckBox_vii_parcellation
        function CheckBox_vii_parcellationValueChanged(app, event)
            value = app.CheckBox_vii_parcellation.Value;
            if value
                % setting this flag is necessary for all quantification modules
                app.opt_quantification = true;
                % vii_parcellation
                app.Lamp_vii_parcellation.Visible = "on";
                app.opt_quant_parcellation = true;
                % vii_CTM
                if app.CheckBox_vii_CTM.Enable
                    app.Lamp_vii_CTM.Visible = "on";
                    app.opt_quant_CTM = true;
                    app.CheckBox_vii_CTM.Value = true;
                end
                % vii
                if app.CheckBox_vii.Enable
                    app.Lamp_vii.Visible = "on";
                    app.CheckBox_vii.Value = true;
                end
                % vi
                if app.CheckBox_vi.Enable
                    app.Lamp_vi.Visible = "on";
                    app.opt_vxm_inference = true;
                    app.CheckBox_vi.Value = true;
                    app.Image_vi_Info.Visible = "on";
                    app.EditField_vi_GPU.Visible = "on";
                    app.EditFieldLabel_vi_GPU.Visible = "on";
                    app.EditFieldLabel_vi_CroppedImageSize.Enable = "on";
                    if ~isnan(app.config_vxm_imgSize_out(1,1))
                        app.EditField_vi_CroppedImageSizeX.Value = num2str(app.config_vxm_imgSize_out(1,1));
                    end
                    if ~isnan(app.config_vxm_imgSize_out(1,2))
                        app.EditField_vi_CroppedImageSizeY.Value = num2str(app.config_vxm_imgSize_out(1,2));
                    end
                    if ~isnan(app.config_vxm_imgSize_out(1,3))
                        app.EditField_vi_CroppedImageSizeZ.Value = num2str(app.config_vxm_imgSize_out(1,3));
                    end
                end
                % v
                if app.CheckBox_v.Enable
                    app.Lamp_v.Visible = "on";
                    app.opt_nnUNet_inference = true;
                    app.CheckBox_v.Value = true;
                    app.Image_v_Info.Visible = "on";
                    app.EditField_v_GPU.Visible = "on";
                    app.EditFieldLabel_v_GPU.Visible = "on";
                end
                % inference images folder
                if app.CheckBox_v.Enable
                    if ~isempty(app.dir_rawData_imgInference)
                        app.EditField_imgInference.Value = app.dir_rawData_imgInference;
                    end
                    app.EditField_imgInference.Enable = "on";
                    app.EditFieldLabel_imgInference.Enable = "on";
                    app.Button_selectPath_imgInference.Enable = "on";
                end
            else
                % vii_parcellation
                app.Lamp_vii_parcellation.Visible = "off";
                app.opt_quant_parcellation = false;
                % vii_regionalAnalysis
                app.Lamp_vii_regionalAnalysis.Visible = "off";
                app.opt_quant_regionalAnalysis = false;
                app.CheckBox_vii_regionalAnalysis.Value = false;
            end
        end

        % Value changed function: CheckBox_vii_regionalAnalysis
        function CheckBox_vii_regionalAnalysisValueChanged(app, event)
            value = app.CheckBox_vii_regionalAnalysis.Value;
            if value
                % setting this flag is necessary for all quantification modules
                app.opt_quantification = true;
                % vii_regionalAnalysis
                app.Lamp_vii_regionalAnalysis.Visible = "on";
                app.opt_quant_regionalAnalysis = true;
                % vii_parcellation
                if app.CheckBox_vii_parcellation.Enable
                    app.Lamp_vii_parcellation.Visible = "on";
                    app.opt_quant_parcellation = true;
                    app.CheckBox_vii_parcellation.Value = true;
                end
                % vii_CTM
                if app.CheckBox_vii_CTM.Enable
                    app.Lamp_vii_CTM.Visible = "on";
                    app.opt_quant_CTM = true;
                    app.CheckBox_vii_CTM.Value = true;
                end
                % vii
                if app.CheckBox_vii.Enable
                    app.Lamp_vii.Visible = "on";
                    app.CheckBox_vii.Value = true;
                end
                % vi
                if app.CheckBox_vi.Enable
                    app.Lamp_vi.Visible = "on";
                    app.opt_vxm_inference = true;
                    app.CheckBox_vi.Value = true;
                    app.Image_vi_Info.Visible = "on";
                    app.EditField_vi_GPU.Visible = "on";
                    app.EditFieldLabel_vi_GPU.Visible = "on";
                    app.EditFieldLabel_vi_CroppedImageSize.Enable = "on";
                    if ~isnan(app.config_vxm_imgSize_out(1,1))
                        app.EditField_vi_CroppedImageSizeX.Value = num2str(app.config_vxm_imgSize_out(1,1));
                    end
                    if ~isnan(app.config_vxm_imgSize_out(1,2))
                        app.EditField_vi_CroppedImageSizeY.Value = num2str(app.config_vxm_imgSize_out(1,2));
                    end
                    if ~isnan(app.config_vxm_imgSize_out(1,3))
                        app.EditField_vi_CroppedImageSizeZ.Value = num2str(app.config_vxm_imgSize_out(1,3));
                    end
                end
                % v
                if app.CheckBox_v.Enable
                    app.Lamp_v.Visible = "on";
                    app.opt_nnUNet_inference = true;
                    app.CheckBox_v.Value = true;
                    app.Image_v_Info.Visible = "on";
                    app.EditField_v_GPU.Visible = "on";
                    app.EditFieldLabel_v_GPU.Visible = "on";
                end
                % inference images folder
                if app.CheckBox_v.Enable
                    if ~isempty(app.dir_rawData_imgInference)
                        app.EditField_imgInference.Value = app.dir_rawData_imgInference;
                    end
                    app.EditField_imgInference.Enable = "on";
                    app.EditFieldLabel_imgInference.Enable = "on";
                    app.Button_selectPath_imgInference.Enable = "on";
                end
            else
                % vii_regionalAnalysis
                app.Lamp_vii_regionalAnalysis.Visible = "off";
                app.opt_quant_regionalAnalysis = false;
            end
        end

        % Value changed function: EditField_iv_CroppedImageSizeX
        function EditField_iv_CroppedImageSizeXValueChanged(app, event)
            if ~isempty(app.EditField_iv_CroppedImageSizeX.Value)
                if all(~isnan(app.config_nnUNet_imgSize))
                    value = str2double(app.EditField_iv_CroppedImageSizeX.Value);
                    if isnan(value) || ~isreal(value) % if user enter a string
                        uialert(app.CartiMorphToolboxUIFigure, 'You need to enter a number.', 'Error');
                        app.EditField_iv_CroppedImageSizeX.Value = '';
                        app.EditField_vi_CroppedImageSizeX.Value = '';
                        app.config_vxm_imgSize_out(1,1) = nan;
                    elseif isinf(value) || floor(value)~=value || value <=0
                        uialert(app.CartiMorphToolboxUIFigure, 'You need to enter a positive integer.', 'Error');
                        app.EditField_iv_CroppedImageSizeX.Value = '';
                        app.EditField_vi_CroppedImageSizeX.Value = '';
                        app.config_vxm_imgSize_out(1,1) = nan;
                    else
                        if value <= app.config_nnUNet_imgSize(1,1)
                            % [important]
                            if ~isempty(app.EditField_iv_CroppedImageSizeY.Value) && ~isempty(app.EditField_iv_CroppedImageSizeZ.Value)
                                % size(feature maps in bottleneck) = 1/vxm_downScale * size(input image)
                                vxm_downScale = 2^length(str2num(app.config_vxm_encoderArchi)); %#ok<*ST2NM>

                                % check if the cropped image size is unresonable
                                app.config_vxm_imgSize_out(1,1) = value;
                                remainders = rem(app.config_vxm_imgSize_out, vxm_downScale);
                                if any(remainders~=0)
                                    CMT_iv_suggestImgSize(app);
                                else
                                    % vi (if the cropped image size is resonable)
                                    app.EditField_vi_CroppedImageSizeX.Value = app.EditField_iv_CroppedImageSizeX.Value;
                                end
                            else
                                app.config_vxm_imgSize_out(1,1) = value;
                                % vi
                                app.EditField_vi_CroppedImageSizeX.Value = app.EditField_iv_CroppedImageSizeX.Value;
                            end
                        else
                            message = ['The cropped image size should be smaller ',...
                                'than the resampled image size defined in (ii).'];
                            uialert(app.CartiMorphToolboxUIFigure, message, 'Error');
                            app.EditField_iv_CroppedImageSizeX.Value = '';
                            app.EditField_vi_CroppedImageSizeX.Value = '';
                        end
                    end
                else
                    if app.CheckBox_iii2.Value
                        uialert(app.CartiMorphToolboxUIFigure, 'You should load the segmentation model (iii-2) first.', 'Error');
                        app.EditField_iv_CroppedImageSizeX.Value = '';
                        app.EditField_vi_CroppedImageSizeX.Value = '';
                    else
                        uialert(app.CartiMorphToolboxUIFigure, 'You should fill in the "Resampled Image Size" in (ii) first.', 'Error');
                        app.EditField_iv_CroppedImageSizeX.Value = '';
                        app.EditField_vi_CroppedImageSizeX.Value = '';
                    end
                end
            else
                app.config_vxm_imgSize_out(1,1) = nan;
                app.EditField_iv_CroppedImageSizeX.Value = '';
                app.EditField_vi_CroppedImageSizeX.Value = '';
            end
        end

        % Value changed function: EditField_iv_CroppedImageSizeY
        function EditField_iv_CroppedImageSizeYValueChanged(app, event)
            if ~isempty(app.EditField_iv_CroppedImageSizeY.Value)
                if all(~isnan(app.config_nnUNet_imgSize))
                    value = str2double(app.EditField_iv_CroppedImageSizeY.Value);
                    if isnan(value) || ~isreal(value) % if user enter a string
                        uialert(app.CartiMorphToolboxUIFigure, 'You need to enter a number.', 'Error');
                        app.EditField_iv_CroppedImageSizeY.Value = '';
                        app.EditField_vi_CroppedImageSizeY.Value = '';
                        app.config_vxm_imgSize_out(1,2) = nan;
                    elseif isinf(value) || floor(value)~=value || value <=0
                        uialert(app.CartiMorphToolboxUIFigure, 'You need to enter a positive integer.', 'Error');
                        app.EditField_iv_CroppedImageSizeY.Value = '';
                        app.EditField_vi_CroppedImageSizeY.Value = '';
                        app.config_vxm_imgSize_out(1,2) = nan;
                    else
                        if value <= app.config_nnUNet_imgSize(1,2)

                            % [important]
                            if ~isempty(app.EditField_iv_CroppedImageSizeX.Value) && ~isempty(app.EditField_iv_CroppedImageSizeZ.Value)
                                % size(feature maps in bottleneck) = 1/vxm_downScale * size(input image)
                                vxm_downScale = 2^length(str2num(app.config_vxm_encoderArchi)); %#ok<*ST2NM>

                                % check if the cropped image size is unresonable
                                app.config_vxm_imgSize_out(1,2) = value;
                                remainders = rem(app.config_vxm_imgSize_out, vxm_downScale);
                                if any(remainders~=0)
                                    CMT_iv_suggestImgSize(app);
                                else
                                    % vi (if the cropped image size is resonable)
                                    app.EditField_vi_CroppedImageSizeY.Value = app.EditField_iv_CroppedImageSizeY.Value;
                                end
                            else
                                app.config_vxm_imgSize_out(1,2) = value;
                                % vi
                                app.EditField_vi_CroppedImageSizeY.Value = app.EditField_iv_CroppedImageSizeY.Value;
                            end
                        else
                            uialert(app.CartiMorphToolboxUIFigure, 'The cropped image size should be smaller than the resampled image size defined in (ii).', 'Error');
                            app.EditField_iv_CroppedImageSizeY.Value = '';
                            app.EditField_vi_CroppedImageSizeY.Value = '';
                        end
                    end
                else
                    uialert(app.CartiMorphToolboxUIFigure, 'You should fill in the "Resampled Image Size" in (ii) first.', 'Error');
                    app.EditField_iv_CroppedImageSizeY.Value = '';
                    app.EditField_vi_CroppedImageSizeY.Value = '';
                end
            else
                app.config_vxm_imgSize_out(1,2) = nan;
                app.EditField_iv_CroppedImageSizeY.Value = '';
                app.EditField_vi_CroppedImageSizeY.Value = '';
            end
        end

        % Value changed function: EditField_iv_CroppedImageSizeZ
        function EditField_iv_CroppedImageSizeZValueChanged(app, event)
            if ~isempty(app.EditField_iv_CroppedImageSizeZ.Value)
                if all(~isnan(app.config_nnUNet_imgSize))
                    value = str2double(app.EditField_iv_CroppedImageSizeZ.Value);
                    if isnan(value) || ~isreal(value) % if user enter a string
                        uialert(app.CartiMorphToolboxUIFigure, 'You need to enter a number.', 'Error');
                        app.EditField_iv_CroppedImageSizeZ.Value = '';
                        app.EditField_vi_CroppedImageSizeZ.Value = '';
                        app.config_vxm_imgSize_out(1,3) = nan;
                    elseif isinf(value) || floor(value)~=value || value <=0
                        uialert(app.CartiMorphToolboxUIFigure, 'You need to enter a positive integer.', 'Error');
                        app.EditField_iv_CroppedImageSizeZ.Value = '';
                        app.EditField_vi_CroppedImageSizeZ.Value = '';
                        app.config_vxm_imgSize_out(1,3) = nan;
                    else
                        if value <= app.config_nnUNet_imgSize(1,3)

                            % [important]
                            if ~isempty(app.EditField_iv_CroppedImageSizeX.Value) && ~isempty(app.EditField_iv_CroppedImageSizeY.Value)
                                % size(feature maps in bottleneck) = 1/vxm_downScale * size(input image)
                                vxm_downScale = 2^length(str2num(app.config_vxm_encoderArchi)); %#ok<*ST2NM>

                                % check if the cropped image size is unresonable
                                app.config_vxm_imgSize_out(1,3) = value;
                                remainders = rem(app.config_vxm_imgSize_out, vxm_downScale);
                                if any(remainders~=0)
                                    CMT_iv_suggestImgSize(app);
                                else
                                    % vi (if the cropped image size is resonable)
                                    app.EditField_vi_CroppedImageSizeZ.Value = app.EditField_iv_CroppedImageSizeZ.Value;
                                end
                            else
                                app.config_vxm_imgSize_out(1,3) = value;
                                % vi
                                app.EditField_vi_CroppedImageSizeZ.Value = app.EditField_iv_CroppedImageSizeZ.Value;
                            end
                        else
                            uialert(app.CartiMorphToolboxUIFigure, 'The cropped image size should be smaller than the resampled image size defined in (ii).', 'Error');
                            app.EditField_iv_CroppedImageSizeZ.Value = '';
                            app.EditField_vi_CroppedImageSizeZ.Value = '';
                        end
                    end
                else
                    uialert(app.CartiMorphToolboxUIFigure, 'You should fill in the "Resampled Image Size" in (ii) first.', 'Error');
                    app.EditField_iv_CroppedImageSizeZ.Value = '';
                    app.EditField_vi_CroppedImageSizeZ.Value = '';
                end
            else
                app.config_vxm_imgSize_out(1,3) = nan;
                app.EditField_iv_CroppedImageSizeZ.Value = '';
                app.EditField_vi_CroppedImageSizeZ.Value = '';
            end
        end

        % Value changed function: EditField_iv_epoch
        function EditField_iv_epochValueChanged(app, event)
            if ~isempty(app.EditField_iv_epoch.Value)
                value = str2double(app.EditField_iv_epoch.Value);
                if isnan(value) || ~isreal(value) % if user enter a string
                    uialert(app.CartiMorphToolboxUIFigure, 'You need to enter a number.', 'Error');
                    app.EditField_iv_epoch.Value = num2str(app.config_vxm_epochs);
                elseif isinf(value) || floor(value)~=value || value<=0
                    uialert(app.CartiMorphToolboxUIFigure, 'You need to enter an positive integer.', 'Error');
                    app.EditField_iv_epoch.Value = num2str(app.config_vxm_epochs);
                else
                    app.config_vxm_epochs = value;
                end
            else
                app.EditField_iv_epoch.Value = num2str(app.config_vxm_epochs);
            end
        end

        % Selection changed function: ButtonGroup_networkWidth
        function ButtonGroup_networkWidthSelectionChanged(app, event)
            selectedButton = app.ButtonGroup_networkWidth.SelectedObject;
            if strcmp(selectedButton.Text, 'x1')
                app.config_vxm_encoderArchi = num2str(app.config_vxm_encoderArchi_base);
                app.config_vxm_decoderArchi = num2str(app.config_vxm_decoderArchi_base);
            elseif strcmp(selectedButton.Text, 'x2')
                app.config_vxm_encoderArchi = num2str(app.config_vxm_encoderArchi_base .* 2);
                app.config_vxm_decoderArchi = num2str(app.config_vxm_decoderArchi_base .* 2);
            elseif strcmp(selectedButton.Text, 'x3')
                app.config_vxm_encoderArchi = num2str(app.config_vxm_encoderArchi_base .* 3);
                app.config_vxm_decoderArchi = num2str(app.config_vxm_decoderArchi_base .* 3);
            elseif strcmp(selectedButton.Text, 'x4')
                app.config_vxm_encoderArchi = num2str(app.config_vxm_encoderArchi_base .* 4);
                app.config_vxm_decoderArchi = num2str(app.config_vxm_decoderArchi_base .* 4);
            elseif strcmp(selectedButton.Text, 'x5')
                app.config_vxm_encoderArchi = num2str(app.config_vxm_encoderArchi_base .* 5);
                app.config_vxm_decoderArchi = num2str(app.config_vxm_decoderArchi_base .* 5);
            elseif strcmp(selectedButton.Text, 'x6')
                app.config_vxm_encoderArchi = num2str(app.config_vxm_encoderArchi_base .* 6);
                app.config_vxm_decoderArchi = num2str(app.config_vxm_decoderArchi_base .* 6);
            elseif strcmp(selectedButton.Text, 'x7')
                app.config_vxm_encoderArchi = num2str(app.config_vxm_encoderArchi_base .* 7);
                app.config_vxm_decoderArchi = num2str(app.config_vxm_decoderArchi_base .* 7);
            elseif strcmp(selectedButton.Text, 'x8')
                app.config_vxm_encoderArchi = num2str(app.config_vxm_encoderArchi_base .* 8);
                app.config_vxm_decoderArchi = num2str(app.config_vxm_decoderArchi_base .* 8);
            elseif strcmp(selectedButton.Text, 'x9')
                app.config_vxm_encoderArchi = num2str(app.config_vxm_encoderArchi_base .* 9);
                app.config_vxm_decoderArchi = num2str(app.config_vxm_decoderArchi_base .* 9);
            end
        end

        % Value changed function: Button_Save
        function Button_SaveValueChanged(app, event)
            % toolbox initialization
            CMT_init(app);

            % disable the buttons
            app.Button_Save.Enable = "off";

            % save config
            CMT_saveConfig(app);

            % enable the buttons
            app.Button_Save.Enable = "on";
            app.Button_Save.Value = false;
        end

        % Value changed function: Button_Load
        function Button_LoadValueChanged(app, event)
            % disable the buttons
            app.Button_Load.Enable = "off";

            % load configuration
            CMT_loadConfig(app);

            % open a new log file for cartilage morphological quantification
            fileName = strcat('log_', regexprep(char(datetime), {' ', ':'}, '_'));
            app.container_logFileID = fopen(fullfile(app.dir_result, fileName), 'w+');

            % -----------------------------------------
            % [reset the buttons]
            % -----------------------------------------
            % general settings
            CMT_enableButtons(app);

            % (settings specific to the "Load" button)
            % load button
            app.Button_Load.Enable = "on";
            app.Button_Load.Value = false;
            % run button
            app.Button_Run.Enable = "on";
            app.Button_Run.BackgroundColor = [0.96,0.96,0.96];
            app.Button_Run.Text = "Run";
            app.Button_Run.Value = false;
            % pause and resume buttons
            app.Button_Pause.Enable = "off";
            app.Button_Pause.Text = "Pause      ";
            app.Image_Info_pause.Enable = "off";
            % -----------------------------------------
        end

        % Value changed function: Button_Run
        function Button_RunValueChanged(app, event)
            % check fields
            status = CMT_checkFields(app);
            if ~status
                return;
            end

            % save configuration and status
            answer = questdlg('Would you like to save the project configuration and status?',...
                'Save project configuration and status', 'Yes', 'No', 'Cancel','Yes');
            switch answer
                case 'Yes'
                    CMT_saveConfig(app);
                    CMT_Run_action(app);
                case 'No'
                    CMT_Run_action(app);
                case 'Cancel'
                    app.Button_Run.Value = false;
                    return;
            end
        end

        % Value changed function: EditField_v_GPU
        function EditField_v_GPUValueChanged(app, event)
            if ~isempty(app.EditField_v_GPU.Value)
                value = str2double(app.EditField_v_GPU.Value);
                if isnan(value) || ~isreal(value) % if user enter a string
                    uialert(app.CartiMorphToolboxUIFigure, 'You need to enter a number. We use CPU for model inference for now (not recommended).', 'Error');
                    % v
                    app.EditField_v_GPU.Value = '';
                    % vi
                    app.EditField_vi_GPU.Value = '';
                    % config_inference_GPU
                    app.config_inference_GPU = '-1'; % inference with CPU
                elseif isinf(value) || floor(value)~=value || value<0 % if user enter a floating number or negative integer
                    uialert(app.CartiMorphToolboxUIFigure, 'You need to enter a positive integer. We use CPU for model inference for now (not recommended).', 'Error');
                    % v
                    app.EditField_v_GPU.Value = '';
                    % vi
                    app.EditField_vi_GPU.Value = '';
                    % config_inference_GPU
                    app.config_inference_GPU = '-1'; % inference with CPU
                else
                    % vi
                    app.EditField_vi_GPU.Value = app.EditField_v_GPU.Value;
                    % config_inference_GPU
                    app.config_inference_GPU = app.EditField_v_GPU.Value;
                end
            else
                uialert(app.CartiMorphToolboxUIFigure, 'We use CPU for model inference for now (not recommended).', 'Warning');
                % vi
                app.EditField_vi_GPU.Value = '';
                % config_inference_GPU
                app.config_inference_GPU = '-1'; % inference with CPU
            end
        end

        % Value changed function: EditField_vi_GPU
        function EditField_vi_GPUValueChanged(app, event)
            if ~isempty(app.EditField_vi_GPU.Value)
                value = str2double(app.EditField_vi_GPU.Value);
                if isnan(value) || ~isreal(value) % if user enter a string
                    msg = ['You need to enter a number. \n',...
                        'We use CPU for model inference for now (not recommended).'];
                    uialert(app.CartiMorphToolboxUIFigure, msg, 'Error');
                    % vi
                    app.EditField_vi_GPU.Value = '';
                    % v
                    app.EditField_v_GPU.Value = '';
                    % config_inference_GPU
                    app.config_inference_GPU = '-1'; % inference with CPU
                elseif isinf(value) || floor(value)~=value || value<0 % if user enter a floating number or negative integer
                    msg = ['You need to enter a positive integer. \n',...
                        'We use CPU for model inference for now (not recommended).'];
                    uialert(app.CartiMorphToolboxUIFigure, msg, 'Error');
                    % vi
                    app.EditField_vi_GPU.Value = '';
                    % v
                    app.EditField_v_GPU.Value = '';
                    % config_inference_GPU
                    app.config_inference_GPU = '-1'; % inference with CPU
                else
                    % v
                    app.EditField_v_GPU.Value = app.EditField_vi_GPU.Value;
                    % config_inference_GPU
                    app.config_inference_GPU = app.EditField_vi_GPU.Value;
                end
            else
                uialert(app.CartiMorphToolboxUIFigure, 'We use CPU for model inference for now (not recommended).', 'Warning');
                % v
                app.EditField_v_GPU.Value = '';
                % config_inference_GPU
                app.config_inference_GPU = '-1'; % inference with CPU
            end
        end

        % Value changed function: CheckBox_iii2
        function CheckBox_iii2ValueChanged(app, event)
            value = app.CheckBox_iii2.Value;
            if ~value
                % iii2
                app.Lamp_iii2.Visible = "off";
                app.CheckBox_iii2.Enable = "off";
                app.CheckBox_iii2.Value = false;
                app.Label_iii2.Enable = "off";
                app.Image_iii2_Info.Visible = "off";
                % iii2-deploy
                app.Lamp_iii2_deploy.Visible = "off";
                app.CheckBox_iii2_deploy.Enable = "off";
                app.CheckBox_iii2_deploy.Value = false;
                app.Label_iii2_deploy.Enable = "off";
                % iii2-finetune
                app.Lamp_iii2_finetune.Visible = "off";
                app.CheckBox_iii2_finetune.Enable = "off";
                app.CheckBox_iii2_finetune.Value = false;
                app.Label_iii2_finetune.Enable = "off";
                % iii2-prepro
                app.Lamp_iii2_prepro.Visible = "off";
                app.CheckBox_iii2_prepro.Enable = "off";
                app.CheckBox_iii2_prepro.Value = false;
                app.Label_iii2_prepro.Enable = "off";
                % iii2-finetuning
                app.Lamp_iii2_finetuning.Visible = "off";
                app.CheckBox_iii2_finetuning.Enable = "off";
                app.CheckBox_iii2_finetuning.Value = false;
                app.Label_iii2_finetuning.Enable = "off";
                % iii2-GPU
                app.EditField_iii2_GPU.Enable = "off";
                app.EditField_iii2_GPU.Value = '';
                % iii2-epoch
                app.EditFieldLabel_iii2_epoch.Enable = "off";
                app.EditField_iii2_epoch.Enable = "off";
                app.EditField_iii2_epoch.Value = '';
                % iii2-loadModel
                app.Label_iii2_Model.Enable = "off";
                app.Button_iii2_loadmodel.Enable = "off";
                app.Lamp_iii2_loadModel.Visible = "off";
                % iii
                app.Image_iii_Info.Visible = "on";
                app.Lamp_iii.Visible = "on";
                app.opt_nnUNet_training = true; % important
                app.CheckBox_iii.Enable = "on";
                app.CheckBox_iii.Value = true;
                app.Label_iii.Enable = "on";
                % iii-prepo
                app.Lamp_iii_prepro.Visible = "on";
                app.opt_nnUNet_planning_preprocessing = true;
                app.CheckBox_iii_prepro.Enable = "on";
                app.CheckBox_iii_prepro.Value = true;
                app.Label_iii_prepro.Enable = "on";
                % iii-train
                app.Lamp_iii_train.Visible = "on";
                app.opt_nnUNet_training_begin = true; % important
                app.CheckBox_iii_train.Enable = "on";
                app.CheckBox_iii_train.Value = true;
                app.Label_iii_train.Enable = "on";
                % iii-GPU
                app.EditField_iii_GPU.Enable = "on";
                app.EditField_iii_GPU.Value = app.config_nnUNet_GPU;
                % iii-trainingType
                selectedButton = app.ButtonGroup_iii.SelectedObject;
                if strcmp(selectedButton.Text, 'Train from scratch')
                    app.opt_nnUNet_training_scratch = true;
                else
                    app.opt_nnUNet_training_scratch = false;
                end
                app.ButtonGroup_iii.Visible = "on";
                % iii-epoch
                app.EditFieldLabel_iii_epoch.Enable = "on";
                app.EditField_iii_epoch.Enable = "on";
                app.EditField_iii_epoch.Value = num2str(app.config_nnUNet_epochs);
                app.Image_iii_epochInfo.Visible = "on";
                % folders for training images and labels
                app.EditField_imgTr.Enable = "on";
                app.EditFieldLabel_imgTr.Enable = "on";
                app.Button_selectPath_imgTr.Enable = "on";
                app.EditField_imgTs.Enable = "on";
                app.EditFieldLabel_imgTs.Enable = "on";
                app.Button_selectPath_imgTs.Enable = "on";
                app.EditField_labelTr.Enable = "on";
                app.EditFieldLabel_labelTr.Enable = "on";
                app.Button_selectPath_labelTr.Enable = "on";
                app.EditField_labelTs.Enable = "on";
                app.EditFieldLabel_labelTs.Enable = "on";
                app.Button_selectPath_labelTs.Enable = "on";
                % enable "Segmentation Label" & "Image Size"
                app.EditField_Femur.Enable = "on";
                app.EditField_FemoralCartilage.Enable = "on";
                app.EditField_Tibia.Enable = "on";
                app.EditField_TibialCartilagemedial.Enable = "on";
                app.EditField_TibialCartilagelateral.Enable = "on";
                app.EditField_background.Enable = "on";
                app.EditField_ii_imgSizeX.Enable = "on";
                app.EditField_ii_imgSizeY.Enable = "on";
                app.EditField_ii_imgSizeZ.Enable = "on";
                app.EditField_Femur.Value = '';
                app.EditField_FemoralCartilage.Value = '';
                app.EditField_Tibia.Value = '';
                app.EditField_TibialCartilagemedial.Value = '';
                app.EditField_TibialCartilagelateral.Value = '';
                app.EditField_background.Value = '';
                if ~isnan(app.config_nnUNet_imgSize(1,1))
                    app.EditField_ii_imgSizeX.Value = num2str(app.config_nnUNet_imgSize(1,1));
                end
                if ~isnan(app.config_nnUNet_imgSize(1,2))
                    app.EditField_ii_imgSizeY.Value = num2str(app.config_nnUNet_imgSize(1,2));
                end
                if ~isnan(app.config_nnUNet_imgSize(1,3))
                    app.EditField_ii_imgSizeZ.Value = num2str(app.config_nnUNet_imgSize(1,3));
                end
            end
        end

        % Value changed function: CheckBox_iii2_deploy
        function CheckBox_iii2_deployValueChanged(app, event)
            value = app.CheckBox_iii2_deploy.Value;
            if value
                % iii2-deploy
                app.Lamp_iii2_deploy.Visible = "on";
                app.opt_nnUNet_training = false;
                % iii2-finetune
                app.Lamp_iii2_finetune.Visible = "off";
                app.CheckBox_iii2_finetune.Value = false;
                % iii2-prepro
                app.Lamp_iii2_prepro.Visible = "off";
                app.CheckBox_iii2_prepro.Value = false;
                % iii2-finetuning
                app.Lamp_iii2_finetuning.Visible = "off";
                app.opt_nnUNet_training_begin = false; % important
                app.CheckBox_iii2_finetuning.Value = false;
                % iii2-GPU
                app.EditField_iii2_GPU.Value = '';
                % iii2-epoch
                app.EditFieldLabel_iii2_epoch.Enable = "off";
                app.EditField_iii2_epoch.Enable = "off";
                app.EditField_iii2_epoch.Value = '';
                if app.CheckBox_iv2_deploy.Value
                    % folders for training images and labels
                    app.EditField_imgTr.Enable = "off";
                    app.EditFieldLabel_imgTr.Enable = "off";
                    app.Button_selectPath_imgTr.Enable = "off";
                    app.EditField_imgTs.Enable = "off";
                    app.EditFieldLabel_imgTs.Enable = "off";
                    app.Button_selectPath_imgTs.Enable = "off";
                    app.EditField_labelTr.Enable = "off";
                    app.EditFieldLabel_labelTr.Enable = "off";
                    app.Button_selectPath_labelTr.Enable = "off";
                    app.EditField_labelTs.Enable = "off";
                    app.EditFieldLabel_labelTs.Enable = "off";
                    app.Button_selectPath_labelTs.Enable = "off";
                    % v
                    app.Lamp_v.Visible = "on";
                    app.opt_nnUNet_inference = true;
                    app.CheckBox_v.Value = true;
                    app.EditFieldLabel_v_GPU.Visible = "on";
                    app.EditField_v_GPU.Visible = "on";
                    % folder for inference images
                    app.EditFieldLabel_imgInference.Enable = "on";
                    app.EditField_imgInference.Enable = "on";
                    app.Button_selectPath_imgInference.Enable = "on";
                end
                if strcmp(app.CheckBox_iii2.Enable, "off")
                    % light the lamp
                    app.Lamp_iii2_deploy.Color = [0.3804, 0.7333, 0.2745];
                    app.Lamp_iii2.Color = [0.3804, 0.7333, 0.2745];
                    % iii2-deploy
                    app.CheckBox_iii2_deploy.Enable = "off";
                    % iii2-finetune
                    app.CheckBox_iii2_finetune.Enable = "off";
                    app.Label_iii2_finetune.Enable = "off";
                    % iii2-prepro
                    app.CheckBox_iii2_prepro.Enable = "off";
                    app.Label_iii2_prepro.Enable = "off";
                    % iii2-finetuning
                    app.CheckBox_iii2_finetuning.Enable = "off";
                    app.Label_iii2_finetuning.Enable = "off";
                    % iii2-GPU
                    app.EditField_iii2_GPU.Enable = "off";
                end
            else
                % iii2-deploy
                app.Lamp_iii2_deploy.Visible = "off";
                % iii2-finetune
                app.Lamp_iii2_finetune.Visible = "on";
                app.opt_nnUNet_training = true;
                app.CheckBox_iii2_finetune.Value = true;
                % iii2-prepro
                app.Lamp_iii2_prepro.Visible = "on";
                app.opt_nnUNet_planning_preprocessing = true;
                app.CheckBox_iii2_prepro.Value = true;
                % iii2-finetuning
                app.Lamp_iii2_finetuning.Visible = "on";
                app.opt_nnUNet_training_begin = true; % important
                app.opt_nnUNet_training_scratch = false;
                app.CheckBox_iii2_finetuning.Value = true;
                % iii2-GPU
                app.EditField_iii2_GPU.Value = app.config_nnUNet_GPU;
                % iii2-epoch
                app.EditFieldLabel_iii2_epoch.Enable = "on";
                app.EditField_iii2_epoch.Enable = "on";
                if ~isempty(app.config_nnUNet_epochs)
                    app.EditField_iii2_epoch.Value = num2str(app.config_nnUNet_epochs);
                else
                    app.EditField_iii2_epoch.Value = '';
                end
                % folders for training images and labels
                app.EditField_imgTr.Enable = "on";
                app.EditFieldLabel_imgTr.Enable = "on";
                app.Button_selectPath_imgTr.Enable = "on";
                app.EditField_imgTs.Enable = "on";
                app.EditFieldLabel_imgTs.Enable = "on";
                app.Button_selectPath_imgTs.Enable = "on";
                app.EditField_labelTr.Enable = "on";
                app.EditFieldLabel_labelTr.Enable = "on";
                app.Button_selectPath_labelTr.Enable = "on";
                app.EditField_labelTs.Enable = "on";
                app.EditFieldLabel_labelTs.Enable = "on";
                app.Button_selectPath_labelTs.Enable = "on";
            end
        end

        % Value changed function: CheckBox_iii2_finetune
        function CheckBox_iii2_finetuneValueChanged(app, event)
            value = app.CheckBox_iii2_finetune.Value;
            if value
                % iii2-finetune
                app.Lamp_iii2_finetune.Visible = "on";
                app.opt_nnUNet_training = true;
                % iii2-prepro
                app.Lamp_iii2_prepro.Visible = "on";
                app.opt_nnUNet_planning_preprocessing = true;
                app.CheckBox_iii2_prepro.Value = true;
                % iii2-finetuning
                app.Lamp_iii2_finetuning.Visible = "on";
                app.opt_nnUNet_training_begin = true; % important
                app.opt_nnUNet_training_scratch = false;
                app.CheckBox_iii2_finetuning.Value = true;
                % iii2-GPU
                app.EditField_iii2_GPU.Value = app.config_nnUNet_GPU;
                % iii2-epoch
                app.EditFieldLabel_iii2_epoch.Enable = "on";
                app.EditField_iii2_epoch.Enable = "on";
                if ~isempty(app.config_nnUNet_epochs)
                    app.EditField_iii2_epoch.Value = num2str(app.config_nnUNet_epochs);
                else
                    app.EditField_iii2_epoch.Value = '';
                end
                % iii2-deploy
                app.Lamp_iii2_deploy.Visible = "off";
                app.CheckBox_iii2_deploy.Value = false;
                % folders for training images and labels
                app.EditField_imgTr.Enable = "on";
                app.EditFieldLabel_imgTr.Enable = "on";
                app.Button_selectPath_imgTr.Enable = "on";
                app.EditField_imgTs.Enable = "on";
                app.EditFieldLabel_imgTs.Enable = "on";
                app.Button_selectPath_imgTs.Enable = "on";
                app.EditField_labelTr.Enable = "on";
                app.EditFieldLabel_labelTr.Enable = "on";
                app.Button_selectPath_labelTr.Enable = "on";
                app.EditField_labelTs.Enable = "on";
                app.EditFieldLabel_labelTs.Enable = "on";
                app.Button_selectPath_labelTs.Enable = "on";
                % continue training
                app.opt_nnUNet_training_scratch = false;
            else
                % iii2-finetune
                app.Lamp_iii2_finetune.Visible = "off";
                app.opt_nnUNet_training = false;
                % iii2-prepro
                app.Lamp_iii2_prepro.Visible = "off";
                app.opt_nnUNet_planning_preprocessing = false;
                app.CheckBox_iii2_prepro.Value = false;
                % iii2-finetuning
                app.Lamp_iii2_finetuning.Visible = "off";
                app.opt_nnUNet_training_begin = false; % important
                app.CheckBox_iii2_finetuning.Value = false;
                % iii2-GPU
                app.EditField_iii2_GPU.Value = '';
                % iii2-epoch
                app.EditFieldLabel_iii2_epoch.Enable = "off";
                app.EditField_iii2_epoch.Enable = "off";
                app.EditField_iii2_epoch.Value = '';
                % iii2-deploy
                app.Lamp_iii2_deploy.Visible = "on";
                app.CheckBox_iii2_deploy.Value = true;

                if app.CheckBox_iv2_deploy.Value
                    % folders for training images and labels
                    app.EditField_imgTr.Enable = "off";
                    app.EditFieldLabel_imgTr.Enable = "off";
                    app.Button_selectPath_imgTr.Enable = "off";
                    app.EditField_imgTs.Enable = "off";
                    app.EditFieldLabel_imgTs.Enable = "off";
                    app.Button_selectPath_imgTs.Enable = "off";
                    app.EditField_labelTr.Enable = "off";
                    app.EditFieldLabel_labelTr.Enable = "off";
                    app.Button_selectPath_labelTr.Enable = "off";
                    app.EditField_labelTs.Enable = "off";
                    app.EditFieldLabel_labelTs.Enable = "off";
                    app.Button_selectPath_labelTs.Enable = "off";
                    % v
                    app.Lamp_v.Visible = "on";
                    app.opt_nnUNet_inference = true;
                    app.CheckBox_v.Value = true;
                    app.EditFieldLabel_v_GPU.Visible = "on";
                    app.EditField_v_GPU.Visible = "on";
                    % folder for inference images
                    app.EditFieldLabel_imgInference.Enable = "on";
                    app.EditField_imgInference.Enable = "on";
                    app.Button_selectPath_imgInference.Enable = "on";
                end
            end
        end

        % Value changed function: CheckBox_iii2_prepro
        function CheckBox_iii2_preproValueChanged(app, event)
            value = app.CheckBox_iii2_prepro.Value;
            if value
                % iii2-prepro
                app.Lamp_iii2_prepro.Visible = "on";
                app.opt_nnUNet_planning_preprocessing = true;
                % iii2-finetune
                app.Lamp_iii2_finetune.Visible = "on";
                app.opt_nnUNet_training = true;
                app.CheckBox_iii2_finetune.Value = true;
                % iii2-deploy
                app.Lamp_iii2_deploy.Visible = "off";
                app.CheckBox_iii2_deploy.Value = false;
                % iii2-finetuning
                app.Lamp_iii2_finetuning.Visible = "on";
                app.opt_nnUNet_training_begin = true; % important
                app.opt_nnUNet_training_scratch = false;
                app.CheckBox_iii2_finetuning.Value = true;
                % iii2-GPU
                app.EditField_iii2_GPU.Value = app.config_nnUNet_GPU;
                % iii2-epoch
                app.EditFieldLabel_iii2_epoch.Enable = "on";
                app.EditField_iii2_epoch.Enable = "on";
                if ~isempty(app.config_nnUNet_epochs)
                    app.EditField_iii2_epoch.Value = num2str(app.config_nnUNet_epochs);
                else
                    app.EditField_iii2_epoch.Value = '';
                end
                % folders for training images and labels
                app.EditField_imgTr.Enable = "on";
                app.EditFieldLabel_imgTr.Enable = "on";
                app.Button_selectPath_imgTr.Enable = "on";
                app.EditField_imgTs.Enable = "on";
                app.EditFieldLabel_imgTs.Enable = "on";
                app.Button_selectPath_imgTs.Enable = "on";
                app.EditField_labelTr.Enable = "on";
                app.EditFieldLabel_labelTr.Enable = "on";
                app.Button_selectPath_labelTr.Enable = "on";
                app.EditField_labelTs.Enable = "on";
                app.EditFieldLabel_labelTs.Enable = "on";
                app.Button_selectPath_labelTs.Enable = "on";
                % continue training
                app.opt_nnUNet_training_scratch = false;
            else
                % iii2-prepro
                app.Lamp_iii2_prepro.Visible = "off";
                app.opt_nnUNet_planning_preprocessing = false;
                % iii2-finetuning
                app.Lamp_iii2_finetuning.Visible = "off";
                app.opt_nnUNet_training_begin = false; % important
                app.CheckBox_iii2_finetuning.Value = false;
                app.EditField_iii2_GPU.Value = '';
                % iii2-finetune
                app.Lamp_iii2_finetune.Visible = "off";
                app.opt_nnUNet_training = false;
                app.CheckBox_iii2_finetune.Value = false;
                % iii2-epoch
                app.EditFieldLabel_iii2_epoch.Enable = "off";
                app.EditField_iii2_epoch.Enable = "off";
                app.EditField_iii2_epoch.Value = '';
                % iii2-deploy
                app.Lamp_iii2_deploy.Visible = "on";
                app.CheckBox_iii2_deploy.Value = true;

                if app.CheckBox_iv2_deploy.Value
                    % folders for training images and labels
                    app.EditField_imgTr.Enable = "off";
                    app.EditFieldLabel_imgTr.Enable = "off";
                    app.Button_selectPath_imgTr.Enable = "off";
                    app.EditField_imgTs.Enable = "off";
                    app.EditFieldLabel_imgTs.Enable = "off";
                    app.Button_selectPath_imgTs.Enable = "off";
                    app.EditField_labelTr.Enable = "off";
                    app.EditFieldLabel_labelTr.Enable = "off";
                    app.Button_selectPath_labelTr.Enable = "off";
                    app.EditField_labelTs.Enable = "off";
                    app.EditFieldLabel_labelTs.Enable = "off";
                    app.Button_selectPath_labelTs.Enable = "off";
                    % v
                    app.Lamp_v.Visible = "on";
                    app.opt_nnUNet_inference = true;
                    app.CheckBox_v.Value = true;
                    app.EditFieldLabel_v_GPU.Visible = "on";
                    app.EditField_v_GPU.Visible = "on";
                    % folder for inference images
                    app.EditFieldLabel_imgInference.Enable = "on";
                    app.EditField_imgInference.Enable = "on";
                    app.Button_selectPath_imgInference.Enable = "on";
                end
            end
        end

        % Value changed function: CheckBox_iii2_finetuning
        function CheckBox_iii2_finetuningValueChanged(app, event)
            value = app.CheckBox_iii2_finetuning.Value;
            if value
                % iii2-finetuning
                app.Lamp_iii2_finetuning.Visible = "on";
                app.opt_nnUNet_training_begin = true; % important
                app.opt_nnUNet_training_scratch = false;
                % iii2-GPU
                app.EditField_iii2_GPU.Value = app.config_nnUNet_GPU;
                % iii2-epoch
                app.EditFieldLabel_iii2_epoch.Enable = "on";
                app.EditField_iii2_epoch.Enable = "on";
                if ~isempty(app.config_nnUNet_epochs)
                    app.EditField_iii2_epoch.Value = num2str(app.config_nnUNet_epochs);
                else
                    app.EditField_iii2_epoch.Value = '';
                end
                % iii2-prepro
                if strcmp(app.CheckBox_iii2_prepro.Enable, "on")
                    app.Lamp_iii2_prepro.Visible = "on";
                    app.CheckBox_iii2_prepro.Value = true;
                    app.opt_nnUNet_planning_preprocessing = true;
                end
                % iii2-finetune
                if strcmp(app.CheckBox_iii2_finetune.Enable, "on")
                    app.Lamp_iii2_finetune.Visible = "on";
                    app.opt_nnUNet_training = true;
                    app.CheckBox_iii2_finetune.Value = true;
                end
                % iii2_deploy
                app.Lamp_iii2_deploy.Visible = "off";
                app.CheckBox_iii2_deploy.Value = false;
                % folders for training images and labels
                app.EditField_imgTr.Enable = "on";
                app.EditFieldLabel_imgTr.Enable = "on";
                app.Button_selectPath_imgTr.Enable = "on";
                app.EditField_imgTs.Enable = "on";
                app.EditFieldLabel_imgTs.Enable = "on";
                app.Button_selectPath_imgTs.Enable = "on";
                app.EditField_labelTr.Enable = "on";
                app.EditFieldLabel_labelTr.Enable = "on";
                app.Button_selectPath_labelTr.Enable = "on";
                app.EditField_labelTs.Enable = "on";
                app.EditFieldLabel_labelTs.Enable = "on";
                app.Button_selectPath_labelTs.Enable = "on";
                % continue training
                app.opt_nnUNet_training_scratch = false;
            else
                % iii2-finetuning
                app.Lamp_iii2_finetuning.Visible = "off";
                app.opt_nnUNet_training_begin = false; % important
                app.EditField_iii2_GPU.Value = '';
                % iii2-epoch
                app.EditFieldLabel_iii2_epoch.Enable = "off";
                app.EditField_iii2_epoch.Enable = "off";
                app.EditField_iii2_epoch.Value = '';
                % v
                if app.CheckBox_v.Value
                    % disable v
                    app.CheckBox_v.Value = false;
                    % v
                    app.Lamp_v.Visible = "off";
                    app.opt_nnUNet_inference = false;
                    app.Image_v_Info.Visible = "off";
                    app.EditFieldLabel_v_GPU.Visible = "off";
                    app.EditField_v_GPU.Visible = "off";
                    % vi
                    app.Lamp_vi.Visible = "off";
                    app.opt_vxm_inference = false;
                    app.CheckBox_vi.Value = false;
                    app.Image_vi_Info.Visible = "off";
                    app.EditFieldLabel_vi_GPU.Visible = "off";
                    app.EditField_vi_GPU.Visible = "off";
                    app.EditField_vi_CroppedImageSizeX.Value = '';
                    app.EditField_vi_CroppedImageSizeY.Value = '';
                    app.EditField_vi_CroppedImageSizeZ.Value = '';
                    % inference images folder
                    app.EditField_imgInference.Value = "";
                    app.EditField_imgInference.Enable = "off";
                    app.EditFieldLabel_imgInference.Enable = "off";
                    app.Button_selectPath_imgInference.Enable = "off";
                    % vii
                    app.Lamp_vii.Visible = "off";
                    app.opt_quantification = false;
                    app.CheckBox_vii.Value = false;
                    app.EditFieldLabel_vi_CroppedImageSize.Enable = "off";
                    % vii-x
                    app.Lamp_vii_CTM.Visible = "off";
                    app.opt_quant_CTM = false;
                    app.CheckBox_vii_CTM.Value = false;
                    app.Lamp_vii_parcellation.Visible = "off";
                    app.opt_quant_parcellation = false;
                    app.CheckBox_vii_parcellation.Value = false;
                    app.Lamp_vii_regionalAnalysis.Visible = "off";
                    app.opt_quant_regionalAnalysis = false;
                    app.CheckBox_vii_regionalAnalysis.Value = false;
                end
            end
        end

        % Value changed function: EditField_iii2_GPU
        function EditField_iii2_GPUValueChanged(app, event)
            if ~isempty(app.EditField_iii2_GPU.Value)
                value = str2double(app.EditField_iii2_GPU.Value);
                if isnan(value) || ~isreal(value) % if user enter a string
                    uialert(app.CartiMorphToolboxUIFigure, 'You need to enter a number.', 'Error');
                    % iii2-GPU
                    app.EditField_iii2_GPU.Value = '';
                    % iii2-finetuning
                    app.Lamp_iii2_finetuning.Visible = "off";
                    app.opt_nnUNet_training_begin = false; % important
                    app.CheckBox_iii2_finetuning.Value = false;
                elseif isinf(value) || floor(value)~=value || value<0 % if user enter a floating number or negative integer
                    uialert(app.CartiMorphToolboxUIFigure, 'You need to enter a positive integer.', 'Error');
                    % iii2-GPU
                    app.EditField_iii2_GPU.Value = '';
                    % iii2-finetuning
                    app.Lamp_iii2_finetuning.Visible = "off";
                    app.opt_nnUNet_training_begin = false; % important
                    app.CheckBox_iii2_finetuning.Value = false;
                else
                    % iii2-GPU
                    app.config_nnUNet_GPU = app.EditField_iii2_GPU.Value;
                    % iii2-epoch
                    app.EditFieldLabel_iii2_epoch.Enable = "on";
                    app.EditField_iii2_epoch.Enable = "on";
                    if ~isempty(app.config_nnUNet_epochs)
                        app.EditField_iii2_epoch.Value = num2str(app.config_nnUNet_epochs);
                    else
                        app.EditField_iii2_epoch.Value = '';
                    end
                    % iii2-finetuning
                    app.Lamp_iii2_finetuning.Visible = "on";
                    app.opt_nnUNet_training_begin = true; % important
                    app.opt_nnUNet_training_scratch = false;
                    app.CheckBox_iii2_finetuning.Value = true;
                    % iii2-prepro
                    if ~app.CheckBox_iii2_prepro.Value
                        app.Lamp_iii2_prepro.Visible = "on";
                        app.CheckBox_iii2_prepro.Value = true;
                        app.opt_nnUNet_planning_preprocessing = true;
                    end
                    % iii2-finetune
                    if ~app.CheckBox_iii2_finetune.Value
                        app.Lamp_iii2_finetune.Visible = "on";
                        app.opt_nnUNet_training = true;
                        app.CheckBox_iii2_finetune.Value = true;
                    end
                    % iii-deploy
                    app.Lamp_iii2_deploy.Visible = "off";
                    app.CheckBox_iii2_deploy.Value = false;
                    % folders for training images and labels
                    app.EditField_imgTr.Enable = "on";
                    app.EditFieldLabel_imgTr.Enable = "on";
                    app.Button_selectPath_imgTr.Enable = "on";
                    app.EditField_imgTs.Enable = "on";
                    app.EditFieldLabel_imgTs.Enable = "on";
                    app.Button_selectPath_imgTs.Enable = "on";
                    app.EditField_labelTr.Enable = "on";
                    app.EditFieldLabel_labelTr.Enable = "on";
                    app.Button_selectPath_labelTr.Enable = "on";
                    app.EditField_labelTs.Enable = "on";
                    app.EditFieldLabel_labelTs.Enable = "on";
                    app.Button_selectPath_labelTs.Enable = "on";
                end
            else
                % iii2-finetuning
                app.Lamp_iii2_finetuning.Visible = "off";
                app.opt_nnUNet_training_begin = false; % important
                app.CheckBox_iii2_finetuning.Value = false;
                % iii2-epoch
                app.EditFieldLabel_iii2_epoch.Enable = "off";
                app.EditField_iii2_epoch.Enable = "off";
                app.EditField_iii2_epoch.Value = '';
                % v
                if app.CheckBox_v.Value
                    % disable v
                    app.CheckBox_v.Value = false;
                    % v
                    app.Lamp_v.Visible = "off";
                    app.opt_nnUNet_inference = false;
                    app.Image_v_Info.Visible = "off";
                    app.EditFieldLabel_v_GPU.Visible = "off";
                    app.EditField_v_GPU.Visible = "off";
                    % vi
                    app.Lamp_vi.Visible = "off";
                    app.opt_vxm_inference = false;
                    app.CheckBox_vi.Value = false;
                    app.Image_vi_Info.Visible = "off";
                    app.EditFieldLabel_vi_GPU.Visible = "off";
                    app.EditField_vi_GPU.Visible = "off";
                    app.EditField_vi_CroppedImageSizeX.Value = '';
                    app.EditField_vi_CroppedImageSizeY.Value = '';
                    app.EditField_vi_CroppedImageSizeZ.Value = '';
                    % inference images folder
                    app.EditField_imgInference.Value = "";
                    app.EditField_imgInference.Enable = "off";
                    app.EditFieldLabel_imgInference.Enable = "off";
                    app.Button_selectPath_imgInference.Enable = "off";
                    % vii
                    app.Lamp_vii.Visible = "off";
                    app.opt_quantification = false;
                    app.CheckBox_vii.Value = false;
                    app.EditFieldLabel_vi_CroppedImageSize.Enable = "off";
                    % vii-x
                    app.Lamp_vii_CTM.Visible = "off";
                    app.opt_quant_CTM = false;
                    app.CheckBox_vii_CTM.Value = false;
                    app.Lamp_vii_parcellation.Visible = "off";
                    app.opt_quant_parcellation = false;
                    app.CheckBox_vii_parcellation.Value = false;
                    app.Lamp_vii_regionalAnalysis.Visible = "off";
                    app.opt_quant_regionalAnalysis = false;
                    app.CheckBox_vii_regionalAnalysis.Value = false;
                end
            end
        end

        % Button pushed function: Button_iii2_loadmodel
        function Button_iii2_loadmodelPushed(app, event)
            % freeze the bunnton
            app.Button_iii2_loadmodel.Enable = "off";
            if ~isempty(app.config_nnUNet_ID) && ~isempty(app.config_nnUNet_task) && ~isempty(app.dir_base)
                % set path
                path = uigetdir([],'Select the folder for the segmentation model.');
                if path~=0
                    % alert
                    message = strcat('Please note that once the model is loaded, ', ...
                        'you can not edit fields in section I (i.e., Computing Environment Configuration). ',...
                        'You can press the "reset (iii-2)" button to make them editable and reload model files.');
                    uialert(app.CartiMorphToolboxUIFigure, message, 'Double-check section I', 'icon', 'info');
                    % display the reset button
                    app.Button_iii2_reset.Visible = "on";
                    app.dir_nnUNet_pretrained_model = path;
                    % light the lamp
                    app.Lamp_iii2_loadModel.Color = [0.3804, 0.7333, 0.2745];
                    % toolbox initialization (important)
                    CMT_init(app);
                    % deploy model
                    CMT_deploySegModel(app);
                    % iii2-loadModel
                    app.Label_iii2_Model.Enable = "off";
                    % alert
                    message = sprintf('Model loading is successful.');
                    uialert(app.CartiMorphToolboxUIFigure, message, 'Segmentation Model Loading', 'icon', 'info');
                else
                    % reset the button
                    app.Button_iii2_loadmodel.Enable = "on";
                end
            else
                uialert(app.CartiMorphToolboxUIFigure, "Please set the 'Project ID', 'Project Code Name', and 'CartiMorph Toolbox Folder' first.",...
                    'Missing configuration', 'icon', 'error');
                % reset the button
                app.Button_iii2_loadmodel.Enable = "on";
            end
        end

        % Value changed function: CheckBox_iv2
        function CheckBox_iv2ValueChanged(app, event)
            value = app.CheckBox_iv2.Value;
            if ~value
                % iv2
                app.Lamp_iv2.Visible = "off";
                app.CheckBox_iv2.Enable = "off";
                app.CheckBox_iv2.Value = false;
                app.Label_iv2.Enable = "off";
                app.Image_iv2_Info.Visible = "off";
                % iv2-deploy
                app.Lamp_iv2_deploy.Visible = "off";
                app.opt_vxm_training = false;
                app.CheckBox_iv2_deploy.Enable = "off";
                app.CheckBox_iv2_deploy.Value = false;
                app.Label_iv2_deploy.Enable = "off";
                % iv2-finetune
                app.Lamp_iv2_finetune.Visible = "off";
                app.CheckBox_iv2_finetune.Enable = "off";
                app.CheckBox_iv2_finetune.Value = false;
                app.Label_iv2_finetune.Enable = "off";
                % iv2-prepro
                app.Lamp_iv2_prepro.Visible = "off";
                app.CheckBox_iv2_prepro.Enable = "off";
                app.CheckBox_iv2_prepro.Value = false;
                app.Label_iv2_prepro.Enable = "off";
                % iv2-croppedImageSize
                app.EditFieldLabel_iv2_CroppedImageSize.Enable = "off";
                app.EditField_iv2_CroppedImageSizeX.Value = '';
                app.EditField_iv2_CroppedImageSizeY.Value = '';
                app.EditField_iv2_CroppedImageSizeZ.Value = '';
                % iv2-finetuning
                app.Lamp_iv2_finetuning.Visible = "off";
                app.CheckBox_iv2_finetuning.Enable = "off";
                app.CheckBox_iv2_finetuning.Value = false;
                app.Label_iv2_finetuning.Enable = "off";
                % iv2-GPU
                app.EditField_iv2_GPU.Enable = "off";
                app.EditField_iv2_GPU.Value = '';
                % iv2-epoch
                app.EditFieldLabel_iv2_epoch.Enable = "off";
                app.EditField_iv2_epoch.Enable = "off";
                app.EditField_iv2_epoch.Value = '';
                % iv2_LossFunction
                app.Label_LossFunction_iv2.Enable = "off";
                app.Button_MSE_iv2.Enable = "off";
                app.Button_LNCC_iv2.Enable = "off";
                app.Button_MSE_LNCC_iv2.Enable = "off";
                % iv2-loadModel
                app.Label_iv2_Model.Enable = "off";
                app.Button_iv2_loadmodel.Enable = "off";
                app.Lamp_iv2_loadModel.Visible = "off";
                % iv
                app.Image_iv_Info.Visible = "on";
                app.Lamp_iv.Visible = "on";
                app.CheckBox_iv.Enable = "on";
                app.CheckBox_iv.Value = true;
                app.Label_iv.Enable = "on";
                app.opt_vxm_training_scratch = true;
                % iv_prepo
                app.Lamp_iv_prepro.Visible = "on";
                app.opt_vxm_preprocessing = true;
                app.CheckBox_iv_prepro.Enable = "on";
                app.CheckBox_iv_prepro.Value = true;
                app.Label_iv_prepro.Enable = "on";
                % iv-croppedImageSize
                app.Image_iv_Info_croppedImageSize.Visible = "on";
                app.EditField_iv_CroppedImageSizeX.Enable = "on";
                app.EditField_iv_CroppedImageSizeY.Enable = "on";
                app.EditField_iv_CroppedImageSizeZ.Enable = "on";
                app.EditFieldLabel_iv_CroppedImageSize.Enable = "on";
                if ~isnan(app.config_vxm_imgSize_out(1,1))
                    app.EditField_iv_CroppedImageSizeX.Value = num2str(app.config_vxm_imgSize_out(1,1));
                end
                if ~isnan(app.config_vxm_imgSize_out(1,2))
                    app.EditField_iv_CroppedImageSizeY.Value = num2str(app.config_vxm_imgSize_out(1,2));
                end
                if ~isnan(app.config_vxm_imgSize_out(1,3))
                    app.EditField_iv_CroppedImageSizeZ.Value = num2str(app.config_vxm_imgSize_out(1,3));
                end
                % iv-train
                app.Lamp_iv_train.Visible = "on";
                app.opt_vxm_training = true;
                app.opt_vxm_training_begin = false;
                app.CheckBox_iv_train.Enable = "on";
                app.CheckBox_iv_train.Value = true;
                app.Label_iv_train.Enable = "on";
                % iv-GPU
                app.EditField_iv_GPU.Enable = "on";
                app.EditField_iv_GPU.Value = app.config_vxm_GPU;
                % iv-epoch
                app.EditFieldLabel_iv_epoch.Enable = "on";
                app.EditField_iv_epoch.Enable = "on";
                app.EditField_iv_epoch.Value = num2str(app.config_vxm_epochs);
                % iv-networkWidth
                app.Label_NetworkWidth.Enable = "on";
                app.Button_x1.Enable = "on";
                app.Button_x2.Enable = "on";
                app.Button_x4.Enable = "on";
                app.Image_iv_networkInfo.Visible = "on";
                % iv-LossFunction
                app.Label_LossFunction_iv.Enable = "on";
                app.Button_MSE_iv.Enable = "on";
                app.Button_LNCC_iv.Enable = "on";
                app.Button_MSE_LNCC_iv.Enable = "on";
                % folders for training images and labels
                app.EditField_imgTr.Enable = "on";
                app.EditFieldLabel_imgTr.Enable = "on";
                app.Button_selectPath_imgTr.Enable = "on";
                app.EditField_imgTs.Enable = "on";
                app.EditFieldLabel_imgTs.Enable = "on";
                app.Button_selectPath_imgTs.Enable = "on";
                app.EditField_labelTr.Enable = "on";
                app.EditFieldLabel_labelTr.Enable = "on";
                app.Button_selectPath_labelTr.Enable = "on";
                app.EditField_labelTs.Enable = "on";
                app.EditFieldLabel_labelTs.Enable = "on";
                app.Button_selectPath_labelTs.Enable = "on";
                if ~isnan(app.config_vxm_imgSize_out(1,1))
                    app.EditField_iv_CroppedImageSizeX.Value = num2str(app.config_vxm_imgSize_out(1,1));
                end
                if ~isnan(app.config_vxm_imgSize_out(1,2))
                    app.EditField_iv_CroppedImageSizeY.Value = num2str(app.config_vxm_imgSize_out(1,2));
                end
                if ~isnan(app.config_vxm_imgSize_out(1,3))
                    app.EditField_iv_CroppedImageSizeZ.Value = num2str(app.config_vxm_imgSize_out(1,3));
                end
            end
        end

        % Button pushed function: Button_iv2_loadmodel
        function Button_iv2_loadmodelPushed(app, event)
            % freeze the button
            app.Button_iv2_loadmodel.Enable = "off";
            if ~isempty(app.config_nnUNet_ID) && ~isempty(app.config_nnUNet_task) && ~isempty(app.dir_base)
                % set path
                path = uigetdir([],'Select the folder for the registration model.');
                if path~=0
                    % alert
                    message = strcat('Please note that once the model is loaded, ', ...
                        'you can not edit fields in section I (i.e., Computing Environment Configuration). ',...
                        'You can press the "reset (iv-2)" button to make them editable and reload model files.');
                    uialert(app.CartiMorphToolboxUIFigure, message, 'Double-check section I', 'icon', 'info');
                    % display the reset button
                    app.Button_iv2_reset.Visible = "on";
                    % select path
                    app.dir_vxm_pretrained_model = path;
                    % light the lamp
                    app.Lamp_iv2_loadModel.Color = [0.3804, 0.7333, 0.2745];
                    % toolbox initialization (important)
                    CMT_init(app);
                    % deploy model
                    CMT_deployRegModel(app);
                    % iv2-loadModel
                    app.Label_iv2_Model.Enable = "off";
                    % alert
                    message = sprintf(['Model loading is successful. Model architecture has been set to \n\n',...
                        'encoder: %s \n', 'docoder: %s \n\n'],...
                        string(app.config_vxm_encoderArchi), string(app.config_vxm_decoderArchi));
                    uialert(app.CartiMorphToolboxUIFigure, message, 'Registration Model Loading', 'icon', 'info');
                else
                    % reset the button
                    app.Button_iv2_loadmodel.Enable = "on";
                end
            else
                uialert(app.CartiMorphToolboxUIFigure, "Please set the 'Project ID', 'Project Code Name', and 'CartiMorph Toolbox Folder' first.",...
                    'Missing configuration', 'icon', 'error');
                % reset the button
                app.Button_iv2_loadmodel.Enable = "on";
            end
        end

        % Value changed function: CheckBox_iv2_deploy
        function CheckBox_iv2_deployValueChanged(app, event)
            value = app.CheckBox_iv2_deploy.Value;
            if value
                % iv2-deploy
                app.Lamp_iv2_deploy.Visible = "on";
                app.opt_vxm_training = false;
                % iv2-finetune
                app.Lamp_iv2_finetune.Visible = "off";
                app.CheckBox_iv2_finetune.Value = false;
                % iv2-prepro
                app.Lamp_iv2_prepro.Visible = "off";
                app.opt_vxm_preprocessing = false;
                app.CheckBox_iv2_prepro.Value = false;
                % iv2-croppedImageSize
                app.EditFieldLabel_iv2_CroppedImageSize.Enable = "off";
                app.EditField_iv2_CroppedImageSizeX.Value = '';
                app.EditField_iv2_CroppedImageSizeY.Value = '';
                app.EditField_iv2_CroppedImageSizeZ.Value = '';
                % iv2-finetuning
                app.Lamp_iv2_finetuning.Visible = "off";
                app.opt_vxm_training_begin = false;
                app.CheckBox_iv2_finetuning.Value = false;
                % iv2-GPU
                app.EditField_iv2_GPU.Value = '';
                % iv2-epoch
                app.EditFieldLabel_iv2_epoch.Enable = "off";
                app.EditField_iv2_epoch.Enable = "off";
                app.EditField_iv2_epoch.Value = '';
                % iv2_LossFunction
                app.Label_LossFunction_iv2.Enable = "off";
                app.Button_MSE_iv2.Enable = "off";
                app.Button_LNCC_iv2.Enable = "off";
                app.Button_MSE_LNCC_iv2.Enable = "off";
                % iv2-freeze
                app.CheckBox_iv2_Freeze.Enable = 'off';
                if app.CheckBox_iii2_deploy.Value
                    % folders for training images and labels
                    app.EditField_imgTr.Enable = "off";
                    app.EditFieldLabel_imgTr.Enable = "off";
                    app.Button_selectPath_imgTr.Enable = "off";
                    app.EditField_imgTs.Enable = "off";
                    app.EditFieldLabel_imgTs.Enable = "off";
                    app.Button_selectPath_imgTs.Enable = "off";
                    app.EditField_labelTr.Enable = "off";
                    app.EditFieldLabel_labelTr.Enable = "off";
                    app.Button_selectPath_labelTr.Enable = "off";
                    app.EditField_labelTs.Enable = "off";
                    app.EditFieldLabel_labelTs.Enable = "off";
                    app.Button_selectPath_labelTs.Enable = "off";
                    % v
                    app.Lamp_v.Visible = "on";
                    app.opt_nnUNet_inference = true;
                    app.CheckBox_v.Value = true;
                    app.EditFieldLabel_v_GPU.Visible = "on";
                    app.EditField_v_GPU.Visible = "on";
                    % folder for inference images
                    app.EditFieldLabel_imgInference.Enable = "on";
                    app.EditField_imgInference.Enable = "on";
                    app.Button_selectPath_imgInference.Enable = "on";
                end
            else
                % iv2-deploy
                app.Lamp_iv2_deploy.Visible = "off";
                % iv2-finetune
                app.Lamp_iv2_finetune.Visible = "on";
                app.opt_vxm_training = true;
                app.CheckBox_iv2_finetune.Value = true;
                % iv2-prepro
                app.Lamp_iv2_prepro.Visible = "on";
                app.opt_vxm_preprocessing = true;
                app.CheckBox_iv2_prepro.Value = true;
                % iv2-croppedImageSize
                app.EditFieldLabel_iv2_CroppedImageSize.Enable = "on";
                if ~isnan(app.config_vxm_imgSize_out(1,1))
                    app.EditField_iv2_CroppedImageSizeX.Value = num2str(app.config_vxm_imgSize_out(1,1));
                end
                if ~isnan(app.config_vxm_imgSize_out(1,2))
                    app.EditField_iv2_CroppedImageSizeY.Value = num2str(app.config_vxm_imgSize_out(1,2));
                end
                if ~isnan(app.config_vxm_imgSize_out(1,3))
                    app.EditField_iv2_CroppedImageSizeZ.Value = num2str(app.config_vxm_imgSize_out(1,3));
                end
                % iv2-finetuning
                app.Lamp_iv2_finetuning.Visible = "on";
                app.opt_vxm_training_begin = true;
                app.opt_vxm_training_scratch = false; % TODO: can be removed
                app.CheckBox_iv2_finetuning.Value = true;
                % iv2-GPU
                app.EditField_iv2_GPU.Enable = "on";
                app.EditField_iv2_GPU.Value = app.config_vxm_GPU;
                % iv2-epoch
                app.EditFieldLabel_iv2_epoch.Enable = "on";
                app.EditField_iv2_epoch.Enable = "on";
                % iv2_LossFunction
                app.Label_LossFunction_iv2.Enable = "on";
                app.Button_MSE_iv2.Enable = "on";
                app.Button_LNCC_iv2.Enable = "on";
                app.Button_MSE_LNCC_iv2.Enable = "on";
                % iv2-freeze
                app.CheckBox_iv2_Freeze.Enable = 'on';
                % folders for training images and labels
                app.EditField_imgTr.Enable = "on";
                app.EditFieldLabel_imgTr.Enable = "on";
                app.Button_selectPath_imgTr.Enable = "on";
                app.EditField_imgTs.Enable = "on";
                app.EditFieldLabel_imgTs.Enable = "on";
                app.Button_selectPath_imgTs.Enable = "on";
                app.EditField_labelTr.Enable = "on";
                app.EditFieldLabel_labelTr.Enable = "on";
                app.Button_selectPath_labelTr.Enable = "on";
                app.EditField_labelTs.Enable = "on";
                app.EditFieldLabel_labelTs.Enable = "on";
                app.Button_selectPath_labelTs.Enable = "on";
                if ~isnan(app.config_vxm_imgSize_out(1,1))
                    app.EditField_vi_CroppedImageSizeX.Value = num2str(app.config_vxm_imgSize_out(1,1));
                end
                if ~isnan(app.config_vxm_imgSize_out(1,2))
                    app.EditField_vi_CroppedImageSizeY.Value = num2str(app.config_vxm_imgSize_out(1,2));
                end
                if ~isnan(app.config_vxm_imgSize_out(1,3))
                    app.EditField_vi_CroppedImageSizeZ.Value = num2str(app.config_vxm_imgSize_out(1,3));
                end
            end
        end

        % Value changed function: CheckBox_iv2_finetune
        function CheckBox_iv2_finetuneValueChanged(app, event)
            value = app.CheckBox_iv2_finetune.Value;
            if value
                % iv2-finetune
                app.Lamp_iv2_finetune.Visible = "on";
                app.opt_vxm_training = true;
                app.CheckBox_iv2_finetune.Value = true;
                % iv2-prepro
                app.Lamp_iv2_prepro.Visible = "on";
                app.opt_vxm_preprocessing = true;
                app.CheckBox_iv2_prepro.Value = true;
                % iv2-croppedImageSize
                app.EditFieldLabel_iv2_CroppedImageSize.Enable = "on";
                if ~isnan(app.config_vxm_imgSize_out(1,1))
                    app.EditField_iv2_CroppedImageSizeX.Value = num2str(app.config_vxm_imgSize_out(1,1));
                end
                if ~isnan(app.config_vxm_imgSize_out(1,2))
                    app.EditField_iv2_CroppedImageSizeY.Value = num2str(app.config_vxm_imgSize_out(1,2));
                end
                if ~isnan(app.config_vxm_imgSize_out(1,3))
                    app.EditField_iv2_CroppedImageSizeZ.Value = num2str(app.config_vxm_imgSize_out(1,3));
                end
                % iv2-finetuning
                app.Lamp_iv2_finetuning.Visible = "on";
                app.opt_vxm_training_begin = true;
                app.opt_vxm_training_scratch = false; % TODO: can be removed
                app.CheckBox_iv2_finetuning.Value = true;
                % iv2-GPU
                app.EditField_iv2_GPU.Value = app.config_vxm_GPU;
                % iv2-epoch
                app.EditFieldLabel_iv2_epoch.Enable = "on";
                app.EditField_iv2_epoch.Enable = "on";
                if ~isempty(app.config_vxm_epochs)
                    app.EditField_iv2_epoch.Value = num2str(app.config_vxm_epochs);
                else
                    app.EditField_iv2_epoch.Value = '';
                end
                % iv2_LossFunction
                app.Label_LossFunction_iv2.Enable = "on";
                app.Button_MSE_iv2.Enable = "on";
                app.Button_LNCC_iv2.Enable = "on";
                app.Button_MSE_LNCC_iv2.Enable = "on";
                % iv2-freeze
                app.CheckBox_iv2_Freeze.Enable = 'on';
                % iv2-deploy
                app.Lamp_iv2_deploy.Visible = "off";
                app.CheckBox_iv2_deploy.Value = false;
                % folders for training images and labels
                app.EditField_imgTr.Enable = "on";
                app.EditFieldLabel_imgTr.Enable = "on";
                app.Button_selectPath_imgTr.Enable = "on";
                app.EditField_imgTs.Enable = "on";
                app.EditFieldLabel_imgTs.Enable = "on";
                app.Button_selectPath_imgTs.Enable = "on";
                app.EditField_labelTr.Enable = "on";
                app.EditFieldLabel_labelTr.Enable = "on";
                app.Button_selectPath_labelTr.Enable = "on";
                app.EditField_labelTs.Enable = "on";
                app.EditFieldLabel_labelTs.Enable = "on";
                app.Button_selectPath_labelTs.Enable = "on";
                if ~isnan(app.config_vxm_imgSize_out(1,1))
                    app.EditField_vi_CroppedImageSizeX.Value = num2str(app.config_vxm_imgSize_out(1,1));
                end
                if ~isnan(app.config_vxm_imgSize_out(1,2))
                    app.EditField_vi_CroppedImageSizeY.Value = num2str(app.config_vxm_imgSize_out(1,2));
                end
                if ~isnan(app.config_vxm_imgSize_out(1,3))
                    app.EditField_vi_CroppedImageSizeZ.Value = num2str(app.config_vxm_imgSize_out(1,3));
                end
            else
                % iv2-finetune
                app.Lamp_iv2_finetune.Visible = "off";
                app.opt_vxm_training = false;
                app.CheckBox_iv2_finetune.Value = false;
                % iv2-prepro
                app.Lamp_iv2_prepro.Visible = "off";
                app.opt_vxm_preprocessing = false;
                app.CheckBox_iv2_prepro.Value = false;
                % iv2-croppedImageSize
                app.EditFieldLabel_iv2_CroppedImageSize.Enable = "off";
                app.EditField_iv2_CroppedImageSizeX.Value = '';
                app.EditField_iv2_CroppedImageSizeY.Value = '';
                app.EditField_iv2_CroppedImageSizeZ.Value = '';
                % iv2-finetuning
                app.Lamp_iv2_finetuning.Visible = "off";
                app.opt_vxm_training_begin = false;
                app.CheckBox_iv2_finetuning.Value = false;
                % iv2-GPU
                app.EditField_iv2_GPU.Value = '';
                % iv2-epoch
                app.EditFieldLabel_iv2_epoch.Enable = "off";
                app.EditField_iv2_epoch.Enable = "off";
                app.EditField_iv2_epoch.Value = '';
                % iv2_LossFunction
                app.Label_LossFunction_iv2.Enable = "off";
                app.Button_MSE_iv2.Enable = "off";
                app.Button_LNCC_iv2.Enable = "off";
                app.Button_MSE_LNCC_iv2.Enable = "off";
                % iv2-freeze
                app.CheckBox_iv2_Freeze.Enable = 'off';
                % iv2-deploy
                app.Lamp_iv2_deploy.Visible = "on";
                app.CheckBox_iv2_deploy.Value = true;

                if app.CheckBox_iii2_deploy.Value
                    % folders for training images and labels
                    app.EditField_imgTr.Enable = "off";
                    app.EditFieldLabel_imgTr.Enable = "off";
                    app.Button_selectPath_imgTr.Enable = "off";
                    app.EditField_imgTs.Enable = "off";
                    app.EditFieldLabel_imgTs.Enable = "off";
                    app.Button_selectPath_imgTs.Enable = "off";
                    app.EditField_labelTr.Enable = "off";
                    app.EditFieldLabel_labelTr.Enable = "off";
                    app.Button_selectPath_labelTr.Enable = "off";
                    app.EditField_labelTs.Enable = "off";
                    app.EditFieldLabel_labelTs.Enable = "off";
                    app.Button_selectPath_labelTs.Enable = "off";
                    % v
                    app.Lamp_v.Visible = "on";
                    app.opt_nnUNet_inference = true;
                    app.CheckBox_v.Value = true;
                    app.EditFieldLabel_v_GPU.Visible = "on";
                    app.EditField_v_GPU.Visible = "on";
                    % folder for inference images
                    app.EditFieldLabel_imgInference.Enable = "on";
                    app.EditField_imgInference.Enable = "on";
                    app.Button_selectPath_imgInference.Enable = "on";
                end
            end
        end

        % Value changed function: CheckBox_iv2_prepro
        function CheckBox_iv2_preproValueChanged(app, event)
            value = app.CheckBox_iv2_prepro.Value;
            if value
                % iv2-prepro
                app.Lamp_iv2_prepro.Visible = "on";
                app.opt_vxm_preprocessing = true;
                % iv2-finetune
                app.Lamp_iv2_finetune.Visible = "on";
                app.opt_vxm_training = true;
                app.CheckBox_iv2_finetune.Value = true;
                % iv2-croppedImageSize
                app.EditFieldLabel_iv2_CroppedImageSize.Enable = "on";
                if ~isnan(app.config_vxm_imgSize_out(1,1))
                    app.EditField_iv2_CroppedImageSizeX.Value = num2str(app.config_vxm_imgSize_out(1,1));
                end
                if ~isnan(app.config_vxm_imgSize_out(1,2))
                    app.EditField_iv2_CroppedImageSizeY.Value = num2str(app.config_vxm_imgSize_out(1,2));
                end
                if ~isnan(app.config_vxm_imgSize_out(1,3))
                    app.EditField_iv2_CroppedImageSizeZ.Value = num2str(app.config_vxm_imgSize_out(1,3));
                end
                % iv2-finetuning
                app.Lamp_iv2_finetuning.Visible = "on";
                app.opt_vxm_training_begin = true;
                app.opt_vxm_training_scratch = false; % TODO: can be removed
                app.CheckBox_iv2_finetuning.Value = true;
                % iv2-GPU
                app.EditField_iv2_GPU.Value = app.config_vxm_GPU;
                % iv2-epoch
                app.EditFieldLabel_iv2_epoch.Enable = "on";
                app.EditField_iv2_epoch.Enable = "on";
                if ~isempty(app.config_vxm_epochs)
                    app.EditField_iv2_epoch.Value = num2str(app.config_vxm_epochs);
                else
                    app.EditField_iv2_epoch.Value = '';
                end
                % iv2_LossFunction
                app.Label_LossFunction_iv2.Enable = "on";
                app.Button_MSE_iv2.Enable = "on";
                app.Button_LNCC_iv2.Enable = "on";
                app.Button_MSE_LNCC_iv2.Enable = "on";
                % iv2-freeze
                app.CheckBox_iv2_Freeze.Enable = 'on';
                % iv2-deploy
                app.Lamp_iv2_deploy.Visible = "off";
                app.CheckBox_iv2_deploy.Value = false;
                % folders for training images and labels
                app.EditField_imgTr.Enable = "on";
                app.EditFieldLabel_imgTr.Enable = "on";
                app.Button_selectPath_imgTr.Enable = "on";
                app.EditField_imgTs.Enable = "on";
                app.EditFieldLabel_imgTs.Enable = "on";
                app.Button_selectPath_imgTs.Enable = "on";
                app.EditField_labelTr.Enable = "on";
                app.EditFieldLabel_labelTr.Enable = "on";
                app.Button_selectPath_labelTr.Enable = "on";
                app.EditField_labelTs.Enable = "on";
                app.EditFieldLabel_labelTs.Enable = "on";
                app.Button_selectPath_labelTs.Enable = "on";
                if ~isnan(app.config_vxm_imgSize_out(1,1))
                    app.EditField_vi_CroppedImageSizeX.Value = num2str(app.config_vxm_imgSize_out(1,1));
                end
                if ~isnan(app.config_vxm_imgSize_out(1,2))
                    app.EditField_vi_CroppedImageSizeY.Value = num2str(app.config_vxm_imgSize_out(1,2));
                end
                if ~isnan(app.config_vxm_imgSize_out(1,3))
                    app.EditField_vi_CroppedImageSizeZ.Value = num2str(app.config_vxm_imgSize_out(1,3));
                end
            else
                % iv2-prepro
                app.Lamp_iv2_prepro.Visible = "off";
                app.opt_vxm_preprocessing = false;
                % iv2-croppedImageSize
                app.EditFieldLabel_iv2_CroppedImageSize.Enable = "off";
                app.EditField_iv2_CroppedImageSizeX.Value = '';
                app.EditField_iv2_CroppedImageSizeY.Value = '';
                app.EditField_iv2_CroppedImageSizeZ.Value = '';
                % iv2-finetuning
                app.Lamp_iv2_finetuning.Visible = "off";
                app.opt_vxm_training_begin = false;
                app.CheckBox_iv2_finetuning.Value = false;
                % iv2-GPU
                app.EditField_iv2_GPU.Value = '';
                % iv2-epoch
                app.EditFieldLabel_iv2_epoch.Enable = "off";
                app.EditField_iv2_epoch.Enable = "off";
                app.EditField_iv2_epoch.Value = '';
                % iv2_LossFunction
                app.Label_LossFunction_iv2.Enable = "off";
                app.Button_MSE_iv2.Enable = "off";
                app.Button_LNCC_iv2.Enable = "off";
                app.Button_MSE_LNCC_iv2.Enable = "off";
                % iv2-freeze
                app.CheckBox_iv2_Freeze.Enable = 'off';
                % iv2-finetune
                app.Lamp_iv2_finetune.Visible = "off";
                app.opt_vxm_training = false;
                app.CheckBox_iv2_finetune.Value = false;
                % iv2-deploy
                app.Lamp_iv2_deploy.Visible = "on";
                app.CheckBox_iv2_deploy.Value = true;

                if app.CheckBox_iii2_deploy.Value
                    % folders for training images and labels
                    app.EditField_imgTr.Enable = "off";
                    app.EditFieldLabel_imgTr.Enable = "off";
                    app.Button_selectPath_imgTr.Enable = "off";
                    app.EditField_imgTs.Enable = "off";
                    app.EditFieldLabel_imgTs.Enable = "off";
                    app.Button_selectPath_imgTs.Enable = "off";
                    app.EditField_labelTr.Enable = "off";
                    app.EditFieldLabel_labelTr.Enable = "off";
                    app.Button_selectPath_labelTr.Enable = "off";
                    app.EditField_labelTs.Enable = "off";
                    app.EditFieldLabel_labelTs.Enable = "off";
                    app.Button_selectPath_labelTs.Enable = "off";
                    % v
                    app.Lamp_v.Visible = "on";
                    app.opt_nnUNet_inference = true;
                    app.CheckBox_v.Value = true;
                    app.EditFieldLabel_v_GPU.Visible = "on";
                    app.EditField_v_GPU.Visible = "on";
                    % folder for inference images
                    app.EditFieldLabel_imgInference.Enable = "on";
                    app.EditField_imgInference.Enable = "on";
                    app.Button_selectPath_imgInference.Enable = "on";
                end
            end
        end

        % Value changed function: CheckBox_iv2_finetuning
        function CheckBox_iv2_finetuningValueChanged(app, event)
            value = app.CheckBox_iv2_finetuning.Value;
            if value
                % iv2-finetuning
                app.Lamp_iv2_finetuning.Visible = "on";
                app.opt_vxm_training_begin = true;
                app.opt_vxm_training_scratch = false; % TODO: can be removed
                % iv2-GPU
                app.EditField_iv2_GPU.Value = app.config_vxm_GPU;
                % iv2-epoch
                app.EditFieldLabel_iv2_epoch.Enable = "on";
                app.EditField_iv2_epoch.Enable = "on";
                if ~isempty(app.config_vxm_epochs)
                    app.EditField_iv2_epoch.Value = num2str(app.config_vxm_epochs);
                else
                    app.EditField_iv2_epoch.Value = '';
                end
                % iv2_LossFunction
                app.Label_LossFunction_iv2.Enable = "on";
                app.Button_MSE_iv2.Enable = "on";
                app.Button_LNCC_iv2.Enable = "on";
                app.Button_MSE_LNCC_iv2.Enable = "on";
                % iv2-freeze
                app.CheckBox_iv2_Freeze.Enable = 'on';
                % iv2-prepro
                if ~app.CheckBox_iv2_prepro.Value
                    app.Lamp_iv2_prepro.Visible = "on";
                    app.opt_vxm_preprocessing = true;
                    app.CheckBox_iv2_prepro.Value = true;
                end
                % iv2-croppedImageSize
                app.EditFieldLabel_iv2_CroppedImageSize.Enable = "on";
                app.EditField_iv2_CroppedImageSizeX.Enable = "off";
                app.EditField_iv2_CroppedImageSizeY.Enable = "off";
                app.EditField_iv2_CroppedImageSizeZ.Enable = "off";
                if ~isnan(app.config_vxm_imgSize_out(1,1))
                    app.EditField_iv2_CroppedImageSizeX.Value = num2str(app.config_vxm_imgSize_out(1,1));
                end
                if ~isnan(app.config_vxm_imgSize_out(1,2))
                    app.EditField_iv2_CroppedImageSizeY.Value = num2str(app.config_vxm_imgSize_out(1,2));
                end
                if ~isnan(app.config_vxm_imgSize_out(1,3))
                    app.EditField_iv2_CroppedImageSizeZ.Value = num2str(app.config_vxm_imgSize_out(1,3));
                end
                % iv2-finetune
                if ~app.CheckBox_iv2_finetune.Value
                    app.Lamp_iv2_finetune.Visible = "on";
                    app.opt_vxm_training = true;
                    app.CheckBox_iv2_finetune.Value = true;
                end
                % iv2_deploy
                app.Lamp_iv2_deploy.Visible = "off";
                app.CheckBox_iv2_deploy.Value = false;
                % folders for training images and labels
                app.EditField_imgTr.Enable = "on";
                app.EditFieldLabel_imgTr.Enable = "on";
                app.Button_selectPath_imgTr.Enable = "on";
                app.EditField_imgTs.Enable = "on";
                app.EditFieldLabel_imgTs.Enable = "on";
                app.Button_selectPath_imgTs.Enable = "on";
                app.EditField_labelTr.Enable = "on";
                app.EditFieldLabel_labelTr.Enable = "on";
                app.Button_selectPath_labelTr.Enable = "on";
                app.EditField_labelTs.Enable = "on";
                app.EditFieldLabel_labelTs.Enable = "on";
                app.Button_selectPath_labelTs.Enable = "on";
            else
                % iv2-finetuning
                app.Lamp_iv2_finetuning.Visible = "off";
                app.opt_vxm_training_begin = false;
                app.EditField_iv2_GPU.Value = '';
                % iv2-epoch
                app.EditFieldLabel_iv2_epoch.Enable = "off";
                app.EditField_iv2_epoch.Enable = "off";
                app.EditField_iv2_epoch.Value = '';
                % iv2_LossFunction
                app.Label_LossFunction_iv2.Enable = "off";
                app.Button_MSE_iv2.Enable = "off";
                app.Button_LNCC_iv2.Enable = "off";
                app.Button_MSE_LNCC_iv2.Enable = "off";
                % iv2-freeze
                app.CheckBox_iv2_Freeze.Enable = 'off';
                % vi
                if app.CheckBox_vi.Value
                    % vi
                    app.CheckBox_vi.Value = false;
                    app.Lamp_vi.Visible = "off";
                    app.opt_vxm_inference = false;
                    app.Image_vi_Info.Visible = "off";
                    app.EditFieldLabel_vi_GPU.Visible = "off";
                    app.EditField_vi_GPU.Visible = "off";
                    % vi-croppedImageSize
                    app.EditFieldLabel_vi_CroppedImageSize.Enable = "off";
                    app.EditField_vi_CroppedImageSizeX.Value = '';
                    app.EditField_vi_CroppedImageSizeY.Value = '';
                    app.EditField_vi_CroppedImageSizeZ.Value = '';
                    % vii
                    app.Lamp_vii.Visible = "off";
                    app.opt_quantification = false;
                    app.CheckBox_vii.Value = false;
                    % vii-x
                    app.Lamp_vii_CTM.Visible = "off";
                    app.opt_quant_CTM = false;
                    app.CheckBox_vii_CTM.Value = false;
                    app.Lamp_vii_parcellation.Visible = "off";
                    app.opt_quant_parcellation = false;
                    app.CheckBox_vii_parcellation.Value = false;
                    app.Lamp_vii_regionalAnalysis.Visible = "off";
                    app.opt_quant_regionalAnalysis = false;
                    app.CheckBox_vii_regionalAnalysis.Value = false;
                end
            end
        end

        % Value changed function: EditField_iv2_GPU
        function EditField_iv2_GPUValueChanged(app, event)
            if ~isempty(app.EditField_iv2_GPU.Value)
                value = str2double(app.EditField_iv2_GPU.Value);
                if isnan(value) || ~isreal(value) % if user enter a string
                    uialert(app.CartiMorphToolboxUIFigure, 'You need to enter a number.', 'Error');
                    % iv2-GPU
                    app.EditField_iv2_GPU.Value = '';
                    % iv2-finetuning
                    app.Lamp_iv2_finetuning.Visible = "off";
                    app.CheckBox_iv2_finetuning.Value = false;
                elseif isinf(value) || floor(value)~=value || value<0 % if user enter a floating number or negative integer
                    uialert(app.CartiMorphToolboxUIFigure, 'You need to enter a positive integer.', 'Error');
                    % iv2-GPU
                    app.EditField_iv2_GPU.Value = '';
                    % iv2-finetuning
                    app.Lamp_iv2_finetuning.Visible = "off";
                    app.CheckBox_iv2_finetuning.Value = false;
                else
                    % iv2-GPU
                    app.EditField_iv2_GPU.Value = app.EditField_iv2_GPU.Value;
                    app.config_vxm_GPU = app.EditField_iv2_GPU.Value;
                    % iv2-finetuning
                    app.Lamp_iv2_finetuning.Visible = "on";
                    app.opt_vxm_training_begin = true;
                    app.opt_vxm_training_scratch = false; % TODO: can be removed
                    app.CheckBox_iv2_finetuning.Value = true;
                    % iv2-epoch
                    app.EditFieldLabel_iv2_epoch.Enable = "on";
                    app.EditField_iv2_epoch.Enable = "on";
                    if ~isempty(app.config_vxm_epochs)
                        app.EditField_iv2_epoch.Value = num2str(app.config_vxm_epochs);
                    else
                        app.EditField_iv2_epoch.Value = '';
                    end
                    % iv2_LossFunction
                    app.Label_LossFunction_iv2.Enable = "on";
                    app.Button_MSE_iv2.Enable = "on";
                    app.Button_LNCC_iv2.Enable = "on";
                    app.Button_MSE_LNCC_iv2.Enable = "on";
                    % iv2-freeze
                    app.CheckBox_iv2_Freeze.Enable = 'on';
                    % iv2-prepro
                    if ~app.CheckBox_iv2_prepro.Value
                        app.Lamp_iv2_prepro.Visible = "on";
                        app.opt_vxm_preprocessing = true;
                        app.CheckBox_iv2_prepro.Value = true;
                    end
                    % iv2-croppedImageSize
                    app.EditFieldLabel_iv2_CroppedImageSize.Enable = "on";
                    app.EditField_iv2_CroppedImageSizeX.Enable = "off";
                    app.EditField_iv2_CroppedImageSizeY.Enable = "off";
                    app.EditField_iv2_CroppedImageSizeZ.Enable = "off";
                    if ~isnan(app.config_vxm_imgSize_out(1,1))
                        app.EditField_iv2_CroppedImageSizeX.Value = num2str(app.config_vxm_imgSize_out(1,1));
                    end
                    if ~isnan(app.config_vxm_imgSize_out(1,2))
                        app.EditField_iv2_CroppedImageSizeY.Value = num2str(app.config_vxm_imgSize_out(1,2));
                    end
                    if ~isnan(app.config_vxm_imgSize_out(1,3))
                        app.EditField_iv2_CroppedImageSizeZ.Value = num2str(app.config_vxm_imgSize_out(1,3));
                    end
                    % iv2-finetune
                    if ~app.CheckBox_iv2_finetune.Value
                        app.Lamp_iv2_finetune.Visible = "on";
                        app.opt_vxm_training = true;
                        app.CheckBox_iv2_finetune.Value = true;
                    end

                    % iv2-deploy
                    app.Lamp_iv2_deploy.Visible = "off";
                    app.CheckBox_iv2_deploy.Value = false;
                    % folders for training images and labels
                    app.EditField_imgTr.Enable = "on";
                    app.EditFieldLabel_imgTr.Enable = "on";
                    app.Button_selectPath_imgTr.Enable = "on";
                    app.EditField_imgTs.Enable = "on";
                    app.EditFieldLabel_imgTs.Enable = "on";
                    app.Button_selectPath_imgTs.Enable = "on";
                    app.EditField_labelTr.Enable = "on";
                    app.EditFieldLabel_labelTr.Enable = "on";
                    app.Button_selectPath_labelTr.Enable = "on";
                    app.EditField_labelTs.Enable = "on";
                    app.EditFieldLabel_labelTs.Enable = "on";
                    app.Button_selectPath_labelTs.Enable = "on";
                end
            else
                % iv2-finetuning
                app.Lamp_iv2_finetuning.Visible = "off";
                app.opt_vxm_training_begin = false;
                app.CheckBox_iv2_finetuning.Value = false;
                % iv2-epoch
                app.EditFieldLabel_iv2_epoch.Enable = "off";
                app.EditField_iv2_epoch.Enable = "off";
                app.EditField_iv2_epoch.Value = '';
                % iv2_LossFunction
                app.Label_LossFunction_iv2.Enable = "off";
                app.Button_MSE_iv2.Enable = "off";
                app.Button_LNCC_iv2.Enable = "off";
                app.Button_MSE_LNCC_iv2.Enable = "off";
                % iv2-freeze
                app.CheckBox_iv2_Freeze.Enable = 'off';
                % vi
                if app.CheckBox_vi.Value
                    % vi
                    app.CheckBox_vi.Value = false;
                    app.Lamp_vi.Visible = "off";
                    app.opt_vxm_inference = false;
                    app.Image_vi_Info.Visible = "off";
                    app.EditFieldLabel_vi_GPU.Visible = "off";
                    app.EditField_vi_GPU.Visible = "off";
                    % vi-croppedImageSize
                    app.EditFieldLabel_vi_CroppedImageSize.Enable = "off";
                    app.EditField_vi_CroppedImageSizeX.Value = '';
                    app.EditField_vi_CroppedImageSizeY.Value = '';
                    app.EditField_vi_CroppedImageSizeZ.Value = '';
                    % vii
                    app.Lamp_vii.Visible = "off";
                    app.opt_quantification = false;
                    app.CheckBox_vii.Value = false;
                    % vii-x
                    app.Lamp_vii_CTM.Visible = "off";
                    app.opt_quant_CTM = false;
                    app.CheckBox_vii_CTM.Value = false;
                    app.Lamp_vii_parcellation.Visible = "off";
                    app.opt_quant_parcellation = false;
                    app.CheckBox_vii_parcellation.Value = false;
                    app.Lamp_vii_regionalAnalysis.Visible = "off";
                    app.opt_quant_regionalAnalysis = false;
                    app.CheckBox_vii_regionalAnalysis.Value = false;
                end
            end
        end

        % Value changed function: EditField_iv2_CroppedImageSizeX
        function EditField_iv2_CroppedImageSizeXValueChanged(app, event)
            if all(~isnan(app.config_nnUNet_imgSize))
                value = str2double(app.EditField_iv2_CroppedImageSizeX.Value);
                if isnan(value) || ~isreal(value) % if user enter a string
                    uialert(app.CartiMorphToolboxUIFigure, 'You need to enter a number.', 'Error');
                    app.EditField_iv2_CroppedImageSizeX.Value = '';
                    app.config_vxm_imgSize_out(1,1) = nan;
                elseif isinf(value) || floor(value)~=value || value <=0
                    uialert(app.CartiMorphToolboxUIFigure, 'You need to enter a positive integer.', 'Error');
                    app.EditField_iv2_CroppedImageSizeX.Value = '';
                    app.config_vxm_imgSize_out(1,1) = nan;
                else
                    if value <= app.config_nnUNet_imgSize(1,1)
                        % [important] excert soft aspect ratio constratint
                        if ~isempty(app.EditField_iv2_CroppedImageSizeY.Value) && ~isempty(app.EditField_iv2_CroppedImageSizeZ.Value)
                            app.config_vxm_imgSize_out(1,1) = value;
                            k = app.config_nnUNet_imgSize ./ app.config_vxm_imgSize_out;
                            kk = [k(1)/k(2), k(1)/k(3), k(2)/k(3)];
                            if any(kk>=2) || any(kk<=1/2)
                                % the cropped image size is unresonable
                                suggestX = round(app.config_nnUNet_imgSize(1,1)/2);
                                suggestY = round(app.config_nnUNet_imgSize(1,2)/2);
                                suggestZ = round(app.config_nnUNet_imgSize(1,3)/2);
                                suggestX_s = num2str(suggestX);
                                suggestY_s = num2str(suggestY);
                                suggestZ_s = num2str(suggestZ);
                                message = sprintf('The cropped image size is unresonable. \n Suggestion: \n [%s, %s, %s]', suggestX_s, suggestY_s, suggestZ_s);
                                uialert(app.CartiMorphToolboxUIFigure, message, 'Error');
                                % reset cropped image size
                                app.config_vxm_imgSize_out = [suggestX, suggestY, suggestZ];
                                app.EditField_iv2_CroppedImageSizeX.Value = suggestX_s;
                                app.EditField_iv2_CroppedImageSizeY.Value = suggestY_s;
                                app.EditField_iv2_CroppedImageSizeZ.Value = suggestZ_s;
                                app.EditField_vi_CroppedImageSizeX.Value = suggestX_s;
                                app.EditField_vi_CroppedImageSizeY.Value = suggestY_s;
                                app.EditField_vi_CroppedImageSizeZ.Value = suggestZ_s;
                            else
                                % the cropped image size is resonable
                                app.config_vxm_imgSize_out(1,1) = value;
                                % vi
                                app.EditField_vi_CroppedImageSizeX.Value = app.EditField_iv2_CroppedImageSizeX.Value;
                            end
                        else
                            app.config_vxm_imgSize_out(1,1) = value;
                            % vi
                            app.EditField_vi_CroppedImageSizeX.Value = app.EditField_iv2_CroppedImageSizeX.Value;
                        end
                    else
                        uialert(app.CartiMorphToolboxUIFigure, 'The cropped image size should be smaller than the resampled image size defined in (ii).', 'Error');
                        app.EditField_iv2_CroppedImageSizeX.Value = '';
                    end
                end
            else
                uialert(app.CartiMorphToolboxUIFigure, 'You should fill in the "Resampled Image Size" in (ii) first.', 'Error');
                app.EditField_iv2_CroppedImageSizeX.Value = '';
            end
        end

        % Value changed function: EditField_iv2_CroppedImageSizeY
        function EditField_iv2_CroppedImageSizeYValueChanged(app, event)
            if all(~isnan(app.config_nnUNet_imgSize))
                value = str2double(app.EditField_iv2_CroppedImageSizeY.Value);
                if isnan(value) || ~isreal(value) % if user enter a string
                    uialert(app.CartiMorphToolboxUIFigure, 'You need to enter a number.', 'Error');
                    app.EditField_iv2_CroppedImageSizeY.Value = '';
                    app.config_vxm_imgSize_out(1,2) = nan;
                elseif isinf(value) || floor(value)~=value || value <=0
                    uialert(app.CartiMorphToolboxUIFigure, 'You need to enter a positive integer.', 'Error');
                    app.EditField_iv2_CroppedImageSizeY.Value = '';
                    app.config_vxm_imgSize_out(1,2) = nan;
                else
                    if value <= app.config_nnUNet_imgSize(1,2)
                        % [important] excert soft aspect ratio constratint
                        if ~isempty(app.EditField_iv2_CroppedImageSizeX.Value) && ~isempty(app.EditField_iv2_CroppedImageSizeZ.Value)
                            app.config_vxm_imgSize_out(1,2) = value;
                            k = app.config_nnUNet_imgSize ./ app.config_vxm_imgSize_out;
                            kk = [k(1)/k(2), k(1)/k(3), k(2)/k(3)];
                            if any(kk>=2) || any(kk<=1/2)
                                % the cropped image size is unresonable
                                suggestX = round(app.config_nnUNet_imgSize(1,1)/2);
                                suggestY = round(app.config_nnUNet_imgSize(1,2)/2);
                                suggestZ = round(app.config_nnUNet_imgSize(1,3)/2);
                                suggestX_s = num2str(suggestX);
                                suggestY_s = num2str(suggestY);
                                suggestZ_s = num2str(suggestZ);
                                message = sprintf('The cropped image size is unresonable. \n Suggestion: \n [%s, %s, %s]', suggestX_s, suggestY_s, suggestZ_s);
                                uialert(app.CartiMorphToolboxUIFigure, message, 'Error');
                                % reset cropped image size
                                app.config_vxm_imgSize_out = [suggestX, suggestY, suggestZ];
                                app.EditField_iv2_CroppedImageSizeX.Value = suggestX_s;
                                app.EditField_iv2_CroppedImageSizeY.Value = suggestY_s;
                                app.EditField_iv2_CroppedImageSizeZ.Value = suggestZ_s;
                                app.EditField_vi_CroppedImageSizeX.Value = suggestX_s;
                                app.EditField_vi_CroppedImageSizeY.Value = suggestY_s;
                                app.EditField_vi_CroppedImageSizeZ.Value = suggestZ_s;
                            else
                                % the cropped image size is resonable
                                app.config_vxm_imgSize_out(1,2) = value;
                                % vi
                                app.EditField_vi_CroppedImageSizeY.Value = app.EditField_iv2_CroppedImageSizeY.Value;
                            end
                        else
                            app.config_vxm_imgSize_out(1,2) = value;
                            % vi
                            app.EditField_vi_CroppedImageSizeY.Value = app.EditField_iv2_CroppedImageSizeY.Value;
                        end
                    else
                        uialert(app.CartiMorphToolboxUIFigure, 'The cropped image size should be smaller than the resampled image size defined in (ii).', 'Error');
                        app.EditField_iv2_CroppedImageSizeY.Value = '';
                    end
                end
            else
                uialert(app.CartiMorphToolboxUIFigure, 'You should fill in the "Resampled Image Size" in (ii) first.', 'Error');
                app.EditField_iv2_CroppedImageSizeY.Value = '';
            end
        end

        % Value changed function: EditField_iv2_CroppedImageSizeZ
        function EditField_iv2_CroppedImageSizeZValueChanged(app, event)
            if all(~isnan(app.config_nnUNet_imgSize))
                value = str2double(app.EditField_iv2_CroppedImageSizeZ.Value);
                if isnan(value) || ~isreal(value) % if user enter a string
                    uialert(app.CartiMorphToolboxUIFigure, 'You need to enter a number.', 'Error');
                    app.EditField_iv2_CroppedImageSizeZ.Value = '';
                    app.config_vxm_imgSize_out(1,3) = nan;
                elseif isinf(value) || floor(value)~=value || value <=0
                    uialert(app.CartiMorphToolboxUIFigure, 'You need to enter a positive integer.', 'Error');
                    app.EditField_iv2_CroppedImageSizeZ.Value = '';
                    app.config_vxm_imgSize_out(1,3) = nan;
                else
                    if value <= app.config_nnUNet_imgSize(1,3)
                        % [important] excert soft aspect ratio constratint
                        if ~isempty(app.EditField_iv2_CroppedImageSizeX.Value) && ~isempty(app.EditField_iv2_CroppedImageSizeY.Value)
                            app.config_vxm_imgSize_out(1,3) = value;
                            k = app.config_nnUNet_imgSize ./ app.config_vxm_imgSize_out;
                            kk = [k(1)/k(2), k(1)/k(3), k(2)/k(3)];
                            if any(kk>=2) || any(kk<=1/2)
                                % the cropped image size is unresonable
                                suggestX = round(app.config_nnUNet_imgSize(1,1)/2);
                                suggestY = round(app.config_nnUNet_imgSize(1,2)/2);
                                suggestZ = round(app.config_nnUNet_imgSize(1,3)/2);
                                suggestX_s = num2str(suggestX);
                                suggestY_s = num2str(suggestY);
                                suggestZ_s = num2str(suggestZ);
                                message = sprintf('The cropped image size is unresonable. \n Suggestion: \n [%s, %s, %s]', suggestX_s, suggestY_s, suggestZ_s);
                                uialert(app.CartiMorphToolboxUIFigure, message, 'Error');
                                % reset cropped image size
                                app.config_vxm_imgSize_out = [suggestX, suggestY, suggestZ];
                                app.EditField_iv2_CroppedImageSizeX.Value = suggestX_s;
                                app.EditField_iv2_CroppedImageSizeY.Value = suggestY_s;
                                app.EditField_iv2_CroppedImageSizeZ.Value = suggestZ_s;
                                app.EditField_vi_CroppedImageSizeX.Value = suggestX_s;
                                app.EditField_vi_CroppedImageSizeY.Value = suggestY_s;
                                app.EditField_vi_CroppedImageSizeZ.Value = suggestZ_s;
                            else
                                % the cropped image size is resonable
                                app.config_vxm_imgSize_out(1,3) = value;
                                % vi
                                app.EditField_vi_CroppedImageSizeZ.Value = app.EditField_iv2_CroppedImageSizeZ.Value;
                            end
                        else
                            app.config_vxm_imgSize_out(1,3) = value;
                            % vi
                            app.EditField_vi_CroppedImageSizeZ.Value = app.EditField_iv2_CroppedImageSizeZ.Value;
                        end
                    else
                        uialert(app.CartiMorphToolboxUIFigure, 'The cropped image size should be smaller than the resampled image size defined in (ii).', 'Error');
                        app.EditField_iv2_CroppedImageSizeZ.Value = '';
                    end
                end
            else
                uialert(app.CartiMorphToolboxUIFigure, 'You should fill in the "Resampled Image Size" in (ii) first.', 'Error');
                app.EditField_iv2_CroppedImageSizeZ.Value = '';
            end
        end

        % Close request function: CartiMorphToolboxUIFigure
        function CartiMorphToolboxUIFigureCloseRequest(app, event)

            % flags for this UI closing action
            flag_SegModel_saved = false;
            flag_RegModel_saved = false;

            % [1] ask for exporting the segmentation model if applicable
            if isfield(app.container_jobStatus, 'job_nnUNet_train') &&...
                    strcmp(app.container_jobStatus.job_nnUNet_train, "finished") &&...
                    ~app.flag_SegModel_exported
                tmpAsk = strcat("You have not exported the segmentation model. ",...
                    "You must export the model first if you want to load the model for deployment or transfer learning. ",...
                    "Would you like to do so?");
                answer = questdlg(tmpAsk, 'Export segmentation model', 'Yes', 'No', 'Cancel', 'Yes');
                switch answer
                    case "Yes"
                        % export the segmentation model
                        CMT_exportSegModel(app);
                        % auto-save configuration to update model exporting flag
                        CMT_autosaveConfig(app, 'config_latest');
                        % set flag for this UI closing action
                        flag_SegModel_saved = true;
                    case "No"
                        % save the model anyway :), but it will be overwritten if anyother model is trained
                        CMT_exportSegModel_auto(app);
                    case "Cancel"
                        return;
                end
            end

            % [2] ask for exporting the registration model if applicable
            if isfield(app.container_jobStatus, 'job_vxm_train') &&...
                    strcmp(app.container_jobStatus.job_vxm_train, "finished") &&...
                    ~app.flag_RegModel_exported
                tmpAsk = strcat("You have not exported the registration model. ",...
                    "You must export the model first if you want to load the model for deployment or transfer learning. ",...
                    "Would you like to do so?");
                answer = questdlg(tmpAsk, 'Export registration model', 'Yes', 'No', 'Cancel', 'Yes');
                switch answer
                    case "Yes"
                        % export the registration model
                        CMT_exportRegModel(app);
                        % auto-save configuration to update model exporting flag
                        CMT_autosaveConfig(app, 'config_latest');
                        % set flag for this UI closing action
                        flag_RegModel_saved = true;
                    case "No"
                        % save the model anyway :), but it will be overwritten if anyother model is trained
                        CMT_exportRegModel_auto(app);
                    case "Cancel"
                        return;
                end
            end

            % [3] ask for saving configuration and status before closing the UI
            if flag_SegModel_saved || flag_RegModel_saved
                if flag_SegModel_saved && flag_RegModel_saved
                    model_saved_msg = "the segmentation and registration models";
                elseif ~flag_SegModel_saved && flag_RegModel_saved
                    model_saved_msg = "the registration model";
                elseif flag_SegModel_saved && ~flag_RegModel_saved
                    model_saved_msg = "the segmentation model";
                end
                quest = strcat('Would you like to save the project configuration and status? ',...
                    "You just exported ", model_saved_msg,...
                    ". You should save the configuration and status to update your action record.");
                answer = questdlg(quest, 'Save project configuration and status', 'Yes', 'No', 'Cancel', 'Yes');
            else
                answer = questdlg('Would you like to save the project configuration and status?',...
                    'Save project configuration and status', 'Yes', 'No', 'Cancel', 'Yes');
            end

            switch answer
                case "Yes"
                    if ~isempty(app.config_nnUNet_ID) && ~isempty(app.config_nnUNet_task) && ~isempty(app.dir_base)
                        % ask to save the project configuration
                        CMT_saveConfig(app);
                        % close the app
                        CMT_closeAllWindows(app)
                    else
                        uialert(app.CartiMorphToolboxUIFigure, "Please set the 'Project ID', 'Project Code Name', and 'CartiMorph Toolbox Folder' first.",...
                            'Missing configuration', 'icon', 'error');
                        return;
                    end
                case "No"
                    % close the app
                    CMT_closeAllWindows(app)
                case "Cancel"
                    return;
            end

            % [4] delete empty log file
            % Check if the log file is empty
            fseek(app.container_logFileID, 0, 'eof');  % Move the file position indicator to the end
            fileSize = ftell(app.container_logFileID); % Get the current position of the file position indicator
            if fileSize == 0
                % Delete the log file
                path_logFile = fopen(app.container_logFileID);
                fclose(app.container_logFileID); % Close the file if it's open
                delete(path_logFile);
            end
        end

        % Button pushed function: Button_iii2_reset
        function Button_iii2_resetPushed(app, event)
            % freeze the button
            app.Button_iii2_reset.Enable = "off";

            % reset the variable "dir_nnUNet_pretrained_model"
            app.dir_nnUNet_pretrained_model = '';

            % [reset ii]
            app.config_nnUNet_imgSize = NaN(1,3);
            app.EditField_ii_imgSizeX.Value = '';
            app.EditField_ii_imgSizeY.Value = '';
            app.EditField_ii_imgSizeZ.Value = '';

            % [reset iii2]
            % Panel iii2
            app.Panel_iii2.Visible = "on";
            % iii2
            app.Lamp_iii2.Visible = "on";
            app.Lamp_iii2.Color = [0.65,0.65,0.65];
            app.CheckBox_iii2.Value = true;
            app.CheckBox_iii2.Enable = "on";
            app.Label_iii2.Enable = "on";
            % iii2-deploy
            app.Lamp_iii2_deploy.Visible = "on";
            app.Lamp_iii2_deploy.Color = [0.65,0.65,0.65];
            app.opt_nnUNet_training = false;
            app.CheckBox_iii2_deploy.Value = true;
            app.CheckBox_iii2_deploy.Enable = "on";
            app.Label_iii2_deploy.Enable = "on";
            % iii2-finetune
            app.Lamp_iii2_finetune.Visible = "off";
            app.CheckBox_iii2_finetune.Value = false;
            app.CheckBox_iii2_finetune.Enable = "on";
            app.Label_iii2_finetune.Enable = "on";
            % iii2-prepro
            app.Lamp_iii2_prepro.Color = [0.65,0.65,0.65];
            app.Lamp_iii2_prepro.Visible = "off";
            app.opt_nnUNet_planning_preprocessing = false;
            app.CheckBox_iii2_prepro.Value = false;
            app.CheckBox_iii2_prepro.Enable ="on";
            app.Label_iii2_prepro.Enable = "on";
            % iii2-finetuning
            app.Lamp_iii2_finetuning.Visible = "off";
            app.opt_nnUNet_training_begin = false;
            app.CheckBox_iii2_finetuning.Value = false;
            app.CheckBox_iii2_finetuning.Enable = "on";
            app.Label_iii2_finetuning.Enable = "on";
            % iii2-GPU
            app.EditField_iii2_GPU.Value = '';
            app.EditField_iii2_GPU.Enable = "on";
            % iii2-epoch
            app.EditFieldLabel_iii2_epoch.Enable = "off";
            app.EditField_iii2_epoch.Value = '';
            app.EditField_iii2_epoch.Enable = "off";
            % iii2-loadModel
            app.Lamp_iii2_loadModel.Color = [0.65,0.65,0.65];
            app.Label_iii2_Model.Enable = "on";
            app.Button_iii2_loadmodel.Enable = "on";
            if app.CheckBox_iv2_deploy.Value
                % folders for training images and labels
                app.EditField_imgTr.Enable = "off";
                app.EditFieldLabel_imgTr.Enable = "off";
                app.Button_selectPath_imgTr.Enable = "off";
                app.EditField_imgTs.Enable = "off";
                app.EditFieldLabel_imgTs.Enable = "off";
                app.Button_selectPath_imgTs.Enable = "off";
                app.EditField_labelTr.Enable = "off";
                app.EditFieldLabel_labelTr.Enable = "off";
                app.Button_selectPath_labelTr.Enable = "off";
                app.EditField_labelTs.Enable = "off";
                app.EditFieldLabel_labelTs.Enable = "off";
                app.Button_selectPath_labelTs.Enable = "off";
                % v
                app.Lamp_v.Visible = "on";
                app.opt_nnUNet_inference = true;
                app.CheckBox_v.Value = true;
                app.EditField_v_GPU.Visible = "on";
                % folder for inference images
                app.EditFieldLabel_imgInference.Enable = "on";
                app.EditField_imgInference.Enable = "on";
                app.Button_selectPath_imgInference.Enable = "on";
            end

            % reset segmentation labels
            app.EditField_Femur.Value = '';
            app.EditField_FemoralCartilage.Value = '';
            app.EditField_Tibia.Value = '';
            app.EditField_TibialCartilagemedial.Value = '';
            app.EditField_TibialCartilagelateral.Value = '';
            app.EditField_background.Value = '';
            app.config_ROI_label{2} = '';
            app.config_ROI_label{3} = '';
            app.config_ROI_label{4} = '';
            app.config_ROI_label{5} = '';
            app.config_ROI_label{6} = '';
            app.config_ROI_label{1} = '';

            % reset image size in (ii)
            app.EditField_ii_imgSizeX.Value = '';
            app.EditField_ii_imgSizeY.Value = '';
            app.EditField_ii_imgSizeZ.Value = '';
            app.config_nnUNet_imgSize(1,1) = nan;
            app.config_nnUNet_imgSize(1,2) = nan;
            app.config_nnUNet_imgSize(1,3) = nan;

            % enable the initialization of toolbox
            app.opt_CMT_init = true;

            % make section (I) editable (important)
            app.EditField_ProjectID.Editable = "on";
            app.EditField_ProjectCodeName.Editable = "on";
            app.EditField_pathCMT.Editable = "on";
            app.Button_selectpathCMT.Enable = "on";
            app.EditField_pathConda.Editable = "on";
            app.Button_selectpathConda.Enable = "on";

            % set the button back
            app.Button_iii2_reset.Enable = "on";
            app.Button_iii2_reset.Visible = "off";
        end

        % Button pushed function: Button_iv2_reset
        function Button_iv2_resetPushed(app, event)
            % freeze the button
            app.Button_iv2_reset.Enable = "off";

            % reset the variable "dir_vxm_pretrained_model"
            app.dir_vxm_pretrained_model = '';

            % [reset iv2]
            % Panel iv2
            app.Panel_iv2.Visible = "on";
            % iv2
            app.Lamp_iv2.Visible = "on";
            app.Lamp_iv2.Color = [0.65,0.65,0.65];
            app.CheckBox_iv2.Enable = "on";
            % iv2-deploy
            app.Lamp_iv2_deploy.Visible = "on";
            app.Lamp_iv2_deploy.Color = [0.65,0.65,0.65];
            app.opt_vxm_training = false;
            app.CheckBox_iv2_deploy.Value = true;
            app.CheckBox_iv2_deploy.Enable = "on";
            app.Label_iv2_deploy.Enable = "on";
            % iv2-finetune
            app.Lamp_iv2_finetune.Visible = "off";
            app.CheckBox_iv2_finetune.Value = false;
            app.CheckBox_iv2_finetune.Enable = "on";
            app.Label_iv2_finetune.Enable = "on";
            % iv2-prepro
            app.Lamp_iv2_prepro.Visible = "off";
            app.opt_vxm_preprocessing = false;
            app.CheckBox_iv2_prepro.Value = false;
            app.CheckBox_iv2_prepro.Enable = "on";
            app.Label_iv2_prepro.Enable = "on";
            app.EditFieldLabel_iv2_CroppedImageSize.Enable = "off";
            app.Lamp_iv2_prepro.Color = [0.651 0.651 0.651];
            % iv2-croppedImageSize
            app.config_vxm_imgSize_out = NaN(1,3);
            app.EditField_iv2_CroppedImageSizeX.Value = '';
            app.EditField_iv2_CroppedImageSizeY.Value = '';
            app.EditField_iv2_CroppedImageSizeZ.Value = '';
            app.EditField_iv2_CroppedImageSizeX.Enable = "off";
            app.EditField_iv2_CroppedImageSizeY.Enable = "off";
            app.EditField_iv2_CroppedImageSizeZ.Enable = "off";
            % iv2-finetuning
            app.Lamp_iv2_finetuning.Visible = "off";
            app.opt_vxm_training_begin = false;
            app.CheckBox_iv2_finetuning.Value = false;
            app.CheckBox_iv2_finetuning.Enable = "on";
            app.Label_iv2_finetuning.Enable = "on";
            % iv2-GPU
            app.EditField_iv2_GPU.Enable = "on";
            app.EditField_iv2_GPU.Value = '';
            if app.CheckBox_iii2_deploy.Value
                % folders for training images and labels
                app.EditField_imgTr.Enable = "off";
                app.EditFieldLabel_imgTr.Enable = "off";
                app.Button_selectPath_imgTr.Enable = "off";
                app.EditField_imgTs.Enable = "off";
                app.EditFieldLabel_imgTs.Enable = "off";
                app.Button_selectPath_imgTs.Enable = "off";
                app.EditField_labelTr.Enable = "off";
                app.EditFieldLabel_labelTr.Enable = "off";
                app.Button_selectPath_labelTr.Enable = "off";
                app.EditField_labelTs.Enable = "off";
                app.EditFieldLabel_labelTs.Enable = "off";
                app.Button_selectPath_labelTs.Enable = "off";
                % v
                app.Lamp_v.Visible = "on";
                app.opt_nnUNet_inference = true;
                app.CheckBox_v.Value = true;
                app.EditField_v_GPU.Visible = "on";
                % folder for inference images
                app.EditFieldLabel_imgInference.Enable = "on";
                app.EditField_imgInference.Enable = "on";
                app.Button_selectPath_imgInference.Enable = "on";
            end
            % iv2-loadModel
            app.Button_iv2_loadmodel.Enable = "on";
            app.Label_iv2_Model.Enable = "on";
            app.Lamp_iv2_loadModel.Color = [0.65,0.65,0.65];

            % [reset vi]
            % vi-croppedImageRange
            app.EditField_vi_CroppedImageSizeX.Value = '';
            app.EditField_vi_CroppedImageSizeY.Value = '';
            app.EditField_vi_CroppedImageSizeZ.Value = '';
            app.config_vxm_imgSize_out(1,1) = nan;
            app.config_vxm_imgSize_out(1,2) = nan;
            app.config_vxm_imgSize_out(1,3) = nan;

            % enable the initialization of toolbox
            app.opt_CMT_init = true;

            % make section (I) editable (important)
            app.EditField_ProjectID.Editable = "on";
            app.EditField_ProjectCodeName.Editable = "on";
            app.EditField_pathCMT.Editable = "on";
            app.Button_selectpathCMT.Enable = "on";
            app.EditField_pathConda.Editable = "on";
            app.Button_selectpathConda.Enable = "on";

            % set the button back
            app.Button_iv2_reset.Enable = "on";
            app.Button_iv2_reset.Visible = "off";
        end

        % Value changed function: EditField_ii_imgSizeX
        function EditField_ii_imgSizeXValueChanged(app, event)
            if ~isempty(app.EditField_ii_imgSizeX.Value)
                value = str2double(app.EditField_ii_imgSizeX.Value);
                if isnan(value) || ~isreal(value) % if user enter a string
                    uialert(app.CartiMorphToolboxUIFigure, 'You need to enter a number.', 'Error');
                    app.EditField_ii_imgSizeX.Value = '';
                elseif isinf(value) || floor(value)~=value || value <=0
                    uialert(app.CartiMorphToolboxUIFigure, 'You need to enter a positive integer.', 'Error');
                    app.EditField_ii_imgSizeX.Value = '';
                else
                    app.config_nnUNet_imgSize(1,1) = value;
                end
            else
                app.config_nnUNet_imgSize(1,1) = nan;
                app.EditField_ii_imgSizeX.Value = '';
                % iv-croppedImageSize
                if ~isempty(app.EditField_iv_CroppedImageSizeX.Value)
                    app.EditField_iv_CroppedImageSizeX.Value = '';
                    app.config_vxm_imgSize_out(1,1) = nan;
                end
            end
        end

        % Value changed function: EditField_ii_imgSizeY
        function EditField_ii_imgSizeYValueChanged(app, event)
            if ~isempty(app.EditField_ii_imgSizeY.Value)
                value = str2double(app.EditField_ii_imgSizeY.Value);
                if isnan(value) || ~isreal(value) % if user enter a string
                    uialert(app.CartiMorphToolboxUIFigure, 'You need to enter a number.', 'Error');
                    app.EditField_ii_imgSizeY.Value = '';
                elseif isinf(value) || floor(value)~=value || value <=0
                    uialert(app.CartiMorphToolboxUIFigure, 'You need to enter a positive integer.', 'Error');
                    app.EditField_ii_imgSizeY.Value = '';
                else
                    app.config_nnUNet_imgSize(1,2) = value;
                end
            else
                app.config_nnUNet_imgSize(1,2) = nan;
                app.EditField_ii_imgSizeY.Value = '';
                % iv-croppedImageSize
                if ~isempty(app.EditField_iv_CroppedImageSizeY.Value)
                    app.EditField_iv_CroppedImageSizeY.Value = '';
                    app.config_vxm_imgSize_out(1,2) = nan;
                end
            end
        end

        % Value changed function: EditField_ii_imgSizeZ
        function EditField_ii_imgSizeZValueChanged(app, event)
            if ~isempty(app.EditField_ii_imgSizeZ.Value)
                value = str2double(app.EditField_ii_imgSizeZ.Value);
                if isnan(value) || ~isreal(value) % if user enter a string
                    uialert(app.CartiMorphToolboxUIFigure, 'You need to enter a number.', 'Error');
                    app.EditField_ii_imgSizeZ.Value = '';
                elseif isinf(value) || floor(value)~=value || value <=0
                    uialert(app.CartiMorphToolboxUIFigure, 'You need to enter a positive integer.', 'Error');
                    app.EditField_ii_imgSizeZ.Value = '';
                else
                    app.config_nnUNet_imgSize(1,3) = value;
                end
            else
                app.config_nnUNet_imgSize(1,3) = nan;
                app.EditField_ii_imgSizeZ.Value = '';
                % iv-croppedImageSize
                if ~isempty(app.EditField_iv_CroppedImageSizeZ.Value)
                    app.EditField_iv_CroppedImageSizeZ.Value = '';
                    app.config_vxm_imgSize_out(1,3) = nan;
                end
            end
        end

        % Value changed function: EditField_iv2_epoch
        function EditField_iv2_epochValueChanged(app, event)
            if ~isempty(app.EditField_iv2_epoch.Value)
                value = str2double(app.EditField_iv2_epoch.Value);
                if isnan(value) || ~isreal(value) % if user enter a string
                    uialert(app.CartiMorphToolboxUIFigure, 'You need to enter a number.', 'Error');
                    app.EditField_iv2_epoch.Value = num2str(app.config_vxm_epochs);
                elseif isinf(value) || floor(value)~=value || value<=0
                    uialert(app.CartiMorphToolboxUIFigure, 'You need to enter an positive integer.', 'Error');
                    app.EditField_iv2_epoch.Value = num2str(app.config_vxm_epochs);
                else
                    app.config_vxm_epochs = value;
                end
            else
                app.EditField_iv2_epoch.Value = num2str(app.config_vxm_epochs);
            end
        end

        % Value changed function: EditField_iii_epoch
        function EditField_iii_epochValueChanged(app, event)
            if ~isempty(app.EditField_iii_epoch.Value)
                value = str2double(app.EditField_iii_epoch.Value);
                if isnan(value) || ~isreal(value) % if user enter a string
                    uialert(app.CartiMorphToolboxUIFigure, 'You need to enter a number.', 'Error');
                    app.EditField_iii_epoch.Value = num2str(app.config_nnUNet_epochs);
                elseif isinf(value) || floor(value)~=value || value<=0
                    uialert(app.CartiMorphToolboxUIFigure, 'You need to enter an positive integer.', 'Error');
                    app.EditField_iii_epoch.Value = num2str(app.config_nnUNet_epochs);
                else
                    app.config_nnUNet_epochs = value;
                end
            else
                app.EditField_iii_epoch.Value = num2str(app.config_nnUNet_epochs);
            end
        end

        % Value changed function: EditField_iii2_epoch
        function EditField_iii2_epochValueChanged(app, event)
            if ~isempty(app.EditField_iii2_epoch.Value)
                value = str2double(app.EditField_iii2_epoch.Value);
                if isnan(value) || ~isreal(value) % if user enter a string
                    uialert(app.CartiMorphToolboxUIFigure, 'You need to enter a number.', 'Error');
                    app.EditField_iii2_epoch.Value = num2str(app.config_nnUNet_epochs);
                elseif isinf(value) || floor(value)~=value || value<=0
                    uialert(app.CartiMorphToolboxUIFigure, 'You need to enter an positive integer.', 'Error');
                    app.EditField_iii2_epoch.Value = num2str(app.config_nnUNet_epochs);
                else
                    app.config_nnUNet_epochs = value;
                end
            else
                app.EditField_iii2_epoch.Value = num2str(app.config_nnUNet_epochs);
            end
        end

        % Value changed function: Button_exportSegModel
        function Button_exportSegModelValueChanged(app, event)
            % disable the buttons
            CMT_disableButtons(app);

            % export model
            value = app.Button_exportSegModel.Value;
            if value
                if app.CheckBox_iii2_deploy.Value
                    uialert(app.CartiMorphToolboxUIFigure, 'You have not enabled segmentation model training.', 'Error', 'CloseFcn','uiresume(gcbf)');
                    uiwait(gcbf);
                elseif ~isfield(app.container_jobStatus, 'job_nnUNet_train')
                    uialert(app.CartiMorphToolboxUIFigure, 'You have not started segmentation model training.', 'Error', 'CloseFcn','uiresume(gcbf)');
                    uiwait(gcbf);
                elseif ~strcmp(app.container_jobStatus.job_nnUNet_train, "finished")
                    uialert(app.CartiMorphToolboxUIFigure, 'The training of the segmentation model is incomplete.', 'Error', 'CloseFcn','uiresume(gcbf)');
                    uiwait(gcbf);
                else
                    CMT_exportSegModel(app);
                end
            end

            % enable the buttons
            CMT_enableButtons(app);
            if ~app.Button_Pause.Enable % not during pipeline running
                % load
                app.Button_Load.Enable = "on";
                app.Button_Load.Value = false;
                % run
                app.Button_Run.Enable = "on";
            end
        end

        % Value changed function: Button_exportRegModel
        function Button_exportRegModelValueChanged(app, event)
            % disable the buttons
            CMT_disableButtons(app);

            % export model
            value = app.Button_exportRegModel.Value;
            if value
                if app.CheckBox_iv2_deploy.Value
                    uialert(app.CartiMorphToolboxUIFigure, 'You have not enabled registration model training.', 'Error', 'CloseFcn','uiresume(gcbf)');
                    uiwait(gcbf);
                elseif ~isfield(app.container_jobStatus, 'job_vxm_train')
                    uialert(app.CartiMorphToolboxUIFigure, 'You have not started registration model training.', 'Error', 'CloseFcn','uiresume(gcbf)');
                    uiwait(gcbf);
                elseif ~strcmp(app.container_jobStatus.job_vxm_train, "finished")
                    uialert(app.CartiMorphToolboxUIFigure, 'The training of the registration model is incomplete.', 'Error', 'CloseFcn','uiresume(gcbf)');
                    uiwait(gcbf);
                else
                    CMT_exportRegModel(app);
                end
            end

            % enable the buttons
            CMT_enableButtons(app);
            % enable the buttons
            CMT_enableButtons(app);
            if ~app.Button_Pause.Enable % not during pipeline running
                % load
                app.Button_Load.Enable = "on";
                app.Button_Load.Value = false;
                % run
                app.Button_Run.Enable = "on";
            end
        end

        % Button pushed function: Button_Pause
        function Button_PauseValueChanged(app, event)
            % the Pause button is actually with text "Pause      " where the space is for better visualization
            if ~app.status_isPaused && strcmp(app.Button_Pause.Text, "Pause      ")
                % set the status
                app.status_isPaused = true;
                % change button color
                app.Button_Pause.BackgroundColor = [0.851, 0.851, 0.851];
                % change the "run" button
                app.Button_Run.Text = "Paused";
                app.Button_Run.BackgroundColor = [244, 167, 85]./255; % #F4A755 (yellow)
            elseif strcmp(app.Button_Pause.Text, "Resume      ")
                % set the status
                app.status_isPaused = false;
                % change the pause button color
                app.Button_Pause.BackgroundColor = [0.96, 0.96, 0.96];
                % disable the export buttons
                app.Button_exportSegModel.Enable = "off";
                app.Button_exportRegModel.Enable = "off";
                % disable models evaluation buttons
                if ~app.CheckBox_v.Enable  % meaning the segmentation model inference is finished
                    app.Button_evalSegModel.Enable = "off";
                end
                if ~app.CheckBox_vi.Enable  % meaning the registration model inference is finished
                    app.Button_evalRegModel.Enable = "off";
                end
                % disable the save button
                app.Button_Save.Enable = "off";
                % disalbe panels
                app.Panel_ComputingEnvironmentConfiguration.Enable = "off";
                app.Panel_ImageProcessingPipeline.Enable = "off";
                app.Panel_ImageDataInformation.Enable = "off";
                % reset the button
                app.Button_Pause.Text = "Pause      ";
                % change the "run" button
                app.Button_Run.Text = "Running";
                app.Button_Run.BackgroundColor = [143, 187, 118]./255; % #8FBB76 (green)
            end
        end

        % Value changed function: Button_evalSegModel
        function Button_evalSegModelValueChanged(app, event)
            % disable the buttons
            CMT_disableButtons(app);

            % model evaluation
            path_DSC = fullfile(app.dir_nnUNet_prediction_testSet, 'metrics_DSC.mat');
            path_DSC_mean = fullfile(app.dir_nnUNet_prediction_testSet, 'metrics_DSC_mean.mat');
            value = app.Button_evalSegModel.Value;
            if value
                if ~exist(path_DSC, "file") || ~exist(path_DSC_mean, "file")
                    [tab_DSC, tab_DSC_mean] = CMT_evalSegModel(app);
                    % save results to prediction folder
                    save(path_DSC, 'tab_DSC');
                    save(path_DSC_mean, 'tab_DSC_mean');
                else
                    quest = 'Model evaluation metric exists. What would you like to do next?';
                    dlgtitle = 'Old model evaluation metric detected';
                    answer = questdlg(quest, dlgtitle, 'load the metric', 'redo model evaluation', 'load the metric');
                    switch answer
                        case 'load the metric'
                            % load results
                            tmp = load(path_DSC_mean);
                            tab_DSC_mean = tmp.tab_DSC_mean;
                        case 'redo model evaluation'
                            [tab_DSC, tab_DSC_mean] = CMT_evalSegModel(app);
                            % save results to prediction folder
                            save(path_DSC, 'tab_DSC');
                            save(path_DSC_mean, 'tab_DSC_mean');
                    end
                end
                % display mean DSC
                msg = sprintf(...
                    strcat("The segmentation model performance was evaluated by Dice Similarity Coefficient (DSC). \n",...
                    "Mean DSC (for labels in increasing order) are: \n", mat2str(table2array(tab_DSC_mean)))...
                    );
                uialert(app.CartiMorphToolboxUIFigure, msg, 'Info');
            end

            % enable the buttons
            CMT_enableButtons(app);
            % enable the buttons
            CMT_enableButtons(app);
            if ~app.Button_Pause.Enable % not during pipeline running
                % load
                app.Button_Load.Enable = "on";
                app.Button_Load.Value = false;
                % run
                app.Button_Run.Enable = "on";
            end
        end

        % Value changed function: Button_evalRegModel
        function Button_evalRegModelValueChanged(app, event)
            % disable the buttons
            CMT_disableButtons(app);

            % disable panels
            app.Panel_ComputingEnvironmentConfiguration.Enable = "off";
            app.Panel_ImageProcessingPipeline.Enable = "off";
            app.Panel_ImageDataInformation.Enable = "off";

            % model evaluation
            path_DSC = fullfile(app.dir_vxm_inference_warpedTempSeg_Ts, 'metrics_DSC.mat');
            path_DSC_mean = fullfile(app.dir_vxm_inference_warpedTempSeg_Ts, 'metrics_DSC_mean.mat');
            value = app.Button_evalRegModel.Value;
            if value
                if ~exist(path_DSC, "file") || ~exist(path_DSC_mean, "file")
                    [tab_DSC, tab_DSC_mean] = CMT_evalRegModel(app);
                    % save results to prediction folder
                    save(path_DSC, 'tab_DSC');
                    save(path_DSC_mean, 'tab_DSC_mean');
                else
                    quest = 'Model evaluation metric exists. What would you like to do next?';
                    dlgtitle = 'Old model evaluation metric detected';
                    answer = questdlg(quest, dlgtitle, 'load the metric', 'redo model evaluation', 'load the metric');
                    switch answer
                        case 'load the metric'
                            % load results
                            tmp = load(path_DSC_mean);
                            tab_DSC_mean = tmp.tab_DSC_mean;
                        case 'redo model evaluation'
                            [tab_DSC, tab_DSC_mean] = CMT_evalRegModel(app);
                            % save results to prediction folder
                            save(path_DSC, 'tab_DSC');
                            save(path_DSC_mean, 'tab_DSC_mean');
                    end
                end
                % display mean DSC
                msg = sprintf(...
                    strcat("The registration model performance was evaluated by Dice Similarity Coefficient (DSC). \n",...
                    "Mean DSC (for labels in increasing order) are: \n", mat2str(table2array(tab_DSC_mean)))...
                    );
                uialert(app.CartiMorphToolboxUIFigure, msg, 'Info');
            end

            % enable the buttons
            CMT_enableButtons(app);
            % enable the buttons
            CMT_enableButtons(app);
            if ~app.Button_Pause.Enable % not during pipeline running
                % load
                app.Button_Load.Enable = "on";
                app.Button_Load.Value = false;
                % run
                app.Button_Run.Enable = "on";
            end
        end

        % Value changed function: TipsButton
        function TipsButtonValueChanged(app, event)
            app.TipsButton.Enable = "off";

            value = app.TipsButton.Value;
            if value
                message = sprintf('Fill in the fields in this order: I, II (i-vii), and III');
                uialert(app.CartiMorphToolboxUIFigure, message, 'Tips',...
                    'icon', 'info', 'CloseFcn','uiresume(gcbf)');
                uiwait(gcbf);
            end

            app.TipsButton.Enable = "on";
            app.TipsButton.Value = false;
        end

        % Value changed function: AboutButton
        function AboutButtonValueChanged(app, event)
            app.AboutButton.Enable = "off";

            value = app.AboutButton.Value;
            if value
                app.aboutCMT = AboutCMT(app);
            end
        end

        % Value changed function: Button_CMV
        function Button_CMVValueChanged(app, event)
            app.Button_CMV.Enable = "off";

            value = app.Button_CMV.Value;
            if value
                app.CMViewer = CartiMorphViewer(app);
            end
        end

        % Value changed function: CheckBox_iv2_Freeze
        function CheckBox_iv2_FreezeValueChanged(app, event)
            value = app.CheckBox_iv2_Freeze.Value;
            if value
                app.opt_vxm_freezeTemp = true;
            else
                app.opt_vxm_freezeTemp = false;
            end
        end

        % Selection changed function: ButtonGroup_Loss_iv
        function ButtonGroup_Loss_ivSelectionChanged(app, event)
            selectedButton = app.ButtonGroup_Loss_iv.SelectedObject;
            if strcmp(selectedButton.Text, 'MSE')
                app.config_vxm_imgLoss = 'MSE';
                % synchronize button group in iv2
                app.ButtonGroup_Loss_iv2.SelectedObject = app.Button_MSE_iv2;
            elseif strcmp(selectedButton.Text, 'LNCC')
                app.config_vxm_imgLoss = 'LNCC';
                % synchronize button group in iv2
                app.ButtonGroup_Loss_iv2.SelectedObject = app.Button_LNCC_iv2;
            elseif strcmp(selectedButton.Text, 'MSE+LNCC')
                app.config_vxm_imgLoss = 'MSE+LNCC';
                % synchronize button group in iv2
                app.ButtonGroup_Loss_iv2.SelectedObject = app.Button_MSE_LNCC_iv2;
            end
        end

        % Selection changed function: ButtonGroup_Loss_iv2
        function ButtonGroup_Loss_iv2SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_Loss_iv2.SelectedObject;
            if strcmp(selectedButton.Text, 'MSE')
                app.config_vxm_imgLoss = 'MSE';
                % synchronize button group in iv
                app.ButtonGroup_Loss_iv.SelectedObject = app.Button_MSE_iv;
            elseif strcmp(selectedButton.Text, 'LNCC')
                app.config_vxm_imgLoss = 'LNCC';
                % synchronize button group in iv
                app.ButtonGroup_Loss_iv.SelectedObject = app.Button_LNCC_iv;
            elseif strcmp(selectedButton.Text, 'MSE+LNCC')
                app.config_vxm_imgLoss = 'MSE+LNCC';
                % synchronize button group in iv
                app.ButtonGroup_Loss_iv.SelectedObject = app.Button_MSE_LNCC_iv;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.CartiMorphToolboxUIFigure = uifigure('Visible', 'off');
            app.CartiMorphToolboxUIFigure.Name = 'CartiMorph Toolbox';
            app.CartiMorphToolboxUIFigure.Icon = fullfile(pathToMLAPP, 'UI', 'appLogo.png');
            app.CartiMorphToolboxUIFigure.CloseRequestFcn = createCallbackFcn(app, @CartiMorphToolboxUIFigureCloseRequest, true);
            app.Panel_CMT = uipanel(app.CartiMorphToolboxUIFigure);
            app.Image = uiimage(app.Panel_CMT);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'UI', 'logo_CMT.png');
            app.Panel_actions = uipanel(app.Panel_CMT);
            app.Button_CMV = uibutton(app.Panel_actions, 'state');
            app.Button_CMV.ValueChangedFcn = createCallbackFcn(app, @Button_CMVValueChanged, true);
            app.Button_CMV.Enable = 'off';
            app.Button_CMV.Text = {'CartiMorph '; 'Viewer'};
            app.Button_Pause = uibutton(app.Panel_actions, 'push');
            app.Button_Pause.ButtonPushedFcn = createCallbackFcn(app, @Button_PauseValueChanged, true);
            app.Button_Pause.Enable = 'off';
            app.Button_Pause.Text = 'Pause      ';
            app.Button_Save = uibutton(app.Panel_actions, 'state');
            app.Button_Save.ValueChangedFcn = createCallbackFcn(app, @Button_SaveValueChanged, true);
            app.Button_Save.Text = 'Save Configuration & Status';
            app.Button_Load = uibutton(app.Panel_actions, 'state');
            app.Button_Load.ValueChangedFcn = createCallbackFcn(app, @Button_LoadValueChanged, true);
            app.Button_Load.Text = 'Load Configuration & Status';
            app.Button_Run = uibutton(app.Panel_actions, 'state');
            app.Button_Run.ValueChangedFcn = createCallbackFcn(app, @Button_RunValueChanged, true);
            app.Button_Run.Text = 'Run';
            app.Button_exportSegModel = uibutton(app.Panel_actions, 'state');
            app.Button_exportSegModel.ValueChangedFcn = createCallbackFcn(app, @Button_exportSegModelValueChanged, true);
            app.Button_exportSegModel.Enable = 'off';
            app.Button_exportSegModel.Text = 'Export Segmentation Model';
            app.Button_exportRegModel = uibutton(app.Panel_actions, 'state');
            app.Button_exportRegModel.ValueChangedFcn = createCallbackFcn(app, @Button_exportRegModelValueChanged, true);
            app.Button_exportRegModel.Enable = 'off';
            app.Button_exportRegModel.Text = 'Export Registration Model';
            app.Image_Info_pause = uiimage(app.Panel_actions);
            app.Image_Info_pause.Enable = 'off';
            app.Image_Info_pause.Tooltip = {'Pressing the "Pause" button may not cause the toolbox to pause immediately, as it only stops at certain internal checkpoints. Therefore, you should wait for the "Pause" button to change to "Resume". Once it changes, you can press the "Resume" button to continue all paused jobs. Note that the "Pause" button will not pause models training and inference since it was designed to assist configuration and status saving when the pipeline is running.'};
            app.Image_Info_pause.ImageSource = fullfile(pathToMLAPP, 'UI', 'info.png');
            app.Button_evalSegModel = uibutton(app.Panel_actions, 'state');
            app.Button_evalSegModel.ValueChangedFcn = createCallbackFcn(app, @Button_evalSegModelValueChanged, true);
            app.Button_evalSegModel.Enable = 'off';
            app.Button_evalSegModel.Text = 'Evaluate Segmentation Model';
            app.Button_evalRegModel = uibutton(app.Panel_actions, 'state');
            app.Button_evalRegModel.ValueChangedFcn = createCallbackFcn(app, @Button_evalRegModelValueChanged, true);
            app.Button_evalRegModel.Enable = 'off';
            app.Button_evalRegModel.Text = 'Evaluate Registration Model';
            app.Panel_ComputingEnvironmentConfiguration = uipanel(app.Panel_CMT);
            app.Panel_ComputingEnvironmentConfiguration.Title = 'I. Computing Environment Configuration';
            app.EditFieldLabel_ProjectID = uilabel(app.Panel_ComputingEnvironmentConfiguration);
            app.EditFieldLabel_ProjectID.Text = 'Project ID (3 digits)';
            app.EditField_ProjectID = uieditfield(app.Panel_ComputingEnvironmentConfiguration, 'text');
            app.EditField_ProjectID.ValueChangedFcn = createCallbackFcn(app, @EditField_ProjectIDValueChanged, true);
            app.EditFieldLabel_ProjectCodeName = uilabel(app.Panel_ComputingEnvironmentConfiguration);
            app.EditFieldLabel_ProjectCodeName.Text = 'Project Code Name';
            app.EditField_ProjectCodeName = uieditfield(app.Panel_ComputingEnvironmentConfiguration, 'text');
            app.EditField_ProjectCodeName.ValueChangedFcn = createCallbackFcn(app, @EditField_ProjectCodeNameValueChanged, true);
            app.EditFieldLabel_pathCMT = uilabel(app.Panel_ComputingEnvironmentConfiguration);
            app.EditFieldLabel_pathCMT.Text = 'CartiMorph Toolbox Folder';
            app.EditField_pathCMT = uieditfield(app.Panel_ComputingEnvironmentConfiguration, 'text');
            app.EditField_pathCMT.ValueChangedFcn = createCallbackFcn(app, @EditField_pathCMTValueChanged, true);
            app.Button_selectpathCMT = uibutton(app.Panel_ComputingEnvironmentConfiguration, 'push');
            app.Button_selectpathCMT.ButtonPushedFcn = createCallbackFcn(app, @Button_selectpathCMTPushed, true);
            app.Button_selectpathCMT.Text = 'select folder';
            app.EditField_pathConda = uieditfield(app.Panel_ComputingEnvironmentConfiguration, 'text');
            app.EditField_pathConda.ValueChangedFcn = createCallbackFcn(app, @EditField_pathCondaValueChanged, true);
            app.EditFieldLabel_Conda = uilabel(app.Panel_ComputingEnvironmentConfiguration);
            app.EditFieldLabel_Conda.Text = 'Conda Folder';
            app.Button_selectpathConda = uibutton(app.Panel_ComputingEnvironmentConfiguration, 'push');
            app.Button_selectpathConda.ButtonPushedFcn = createCallbackFcn(app, @Button_selectpathCondaPushed, true);
            app.Button_selectpathConda.Text = 'select folder';
            app.Panel_ImageProcessingPipeline = uipanel(app.Panel_CMT);
            app.Panel_ImageProcessingPipeline.Title = 'II. Image Processing Pipeline';
            app.Panel_i = uipanel(app.Panel_ImageProcessingPipeline);
            app.Image_i_Info = uiimage(app.Panel_i);
            app.Image_i_Info.Tooltip = {'Network connection is required.'};
            app.Image_i_Info.ImageSource = fullfile(pathToMLAPP, 'UI', 'info.png');
            app.CheckBox_i = uicheckbox(app.Panel_i);
            app.CheckBox_i.ValueChangedFcn = createCallbackFcn(app, @CheckBox_iValueChanged, true);
            app.CheckBox_i.Text = '';
            app.CheckBox_i.Value = true;
            app.iComputingEnvironmentInitializationLabel = uilabel(app.Panel_i);
            app.iComputingEnvironmentInitializationLabel.Text = '(i) Computing Environment Initialization @';
            app.Lamp_i = uilamp(app.Panel_i);
            app.Panel_ii = uipanel(app.Panel_ImageProcessingPipeline);
            app.CheckBox_ii = uicheckbox(app.Panel_ii);
            app.CheckBox_ii.ValueChangedFcn = createCallbackFcn(app, @CheckBox_iiValueChanged, true);
            app.CheckBox_ii.Text = '';
            app.CheckBox_ii.Value = true;
            app.iiRawImageStandardizationLabel = uilabel(app.Panel_ii);
            app.iiRawImageStandardizationLabel.Text = '(ii) Raw Image Standardization *';
            app.Lamp_ii = uilamp(app.Panel_ii);
            app.Label_ii_ImageSize = uilabel(app.Panel_ii);
            app.Label_ii_ImageSize.Text = '-- Resampled Image Size:';
            app.EditFieldLabel_ii_imgSizeX = uilabel(app.Panel_ii);
            app.EditFieldLabel_ii_imgSizeX.Text = 'x (L-R)';
            app.EditFieldLabel_ii_imgSizeY = uilabel(app.Panel_ii);
            app.EditFieldLabel_ii_imgSizeY.Text = 'y (P-A)';
            app.EditFieldLabel_ii_imgSizeZ = uilabel(app.Panel_ii);
            app.EditFieldLabel_ii_imgSizeZ.Text = 'z (I-S)';
            app.Label_ii_orientation = uilabel(app.Panel_ii);
            app.Label_ii_orientation.Text = '(orientation: RAS+)';
            app.Image_ii_Info_orientation = uiimage(app.Panel_ii);
            app.Image_ii_Info_orientation.Tooltip = {'Images are resampled to defined image size, reoriented to RAS+, and normalized to [0,1]. RAS+ orientation: (1) the (positive) x axis in the image coordinate system (i.e., the first dimension of the 3D image) corresponds to the left-to-right (L-R) direction in the real-world  corrdinate system; (2) the (positive) y axis in the image coordinate system (i.e., the second dimension of the image) corresponds to the posterior-to-anterior (P-A) direction in the real-world  corrdinate system; (3) the (positive) z axis in the image coordinate system (i.e., the third dimension of the image) corresponds to the inferior-to-superior (I-S) direction in the real-world  corrdinate system. That is, you can control the number of slices of the resampled image in the L-R, P-A, and I-S directions by filling the fields on the right.'};
            app.Image_ii_Info_orientation.ImageSource = fullfile(pathToMLAPP, 'UI', 'info.png');
            app.EditField_ii_imgSizeX = uieditfield(app.Panel_ii, 'text');
            app.EditField_ii_imgSizeX.ValueChangedFcn = createCallbackFcn(app, @EditField_ii_imgSizeXValueChanged, true);
            app.EditField_ii_imgSizeY = uieditfield(app.Panel_ii, 'text');
            app.EditField_ii_imgSizeY.ValueChangedFcn = createCallbackFcn(app, @EditField_ii_imgSizeYValueChanged, true);
            app.EditField_ii_imgSizeZ = uieditfield(app.Panel_ii, 'text');
            app.EditField_ii_imgSizeZ.ValueChangedFcn = createCallbackFcn(app, @EditField_ii_imgSizeZValueChanged, true);
            app.Panel_iii = uipanel(app.Panel_ImageProcessingPipeline);
            app.Image_iii_Info = uiimage(app.Panel_iii);
            app.Image_iii_Info.Tooltip = {'Uncheck this box to deploy/fine-tune a pretrained segmentation model.'};
            app.Image_iii_Info.ImageSource = fullfile(pathToMLAPP, 'UI', 'info.png');
            app.CheckBox_iii = uicheckbox(app.Panel_iii);
            app.CheckBox_iii.ValueChangedFcn = createCallbackFcn(app, @CheckBox_iiiValueChanged, true);
            app.CheckBox_iii.Text = '';
            app.CheckBox_iii.Value = true;
            app.Label_iii = uilabel(app.Panel_iii);
            app.Label_iii.Text = '(iii) Train Segmentation Model';
            app.Lamp_iii = uilamp(app.Panel_iii);
            app.CheckBox_iii_prepro = uicheckbox(app.Panel_iii);
            app.CheckBox_iii_prepro.ValueChangedFcn = createCallbackFcn(app, @CheckBox_iii_preproValueChanged, true);
            app.CheckBox_iii_prepro.Text = '';
            app.CheckBox_iii_prepro.Value = true;
            app.Label_iii_prepro = uilabel(app.Panel_iii);
            app.Label_iii_prepro.Text = 'Preprocessing';
            app.Lamp_iii_prepro = uilamp(app.Panel_iii);
            app.CheckBox_iii_train = uicheckbox(app.Panel_iii);
            app.CheckBox_iii_train.ValueChangedFcn = createCallbackFcn(app, @CheckBox_iii_trainValueChanged, true);
            app.CheckBox_iii_train.Text = '';
            app.CheckBox_iii_train.Value = true;
            app.Label_iii_train = uilabel(app.Panel_iii);
            app.Label_iii_train.Text = 'Training with GPU # ';
            app.Lamp_iii_train = uilamp(app.Panel_iii);
            app.EditField_iii_GPU = uieditfield(app.Panel_iii, 'text');
            app.EditField_iii_GPU.ValueChangedFcn = createCallbackFcn(app, @EditField_iii_GPUValueChanged, true);
            app.EditField_iii_GPU.Value = '0';
            app.ButtonGroup_iii = uibuttongroup(app.Panel_iii);
            app.ButtonGroup_iii.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_iiiSelectionChanged, true);
            app.Button_iii_scratch = uitogglebutton(app.ButtonGroup_iii);
            app.Button_iii_scratch.Text = 'Train from scratch';
            app.Button_iii_scratch.Value = true;
            app.Button_iii_continue = uitogglebutton(app.ButtonGroup_iii);
            app.Button_iii_continue.Text = 'Continue training';
            app.EditFieldLabel_iii_epoch = uilabel(app.Panel_iii);
            app.EditFieldLabel_iii_epoch.Text = '-- Training Epoch';
            app.EditField_iii_epoch = uieditfield(app.Panel_iii, 'text');
            app.EditField_iii_epoch.ValueChangedFcn = createCallbackFcn(app, @EditField_iii_epochValueChanged, true);
            app.EditField_iii_epoch.Tooltip = {''};
            app.EditField_iii_epoch.Value = '500';
            app.Image_iii_epochInfo = uiimage(app.Panel_iii);
            app.Image_iii_epochInfo.Tooltip = {'This is the total number of training epoch.'};
            app.Image_iii_epochInfo.ImageSource = fullfile(pathToMLAPP, 'UI', 'info.png');
            app.Panel_iii2 = uipanel(app.Panel_ImageProcessingPipeline);
            app.CheckBox_iii2 = uicheckbox(app.Panel_iii2);
            app.CheckBox_iii2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_iii2ValueChanged, true);
            app.CheckBox_iii2.Enable = 'off';
            app.CheckBox_iii2.Text = '';
            app.Label_iii2 = uilabel(app.Panel_iii2);
            app.Label_iii2.Enable = 'off';
            app.Label_iii2.Text = '(iii-2) Deploy/Fine-tune Segmentation Model';
            app.Lamp_iii2 = uilamp(app.Panel_iii2);
            app.Lamp_iii2.Visible = 'off';
            app.CheckBox_iii2_deploy = uicheckbox(app.Panel_iii2);
            app.CheckBox_iii2_deploy.ValueChangedFcn = createCallbackFcn(app, @CheckBox_iii2_deployValueChanged, true);
            app.CheckBox_iii2_deploy.Enable = 'off';
            app.CheckBox_iii2_deploy.Text = '';
            app.Label_iii2_deploy = uilabel(app.Panel_iii2);
            app.Label_iii2_deploy.Enable = 'off';
            app.Label_iii2_deploy.Text = 'Deploy the model';
            app.Lamp_iii2_deploy = uilamp(app.Panel_iii2);
            app.Lamp_iii2_deploy.Visible = 'off';
            app.CheckBox_iii2_finetune = uicheckbox(app.Panel_iii2);
            app.CheckBox_iii2_finetune.ValueChangedFcn = createCallbackFcn(app, @CheckBox_iii2_finetuneValueChanged, true);
            app.CheckBox_iii2_finetune.Enable = 'off';
            app.CheckBox_iii2_finetune.Text = '';
            app.Label_iii2_finetune = uilabel(app.Panel_iii2);
            app.Label_iii2_finetune.Enable = 'off';
            app.Label_iii2_finetune.Text = 'Fine-tune the pre-trained model';
            app.Lamp_iii2_finetune = uilamp(app.Panel_iii2);
            app.Lamp_iii2_finetune.Visible = 'off';
            app.CheckBox_iii2_prepro = uicheckbox(app.Panel_iii2);
            app.CheckBox_iii2_prepro.ValueChangedFcn = createCallbackFcn(app, @CheckBox_iii2_preproValueChanged, true);
            app.CheckBox_iii2_prepro.Enable = 'off';
            app.CheckBox_iii2_prepro.Text = '';
            app.Label_iii2_prepro = uilabel(app.Panel_iii2);
            app.Label_iii2_prepro.Enable = 'off';
            app.Label_iii2_prepro.Text = 'Preprocessing';
            app.Lamp_iii2_prepro = uilamp(app.Panel_iii2);
            app.Lamp_iii2_prepro.Visible = 'off';
            app.CheckBox_iii2_finetuning = uicheckbox(app.Panel_iii2);
            app.CheckBox_iii2_finetuning.ValueChangedFcn = createCallbackFcn(app, @CheckBox_iii2_finetuningValueChanged, true);
            app.CheckBox_iii2_finetuning.Enable = 'off';
            app.CheckBox_iii2_finetuning.Text = '';
            app.Label_iii2_finetuning = uilabel(app.Panel_iii2);
            app.Label_iii2_finetuning.Enable = 'off';
            app.Label_iii2_finetuning.Text = 'Fine-tuning with GPU # ';
            app.EditField_iii2_GPU = uieditfield(app.Panel_iii2, 'text');
            app.EditField_iii2_GPU.ValueChangedFcn = createCallbackFcn(app, @EditField_iii2_GPUValueChanged, true);
            app.EditField_iii2_GPU.Enable = 'off';
            app.Lamp_iii2_finetuning = uilamp(app.Panel_iii2);
            app.Lamp_iii2_finetuning.Visible = 'off';
            app.Label_iii2_Model = uilabel(app.Panel_iii2);
            app.Label_iii2_Model.Enable = 'off';
            app.Label_iii2_Model.Text = 'Model: ';
            app.Button_iii2_loadmodel = uibutton(app.Panel_iii2, 'push');
            app.Button_iii2_loadmodel.ButtonPushedFcn = createCallbackFcn(app, @Button_iii2_loadmodelPushed, true);
            app.Button_iii2_loadmodel.Enable = 'off';
            app.Button_iii2_loadmodel.Text = 'load model files';
            app.Lamp_iii2_loadModel = uilamp(app.Panel_iii2);
            app.Lamp_iii2_loadModel.Visible = 'off';
            app.Button_iii2_reset = uibutton(app.Panel_iii2, 'push');
            app.Button_iii2_reset.ButtonPushedFcn = createCallbackFcn(app, @Button_iii2_resetPushed, true);
            app.Button_iii2_reset.Visible = 'off';
            app.Button_iii2_reset.Text = 'reset (iii-2)';
            app.EditFieldLabel_iii2_epoch = uilabel(app.Panel_iii2);
            app.EditFieldLabel_iii2_epoch.Enable = 'off';
            app.EditFieldLabel_iii2_epoch.Text = '-- Training Epoch';
            app.EditField_iii2_epoch = uieditfield(app.Panel_iii2, 'text');
            app.EditField_iii2_epoch.ValueChangedFcn = createCallbackFcn(app, @EditField_iii2_epochValueChanged, true);
            app.EditField_iii2_epoch.Enable = 'off';
            app.EditField_iii2_epoch.Tooltip = {''};
            app.Image_iii2_Info = uiimage(app.Panel_iii2);
            app.Image_iii2_Info.Visible = 'off';
            app.Image_iii2_Info.Tooltip = {'Uncheck this box to train your own segmentation model.'};
            app.Image_iii2_Info.ImageSource = fullfile(pathToMLAPP, 'UI', 'info.png');
            app.Panel_iv = uipanel(app.Panel_ImageProcessingPipeline);
            app.CheckBox_iv = uicheckbox(app.Panel_iv);
            app.CheckBox_iv.ValueChangedFcn = createCallbackFcn(app, @CheckBox_ivValueChanged, true);
            app.CheckBox_iv.Text = '';
            app.CheckBox_iv.Value = true;
            app.Label_iv = uilabel(app.Panel_iv);
            app.Label_iv.Text = '(iv) Train Registration Model';
            app.Lamp_iv = uilamp(app.Panel_iv);
            app.CheckBox_iv_prepro = uicheckbox(app.Panel_iv);
            app.CheckBox_iv_prepro.ValueChangedFcn = createCallbackFcn(app, @CheckBox_iv_preproValueChanged, true);
            app.CheckBox_iv_prepro.Text = '';
            app.CheckBox_iv_prepro.Value = true;
            app.Label_iv_prepro = uilabel(app.Panel_iv);
            app.Label_iv_prepro.Text = 'Preprocessing';
            app.Lamp_iv_prepro = uilamp(app.Panel_iv);
            app.EditField_iv_CroppedImageSizeX = uieditfield(app.Panel_iv, 'text');
            app.EditField_iv_CroppedImageSizeX.ValueChangedFcn = createCallbackFcn(app, @EditField_iv_CroppedImageSizeXValueChanged, true);
            app.EditField_iv_CroppedImageSizeY = uieditfield(app.Panel_iv, 'text');
            app.EditField_iv_CroppedImageSizeY.ValueChangedFcn = createCallbackFcn(app, @EditField_iv_CroppedImageSizeYValueChanged, true);
            app.EditField_iv_CroppedImageSizeZ = uieditfield(app.Panel_iv, 'text');
            app.EditField_iv_CroppedImageSizeZ.ValueChangedFcn = createCallbackFcn(app, @EditField_iv_CroppedImageSizeZValueChanged, true);
            app.CheckBox_iv_train = uicheckbox(app.Panel_iv);
            app.CheckBox_iv_train.ValueChangedFcn = createCallbackFcn(app, @CheckBox_iv_trainValueChanged, true);
            app.CheckBox_iv_train.Text = '';
            app.CheckBox_iv_train.Value = true;
            app.Label_iv_train = uilabel(app.Panel_iv);
            app.Label_iv_train.Text = 'Training with GPU # ';
            app.Lamp_iv_train = uilamp(app.Panel_iv);
            app.EditField_iv_GPU = uieditfield(app.Panel_iv, 'text');
            app.EditField_iv_GPU.ValueChangedFcn = createCallbackFcn(app, @EditField_iv_GPUValueChanged, true);
            app.EditField_iv_GPU.Value = '0';
            app.EditFieldLabel_iv_CroppedImageSize = uilabel(app.Panel_iv);
            app.EditFieldLabel_iv_CroppedImageSize.Text = '-- Cropped Image Size';
            app.Image_iv_Info_croppedImageSize = uiimage(app.Panel_iv);
            app.Image_iv_Info_croppedImageSize.Tooltip = {'The proposed CartiMorph framework involves downsampling and cropping standardized images to conform to the registration model, using cost-effective GPU devices. Our algorithm dynamically determines the downsampling factor to ensure that the cropped images cover all regions of interest (i.e., cartilages).  The cropped images serve as intermediate results, which will be restored to the original full resolution during the morphological quantification stage. The cropped image size should be divided by 16.'};
            app.Image_iv_Info_croppedImageSize.ImageSource = fullfile(pathToMLAPP, 'UI', 'info.png');
            app.Image_iv_Info = uiimage(app.Panel_iv);
            app.Image_iv_Info.Tooltip = {'Uncheck this box to deploy/fine-tune a pretrained registration model.'};
            app.Image_iv_Info.ImageSource = fullfile(pathToMLAPP, 'UI', 'info.png');
            app.EditFieldLabel_iv_epoch = uilabel(app.Panel_iv);
            app.EditFieldLabel_iv_epoch.Text = '-- Training Epoch';
            app.EditField_iv_epoch = uieditfield(app.Panel_iv, 'text');
            app.EditField_iv_epoch.ValueChangedFcn = createCallbackFcn(app, @EditField_iv_epochValueChanged, true);
            app.EditField_iv_epoch.Tooltip = {''};
            app.EditField_iv_epoch.Value = '500';
            app.ButtonGroup_networkWidth = uibuttongroup(app.Panel_iv);
            app.ButtonGroup_networkWidth.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_networkWidthSelectionChanged, true);
            app.Button_x1 = uitogglebutton(app.ButtonGroup_networkWidth);
            app.Button_x1.Text = 'x1';
            app.Button_x1.Value = true;
            app.Button_x2 = uitogglebutton(app.ButtonGroup_networkWidth);
            app.Button_x2.Text = 'x2';
            app.Button_x4 = uitogglebutton(app.ButtonGroup_networkWidth);
            app.Button_x4.Text = 'x4';
            app.Button_x3 = uitogglebutton(app.ButtonGroup_networkWidth);
            app.Button_x3.Text = 'x3';
            app.Button_x5 = uitogglebutton(app.ButtonGroup_networkWidth);
            app.Button_x5.Text = 'x5';
            app.Button_x6 = uitogglebutton(app.ButtonGroup_networkWidth);
            app.Button_x6.Text = 'x6';
            app.Button_x7 = uitogglebutton(app.ButtonGroup_networkWidth);
            app.Button_x7.Text = 'x7';
            app.Button_x8 = uitogglebutton(app.ButtonGroup_networkWidth);
            app.Button_x8.Text = 'x8';
            app.Button_x9 = uitogglebutton(app.ButtonGroup_networkWidth);
            app.Button_x9.Text = 'x9';
            app.Label_NetworkWidth = uilabel(app.Panel_iv);
            app.Label_NetworkWidth.Text = '-- Network Width';
            app.Image_iv_networkInfo = uiimage(app.Panel_iv);
            app.Image_iv_networkInfo.Tooltip = {'Network width controls the number of channels in a convolutional neural network (CNN). Increased network width may improve network performance. Note that larger model requires more GPU memory.'};
            app.Image_iv_networkInfo.ImageSource = fullfile(pathToMLAPP, 'UI', 'info.png');
            app.ButtonGroup_Loss_iv = uibuttongroup(app.Panel_iv);
            app.ButtonGroup_Loss_iv.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Loss_ivSelectionChanged, true);
            app.Button_MSE_iv = uitogglebutton(app.ButtonGroup_Loss_iv);
            app.Button_MSE_iv.Text = 'MSE';
            app.Button_LNCC_iv = uitogglebutton(app.ButtonGroup_Loss_iv);
            app.Button_LNCC_iv.Text = 'LNCC';
            app.Button_MSE_LNCC_iv = uitogglebutton(app.ButtonGroup_Loss_iv);
            app.Button_MSE_LNCC_iv.Text = 'MSE+LNCC';
            app.Button_MSE_LNCC_iv.Value = true;
            app.Label_LossFunction_iv = uilabel(app.Panel_iv);
            app.Label_LossFunction_iv.Text = '-- Loss';
            app.Panel_iv2 = uipanel(app.Panel_ImageProcessingPipeline);
            app.CheckBox_iv2 = uicheckbox(app.Panel_iv2);
            app.CheckBox_iv2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_iv2ValueChanged, true);
            app.CheckBox_iv2.Enable = 'off';
            app.CheckBox_iv2.Text = '';
            app.Label_iv2 = uilabel(app.Panel_iv2);
            app.Label_iv2.Enable = 'off';
            app.Label_iv2.Text = '(iv-2) Deploy/Fine-tune Registration Model';
            app.Lamp_iv2 = uilamp(app.Panel_iv2);
            app.Lamp_iv2.Visible = 'off';
            app.CheckBox_iv2_deploy = uicheckbox(app.Panel_iv2);
            app.CheckBox_iv2_deploy.ValueChangedFcn = createCallbackFcn(app, @CheckBox_iv2_deployValueChanged, true);
            app.CheckBox_iv2_deploy.Enable = 'off';
            app.CheckBox_iv2_deploy.Text = '';
            app.Label_iv2_deploy = uilabel(app.Panel_iv2);
            app.Label_iv2_deploy.Enable = 'off';
            app.Label_iv2_deploy.Text = 'Deploy the model';
            app.Lamp_iv2_deploy = uilamp(app.Panel_iv2);
            app.Lamp_iv2_deploy.Visible = 'off';
            app.CheckBox_iv2_finetune = uicheckbox(app.Panel_iv2);
            app.CheckBox_iv2_finetune.ValueChangedFcn = createCallbackFcn(app, @CheckBox_iv2_finetuneValueChanged, true);
            app.CheckBox_iv2_finetune.Enable = 'off';
            app.CheckBox_iv2_finetune.Text = '';
            app.Label_iv2_finetune = uilabel(app.Panel_iv2);
            app.Label_iv2_finetune.Enable = 'off';
            app.Label_iv2_finetune.Text = 'Fine-tune the pre-trained model';
            app.Lamp_iv2_finetune = uilamp(app.Panel_iv2);
            app.Lamp_iv2_finetune.Visible = 'off';
            app.CheckBox_iv2_prepro = uicheckbox(app.Panel_iv2);
            app.CheckBox_iv2_prepro.ValueChangedFcn = createCallbackFcn(app, @CheckBox_iv2_preproValueChanged, true);
            app.CheckBox_iv2_prepro.Enable = 'off';
            app.CheckBox_iv2_prepro.Text = '';
            app.Label_iv2_prepro = uilabel(app.Panel_iv2);
            app.Label_iv2_prepro.Enable = 'off';
            app.Label_iv2_prepro.Text = 'Preprocessing';
            app.Lamp_iv2_prepro = uilamp(app.Panel_iv2);
            app.Lamp_iv2_prepro.Visible = 'off';
            app.EditFieldLabel_iv2_CroppedImageSize = uilabel(app.Panel_iv2);
            app.EditFieldLabel_iv2_CroppedImageSize.Enable = 'off';
            app.EditFieldLabel_iv2_CroppedImageSize.Text = '-- Cropped Image Size';
            app.EditField_iv2_CroppedImageSizeX = uieditfield(app.Panel_iv2, 'text');
            app.EditField_iv2_CroppedImageSizeX.ValueChangedFcn = createCallbackFcn(app, @EditField_iv2_CroppedImageSizeXValueChanged, true);
            app.EditField_iv2_CroppedImageSizeX.Enable = 'off';
            app.EditField_iv2_CroppedImageSizeY = uieditfield(app.Panel_iv2, 'text');
            app.EditField_iv2_CroppedImageSizeY.ValueChangedFcn = createCallbackFcn(app, @EditField_iv2_CroppedImageSizeYValueChanged, true);
            app.EditField_iv2_CroppedImageSizeY.Enable = 'off';
            app.EditField_iv2_CroppedImageSizeZ = uieditfield(app.Panel_iv2, 'text');
            app.EditField_iv2_CroppedImageSizeZ.ValueChangedFcn = createCallbackFcn(app, @EditField_iv2_CroppedImageSizeZValueChanged, true);
            app.EditField_iv2_CroppedImageSizeZ.Enable = 'off';
            app.CheckBox_iv2_finetuning = uicheckbox(app.Panel_iv2);
            app.CheckBox_iv2_finetuning.ValueChangedFcn = createCallbackFcn(app, @CheckBox_iv2_finetuningValueChanged, true);
            app.CheckBox_iv2_finetuning.Enable = 'off';
            app.CheckBox_iv2_finetuning.Text = '';
            app.Label_iv2_finetuning = uilabel(app.Panel_iv2);
            app.Label_iv2_finetuning.Enable = 'off';
            app.Label_iv2_finetuning.Text = 'Fine-tuning with GPU # ';
            app.EditField_iv2_GPU = uieditfield(app.Panel_iv2, 'text');
            app.EditField_iv2_GPU.ValueChangedFcn = createCallbackFcn(app, @EditField_iv2_GPUValueChanged, true);
            app.EditField_iv2_GPU.Enable = 'off';
            app.Lamp_iv2_finetuning = uilamp(app.Panel_iv2);
            app.Lamp_iv2_finetuning.Visible = 'off';
            app.EditFieldLabel_iv2_epoch = uilabel(app.Panel_iv2);
            app.EditFieldLabel_iv2_epoch.Enable = 'off';
            app.EditFieldLabel_iv2_epoch.Text = '-- Training Epoch';
            app.EditField_iv2_epoch = uieditfield(app.Panel_iv2, 'text');
            app.EditField_iv2_epoch.ValueChangedFcn = createCallbackFcn(app, @EditField_iv2_epochValueChanged, true);
            app.EditField_iv2_epoch.Enable = 'off';
            app.Label_iv2_Model = uilabel(app.Panel_iv2);
            app.Label_iv2_Model.Enable = 'off';
            app.Label_iv2_Model.Text = 'Model: ';
            app.Button_iv2_loadmodel = uibutton(app.Panel_iv2, 'push');
            app.Button_iv2_loadmodel.ButtonPushedFcn = createCallbackFcn(app, @Button_iv2_loadmodelPushed, true);
            app.Button_iv2_loadmodel.Enable = 'off';
            app.Button_iv2_loadmodel.Text = 'load model files';
            app.Lamp_iv2_loadModel = uilamp(app.Panel_iv2);
            app.Lamp_iv2_loadModel.Visible = 'off';
            app.Button_iv2_reset = uibutton(app.Panel_iv2, 'push');
            app.Button_iv2_reset.ButtonPushedFcn = createCallbackFcn(app, @Button_iv2_resetPushed, true);
            app.Button_iv2_reset.Visible = 'off';
            app.Button_iv2_reset.Text = 'reset (iv-2)';
            app.Image_iv2_Info = uiimage(app.Panel_iv2);
            app.Image_iv2_Info.Visible = 'off';
            app.Image_iv2_Info.Tooltip = {'Uncheck this box to train your own registration model.'};
            app.Image_iv2_Info.ImageSource = fullfile(pathToMLAPP, 'UI', 'info.png');
            app.CheckBox_iv2_Freeze = uicheckbox(app.Panel_iv2);
            app.CheckBox_iv2_Freeze.ValueChangedFcn = createCallbackFcn(app, @CheckBox_iv2_FreezeValueChanged, true);
            app.CheckBox_iv2_Freeze.Enable = 'off';
            app.CheckBox_iv2_Freeze.Text = 'Freeze Template Image';
            app.ButtonGroup_Loss_iv2 = uibuttongroup(app.Panel_iv2);
            app.ButtonGroup_Loss_iv2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Loss_iv2SelectionChanged, true);
            app.Button_MSE_iv2 = uitogglebutton(app.ButtonGroup_Loss_iv2);
            app.Button_MSE_iv2.Enable = 'off';
            app.Button_MSE_iv2.Text = 'MSE';
            app.Button_LNCC_iv2 = uitogglebutton(app.ButtonGroup_Loss_iv2);
            app.Button_LNCC_iv2.Enable = 'off';
            app.Button_LNCC_iv2.Text = 'LNCC';
            app.Button_MSE_LNCC_iv2 = uitogglebutton(app.ButtonGroup_Loss_iv2);
            app.Button_MSE_LNCC_iv2.Enable = 'off';
            app.Button_MSE_LNCC_iv2.Text = 'MSE+LNCC';
            app.Button_MSE_LNCC_iv2.Value = true;
            app.Label_LossFunction_iv2 = uilabel(app.Panel_iv2);
            app.Label_LossFunction_iv2.Enable = 'off';
            app.Label_LossFunction_iv2.Text = '-- Loss';
            app.Panel_v = uipanel(app.Panel_ImageProcessingPipeline);
            app.Image_v_Info = uiimage(app.Panel_v);
            app.Image_v_Info.Tooltip = {'If this is field is empty, then we use CPU for model inference, which is not recommended.'};
            app.Image_v_Info.ImageSource = fullfile(pathToMLAPP, 'UI', 'info.png');
            app.CheckBox_v = uicheckbox(app.Panel_v);
            app.CheckBox_v.ValueChangedFcn = createCallbackFcn(app, @CheckBox_vValueChanged, true);
            app.CheckBox_v.Text = '';
            app.CheckBox_v.Value = true;
            app.Label_v = uilabel(app.Panel_v);
            app.Label_v.Text = '(v) Segmentation Model Inference';
            app.EditFieldLabel_v_GPU = uilabel(app.Panel_v);
            app.EditFieldLabel_v_GPU.Text = ' GPU ';
            app.EditField_v_GPU = uieditfield(app.Panel_v, 'text');
            app.EditField_v_GPU.ValueChangedFcn = createCallbackFcn(app, @EditField_v_GPUValueChanged, true);
            app.EditField_v_GPU.Value = '0';
            app.Lamp_v = uilamp(app.Panel_v);
            app.Panel_vi = uipanel(app.Panel_ImageProcessingPipeline);
            app.Label_vi = uilabel(app.Panel_vi);
            app.Label_vi.Text = '(vi) Registration Model Inference  ';
            app.Image_vi_Info = uiimage(app.Panel_vi);
            app.Image_vi_Info.Tooltip = {'If this is field is empty, then we use CPU for model inference, which is not recommended.'};
            app.Image_vi_Info.ImageSource = fullfile(pathToMLAPP, 'UI', 'info.png');
            app.CheckBox_vi = uicheckbox(app.Panel_vi);
            app.CheckBox_vi.ValueChangedFcn = createCallbackFcn(app, @CheckBox_viValueChanged, true);
            app.CheckBox_vi.Text = '';
            app.CheckBox_vi.Value = true;
            app.EditFieldLabel_vi_GPU = uilabel(app.Panel_vi);
            app.EditFieldLabel_vi_GPU.Text = ' GPU ';
            app.EditField_vi_GPU = uieditfield(app.Panel_vi, 'text');
            app.EditField_vi_GPU.ValueChangedFcn = createCallbackFcn(app, @EditField_vi_GPUValueChanged, true);
            app.EditField_vi_GPU.Value = '0';
            app.Lamp_vi = uilamp(app.Panel_vi);
            app.EditFieldLabel_vi_CroppedImageSize = uilabel(app.Panel_vi);
            app.EditFieldLabel_vi_CroppedImageSize.Text = '-- Cropped Image Size';
            app.EditField_vi_CroppedImageSizeX = uieditfield(app.Panel_vi, 'text');
            app.EditField_vi_CroppedImageSizeX.Enable = 'off';
            app.EditField_vi_CroppedImageSizeY = uieditfield(app.Panel_vi, 'text');
            app.EditField_vi_CroppedImageSizeY.Enable = 'off';
            app.EditField_vi_CroppedImageSizeZ = uieditfield(app.Panel_vi, 'text');
            app.EditField_vi_CroppedImageSizeZ.Enable = 'off';
            app.Panel_vii = uipanel(app.Panel_ImageProcessingPipeline);
            app.CheckBox_vii = uicheckbox(app.Panel_vii);
            app.CheckBox_vii.ValueChangedFcn = createCallbackFcn(app, @CheckBox_viiValueChanged, true);
            app.CheckBox_vii.Text = '';
            app.CheckBox_vii.Value = true;
            app.Label_vii = uilabel(app.Panel_vii);
            app.Label_vii.Text = '(vii) Morphological Quantification ';
            app.Lamp_vii = uilamp(app.Panel_vii);
            app.CheckBox_vii_CTM = uicheckbox(app.Panel_vii);
            app.CheckBox_vii_CTM.ValueChangedFcn = createCallbackFcn(app, @CheckBox_vii_CTMValueChanged, true);
            app.CheckBox_vii_CTM.Text = '';
            app.CheckBox_vii_CTM.Value = true;
            app.Label_vii_CTM = uilabel(app.Panel_vii);
            app.Label_vii_CTM.Text = 'cartilage thickness mapping';
            app.Lamp_vii_CTM = uilamp(app.Panel_vii);
            app.CheckBox_vii_parcellation = uicheckbox(app.Panel_vii);
            app.CheckBox_vii_parcellation.ValueChangedFcn = createCallbackFcn(app, @CheckBox_vii_parcellationValueChanged, true);
            app.CheckBox_vii_parcellation.Text = '';
            app.CheckBox_vii_parcellation.Value = true;
            app.Label_vii_parcellation = uilabel(app.Panel_vii);
            app.Label_vii_parcellation.Text = 'cartilage parcellation';
            app.Lamp_vii_parcellation = uilamp(app.Panel_vii);
            app.CheckBox_vii_regionalAnalysis = uicheckbox(app.Panel_vii);
            app.CheckBox_vii_regionalAnalysis.ValueChangedFcn = createCallbackFcn(app, @CheckBox_vii_regionalAnalysisValueChanged, true);
            app.CheckBox_vii_regionalAnalysis.Text = '';
            app.CheckBox_vii_regionalAnalysis.Value = true;
            app.Label_vii_regionalAnalysis = uilabel(app.Panel_vii);
            app.Label_vii_regionalAnalysis.Text = 'regional analysis';
            app.Lamp_vii_regionalAnalysis = uilamp(app.Panel_vii);
            app.Label_vii_progress = uilabel(app.Panel_vii);
            app.Label_vii_progress.Visible = 'off';
            app.Label_vii_progress.Text = 'progress (%)';
            app.Gauge_vii = uigauge(app.Panel_vii, 'circular');
            app.Gauge_vii.Visible = 'off';
            app.Gauge_vii.MinorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.Panel_Note = uipanel(app.Panel_ImageProcessingPipeline);
            app.PipelineLabel = uilabel(app.Panel_Note);
            app.PipelineLabel.Text = 'Pipeline:';
            app.checkatleastonceforeachmachineLabel = uilabel(app.Panel_Note);
            app.checkatleastonceforeachmachineLabel.Text = '@ check at least once for each machine';
            app.checkatleastonceforeachprojectLabel = uilabel(app.Panel_Note);
            app.checkatleastonceforeachprojectLabel.Text = '*   check at least once for each project';
            app.ImageorientationLabel = uilabel(app.Panel_Note);
            app.ImageorientationLabel.Text = 'Image orientation:';
            app.LleftLabel = uilabel(app.Panel_Note);
            app.LleftLabel.Text = 'L: left';
            app.RrightLabel = uilabel(app.Panel_Note);
            app.RrightLabel.Text = 'R: right';
            app.PposteriorLabel = uilabel(app.Panel_Note);
            app.PposteriorLabel.Text = 'P: posterior';
            app.AanteriorLabel = uilabel(app.Panel_Note);
            app.AanteriorLabel.Text = 'A: anterior';
            app.IinferiorLabel = uilabel(app.Panel_Note);
            app.IinferiorLabel.Text = 'I: inferior';
            app.SsuperiorLabel = uilabel(app.Panel_Note);
            app.SsuperiorLabel.Text = 'S: superior';
            app.setdifferentIDstotrainmodelsinparallelLabel = uilabel(app.Panel_Note);
            app.setdifferentIDstotrainmodelsinparallelLabel.Text = '#   set different IDs to train models in parallel';
            app.Panel_ImageDataInformation = uipanel(app.Panel_CMT);
            app.Panel_ImageDataInformation.Title = 'III. Raw Image Information';
            app.Panel_RawImageFolders = uipanel(app.Panel_ImageDataInformation);
            app.Panel_RawImageFolders.Tooltip = {''};
            app.Panel_RawImageFolders.Title = 'Raw Image Folders';
            app.EditFieldLabel_imgTr = uilabel(app.Panel_RawImageFolders);
            app.EditFieldLabel_imgTr.Text = 'Training Images';
            app.EditField_imgTr = uieditfield(app.Panel_RawImageFolders, 'text');
            app.EditField_imgTr.ValueChangedFcn = createCallbackFcn(app, @EditField_imgTrValueChanged, true);
            app.Button_selectPath_imgTr = uibutton(app.Panel_RawImageFolders, 'push');
            app.Button_selectPath_imgTr.ButtonPushedFcn = createCallbackFcn(app, @Button_selectPath_imgTrPushed, true);
            app.Button_selectPath_imgTr.Text = 'select folder';
            app.EditField_imgTs = uieditfield(app.Panel_RawImageFolders, 'text');
            app.EditField_imgTs.ValueChangedFcn = createCallbackFcn(app, @EditField_imgTsValueChanged, true);
            app.Button_selectPath_imgTs = uibutton(app.Panel_RawImageFolders, 'push');
            app.Button_selectPath_imgTs.ButtonPushedFcn = createCallbackFcn(app, @Button_selectPath_imgTsPushed, true);
            app.Button_selectPath_imgTs.Text = 'select folder';
            app.EditField_labelTr = uieditfield(app.Panel_RawImageFolders, 'text');
            app.EditField_labelTr.ValueChangedFcn = createCallbackFcn(app, @EditField_labelTrValueChanged, true);
            app.Button_selectPath_labelTr = uibutton(app.Panel_RawImageFolders, 'push');
            app.Button_selectPath_labelTr.ButtonPushedFcn = createCallbackFcn(app, @Button_selectPath_labelTrPushed, true);
            app.Button_selectPath_labelTr.Text = 'select folder';
            app.EditField_labelTs = uieditfield(app.Panel_RawImageFolders, 'text');
            app.EditField_labelTs.ValueChangedFcn = createCallbackFcn(app, @EditField_labelTsValueChanged, true);
            app.Button_selectPath_labelTs = uibutton(app.Panel_RawImageFolders, 'push');
            app.Button_selectPath_labelTs.ButtonPushedFcn = createCallbackFcn(app, @Button_selectPath_labelTsPushed, true);
            app.Button_selectPath_labelTs.Text = 'select folder';
            app.EditField_imgInference = uieditfield(app.Panel_RawImageFolders, 'text');
            app.EditField_imgInference.ValueChangedFcn = createCallbackFcn(app, @EditField_imgInferenceValueChanged, true);
            app.EditFieldLabel_imgTs = uilabel(app.Panel_RawImageFolders);
            app.EditFieldLabel_imgTs.Text = 'Testing Images';
            app.EditFieldLabel_labelTr = uilabel(app.Panel_RawImageFolders);
            app.EditFieldLabel_labelTr.Text = 'Training Labels';
            app.EditFieldLabel_labelTs = uilabel(app.Panel_RawImageFolders);
            app.EditFieldLabel_labelTs.Text = 'Testing Labels';
            app.EditFieldLabel_imgInference = uilabel(app.Panel_RawImageFolders);
            app.EditFieldLabel_imgInference.Text = 'Inference Images';
            app.Button_selectPath_imgInference = uibutton(app.Panel_RawImageFolders, 'push');
            app.Button_selectPath_imgInference.ButtonPushedFcn = createCallbackFcn(app, @Button_selectPath_imgInferencePushed, true);
            app.Button_selectPath_imgInference.Text = 'select folder';
            app.Panel_SegmentationLabels = uipanel(app.Panel_ImageDataInformation);
            app.Panel_SegmentationLabels.Title = 'Segmentation Labels';
            app.Name = uilabel(app.Panel_SegmentationLabels);
            app.Name.Text = 'Name';
            app.Label = uilabel(app.Panel_SegmentationLabels);
            app.Name2 = uilabel(app.Panel_SegmentationLabels);
            app.Name2.Text = 'Name';
            app.Label2 = uilabel(app.Panel_SegmentationLabels);
            app.EditFieldLabel_Femur = uilabel(app.Panel_SegmentationLabels);
            app.EditFieldLabel_Femur.Text = 'Femur';
            app.EditField_Femur = uieditfield(app.Panel_SegmentationLabels, 'text');
            app.EditField_Femur.ValueChangedFcn = createCallbackFcn(app, @EditField_FemurValueChanged, true);
            app.EditField_FemoralCartilage = uieditfield(app.Panel_SegmentationLabels, 'text');
            app.EditField_FemoralCartilage.ValueChangedFcn = createCallbackFcn(app, @EditField_FemoralCartilageValueChanged, true);
            app.EditField_Tibia = uieditfield(app.Panel_SegmentationLabels, 'text');
            app.EditField_Tibia.ValueChangedFcn = createCallbackFcn(app, @EditField_TibiaValueChanged, true);
            app.EditFieldLabel_TibialCartilagemedial = uilabel(app.Panel_SegmentationLabels);
            app.EditFieldLabel_TibialCartilagemedial.Text = 'Tibial Cartilage (medial)';
            app.EditField_TibialCartilagemedial = uieditfield(app.Panel_SegmentationLabels, 'text');
            app.EditField_TibialCartilagemedial.ValueChangedFcn = createCallbackFcn(app, @EditField_TibialCartilagemedialValueChanged, true);
            app.EditFieldLabel_FemoralCartilage = uilabel(app.Panel_SegmentationLabels);
            app.EditFieldLabel_FemoralCartilage.Text = 'Femoral Cartilage';
            app.EditFieldLabel_Tibia = uilabel(app.Panel_SegmentationLabels);
            app.EditFieldLabel_Tibia.Text = 'Tibia';
            app.EditFieldLabel_TibialCartilagelateral = uilabel(app.Panel_SegmentationLabels);
            app.EditFieldLabel_TibialCartilagelateral.Text = 'Tibial Cartilage (lateral)';
            app.EditFieldLabel_background = uilabel(app.Panel_SegmentationLabels);
            app.EditFieldLabel_background.Text = 'Background';
            app.EditField_TibialCartilagelateral = uieditfield(app.Panel_SegmentationLabels, 'text');
            app.EditField_TibialCartilagelateral.ValueChangedFcn = createCallbackFcn(app, @EditField_TibialCartilagelateralValueChanged, true);
            app.EditField_background = uieditfield(app.Panel_SegmentationLabels, 'text');
            app.EditField_background.ValueChangedFcn = createCallbackFcn(app, @EditField_backgroundValueChanged, true);
            app.Panel_KneeSideInformation = uipanel(app.Panel_ImageDataInformation);
            app.Panel_KneeSideInformation.Title = 'Knee Side Information';
            app.EditFieldLabel_csvPath = uilabel(app.Panel_KneeSideInformation);
            app.EditFieldLabel_csvPath.Text = 'File Path';
            app.EditField_csvPath = uieditfield(app.Panel_KneeSideInformation, 'text');
            app.EditField_csvPath.ValueChangedFcn = createCallbackFcn(app, @EditField_csvPathValueChanged, true);
            app.Button_loadCSV = uibutton(app.Panel_KneeSideInformation, 'push');
            app.Button_loadCSV.ButtonPushedFcn = createCallbackFcn(app, @Button_loadCSVPushed, true);
            app.Button_loadCSV.Text = 'load .csv';
            app.AboutButton = uibutton(app.Panel_CMT, 'state');
            app.AboutButton.ValueChangedFcn = createCallbackFcn(app, @AboutButtonValueChanged, true);
            app.AboutButton.Text = 'About';
            app.TipsButton = uibutton(app.Panel_CMT, 'state');
            app.TipsButton.ValueChangedFcn = createCallbackFcn(app, @TipsButtonValueChanged, true);
            app.TipsButton.Text = 'Tips';
            app.CMT_version = uilabel(app.Panel_CMT);
            app.CMT_version.Text = {'Version: 1.0.5'; 'License: CC-BY-NC 4.0'};
            % Show the figure after all components are created
            app.CartiMorphToolboxUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CartiMorphToolbox

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CartiMorphToolboxUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CartiMorphToolboxUIFigure)
        end
    end
end