classdef CartiMorphViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CartiMorphViewerUIFigure     matlab.ui.Figure
        CMV_version                  matlab.ui.control.Label
        Panel_MRImageSegmentation    matlab.ui.container.Panel
        Label_slice_coronal          matlab.ui.control.Label
        Label_slice_sagittal         matlab.ui.control.Label
        Label_slice_axial            matlab.ui.control.Label
        Slider_coronal               matlab.ui.control.Slider
        Panel_MRImg_coronal          matlab.ui.container.Panel
        Label_corView_R              matlab.ui.control.Label
        Label_corView_L              matlab.ui.control.Label
        Label_corView_I              matlab.ui.control.Label
        Label_corView_S              matlab.ui.control.Label
        UIAxes_MRImg_coronal_mask    matlab.ui.control.UIAxes
        UIAxes_MRImg_coronal         matlab.ui.control.UIAxes
        Slider_sagittal              matlab.ui.control.Slider
        Panel_MRImg_sagittal         matlab.ui.container.Panel
        Label_sagView_I              matlab.ui.control.Label
        Label_sagView_S              matlab.ui.control.Label
        Label_sagView_P              matlab.ui.control.Label
        Label_sagView_A              matlab.ui.control.Label
        UIAxes_MRImg_sagittal_mask   matlab.ui.control.UIAxes
        UIAxes_MRImg_sagittal        matlab.ui.control.UIAxes
        Slider_axial                 matlab.ui.control.Slider
        Panel_MRImg_axial            matlab.ui.container.Panel
        Label_axiView_R              matlab.ui.control.Label
        Label_axiView_L              matlab.ui.control.Label
        Label_axiView_P              matlab.ui.control.Label
        Label_axiView_A              matlab.ui.control.Label
        UIAxes_MRImg_axial_mask      matlab.ui.control.UIAxes
        UIAxes_MRImg_axial           matlab.ui.control.UIAxes
        Panel_MRImg_3DView           matlab.ui.container.Panel
        UIAxes_MRImg_3DView          matlab.ui.control.UIAxes
        Panel_FCL                    matlab.ui.container.Panel
        Panel_FCL_FC                 matlab.ui.container.Panel
        UIAxes_FCL_FC                matlab.ui.control.UIAxes
        Panel_FCL_TC                 matlab.ui.container.Panel
        UIAxes_FCL_TC                matlab.ui.control.UIAxes
        Panel_FCL_FCorTC             matlab.ui.container.Panel
        UIAxes_FCL_FCorTC            matlab.ui.control.UIAxes
        Panel_Buttons                matlab.ui.container.Panel
        Button_save                  matlab.ui.control.Button
        Button_update                matlab.ui.control.Button
        AboutButton                  matlab.ui.control.StateButton
        Panel_i                      matlab.ui.container.Panel
        caseNameMask                 matlab.ui.control.Label
        Button_hideCase              matlab.ui.control.StateButton
        EditField_kneeSide           matlab.ui.control.EditField
        kneesideofthiscaseLabel      matlab.ui.control.Label
        Button_hidePath              matlab.ui.control.StateButton
        DropDown_case                matlab.ui.control.DropDown
        CaseDropDownLabel            matlab.ui.control.Label
        EditField_path               matlab.ui.control.EditField
        EditFieldLabel_pathCMT       matlab.ui.control.Label
        Button_selectPath            matlab.ui.control.Button
        Panel_ii                     matlab.ui.container.Panel
        ButtonGroup_Image            matlab.ui.container.ButtonGroup
        EditField_opacity            matlab.ui.control.NumericEditField
        SegmentationOpacityEditFieldLabel  matlab.ui.control.Label
        Button_img                   matlab.ui.control.ToggleButton
        Button_img_subregion         matlab.ui.control.ToggleButton
        ButtonGroup_map              matlab.ui.container.ButtonGroup
        Button_all                   matlab.ui.control.ToggleButton
        Button_cartilage             matlab.ui.control.ToggleButton
        Button_bone                  matlab.ui.control.ToggleButton
        ButtonGroup_cartilage        matlab.ui.container.ButtonGroup
        Button_FTC                   matlab.ui.control.ToggleButton
        Button_TC                    matlab.ui.control.ToggleButton
        Button_FC                    matlab.ui.control.ToggleButton
        Panel_CartilageSurface       matlab.ui.container.Panel
        Panel_surface_FC             matlab.ui.container.Panel
        UIAxes_surface_FC            matlab.ui.control.UIAxes
        Panel_surface_TC             matlab.ui.container.Panel
        UIAxes_surface_TC            matlab.ui.control.UIAxes
        Panel_surface_FCorTC         matlab.ui.container.Panel
        UIAxes_surface_FCorTC        matlab.ui.control.UIAxes
        Panel_SurfaceNormal          matlab.ui.container.Panel
        Panel_surfaceNormal_FC       matlab.ui.container.Panel
        UIAxes_surfaceNormal_FC      matlab.ui.control.UIAxes
        Panel_surfaceNormal_TC       matlab.ui.container.Panel
        UIAxes_surfaceNormal_TC      matlab.ui.control.UIAxes
        Panel_surfaceNormal_FCorTC   matlab.ui.container.Panel
        UIAxes_surfaceNormal_FCorTC  matlab.ui.control.UIAxes
        Panel_CartilageSubregions    matlab.ui.container.Panel
        Panel_subregions_FC          matlab.ui.container.Panel
        UIAxes_subregions_FC         matlab.ui.control.UIAxes
        Panel_subregions_TC          matlab.ui.container.Panel
        UIAxes_subregions_TC         matlab.ui.control.UIAxes
        Panel_subregions_FCorTC      matlab.ui.container.Panel
        UIAxes_subregions_FCorTC     matlab.ui.control.UIAxes
        Panel_ThicknessMap           matlab.ui.container.Panel
        Panel_thicknessMap           matlab.ui.container.Panel
        UIAxes_thicknessMap          matlab.ui.control.UIAxes
        Panel_MorphQuant             matlab.ui.container.Panel
        UITable                      matlab.ui.control.Table
        Image_logo                   matlab.ui.control.Image
    end


    properties (Access = private)
        % app
        CMT % CartiMorph Toolbox (CMT), the main app

        % flag
        flag_fromCMT logical =false % called from CMT

        % folder
        dir_result char % CMT results folder

        % config
        config_caseList cell % list of cases
        config_caseName char % name of selected case
        config_kneeSide char % knee side
        config_mask_opacity double =0.8 % opacity of subregion masks

        % option
        opt_cartilage_FTC logical =true % show both FC and TC
        opt_cartilage_FC logical =false % show FC
        opt_cartilage_TC logical =false % show TC
        opt_map_bone_cartilage logical =true % show thickness map, bone, and cartilage
        opt_map_bone logical =false % show thickness map and bone
        opt_map_cartilage logical =false % show thickness map and cartilage
        opt_img_subregion logical =true % show image and cartilage subregion mask
        opt_img logical =false % show image only

        % status
        stat_sagSlice double =1 % the selected sagittal slice
        stat_axiSlice double =1 % the selected axial slice
        stat_corSlice double =1 % the selected coronal slice
        
        % apps
        aboutCMV % the matlab app showing CMV info
    end

    methods (Access = private)

        function CMV_migrateConfig(app)
            % CMT result folder
            app.dir_result = app.CMT.dir_result;
        end


        function CMV_updateCaseList(app)
            % get case list
            tmp = dir(fullfile(app.dir_result));
            folders = tmp([tmp.isdir]);
            folderNames = {folders.name}'; % cell
            idx_tbr = strcmp(folderNames, ".") | strcmp(folderNames, "..");
            caseList = folderNames(~idx_tbr); % cell
            % update dropdown list
            if ~isempty(caseList)
                % update case list
                app.config_caseList = caseList;
                % update the drop down list in CMV
                app.DropDown_case.Items = caseList;
                app.DropDown_case.Value = caseList{1};
                app.config_caseName = caseList{1};
                % update knee side
                CMV_updateKneeSide(app, app.config_caseName);
                % enable buttons
                CMV_enableButtons(app);
            else
                uialert(app.CartiMorphViewerUIFigure, "The result folder is empty.", "Empty Folder", "Icon","error" );
                % disable buttons
                CMV_disableButtons(app);
            end
        end


        function CMV_updateCartilageSurface(app)
            dir_fig = fullfile(app.dir_result, app.config_caseName, 'Surface_Figure');

            % clear figures
            cla(app.UIAxes_surface_FC);
            cla(app.UIAxes_surface_TC);
            cla(app.UIAxes_surface_FCorTC);
            legend(app.UIAxes_surface_FC, "off");
            legend(app.UIAxes_surface_TC, "off");
            legend(app.UIAxes_surface_FCorTC, "off");

            % hide figures
            app.UIAxes_surface_FC.Visible = "off";
            app.UIAxes_surface_TC.Visible = "off";
            app.UIAxes_surface_FCorTC.Visible = "off";
            app.Panel_surface_FC.Visible = "off";
            app.Panel_surface_TC.Visible = "off";
            app.Panel_surface_FCorTC.Visible = "off";

            % set path of figure files generated from CMT
            path_fig_FC = fullfile(dir_fig, strcat(app.config_caseName, "_sMesh_iCeC_FemoralCartilage.fig"));
            path_fig_mTC = fullfile(dir_fig, strcat(app.config_caseName, "_sMesh_iCeC_mTibialCartilage.fig"));
            path_fig_lTC = fullfile(dir_fig, strcat(app.config_caseName, "_sMesh_iCeC_lTibialCartilage.fig"));

            % modify figures
            % ------------------------------------------------
            % surfHandles_xxx = [surfHandle_1, surfHandle_2]
            %   - surfHandle_1: the interior surface
            %   - surfHandle_2: exterior surface or the FCL surface
            % ------------------------------------------------
            if app.opt_cartilage_FTC
                if isfile(path_fig_FC)
                    % load figure from files
                    openfig(path_fig_FC, 'invisible');
                    handle_FC = gca;
                    % plot FC
                    childHandles_FC = CMV_modifyCartilageSurface(app, handle_FC, "FC");
                    app.UIAxes_surface_FC.Visible = "on";
                    app.Panel_surface_FC.Visible = "on";
                    copyobj(get(handle_FC, 'children'), app.UIAxes_surface_FC);
                    childHandles4legend = childHandles_FC;
                    CMV_resetCartilageSurface(app, app.UIAxes_surface_FC, "FC", childHandles4legend);
                end
                if isfile(path_fig_mTC) && isfile(path_fig_lTC)
                    % load figure from files
                    openfig(path_fig_mTC, 'invisible');
                    handle_mTC = gca;
                    openfig(path_fig_lTC, 'invisible');
                    handle_lTC = gca;
                    % plot TC
                    childHandles_mTC = CMV_modifyCartilageSurface(app, handle_mTC, "mTC");
                    childHandles_lTC = CMV_modifyCartilageSurface(app, handle_lTC, "lTC");
                    app.UIAxes_surface_TC.Visible = "on";
                    app.Panel_surface_TC.Visible = "on";
                    copyobj(get(handle_mTC, 'children'), app.UIAxes_surface_TC);
                    copyobj(get(handle_lTC, 'children'), app.UIAxes_surface_TC);
                    childHandles4legend = [childHandles_mTC(1), childHandles_lTC(1), childHandles_mTC(2)];
                    CMV_resetCartilageSurface(app, app.UIAxes_surface_TC, "TC", childHandles4legend);
                end

            elseif app.opt_cartilage_FC
                if isfile(path_fig_FC)
                    % load figure from files
                    openfig(path_fig_FC, 'invisible');
                    handle_FC = gca;
                    % modify figures
                    childHandles_FC = CMV_modifyCartilageSurface(app, handle_FC, "FC");
                    % plot FC
                    app.UIAxes_surface_FCorTC.Visible = "on";
                    app.Panel_surface_FCorTC.Visible = "on";
                    copyobj(get(handle_FC, 'children'), app.UIAxes_surface_FCorTC);
                    childHandles4legend = childHandles_FC;
                    CMV_resetCartilageSurface(app, app.UIAxes_surface_FCorTC, "FC", childHandles4legend);
                end

            elseif app.opt_cartilage_TC
                if isfile(path_fig_mTC) && isfile(path_fig_lTC)
                    % load figure from files
                    openfig(path_fig_mTC, 'invisible');
                    handle_mTC = gca;
                    openfig(path_fig_lTC, 'invisible');
                    handle_lTC = gca;
                    % modify figures
                    childHandles_mTC = CMV_modifyCartilageSurface(app, handle_mTC, "mTC");
                    childHandles_lTC = CMV_modifyCartilageSurface(app, handle_lTC, "lTC");
                    % plot TC
                    app.UIAxes_surface_FCorTC.Visible = "on";
                    app.Panel_surface_FCorTC.Visible = "on";
                    copyobj(get(handle_mTC, 'children'), app.UIAxes_surface_FCorTC);
                    copyobj(get(handle_lTC, 'children'), app.UIAxes_surface_FCorTC);
                    childHandles4legend = [childHandles_mTC(1), childHandles_lTC(1), childHandles_mTC(2)];
                    CMV_resetCartilageSurface(app, app.UIAxes_surface_FCorTC, "TC", childHandles4legend);
                end
            end
        end


        function childHandles = CMV_modifyCartilageSurface(~, handle, type)
            % --------------------------------------------
            % [modify figures]
            % surface patches are determined by CMT function: CMT_wrapper_KneeCartilageThickness_wBone
            %   - patch 1 is either the exterior surface or the FCL surface
            %   - patch 2 is the interior surface
            % --------------------------------------------
            switch type
                case "FC"
                    % change the "FaceColor" of interior cartilage surface
                    handle.Children(2).FaceColor = "#FAD7A0";
                    % change the "FaceColor" and "FaceAlpha" of the FCL surface
                    handle.Children(1).FaceColor = "#1D71F2";

                case "mTC"
                    % change the "FaceColor" of interior cartilage surface
                    handle.Children(2).FaceColor = "#F6D3DE";
                    % change the "FaceColor" and "FaceAlpha" of the FCL surface
                    handle.Children(1).FaceColor = "#1D71F2";

                case "lTC"
                    % change the "FaceColor" of interior cartilage surface
                    handle.Children(2).FaceColor = "#B08FC7";
                    % change the "FaceColor" and "FaceAlpha" of the FCL surface
                    handle.Children(1).FaceColor = "#1D71F2";
            end

            % surface patch handles
            childHandles = [handle.Children(2), handle.Children(1)];

            % change EdgeAlpha
            handle.Children(1).EdgeAlpha = 0.2;
            handle.Children(2).EdgeAlpha = 0.2;
        end


        function CMV_resetCartilageSurface(~, subplot, type, childHandles)
            switch type
                case "FC"
                    axis(subplot, "equal");
                    axis(subplot, "off");
                    view(subplot, -145, 20);
                    title(subplot, "");
                    axis(subplot, "tight");
                    xticks(subplot, []);
                    yticks(subplot, []);
                    zticks(subplot, []);
                    xlabel(subplot, '');
                    ylabel(subplot, '');
                    zlabel(subplot, '');
                    legendLabels = {'FC (iC)', 'FC (eC)'};
                    legend(subplot, childHandles, legendLabels,...
                        'Location','best', 'Color','none', 'Box','off', 'FontName','TimesNewRome');

                case "TC"
                    axis(subplot, "equal");
                    axis(subplot, "off");
                    view(subplot, 0, 90);
                    title(subplot, "");
                    axis(subplot, "tight");
                    xticks(subplot, []);
                    yticks(subplot, []);
                    zticks(subplot, []);
                    xlabel(subplot, '');
                    ylabel(subplot, '');
                    zlabel(subplot, '');
                    legendLabels = {'mTC (iC)', 'lTC (iC)', 'TC (eC)'};
                    legend(subplot, childHandles, legendLabels,...
                        'Location','best', 'Color','none', 'Box','off', 'FontName','TimesNewRome');
            end
        end


        function CMV_updateFCL(app)
            dir_fig = fullfile(app.dir_result, app.config_caseName, 'Surface_Figure');

            % clear figures
            cla(app.UIAxes_FCL_FC);
            cla(app.UIAxes_FCL_TC);
            cla(app.UIAxes_FCL_FCorTC);
            legend(app.UIAxes_FCL_FC, "off");
            legend(app.UIAxes_FCL_TC, "off");
            legend(app.UIAxes_FCL_FCorTC, "off");

            % hide figures
            app.UIAxes_FCL_FC.Visible = "off";
            app.UIAxes_FCL_TC.Visible = "off";
            app.UIAxes_FCL_FCorTC.Visible = "off";
            app.Panel_FCL_FC.Visible = "off";
            app.Panel_FCL_TC.Visible = "off";
            app.Panel_FCL_FCorTC.Visible = "off";

            % set path of figure files generated from CMT
            path_fig_FC = fullfile(dir_fig, strcat(app.config_caseName, "_sMesh_iCFCL_FemoralCartilage.fig"));
            path_fig_mTC = fullfile(dir_fig, strcat(app.config_caseName, "_sMesh_iCFCL_mTibialCartilage.fig"));
            path_fig_lTC = fullfile(dir_fig, strcat(app.config_caseName, "_sMesh_iCFCL_lTibialCartilage.fig"));

            % modify figures
            % ------------------------------------------------
            % surfHandles_xxx = [surfHandle_1, surfHandle_2]
            %   - surfHandle_1: the interior surface
            %   - surfHandle_2: exterior surface or the FCL surface
            % ------------------------------------------------
            if app.opt_cartilage_FTC
                if isfile(path_fig_FC)
                    % load figure from files
                    openfig(path_fig_FC, 'invisible');
                    handle_FC = gca;
                    % plot FC
                    childHandles_FC = CMV_modifyFCL(app, handle_FC, "FC");
                    app.UIAxes_FCL_FC.Visible = "on";
                    app.Panel_FCL_FC.Visible = "on";
                    copyobj(get(handle_FC, 'children'), app.UIAxes_FCL_FC);
                    childHandles4legend = childHandles_FC;
                    CMV_resetFCL(app, app.UIAxes_FCL_FC, "FC", childHandles4legend);
                end
                if isfile(path_fig_mTC) && isfile(path_fig_lTC)
                    % load figure from files
                    openfig(path_fig_mTC, 'invisible');
                    handle_mTC = gca;
                    openfig(path_fig_lTC, 'invisible');
                    handle_lTC = gca;
                    % plot TC
                    childHandles_mTC = CMV_modifyFCL(app, handle_mTC, "mTC");
                    childHandles_lTC = CMV_modifyFCL(app, handle_lTC, "lTC");
                    app.UIAxes_FCL_TC.Visible = "on";
                    app.Panel_FCL_TC.Visible = "on";
                    copyobj(get(handle_mTC, 'children'), app.UIAxes_FCL_TC);
                    copyobj(get(handle_lTC, 'children'), app.UIAxes_FCL_TC);
                    childHandles4legend = [childHandles_mTC(1), childHandles_lTC(1), childHandles_mTC(2)];
                    CMV_resetFCL(app, app.UIAxes_FCL_TC, "TC", childHandles4legend);
                end

            elseif app.opt_cartilage_FC
                if isfile(path_fig_FC)
                    % load figure from files
                    openfig(path_fig_FC, 'invisible');
                    handle_FC = gca;
                    % modify figures
                    childHandles_FC = CMV_modifyFCL(app, handle_FC, "FC");
                    % plot FC
                    app.UIAxes_FCL_FCorTC.Visible = "on";
                    app.Panel_FCL_FCorTC.Visible = "on";
                    copyobj(get(handle_FC, 'children'), app.UIAxes_FCL_FCorTC);
                    childHandles4legend = childHandles_FC;
                    CMV_resetFCL(app, app.UIAxes_FCL_FCorTC, "FC", childHandles4legend);
                end

            elseif app.opt_cartilage_TC
                if isfile(path_fig_mTC) && isfile(path_fig_lTC)
                    % load figure from files
                    openfig(path_fig_mTC, 'invisible');
                    handle_mTC = gca;
                    openfig(path_fig_lTC, 'invisible');
                    handle_lTC = gca;
                    % modify figures
                    childHandles_mTC = CMV_modifyFCL(app, handle_mTC, "mTC");
                    childHandles_lTC = CMV_modifyFCL(app, handle_lTC, "lTC");
                    % plot TC
                    app.UIAxes_FCL_FCorTC.Visible = "on";
                    app.Panel_FCL_FCorTC.Visible = "on";
                    copyobj(get(handle_mTC, 'children'), app.UIAxes_FCL_FCorTC);
                    copyobj(get(handle_lTC, 'children'), app.UIAxes_FCL_FCorTC);
                    childHandles4legend = [childHandles_mTC(1), childHandles_lTC(1), childHandles_mTC(2)];
                    CMV_resetFCL(app, app.UIAxes_FCL_FCorTC, "TC", childHandles4legend);
                end
            end
        end


        function childHandles = CMV_modifyFCL(~, handle, type)
            % --------------------------------------------
            % [modify figures]
            % surface patches are determined by CMT function: CMT_wrapper_KneeCartilageThickness_wBone
            %   - patch 1 is either the exterior surface or the FCL surface
            %   - patch 2 is the interior surface
            % --------------------------------------------
            switch type
                case "FC"
                    % change the "FaceColor" of interior cartilage surface
                    handle.Children(2).FaceColor = "#FAD7A0";
                case "mTC"
                    % change the "FaceColor" of interior cartilage surface
                    handle.Children(2).FaceColor = "#F6D3DE";
                case "lTC"
                    % change the "FaceColor" of interior cartilage surface
                    handle.Children(2).FaceColor = "#B08FC7";
            end

            % surface patch handles
            childHandles = [handle.Children(2), handle.Children(1)];

            % change EdgeAlpha
            handle.Children(1).EdgeAlpha = 0.2;
            handle.Children(2).EdgeAlpha = 0.2;
        end


        function CMV_resetFCL(~, subplot, type, childHandles)
            switch type
                case "FC"
                    axis(subplot, "equal");
                    axis(subplot, "off");
                    view(subplot, -145, 20);
                    title(subplot, "");
                    axis(subplot, "tight");
                    xticks(subplot, []);
                    yticks(subplot, []);
                    zticks(subplot, []);
                    xlabel(subplot, '');
                    ylabel(subplot, '');
                    zlabel(subplot, '');
                    legendLabels = {'FC (iC)', 'FC (FCL)'};
                    legend(subplot, childHandles, legendLabels,...
                        'Location','best', 'Color','none', 'Box','off', 'FontName','TimesNewRome');

                case "TC"
                    axis(subplot, "equal");
                    axis(subplot, "off");
                    view(subplot, 0, 90);
                    title(subplot, "");
                    axis(subplot, "tight");
                    xticks(subplot, []);
                    yticks(subplot, []);
                    zticks(subplot, []);
                    xlabel(subplot, '');
                    ylabel(subplot, '');
                    zlabel(subplot, '');
                    legendLabels = {'mTC (iC)', 'lTC (iC)', 'TC (FCL)'};
                    legend(subplot, childHandles, legendLabels,...
                        'Location','best', 'Color','none', 'Box','off', 'FontName','TimesNewRome');
            end
        end


        function CMV_updateSurfaceNormal(app)
            dir_fig = fullfile(app.dir_result, app.config_caseName, 'SurfaceNormal_Figure');

            % clear figures
            cla(app.UIAxes_surfaceNormal_FC);
            cla(app.UIAxes_surfaceNormal_TC);
            cla(app.UIAxes_surfaceNormal_FCorTC);
            legend(app.UIAxes_surfaceNormal_FC, "off");
            legend(app.UIAxes_surfaceNormal_TC, "off");
            legend(app.UIAxes_surfaceNormal_FCorTC, "off");

            % hide figures
            app.UIAxes_surfaceNormal_FC.Visible = "off";
            app.UIAxes_surfaceNormal_TC.Visible = "off";
            app.UIAxes_surfaceNormal_FCorTC.Visible = "off";
            app.Panel_surfaceNormal_FC.Visible = "off";
            app.Panel_surfaceNormal_TC.Visible = "off";
            app.Panel_surfaceNormal_FCorTC.Visible = "off";

            % set path of figure files generated from CMT
            path_fig_FC = fullfile(dir_fig, strcat(app.config_caseName, "_SN_", "FemoralCartilage.fig"));
            path_fig_mTC = fullfile(dir_fig, strcat(app.config_caseName, "_SN_", "mTibialCartilage.fig"));
            path_fig_lTC = fullfile(dir_fig, strcat(app.config_caseName, "_SN_", "lTibialCartilage.fig"));

            if app.opt_cartilage_FTC
                if isfile(path_fig_FC)
                    % load figure from files
                    openfig(path_fig_FC, 'invisible');
                    handle_FC = gca;
                    % modify figures
                    childHandles_FC = CMV_modifySurfaceNormal(app, handle_FC);
                    % plot FC
                    app.UIAxes_surfaceNormal_FC.Visible = "on";
                    app.Panel_surfaceNormal_FC.Visible = "on";
                    copyobj(get(handle_FC, 'children'), app.UIAxes_surfaceNormal_FC);
                    childHandles4legend = [childHandles_FC(1), childHandles_FC(2), childHandles_FC(3)];
                    CMV_resetSurfaceNormal(app, app.UIAxes_surfaceNormal_FC, "FC", childHandles4legend);
                end
                if isfile(path_fig_mTC) && isfile(path_fig_lTC)
                    % load figure from files
                    openfig(path_fig_mTC, 'invisible');
                    handle_mTC = gca;
                    openfig(path_fig_lTC, 'invisible');
                    handle_lTC = gca;
                    % modify figures
                    childHandles_mTC = CMV_modifySurfaceNormal(app, handle_mTC);
                    CMV_modifySurfaceNormal(app, handle_lTC);
                    % plot TC
                    app.UIAxes_surfaceNormal_TC.Visible = "on";
                    app.Panel_surfaceNormal_TC.Visible = "on";
                    copyobj(get(handle_mTC, 'children'), app.UIAxes_surfaceNormal_TC);
                    copyobj(get(handle_lTC, 'children'), app.UIAxes_surfaceNormal_TC);
                    childHandles4legend = [childHandles_mTC(1), childHandles_mTC(2), childHandles_mTC(3)];
                    CMV_resetSurfaceNormal(app, app.UIAxes_surfaceNormal_TC, "TC", childHandles4legend);
                end

            elseif app.opt_cartilage_FC
                if isfile(path_fig_FC)
                    % load figure from files
                    openfig(path_fig_FC, 'invisible');
                    handle_FC = gca;
                    % modify figures
                    childHandles_FC = CMV_modifySurfaceNormal(app, handle_FC);
                    % plot FC
                    app.UIAxes_surfaceNormal_FCorTC.Visible = "on";
                    app.Panel_surfaceNormal_FCorTC.Visible = "on";
                    copyobj(get(handle_FC, 'children'), app.UIAxes_surfaceNormal_FCorTC);
                    childHandles4legend = [childHandles_FC(1), childHandles_FC(2), childHandles_FC(3)];
                    CMV_resetSurfaceNormal(app, app.UIAxes_surfaceNormal_FCorTC, "FC", childHandles4legend);
                end

            elseif app.opt_cartilage_TC
                if isfile(path_fig_mTC) && isfile(path_fig_lTC)
                    % load figure from files
                    openfig(path_fig_mTC, 'invisible');
                    handle_mTC = gca;
                    openfig(path_fig_lTC, 'invisible');
                    handle_lTC = gca;
                    % modify figures
                    childHandles_mTC = CMV_modifySurfaceNormal(app, handle_mTC);
                    CMV_modifySurfaceNormal(app, handle_lTC);
                    % plot TC
                    app.UIAxes_surfaceNormal_FCorTC.Visible = "on";
                    app.Panel_surfaceNormal_FCorTC.Visible = "on";
                    copyobj(get(handle_mTC, 'children'), app.UIAxes_surfaceNormal_FCorTC);
                    copyobj(get(handle_lTC, 'children'), app.UIAxes_surfaceNormal_FCorTC);
                    childHandles4legend = [childHandles_mTC(1), childHandles_mTC(2), childHandles_mTC(3)];
                    CMV_resetSurfaceNormal(app, app.UIAxes_surfaceNormal_FCorTC, "TC", childHandles4legend);
                end
            end
        end


        function childHandles = CMV_modifySurfaceNormal(~, handle)
            % --------------------------------------------
            % [modify figures]
            % figure components are determined by CMT function: wrapper_thickness_SN
            %   - handle.Children(1): surface normal
            %   - handle.Children(2): exterior surface
            %   - handle.Children(3): interior surface
            % --------------------------------------------

            % surface normal
            handle.Children(1).Color = "#68C74F";

            % exterior surface
            handle.Children(2).FaceColor = "#1D71F2";
            handle.Children(2).FaceAlpha = 0.2;
            handle.Children(2).EdgeAlpha = 0;

            % interior surface
            handle.Children(3).FaceColor = "#E03A3E";
            handle.Children(3).EdgeAlpha = 0.2;
            handle.Children(3).FaceAlpha = 1;

            % figure handles
            childHandles = [handle.Children(3), handle.Children(2), handle.Children(1)];
        end


        function CMV_resetSurfaceNormal(app, subplot, type, childHandles)
            switch type
                case "FC"
                    axis(subplot, "equal");
                    axis(subplot, "off");
                    view(subplot, -145, 20);
                    title(subplot, "");
                    axis(subplot, "tight");
                    xticks(subplot, []);
                    yticks(subplot, []);
                    zticks(subplot, []);
                    xlabel(subplot, '');
                    ylabel(subplot, '');
                    zlabel(subplot, '');
                    if app.opt_cartilage_FC
                        legendLabels = {'iC', 'eC', 'Surface Normal'};
                        legend(subplot, childHandles, legendLabels,...
                            'Location','best', 'Color','none', 'Box','off', 'FontName','TimesNewRome');
                    end
                case "TC"
                    axis(subplot, "equal");
                    axis(subplot, "off");
                    view(subplot, -145, 20);
                    title(subplot, "");
                    axis(subplot, "tight");
                    xticks(subplot, []);
                    yticks(subplot, []);
                    zticks(subplot, []);
                    xlabel(subplot, '');
                    ylabel(subplot, '');
                    zlabel(subplot, '');
                    legendLabels = {'iC', 'eC', 'Surface Normal'};
                    legend(subplot, childHandles, legendLabels,...
                        'Location','best', 'Color','none', 'Box','off', 'FontName','TimesNewRome');
            end
        end


        function CMV_updateCartilageSubregions(app)
            % clear figures
            cla(app.UIAxes_subregions_FC);
            cla(app.UIAxes_subregions_TC);
            cla(app.UIAxes_subregions_FCorTC);
            legend(app.UIAxes_subregions_FC, "off");
            legend(app.UIAxes_subregions_TC, "off");
            legend(app.UIAxes_subregions_FCorTC, "off");

            % hide figures
            app.UIAxes_subregions_FC.Visible = "off";
            app.UIAxes_subregions_TC.Visible = "off";
            app.UIAxes_subregions_FCorTC.Visible = "off";
            app.Panel_subregions_FC.Visible = "off";
            app.Panel_subregions_TC.Visible = "off";
            app.Panel_subregions_FCorTC.Visible = "off";

            % load figure generated from CMT
            dir_fig = fullfile(app.dir_result, app.config_caseName, 'CartilageSubregion_Figure');
            path_fig_FTC = fullfile(dir_fig, strcat(app.config_caseName, "_mesh_subreg_iC_FTC.fig"));
            openfig(path_fig_FTC, 'invisible');
            handle_FTC = gca;

            % load empty surface index
            path_emptySurfaceIdx = fullfile(dir_fig, strcat(app.config_caseName, '_emptySurfaceIdx.mat'));
            load(path_emptySurfaceIdx, 'emptySurfaceIdx'); % variable name defined in CMT: wrapper_MorphQuant

            if app.opt_cartilage_FTC
                % modify figures
                [figHandle_FC, childHandles_FC] = CMV_modifyCartilageSubregions(app, handle_FTC, "FC", emptySurfaceIdx);
                [figHandle_TC, childHandles_TC] = CMV_modifyCartilageSubregions(app, handle_FTC, "TC", emptySurfaceIdx);

                % plot FC
                app.UIAxes_subregions_FC.Visible = "on";
                app.Panel_subregions_FC.Visible = "on";
                copyobj(get(figHandle_FC, 'children'), app.UIAxes_subregions_FC);
                CMV_resetCartilageSubregions(app, app.UIAxes_subregions_FC, "FC", childHandles_FC);

                % plot TC
                app.UIAxes_subregions_TC.Visible = "on";
                app.Panel_subregions_TC.Visible = "on";
                copyobj(get(figHandle_TC, 'children'), app.UIAxes_subregions_TC);
                CMV_resetCartilageSubregions(app, app.UIAxes_subregions_TC, "TC", childHandles_TC);

            elseif app.opt_cartilage_FC
                % load figure from files
                openfig(path_fig_FTC, 'invisible');
                handle_FTC = gca;

                % modify figures
                [figHandle_FC, childHandles_FC] = CMV_modifyCartilageSubregions(app, handle_FTC, "FC", emptySurfaceIdx);

                % plot FC
                app.UIAxes_subregions_FCorTC.Visible = "on";
                app.Panel_subregions_FCorTC.Visible = "on";
                copyobj(get(figHandle_FC, 'children'), app.UIAxes_subregions_FCorTC);
                CMV_resetCartilageSubregions(app, app.UIAxes_subregions_FCorTC, "FC", childHandles_FC);

            elseif app.opt_cartilage_TC
                % load figure from files
                openfig(path_fig_FTC, 'invisible');
                handle_FTC = gca;

                % modify figures
                [figHandle_TC, childHandles_TC] = CMV_modifyCartilageSubregions(app, handle_FTC, "TC", emptySurfaceIdx);

                % plot TC
                app.UIAxes_subregions_FCorTC.Visible = "on";
                app.Panel_subregions_FCorTC.Visible = "on";
                copyobj(get(figHandle_TC, 'children'), app.UIAxes_subregions_FCorTC);
                CMV_resetCartilageSubregions(app, app.UIAxes_subregions_FCorTC, "TC", childHandles_TC);
            end

        end


        function [figHandle, childHandles] = CMV_modifyCartilageSubregions(~, handle, type, emptySurfaceIdx)
            % --------------------------------------------
            % [modify figures]
            % the order of surface patches are determined by CMT function: plot_CartSubregions_mesh
            % (the order of children is the inverse order of patches creation)
            %   - handle.Children(1): cLTC
            %   - handle.Children(2): iLTC
            %   - handle.Children(3): pLTC
            %   - handle.Children(4): eLTC
            %   - handle.Children(5): aLTC
            %   - handle.Children(6): cMTC
            %   - handle.Children(7): iMTC
            %   - handle.Children(8): pMTC
            %   - handle.Children(9): eMTC
            %   - handle.Children(10): aMTC
            %   - handle.Children(11): pLFC
            %   - handle.Children(12): icLFC
            %   - handle.Children(13): ccLFC
            %   - handle.Children(14): ecLFC
            %   - handle.Children(15): aLFC
            %   - handle.Children(16): pMFC
            %   - handle.Children(17): icMFC
            %   - handle.Children(18): ccMFC
            %   - handle.Children(19): ecMFC
            %   - handle.Children(20): aMFC
            % --------------------------------------------

            % separate surface patches of FC and TC
            % ----------------------
            %             switch type
            %                 case "FC"
            %                     figure(Visible="off");
            %                     figHandle = axes;
            %                     copyobj(get(handle, 'children'), figHandle);
            %                     delete(figHandle.Children(1:10));
            %                 case "TC"
            %                     figure(Visible="off");
            %                     figHandle = axes;
            %                     copyobj(get(handle, 'children'), figHandle);
            %                     delete(figHandle.Children(11:20));
            %             end


            figure(Visible="off");
            figHandle = axes;
            copyobj(get(handle, 'children'), figHandle);
            switch type
                case "FC"
                    ROI_idx_nonempty = transpose(1:20);
                    ROI_idx_nonempty(logical(emptySurfaceIdx)) = [];
                    ROI_idx_aligned = flip(ROI_idx_nonempty, 1);
                    idx_tbr = ismember(ROI_idx_aligned, 11:20);
                    delete(figHandle.Children(idx_tbr));

                case "TC"
                    ROI_idx_nonempty = transpose(1:20);
                    ROI_idx_nonempty(logical(emptySurfaceIdx)) = [];
                    ROI_idx_aligned = flip(ROI_idx_nonempty, 1);
                    idx_tbr = ismember(ROI_idx_aligned, 1:10);
                    delete(figHandle.Children(idx_tbr));
            end
            % ----------------------

            % figure handles in this order:
            %    {'aMFC','ecMFC','ccMFC','icMFC','pMFC','aLFC','ecLFC','ccLFC','icLFC','pLFC',
            %     'aMTC','eMTC','pMTC','iMTC','cMTC',
            %     'aLTC','eLTC','pLTC','iLTC','cLTC'}
            childHandles = flip(figHandle.Children);
        end


        function CMV_resetCartilageSubregions(app, subplot, type, childHandles)
            switch type
                case "FC"
                    axis(subplot, "equal");
                    axis(subplot, "off");
                    view(subplot, 0, 90);
                    title(subplot, "");
                    axis(subplot, "padded");
                    xticks(subplot, []);
                    yticks(subplot, []);
                    zticks(subplot, []);
                    xlabel(subplot, '');
                    ylabel(subplot, '');
                    zlabel(subplot, '');
                    if app.opt_cartilage_FTC
                        legendLabels = {'aMFC','ecMFC','ccMFC','icMFC','pMFC',...
                            'aLFC','ecLFC','ccLFC','icLFC','pLFC'};
                    else
                        legendLabels = {'[01] aMFC','[02] ecMFC','[03] ccMFC','[04] icMFC','[05] pMFC',...
                            '[06] aLFC','[07] ecLFC','[08] ccLFC','[09] icLFC','[10] pLFC'};
                    end
                    if app.opt_cartilage_FTC
                        config_legendLoc = "south";
                        config_numCol = 4;
                    else
                        config_legendLoc = "best";
                        config_numCol = 1;
                    end
                    legend(subplot, childHandles, legendLabels,...
                        'Location',config_legendLoc,...
                        'Color','none',...
                        'Box','off',...
                        'FontName','TimesNewRome',...
                        'NumColumns', config_numCol);
                case "TC"
                    axis(subplot, "equal");
                    axis(subplot, "off");
                    view(subplot, 0, 90);
                    title(subplot, "");
                    axis(subplot, "padded");
                    xticks(subplot, []);
                    yticks(subplot, []);
                    zticks(subplot, []);
                    xlabel(subplot, '');
                    ylabel(subplot, '');
                    zlabel(subplot, '');
                    if app.opt_cartilage_FTC
                        legendLabels = {'aMTC','eMTC','pMTC', 'iMTC','cMTC',...
                            'aLTC','eLTC','pLTC','iLTC','cLTC'};
                    else
                        legendLabels = {'[11] aMTC','[12] eMTC','[13] pMTC','[14] iMTC','[15] cMTC',...
                            '[16] aLTC','[17] eLTC','[18] pLTC','[19] iLTC','[20] cLTC'};
                    end
                    if app.opt_cartilage_FTC
                        config_legendLoc = "south";
                        config_numCol = 4;
                    else
                        config_legendLoc = "best";
                        config_numCol = 1;
                    end
                    legend(subplot, childHandles, legendLabels,...
                        'Location',config_legendLoc,...
                        'Color','none',...
                        'Box','off',...
                        'FontName','TimesNewRome',...
                        'NumColumns', config_numCol);
            end
        end


        function CMV_updateThicknessMap(app)
            dir_fig = fullfile(app.dir_result, app.config_caseName, 'ThicknessMap_Figure');

            % clear figures
            cla(app.UIAxes_thicknessMap);
            legend(app.UIAxes_thicknessMap, "off");

            % hide figures
            app.UIAxes_thicknessMap.Visible = "off";
            app.Panel_thicknessMap.Visible = "off";

            % set path of figure files generated from CMT
            path_fig_FTC = fullfile(dir_fig, 'AllCartilages', strcat(app.config_caseName, "_sMap_allCartilages_v15.fig"));

            % load figure from files
            openfig(path_fig_FTC, 'invisible');
            handle_FTC = gca;

            % modify figure
            if app.opt_cartilage_FTC
                figHandle = CMV_modifyThicknessMap(app, handle_FTC, "FTC");
            elseif app.opt_cartilage_FC
                figHandle = CMV_modifyThicknessMap(app, handle_FTC, "FC");
            elseif app.opt_cartilage_TC
                figHandle = CMV_modifyThicknessMap(app, handle_FTC, "TC");
            end

            % plot figure
            app.UIAxes_thicknessMap.Visible = "on";
            app.Panel_thicknessMap.Visible = "on";
            copyobj(get(figHandle, 'children'), app.UIAxes_thicknessMap);
            set(app.UIAxes_thicknessMap, 'CLim', get(figHandle, 'CLim'));
            CMV_resetThicknessMap(app, app.UIAxes_thicknessMap);
        end


        function figHandle = CMV_modifyThicknessMap(app, handle, type)
            % --------------------------------------------
            % [modify figures]
            % the order of figure components are determined by CMT function: plot_integrated_thicknessMap_wBone (Figure 15)
            % (the order of children is the inverse generation order)
            %   - handle.Children(1): mesh faces for mTC
            %   - handle.Children(2): mesh faces for lTC
            %   - handle.Children(3): mesh faces for FC
            %   - handle.Children(4): mesh faces for Tibia
            %   - handle.Children(5): mesh faces for Femur
            %   - handle.Children(6): thickness map for mTC
            %   - handle.Children(7): thickness map for lTC
            %   - handle.Children(8): thickness map for FC
            % --------------------------------------------

            % ---------------------------------
            % [modify figure]
            % ---------------------------------
            % copy figure object
            figure(Visible="off");
            figHandle = axes;
            copyobj(get(handle, 'children'), figHandle);

            % set limit of color bar
            set(figHandle, 'CLim', get(handle, "CLim"));

            % change the mesh color of Femur
            if app.opt_map_bone || app.opt_map_bone_cartilage
                figHandle.Children(5).FaceColor = "#5EBD3E";
                figHandle.Children(5).FaceAlpha = 0.1;
            end

            % change the face color of Tibia
            if app.opt_map_bone || app.opt_map_bone_cartilage
                figHandle.Children(4).FaceColor = "#F78200";
                figHandle.Children(4).FaceAlpha = 0.1;
            end

            % change the face color of FC
            if app.opt_map_cartilage || app.opt_map_bone_cartilage
                figHandle.Children(3).FaceColor = "#1D71F2";
                figHandle.Children(3).FaceAlpha = 0.1;
            end

            % change the face color of lTC
            if app.opt_map_cartilage || app.opt_map_bone_cartilage
                figHandle.Children(2).FaceColor = "#1D71F2";
                figHandle.Children(2).FaceAlpha = 0.1;
            end

            % change the face color of mTC
            if app.opt_map_cartilage || app.opt_map_bone_cartilage
                figHandle.Children(1).FaceColor = "#1D71F2";
                figHandle.Children(1).FaceAlpha = 0.1;
            end
            % ---------------------------------

            % ---------------------------------
            % [delete unused figure components]
            % ---------------------------------
            switch type
                case "FTC"
                    if app.opt_map_cartilage
                        idx_tbr = [4,5];
                    elseif app.opt_map_bone
                        idx_tbr = [1,2,3];
                    else
                        idx_tbr = [];
                    end
                    delete(figHandle.Children(idx_tbr));

                case "FC"
                    if app.opt_map_cartilage
                        idx_tbr = [1,2,4,5,6,7];
                    elseif app.opt_map_bone
                        idx_tbr = [1,2,3,4,6,7];
                    else
                        idx_tbr = [1,2,4,6,7];
                    end
                    delete(figHandle.Children(idx_tbr));

                case "TC"
                    if app.opt_map_cartilage
                        idx_tbr = [3,4,5,8];
                    elseif app.opt_map_bone
                        idx_tbr = [1,2,3,5,8];
                    else
                        idx_tbr = [3,5,8];
                    end
                    delete(figHandle.Children(idx_tbr));
            end
            % ---------------------------------
        end


        function  CMV_resetThicknessMap(~, subplot)
            axis(subplot, "equal");
            axis(subplot, "off");
            view(subplot, -145, 20);
            title(subplot, "");
            axis(subplot, "tight");
            xticks(subplot, []);
            yticks(subplot, []);
            zticks(subplot, []);
            xlabel(subplot, '');
            ylabel(subplot, '');
            zlabel(subplot, '');
            colormap(subplot, jet);
            colorbar(subplot);
        end


        function CMV_updateMRI(app)
            dir_fig = fullfile(app.dir_result, app.config_caseName, 'Image');

            % standardized MR image (orientation: RAS+)
            path_mat_img = fullfile(dir_fig, strcat(app.config_caseName, "_image.mat"));
            load(path_mat_img, 'i_img'); % the variable name is set in CMT: CMT_morphQuant(app)
            image = i_img;
            clear('i_img');

            % cartilage subregion masks
            path_subregionMask = fullfile(app.dir_result, app.config_caseName, 'CartilageSubregion',...
                strcat(app.config_caseName, '_subregions.mat'));
            load(path_subregionMask, 'cartilageSubregions'); % the variable name is set in CMT: wrapper_MorphQuant(app)
            mask_subregion = cartilageSubregions;

            % voxel size
            path_voxSize = fullfile(app.dir_result, app.config_caseName, 'CartilageSubregion',...
                strcat(app.config_caseName, '_voxelSize.mat'));
            load(path_voxSize, 'voxelSize'); % the variable name is set in CMT: wrapper_MorphQuant(app)
            voxSize = voxelSize;

            % select slices for the first time of visualisation
            if strcmp(app.Panel_MRImg_axial.Visible, 'off')
                app.stat_axiSlice = round(size(image,3)/2);
                app.stat_corSlice = round(size(image,2)/2);
                app.stat_sagSlice = round(size(image,1)/2);
            end

            % plot MR images and subregion masks --> set app.Panel_MRImg_axial to visible
            figHandle_axial = CMV_plotMRIandMask(app, image, mask_subregion, 'axial');
            figHandle_sagittal = CMV_plotMRIandMask(app, image, mask_subregion, 'sagittal');
            figHandle_coronal = CMV_plotMRIandMask(app, image, mask_subregion, 'coronal');

            if length(figHandle_axial.Children)==2
                % show MR images
                copyobj(figHandle_axial.Children(2), app.UIAxes_MRImg_axial);
                copyobj(figHandle_sagittal.Children(2), app.UIAxes_MRImg_sagittal);
                copyobj(figHandle_coronal.Children(2), app.UIAxes_MRImg_coronal);
                % show subregion masks
                copyobj(figHandle_axial.Children(1), app.UIAxes_MRImg_axial_mask);
                copyobj(figHandle_sagittal.Children(1), app.UIAxes_MRImg_sagittal_mask);
                copyobj(figHandle_coronal.Children(1), app.UIAxes_MRImg_coronal_mask);
            else
                % show MR images
                copyobj(figHandle_axial.Children(1), app.UIAxes_MRImg_axial);
                copyobj(figHandle_sagittal.Children(1), app.UIAxes_MRImg_sagittal);
                copyobj(figHandle_coronal.Children(1), app.UIAxes_MRImg_coronal);
                % remove subregion masks
                app.UIAxes_MRImg_axial_mask.Visible = "off";
                app.UIAxes_MRImg_sagittal_mask.Visible = "off";
                app.UIAxes_MRImg_coronal_mask.Visible = "off";
            end

            % set the appearance of MR images and subregion masks
            CMV_resetMRIandMask(app, voxSize, 'axial');
            CMV_resetMRIandMask(app, voxSize, 'sagittal');
            CMV_resetMRIandMask(app, voxSize, 'coronal');

            % disable interaction
            app.UIAxes_MRImg_axial.HitTest = "off";
            app.UIAxes_MRImg_axial_mask.HitTest = "off";
            app.UIAxes_MRImg_sagittal.HitTest = "off";
            app.UIAxes_MRImg_sagittal_mask.HitTest = "off";
            app.UIAxes_MRImg_coronal.HitTest = "off";
            app.UIAxes_MRImg_coronal_mask.HitTest = "off";
        end


        function figHandle = CMV_plotMRIandMask(app, image, mask_subregions, view)

            % ===========================================================
            % plot MR image and  subregion masks [subject to RAS+ orientation]
            % -----------------------------------------------------------
            % Note:
            %   - the x axis of 2D image in matlab is the column of array
            %   - the y axis of 2D image in matlab is the row of array
            % ===========================================================

            switch view
                case 'axial'
                    % clear figures
                    cla(app.UIAxes_MRImg_axial);
                    cla(app.UIAxes_MRImg_axial_mask);
                    % set visibility
                    app.Panel_MRImg_axial.Visible = "on";
                    app.Slider_axial.Visible = "on";
                    % set slider limits
                    app.Slider_axial.Limits = [1, size(image,3)];
                    % select slice
                    app.Slider_axial.Value = app.stat_axiSlice;
                    % set slice number label
                    app.Label_slice_axial.Text = {num2str(app.stat_axiSlice); '----'; num2str(size(image,3))};
                    app.Label_slice_axial.Visible = "on";
                    % get slice indices [subject to RAS+ orientation]
                    [~, ~, idx_axial] = ind2sub(size(mask_subregions), find(mask_subregions));
                    idx_axial = unique(idx_axial);

                    figure(Visible="off");
                    figHandle_axial_f1 = subplot(1,1,1);
                    img_axial = transpose(squeeze(image(:, :, app.stat_axiSlice)));
                    img_axial_autoAdj = imadjust(img_axial);
                    xLim_axial = 1:size(img_axial,2);
                    yLim_axial = 1:size(img_axial,1);
                    imagesc(figHandle_axial_f1, xLim_axial, yLim_axial, img_axial_autoAdj);
                    if app.opt_img_subregion && sum(idx_axial==app.stat_axiSlice)==1
                        % subregion masks
                        mask_subregions_2D = transpose(squeeze(mask_subregions(:, :, app.stat_axiSlice)));
                        if app.opt_cartilage_FTC
                            visibility = mask_subregions_2D>0;
                        elseif app.opt_cartilage_FC
                            visibility = mask_subregions_2D>0 & mask_subregions_2D<=10;
                        elseif app.opt_cartilage_TC
                            visibility = mask_subregions_2D>10 & mask_subregions_2D<=20;
                        end
                        mask_subregions_visible = zeros(size(mask_subregions_2D), class(mask_subregions_2D));
                        mask_subregions_visible(visibility) = mask_subregions_2D(visibility);
                        % plot subregions
                        figHandle_axial_f2 = axes('position', figHandle_axial_f1.Position);
                        imagesc(figHandle_axial_f2, xLim_axial, yLim_axial, mask_subregions_visible,...
                            'alphadata', visibility*app.config_mask_opacity);
                        % handle
                        figHandle = gca(figure(Visible="off"));
                        copyobj(get(figHandle_axial_f1, 'children'), figHandle);
                        copyobj(get(figHandle_axial_f2, 'children'), figHandle);
                    else
                        % handle
                        figHandle = gca(figure(Visible="off"));
                        copyobj(get(figHandle_axial_f1, 'children'), figHandle);
                    end


                case 'sagittal'
                    % clear figures
                    cla(app.UIAxes_MRImg_sagittal);
                    cla(app.UIAxes_MRImg_sagittal_mask);
                    % set visibility
                    app.Panel_MRImg_sagittal.Visible = "on";
                    app.Slider_sagittal.Visible = "on";
                    % set slider limits
                    app.Slider_sagittal.Limits = [1, size(image,1)];
                    % select slice
                    app.Slider_sagittal.Value = app.stat_sagSlice;
                    % set slice number label
                    app.Label_slice_sagittal.Text = {num2str(app.stat_sagSlice); '----'; num2str(size(image,1))};
                    app.Label_slice_sagittal.Visible = "on";
                    % get slice indices [subject to RAS+ orientation]
                    [idx_sagittal, ~, ~] = ind2sub(size(mask_subregions), find(mask_subregions));
                    idx_sagittal = unique(idx_sagittal);

                    figure(Visible="off");
                    figHandle_sagittal_f1 = subplot(1,1,1);
                    img_sagittal = transpose(squeeze(image(app.stat_sagSlice, :, :)));
                    img_sagittal_autoAdj = imadjust(img_sagittal);
                    xLim_sagittal = 1:size(img_sagittal,2);
                    yLim_sagittal = 1:size(img_sagittal,1);
                    imagesc(figHandle_sagittal_f1, xLim_sagittal, yLim_sagittal, img_sagittal_autoAdj);
                    if app.opt_img_subregion && sum(idx_sagittal==app.stat_sagSlice)==1
                        % subregion masks
                        mask_subregions_2D = transpose(squeeze(mask_subregions(app.stat_sagSlice, :, :)));
                        if app.opt_cartilage_FTC
                            visibility = mask_subregions_2D>0;
                        elseif app.opt_cartilage_FC
                            visibility = mask_subregions_2D>0 & mask_subregions_2D<=10;
                        elseif app.opt_cartilage_TC
                            visibility = mask_subregions_2D>10 & mask_subregions_2D<=20;
                        end
                        mask_subregions_visible = zeros(size(mask_subregions_2D), class(mask_subregions_2D));
                        mask_subregions_visible(visibility) = mask_subregions_2D(visibility);
                        % plot subregions
                        figHandle_sagittal_f2 = axes('position', figHandle_sagittal_f1.Position);
                        imagesc(figHandle_sagittal_f2, xLim_sagittal, yLim_sagittal, mask_subregions_visible,...
                            'alphadata', visibility*app.config_mask_opacity);
                        % handle
                        figHandle = gca(figure(Visible="off"));
                        copyobj(get(figHandle_sagittal_f1, 'children'), figHandle);
                        copyobj(get(figHandle_sagittal_f2, 'children'), figHandle);
                    else
                        % handle
                        figHandle = gca(figure(Visible="off"));
                        copyobj(get(figHandle_sagittal_f1, 'children'), figHandle);
                    end


                case 'coronal'
                    % clear figures
                    cla(app.UIAxes_MRImg_coronal);
                    cla(app.UIAxes_MRImg_coronal_mask);
                    % set visibility
                    app.Panel_MRImg_coronal.Visible = "on";
                    app.Slider_coronal.Visible = "on";
                    % set slider limits
                    app.Slider_coronal.Limits = [1, size(image,2)];
                    % select slice
                    app.Slider_coronal.Value = app.stat_corSlice;
                    % set slice number label
                    app.Label_slice_coronal.Text = {num2str(app.stat_corSlice); '----'; num2str(size(image,2))};
                    app.Label_slice_coronal.Visible = "on";
                    % get slice indices [subject to RAS+ orientation]
                    [~, idx_coronal, ~] = ind2sub(size(mask_subregions), find(mask_subregions));
                    idx_coronal = unique(idx_coronal);

                    figure(Visible="off");
                    figHandle_coronal_f1 = subplot(1,1,1);
                    img_coronal = transpose(squeeze(image(:, app.stat_corSlice, :)));
                    img_coronal_autoAdj = imadjust(img_coronal);
                    xLim_coronal = 1:size(img_coronal,2);
                    yLim_coronal = 1:size(img_coronal,1);
                    imagesc(figHandle_coronal_f1, xLim_coronal, yLim_coronal, img_coronal_autoAdj);
                    if app.opt_img_subregion && sum(idx_coronal==app.stat_corSlice)==1
                        % subregion masks
                        mask_subregions_2D = transpose(squeeze(mask_subregions(:, app.stat_corSlice, :)));
                        if app.opt_cartilage_FTC
                            visibility = mask_subregions_2D>0;
                        elseif app.opt_cartilage_FC
                            visibility = mask_subregions_2D>0 & mask_subregions_2D<=10;
                        elseif app.opt_cartilage_TC
                            visibility = mask_subregions_2D>10 & mask_subregions_2D<=20;
                        end
                        mask_subregions_visible = zeros(size(mask_subregions_2D), class(mask_subregions_2D));
                        mask_subregions_visible(visibility) = mask_subregions_2D(visibility);
                        % plot subregions
                        figHandle_coronal_f2 = axes('position', figHandle_coronal_f1.Position);
                        imagesc(figHandle_coronal_f2, xLim_coronal, yLim_coronal, mask_subregions_visible,...
                            'alphadata', visibility*app.config_mask_opacity);
                        % handle
                        figHandle = gca(figure(Visible="off"));
                        copyobj(get(figHandle_coronal_f1, 'children'), figHandle);
                        copyobj(get(figHandle_coronal_f2, 'children'), figHandle);
                    else
                        % handle
                        figHandle = gca(figure(Visible="off"));
                        copyobj(get(figHandle_coronal_f1, 'children'), figHandle);
                    end
            end
            % ===========================================================
        end


        function CMV_resetMRIandMask(app, voxSize, view)
            % ===========================================================
            % set MR images and subregion masks [subject to RAS+ orientation]
            % -----------------------------------------------------------
            % Note:
            %   - the x axis of 2D image in matlab is the column of array
            %   - the y axis of 2D image in matlab is the row of array
            % ===========================================================
            colorMap_hex = {'#000000', '#FAD7A0', '#F1948A', '#D7BDE2', '#D6EAF8', '#7DCEA0',...
                '#F39C12', '#E74C3C', '#9B59B6', '#3498DB', '#27AE60',...
                '#FFDA5E', '#EA6975', '#57AB89', '#75AAD6', '#F6D3DE',...
                '#FFEB11', '#D71729', '#8BE071', '#1076E2', '#B08FC7'};
            colorMap_rgb = validatecolor(colorMap_hex, 'multiple');

            switch view
                case 'axial'
                    figHandle_axial = app.UIAxes_MRImg_axial;
                    voxSize_axial = [1/voxSize(1), 1/voxSize(2), 1];
                    CMV_resetMRIorMask(app, figHandle_axial, voxSize_axial, 'axial');
                    if app.opt_img_subregion
                        figHandle_axial_mask = app.UIAxes_MRImg_axial_mask;
                        figHandle_axial_mask.Position = app.UIAxes_MRImg_axial.Position;
                        CMV_resetMRIorMask(app, figHandle_axial_mask, voxSize_axial, 'axial');
                        set(figHandle_axial_mask,'color','none','visible','off');
                        if ~isempty(figHandle_axial_mask.Children)
                            figHandle_axial_mask.Children.CDataMapping = 'direct';
                        end
                        colormap(figHandle_axial_mask, colorMap_rgb);
                    end

                case 'sagittal'
                    figHandle_sagittal = app.UIAxes_MRImg_sagittal;
                    voxSize_sagittal = [1/voxSize(2), 1/voxSize(3), 1];
                    CMV_resetMRIorMask(app, figHandle_sagittal, voxSize_sagittal, 'sagittal');
                    if app.opt_img_subregion
                        figHandle_sagittal_mask = app.UIAxes_MRImg_sagittal_mask;
                        figHandle_sagittal_mask.Position = app.UIAxes_MRImg_sagittal.Position;
                        CMV_resetMRIorMask(app, figHandle_sagittal_mask, voxSize_sagittal, 'sagittal');
                        set(figHandle_sagittal_mask,'color','none','visible','off');
                        if ~isempty(figHandle_sagittal_mask.Children)
                            figHandle_sagittal_mask.Children.CDataMapping = 'direct';
                        end
                        colormap(figHandle_sagittal_mask, colorMap_rgb);
                    end

                case 'coronal'
                    figHandle_coronal = app.UIAxes_MRImg_coronal;
                    voxSize_coronal = [1/voxSize(1), 1/voxSize(3), 1];
                    CMV_resetMRIorMask(app, figHandle_coronal, voxSize_coronal, 'coronal');
                    if app.opt_img_subregion
                        figHandle_coronal_mask = app.UIAxes_MRImg_coronal_mask;
                        figHandle_coronal_mask.Position = app.UIAxes_MRImg_coronal.Position;
                        CMV_resetMRIorMask(app, figHandle_coronal_mask, voxSize_coronal, 'coronal');
                        set(figHandle_coronal_mask,'color','none','visible','off');
                        if ~isempty(figHandle_coronal_mask.Children)
                            figHandle_coronal_mask.Children.CDataMapping = 'direct';
                        end
                        colormap(figHandle_coronal_mask, colorMap_rgb);
                    end
            end
            % ===========================================================
        end


        function CMV_resetMRIorMask(~, figHandle, voxSize, view)
            colormap(figHandle, 'gray');

            switch view
                case 'axial'
                    set(figHandle, 'xdir','normal');
                    set(figHandle, 'ydir','normal');
                case 'sagittal'
                    set(figHandle, 'xdir', 'reverse');
                    set(figHandle, 'ydir','normal');
                case 'coronal'
                    set(figHandle, 'xdir','normal');
                    set(figHandle, 'ydir','normal');
            end

            set(figHandle, 'DataAspectRatio', voxSize);
            set(figHandle, 'Color', 'k');
            axis(figHandle, "off");
            title(figHandle, "");
            axis(figHandle, "tight");
            xticks(figHandle, []);
            yticks(figHandle, []);
            zticks(figHandle, []);
            xlabel(figHandle, '');
            ylabel(figHandle, '');
            zlabel(figHandle, '');
        end


        function CMV_update3DSubregions(app)

            if app.opt_img_subregion
                % clear figures
                cla(app.UIAxes_MRImg_3DView);

                % set path of figure files generated from CMT
                path_fig_FTC = fullfile(app.dir_result, app.config_caseName, 'CartilageSubregion_Figure',...
                    strcat(app.config_caseName, '_3DSubregions_FTC.fig'));

                % load empty surface indicator
                path_emptySurfaceIdx = fullfile(app.dir_result, app.config_caseName, 'CartilageSubregion_Figure',...
                    strcat(app.config_caseName, '_emptySurfaceIdx.mat'));
                load(path_emptySurfaceIdx, 'emptySurfaceIdx'); % variable name defined in CMT: wrapper_MorphQuant

                % load figure from files
                openfig(path_fig_FTC, 'invisible');
                handle_FTC = gca;

                % modify figure
                if app.opt_cartilage_FTC
                    figHandle = CMV_modify3DSubregions(app, handle_FTC, "FTC", emptySurfaceIdx);
                elseif app.opt_cartilage_FC
                    figHandle = CMV_modify3DSubregions(app, handle_FTC, "FC", emptySurfaceIdx);
                elseif app.opt_cartilage_TC
                    figHandle = CMV_modify3DSubregions(app, handle_FTC, "TC", emptySurfaceIdx);
                end

                % plot figure
                app.UIAxes_MRImg_3DView.Visible = "on";
                app.Panel_MRImg_3DView.Visible = "on";
                copyobj(get(figHandle, 'children'), app.UIAxes_MRImg_3DView);
                CMV_reset3DSubregions(app);
            else
                app.UIAxes_MRImg_3DView.Visible = "off";
                app.Panel_MRImg_3DView.Visible = "off";
            end
        end


        function figHandle = CMV_modify3DSubregions(~, handle, type, emptySurfaceIdx)
            % --------------------------------------------
            % [modify figures]
            % the order of figure components are determined by CMT function: wrapper_MorphQuant
            % (the order of children is the inverse order of patches creation)
            %   - handle.Children(1): cLTC
            %   - handle.Children(2): iLTC
            %   - handle.Children(3): pLTC
            %   - handle.Children(4): eLTC
            %   - handle.Children(5): aLTC
            %   - handle.Children(6): cMTC
            %   - handle.Children(7): iMTC
            %   - handle.Children(8): pMTC
            %   - handle.Children(9): eMTC
            %   - handle.Children(10): aMTC
            %   - handle.Children(11): pLFC
            %   - handle.Children(12): icLFC
            %   - handle.Children(13): ccLFC
            %   - handle.Children(14): ecLFC
            %   - handle.Children(15): aLFC
            %   - handle.Children(16): pMFC
            %   - handle.Children(17): icMFC
            %   - handle.Children(18): ccMFC
            %   - handle.Children(19): ecMFC
            %   - handle.Children(20): aMFC
            % --------------------------------------------

            % ---------------------------------
            % [delete unused figure components]
            % ---------------------------------
            % copy figure object
            figure(Visible="off");
            figHandle = axes;
            copyobj(get(handle, 'children'), figHandle);

            switch type
                case "FTC"
                    idx_tbr = [];
                    delete(figHandle.Children(idx_tbr));

                case "FC"
                    ROI_idx_nonempty = transpose(1:20);
                    ROI_idx_nonempty(logical(emptySurfaceIdx)) = [];
                    ROI_idx_aligned = flip(ROI_idx_nonempty, 1);
                    idx_tbr = ismember(ROI_idx_aligned, 11:20);
                    delete(figHandle.Children(idx_tbr));

                case "TC"
                    ROI_idx_nonempty = transpose(1:20);
                    ROI_idx_nonempty(logical(emptySurfaceIdx)) = [];
                    ROI_idx_aligned = flip(ROI_idx_nonempty, 1);
                    idx_tbr = ismember(ROI_idx_aligned, 1:10);
                    delete(figHandle.Children(idx_tbr));
            end
            % ---------------------------------
        end


        function CMV_reset3DSubregions(app)
            % 3D subregions
            figHandel_3DSubregions = app.UIAxes_MRImg_3DView;
            axis(figHandel_3DSubregions, "equal");
            axis(figHandel_3DSubregions, "off");
            view(figHandel_3DSubregions, -145, 20);
            title(figHandel_3DSubregions, "");
            axis(figHandel_3DSubregions, "tight");
            xticks(figHandel_3DSubregions, []);
            yticks(figHandel_3DSubregions, []);
            zticks(figHandel_3DSubregions, []);
            xlabel(figHandel_3DSubregions, '');
            ylabel(figHandel_3DSubregions, '');
            zlabel(figHandel_3DSubregions, '');
        end


        function CMV_updateMorphQuant(app)
            % read quantification results
            path_csv = fullfile(app.dir_result, app.config_caseName, 'MorphologicalQuantification',...
                strcat(app.config_caseName, '_MorphQuant.csv'));
            table = readtable(path_csv, "ReadRowNames",true, "ReadVariableNames",true);


            % set variable and row names
            % ---------------------------------
            %             % option 1: use CMV variable names -- the ones witout ROI number [xx]
            %             varNames = table.Properties.VariableNames;
            % option 2: reset variable names -- the ones with ROI number [xx]
            varNames = {'[01] aMFC','[02] ecMFC','[03] ccMFC','[04] icMFC','[05] pMFC',...
                '[06] aLFC','[07] ecLFC','[08] ccLFC','[09] icLFC','[10] pLFC',...
                '[11] aMTC','[12] eMTC','[13] pMTC','[14] iMTC','[15] cMTC',...
                '[16] aLTC','[17] eLTC','[18] pLTC','[19] iLTC','[20] cLTC'};
            % ---------------------------------
            rowNames = {'FCL (%)', 'Mean Thickness (mm)', 'Surface Area (mm^2)', 'Volume (mm^3)'};

            % [format table]
            table(1,:) = array2table(table{1,:} * 100);
            table_FCL = varfun(@(x) num2str(x, '%.4f'), table(1,:));
            table_FCL.Properties.VariableNames = varNames;
            table_FCL = cell2table(table2cell(table_FCL));

            table_meanThickness =  varfun(@(x) num2str(x, '%.4f'), table(2,:));
            table_meanThickness.Properties.VariableNames = varNames;
            table_meanThickness = cell2table(table2cell(table_meanThickness));

            table_surfaceArea =  varfun(@(x) num2str(x, '%.0f'), table(3,:));
            table_surfaceArea.Properties.VariableNames = varNames;
            table_surfaceArea = cell2table(table2cell(table_surfaceArea));

            table_volume =  varfun(@(x) num2str(x, '%.0f'), table(4,:));
            table_volume.Properties.VariableNames = varNames;
            table_volume = cell2table(table2cell(table_volume));

            table_display = [table_FCL; table_meanThickness; table_surfaceArea; table_volume];
            table_display.Properties.VariableNames = varNames;
            table_display.Properties.RowNames = rowNames;

            % update table
            app.UITable.Data = table_display;
            app.UITable.ColumnName = varNames;
            app.UITable.RowName = rowNames;
            addStyle(app.UITable, uistyle('HorizontalAlignment','center'));
            addStyle(app.UITable, uistyle('Interpreter','tex'));
        end


        function CMV_disableButtons(app)
            app.Button_update.Enable = "off";
            app.Button_save.Enable = "off";
        end


        function CMV_enableButtons(app)
            app.Button_update.Enable = "on";
            app.Button_save.Enable = "on";
        end


        function CMV_updateKneeSide(app, caseName)
            % load knee side info from individual result folder
            load(fullfile(app.dir_result, caseName, "kneeSideInfo.mat"), "kneeSideInfo");
            app.config_kneeSide = kneeSideInfo;
            % show knee side in CMV
            app.EditField_kneeSide.Value = app.config_kneeSide;
            % enable buttons
            CMV_enableButtons(app);
        end


        function CMV_closeAllWindows(app)
            delete(app.aboutCMV);
            delete(app);
        end


        function CMV_disablePanels(app)
            % disable panels
            app.Panel_i.Enable = "off";
            app.Panel_ii.Enable = "off";
            app.Panel_Buttons.Enable = "off";
            % "About" button
            app.AboutButton.Enable = "off";
        end


        function CMV_enablePanels(app)
            % disable panels
            app.Panel_i.Enable = "on";
            app.Panel_ii.Enable = "on";
            app.Panel_Buttons.Enable = "on";
            % "About" button
            app.AboutButton.Enable = "on";
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if length(varargin)==1
                % the input argument should be the main app -- CartiMorph Toolobx
                app.CMT = varargin{1};
                % set flag
                app.flag_fromCMT = true;

                % migrate configurations from CMT
                CMV_migrateConfig(app);

                % freeze fields
                app.EditField_path.Value = app.dir_result;
                app.EditField_path.Editable = "off";
                app.Button_selectPath.Enable = "off";

                % update case list
                CMV_updateCaseList(app);

            elseif isempty(varargin)
                % set flag
                app.flag_fromCMT = false;
                % disable buttons
                CMV_disableButtons(app);
            end
        end

        % Close request function: CartiMorphViewerUIFigure
        function CartiMorphViewerUIFigureCloseRequest(app, event)
            if app.flag_fromCMT
                % reset button in the main app
                app.CMT.Button_CMV.Enable = "on";
                app.CMT.Button_CMV.Value = false;
            end

            delete(app);
        end

        % Button pushed function: Button_selectPath
        function Button_selectPathPushed(app, event)
            % freeze the button
            app.Button_selectPath.Enable = "off";
            % select path
            path = uigetdir([],'Select the result folder.');
            if path~=0
                % set path
                app.EditField_path.Value = path;
                app.dir_result = path;
                % set buttons
                app.Button_hidePath.Value = false;
                app.Button_hidePath.Text = "hide path";

                % update case list
                CMV_updateCaseList(app);
            end
            % reset the button
            app.Button_selectPath.Enable = "on";
        end

        % Value changed function: EditField_path
        function EditField_pathValueChanged(app, event)
            value = app.EditField_path.Value;
            if ~isfolder(value)
                uialert(app.CartiMorphViewerUIFigure, 'Folder does not exist', 'Error');
                app.EditField_path.Value = '';
                app.dir_result = '';
            else
                % set result folder
                app.dir_result = value;
                % set buttons
                app.Button_hidePath.Value = false;
                app.Button_hidePath.Text = "hide path";
                % update case list
                CMV_updateCaseList(app);
            end
        end

        % Value changed function: Button_hidePath
        function Button_hidePathValueChanged(app, event)
            value = app.Button_hidePath.Value;
            if value
                app.EditField_path.Value = "******";
                app.Button_hidePath.Text = "show path";
            else
                app.EditField_path.Value = app.dir_result;
                app.Button_hidePath.Text = "hide path";
            end
        end

        % Selection changed function: ButtonGroup_cartilage
        function ButtonGroup_cartilageSelectionChanged(app, event)
            selectedButton = app.ButtonGroup_cartilage.SelectedObject;
            switch selectedButton.Text
                case app.Button_FTC.Text
                    app.opt_cartilage_FTC = true;
                    app.opt_cartilage_TC = false;
                    app.opt_cartilage_FC = false;
                case app.Button_TC.Text
                    app.opt_cartilage_FTC = false;
                    app.opt_cartilage_TC = true;
                    app.opt_cartilage_FC = false;
                case app.Button_FC.Text
                    app.opt_cartilage_FTC = false;
                    app.opt_cartilage_TC = false;
                    app.opt_cartilage_FC = true;
            end
        end

        % Selection changed function: ButtonGroup_map
        function ButtonGroup_mapSelectionChanged(app, event)
            selectedButton = app.ButtonGroup_map.SelectedObject;
            switch selectedButton.Text
                case app.Button_all.Text
                    app.opt_map_bone_cartilage = true;
                    app.opt_map_bone = false;
                    app.opt_map_cartilage = false;
                case app.Button_bone.Text
                    app.opt_map_bone_cartilage = false;
                    app.opt_map_bone = true;
                    app.opt_map_cartilage = false;
                case app.Button_cartilage.Text
                    app.opt_map_bone_cartilage = false;
                    app.opt_map_bone = false;
                    app.opt_map_cartilage = true;
            end
        end

        % Value changed function: DropDown_case
        function DropDown_caseValueChanged(app, event)
            % update case list
            value = app.DropDown_case.Value;
            app.config_caseName = value;

            % update knee side
            CMV_updateKneeSide(app, app.config_caseName)
        end

        % Button pushed function: Button_update
        function Button_updatePushed(app, event)
            % freeze the buttons and panels
            CMV_disablePanels(app);

            % --------------------------------------
            % Cartilage Surface
            CMV_updateCartilageSurface(app);

            % FCL
            CMV_updateFCL(app);

            % Surface Normal
            CMV_updateSurfaceNormal(app);

            % Cartilage Subregions
            CMV_updateCartilageSubregions(app);

            % Cartilage Thickness Map
            CMV_updateThicknessMap(app);

            % MR image and 3D subregions
            CMV_updateMRI(app);
            CMV_update3DSubregions(app);

            % Morphological Quantification
            CMV_updateMorphQuant(app);
            % --------------------------------------

            % enable the buttons and panels
            CMV_enablePanels(app);
        end

        % Value changed function: Button_hideCase
        function Button_hideCaseValueChanged(app, event)
            value = app.Button_hideCase.Value;
            if value
                app.caseNameMask.Visible = "on";
                app.Button_hideCase.Text = "show case";
            else
                app.caseNameMask.Visible = "off";
                app.Button_hideCase.Text = "hide case";
            end
        end

        % Button pushed function: Button_save
        function Button_savePushed(app, event)
            % freeze the button
            app.Button_save.Enable = "off";

            % output folder 
            dir_screenshot = fullfile(app.dir_result, app.config_caseName, 'Report_screenshot');
            if ~isfolder(dir_screenshot)
                mkdir(dir_screenshot);
            end

            % user-defined file name
            defaultFileName = strcat('report_', regexprep(char(datetime), {' ', ':'}, '_'));
            prompt = {sprintf(['We will capture the UI window and save to a pdf file. \n',...
                'Please set the name of the file to be saved.'])};
            opts.Interpreter = 'tex';
            answer = inputdlg(prompt, "Screen-shot file name", 1, ...
                {defaultFileName}, opts);
            fileName = answer{1};
            if ~endsWith(fileName, '.pdf')
                fileName = [fileName,'.pdf'];
            end

            % save ui screenshot
            path_file = fullfile(dir_screenshot, fileName);
            exportapp(app.CartiMorphViewerUIFigure, path_file);

            % show message
            message = sprintf('The UI screenshot has been saved to: %s \n', path_file);
            uialert(app.CartiMorphViewerUIFigure, message, 'UI screenshot saved',...
                'icon', 'success', 'CloseFcn','uiresume(gcbf)');
            uiwait(gcbf);

            % enable the button
            app.Button_save.Enable = "on";
        end

        % Selection changed function: ButtonGroup_Image
        function ButtonGroup_ImageSelectionChanged(app, event)
            selectedButton = app.ButtonGroup_Image.SelectedObject;
            switch selectedButton.Text
                case app.Button_img_subregion.Text
                    app.opt_img_subregion = true;
                    app.opt_img = false;
                case app.Button_img.Text
                    app.opt_img_subregion = false;
                    app.opt_img = true;
            end
        end

        % Value changing function: Slider_axial
        function Slider_axialValueChanging(app, event)
            changingValue = round(event.Value);
            sliderMax = app.Slider_axial.Limits(2);
            % set slice number label
            app.Label_slice_axial.Text = {num2str(changingValue); '----'; num2str(sliderMax)};
            app.Label_slice_axial.Visible = "on";
        end

        % Value changing function: Slider_sagittal
        function Slider_sagittalValueChanging(app, event)
            changingValue = round(event.Value);
            sliderMax = app.Slider_sagittal.Limits(2);
            % set slice number label
            app.Label_slice_sagittal.Text = {num2str(changingValue); '----'; num2str(sliderMax)};
            app.Label_slice_sagittal.Visible = "on";
        end

        % Value changing function: Slider_coronal
        function Slider_coronalValueChanging(app, event)
            changingValue = round(event.Value);
            sliderMax = app.Slider_coronal.Limits(2);
            % set slice number label
            app.Label_slice_coronal.Text = {num2str(changingValue); '----'; num2str(sliderMax)};
            app.Label_slice_coronal.Visible = "on";
        end

        % Value changed function: Slider_axial
        function Slider_axialValueChanged(app, event)
            % select slices
            app.stat_axiSlice = round(app.Slider_axial.Value);

            % standardized MR image (orientation: RAS+)
            dir_fig = fullfile(app.dir_result, app.config_caseName, 'Image');
            path_mat_img = fullfile(dir_fig, strcat(app.config_caseName, "_image.mat"));
            load(path_mat_img, 'i_img'); % the variable name is set in CMT: CMT_morphQuant(app)
            image = i_img;
            clear('i_img');

            % cartilage subregion masks
            path_subregionMask = fullfile(app.dir_result, app.config_caseName, 'CartilageSubregion',...
                strcat(app.config_caseName, '_subregions.mat'));
            load(path_subregionMask, 'cartilageSubregions'); % the variable name is set in CMT: wrapper_MorphQuant(app)
            mask_subregion = cartilageSubregions;

            % voxel size
            path_voxSize = fullfile(app.dir_result, app.config_caseName, 'CartilageSubregion',...
                strcat(app.config_caseName, '_voxelSize.mat'));
            load(path_voxSize, 'voxelSize'); % the variable name is set in CMT: wrapper_MorphQuant(app)
            voxSize = voxelSize;

            % plot MR images and subregion masks
            figHandle_axial = CMV_plotMRIandMask(app, image, mask_subregion, 'axial');

            if length(figHandle_axial.Children)==2
                % show MR images
                copyobj(figHandle_axial.Children(2), app.UIAxes_MRImg_axial);
                % show subregion masks
                copyobj(figHandle_axial.Children(1), app.UIAxes_MRImg_axial_mask);
            else
                % show MR images
                copyobj(figHandle_axial.Children(1), app.UIAxes_MRImg_axial);
                % remove subregion masks
                app.UIAxes_MRImg_axial_mask.Visible = "off";
            end

            % set the appearance of MR images and subregion masks
            CMV_resetMRIandMask(app, voxSize, 'axial');

            % disable interaction
            app.UIAxes_MRImg_axial.HitTest = "off";
            app.UIAxes_MRImg_axial_mask.HitTest = "off";
        end

        % Value changed function: Slider_sagittal
        function Slider_sagittalValueChanged(app, event)
            % select slices
            app.stat_sagSlice = round( app.Slider_sagittal.Value);

            % standardized MR image (orientation: RAS+)
            dir_fig = fullfile(app.dir_result, app.config_caseName, 'Image');
            path_mat_img = fullfile(dir_fig, strcat(app.config_caseName, "_image.mat"));
            load(path_mat_img, 'i_img'); % the variable name is set in CMT: CMT_morphQuant(app)
            image = i_img;
            clear('i_img');

            % cartilage subregion masks
            path_subregionMask = fullfile(app.dir_result, app.config_caseName, 'CartilageSubregion',...
                strcat(app.config_caseName, '_subregions.mat'));
            load(path_subregionMask, 'cartilageSubregions'); % the variable name is set in CMT: wrapper_MorphQuant(app)
            mask_subregion = cartilageSubregions;

            % voxel size
            path_voxSize = fullfile(app.dir_result, app.config_caseName, 'CartilageSubregion',...
                strcat(app.config_caseName, '_voxelSize.mat'));
            load(path_voxSize, 'voxelSize'); % the variable name is set in CMT: wrapper_MorphQuant(app)
            voxSize = voxelSize;

            % plot MR images and subregion masks
            figHandle_sagittal = CMV_plotMRIandMask(app, image, mask_subregion, 'sagittal');

            if length(figHandle_sagittal.Children)==2
                % show MR images
                copyobj(figHandle_sagittal.Children(2), app.UIAxes_MRImg_sagittal);
                % show subregion masks
                copyobj(figHandle_sagittal.Children(1), app.UIAxes_MRImg_sagittal_mask);
            else
                % show MR images
                copyobj(figHandle_sagittal.Children(1), app.UIAxes_MRImg_sagittal);
                % remove subregion masks
                app.UIAxes_MRImg_sagittal_mask.Visible = "off";
            end

            % set the appearance of MR images and subregion masks
            CMV_resetMRIandMask(app, voxSize, 'sagittal');

            % disable interaction
            app.UIAxes_MRImg_sagittal.HitTest = "off";
            app.UIAxes_MRImg_sagittal_mask.HitTest = "off";
        end

        % Value changed function: Slider_coronal
        function Slider_coronalValueChanged(app, event)
            % select slices
            app.stat_corSlice = round(app.Slider_coronal.Value);

            % standardized MR image (orientation: RAS+)
            dir_fig = fullfile(app.dir_result, app.config_caseName, 'Image');
            path_mat_img = fullfile(dir_fig, strcat(app.config_caseName, "_image.mat"));
            load(path_mat_img, 'i_img'); % the variable name is set in CMT: CMT_morphQuant(app)
            image = i_img;
            clear('i_img');

            % cartilage subregion masks
            path_subregionMask = fullfile(app.dir_result, app.config_caseName, 'CartilageSubregion',...
                strcat(app.config_caseName, '_subregions.mat'));
            load(path_subregionMask, 'cartilageSubregions'); % the variable name is set in CMT: wrapper_MorphQuant(app)
            mask_subregion = cartilageSubregions;

            % voxel size
            path_voxSize = fullfile(app.dir_result, app.config_caseName, 'CartilageSubregion',...
                strcat(app.config_caseName, '_voxelSize.mat'));
            load(path_voxSize, 'voxelSize'); % the variable name is set in CMT: wrapper_MorphQuant(app)
            voxSize = voxelSize;

            % plot MR images and subregion masks
            figHandle_coronal = CMV_plotMRIandMask(app, image, mask_subregion, 'coronal');

            if length(figHandle_coronal.Children)==2
                % show MR images
                copyobj(figHandle_coronal.Children(2), app.UIAxes_MRImg_coronal);
                % show subregion masks
                copyobj(figHandle_coronal.Children(1), app.UIAxes_MRImg_coronal_mask);
            else
                % show MR images
                copyobj(figHandle_coronal.Children(1), app.UIAxes_MRImg_coronal);
                % remove subregion masks
                app.UIAxes_MRImg_coronal_mask.Visible = "off";
            end

            % set the appearance of MR images and subregion masks
            CMV_resetMRIandMask(app, voxSize, 'coronal');

            % disable interaction
            app.UIAxes_MRImg_coronal.HitTest = "off";
            app.UIAxes_MRImg_coronal_mask.HitTest = "off";
        end

        % Value changed function: EditField_opacity
        function EditField_opacityValueChanged(app, event)
            value = app.EditField_opacity.Value;
            if ~isempty(value)
                if isnan(value) || ~isreal(value) % if user enter a string
                    uialert(app.CartiMorphViewerUIFigure, 'You need to enter a number.', 'Error');
                    app.EditField_opacity.Value = app.config_mask_opacity;
                elseif value >1 || value <0
                    uialert(app.CartiMorphViewerUIFigure, 'The opacity should be in the range [0,1].', 'Error');
                    app.EditField_opacity.Value = app.config_mask_opacity;
                else
                    app.config_mask_opacity = value;
                    % update the mask opacity if the figure is visible
                    if strcmp(app.Panel_MRImg_axial.Visible, 'on')
                        CMV_updateMRI(app);
                    end
                end
            end
        end

        % Value changed function: AboutButton
        function AboutButtonValueChanged(app, event)
            app.AboutButton.Enable = "off";

            value = app.AboutButton.Value;
            if value
                app.aboutCMV = AboutCMV(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.CartiMorphViewerUIFigure = uifigure('Visible', 'off');
            app.CartiMorphViewerUIFigure.Name = 'CartiMorph Viewer';
            app.CartiMorphViewerUIFigure.Icon = fullfile(pathToMLAPP, 'UI', 'appLogo.png');
            app.CartiMorphViewerUIFigure.CloseRequestFcn = createCallbackFcn(app, @CartiMorphViewerUIFigureCloseRequest, true);
            app.Image_logo = uiimage(app.CartiMorphViewerUIFigure);
            app.Image_logo.ImageSource = fullfile(pathToMLAPP, 'UI', 'logo_CMV.png');
            app.Panel_MorphQuant = uipanel(app.CartiMorphViewerUIFigure);
            app.Panel_MorphQuant.Title = 'Morphological Quantification';
            app.UITable = uitable(app.Panel_MorphQuant);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.Panel_ThicknessMap = uipanel(app.CartiMorphViewerUIFigure);
            app.Panel_ThicknessMap.Title = 'Thickness Map';
            app.Panel_thicknessMap = uipanel(app.Panel_ThicknessMap);
            app.Panel_thicknessMap.Visible = 'off';
            app.UIAxes_thicknessMap = uiaxes(app.Panel_thicknessMap);
            title(app.UIAxes_thicknessMap, 'Title')
            xlabel(app.UIAxes_thicknessMap, 'X')
            ylabel(app.UIAxes_thicknessMap, 'Y')
            zlabel(app.UIAxes_thicknessMap, 'Z')
            app.UIAxes_thicknessMap.Visible = 'off';
            app.Panel_CartilageSubregions = uipanel(app.CartiMorphViewerUIFigure);
            app.Panel_CartilageSubregions.Title = 'Cartilage Subregions';
            app.Panel_subregions_FCorTC = uipanel(app.Panel_CartilageSubregions);
            app.Panel_subregions_FCorTC.Visible = 'off';
            app.UIAxes_subregions_FCorTC = uiaxes(app.Panel_subregions_FCorTC);
            title(app.UIAxes_subregions_FCorTC, 'Title')
            xlabel(app.UIAxes_subregions_FCorTC, 'X')
            ylabel(app.UIAxes_subregions_FCorTC, 'Y')
            zlabel(app.UIAxes_subregions_FCorTC, 'Z')
            app.UIAxes_subregions_FCorTC.Visible = 'off';
            app.Panel_subregions_TC = uipanel(app.Panel_CartilageSubregions);
            app.Panel_subregions_TC.Visible = 'off';
            app.UIAxes_subregions_TC = uiaxes(app.Panel_subregions_TC);
            title(app.UIAxes_subregions_TC, 'Title')
            xlabel(app.UIAxes_subregions_TC, 'X')
            ylabel(app.UIAxes_subregions_TC, 'Y')
            zlabel(app.UIAxes_subregions_TC, 'Z')
            app.UIAxes_subregions_TC.Visible = 'off';
            app.Panel_subregions_FC = uipanel(app.Panel_CartilageSubregions);
            app.Panel_subregions_FC.Visible = 'off';
            app.UIAxes_subregions_FC = uiaxes(app.Panel_subregions_FC);
            title(app.UIAxes_subregions_FC, 'Title')
            xlabel(app.UIAxes_subregions_FC, 'X')
            ylabel(app.UIAxes_subregions_FC, 'Y')
            zlabel(app.UIAxes_subregions_FC, 'Z')
            app.UIAxes_subregions_FC.Visible = 'off';
            app.Panel_SurfaceNormal = uipanel(app.CartiMorphViewerUIFigure);
            app.Panel_SurfaceNormal.Title = 'Surface Normal';
            app.Panel_surfaceNormal_FCorTC = uipanel(app.Panel_SurfaceNormal);
            app.Panel_surfaceNormal_FCorTC.Visible = 'off';
            app.UIAxes_surfaceNormal_FCorTC = uiaxes(app.Panel_surfaceNormal_FCorTC);
            title(app.UIAxes_surfaceNormal_FCorTC, 'Title')
            xlabel(app.UIAxes_surfaceNormal_FCorTC, 'X')
            ylabel(app.UIAxes_surfaceNormal_FCorTC, 'Y')
            zlabel(app.UIAxes_surfaceNormal_FCorTC, 'Z')
            app.UIAxes_surfaceNormal_FCorTC.Visible = 'off';
            app.Panel_surfaceNormal_TC = uipanel(app.Panel_SurfaceNormal);
            app.Panel_surfaceNormal_TC.Visible = 'off';
            app.UIAxes_surfaceNormal_TC = uiaxes(app.Panel_surfaceNormal_TC);
            title(app.UIAxes_surfaceNormal_TC, 'Title')
            xlabel(app.UIAxes_surfaceNormal_TC, 'X')
            ylabel(app.UIAxes_surfaceNormal_TC, 'Y')
            zlabel(app.UIAxes_surfaceNormal_TC, 'Z')
            app.UIAxes_surfaceNormal_TC.Visible = 'off';
            app.Panel_surfaceNormal_FC = uipanel(app.Panel_SurfaceNormal);
            app.Panel_surfaceNormal_FC.Visible = 'off';
            app.UIAxes_surfaceNormal_FC = uiaxes(app.Panel_surfaceNormal_FC);
            title(app.UIAxes_surfaceNormal_FC, 'Title')
            xlabel(app.UIAxes_surfaceNormal_FC, 'X')
            ylabel(app.UIAxes_surfaceNormal_FC, 'Y')
            zlabel(app.UIAxes_surfaceNormal_FC, 'Z')
            app.UIAxes_surfaceNormal_FC.Visible = 'off';
            app.Panel_CartilageSurface = uipanel(app.CartiMorphViewerUIFigure);
            app.Panel_CartilageSurface.Title = 'Cartilage Surface';
            app.Panel_surface_FCorTC = uipanel(app.Panel_CartilageSurface);
            app.Panel_surface_FCorTC.Visible = 'off';
            app.UIAxes_surface_FCorTC = uiaxes(app.Panel_surface_FCorTC);
            title(app.UIAxes_surface_FCorTC, 'Title')
            xlabel(app.UIAxes_surface_FCorTC, 'X')
            ylabel(app.UIAxes_surface_FCorTC, 'Y')
            zlabel(app.UIAxes_surface_FCorTC, 'Z')
            app.UIAxes_surface_FCorTC.Visible = 'off';
            app.Panel_surface_TC = uipanel(app.Panel_CartilageSurface);
            app.Panel_surface_TC.Visible = 'off';
            app.UIAxes_surface_TC = uiaxes(app.Panel_surface_TC);
            title(app.UIAxes_surface_TC, 'Title')
            xlabel(app.UIAxes_surface_TC, 'X')
            ylabel(app.UIAxes_surface_TC, 'Y')
            zlabel(app.UIAxes_surface_TC, 'Z')
            app.UIAxes_surface_TC.Visible = 'off';
            app.Panel_surface_FC = uipanel(app.Panel_CartilageSurface);
            app.Panel_surface_FC.Visible = 'off';
            app.UIAxes_surface_FC = uiaxes(app.Panel_surface_FC);
            title(app.UIAxes_surface_FC, 'Title')
            xlabel(app.UIAxes_surface_FC, 'X')
            ylabel(app.UIAxes_surface_FC, 'Y')
            zlabel(app.UIAxes_surface_FC, 'Z')
            app.UIAxes_surface_FC.Visible = 'off';
            app.Panel_ii = uipanel(app.CartiMorphViewerUIFigure);
            app.Panel_ii.Title = 'II. Visualization Options';
            app.ButtonGroup_cartilage = uibuttongroup(app.Panel_ii);
            app.ButtonGroup_cartilage.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_cartilageSelectionChanged, true);
            app.ButtonGroup_cartilage.Title = 'Cartilage Type';
            app.Button_FC = uitogglebutton(app.ButtonGroup_cartilage);
            app.Button_FC.Text = 'Femoral Cartilage (FC)';
            app.Button_TC = uitogglebutton(app.ButtonGroup_cartilage);
            app.Button_TC.Text = 'Tibial Cartilage (TC)';
            app.Button_FTC = uitogglebutton(app.ButtonGroup_cartilage);
            app.Button_FTC.Text = 'FC + TC';
            app.Button_FTC.Value = true;
            app.ButtonGroup_map = uibuttongroup(app.Panel_ii);
            app.ButtonGroup_map.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_mapSelectionChanged, true);
            app.ButtonGroup_map.Title = 'Cartilage Thickness Map';
            app.Button_bone = uitogglebutton(app.ButtonGroup_map);
            app.Button_bone.Text = 'thickness map + bone';
            app.Button_cartilage = uitogglebutton(app.ButtonGroup_map);
            app.Button_cartilage.Text = 'thickness map + cartilage';
            app.Button_all = uitogglebutton(app.ButtonGroup_map);
            app.Button_all.Text = 'thickness map + cartilage + bone';
            app.Button_all.Value = true;
            app.ButtonGroup_Image = uibuttongroup(app.Panel_ii);
            app.ButtonGroup_Image.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_ImageSelectionChanged, true);
            app.ButtonGroup_Image.Title = 'MR Image';
            app.Button_img_subregion = uitogglebutton(app.ButtonGroup_Image);
            app.Button_img_subregion.Text = 'Image + Cartilage Subregions';
            app.Button_img_subregion.Value = true;
            app.Button_img = uitogglebutton(app.ButtonGroup_Image);
            app.Button_img.Text = 'Image';
            app.SegmentationOpacityEditFieldLabel = uilabel(app.ButtonGroup_Image);
            app.SegmentationOpacityEditFieldLabel.Text = 'Segmentation Opacity';
            app.EditField_opacity = uieditfield(app.ButtonGroup_Image, 'numeric');
            app.EditField_opacity.Limits = [0 1];
            app.EditField_opacity.ValueChangedFcn = createCallbackFcn(app, @EditField_opacityValueChanged, true);
            app.EditField_opacity.Value = 0.8;
            app.Panel_i = uipanel(app.CartiMorphViewerUIFigure);
            app.Panel_i.Title = 'I. Select Case';
            app.Button_selectPath = uibutton(app.Panel_i, 'push');
            app.Button_selectPath.ButtonPushedFcn = createCallbackFcn(app, @Button_selectPathPushed, true);
            app.Button_selectPath.Text = 'select folder';
            app.EditFieldLabel_pathCMT = uilabel(app.Panel_i);
            app.EditFieldLabel_pathCMT.Text = 'Result Folder';
            app.EditField_path = uieditfield(app.Panel_i, 'text');
            app.EditField_path.ValueChangedFcn = createCallbackFcn(app, @EditField_pathValueChanged, true);
            app.CaseDropDownLabel = uilabel(app.Panel_i);
            app.CaseDropDownLabel.Text = 'Case';
            app.DropDown_case = uidropdown(app.Panel_i);
            app.DropDown_case.Items = {};
            app.DropDown_case.ValueChangedFcn = createCallbackFcn(app, @DropDown_caseValueChanged, true);
            app.DropDown_case.Value = {};
            app.Button_hidePath = uibutton(app.Panel_i, 'state');
            app.Button_hidePath.ValueChangedFcn = createCallbackFcn(app, @Button_hidePathValueChanged, true);
            app.Button_hidePath.Text = 'hide path';
            app.kneesideofthiscaseLabel = uilabel(app.Panel_i);
            app.kneesideofthiscaseLabel.Text = 'knee side of this case:';
            app.EditField_kneeSide = uieditfield(app.Panel_i, 'text');
            app.EditField_kneeSide.Editable = 'off';
            app.Button_hideCase = uibutton(app.Panel_i, 'state');
            app.Button_hideCase.ValueChangedFcn = createCallbackFcn(app, @Button_hideCaseValueChanged, true);
            app.Button_hideCase.Text = 'hide case';
            app.caseNameMask = uilabel(app.Panel_i);
            app.caseNameMask.Visible = 'off';
            app.caseNameMask.Text = '******';
            app.AboutButton = uibutton(app.CartiMorphViewerUIFigure, 'state');
            app.AboutButton.ValueChangedFcn = createCallbackFcn(app, @AboutButtonValueChanged, true);
            app.AboutButton.Text = 'About';
            app.Panel_Buttons = uipanel(app.CartiMorphViewerUIFigure);
            app.Button_update = uibutton(app.Panel_Buttons, 'push');
            app.Button_update.ButtonPushedFcn = createCallbackFcn(app, @Button_updatePushed, true);
            app.Button_update.Enable = 'off';
            app.Button_update.Text = 'Update Figures and Table';
            app.Button_save = uibutton(app.Panel_Buttons, 'push');
            app.Button_save.ButtonPushedFcn = createCallbackFcn(app, @Button_savePushed, true);
            app.Button_save.Enable = 'off';
            app.Button_save.Text = 'Capture & Save App Screen';
            app.Panel_FCL = uipanel(app.CartiMorphViewerUIFigure);
            app.Panel_FCL.Title = 'Full-thickness Cartilage Loss (FCL)';
            app.Panel_FCL_FCorTC = uipanel(app.Panel_FCL);
            app.Panel_FCL_FCorTC.Visible = 'off';
            app.UIAxes_FCL_FCorTC = uiaxes(app.Panel_FCL_FCorTC);
            title(app.UIAxes_FCL_FCorTC, 'Title')
            xlabel(app.UIAxes_FCL_FCorTC, 'X')
            ylabel(app.UIAxes_FCL_FCorTC, 'Y')
            zlabel(app.UIAxes_FCL_FCorTC, 'Z')
            app.UIAxes_FCL_FCorTC.Visible = 'off';
            app.Panel_FCL_TC = uipanel(app.Panel_FCL);
            app.Panel_FCL_TC.Visible = 'off';
            app.UIAxes_FCL_TC = uiaxes(app.Panel_FCL_TC);
            title(app.UIAxes_FCL_TC, 'Title')
            xlabel(app.UIAxes_FCL_TC, 'X')
            ylabel(app.UIAxes_FCL_TC, 'Y')
            zlabel(app.UIAxes_FCL_TC, 'Z')
            app.UIAxes_FCL_TC.Visible = 'off';
            app.Panel_FCL_FC = uipanel(app.Panel_FCL);
            app.Panel_FCL_FC.Visible = 'off';
            app.UIAxes_FCL_FC = uiaxes(app.Panel_FCL_FC);
            title(app.UIAxes_FCL_FC, 'Title')
            xlabel(app.UIAxes_FCL_FC, 'X')
            ylabel(app.UIAxes_FCL_FC, 'Y')
            zlabel(app.UIAxes_FCL_FC, 'Z')
            app.UIAxes_FCL_FC.Visible = 'off';
            app.Panel_MRImageSegmentation = uipanel(app.CartiMorphViewerUIFigure);
            app.Panel_MRImageSegmentation.Title = 'MR Image ';
            app.Panel_MRImg_3DView = uipanel(app.Panel_MRImageSegmentation);
            app.Panel_MRImg_3DView.Visible = 'off';
            app.UIAxes_MRImg_3DView = uiaxes(app.Panel_MRImg_3DView);
            title(app.UIAxes_MRImg_3DView, 'Title')
            xlabel(app.UIAxes_MRImg_3DView, 'X')
            ylabel(app.UIAxes_MRImg_3DView, 'Y')
            zlabel(app.UIAxes_MRImg_3DView, 'Z')
            app.UIAxes_MRImg_3DView.Visible = 'off';
            app.Panel_MRImg_axial = uipanel(app.Panel_MRImageSegmentation);
            app.Panel_MRImg_axial.Visible = 'off';
            app.UIAxes_MRImg_axial = uiaxes(app.Panel_MRImg_axial);
            title(app.UIAxes_MRImg_axial, 'Title')
            xlabel(app.UIAxes_MRImg_axial, 'X')
            ylabel(app.UIAxes_MRImg_axial, 'Y')
            zlabel(app.UIAxes_MRImg_axial, 'Z')
            app.UIAxes_MRImg_axial.Visible = 'off';
            app.UIAxes_MRImg_axial_mask = uiaxes(app.Panel_MRImg_axial);
            title(app.UIAxes_MRImg_axial_mask, 'Title')
            xlabel(app.UIAxes_MRImg_axial_mask, 'X')
            ylabel(app.UIAxes_MRImg_axial_mask, 'Y')
            zlabel(app.UIAxes_MRImg_axial_mask, 'Z')
            app.UIAxes_MRImg_axial_mask.Visible = 'off';
            app.Label_axiView_A = uilabel(app.Panel_MRImg_axial);
            app.Label_axiView_A.Text = 'A';
            app.Label_axiView_P = uilabel(app.Panel_MRImg_axial);
            app.Label_axiView_P.Text = 'P';
            app.Label_axiView_L = uilabel(app.Panel_MRImg_axial);
            app.Label_axiView_L.Text = 'L';
            app.Label_axiView_R = uilabel(app.Panel_MRImg_axial);
            app.Label_axiView_R.Text = 'R';
            app.Slider_axial = uislider(app.Panel_MRImageSegmentation);
            app.Slider_axial.MajorTicks = [];
            app.Slider_axial.ValueChangedFcn = createCallbackFcn(app, @Slider_axialValueChanged, true);
            app.Slider_axial.ValueChangingFcn = createCallbackFcn(app, @Slider_axialValueChanging, true);
            app.Slider_axial.MinorTicks = [];
            app.Slider_axial.Visible = 'off';
            app.Slider_axial.Value = 100;
            app.Panel_MRImg_sagittal = uipanel(app.Panel_MRImageSegmentation);
            app.Panel_MRImg_sagittal.Visible = 'off';
            app.UIAxes_MRImg_sagittal = uiaxes(app.Panel_MRImg_sagittal);
            title(app.UIAxes_MRImg_sagittal, 'Title')
            xlabel(app.UIAxes_MRImg_sagittal, 'X')
            ylabel(app.UIAxes_MRImg_sagittal, 'Y')
            zlabel(app.UIAxes_MRImg_sagittal, 'Z')
            app.UIAxes_MRImg_sagittal.Visible = 'off';
            app.UIAxes_MRImg_sagittal_mask = uiaxes(app.Panel_MRImg_sagittal);
            title(app.UIAxes_MRImg_sagittal_mask, 'Title')
            xlabel(app.UIAxes_MRImg_sagittal_mask, 'X')
            ylabel(app.UIAxes_MRImg_sagittal_mask, 'Y')
            zlabel(app.UIAxes_MRImg_sagittal_mask, 'Z')
            app.UIAxes_MRImg_sagittal_mask.Visible = 'off';
            app.Label_sagView_A = uilabel(app.Panel_MRImg_sagittal);
            app.Label_sagView_A.Text = 'A';
            app.Label_sagView_P = uilabel(app.Panel_MRImg_sagittal);
            app.Label_sagView_P.Text = 'P';
            app.Label_sagView_S = uilabel(app.Panel_MRImg_sagittal);
            app.Label_sagView_S.Text = 'S';
            app.Label_sagView_I = uilabel(app.Panel_MRImg_sagittal);
            app.Label_sagView_I.Text = 'I';
            app.Slider_sagittal = uislider(app.Panel_MRImageSegmentation);
            app.Slider_sagittal.MajorTicks = [];
            app.Slider_sagittal.MajorTickLabels = {''};
            app.Slider_sagittal.ValueChangedFcn = createCallbackFcn(app, @Slider_sagittalValueChanged, true);
            app.Slider_sagittal.ValueChangingFcn = createCallbackFcn(app, @Slider_sagittalValueChanging, true);
            app.Slider_sagittal.MinorTicks = [];
            app.Slider_sagittal.Visible = 'off';
            app.Slider_sagittal.Value = 100;
            app.Panel_MRImg_coronal = uipanel(app.Panel_MRImageSegmentation);
            app.Panel_MRImg_coronal.Visible = 'off';
            app.UIAxes_MRImg_coronal = uiaxes(app.Panel_MRImg_coronal);
            title(app.UIAxes_MRImg_coronal, 'Title')
            xlabel(app.UIAxes_MRImg_coronal, 'X')
            ylabel(app.UIAxes_MRImg_coronal, 'Y')
            zlabel(app.UIAxes_MRImg_coronal, 'Z')
            app.UIAxes_MRImg_coronal.Visible = 'off';
            app.UIAxes_MRImg_coronal_mask = uiaxes(app.Panel_MRImg_coronal);
            title(app.UIAxes_MRImg_coronal_mask, 'Title')
            xlabel(app.UIAxes_MRImg_coronal_mask, 'X')
            ylabel(app.UIAxes_MRImg_coronal_mask, 'Y')
            zlabel(app.UIAxes_MRImg_coronal_mask, 'Z')
            app.UIAxes_MRImg_coronal_mask.Visible = 'off';
            app.Label_corView_S = uilabel(app.Panel_MRImg_coronal);
            app.Label_corView_S.Text = 'S';
            app.Label_corView_I = uilabel(app.Panel_MRImg_coronal);
            app.Label_corView_I.Text = 'I';
            app.Label_corView_L = uilabel(app.Panel_MRImg_coronal);
            app.Label_corView_L.Text = 'L';
            app.Label_corView_R = uilabel(app.Panel_MRImg_coronal);
            app.Label_corView_R.Text = 'R';
            app.Slider_coronal = uislider(app.Panel_MRImageSegmentation);
            app.Slider_coronal.MajorTicks = [];
            app.Slider_coronal.ValueChangedFcn = createCallbackFcn(app, @Slider_coronalValueChanged, true);
            app.Slider_coronal.ValueChangingFcn = createCallbackFcn(app, @Slider_coronalValueChanging, true);
            app.Slider_coronal.MinorTicks = [];
            app.Slider_coronal.Visible = 'off';
            app.Slider_coronal.Value = 100;
            app.Label_slice_axial = uilabel(app.Panel_MRImageSegmentation);
            app.Label_slice_axial.Visible = 'off';
            app.Label_slice_axial.Text = {'100'; '----'; '100'};
            app.Label_slice_sagittal = uilabel(app.Panel_MRImageSegmentation);
            app.Label_slice_sagittal.Visible = 'off';
            app.Label_slice_sagittal.Text = {'100'; '----'; '100'};
            app.Label_slice_coronal = uilabel(app.Panel_MRImageSegmentation);
            app.Label_slice_coronal.Visible = 'off';
            app.Label_slice_coronal.Text = {'100'; '----'; '100'};
            app.CMV_version = uilabel(app.CartiMorphViewerUIFigure);
            app.CMV_version.Text = {'Version: 1.0.4'; 'License: CC-BY-NC 4.0'};
            % Show the figure after all components are created
            app.CartiMorphViewerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CartiMorphViewer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CartiMorphViewerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CartiMorphViewerUIFigure)
        end
    end
end